classdef AllenAtlasROICreator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        GridLayout2              matlab.ui.container.GridLayout
        Tree                     matlab.ui.container.CheckBoxTree
        Node                     matlab.ui.container.TreeNode
        Node3                    matlab.ui.container.TreeNode
        Node4                    matlab.ui.container.TreeNode
        Node2                    matlab.ui.container.TreeNode
        GridLayout3              matlab.ui.container.GridLayout
        RememberselectionButton  matlab.ui.control.Button
        CancelButton             matlab.ui.control.Button
        OkButton                 matlab.ui.control.Button
        SearchResultsDropDown    matlab.ui.control.DropDown
        SearchField              matlab.ui.control.EditField
        UIAxes                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % Atlas source
        AtlasFile char = ''
        BregmaCoordsXY double = []
        LambdaCoordsXY double = []
        area_bwlabel double = []
        area_longNames cell = {}
        area_parentNames cell = {}
        area_polyshapes
        area_tags cell = {}
        bound_mask logical = []
        cluster_bwlabel double = []
        cluster_longNames cell = {}
        cluster_polyshapes
        cluster_tags cell = {}
        pixels_per_mm double = []

        % Normalized atlas lookup tables
        AreaTable table = table()
        ModuleTable table = table()
        SearchTable table = table()

        % Lookup maps
        TreeNodeByAreaBaseKey
        TreeNodeByModuleBaseKey
        AreaKeysByModuleBaseKey
        ModuleKeysByModuleBaseKey
        AreaHandleByAreaKey
        ModuleHandleByModuleKey
        ModuleNodeContextMenuByKey

        % Selection state
        SelectedAreaKeys string = strings(0, 1)       % side-specific area tags, e.g. "VISp_L"
        SelectedModuleKeys string = strings(0, 1)     % side-specific module tags, e.g. "VIS_L"
        MergedModuleBaseKeys string = strings(0, 1)   % side-collapsed module tags, e.g. "VIS"
        CheckedTreeNodeKeys string = strings(0, 1)

        % Map runtime handles
        AreaPatchHandles = gobjects(0)
        ModulePatchHandles = gobjects(0)
        BoundaryHandle = gobjects(1)
        BregmaHandle = gobjects(1)
        LambdaHandle = gobjects(1)

        % Runtime flags
        IsUpdatingTree logical = false
        IsUpdatingSearch logical = false
        % Temporary title-status timer
        StatusTitleTimer timer = timer.empty

        % Parent app and OK output
        MainApp = []
        Result struct = struct()
        Confirmed logical = false
    end

    methods (Access = private)
        function initializeAtlasRuntimeMaps(app)
            %INITIALIZEATLASRUNTIMEMAPS Initialize selector lookup maps.

            app.TreeNodeByAreaBaseKey = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.TreeNodeByModuleBaseKey = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.AreaKeysByModuleBaseKey = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.ModuleKeysByModuleBaseKey = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.AreaHandleByAreaKey = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.ModuleHandleByModuleKey = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.ModuleNodeContextMenuByKey = containers.Map('KeyType', 'char', 'ValueType', 'any');

            app.SelectedAreaKeys = strings(0, 1);
            app.SelectedModuleKeys = strings(0, 1);
            app.MergedModuleBaseKeys = strings(0, 1);
            app.CheckedTreeNodeKeys = strings(0, 1);
        end

        function loadAtlasInfo(app, atlasFileOrStruct)
            %LOADATLASINFO Load and validate Allen atlas top-view info.
            %
            %   If no input is provided, the atlas file is loaded from the same folder as
            %   this app. No user prompt is shown.

            if nargin < 2 || isempty(atlasFileOrStruct)
                atlasFileOrStruct = app.getDefaultAtlasInfoFilePath();
            end

            if isstruct(atlasFileOrStruct)
                S = atlasFileOrStruct;
                app.AtlasFile = '';

            else
                app.AtlasFile = char(string(atlasFileOrStruct));

                if ~isfile(app.AtlasFile)
                    error('AllenAtlasROICreator:AtlasFileNotFound', ...
                        'Atlas info file not found: "%s".', app.AtlasFile);
                end

                S = load(app.AtlasFile, '-mat');
            end

            requiredVars = { ...
                'BregmaCoordsXY', ...
                'LambdaCoordsXY', ...
                'area_bwlabel', ...
                'area_longNames', ...
                'area_parentNames', ...
                'area_polyshapes', ...
                'area_tags', ...
                'bound_mask', ...
                'cluster_bwlabel', ...
                'cluster_longNames', ...
                'cluster_polyshapes', ...
                'cluster_tags', ...
                'pixels_per_mm'};

            for iVar = 1:numel(requiredVars)
                if ~isfield(S, requiredVars{iVar})
                    error('AllenAtlasROICreator:InvalidAtlasInfo', ...
                        'Atlas info is missing required variable "%s".', requiredVars{iVar});
                end
            end

            app.BregmaCoordsXY = double(S.BregmaCoordsXY);
            app.LambdaCoordsXY = double(S.LambdaCoordsXY);
            app.area_bwlabel = double(S.area_bwlabel);
            app.area_longNames = S.area_longNames;
            app.area_parentNames = S.area_parentNames;
            app.area_polyshapes = S.area_polyshapes;
            app.area_tags = S.area_tags;
            app.bound_mask = logical(S.bound_mask);
            app.cluster_bwlabel = double(S.cluster_bwlabel);
            app.cluster_longNames = S.cluster_longNames;
            app.cluster_polyshapes = S.cluster_polyshapes;
            app.cluster_tags = S.cluster_tags;
            app.pixels_per_mm = double(S.pixels_per_mm);

            app.validateAtlasInfo();
        end


        function validateAtlasInfo(app)
            %VALIDATEATLASINFO Validate dimensions/classes needed by selector.

            nAreas = numel(app.area_tags);
            if numel(app.area_longNames) ~= nAreas || ...
                    numel(app.area_parentNames) ~= nAreas || ...
                    numel(app.area_polyshapes) ~= nAreas
                error('AllenAtlasROICreator:InvalidAtlasInfo', ...
                    'Area fields must have matching lengths.');
            end

            nModules = numel(app.cluster_tags);
            if numel(app.cluster_longNames) ~= nModules || ...
                    numel(app.cluster_polyshapes) ~= nModules
                error('AllenAtlasROICreator:InvalidAtlasInfo', ...
                    'Cluster/module fields must have matching lengths.');
            end

            if ~isequal(size(app.area_bwlabel), size(app.bound_mask)) || ...
                    ~isequal(size(app.cluster_bwlabel), size(app.bound_mask))
                error('AllenAtlasROICreator:InvalidAtlasInfo', ...
                    'area_bwlabel, cluster_bwlabel, and bound_mask must have matching sizes.');
            end

            if numel(app.BregmaCoordsXY) ~= 2 || numel(app.LambdaCoordsXY) ~= 2
                error('AllenAtlasROICreator:InvalidAtlasInfo', ...
                    'BregmaCoordsXY and LambdaCoordsXY must be 1-by-2 coordinates.');
            end

            if isempty(app.pixels_per_mm) || ~isfinite(app.pixels_per_mm(1)) || app.pixels_per_mm(1) <= 0
                error('AllenAtlasROICreator:InvalidAtlasInfo', ...
                    'pixels_per_mm must be a positive finite value.');
            end
        end


        function buildAtlasLookupTables(app)
            %BUILDATLASLOOKUPTABLES Normalize side-specific atlas metadata.

            nAreas = numel(app.area_tags);
            nModules = numel(app.cluster_tags);

            try
                areaColors = distinguishable_colors(nAreas, {'b', 'w'});
            catch
                areaColors = lines(max(nAreas, 1));
                areaColors = areaColors(1:nAreas, :);
            end

            areaIdx = (1:nAreas).';
            areaTagFull = strings(nAreas, 1);
            areaTagBase = strings(nAreas, 1);
            areaSide = strings(nAreas, 1);
            areaLongNameFull = strings(nAreas, 1);
            areaLongNameBase = strings(nAreas, 1);
            areaParentFull = strings(nAreas, 1);
            areaParentBase = strings(nAreas, 1);
            areaModuleBaseKey = strings(nAreas, 1);
            areaColor = cell(nAreas, 1);

            for iArea = 1:nAreas
                tagFull = string(app.area_tags{iArea});
                longNameFull = string(app.area_longNames{iArea});
                parentFull = string(app.area_parentNames{iArea});

                [tagBase, sideFromTag] = app.stripAtlasSideFromTag(tagFull);
                [longNameBase, sideFromName] = app.stripAtlasSideFromName(longNameFull);
                [parentBase, ~] = app.stripAtlasSideFromName(parentFull);

                side = sideFromTag;
                if side == ""
                    side = sideFromName;
                end

                areaTagFull(iArea) = tagFull;
                areaTagBase(iArea) = tagBase;
                areaSide(iArea) = side;
                areaLongNameFull(iArea) = longNameFull;
                areaLongNameBase(iArea) = longNameBase;
                areaParentFull(iArea) = parentFull;
                areaParentBase(iArea) = parentBase;
                areaModuleBaseKey(iArea) = matlab.lang.makeValidName(char(parentBase));
                areaColor{iArea} = areaColors(iArea, :);
            end

            moduleIdx = (1:nModules).';
            moduleTagFull = strings(nModules, 1);
            moduleTagBase = strings(nModules, 1);
            moduleSide = strings(nModules, 1);
            moduleLongNameFull = strings(nModules, 1);
            moduleLongNameBase = strings(nModules, 1);
            moduleBaseKey = strings(nModules, 1);
            moduleColor = cell(nModules, 1);

            for iModule = 1:nModules
                tagFull = string(app.cluster_tags{iModule});
                longNameFull = string(app.cluster_longNames{iModule});

                [tagBase, sideFromTag] = app.stripAtlasSideFromTag(tagFull);
                [longNameBase, sideFromName] = app.stripAtlasSideFromName(longNameFull);

                side = sideFromTag;
                if side == ""
                    side = sideFromName;
                end

                keyBase = tagBase;
                if keyBase == ""
                    keyBase = matlab.lang.makeValidName(char(longNameBase));
                end

                moduleTagFull(iModule) = tagFull;
                moduleTagBase(iModule) = tagBase;
                moduleSide(iModule) = side;
                moduleLongNameFull(iModule) = longNameFull;
                moduleLongNameBase(iModule) = longNameBase;
                moduleBaseKey(iModule) = keyBase;
            end

            nameToModuleKey = containers.Map('KeyType', 'char', 'ValueType', 'char');
            for iModule = 1:nModules
                nameKey = char(moduleLongNameBase(iModule));
                if ~isKey(nameToModuleKey, nameKey)
                    if moduleBaseKey(iModule) ~= ""
                        nameToModuleKey(nameKey) = char(moduleBaseKey(iModule));
                    else
                        nameToModuleKey(nameKey) = matlab.lang.makeValidName(nameKey);
                    end
                end
            end

            for iArea = 1:nAreas
                parentName = char(areaParentBase(iArea));
                if isKey(nameToModuleKey, parentName)
                    areaModuleBaseKey(iArea) = string(nameToModuleKey(parentName));
                else
                    areaModuleBaseKey(iArea) = string(matlab.lang.makeValidName(parentName));
                end
            end

            for iModule = 1:nModules
                idx = find(areaModuleBaseKey == moduleBaseKey(iModule) & ...
                    areaSide == moduleSide(iModule), 1, 'first');
                if isempty(idx)
                    idx = find(areaModuleBaseKey == moduleBaseKey(iModule), 1, 'first');
                end

                if isempty(idx)
                    moduleColor{iModule} = [0.85 0.85 0.85];
                else
                    moduleColor{iModule} = areaColor{idx};
                end
            end

            app.AreaTable = table(areaIdx, areaTagFull, areaTagBase, areaSide, ...
                areaLongNameFull, areaLongNameBase, areaParentFull, areaParentBase, ...
                areaModuleBaseKey, areaColor, ...
                'VariableNames', {'AreaIdx', 'TagFull', 'TagBase', 'Side', ...
                'LongNameFull', 'LongNameBase', 'ParentFull', 'ParentBase', ...
                'ModuleBaseKey', 'Color'});

            app.ModuleTable = table(moduleIdx, moduleTagFull, moduleTagBase, moduleSide, ...
                moduleLongNameFull, moduleLongNameBase, moduleBaseKey, moduleColor, ...
                'VariableNames', {'ModuleIdx', 'TagFull', 'TagBase', 'Side', ...
                'LongNameFull', 'LongNameBase', 'ModuleBaseKey', 'Color'});

            app.buildAtlasSearchTable();
            app.buildModuleAreaLookupMaps();
        end


        function buildModuleAreaLookupMaps(app)
            %BUILDMODULEAREALOOKUPMAPS Cache module-to-area/module key lists.

            app.AreaKeysByModuleBaseKey = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.ModuleKeysByModuleBaseKey = containers.Map('KeyType', 'char', 'ValueType', 'any');

            moduleKeys = unique(app.AreaTable.ModuleBaseKey, 'stable');

            for iKey = 1:numel(moduleKeys)
                key = char(moduleKeys(iKey));

                idxArea = app.AreaTable.ModuleBaseKey == moduleKeys(iKey);
                app.AreaKeysByModuleBaseKey(key) = cellstr(app.AreaTable.TagFull(idxArea));

                idxModule = app.ModuleTable.ModuleBaseKey == moduleKeys(iKey);
                app.ModuleKeysByModuleBaseKey(key) = cellstr(app.ModuleTable.TagFull(idxModule));
            end
        end


        function buildAtlasSearchTable(app)
            %BUILDATLASSEARCHTABLE Build side-collapsed search index.

            itemKey = strings(0, 1);
            itemType = strings(0, 1);
            displayText = strings(0, 1);
            searchText = strings(0, 1);

            moduleKeys = unique(app.ModuleTable.ModuleBaseKey, 'stable');
            for iModule = 1:numel(moduleKeys)
                idx = find(app.ModuleTable.ModuleBaseKey == moduleKeys(iModule), 1, 'first');
                if isempty(idx), continue; end

                name = app.ModuleTable.LongNameBase(idx);
                tag = app.ModuleTable.ModuleBaseKey(idx);

                itemKey(end+1, 1) = "module|" + tag; %#ok<AGROW>
                itemType(end+1, 1) = "module"; %#ok<AGROW>
                displayText(end+1, 1) = sprintf('%s (%s)', name, tag); %#ok<AGROW>
                searchText(end+1, 1) = lower(displayText(end)); %#ok<AGROW>
            end

            areaBases = unique(app.AreaTable.TagBase, 'stable');
            for iAreaBase = 1:numel(areaBases)
                idx = find(app.AreaTable.TagBase == areaBases(iAreaBase), 1, 'first');
                if isempty(idx), continue; end

                name = app.AreaTable.LongNameBase(idx);
                tag = app.AreaTable.TagBase(idx);

                itemKey(end+1, 1) = "area|" + tag; %#ok<AGROW>
                itemType(end+1, 1) = "area"; %#ok<AGROW>
                displayText(end+1, 1) = sprintf('%s (%s)', name, tag); %#ok<AGROW>
                searchText(end+1, 1) = lower(displayText(end)); %#ok<AGROW>
            end

            app.SearchTable = table(itemKey, itemType, displayText, searchText, ...
                'VariableNames', {'ItemKey', 'ItemType', 'DisplayText', 'SearchText'});
        end

        function populateAtlasTree(app)
            %POPULATEATLANTREE Populate bilateral module/area checkbox tree.

            delete(app.Tree.Children);

            app.TreeNodeByAreaBaseKey = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.TreeNodeByModuleBaseKey = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.ModuleNodeContextMenuByKey = containers.Map('KeyType', 'char', 'ValueType', 'any');

            rootNode = uitreenode(app.Tree);
            rootNode.Text = 'Neocortex';
            rootNode.NodeData = struct( ...
                'type', 'root', ...
                'nodeKey', "root|neocortex");

            moduleKeys = unique(app.AreaTable.ModuleBaseKey, 'stable');

            for iModule = 1:numel(moduleKeys)
                moduleKey = moduleKeys(iModule);
                idxModuleTable = find(app.ModuleTable.ModuleBaseKey == moduleKey, 1, 'first');

                if isempty(idxModuleTable)
                    idxArea = find(app.AreaTable.ModuleBaseKey == moduleKey, 1, 'first');
                    moduleName = app.AreaTable.ParentBase(idxArea);
                    moduleTag = moduleKey;
                else
                    moduleName = app.ModuleTable.LongNameBase(idxModuleTable);
                    moduleTag = app.ModuleTable.ModuleBaseKey(idxModuleTable);
                end

                moduleNode = uitreenode(rootNode);
                moduleNode.Text = app.formatAtlasTreeNodeText(moduleName, moduleTag, false);
                moduleNode.NodeData = struct( ...
                    'type', 'module', ...
                    'nodeKey', "module|" + moduleKey, ...
                    'moduleBaseKey', moduleKey);

                app.TreeNodeByModuleBaseKey(char(moduleKey)) = moduleNode;
                app.attachModuleContextMenu(moduleNode, moduleKey);

                areaIdx = find(app.AreaTable.ModuleBaseKey == moduleKey);
                areaBases = unique(app.AreaTable.TagBase(areaIdx), 'stable');

                for iAreaBase = 1:numel(areaBases)
                    areaBase = areaBases(iAreaBase);
                    idxAreaTable = find(app.AreaTable.TagBase == areaBase & ...
                        app.AreaTable.ModuleBaseKey == moduleKey, 1, 'first');

                    if isempty(idxAreaTable)
                        continue
                    end

                    areaName = app.AreaTable.LongNameBase(idxAreaTable);
                    areaTag = app.AreaTable.TagBase(idxAreaTable);
                    sideRows = app.AreaTable.TagBase == areaBase & ...
                        app.AreaTable.ModuleBaseKey == moduleKey;
                    areaKeys = app.AreaTable.TagFull(sideRows);

                    areaNode = uitreenode(moduleNode);
                    areaNode.Text = app.formatAtlasTreeNodeText(areaName, areaTag, false);
                    areaNode.NodeData = struct( ...
                        'type', 'area', ...
                        'nodeKey', "area|" + areaBase, ...
                        'areaBaseKey', areaBase, ...
                        'moduleBaseKey', moduleKey, ...
                        'areaKeys', areaKeys(:).');

                    app.TreeNodeByAreaBaseKey(char(areaBase)) = areaNode;
                end
            end

            app.expandAllAtlasTreeNodes();
            app.updateTreeChecksFromSelection();
        end

        function createAtlasMapLayers(app)
            %CREATEATLASMAPLAYERS Draw module, area, and boundary layers.
            %
            %   Bregma/Lambda markers are intentionally not drawn in this child selector.
            %   They will be drawn during the atlas mask fitting step in the main
            %   DataViewer app.

            cla(app.UIAxes);
            hold(app.UIAxes, 'on');

            app.AreaHandleByAreaKey = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.ModuleHandleByModuleKey = containers.Map('KeyType', 'char', 'ValueType', 'any');
            app.AreaPatchHandles = gobjects(0);
            app.ModulePatchHandles = gobjects(0);

            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Box = 'on';
            app.UIAxes.YDir = 'reverse';
            app.UIAxes.DataAspectRatio = [1 1 1];

            % Bottom layer: cluster/module polyshapes.
            for iModule = 1:height(app.ModuleTable)
                moduleKey = app.ModuleTable.TagFull(iModule);
                pgon = app.cluster_polyshapes(app.ModuleTable.ModuleIdx(iModule));

                h = plot(app.UIAxes, pgon);
                h.FaceColor = [0.85 0.85 0.85];
                h.FaceAlpha = 0.12;
                h.EdgeColor = 'none';
                h.LineWidth = 0.5;
                h.HitTest = 'on';
                h.PickableParts = 'visible';
                h.ButtonDownFcn = @(src, evt) app.onAtlasModulePatchClicked(moduleKey);

                app.ModulePatchHandles(end+1) = h; %#ok<AGROW>
                app.ModuleHandleByModuleKey(char(moduleKey)) = h;
            end

            % Middle layer: area polyshapes.
            for iArea = 1:height(app.AreaTable)
                areaKey = app.AreaTable.TagFull(iArea);
                pgon = app.area_polyshapes(app.AreaTable.AreaIdx(iArea));

                h = plot(app.UIAxes, pgon);
                h.FaceColor = [0.85 0.85 0.85];
                h.FaceAlpha = 0.20;
                h.EdgeColor = 'none';
                h.LineWidth = 0.5;
                h.HitTest = 'on';
                h.PickableParts = 'visible';
                h.ButtonDownFcn = @(src, evt) app.onAtlasAreaPatchClicked(areaKey);

                app.AreaPatchHandles(end+1) = h; %#ok<AGROW>
                app.AreaHandleByAreaKey(char(areaKey)) = h;
            end

            % Top layer: area boundary mask in black. Non-interactive.
            [Ny, Nx] = size(app.bound_mask);
            blackRGB = zeros(Ny, Nx, 3, 'single');

            app.BoundaryHandle = image(app.UIAxes, ...
                'CData', blackRGB, ...
                'AlphaData', 0.95 .* double(app.bound_mask), ...
                'XData', [1 Nx], ...
                'YData', [1 Ny], ...
                'HitTest', 'off', ...
                'PickableParts', 'none');

            app.BregmaHandle = gobjects(1);
            app.LambdaHandle = gobjects(1);

            axis(app.UIAxes, 'image');
            app.UIAxes.YDir = 'reverse';
            title(app.UIAxes, 'Interactive Map');

            hold(app.UIAxes, 'off');
            app.stackAtlasTopLayer();
        end


        function updateAtlasMapDisplay(app)
            %UPDATEATLASMAPDISPLAY Refresh map colors/visibility from state.

            gray = [0.85 0.85 0.85];

            for iModule = 1:height(app.ModuleTable)
                moduleKey = app.ModuleTable.TagFull(iModule);
                moduleBaseKey = app.ModuleTable.ModuleBaseKey(iModule);
                if ~isKey(app.ModuleHandleByModuleKey, char(moduleKey)), continue; end
                h = app.ModuleHandleByModuleKey(char(moduleKey));
                if ~isvalid(h), continue; end

                bMerged = app.isModuleMerged(moduleBaseKey);
                bSelected = app.isKeySelected(moduleKey, app.SelectedModuleKeys);

                h.Visible = 'on';

                if bMerged && bSelected
                    h.FaceColor = app.ModuleTable.Color{iModule};
                    h.FaceAlpha = 0.65;
                    h.EdgeColor = [0 0 0];
                    h.LineWidth = 1.0;
                elseif bMerged
                    h.FaceColor = gray;
                    h.FaceAlpha = 0.20;
                    h.EdgeColor = 'none';
                    h.LineWidth = 0.5;
                else
                    h.FaceColor = gray;
                    h.FaceAlpha = 0.10;
                    h.EdgeColor = 'none';
                    h.LineWidth = 0.5;
                end

                h.HitTest = 'on';
                h.PickableParts = 'visible';
            end

            for iArea = 1:height(app.AreaTable)
                areaKey = app.AreaTable.TagFull(iArea);
                moduleBaseKey = app.AreaTable.ModuleBaseKey(iArea);
                if ~isKey(app.AreaHandleByAreaKey, char(areaKey)), continue; end
                h = app.AreaHandleByAreaKey(char(areaKey));
                if ~isvalid(h), continue; end

                if app.isModuleMerged(moduleBaseKey)
                    h.Visible = 'off';
                    h.HitTest = 'off';
                    h.PickableParts = 'none';
                    continue
                end

                bSelected = app.isKeySelected(areaKey, app.SelectedAreaKeys);
                h.Visible = 'on';
                h.HitTest = 'on';
                h.PickableParts = 'visible';

                if bSelected
                    h.FaceColor = app.AreaTable.Color{iArea};
                    h.FaceAlpha = 0.70;
                    h.EdgeColor = [0 0 0];
                    h.LineWidth = 0.8;
                else
                    h.FaceColor = gray;
                    h.FaceAlpha = 0.20;
                    h.EdgeColor = 'none';
                    h.LineWidth = 0.5;
                end
            end

            app.stackAtlasTopLayer();
            drawnow limitrate
        end


        function stackAtlasTopLayer(app)
            %STACKATLASTOPLAYER Keep boundary mask above polygons.

            try
                if ~isempty(app.BoundaryHandle) && isvalid(app.BoundaryHandle)
                    uistack(app.BoundaryHandle, 'top');
                end
            catch
            end
        end

        function updateAtlasSearchResults(app, queryText)
            %UPDATEATLASEARCHRESULTS Update dropdown matches using contains search.
            %
            %   The dropdown always includes a placeholder first item. This prevents the
            %   first real search result from being preselected, so selecting it triggers
            %   ValueChangedFcn normally.

            if app.IsUpdatingSearch
                return
            end

            queryText = strtrim(lower(char(string(queryText))));

            if isempty(queryText)
                app.SearchResultsDropDown.Items = {'Select search result...'};
                app.SearchResultsDropDown.ItemsData = {'__select__'};
                app.SearchResultsDropDown.Value = '__select__';
                app.hideSearchResultsDropdown();
                return
            end

            idx = contains(app.SearchTable.SearchText, queryText);

            if ~any(idx)
                app.SearchResultsDropDown.Items = {'No matches'};
                app.SearchResultsDropDown.ItemsData = {'__none__'};
                app.SearchResultsDropDown.Value = '__none__';
                app.showSearchResultsDropdown();
                return
            end

            matches = app.SearchTable(idx, :);

            maxMatches = min(20, height(matches));
            matches = matches(1:maxMatches, :);

            app.IsUpdatingSearch = true;
            cleanupObj = onCleanup(@() app.setSearchUpdatingFalse());

            app.SearchResultsDropDown.Items = [{'Select search result...'}; cellstr(matches.DisplayText)];
            app.SearchResultsDropDown.ItemsData = [{'__select__'}; cellstr(matches.ItemKey)];
            app.SearchResultsDropDown.Value = '__select__';

            clear cleanupObj
            app.IsUpdatingSearch = false;

            app.showSearchResultsDropdown();
        end

        function selectAtlasItemFromSearchKey(app, itemKey)
            %SELECTATLASITEMFROMSEARCHKEY Select area/module chosen in dropdown.
            %
            %   After a valid dropdown selection, clear the search field and hide/reset the
            %   dropdown so the next search starts from a clean state.

            if app.IsUpdatingSearch
                return
            end

            itemKey = string(itemKey);

            if itemKey == "" || itemKey == "__select__" || itemKey == "__none__"
                return
            end

            parts = split(itemKey, "|");

            if numel(parts) ~= 2
                return
            end

            itemType = parts(1);
            baseKey = parts(2);

            switch itemType
                case "area"
                    app.selectAreaBaseFromTreeOrSearch(baseKey, true);
                    app.expandParentModuleForArea(baseKey);

                case "module"
                    app.selectModuleBaseFromTreeOrSearch(baseKey, true);
                    app.expandModuleNode(baseKey);
            end

            app.updateTreeChecksFromSelection();
            app.updateAtlasMapDisplay();

            % Reset search UI after successful selection.
            app.IsUpdatingSearch = true;
            cleanupObj = onCleanup(@() app.setSearchUpdatingFalse());

            app.SearchField.Value = '';
            app.SearchResultsDropDown.Items = {'Select search result...'};
            app.SearchResultsDropDown.ItemsData = {'__select__'};
            app.SearchResultsDropDown.Value = '__select__';

            clear cleanupObj
            app.IsUpdatingSearch = false;

            app.hideSearchResultsDropdown();
        end

        function hideSearchResultsDropdown(app)
            %HIDESEARCHRESULTSDROPDOWN Hide dropdown and collapse its grid row.
            try
                app.SearchResultsDropDown.Visible = 'off';
            catch
            end

            try
                rowHeight = app.GridLayout2.RowHeight;
                rowHeight{2} = 0;
                rowHeight{3} = 325;
                app.GridLayout2.RowHeight = rowHeight;
            catch
            end
        end

        function result = buildAtlasSelectionResult(app)
            %BUILDATLASSELECTIONRESULT Build selected-region output for DataViewer.
            %
            %   Selected areas are side-specific area tags, e.g. VISp_L.
            %   Selected modules are side-specific cluster/module tags, e.g. VIS_L.
            %   All final ROI names should use the tag field.

            result = struct();
            result.confirmed = true;
            result.createdBy = 'AllenAtlasROICreator';
            result.createdOn = datetime('now');

            result.atlasFile = app.AtlasFile;
            result.BregmaCoordsXY = double(app.BregmaCoordsXY(:).');
            result.LambdaCoordsXY = double(app.LambdaCoordsXY(:).');
            result.pixels_per_mm = double(app.pixels_per_mm(1));
            result.atlasImageSizeYX = double(size(app.bound_mask));
            result.boundaryVerticesXY = app.getAtlasBoundaryVerticesFromMask();

            selectedRegions = app.buildSelectedAtlasRegionStructArray();

            result.selectedRegions = selectedRegions;
            result.selectedAreaTags = cellstr(app.SelectedAreaKeys(:));
            result.selectedModuleTags = cellstr(app.SelectedModuleKeys(:));
            result.mergedModuleTags = cellstr(app.MergedModuleBaseKeys(:));

        end


        function selectedRegions = buildSelectedAtlasRegionStructArray(app)
            %BUILDSELECTEDATLASREGIONSTRUCTARRAY Return selected atlas area/module records.

            selectedRegions = app.emptyAtlasOutputRegionStruct();

            % Selected side-specific areas.
            areaKeys = string(app.SelectedAreaKeys(:));

            for iKey = 1:numel(areaKeys)
                idx = find(app.AreaTable.TagFull == areaKeys(iKey), 1, 'first');

                if isempty(idx)
                    continue
                end

                pgon = app.area_polyshapes(app.AreaTable.AreaIdx(idx));

                rec = app.emptyAtlasOutputRegionStruct();
                rec.type = 'area';
                rec.tag = char(app.AreaTable.TagFull(idx));
                rec.baseTag = char(app.AreaTable.TagBase(idx));
                rec.side = char(app.AreaTable.Side(idx));
                rec.name = char(app.AreaTable.LongNameBase(idx));
                rec.fullName = char(app.AreaTable.LongNameFull(idx));
                rec.parentTag = char(app.AreaTable.ModuleBaseKey(idx));
                rec.parentName = char(app.AreaTable.ParentBase(idx));
                rec.color = double(app.AreaTable.Color{idx});
                rec.polyshape = pgon;
                rec.verticesXY = app.getVerticesFromAtlasPolyshape(pgon);

                selectedRegions(end+1) = rec; %#ok<AGROW>
            end

            % Selected side-specific merged modules.
            moduleKeys = string(app.SelectedModuleKeys(:));

            for iKey = 1:numel(moduleKeys)
                idx = find(app.ModuleTable.TagFull == moduleKeys(iKey), 1, 'first');

                if isempty(idx)
                    continue
                end

                pgon = app.cluster_polyshapes(app.ModuleTable.ModuleIdx(idx));

                rec = app.emptyAtlasOutputRegionStruct();
                rec.type = 'module';
                rec.tag = char(app.ModuleTable.TagFull(idx));
                rec.baseTag = char(app.ModuleTable.ModuleBaseKey(idx));
                rec.side = char(app.ModuleTable.Side(idx));
                rec.name = char(app.ModuleTable.LongNameBase(idx));
                rec.fullName = char(app.ModuleTable.LongNameFull(idx));
                rec.parentTag = char(app.ModuleTable.ModuleBaseKey(idx));
                rec.parentName = char(app.ModuleTable.LongNameBase(idx));
                rec.color = double(app.ModuleTable.Color{idx});
                rec.polyshape = pgon;
                rec.verticesXY = app.getVerticesFromAtlasPolyshape(pgon);

                selectedRegions(end+1) = rec; %#ok<AGROW>
            end

            if numel(selectedRegions) == 1 && isempty(selectedRegions(1).tag)
                selectedRegions = app.emptyAtlasOutputRegionStruct();
            elseif ~isempty(selectedRegions) && isempty(selectedRegions(1).tag)
                selectedRegions(1) = [];
            end

        end


        function rec = emptyAtlasOutputRegionStruct(app) %#ok<INUSL>
            %EMPTYATLASOUTPUTREGIONSTRUCT Template for selected atlas output regions.

            rec = struct( ...
                'type', '', ...
                'tag', '', ...
                'baseTag', '', ...
                'side', '', ...
                'name', '', ...
                'fullName', '', ...
                'parentTag', '', ...
                'parentName', '', ...
                'color', [0.5 0.5 0.5], ...
                'polyshape', polyshape(), ...
                'verticesXY', zeros(0, 2));

        end


        function verticesXY = getVerticesFromAtlasPolyshape(app, pgon) %#ok<INUSL>
            %GETVERTICESFROMATLASPOLYSHAPE Extract vertices from atlas polyshape.

            verticesXY = zeros(0, 2);

            try
                verticesXY = double(pgon.Vertices);
            catch
                verticesXY = zeros(0, 2);
            end

            if isempty(verticesXY)
                verticesXY = zeros(0, 2);
                return
            end

            if size(verticesXY, 2) ~= 2
                verticesXY = zeros(0, 2);
                return
            end

        end


        function boundaryVerticesXY = getAtlasBoundaryVerticesFromMask(app)
            %GETATLASBOUNDARYVERTICESFROMMASK Convert bound_mask to boundary polylines.
            %
            %   Output is a cell array of N-by-2 [X Y] vertex arrays. These are drawn in
            %   black in DataViewer during placement.

            boundaryVerticesXY = {};

            if isempty(app.bound_mask) || ~any(app.bound_mask(:))
                return
            end

            try
                B = bwboundaries(app.bound_mask, 8, 'noholes');
            catch
                B = bwboundaries(app.bound_mask);
            end

            for iB = 1:numel(B)
                rc = double(B{iB});

                if size(rc, 1) < 2
                    continue
                end

                % bwboundaries returns [row col] = [Y X]. Store as [X Y].
                boundaryVerticesXY{end+1} = [rc(:, 2), rc(:, 1)]; %#ok<AGROW>
            end

        end


        function applyRememberedAtlasSelectionFromJSON(app)
            %APPLYREMEMBEREDATLASSELECTIONFROMJSON Restore remembered atlas selection.
            %
            %   This method is intentionally tolerant:
            %       - missing JSON file is ignored;
            %       - stale atlas tags are ignored;
            %       - older JSON fields are handled when possible.

            try
                [~, jsonFile] = app.getAtlasSelectionJSONPath();
            catch
                return
            end

            if ~isfile(jsonFile)
                return
            end

            try
                jsonText = fileread(jsonFile);
                savedSelection = jsondecode(jsonText);
            catch ME
                warning('AllenAtlasROICreator:RememberedSelectionReadFailed', ...
                    'Could not read remembered atlas selection: %s', ME.message);
                return
            end

            [selectedAreaTags, selectedModuleTags, mergedModuleTags] = ...
                app.parseRememberedAtlasSelection(savedSelection);

            % Validate keys against the current atlas metadata. This avoids errors when the
            % atlas file changed or the JSON contains stale tags.
            selectedAreaTags = app.keepValidRememberedKeys(selectedAreaTags, app.AreaTable.TagFull);
            selectedModuleTags = app.keepValidRememberedKeys(selectedModuleTags, app.ModuleTable.TagFull);
            mergedModuleTags = app.keepValidRememberedKeys(mergedModuleTags, app.ModuleTable.ModuleBaseKey);

            app.MergedModuleBaseKeys = unique(mergedModuleTags(:), 'stable');
            app.SelectedAreaKeys = unique(selectedAreaTags(:), 'stable');
            app.SelectedModuleKeys = unique(selectedModuleTags(:), 'stable');

            % If a module is merged, its child area patches are hidden and the module layer
            % is the editable/interactive representation. Remove child area selections from
            % merged modules to avoid ambiguous hidden selections.
            for iModule = 1:numel(app.MergedModuleBaseKeys)
                areaKeys = app.getAreaKeysForModule(app.MergedModuleBaseKeys(iModule));
                app.SelectedAreaKeys = setdiff(app.SelectedAreaKeys(:), areaKeys(:), 'stable');
            end

            % Refresh module node labels and collapse state for restored merged modules.
            for iModule = 1:numel(app.MergedModuleBaseKeys)
                app.updateModuleNodeMergedText(app.MergedModuleBaseKeys(iModule));
                app.collapseModuleNode(app.MergedModuleBaseKeys(iModule));
            end

            app.showStatusInTitle('remembered selection restored');

        end


        function [selectedAreaTags, selectedModuleTags, mergedModuleTags] = parseRememberedAtlasSelection(app, savedSelection)
            %PARSEREMEMBEREDATLASSELECTION Extract tag arrays from remembered JSON.
            %
            %   Preferred fields:
            %       selectedAreaTags
            %       selectedModuleTags
            %       mergedModuleTags
            %
            %   Fallback fields:
            %       selectedAreas(:).tag
            %       selectedModules(:).tag
            %       mergedModules(:).baseTag

            selectedAreaTags = strings(0, 1);
            selectedModuleTags = strings(0, 1);
            mergedModuleTags = strings(0, 1);

            if ~isstruct(savedSelection) || ~isscalar(savedSelection)
                return
            end

            selectedAreaTags = app.readStringVectorField(savedSelection, 'selectedAreaTags');
            selectedModuleTags = app.readStringVectorField(savedSelection, 'selectedModuleTags');
            mergedModuleTags = app.readStringVectorField(savedSelection, 'mergedModuleTags');

            if isempty(selectedAreaTags) && isfield(savedSelection, 'selectedAreas')
                selectedAreaTags = app.readTagFieldFromRecordArray(savedSelection.selectedAreas, 'tag');
            end

            if isempty(selectedModuleTags) && isfield(savedSelection, 'selectedModules')
                selectedModuleTags = app.readTagFieldFromRecordArray(savedSelection.selectedModules, 'tag');
            end

            if isempty(mergedModuleTags) && isfield(savedSelection, 'mergedModules')
                mergedModuleTags = app.readTagFieldFromRecordArray(savedSelection.mergedModules, 'baseTag');
            end

            selectedAreaTags = unique(selectedAreaTags(:), 'stable');
            selectedModuleTags = unique(selectedModuleTags(:), 'stable');
            mergedModuleTags = unique(mergedModuleTags(:), 'stable');

        end


        function values = readStringVectorField(app, S, fieldName) %#ok<INUSL>
            %READSTRINGVECTORFIELD Safely read a JSON-decoded string/cell/char field.

            values = strings(0, 1);

            if ~isstruct(S) || ~isfield(S, fieldName)
                return
            end

            raw = S.(fieldName);

            if isempty(raw)
                return
            end

            try
                values = string(raw);
                values = values(:);
                values = values(strlength(values) > 0);
            catch
                values = strings(0, 1);
            end

        end


        function values = readTagFieldFromRecordArray(app, records, fieldName) %#ok<INUSL>
            %READTAGFIELDFROMRECORDARRAY Read a tag-like field from JSON record structs.

            values = strings(0, 1);

            if isempty(records) || ~isstruct(records)
                return
            end

            for iRec = 1:numel(records)
                if ~isfield(records(iRec), fieldName)
                    continue
                end

                try
                    thisValue = string(records(iRec).(fieldName));

                    if strlength(thisValue) > 0
                        values(end+1, 1) = thisValue; %#ok<AGROW>
                    end
                catch
                end
            end

            values = unique(values, 'stable');

        end


        function validKeys = keepValidRememberedKeys(app, candidateKeys, validKeysReference) %#ok<INUSL>
            %KEEPVALIDREMEMBEREDKEYS Keep only remembered keys present in current atlas.

            candidateKeys = string(candidateKeys(:));
            candidateKeys = candidateKeys(strlength(candidateKeys) > 0);

            validKeysReference = string(validKeysReference(:));

            validKeys = candidateKeys(ismember(candidateKeys, validKeysReference));
            validKeys = unique(validKeys(:), 'stable');

        end


        function rememberAtlasSelectionToJSON(app)
            %REMEMBERATLASSELECTIONTOJSON Save selected atlas regions to JSON.
            %
            %   The selection is saved under:
            %       <home>/Umit/AllenAtlasROISelection.json
            %
            %   Saved content:
            %       - side-specific selected area tags,
            %       - side-specific selected merged-module tags,
            %       - side-collapsed merged module tags,
            %       - human-readable names and region types.

            [outFolder, outFile] = app.getAtlasSelectionJSONPath();

            if ~isfolder(outFolder)
                mkdir(outFolder);
            end

            selectionStruct = app.buildAtlasSelectionJSONStruct();

            try
                jsonText = jsonencode(selectionStruct, 'PrettyPrint', true);
            catch
                jsonText = jsonencode(selectionStruct);
            end

            fid = fopen(outFile, 'w');

            if fid < 0
                error('AllenAtlasROICreator:JSONWriteFailed', ...
                    'Could not open file for writing: "%s".', outFile);
            end

            cleaner = onCleanup(@() fclose(fid));
            fwrite(fid, jsonText, 'char');

            clear cleaner

            app.showStatusInTitle(sprintf('Selection saved to %s', outFile));

        end

        function [outFolder, outFile] = getAtlasSelectionJSONPath(app)
            %GETATLASSELECTIONJSONPATH Return default JSON save path.
            %
            %   Uses the user's home folder and creates:
            %       <home>/Umit/AllenAtlasROISelection.json

            homeFolder = app.getUserHomeFolder();
            outFolder = fullfile(homeFolder, 'Umit');
            outFile = fullfile(outFolder, 'AllenAtlasROISelection.json');

        end


        function homeFolder = getUserHomeFolder(app) %#ok<INUSL>
            %GETUSERHOMEFOLDER Return cross-platform user home folder.

            homeFolder = '';

            try
                homeFolder = char(java.lang.System.getProperty('user.home'));
            catch
                homeFolder = '';
            end

            if isempty(homeFolder)
                homeFolder = getenv('USERPROFILE');
            end

            if isempty(homeFolder)
                homeFolder = getenv('HOME');
            end

            if isempty(homeFolder)
                homeFolder = pwd;
            end

        end


        function selectionStruct = buildAtlasSelectionJSONStruct(app)
            %BUILDATLASSELECTIONJSONSTRUCT Build serializable atlas selection struct.

            selectionStruct = struct();
            selectionStruct.schemaVersion = 1;
            selectionStruct.createdBy = 'AllenAtlasROICreator';
            selectionStruct.savedOn = char(datetime('now', 'Format', 'yyyy-MM-dd HH:mm:ss'));
            selectionStruct.atlasFile = app.AtlasFile;

            selectionStruct.selectedAreas = app.buildSelectedAreaJSONRecords();
            selectionStruct.selectedModules = app.buildSelectedModuleJSONRecords();
            selectionStruct.mergedModules = app.buildMergedModuleJSONRecords();

            selectionStruct.selectedAreaTags = cellstr(app.SelectedAreaKeys(:));
            selectionStruct.selectedModuleTags = cellstr(app.SelectedModuleKeys(:));
            selectionStruct.mergedModuleTags = cellstr(app.MergedModuleBaseKeys(:));

        end


        function records = buildSelectedAreaJSONRecords(app)
            %BUILDSELECTEDAREAJSONRECORDS Build JSON records for selected areas.

            records = app.emptyAtlasRegionJSONRecord();

            selectedKeys = string(app.SelectedAreaKeys(:));

            for iKey = 1:numel(selectedKeys)
                idx = find(app.AreaTable.TagFull == selectedKeys(iKey), 1, 'first');

                if isempty(idx)
                    continue
                end

                moduleIdx = find(app.ModuleTable.ModuleBaseKey == app.AreaTable.ModuleBaseKey(idx) & ...
                    app.ModuleTable.Side == app.AreaTable.Side(idx), 1, 'first');

                if isempty(moduleIdx)
                    moduleTag = app.AreaTable.ModuleBaseKey(idx);
                    moduleName = app.AreaTable.ParentBase(idx);
                else
                    moduleTag = app.ModuleTable.TagFull(moduleIdx);
                    moduleName = app.ModuleTable.LongNameBase(moduleIdx);
                end

                rec = struct();
                rec.type = 'area';
                rec.tag = char(app.AreaTable.TagFull(idx));
                rec.baseTag = char(app.AreaTable.TagBase(idx));
                rec.side = char(app.AreaTable.Side(idx));
                rec.name = char(app.AreaTable.LongNameBase(idx));
                rec.fullName = char(app.AreaTable.LongNameFull(idx));
                rec.moduleTag = char(moduleTag);
                rec.moduleBaseTag = char(app.AreaTable.ModuleBaseKey(idx));
                rec.moduleName = char(moduleName);

                records(end+1) = rec; %#ok<AGROW>
            end

            records = app.trimEmptyAtlasRegionJSONRecord(records);

        end


        function records = buildSelectedModuleJSONRecords(app)
            %BUILDSELECTEDMODULEJSONRECORDS Build JSON records for selected merged modules.

            records = app.emptyAtlasRegionJSONRecord();

            selectedKeys = string(app.SelectedModuleKeys(:));

            for iKey = 1:numel(selectedKeys)
                idx = find(app.ModuleTable.TagFull == selectedKeys(iKey), 1, 'first');

                if isempty(idx)
                    continue
                end

                rec = struct();
                rec.type = 'module';
                rec.tag = char(app.ModuleTable.TagFull(idx));
                rec.baseTag = char(app.ModuleTable.ModuleBaseKey(idx));
                rec.side = char(app.ModuleTable.Side(idx));
                rec.name = char(app.ModuleTable.LongNameBase(idx));
                rec.fullName = char(app.ModuleTable.LongNameFull(idx));
                rec.moduleTag = char(app.ModuleTable.TagFull(idx));
                rec.moduleBaseTag = char(app.ModuleTable.ModuleBaseKey(idx));
                rec.moduleName = char(app.ModuleTable.LongNameBase(idx));

                records(end+1) = rec; %#ok<AGROW>
            end

            records = app.trimEmptyAtlasRegionJSONRecord(records);

        end


        function records = buildMergedModuleJSONRecords(app)
            %BUILDMERGEDMODULEJSONRECORDS Build JSON records for side-collapsed merged modules.

            records = app.emptyAtlasMergedModuleJSONRecord();

            mergedKeys = string(app.MergedModuleBaseKeys(:));

            for iKey = 1:numel(mergedKeys)
                idx = find(app.ModuleTable.ModuleBaseKey == mergedKeys(iKey), 1, 'first');

                if isempty(idx)
                    continue
                end

                rec = struct();
                rec.type = 'mergedModule';
                rec.baseTag = char(app.ModuleTable.ModuleBaseKey(idx));
                rec.name = char(app.ModuleTable.LongNameBase(idx));
                rec.leftTag = '';
                rec.rightTag = '';

                idxLeft = find(app.ModuleTable.ModuleBaseKey == mergedKeys(iKey) & app.ModuleTable.Side == "L", 1, 'first');
                idxRight = find(app.ModuleTable.ModuleBaseKey == mergedKeys(iKey) & app.ModuleTable.Side == "R", 1, 'first');

                if ~isempty(idxLeft)
                    rec.leftTag = char(app.ModuleTable.TagFull(idxLeft));
                end

                if ~isempty(idxRight)
                    rec.rightTag = char(app.ModuleTable.TagFull(idxRight));
                end

                records(end+1) = rec; %#ok<AGROW>
            end

            records = app.trimEmptyAtlasMergedModuleJSONRecord(records);

        end


        function rec = emptyAtlasRegionJSONRecord(app) %#ok<INUSL>
            %EMPTYATLASREGIONJSONRECORD Template for selected area/module records.

            rec = struct( ...
                'type', '', ...
                'tag', '', ...
                'baseTag', '', ...
                'side', '', ...
                'name', '', ...
                'fullName', '', ...
                'moduleTag', '', ...
                'moduleBaseTag', '', ...
                'moduleName', '');

        end


        function records = trimEmptyAtlasRegionJSONRecord(app, records) %#ok<INUSL>
            %TRIMEMPTYATLASREGIONJSONRECORD Remove initial template record.

            if numel(records) == 1 && isempty(records(1).type)
                records = struct( ...
                    'type', {}, ...
                    'tag', {}, ...
                    'baseTag', {}, ...
                    'side', {}, ...
                    'name', {}, ...
                    'fullName', {}, ...
                    'moduleTag', {}, ...
                    'moduleBaseTag', {}, ...
                    'moduleName', {});
                return
            end

            if ~isempty(records) && isempty(records(1).type)
                records(1) = [];
            end

        end


        function rec = emptyAtlasMergedModuleJSONRecord(app) %#ok<INUSL>
            %EMPTYATLASMERGEDMODULEJSONRECORD Template for merged module records.

            rec = struct( ...
                'type', '', ...
                'baseTag', '', ...
                'name', '', ...
                'leftTag', '', ...
                'rightTag', '');

        end


        function records = trimEmptyAtlasMergedModuleJSONRecord(app, records) %#ok<INUSL>
            %TRIMEMPTYATLASMERGEDMODULEJSONRECORD Remove initial template record.

            if numel(records) == 1 && isempty(records(1).type)
                records = struct( ...
                    'type', {}, ...
                    'baseTag', {}, ...
                    'name', {}, ...
                    'leftTag', {}, ...
                    'rightTag', {});
                return
            end

            if ~isempty(records) && isempty(records(1).type)
                records(1) = [];
            end

        end

        function atlasFile = getDefaultAtlasInfoFilePath(app)
            %GETDEFAULTATLASINFOFILEPATH Return atlas file path next to this app.
            %
            %   Works for exported .m apps and for regular class files on the MATLAB path.
            %   For a compiled/packaged mlapp workflow, mfilename('fullpath') should still
            %   resolve to the generated class path.

            classFile = mfilename('fullpath');
            appFolder = fileparts(classFile);

            atlasFile = fullfile(appFolder, 'CCF_2022_mouse_topView_data.mat');
        end


        function expandAllAtlasTreeNodes(app)
            %EXPANDALLATLANTREENODES Expand all tree nodes by default.

            if isempty(app.Tree.Children)
                return
            end

            try
                iExpandNodeRecursive(app.Tree.Children(1));
            catch
                try
                    expand(app.Tree.Children(1));
                catch
                end
            end

            function iExpandNodeRecursive(node)
                try
                    expand(node);
                catch
                end

                for iChild = 1:numel(node.Children)
                    iExpandNodeRecursive(node.Children(iChild));
                end
            end
        end


        function setSearchUpdatingFalse(app)
            %SETSEARCHUPDATINGFALSE Cleanup helper for onCleanup.

            app.IsUpdatingSearch = false;
        end


        function showSearchResultsDropdown(app)
            %SHOWSEARCHRESULTSDROPDOWN Show dropdown and restore its grid row.
            try, app.SearchResultsDropDown.Visible = 'on'; catch, end
            try
                rowHeight = app.GridLayout2.RowHeight;
                rowHeight{2} = 30;
                rowHeight{3} = 295;
                app.GridLayout2.RowHeight = rowHeight;
            catch
            end
        end


        function onAtlasTreeCheckedNodesChanged(app)
            %ONATLASTREECHECKEDNODESCHANGED Apply checkbox tree selection changes.
            if app.IsUpdatingTree
                return
            end

            newKeys = app.getCheckedNodeKeys();
            oldKeys = app.CheckedTreeNodeKeys(:);
            addedKeys = setdiff(newKeys, oldKeys, 'stable');
            removedKeys = setdiff(oldKeys, newKeys, 'stable');

            for iKey = 1:numel(addedKeys)
                app.applyAtlasTreeNodeCheckChange(addedKeys(iKey), true);
            end
            for iKey = 1:numel(removedKeys)
                app.applyAtlasTreeNodeCheckChange(removedKeys(iKey), false);
            end

            app.updateTreeChecksFromSelection();
            app.updateAtlasMapDisplay();
        end


        function onAtlasTreeSelectionChanged(app)
            %ONATLASTREESELECTIONCHANGED Collapse merged module nodes if needed.
            app.collapseMergedModuleNodes();
        end


        function applyAtlasTreeNodeCheckChange(app, nodeKey, bChecked)
            %APPLYATLANTREENODECHECKCHANGE Apply user checkbox change.
            nodeKey = string(nodeKey);
            parts = split(nodeKey, "|");
            if numel(parts) ~= 2, return; end

            switch parts(1)
                case "root"
                    app.selectAllAtlasTreeItems(bChecked);
                case "module"
                    app.selectModuleBaseFromTreeOrSearch(parts(2), bChecked);
                case "area"
                    moduleKey = app.getModuleKeyForAreaBase(parts(2));
                    if moduleKey ~= "" && app.isModuleMerged(moduleKey)
                        return
                    end
                    app.selectAreaBaseFromTreeOrSearch(parts(2), bChecked);
            end
        end


        function selectAllAtlasTreeItems(app, bSelected)
            %SELECTALLATLANTREEITEMS Select/unselect all tree items.
            if ~bSelected
                app.SelectedAreaKeys = strings(0, 1);
                app.SelectedModuleKeys = strings(0, 1);
                return
            end

            areaKeys = strings(0, 1);
            moduleKeys = strings(0, 1);
            moduleBaseKeys = unique(app.AreaTable.ModuleBaseKey, 'stable');

            for iModule = 1:numel(moduleBaseKeys)
                if app.isModuleMerged(moduleBaseKeys(iModule))
                    moduleKeys = [moduleKeys; app.getModuleSideKeys(moduleBaseKeys(iModule))]; %#ok<AGROW>
                else
                    areaKeys = [areaKeys; app.getAreaKeysForModule(moduleBaseKeys(iModule))]; %#ok<AGROW>
                end
            end

            app.SelectedAreaKeys = unique(areaKeys, 'stable');
            app.SelectedModuleKeys = unique(moduleKeys, 'stable');
        end


        function selectAreaBaseFromTreeOrSearch(app, areaBaseKey, bSelected)
            %SELECTAREABASEFROMTREEORSEARCH Select/unselect both sides of one area.
            areaBaseKey = string(areaBaseKey);
            areaKeys = app.AreaTable.TagFull(app.AreaTable.TagBase == areaBaseKey);

            if bSelected
                app.SelectedAreaKeys = unique([app.SelectedAreaKeys(:); areaKeys(:)], 'stable');
            else
                app.SelectedAreaKeys = setdiff(app.SelectedAreaKeys(:), areaKeys(:), 'stable');
            end
        end


        function selectModuleBaseFromTreeOrSearch(app, moduleBaseKey, bSelected)
            %SELECTMODULEBASEFROMTREEORSEARCH Select module or its child areas.
            moduleBaseKey = string(moduleBaseKey);

            if app.isModuleMerged(moduleBaseKey)
                moduleKeys = app.getModuleSideKeys(moduleBaseKey);
                if bSelected
                    app.SelectedModuleKeys = unique([app.SelectedModuleKeys(:); moduleKeys(:)], 'stable');
                else
                    app.SelectedModuleKeys = setdiff(app.SelectedModuleKeys(:), moduleKeys(:), 'stable');
                end
                return
            end

            areaKeys = app.getAreaKeysForModule(moduleBaseKey);
            if bSelected
                app.SelectedAreaKeys = unique([app.SelectedAreaKeys(:); areaKeys(:)], 'stable');
            else
                app.SelectedAreaKeys = setdiff(app.SelectedAreaKeys(:), areaKeys(:), 'stable');
            end
        end


        function onAtlasAreaPatchClicked(app, areaKey)
            %ONATLASAREAPATCHCLICKED Toggle one side-specific area from map.
            areaKey = string(areaKey);
            row = find(app.AreaTable.TagFull == areaKey, 1, 'first');
            if isempty(row), return; end

            moduleBaseKey = app.AreaTable.ModuleBaseKey(row);
            if app.isModuleMerged(moduleBaseKey)
                return
            end

            if app.isKeySelected(areaKey, app.SelectedAreaKeys)
                app.SelectedAreaKeys = setdiff(app.SelectedAreaKeys(:), areaKey, 'stable');
            else
                app.SelectedAreaKeys = unique([app.SelectedAreaKeys(:); areaKey], 'stable');
            end

            app.updateTreeChecksFromSelection();
            app.updateAtlasMapDisplay();
        end


        function onAtlasModulePatchClicked(app, moduleKey)
            %ONATLASMODULEPATCHCLICKED Toggle one side-specific module if merged.
            moduleKey = string(moduleKey);
            row = find(app.ModuleTable.TagFull == moduleKey, 1, 'first');
            if isempty(row), return; end

            moduleBaseKey = app.ModuleTable.ModuleBaseKey(row);
            if ~app.isModuleMerged(moduleBaseKey)
                return
            end

            if app.isKeySelected(moduleKey, app.SelectedModuleKeys)
                app.SelectedModuleKeys = setdiff(app.SelectedModuleKeys(:), moduleKey, 'stable');
            else
                app.SelectedModuleKeys = unique([app.SelectedModuleKeys(:); moduleKey], 'stable');
            end

            app.updateTreeChecksFromSelection();
            app.updateAtlasMapDisplay();
        end


        function attachModuleContextMenu(app, moduleNode, moduleBaseKey)
            %ATTACHMODULECONTEXTMENU Add merge/unmerge context menu to module node.
            try
                cm = uicontextmenu(app.UIFigure);

                mergeMenu = uimenu(cm);
                mergeMenu.Text = 'Merge module';
                mergeMenu.MenuSelectedFcn = @(src, evt) app.mergeAtlasModule(moduleBaseKey);

                unmergeMenu = uimenu(cm);
                unmergeMenu.Text = 'Unmerge module';
                unmergeMenu.MenuSelectedFcn = @(src, evt) app.unmergeAtlasModule(moduleBaseKey);

                moduleNode.ContextMenu = cm;
                app.ModuleNodeContextMenuByKey(char(moduleBaseKey)) = cm;
            catch
                % Some MATLAB releases may not support node-specific context menus.
            end
        end


        function mergeAtlasModule(app, moduleBaseKey)
            %MERGEATLASMODULE Merge a side-collapsed module.
            moduleBaseKey = string(moduleBaseKey);
            app.MergedModuleBaseKeys = unique([app.MergedModuleBaseKeys(:); moduleBaseKey], 'stable');

            areaKeys = app.getAreaKeysForModule(moduleBaseKey);
            bAnyChildSelected = any(ismember(areaKeys, app.SelectedAreaKeys));
            app.SelectedAreaKeys = setdiff(app.SelectedAreaKeys(:), areaKeys(:), 'stable');

            if bAnyChildSelected
                moduleKeys = app.getModuleSideKeys(moduleBaseKey);
                app.SelectedModuleKeys = unique([app.SelectedModuleKeys(:); moduleKeys(:)], 'stable');
            end

            app.updateModuleNodeMergedText(moduleBaseKey);
            app.collapseModuleNode(moduleBaseKey);
            app.updateTreeChecksFromSelection();
            app.updateAtlasMapDisplay();
        end


        function unmergeAtlasModule(app, moduleBaseKey)
            %UNMERGEATLASMODULE Unmerge module and select all child areas.
            moduleBaseKey = string(moduleBaseKey);
            app.MergedModuleBaseKeys = setdiff(app.MergedModuleBaseKeys(:), moduleBaseKey, 'stable');

            moduleKeys = app.getModuleSideKeys(moduleBaseKey);
            app.SelectedModuleKeys = setdiff(app.SelectedModuleKeys(:), moduleKeys(:), 'stable');

            areaKeys = app.getAreaKeysForModule(moduleBaseKey);
            app.SelectedAreaKeys = unique([app.SelectedAreaKeys(:); areaKeys(:)], 'stable');

            app.updateModuleNodeMergedText(moduleBaseKey);
            app.expandModuleNode(moduleBaseKey);
            app.updateTreeChecksFromSelection();
            app.updateAtlasMapDisplay();
        end


        function updateModuleNodeMergedText(app, moduleBaseKey)
            %UPDATEMODULENODEMERGEDTEXT Append/remove " - merged".
            moduleBaseKey = string(moduleBaseKey);
            if ~isKey(app.TreeNodeByModuleBaseKey, char(moduleBaseKey)), return; end
            node = app.TreeNodeByModuleBaseKey(char(moduleBaseKey));

            idx = find(app.ModuleTable.ModuleBaseKey == moduleBaseKey, 1, 'first');
            if isempty(idx)
                moduleName = moduleBaseKey;
                moduleTag = moduleBaseKey;
            else
                moduleName = app.ModuleTable.LongNameBase(idx);
                moduleTag = app.ModuleTable.ModuleBaseKey(idx);
            end

            node.Text = app.formatAtlasTreeNodeText(moduleName, moduleTag, app.isModuleMerged(moduleBaseKey));
        end


        function updateTreeChecksFromSelection(app)
            %UPDATETREECHECKSFROMSELECTION Reflect current selection in checkboxes.
            if isempty(app.Tree.Children), return; end

            app.IsUpdatingTree = true;
            cleanupObj = onCleanup(@() app.setTreeUpdatingFalse());
            checkedNodes = matlab.ui.container.TreeNode.empty;

            areaBaseKeys = keys(app.TreeNodeByAreaBaseKey);
            for iKey = 1:numel(areaBaseKeys)
                areaBaseKey = string(areaBaseKeys{iKey});
                node = app.TreeNodeByAreaBaseKey(areaBaseKeys{iKey});
                data = node.NodeData;

                if isfield(data, 'moduleBaseKey') && app.isModuleMerged(data.moduleBaseKey)
                    continue
                end

                areaKeys = string(data.areaKeys(:));
                if any(ismember(areaKeys, app.SelectedAreaKeys))
                    checkedNodes(end+1) = node; %#ok<AGROW>
                end
            end

            moduleBaseKeys = keys(app.TreeNodeByModuleBaseKey);
            for iKey = 1:numel(moduleBaseKeys)
                moduleBaseKey = string(moduleBaseKeys{iKey});
                node = app.TreeNodeByModuleBaseKey(moduleBaseKeys{iKey});

                if app.isModuleMerged(moduleBaseKey)
                    moduleKeys = app.getModuleSideKeys(moduleBaseKey);
                    if any(ismember(moduleKeys, app.SelectedModuleKeys))
                        checkedNodes(end+1) = node; %#ok<AGROW>
                    end
                else
                    areaKeys = app.getAreaKeysForModule(moduleBaseKey);
                    if ~isempty(areaKeys) && all(ismember(areaKeys, app.SelectedAreaKeys))
                        checkedNodes(end+1) = node; %#ok<AGROW>
                    end
                end
            end

            if ~isempty(app.Tree.Children)
                rootNode = app.Tree.Children(1);
                moduleNodes = rootNode.Children;
                if ~isempty(moduleNodes) && all(ismember(moduleNodes, checkedNodes))
                    checkedNodes(end+1) = rootNode; %#ok<AGROW>
                end
            end

            try
                app.Tree.CheckedNodes = checkedNodes;
            catch
            end

            app.CheckedTreeNodeKeys = app.getNodeKeysFromNodes(checkedNodes);
            clear cleanupObj
            app.IsUpdatingTree = false;
            app.collapseMergedModuleNodes();
        end


        function setTreeUpdatingFalse(app)
            app.IsUpdatingTree = false;
        end


        function checkedKeys = getCheckedNodeKeys(app)
            try
                checkedNodes = app.Tree.CheckedNodes;
            catch
                checkedNodes = matlab.ui.container.TreeNode.empty;
            end
            checkedKeys = app.getNodeKeysFromNodes(checkedNodes);
        end


        function nodeKeys = getNodeKeysFromNodes(app, nodeArray) %#ok<INUSL>
            nodeKeys = strings(0, 1);
            for iNode = 1:numel(nodeArray)
                try
                    data = nodeArray(iNode).NodeData;
                    if isstruct(data) && isfield(data, 'nodeKey')
                        nodeKeys(end+1, 1) = string(data.nodeKey); %#ok<AGROW>
                    end
                catch
                end
            end
            nodeKeys = unique(nodeKeys, 'stable');
        end


        function collapseMergedModuleNodes(app)
            for iKey = 1:numel(app.MergedModuleBaseKeys)
                app.collapseModuleNode(app.MergedModuleBaseKeys(iKey));
            end
        end

        function collapseModuleNode(app, moduleBaseKey)
            moduleBaseKey = string(moduleBaseKey);
            if ~isKey(app.TreeNodeByModuleBaseKey, char(moduleBaseKey)), return; end
            try, collapse(app.TreeNodeByModuleBaseKey(char(moduleBaseKey))); catch, end
        end

        function expandModuleNode(app, moduleBaseKey)
            moduleBaseKey = string(moduleBaseKey);
            if app.isModuleMerged(moduleBaseKey)
                app.collapseModuleNode(moduleBaseKey);
                return
            end
            if ~isKey(app.TreeNodeByModuleBaseKey, char(moduleBaseKey)), return; end
            try, expand(app.TreeNodeByModuleBaseKey(char(moduleBaseKey))); catch, end
        end

        function expandParentModuleForArea(app, areaBaseKey)
            moduleKey = app.getModuleKeyForAreaBase(areaBaseKey);
            if moduleKey ~= ""
                app.expandModuleNode(moduleKey);
            end
        end

        function moduleKey = getModuleKeyForAreaBase(app, areaBaseKey)
            idx = find(app.AreaTable.TagBase == string(areaBaseKey), 1, 'first');
            if isempty(idx)
                moduleKey = "";
            else
                moduleKey = app.AreaTable.ModuleBaseKey(idx);
            end
        end

        function areaKeys = getAreaKeysForModule(app, moduleBaseKey)
            moduleBaseKey = string(moduleBaseKey);
            if isKey(app.AreaKeysByModuleBaseKey, char(moduleBaseKey))
                areaKeys = string(app.AreaKeysByModuleBaseKey(char(moduleBaseKey)));
            else
                areaKeys = strings(0, 1);
            end
            areaKeys = areaKeys(:);
        end

        function moduleKeys = getModuleSideKeys(app, moduleBaseKey)
            moduleBaseKey = string(moduleBaseKey);
            if isKey(app.ModuleKeysByModuleBaseKey, char(moduleBaseKey))
                moduleKeys = string(app.ModuleKeysByModuleBaseKey(char(moduleBaseKey)));
            else
                moduleKeys = strings(0, 1);
            end
            moduleKeys = moduleKeys(:);
        end

        function tf = isModuleMerged(app, moduleBaseKey)
            tf = any(app.MergedModuleBaseKeys == string(moduleBaseKey));
        end

        function tf = isKeySelected(app, key, keyList) %#ok<INUSL>
            tf = any(string(keyList(:)) == string(key));
        end

        function txt = formatAtlasTreeNodeText(app, nameText, tagText, bMerged) %#ok<INUSL>
            nameText = strtrim(char(string(nameText)));
            tagText = strtrim(char(string(tagText)));
            if isempty(tagText)
                txt = nameText;
            else
                txt = sprintf('%s (%s)', nameText, tagText);
            end
            if bMerged
                txt = sprintf('%s - merged', txt);
            end
        end

        function [baseName, side] = stripAtlasSideFromName(app, fullName) %#ok<INUSL>
            txt = strtrim(char(string(fullName)));
            side = "";
            if startsWith(txt, 'Left ', 'IgnoreCase', true)
                side = "L";
                txt = regexprep(txt, '^[Ll]eft\s+', '');
            elseif startsWith(txt, 'Right ', 'IgnoreCase', true)
                side = "R";
                txt = regexprep(txt, '^[Rr]ight\s+', '');
            end
            baseName = string(strtrim(txt));
        end

        function [baseTag, side] = stripAtlasSideFromTag(app, fullTag) %#ok<INUSL>
            tag = strtrim(char(string(fullTag)));
            side = "";
            if endsWith(tag, '_L')
                side = "L";
                tag = extractBefore(string(tag), strlength(tag) - 1);
            elseif endsWith(tag, '_R')
                side = "R";
                tag = extractBefore(string(tag), strlength(tag) - 1);
            else
                tag = string(tag);
            end
            baseTag = string(tag);
        end

        function showStatusInTitle(app, msg)
            %SHOWSTATUSINTITLE Show temporary status text in app title for 5 seconds.
            %
            %   The timer is deleted from StopFcn instead of TimerFcn to avoid MATLAB's
            %   warning about deleting a running timer object.

            baseTitle = 'Allen Atlas ROI Creator';

            app.cleanupStatusTitleTimer();

            try
                app.UIFigure.Name = sprintf('%s - %s', baseTitle, char(string(msg)));
            catch
                return
            end

            t = timer( ...
                'ExecutionMode', 'singleShot', ...
                'StartDelay', 5, ...
                'TimerFcn', @(src, evt) app.restoreStatusTitleTimerFcn(src, baseTitle), ...
                'StopFcn', @(src, evt) app.deleteStatusTitleTimerFcn(src));

            app.StatusTitleTimer = t;
            start(t);

        end

        function restoreStatusTitleTimerFcn(app, timerObj, baseTitle)
            %RESTORESTATUSTITLETIMERFCN Restore the app title after temporary status.

            try
                if isvalid(app) && isvalid(app.UIFigure)
                    app.UIFigure.Name = baseTitle;
                end
            catch
            end

            % Do not delete timerObj here. It is still running while TimerFcn executes.
            % The timer deletes itself safely from StopFcn.
        end


        function deleteStatusTitleTimerFcn(app, timerObj)
            %DELETESTATUSTITLETIMERFCN Delete stopped status-title timer.

            try
                if isvalid(timerObj)
                    delete(timerObj);
                end
            catch
            end

            try
                if isvalid(app) && isequal(app.StatusTitleTimer, timerObj)
                    app.StatusTitleTimer = timer.empty;
                end
            catch
            end
        end

        function cleanupStatusTitleTimer(app)
            %CLEANUPSTATUSTITLETIMER Stop/delete any existing title-status timer.

            try
                if isempty(app.StatusTitleTimer) || ~isvalid(app.StatusTitleTimer)
                    app.StatusTitleTimer = timer.empty;
                    return
                end

                t = app.StatusTitleTimer;
                app.StatusTitleTimer = timer.empty;

                if strcmpi(t.Running, 'on')
                    stop(t);   % StopFcn will delete it.
                else
                    delete(t);
                end
            catch
                app.StatusTitleTimer = timer.empty;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            %STARTUPFCN Load atlas data and initialize selector GUI.
            %
            %   By default, the atlas file is loaded from the same folder as this app:
            %       CCF_2022_mouse_topView_data.mat
            %
            %   If a remembered selection JSON exists under <home>/Umit, it is restored
            %   after the atlas lookup tables, tree nodes, and map layers are created.

            if nargin < 2
                warning('Operating in dev mode. No DataViewer handle passed!')
            else
                app.MainApp = MainApp;
            end

            app.UIFigure.Visible = 'off';
            placeAppInsideCaller(app.MainApp,app,'center');
            app.initializeAtlasRuntimeMaps();
            app.hideSearchResultsDropdown();

            app.loadAtlasInfo();
            app.buildAtlasLookupTables();
            app.populateAtlasTree();
            app.createAtlasMapLayers();

            % Restore remembered selection after tree/map creation so node labels, checks,
            % collapse state, and map graphics can be updated immediately.
            app.applyRememberedAtlasSelectionFromJSON();

            app.updateTreeChecksFromSelection();
            app.updateAtlasMapDisplay();

            app.SearchResultsDropDown.Items = {'Select search result...'};
            app.SearchResultsDropDown.ItemsData = {'__select__'};
            app.SearchResultsDropDown.Value = '__select__';
            app.hideSearchResultsDropdown();

            app.UIFigure.Visible = 'on';
        end

        % Value changing function: SearchField
        function SearchFieldValueChanging(app, event)
            app.updateAtlasSearchResults(event.Value);

        end

        % Value changed function: SearchField
        function SearchFieldValueChanged(app, event)
            app.updateAtlasSearchResults(app.SearchField.Value);

        end

        % Value changed function: SearchResultsDropDown
        function SearchResultsDropDownValueChanged(app, event)
            app.selectAtlasItemFromSearchKey(app.SearchResultsDropDown.Value);


        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            %OKBUTTONPUSHED Return selected atlas regions to the parent DataViewer app.

            result = app.buildAtlasSelectionResult();

            if isempty(result.selectedRegions)
                app.showStatusInTitle('no atlas regions selected');
                return
            end

            app.Result = result;
            app.Confirmed = true;

            if ~isempty(app.MainApp) && isvalid(app.MainApp)
                try
                    app.MainApp.startAllenAtlasROIPlacement(result);
                    app.UIFigure.Visible = 'off';
                catch ME
                    app.showStatusInTitle(sprintf('handoff failed: %s', ME.message));
                    rethrow(ME)
                end
            else
                % Standalone/test mode: keep result in app.Result and close modal wait if used.
                try
                    uiresume(app.UIFigure);
                catch ME
                    rethrow(ME)
                end
                app.showStatusInTitle('selection ready');
            end

        end

        % Callback function: CancelButton, UIFigure
        function CancelButtonPushed(app, event)
            app.cleanupStatusTitleTimer();
            delete(app);

        end

        % Button pushed function: RememberselectionButton
        function RememberselectionButtonPushed(app, event)
            %REMEMBERSELECTIONBUTTONPUSHED Save current atlas ROI selection to JSON.

            app.rememberAtlasSelectionToJSON();

        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            app.onAtlasTreeCheckedNodesChanged();


        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            app.onAtlasTreeSelectionChanged();


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Allen Atlas ROI Creator';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Interactive Map')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SearchField = uieditfield(app.GridLayout2, 'text');
            app.SearchField.ValueChangedFcn = createCallbackFcn(app, @SearchFieldValueChanged, true);
            app.SearchField.ValueChangingFcn = createCallbackFcn(app, @SearchFieldValueChanging, true);
            app.SearchField.Placeholder = 'Search for area...';
            app.SearchResultsDropDown = uidropdown(app.GridLayout2);
            app.SearchResultsDropDown.ValueChangedFcn = createCallbackFcn(app, @SearchResultsDropDownValueChanged, true);
            app.SearchResultsDropDown.Visible = 'off';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.OkButton = uibutton(app.GridLayout3, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.GridLayout3, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.RememberselectionButton = uibutton(app.GridLayout3, 'push');
            app.RememberselectionButton.ButtonPushedFcn = createCallbackFcn(app, @RememberselectionButtonPushed, true);
            app.RememberselectionButton.Text = 'Remember selection';
            app.Tree = uitree(app.GridLayout2, 'checkbox');
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.Node2 = uitreenode(app.Tree);
            app.Node2.Text = 'Node2';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AllenAtlasROICreator(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end