classdef DataViewer_Coreg2Cams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        StatusLabel                     matlab.ui.control.Label
        GrifLayoutLeft                  matlab.ui.container.GridLayout
        CoregistrationSourcePanel       matlab.ui.container.Panel
        GridCoregistrationSource        matlab.ui.container.GridLayout
        LoadCoregistrationDataButton    matlab.ui.control.Button
        CoregistrationHistoryPanel      matlab.ui.container.Panel
        GridGeometricPanel              matlab.ui.container.GridLayout
        RestorePreviousCoregistrationButton  matlab.ui.control.Button
        BrowseCoregistrationHistoryButton  matlab.ui.control.Button
        CoregistrationFileInfoTextArea  matlab.ui.control.TextArea
        ManualfinetuningPanel           matlab.ui.container.Panel
        GridManualFineTuning            matlab.ui.container.GridLayout
        ResetButton                     matlab.ui.control.Button
        ApplyStepSpinner                matlab.ui.control.Spinner
        ApplyStepSpinnerLabel           matlab.ui.control.Label
        StepsizeEditField               matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel          matlab.ui.control.Label
        TransformationDropDown          matlab.ui.control.DropDown
        TransformationDropDownLabel     matlab.ui.control.Label
        ViewmodeButtonGroup             matlab.ui.container.ButtonGroup
        FalseColorOverlayButton         matlab.ui.control.RadioButton
        AlternateViewButton             matlab.ui.control.RadioButton
        SaveCoregistrationButton        matlab.ui.control.Button
        CoregistrationparametersPanel   matlab.ui.container.Panel
        GridCoregistrationPanel         matlab.ui.container.GridLayout
        FlipHorizontallyCheckBox        matlab.ui.control.CheckBox
        UseenhancedcontrastCheckBox     matlab.ui.control.CheckBox
        RunAutomaticcoregistrationButton  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % Parent app/context.
        MainApp = []
        appMode char = 'CoregistrationUtility'

        % Centralized rig context. UMITRigStore is this app's sole persistence layer
        % for camera coregistration -- a default rig is resolved/auto-created at
        % startup (see ensureRigContext) if the caller didn't already provide one, so
        % HasRigContext should normally always be true once startupFcn completes.
        % cameraCoregistration is a rig-owned resource, completely independent of any
        % project; ProjectStore itself is not required to resolve/save it, but is
        % kept for possible future use and parity with the launch context DataViewer
        % already resolves.
        ProjectStore = []
        RigUUID char = ''
        RigID char = ''
        SubjectUUID char = ''
        SubjectID char = ''
        SessionUUID char = ''
        SessionID char = ''
        HasRigContext logical = false
        RigStoreHandle = [] % Cached UMITRigStore handle; see openRigStore.

        % Coregistration source images. These must represent unregistered camera
        % images. CurrentMovingImage is transformed into CurrentRegisteredMovingImage.
        CurrentFixedImage = []
        CurrentMovingImage = []
        CurrentRegisteredMovingImage = []
        EnhancedFixedImage = []
        EnhancedMovingImage = []
        HasCoregistrationSource logical = false
        CoregistrationSourceFolder char = ''
        CoregistrationSourceDescription char = ''

        % Active coregistration, resolved from UMITRigStore (see loadActiveCoregistration).
        ActiveTform = []
        ActiveTformInfo = struct()

        % Candidate coregistration currently previewed in the app.
        CurrentTform = []
        CurrentTformInfo = struct()
        CandidateSource char = ''
        HasUnsavedCoregistration logical = false

        % Manual adjustment state. These are applied on top of BaseTform.
        BaseTform = []
        ManualDx double = 0
        ManualDy double = 0
        ManualRotationDeg double = 0
        ManualScale double = 1

        % Alternate-view timer state.
        AlternateViewTimer = timer.empty
        AlternateViewImageHandle = []
        AlternateViewIndex double = 1
    end


    properties (Access = public)
        DataFolder                % Optional context folder from the parent app.
    end


    methods (Access = {?DataViewer})

        function rigStore = openRigStore(app)
            %OPENRIGSTORE Return this app's cached rig store handle, opening (and
            %caching) it on first use. Returns [] if there is no rig context or the
            %rig can't be opened. Cached so frequent UI refreshes don't repeatedly
            %pay for UMITRigStore.open's directory scan; each accessor call on the
            %returned handle still reads current on-disk registry data.

            if ~app.HasRigContext
                app.RigStoreHandle = [];
                rigStore = [];
                return
            end

            if ~isempty(app.RigStoreHandle) && isvalid(app.RigStoreHandle)
                rigStore = app.RigStoreHandle;
                return
            end

            rigStore = [];
            if ~isempty(app.RigUUID)
                try
                    rigStore = UMITRigStore.open(app.RigUUID);
                catch
                    rigStore = [];
                end
            end
            if isempty(rigStore) && ~isempty(app.RigID)
                try
                    rigStore = UMITRigStore.openByRigID(app.RigID);
                catch
                    rigStore = [];
                end
            end

            app.RigStoreHandle = rigStore;
        end

        function ok = ensureRigContext(app)
            %ENSURERIGCONTEXT Resolve/auto-create a default rig if none is set yet.
            %
            %   A rig is mandatory for camera coregistration: UMITRigStore is this
            %   app's sole persistence layer, so a coregistration cannot be saved
            %   without one. Called at startup, and defensively again before save in
            %   case the rig became unavailable mid-session.

            ok = app.HasRigContext && ~isempty(app.openRigStore());
            if ok
                return
            end

            try
                rigStore = UMITRigStore.getOrCreateDefaultRig();
                RigInfo = rigStore.getRigInfo();
                app.RigUUID = RigInfo.uuid;
                app.RigID = RigInfo.rigID;
                app.HasRigContext = true;
                app.RigStoreHandle = rigStore;
                ok = true;
            catch
                app.HasRigContext = false;
                app.RigStoreHandle = [];
                ok = false;
            end
        end

        function resourceUUID = saveTform(app, tform, tformInfo)
            %SAVETFORM Save a dual-camera coregistration transform as the rig's new
            %active managed cameraCoregistration resource.
            %
            %   resourceUUID = saveTform(app, tform, tformInfo) writes tform/tformInfo
            %   to a temporary .mat file, imports it into UMITRigStore via
            %   addCameraCoregistration, and activates it. UMITRigStore is the sole
            %   persistence layer for this -- there is no local flat-file fallback --
            %   so this errors (rather than warning and continuing) if a rig can't be
            %   resolved or the store write fails. The previously active resource, if
            %   any, is automatically demoted to 'available' (not deleted) by
            %   setActiveCameraCoregistration, and remains browsable/restorable from
            %   Coregistration History.

            errID = 'DataViewerCoreg2Cams:SaveFailed';

            if ~app.ensureRigContext()
                error(errID, ...
                    ['Could not resolve or create a rig for this machine. A rig is ' ...
                    'required to save a camera coregistration.']);
            end

            rigStore = app.openRigStore();
            if isempty(rigStore)
                error(errID, 'Could not open the rig store to save this coregistration.');
            end

            tempFile = [tempname() '.mat'];
            save(tempFile, 'tform', 'tformInfo');

            try
                resourceInfo = struct( ...
                    'displayName', sprintf('Camera coregistration %s', ...
                    char(datetime('now', 'Format', 'yyyy-MM-dd HH:mm'))), ...
                    'description', sprintf('Saved via DataViewer_Coreg2Cams from %s', ...
                    app.CoregistrationSourceFolder));

                resourceUUID = rigStore.addCameraCoregistration(tempFile, resourceInfo);
                rigStore.setActiveCameraCoregistration(resourceUUID);
            catch ME
                if isfile(tempFile)
                    delete(tempFile);
                end
                rethrow(ME)
            end

            if isfile(tempFile)
                delete(tempFile);
            end

            app.ActiveTform = tform;
            app.ActiveTformInfo = tformInfo;
        end
    end
    methods (Access = private)
        function loadCameraImages(app)
            %LOADCAMERAIMAGES Load unregistered coregistration-source camera images.
            %
            %   The coregistration source folder must contain AcqInfos.mat and the
            %   per-channel .dat files generated without applying dual-camera
            %   coregistration. This function does not import raw data by itself.

            if isempty(app.CoregistrationSourceFolder) || ~isfolder(app.CoregistrationSourceFolder)
                error('DataViewerCoreg2Cams:MissingCoregistrationSource', ...
                    'Select a valid coregistration-source folder first.');
            end


            %%%% Import Coregistration recording %%%%

            % Get list of current files in the folder to preserve after
            % cleanup
            fileInfoCoregFolder = dir(app.CoregistrationSourceFolder);
            fileInfoCoregFolder([fileInfoCoregFolder.isdir]) = [];
            % Import data without applying the registration by calling
            % ImagesClassification directly.
            bkpFile = ['backup_' datestr(now(),'yyyymmdd_hhMMss')];
            coregFiles = ImagesClassification(app.CoregistrationSourceFolder,app.CoregistrationSourceFolder,1,1,'backupOpts',bkpFile);
            bkpFile = [bkpFile '.zip'];
            % Get AcqInfo:
            S = load(fullfile(app.CoregistrationSourceFolder,'AcqInfos.mat'));
            AcqInfoStream = S.AcqInfoStream;
            illumFields = fieldnames(AcqInfoStream);
            illumFields = illumFields(startsWith(illumFields, 'Illumination'));

            if isempty(illumFields)
                error('DataViewerCoreg2Cams:NoIlluminations', ...
                    'No Illumination fields were found in AcqInfoStream.');
            end

            cam1List = {};
            cam2List = {};

            for iField = 1:numel(illumFields)
                thisInfo = AcqInfoStream.(illumFields{iField});

                if ~isfield(thisInfo, 'CamIdx') || ~isfield(thisInfo, 'Color')
                    continue
                end

                idx = thisInfo.CamIdx;
                chan = lower(char(string(thisInfo.Color)));
                % Manage Fluo Channel Name
                if contains(chan, 'fluo')
                    tok= regexp(chan, '(\d+)\s*nm\b*', 'tokens');
                    if ~isempty(tok)
                        wavTag = tok{:}{:};
                        chan = ['fluo_' wavTag];
                    else
                        chan = 'fluo';
                    end
                end
                % Manage Yellow Channel name
                if contains(chan, 'amber')
                    chan = 'yellow';
                end

                datName = [chan '.dat'];
                if idx == 1
                    cam1List{end+1} = datName; %#ok<AGROW>
                elseif idx == 2
                    cam2List{end+1} = datName; %#ok<AGROW>
                end
            end

            if isempty(cam1List) || isempty(cam2List)
                error('DataViewerCoreg2Cams:MissingCameraChannels', ...
                    'Could not identify at least one .dat channel for each camera.');
            end
            assert(all(ismember([cam1List, cam2List],coregFiles)), ...
                'There is a mismatch between the existing .dat files and the channel names extracted from AcqInfos.mat file.');

            app.CurrentFixedImage = app.buildCompositeCameraImage(cam1List);
            app.CurrentMovingImage = app.buildCompositeCameraImage(cam2List);

            if app.FlipHorizontallyCheckBox.Value
                app.CurrentMovingImage = fliplr(app.CurrentMovingImage);
            end

            app.CurrentFixedImage = app.normalizeImage(app.CurrentFixedImage);
            app.CurrentMovingImage = app.normalizeImage(app.CurrentMovingImage);

            app.EnhancedFixedImage = adapthisteq(app.CurrentFixedImage);
            app.EnhancedMovingImage = adapthisteq(app.CurrentMovingImage);

            app.HasCoregistrationSource = true;
            app.CoregistrationSourceDescription = app.CoregistrationSourceFolder;
            % Restore previous files
            unzip(fullfile(app.CoregistrationSourceFolder,bkpFile),app.CoregistrationSourceFolder);
            delete(fullfile(app.CoregistrationSourceFolder,bkpFile));
        end

        function configureInitialState(app)
            %CONFIGUREINITIALSTATE Initialize UI state before file/source loading.

            app.UIFigure.Visible = 'off';
            app.UIFigure.WindowStyle = 'modal';

            app.CoregistrationFileInfoTextArea.Editable = 'off';
            app.CoregistrationFileInfoTextArea.Value = {'No coregistration information loaded.'};

            app.StepsizeEditField.Limits = [eps Inf];
            app.StepsizeEditField.Value = 1;
            app.updateStepSizeLabel();

            app.ApplyStepSpinner.Limits = [-1 1];
            app.ApplyStepSpinner.Step = 1;
            app.ApplyStepSpinner.Value = 0;

            app.RunAutomaticcoregistrationButton.Enable = 'off';
            app.SaveCoregistrationButton.Enable = 'off';
            app.BrowseCoregistrationHistoryButton.Enable = 'off';
            app.RestorePreviousCoregistrationButton.Enable = 'off';
            app.setManualControlsEnabled(false);

            cla(app.UIAxes);
            title(app.UIAxes, 'Load coregistration data to start');
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            axis(app.UIAxes, 'image');
            colormap(app.UIAxes, gray);

            app.setLocalStatus('Ready. Load coregistration data to preview or create a coregistration.');
        end

        function severity = setLocalStatus(app, msg, varargin)
            %SETLOCALSTATUS Update status label text.
            %
            %   severity = setLocalStatus(app, msg)
            %   severity = setLocalStatus(app, msg, 'Severity', severity)
            %
            %   Routes Dual Camera Coregistration footer messages through the shared
            %   GUI status utility. This wrapper keeps callbacks independent from the
            %   concrete status-label name and from light/dark color rules.

            if isempty(app.StatusLabel) || ~isvalid(app.StatusLabel)
                severity = '';
                return
            end

            background = app.getStatusBackgroundPolarity();

            severity = setStatusMessage(app.StatusLabel, msg, ...
                'Background', background, ...
                varargin{:});

        end

        function background = getStatusBackgroundPolarity(app)
            %GETSTATUSBACKGROUNDPOLARITY Return UI background polarity for status colors.
            %
            %   The Dual Camera Coregistration utility currently uses the default
            %   light App Designer appearance. This helper keeps the status-message
            %   API ready for a future manual light/dark mode implementation. When a
            %   dedicated theme property is added to the app, update this method only.

            background = 'light';

            if isempty(app.UIFigure) || ~isvalid(app.UIFigure)
                return
            end

            try
                if isprop(app.UIFigure, 'Color') && ~isempty(app.UIFigure.Color)
                    figColor = double(app.UIFigure.Color(:).');

                    if numel(figColor) >= 3 && all(isfinite(figColor(1:3)))
                        luminanceValue = 0.2126 * figColor(1) + ...
                            0.7152 * figColor(2) + ...
                            0.0722 * figColor(3);

                        if luminanceValue < 0.5
                            background = 'dark';
                        end
                    end
                end
            catch
                background = 'light';
            end

        end

        function setManualControlsEnabled(app, tf)
            %SETMANUALCONTROLSENABLED Enable or disable manual fine-tuning controls.

            state = matlab.lang.OnOffSwitchState(tf);
            app.TransformationDropDown.Enable = state;
            app.StepsizeEditField.Enable = state;
            app.ApplyStepSpinner.Enable = state;
            app.ResetButton.Enable = state;
        end

        function refreshButtonStates(app)
            %REFRESHBUTTONSTATES Enable controls from current camera coregistration state.

            hasSource = app.HasCoregistrationSource && ...
                ~isempty(app.CurrentFixedImage) && ~isempty(app.CurrentMovingImage);
            hasCandidate = ~isempty(app.CurrentTform);
            hasHistory = app.hasCoregistrationHistory();

            app.LoadCoregistrationDataButton.Enable = 'on';
            app.RunAutomaticcoregistrationButton.Enable = matlab.lang.OnOffSwitchState(hasSource);
            app.BrowseCoregistrationHistoryButton.Enable = matlab.lang.OnOffSwitchState(hasHistory);
            app.RestorePreviousCoregistrationButton.Enable = matlab.lang.OnOffSwitchState(hasHistory);
            app.SaveCoregistrationButton.Enable = matlab.lang.OnOffSwitchState(~isempty(app.CurrentTform) && app.HasUnsavedCoregistration);
            app.setManualControlsEnabled(hasSource && hasCandidate);
        end

        function refreshCoregistrationFileInfo(app)
            %REFRESHCOREGISTRATIONFILEINFO Update coregistration/source status text area.

            lines = strings(0, 1);

            if app.hasActiveCoregistration()
                rigStore = app.openRigStore();
                activeResource = rigStore.getActiveCameraCoregistration();
                lines(end+1) = "Active coregistration: " + string(activeResource.displayName);
                lines(end+1) = "Created: " + string(activeResource.createdOn);
            else
                lines(end+1) = "Active coregistration: not found";
            end

            lines(end+1) = "";

            if app.HasCoregistrationSource
                lines(end+1) = "Coregistration source: loaded";
                lines(end+1) = "Source folder: " + string(app.CoregistrationSourceFolder);
            else
                lines(end+1) = "Coregistration source: not loaded";
            end

            lines(end+1) = "";
            if isempty(app.CandidateSource)
                lines(end+1) = "Preview candidate: none";
            else
                lines(end+1) = "Preview candidate: " + string(app.CandidateSource);
            end

            hasPreviewImages = ~isempty(app.CurrentFixedImage) && ~isempty(app.CurrentMovingImage);
            lines(end+1) = "Preview available: " + string(hasPreviewImages);

            if app.HasUnsavedCoregistration
                lines(end+1) = "Unsaved candidate: yes";
            else
                lines(end+1) = "Unsaved candidate: no";
            end

            if ~isempty(app.CurrentTform)
                lines(end+1) = sprintf('Manual adjustment: dx=%.3g px, dy=%.3g px, rot=%.3g deg, scale=%.6g', ...
                    app.ManualDx, app.ManualDy, app.ManualRotationDeg, app.ManualScale);
            end

            app.CoregistrationFileInfoTextArea.Value = cellstr(lines);
        end


        function refreshAllState(app)
            %REFRESHALLSTATE Refresh all status, buttons, and preview.


            app.refreshCoregistrationFileInfo();
            app.refreshButtonStates();
            app.refreshPreview();
        end

        function tf = hasCoregistrationHistory(app)
            %HASCOREGISTRATIONHISTORY Return true when the rig has any saved
            %cameraCoregistration resources to browse/restore.

            rigStore = app.openRigStore();
            tf = ~isempty(rigStore) && ...
                ~isempty(rigStore.listResources('Type', 'cameraCoregistration'));
        end

        function record = pickCoregistrationResource(app, dialogTitle)
            %PICKCOREGISTRATIONRESOURCE Let the user pick one cameraCoregistration
            %resource from the rig's history (active + available + archived).
            %
            %   Returns the selected resource record (a UMITRigStore resource
            %   struct), or [] if there are none or the user cancels.

            record = [];

            rigStore = app.openRigStore();
            if isempty(rigStore)
                uialert(app.UIFigure, ...
                    'No rig is available to browse coregistration history.', ...
                    'No rig available', 'Icon', 'warning');
                return
            end

            resources = rigStore.listResources('Type', 'cameraCoregistration');
            if isempty(resources)
                uialert(app.UIFigure, ...
                    'This rig has no saved camera coregistrations yet.', ...
                    'No coregistration history', 'Icon', 'info');
                return
            end

            [~, sortIdx] = sort([resources.createdOn], 'descend');
            resources = resources(sortIdx);

            listItems = cell(numel(resources), 1);
            for iRes = 1:numel(resources)
                listItems{iRes} = sprintf('%s  [%s]  %s', ...
                    resources(iRes).displayName, resources(iRes).status, ...
                    char(resources(iRes).createdOn));
            end

            [selIdx, isOK] = listdlg('ListString', listItems, ...
                'SelectionMode', 'single', ...
                'Name', dialogTitle, ...
                'PromptString', 'Select a camera coregistration:', ...
                'ListSize', [420, 300]);

            if ~isOK || isempty(selIdx)
                return
            end

            record = resources(selIdx);
        end

        function tf = restorePreviewImagesFromTformInfo(app, tformInfo)
            %RESTOREPREVIEWIMAGESFROMTFORMINFO Populate preview images from a loaded
            %tformInfo's stored OriginalImages, so an existing coregistration can be
            %reviewed/manually edited without reloading raw coregistration data.
            %
            %   Returns true when tformInfo.OriginalImages was present and used.
            %   OriginalImages is set by genTform2Cams (the fixed/moving pair that
            %   actually produced the stored tform) and is reused verbatim here -- it
            %   is NOT re-flipped against FlipHorizontallyCheckBox, since doing so
            %   could double-flip relative to how the tform was originally computed.

            tf = false;

            if ~isstruct(tformInfo) || ~isfield(tformInfo, 'OriginalImages')
                return
            end

            images = tformInfo.OriginalImages;
            if ~iscell(images) || numel(images) < 2 || isempty(images{1}) || isempty(images{2})
                return
            end

            app.CurrentFixedImage = images{1};
            app.CurrentMovingImage = images{2};
            app.EnhancedFixedImage = adapthisteq(app.CurrentFixedImage);
            app.EnhancedMovingImage = adapthisteq(app.CurrentMovingImage);
            app.HasCoregistrationSource = true;

            tf = true;
        end

        function loadActiveCoregistration(app)
            %LOADACTIVECOREGISTRATION Load the rig's active camera coregistration, if
            %any, into active/candidate state -- including its stored preview images,
            %when available, so it can be reviewed/edited without a fresh data load.

            app.ActiveTform = [];
            app.ActiveTformInfo = struct();

            rigStore = app.openRigStore();
            if isempty(rigStore)
                return
            end

            resource = rigStore.getActiveCameraCoregistration();
            if isempty(resource)
                return
            end

            activePath = rigStore.resolveResourcePath(resource.uuid);
            S = load(activePath, 'tform', 'tformInfo');
            if ~isfield(S, 'tform')
                error('DataViewerCoreg2Cams:InvalidTformFile', ...
                    'The active coregistration file does not contain variable "tform".');
            end

            app.ActiveTform = S.tform;
            if isfield(S, 'tformInfo')
                app.ActiveTformInfo = S.tformInfo;
            else
                app.ActiveTformInfo = struct();
            end

            app.restorePreviewImagesFromTformInfo(app.ActiveTformInfo);

            app.setCandidateTransform(app.ActiveTform, app.ActiveTformInfo, ...
                'Active coregistration', false, true);
        end

        function setCandidateTransform(app, tform, tformInfo, sourceLabel, isUnsaved, resetManual)
            %SETCANDIDATETRANSFORM Set current preview transform and metadata.

            if nargin < 6
                resetManual = true;
            end

            app.BaseTform = tform;
            app.CurrentTform = tform;
            app.CurrentTformInfo = tformInfo;
            app.CandidateSource = char(string(sourceLabel));
            app.HasUnsavedCoregistration = logical(isUnsaved);

            if resetManual
                app.ManualDx = 0;
                app.ManualDy = 0;
                app.ManualRotationDeg = 0;
                app.ManualScale = 1;
            end

            app.updateRegisteredMovingImage();
            app.refreshCoregistrationFileInfo();
            app.refreshButtonStates();
            app.refreshPreview();
        end

        function updateRegisteredMovingImage(app)
            %UPDATEREGISTEREDMOVINGIMAGE Apply current transform to moving image.

            app.CurrentRegisteredMovingImage = [];

            if isempty(app.CurrentTform) || isempty(app.CurrentMovingImage) || isempty(app.CurrentFixedImage)
                return
            end

            outRef = imref2d(size(app.CurrentFixedImage));
            app.CurrentRegisteredMovingImage = imwarp(app.CurrentMovingImage, app.CurrentTform, ...
                'OutputView', outRef, 'FillValues', 0);
            app.CurrentRegisteredMovingImage = app.normalizeImage(app.CurrentRegisteredMovingImage);
        end

        function img = getDisplayFixedImage(app)
            %GETDISPLAYFIXEDIMAGE Return fixed image with selected contrast mode.

            if app.UseenhancedcontrastCheckBox.Value && ~isempty(app.EnhancedFixedImage)
                img = app.EnhancedFixedImage;
            else
                img = app.CurrentFixedImage;
            end
        end

        function img = getDisplayMovingImage(app)
            %GETDISPLAYMOVINGIMAGE Return registered/unregistered moving image for display.

            if ~isempty(app.CurrentRegisteredMovingImage)
                img = app.CurrentRegisteredMovingImage;
            else
                img = app.CurrentMovingImage;
            end

            if app.UseenhancedcontrastCheckBox.Value
                img = adapthisteq(app.normalizeImage(img));
            else
                img = app.normalizeImage(img);
            end
        end

        function refreshPreview(app)
            %REFRESHPREVIEW Draw the current preview mode.

            app.stopAlternateViewTimer();
            cla(app.UIAxes);

            if ~app.HasCoregistrationSource || isempty(app.CurrentFixedImage) || isempty(app.CurrentMovingImage)
                title(app.UIAxes, 'Load coregistration data to preview coregistration');
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                axis(app.UIAxes, 'image');
                return
            end

            fixedImg = app.getDisplayFixedImage();
            movingImg = app.getDisplayMovingImage();

            if startsWith(app.ViewmodeButtonGroup.SelectedObject.Text, 'False', 'IgnoreCase', true)
                imshowpair(fixedImg, movingImg, 'falsecolor', 'Scaling', 'joint', 'Parent', app.UIAxes);
                title(app.UIAxes, app.getPreviewTitle('False-color overlay'), 'Interpreter', 'none');
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                axis(app.UIAxes, 'image');
            else
                app.startAlternateView(fixedImg, movingImg);
            end
        end

        function titleText = getPreviewTitle(app, modeText)
            %GETPREVIEWTITLE Compose preview title.

            if isempty(app.CandidateSource)
                candidateText = 'unregistered source';
            else
                candidateText = app.CandidateSource;
            end

            titleText = sprintf('%s | %s', modeText, candidateText);
        end

        function startAlternateView(app, fixedImg, movingImg)
            %STARTALTERNATEVIEW Start 1 Hz flicker comparison on one axes.

            app.stopAlternateViewTimer();
            app.AlternateViewIndex = 1;

            app.AlternateViewImageHandle = imagesc(app.UIAxes, fixedImg, [0 1]);
            colormap(app.UIAxes, gray);
            axis(app.UIAxes, 'image');
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            title(app.UIAxes, app.getPreviewTitle('Alternate view: Camera 1'), 'Interpreter', 'none');

            app.AlternateViewTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 1, ...
                'BusyMode', 'drop', ...
                'TimerFcn', @(~, ~) app.onAlternateViewTimerTick(fixedImg, movingImg));

            start(app.AlternateViewTimer);
        end

        function onAlternateViewTimerTick(app, fixedImg, movingImg)
            %ONALTERNATEVIEWTIMERTICK Alternate fixed and moving images in UIAxes.

            if isempty(app) || ~isvalid(app) || isempty(app.UIFigure) || ~isvalid(app.UIFigure)
                return
            end

            if isempty(app.AlternateViewImageHandle) || ~isvalid(app.AlternateViewImageHandle)
                return
            end

            if app.AlternateViewIndex == 1
                app.AlternateViewImageHandle.CData = movingImg;
                app.AlternateViewIndex = 2;
                title(app.UIAxes, app.getPreviewTitle('Alternate view: Camera 2'), 'Interpreter', 'none');
            else
                app.AlternateViewImageHandle.CData = fixedImg;
                app.AlternateViewIndex = 1;
                title(app.UIAxes, app.getPreviewTitle('Alternate view: Camera 1'), 'Interpreter', 'none');
            end

            drawnow limitrate
        end

        function stopAlternateViewTimer(app)
            %STOPALTERNATEVIEWTIMER Stop and delete alternate-view timer.

            if ~isempty(app.AlternateViewTimer)
                try
                    if isvalid(app.AlternateViewTimer)
                        stop(app.AlternateViewTimer);
                        delete(app.AlternateViewTimer);
                    end
                catch
                end
            end

            app.AlternateViewTimer = timer.empty;
            app.AlternateViewImageHandle = [];
        end

        function updateStepSizeLabel(app)
            %UPDATESTEPSIZELABEL Update step-size label unit from selected transform.

            switch app.TransformationDropDown.Value
                case {'Horizontal (transl.)', 'Vertical (transl.)'}
                    app.StepsizeEditFieldLabel.Text = 'Step size (px)';
                    if app.StepsizeEditField.Value <= 0
                        app.StepsizeEditField.Value = 1;
                    end
                case 'Rotation'
                    app.StepsizeEditFieldLabel.Text = 'Step size (deg)';
                    if app.StepsizeEditField.Value <= 0
                        app.StepsizeEditField.Value = 0.1;
                    end
                case 'Scaling'
                    app.StepsizeEditFieldLabel.Text = 'Step size (%)';
                    if app.StepsizeEditField.Value <= 0
                        app.StepsizeEditField.Value = 1;
                    end
            end
        end

        function applyManualStep(app, signedStep)
            %APPLYMANUALSTEP Apply one manual nudge to the candidate transform.

            if isempty(app.BaseTform) || isempty(app.CurrentTform)
                return
            end

            stepSize = double(app.StepsizeEditField.Value);
            if ~isfinite(stepSize) || stepSize <= 0
                error('DataViewerCoreg2Cams:InvalidStepSize', ...
                    'Step size must be a finite positive value.');
            end

            delta = signedStep * stepSize;

            switch app.TransformationDropDown.Value
                case 'Horizontal (transl.)'
                    app.ManualDx = app.ManualDx + delta;
                case 'Vertical (transl.)'
                    app.ManualDy = app.ManualDy + delta;
                case 'Rotation'
                    app.ManualRotationDeg = app.ManualRotationDeg + delta;
                case 'Scaling'
                    app.ManualScale = app.ManualScale * (1 + delta / 100);
                    app.ManualScale = max(app.ManualScale, eps);
            end

            app.recomposeCurrentTransform();
            app.HasUnsavedCoregistration = true;
            app.CandidateSource = app.appendManualCandidateLabel(app.CandidateSource);

            app.updateRegisteredMovingImage();
            app.refreshCoregistrationFileInfo();
            app.refreshButtonStates();
            app.refreshPreview();
            app.setLocalStatus('Manual adjustment applied. Save Coregistration to make it active.');
        end

        function label = appendManualCandidateLabel(app, labelIn) %#ok<INUSL>
            %APPENDMANUALCANDIDATELABEL Mark source label as manually adjusted.

            label = char(string(labelIn));
            if isempty(label)
                label = 'Manual adjustment';
            elseif ~contains(label, '+ manual adjustment')
                label = [label '+ manual adjustment'];
            end
        end

        function recomposeCurrentTransform(app)
            %RECOMPOSECURRENTTRANSFORM Compose base transform plus manual adjustment.

            if isempty(app.BaseTform)
                return
            end

            fixedSize = size(app.CurrentFixedImage);
            cx = (fixedSize(2) + 1) / 2;
            cy = (fixedSize(1) + 1) / 2;

            TtoOrigin = [1 0 0; 0 1 0; -cx -cy 1];
            TfromOrigin = [1 0 0; 0 1 0; cx cy 1];

            theta = deg2rad(app.ManualRotationDeg);
            R = [cos(theta) sin(theta) 0; -sin(theta) cos(theta) 0; 0 0 1];
            S = [app.ManualScale 0 0; 0 app.ManualScale 0; 0 0 1];
            Tr = [1 0 0; 0 1 0; app.ManualDx app.ManualDy 1];

            manualT = TtoOrigin * S * R * TfromOrigin * Tr;
            app.CurrentTform = affine2d(app.BaseTform.T * manualT);

            app.CurrentTformInfo = app.updateTformInfoForManualAdjustment(app.CurrentTformInfo);
        end

        function info = updateTformInfoForManualAdjustment(app, info)
            %UPDATETFORMINFOFORMANUALADJUSTMENT Add manual-adjustment metadata.

            if isempty(info) || ~isstruct(info)
                info = struct();
            end

            info.ManualAdjustment = struct( ...
                'dx_px', app.ManualDx, ...
                'dy_px', app.ManualDy, ...
                'rotation_deg', app.ManualRotationDeg, ...
                'scale', app.ManualScale, ...
                'updatedOn', datetime('now'));

            info.CandidateSource = app.CandidateSource;
        end

        function img = buildCompositeCameraImage(app, datFileList)
            %BUILDCOMPOSITECAMERAIMAGE Create one normalized mean image from channels.

            if isempty(datFileList)
                error('DataViewerCoreg2Cams:EmptyDatList', ...
                    'No .dat files were provided for camera image construction.');
            end

            firstPath = fullfile(app.CoregistrationSourceFolder, datFileList{1});
            if ~isfile(firstPath)
                error('DataViewerCoreg2Cams:MissingDatFile', ...
                    'Missing required channel file: %s', firstPath);
            end

            datMap = mapDat(firstPath);
            img = zeros([numel(datFileList), size(datMap.Data.data, 1), size(datMap.Data.data, 2)], 'single');
            clear datMap

            for iFile = 1:numel(datFileList)
                datPath = fullfile(app.CoregistrationSourceFolder, datFileList{iFile});
                if ~isfile(datPath)
                    error('DataViewerCoreg2Cams:MissingDatFile', ...
                        'Missing required channel file: %s', datPath);
                end

                datMap = mapDat(datPath);
                nFrames = size(datMap.Data.data, 3);

                if nFrames > 100
                    frameIdx = round(linspace(1, nFrames, 100));
                    dat = mean(datMap.Data.data(:, :, frameIdx), 3);
                else
                    dat = mean(datMap.Data.data, 3);
                end

                if mean(dat(:), 'omitnan') > 1e3
                    dat = dat ./ mean(dat(:), 'omitnan');
                else
                    dat = zeros(size(dat), 'single');
                end

                img(iFile, :, :) = single(dat);
                clear datMap
            end

            img = squeeze(sum(img, 1));
        end

        function img = normalizeImage(app, img) %#ok<INUSL>
            %NORMALIZEIMAGE Normalize image to [0 1] safely.

            img = single(img);
            imgMin = min(img(:), [], 'omitnan');
            imgMax = max(img(:), [], 'omitnan');

            if ~isfinite(imgMin) || ~isfinite(imgMax) || imgMax <= imgMin
                img = zeros(size(img), 'single');
            else
                img = (img - imgMin) ./ (imgMax - imgMin);
            end
        end

        function ensureCurrentTformInfoHasRegisteredImages(app)
            %ENSURECURRENTTFORMINFOHASREGISTEREDIMAGES Store preview images in tformInfo.

            if isempty(app.CurrentTformInfo) || ~isstruct(app.CurrentTformInfo)
                app.CurrentTformInfo = struct();
            end

            if ~isempty(app.CurrentFixedImage) && ~isempty(app.CurrentRegisteredMovingImage)
                app.CurrentTformInfo.RegisteredImages = { ...
                    app.CurrentFixedImage, ...
                    app.CurrentRegisteredMovingImage};
            end

            if ~isempty(app.CurrentFixedImage) && ~isempty(app.CurrentMovingImage)
                % Keep the true unregistered pair explicit (not just carried over by
                % accident from genTform2Cams's output) so a future review/edit
                % session -- from any load path -- can always restore a preview
                % without needing a fresh data import.
                app.CurrentTformInfo.OriginalImages = { ...
                    app.CurrentFixedImage, ...
                    app.CurrentMovingImage};
            end

            app.CurrentTformInfo.SourceDataFolder = app.CoregistrationSourceFolder;
            app.CurrentTformInfo.CandidateSource = app.CandidateSource;
            app.CurrentTformInfo.SavedOn = datetime('now');
        end

        function tf = hasCandidateTransform(app)
            %HASCANDIDATETRANSFORM Return true when a candidate transform exists.

            tf = ~isempty(app.CurrentTform);
        end

        function tf = hasActiveCoregistration(app)
            %HASACTIVECOREGISTRATION Return true when the rig has an active camera
            %coregistration resource (file existence is already checked inside
            %UMITRigStore's iGetActiveResource).

            rigStore = app.openRigStore();
            tf = ~isempty(rigStore) && ~isempty(rigStore.getActiveCameraCoregistration());
        end


        function updateCameraCoregistrationDataParams(app, tform, resourceUUID, ...
                sourceFilePath, sourceTimestamp)
            %UPDATECAMERACOREGISTRATIONDATAPARAMS Record the applied camera coregistration in
            %the coregistration-source folder's DataParams.mat.
            %
            %   Written unconditionally after a successful camera coregistration save
            %   to UMITRigStore. This local DataParams.mat record is a convenience
            %   mirror, not the authoritative store -- a failure here must never undo
            %   or block the rig-store save that already happened.


            try
                folder = app.CoregistrationSourceFolder;

                if isempty(folder) || ~isfolder(folder)
                    return
                end

                if ~isfile(fullfile(folder, 'DataParams.mat'))
                    createDataParams(folder);
                end

                DataParams = loadDataParams(folder);
                cc = DataParams.cameraCoregistration;

                cc.isCoregistered = true;
                cc.isReviewed = true;
                cc.tform = tform;
                cc.resourceUUID = char(string(resourceUUID));
                cc.rigID = app.RigID;
                cc.transformType = 'similarity';
                cc.method = 'DataViewer_Coreg2Cams';
                cc.sourceFile = char(string(sourceFilePath));
                cc.sourceFileTimestamp = char(string(sourceTimestamp));
                cc.createdOn = char(datetime('now'));
                cc.source = folder;
                cc.appliedOn = char(datetime('now'));
                cc.appliedBy = 'DataViewer_Coreg2Cams';
                cc.confirmationMode = 'user-confirmed-save-coregistration';
                cc.notes = 'Active dual-camera coregistration saved via DataViewer_Coreg2Cams.';

                T = app.getTformMatrix(tform);
                A = T(1:2, 1:2);
                cc.qcMetrics.translationXY_px = [T(3,1), T(3,2)];
                cc.qcMetrics.rotationDeg = atan2d(A(1,2), A(1,1));
                cc.qcMetrics.scaleXY = [hypot(A(1,1), A(1,2)), hypot(A(2,1), A(2,2))];
                cc.qcMetrics.determinant = det(A);

                DataParams.cameraCoregistration = cc;
                DataParams.lastModified = datetime('now');

                validateDataParams(DataParams);
                save(fullfile(folder, 'DataParams.mat'), 'DataParams', '-mat');

            catch ME
                warning('DataViewerCoreg2Cams:DataParamsRecordFailed', ...
                    ['Coregistration was saved successfully, but the local DataParams.mat ' ...
                    'record could not be updated: %s'], ME.message);
            end
        end

        function T = getTformMatrix(app, tform) %#ok<INUSL>
            %GETTFORMMATRIX Return 3 x 3 transform matrix from a tform object.

            if isnumeric(tform)
                T = tform;
                return
            end

            if isprop(tform, 'T')
                T = tform.T;
                return
            end

            if isprop(tform, 'A')
                T = tform.A;
                return
            end

            error('DataViewerCoreg2Cams:MissingTransformMatrix', ...
                'Could not extract transform matrix from class "%s".', class(tform));
        end


        function cleanupCoregistrationApp(app)
            %CLEANUPCOREGISTRATIONAPP Stop timers and release transient UI resources.

            try
                app.stopAlternateViewTimer();
            catch
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, dataFolder, appMode, varargin)
            %STARTUPFCN Initialize the dual-camera coregistration utility.
            %
            %   DataViewer_Coreg2Cams(parentApp, dataFolder)
            %   DataViewer_Coreg2Cams(parentApp, dataFolder, appMode)
            %   DataViewer_Coreg2Cams(parentApp, dataFolder, appMode, ...
            %       'ProjectStore', store, 'RigUUID', rigUUID, 'RigID', rigID, ...
            %       'SubjectUUID', subjectUUID, 'SubjectID', subjectID, ...
            %       'SessionUUID', sessionUUID, 'SessionID', sessionID)
            %
            %   The dataFolder argument is treated as context only. Camera coregistration
            %   source data must be explicitly loaded by the user because processed
            %   .dat files may already have been coregistered during import.

            %
            %   The rig/project context name-value pairs are all optional. This
            %   tool must keep working standalone/unbound, since dual-camera
            %   coregistration is a legacy, project-independent workflow.

            if nargin < 2
                parentApp = [];
            end

            if nargin < 3 || isempty(dataFolder)
                dataFolder = '';
            end

            if nargin < 4 || isempty(appMode)
                appMode = 'CoregistrationUtility';
            end

            app.MainApp = parentApp;
            app.DataFolder = char(string(dataFolder));
            app.appMode = char(string(appMode));

            p = inputParser;
            p.FunctionName = 'DataViewer_Coreg2Cams.startupFcn';

            addParameter(p, 'ProjectStore', [], ...
                @(x) isempty(x) || isa(x, 'UMITProjectStore'));
            addParameter(p, 'RigUUID', '', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));
            addParameter(p, 'RigID', '', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));
            addParameter(p, 'SubjectUUID', '', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));
            addParameter(p, 'SubjectID', '', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));
            addParameter(p, 'SessionUUID', '', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));
            addParameter(p, 'SessionID', '', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));

            parse(p, varargin{:});
            R = p.Results;

            app.ProjectStore = R.ProjectStore;
            app.RigUUID = char(string(R.RigUUID));
            app.RigID = char(string(R.RigID));
            app.SubjectUUID = char(string(R.SubjectUUID));
            app.SubjectID = char(string(R.SubjectID));
            app.SessionUUID = char(string(R.SessionUUID));
            app.SessionID = char(string(R.SessionID));

            % Rigs are independent of any project, so HasRigContext only
            % needs a rig identifier -- ProjectStore is not required to
            % resolve/centralize a cameraCoregistration resource.
            app.HasRigContext = ~isempty(app.RigID) || ~isempty(app.RigUUID);

            app.configureInitialState();

            if ~app.ensureRigContext()
                uialert(app.UIFigure, ...
                    ['Could not resolve or create a rig for this machine. Camera ' ...
                    'coregistration requires a working UMITRigStore rig -- fix the ' ...
                    'underlying issue and reopen this tool.'], ...
                    'No rig available', ...
                    'Icon', 'error');
            end

            app.loadActiveCoregistration();

            app.refreshCoregistrationFileInfo();

            app.refreshButtonStates();
            app.refreshPreview();

            if exist('placeAppInsideCaller', 'file') == 2 && ~isempty(parentApp)
                try
                    placeAppInsideCaller(parentApp, app.UIFigure, 'center');
                catch
                    movegui(app.UIFigure, 'center');
                end
            else
                movegui(app.UIFigure, 'center');
            end

            app.UIFigure.Visible = 'on';
            initializeUmitTheme(app.UIFigure);
            %             if strcmpi(AppMode, 'CoregistrationMode')
            %                 app.UseenhancedcontrastCheckBoxValueChanged('');
            %             end

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            %UIFIGURECLOSEREQUEST Close app after stopping preview timers.

            if app.HasUnsavedCoregistration
                answer = uiconfirm(app.UIFigure, ...
                    'There is an unsaved coregistration candidate. Close without saving?', ...
                    'Unsaved coregistration', ...
                    'Options', {'Close without saving', 'Cancel'}, ...
                    'DefaultOption', 'Cancel', ...
                    'CancelOption', 'Cancel', ...
                    'Icon', 'warning');

                if strcmpi(answer, 'Cancel')
                    return
                end
            end

            app.cleanupCoregistrationApp();
            delete(app)

        end

        % Button pushed function: RunAutomaticcoregistrationButton
        function RunAutomaticcoregistrationButtonPushed(app, event)
            %RUNAUTOMATICCOREGISTRATIONBUTTONPUSHED Generate automatic candidate tform.

            if ~app.HasCoregistrationSource
                uialert(app.UIFigure, ...
                    'Load unregistered coregistration data before running automatic coregistration.', ...
                    'No coregistration source', ...
                    'Icon', 'warning');
                return
            end

            try
                w = uiprogressdlg(app.UIFigure, ...
                    'Message', 'Calculating geometric transformation...', ...
                    'Title', 'Please wait', ...
                    'Indeterminate', 'on');
                cleanupObj = onCleanup(@() delete(w));

                [tform, tformInfo, warnmsg] = genTform2Cams(app.CoregistrationSourceFolder, ...
                    app.UseenhancedcontrastCheckBox.Value, ...
                    app.FlipHorizontallyCheckBox.Value);

                if ~isempty(warnmsg)
                    uialert(app.UIFigure, warnmsg, ...
                        'Failed to generate geometric transformation file', ...
                        'Icon', 'warning');
                    app.setLocalStatus('Automatic coregistration failed.');
                    return
                end

                tformInfo.CandidateSource = 'Automatic coregistration';
                tformInfo.SourceDataFolder = app.CoregistrationSourceFolder;
                tformInfo.GeneratedOn = datetime('now');

                app.setCandidateTransform(tform, tformInfo, ...
                    'Automatic coregistration', true, true);

                app.setLocalStatus('Automatic coregistration completed. Review and save the coregistration if acceptable.');

            catch ME
                app.setLocalStatus(sprintf('Automatic coregistration failed: %s', ME.message));
                uialert(app.UIFigure, getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Automatic coregistration failed', ...
                    'Icon', 'error');
            end

        end

        % Selection changed function: ViewmodeButtonGroup
        function ViewmodeButtonGroupSelectionChanged(app, event)
            %VIEWMODEBUTTONGROUPSELECTIONCHANGED Refresh preview mode.

            app.refreshPreview();

        end

        % Value changed function: UseenhancedcontrastCheckBox
        function UseenhancedcontrastCheckBoxValueChanged(app, event)
            %USEENHANCEDCONTRASTCHECKBOXVALUECHANGED Refresh preview contrast mode.

            app.refreshPreview();

        end

        % Value changed function: FlipHorizontallyCheckBox
        function FlipHorizontallyCheckBoxValueChanged(app, event)
            %FLIPHORIZONTALLYCHECKBOXVALUECHANGED Flip moving source image before coregistration.


            if app.HasCoregistrationSource && ~isempty(app.CurrentMovingImage)
                app.CurrentMovingImage = fliplr(app.CurrentMovingImage);
                app.EnhancedMovingImage = adapthisteq(app.normalizeImage(app.CurrentMovingImage));
                app.updateRegisteredMovingImage();
                app.refreshPreview();
                app.setLocalStatus('Moving camera image flipped horizontally. Re-run automatic coregistration if needed.');
            end

        end

        % Value changed function: TransformationDropDown
        function TransformationDropDownValueChanged(app, event)
            %TRANSFORMATIONDROPDOWNVALUECHANGED Update manual-step units.

            app.updateStepSizeLabel();

        end

        % Value changed function: StepsizeEditField
        function StepsizeEditFieldValueChanged(app, event)
            %STEPSIZEEDITFIELDVALUECHANGED Validate manual adjustment step size.

            if ~isfinite(app.StepsizeEditField.Value) || app.StepsizeEditField.Value <= 0
                app.StepsizeEditField.Value = 1;
                uialert(app.UIFigure, ...
                    'Step size must be a finite positive value.', ...
                    'Invalid step size', ...
                    'Icon', 'warning');
            end

        end

        % Value changed function: ApplyStepSpinner
        function ApplyStepSpinnerValueChanged(app, event)
            %APPLYSTEPSPINNERVALUECHANGED Apply relative manual transform step.

            try
                if isempty(app.CurrentTform)
                    app.ApplyStepSpinner.Value = 0;
                    return
                end

                signedStep = event.Value - event.PreviousValue;
                if signedStep == 0
                    return
                end

                app.applyManualStep(signedStep);

            catch ME
                uialert(app.UIFigure, getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Manual adjustment failed', ...
                    'Icon', 'error');
            end

            app.ApplyStepSpinner.Value = 0;

        end

        % Button pushed function: SaveCoregistrationButton
        function SaveCoregistrationButtonPushed(app, event)
            %SAVECOREGISTRATIONBUTTONPUSHED Save current candidate as the rig's new
            %active camera coregistration resource.

            if isempty(app.CurrentTform)
                uialert(app.UIFigure, ...
                    'No candidate coregistration is available to save.', ...
                    'No coregistration candidate', ...
                    'Icon', 'warning');
                return
            end

            answer = uiconfirm(app.UIFigure, ...
                ['Save the currently previewed coregistration as the active coregistration?' newline newline ...
                'If an active coregistration exists, it will remain saved and browsable ' ...
                'from Coregistration History -- it just won''t be active anymore.'], ...
                'Save active coregistration?', ...
                'Options', {'Save Coregistration', 'Cancel'}, ...
                'DefaultOption', 'Save Coregistration', ...
                'CancelOption', 'Cancel', ...
                'Icon', 'question');

            if strcmpi(answer, 'Cancel')
                return
            end

            try
                app.ensureCurrentTformInfoHasRegisteredImages();
                tform = app.CurrentTform;
                tformInfo = app.CurrentTformInfo;

                resourceUUID = app.saveTform(tform, tformInfo);

                % From here on, the camera coregistration is already saved and active
                % in the rig store. Everything below is best-effort local
                % provenance and must never undo or block the save that already
                % happened.
                sourceFilePath = '';
                try
                    rigStore = app.openRigStore();
                    if ~isempty(rigStore)
                        sourceFilePath = rigStore.resolveResourcePath(resourceUUID);
                    end
                catch
                    sourceFilePath = '';
                end

                app.updateCameraCoregistrationDataParams( ...
                    tform, resourceUUID, sourceFilePath, char(datetime('now')));

                app.HasUnsavedCoregistration = false;
                app.setCandidateTransform(app.ActiveTform, app.ActiveTformInfo, ...
                    'Active coregistration', false, true);

                app.refreshAllState();
                app.setLocalStatus('Coregistration saved and activated in the rig''s coregistration history.');

            catch ME
                app.setLocalStatus(sprintf('Save coregistration failed: %s', ME.message));
                uialert(app.UIFigure, getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Save coregistration failed', ...
                    'Icon', 'error');
            end


        end

        % Button pushed function: RestorePreviousCoregistrationButton
        function RestorePreviousCoregistrationButtonPushed(app, event)
            %ARCHIVEACTIVECOREGISTRATIONBUTTONPUSHED Restore a previous camera
            %coregistration as the rig's active one. Browses the same history list as
            %LoadBackupButtonPushed; an archived entry is restored (via
            %UMITRigStore.restoreResource) before being activated. There is always
            %exactly one active cameraCoregistration once one has ever been created
            %-- this replaces it, it doesn't clear it.

            record = app.pickCoregistrationResource('Restore Previous Coregistration');
            if isempty(record)
                app.refreshAllState();
                return
            end

            if strcmp(record.status, 'active')
                app.setLocalStatus(sprintf('"%s" is already the active coregistration.', ...
                    record.displayName));
                return
            end

            answer = uiconfirm(app.UIFigure, ...
                sprintf('Restore "%s" as the active camera coregistration?', record.displayName), ...
                'Restore previous coregistration?', ...
                'Options', {'Restore', 'Cancel'}, ...
                'DefaultOption', 'Cancel', ...
                'CancelOption', 'Cancel', ...
                'Icon', 'question');

            if strcmpi(answer, 'Cancel')
                return
            end

            try
                rigStore = app.openRigStore();
                if isempty(rigStore)
                    error('DataViewerCoreg2Cams:NoRig', ...
                        'No rig is available to restore this coregistration.');
                end

                if strcmp(record.status, 'archived')
                    rigStore.restoreResource(record.uuid);
                end
                rigStore.setActiveCameraCoregistration(record.uuid);

                app.loadActiveCoregistration();
                app.refreshAllState();
                app.setLocalStatus(sprintf('Restored "%s" as the active coregistration.', ...
                    record.displayName));

            catch ME
                app.setLocalStatus(sprintf('Restore failed: %s', ME.message));
                uialert(app.UIFigure, getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Restore previous coregistration failed', ...
                    'Icon', 'error');
            end


        end

        % Button pushed function: BrowseCoregistrationHistoryButton
        function BrowseCoregistrationHistoryButtonPushed(app, event)
            %LOADBACKUPBUTTONPUSHED Browse the rig's coregistration history and load
            %one as the current preview candidate for review/editing -- does not
            %change what's active in the rig store. Archived entries can also be
            %purged (permanently deleted) from here, the only sanctioned way to
            %delete a coregistration file.

            record = app.pickCoregistrationResource('Browse Coregistration History');
            if isempty(record)
                return
            end

            if strcmp(record.status, 'archived')
                answer = uiconfirm(app.UIFigure, ...
                    sprintf(['"%s" is archived.' newline newline ...
                    'Load it for review, or permanently purge this archived file?'], ...
                    record.displayName), ...
                    'Archived coregistration', ...
                    'Options', {'Load for Review', 'Purge Permanently', 'Cancel'}, ...
                    'DefaultOption', 'Load for Review', ...
                    'CancelOption', 'Cancel', ...
                    'Icon', 'warning');

                if strcmpi(answer, 'Cancel')
                    return
                end

                if strcmpi(answer, 'Purge Permanently')
                    try
                        rigStore = app.openRigStore();
                        rigStore.purgeResource(record.uuid);
                        app.refreshAllState();
                        app.setLocalStatus(sprintf('Purged "%s".', record.displayName));
                    catch ME
                        uialert(app.UIFigure, getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                            'Purge failed', 'Icon', 'error');
                    end
                    return
                end
            end

            try
                rigStore = app.openRigStore();
                resourcePath = rigStore.resolveResourcePath(record.uuid);
                S = load(resourcePath, 'tform', 'tformInfo');
                if ~isfield(S, 'tform')
                    error('Selected coregistration does not contain variable "tform".');
                end

                if isfield(S, 'tformInfo')
                    tformInfo = S.tformInfo;
                else
                    tformInfo = struct();
                end

                tformInfo.CandidateSource = 'History';
                tformInfo.SourceResourceUUID = record.uuid;
                tformInfo.LoadedFromHistoryOn = datetime('now');

                hasPreview = app.restorePreviewImagesFromTformInfo(tformInfo);

                app.setCandidateTransform(S.tform, tformInfo, ...
                    ['History: ' record.displayName], true, true);

                if hasPreview
                    app.setLocalStatus(['History entry loaded with its stored preview. ' ...
                        'Review it, optionally fine tune small drifts, then Save ' ...
                        'Coregistration to make it active.']);
                else
                    app.setLocalStatus(['History entry loaded, but it has no stored ' ...
                        'preview images -- load coregistration data to review/edit it.']);
                end

            catch ME
                app.setLocalStatus(sprintf('Load from history failed: %s', ME.message));
                uialert(app.UIFigure, getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Load from history failed', ...
                    'Icon', 'error');
            end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %RESETBUTTONPUSHED Reset manual fine-tuning adjustment.

            if isempty(app.BaseTform)
                return
            end

            app.ManualDx = 0;
            app.ManualDy = 0;
            app.ManualRotationDeg = 0;
            app.ManualScale = 1;
            app.CurrentTform = app.BaseTform;
            app.CurrentTformInfo = app.updateTformInfoForManualAdjustment(app.CurrentTformInfo);
            app.updateRegisteredMovingImage();
            app.HasUnsavedCoregistration = true;

            app.refreshCoregistrationFileInfo();
            app.refreshButtonStates();
            app.refreshPreview();
            app.setLocalStatus('Manual adjustment reset. Save Coregistration to keep the reset candidate.');

        end

        % Button pushed function: LoadCoregistrationDataButton
        function LoadCoregistrationDataButtonPushed(app, event)
            %LOADCOREGISTRATIONDATABUTTONPUSHED Load unregistered source images.
            %
            %   This implementation expects a folder containing AcqInfos.mat and
            %   unregistered .dat channel files. Do not select processed data that
            %   were imported while an active coreg2cam_tform.mat existed.

            startFolder = app.DataFolder;
            if isempty(startFolder) || ~isfolder(startFolder)
                startFolder = pwd;
            end

            answer = uiconfirm(app.UIFigure, ...
                ['Select a folder containing UNREGISTERED dual-camera coregistration .dat files.' newline newline ...
                'Do not select processed data that may already have been coregistered during import.'], ...
                'Load coregistration source data', ...
                'Options', {'Select Folder', 'Cancel'}, ...
                'DefaultOption', 'Select Folder', ...
                'CancelOption', 'Cancel', ...
                'Icon', 'warning');

            if strcmpi(answer, 'Cancel')
                return
            end

            selectedFolder = uigetdir(startFolder, 'Select unregistered coregistration-source folder');
            if isequal(selectedFolder, 0)
                return
            end

            try
                app.stopAlternateViewTimer();
                app.CoregistrationSourceFolder = selectedFolder;
                app.CurrentFixedImage = [];
                app.CurrentMovingImage = [];
                app.CurrentRegisteredMovingImage = [];
                app.EnhancedFixedImage = [];
                app.EnhancedMovingImage = [];
                app.HasCoregistrationSource = false;

                w = uiprogressdlg(app.UIFigure, ...
                    'Title', 'Loading coregistration data', ...
                    'Message', 'Building camera preview images...', ...
                    'Indeterminate', 'on');
                cleanupObj = onCleanup(@() delete(w));

                app.loadCameraImages();

                if ~isempty(app.CurrentTform)
                    app.updateRegisteredMovingImage();
                elseif ~isempty(app.ActiveTform)
                    app.setCandidateTransform(app.ActiveTform, app.ActiveTformInfo, ...
                        'Active coregistration', false, true);
                end

                app.refreshCoregistrationFileInfo();
                app.refreshButtonStates();
                app.refreshPreview();
                app.setLocalStatus('Coregistration source loaded. Run automatic coregistration or preview the current candidate.');

            catch ME
                app.HasCoregistrationSource = false;
                app.CoregistrationSourceFolder = '';
                app.refreshCoregistrationFileInfo();
                app.refreshButtonStates();
                app.refreshPreview();
                app.setLocalStatus(sprintf('Load coregistration data failed: %s', ME.message));
                uialert(app.UIFigure, getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Load coregistration data failed', ...
                    'Icon', 'error');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Dual Camera coregistration';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.GrifLayoutLeft = uigridlayout(app.GridLayout);
            app.RunAutomaticcoregistrationButton = uibutton(app.GrifLayoutLeft, 'push');
            app.RunAutomaticcoregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @RunAutomaticcoregistrationButtonPushed, true);
            app.RunAutomaticcoregistrationButton.Text = 'Run Automatic coregistration';
            app.CoregistrationparametersPanel = uipanel(app.GrifLayoutLeft);
            app.CoregistrationparametersPanel.Title = 'Coregistration parameters';
            app.GridCoregistrationPanel = uigridlayout(app.CoregistrationparametersPanel);
            app.UseenhancedcontrastCheckBox = uicheckbox(app.GridCoregistrationPanel);
            app.UseenhancedcontrastCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseenhancedcontrastCheckBoxValueChanged, true);
            app.UseenhancedcontrastCheckBox.Tooltip = {'Check this box to enhance the image contrast to improve coregistration'};
            app.UseenhancedcontrastCheckBox.Text = 'Use enhanced contrast';
            app.UseenhancedcontrastCheckBox.Value = true;
            app.FlipHorizontallyCheckBox = uicheckbox(app.GridCoregistrationPanel);
            app.FlipHorizontallyCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlipHorizontallyCheckBoxValueChanged, true);
            app.FlipHorizontallyCheckBox.Tooltip = {'Some Imaging systems will have a mirrored image in camera #2. Check this box if this is the case.'};
            app.FlipHorizontallyCheckBox.Text = 'Flip Horizontally';
            app.SaveCoregistrationButton = uibutton(app.GrifLayoutLeft, 'push');
            app.SaveCoregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCoregistrationButtonPushed, true);
            app.SaveCoregistrationButton.Text = 'Save Coregistration';
            app.ViewmodeButtonGroup = uibuttongroup(app.GrifLayoutLeft);
            app.ViewmodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ViewmodeButtonGroupSelectionChanged, true);
            app.ViewmodeButtonGroup.Title = 'View mode';
            app.AlternateViewButton = uiradiobutton(app.ViewmodeButtonGroup);
            app.AlternateViewButton.Text = 'Alternate View';
            app.AlternateViewButton.Value = true;
            app.FalseColorOverlayButton = uiradiobutton(app.ViewmodeButtonGroup);
            app.FalseColorOverlayButton.Text = 'False Color Overlay';
            app.ManualfinetuningPanel = uipanel(app.GrifLayoutLeft);
            app.ManualfinetuningPanel.Title = 'Manual fine tuning';
            app.GridManualFineTuning = uigridlayout(app.ManualfinetuningPanel);
            app.TransformationDropDownLabel = uilabel(app.GridManualFineTuning);
            app.TransformationDropDownLabel.Text = 'Transformation';
            app.TransformationDropDown = uidropdown(app.GridManualFineTuning);
            app.TransformationDropDown.Items = {'Horizontal (transl.)', 'Vertical (transl.)', 'Rotation', 'Scaling'};
            app.TransformationDropDown.ValueChangedFcn = createCallbackFcn(app, @TransformationDropDownValueChanged, true);
            app.TransformationDropDown.Value = 'Horizontal (transl.)';
            app.StepsizeEditFieldLabel = uilabel(app.GridManualFineTuning);
            app.StepsizeEditFieldLabel.Text = 'Step size';
            app.StepsizeEditField = uieditfield(app.GridManualFineTuning, 'numeric');
            app.StepsizeEditField.ValueChangedFcn = createCallbackFcn(app, @StepsizeEditFieldValueChanged, true);
            app.ApplyStepSpinnerLabel = uilabel(app.GridManualFineTuning);
            app.ApplyStepSpinnerLabel.Text = 'Apply Step';
            app.ApplyStepSpinner = uispinner(app.GridManualFineTuning);
            app.ApplyStepSpinner.ValueChangedFcn = createCallbackFcn(app, @ApplyStepSpinnerValueChanged, true);
            app.ResetButton = uibutton(app.GridManualFineTuning, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CoregistrationHistoryPanel = uipanel(app.GrifLayoutLeft);
            app.CoregistrationHistoryPanel.Title = 'Coregistration History';
            app.GridGeometricPanel = uigridlayout(app.CoregistrationHistoryPanel);
            app.CoregistrationFileInfoTextArea = uitextarea(app.GridGeometricPanel);
            app.BrowseCoregistrationHistoryButton = uibutton(app.GridGeometricPanel, 'push');
            app.BrowseCoregistrationHistoryButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseCoregistrationHistoryButtonPushed, true);
            app.BrowseCoregistrationHistoryButton.Tooltip = {'Browse this rig''s coregistration history and load one for review/editing.'};
            app.BrowseCoregistrationHistoryButton.Text = 'Browse Coregistration History';
            app.RestorePreviousCoregistrationButton = uibutton(app.GridGeometricPanel, 'push');
            app.RestorePreviousCoregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @RestorePreviousCoregistrationButtonPushed, true);
            app.RestorePreviousCoregistrationButton.Text = 'Restore Previous Coregistration';
            app.CoregistrationSourcePanel = uipanel(app.GrifLayoutLeft);
            app.CoregistrationSourcePanel.Title = 'Coregistration Source';
            app.GridCoregistrationSource = uigridlayout(app.CoregistrationSourcePanel);
            app.LoadCoregistrationDataButton = uibutton(app.GridCoregistrationSource, 'push');
            app.LoadCoregistrationDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCoregistrationDataButtonPushed, true);
            app.LoadCoregistrationDataButton.Text = 'Load Coregistration Data....';
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataViewer_Coreg2Cams(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end