classdef ImageReferenceManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        StatusLabel                   matlab.ui.control.Label
        GridLayoutBottom              matlab.ui.container.GridLayout
        CreatefromcurrentframeButton  matlab.ui.control.Button
        CreatefromfileButton          matlab.ui.control.Button
        ArchiveButton                 matlab.ui.control.Button
        CloseButton                   matlab.ui.control.Button
        UseSelectedButton             matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        SelectedImageReferencePanel   matlab.ui.container.Panel
        GridSelectedImageReferencePanel  matlab.ui.container.GridLayout
        RestoreButton                 matlab.ui.control.Button
        EditButton                    matlab.ui.control.Button
        MetaDataTextArea              matlab.ui.control.TextArea
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        ParentApp = []
        AppMode char = 'manage'  % 'manage' or 'select'

        ReferenceTable table = table()
        SelectedRow double = []
        SelectedReferenceFileRel char = ''
        SelectedReferenceFileAbs char = ''
        SelectedImageReference = []

        CurrentFrameImage = []
        CurrentFrameSourceFile char = ''
        CurrentFrameSourceFolder char = ''
        CurrentFrameIndex double = NaN
        CurrentSourceType char = ''
        Property17 % Description

        IsEditingReference logical = false
        ReferenceEditOriginalImage = []
        ReferenceEditCandidateImage = []
        ReferenceEditBoxHandle = gobjects(1)
        ReferenceEditListeners cell = {}
        ReferenceEditTform = []
        ReferenceEditPreviewHandle = matlab.graphics.primitive.Image.empty
        ReferencePreviewHandle = matlab.graphics.primitive.Image.empty
    end

    properties (Access = public)
        OutputReferenceFileRel char = ''
        OutputReferenceFileAbs char = ''
        OutputImageReference = []
        WasSelectionConfirmed logical = false
    end

    methods (Access = private)


        function configureInitialState(app)
            %CONFIGUREINITIALSTATE Initialize static GUI state.

            app.StatusLabel.Text = 'Ready.';
            app.MetaDataTextArea.Editable = 'off';
            app.MetaDataTextArea.Value = {'Select an Image Reference from the table.'};

            cla(app.UIAxes);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            title(app.UIAxes, 'No Image Reference selected', 'Interpreter', 'none');

            if strcmpi(app.AppMode, 'select')
                app.StatusLabel.Text = 'Select an Image Reference.';
            else
                app.StatusLabel.Text = 'Manage Image References.';
            end
        end

        function refreshReferenceTable(app)
            %REFRESHREFERENCETABLE Reload ImageReference metadata table.

            try
                T = listImageReferences();

                app.ReferenceTable = T;

                visibleColumns = { ...
                    'CreatedOn', ...
                    'ProjectName', ...
                    'GroupName', ...
                    'MouseID', ...
                    'SessionID', ...
                    'ReferenceName', ...
                    'ImageSizeYX', ...
                    'FileName'};

                app.UITable.Data = T(:, visibleColumns);
                app.UITable.ColumnName = visibleColumns;
                app.UITable.RowName = {};

                app.setStatusMessage(sprintf('Loaded %d Image Reference file(s).', height(T)));

            catch ME
                app.ReferenceTable = table();
                app.UITable.Data = table();
                app.setStatusMessage(sprintf('Failed to list Image References: %s', ME.message));
            end

            app.clearSelectedReference();
        end

        function selectReferenceRow(app, rowIdx)
            %SELECTREFERENCEROW Load, preview, and store selected table row.

            if isempty(app.ReferenceTable) || height(app.ReferenceTable) == 0
                app.clearSelectedReference();
                return
            end

            if rowIdx < 1 || rowIdx > height(app.ReferenceTable)
                app.clearSelectedReference();
                return
            end

            T = app.ReferenceTable;

            if ismember('IsValid', T.Properties.VariableNames) && ~T.IsValid(rowIdx)
                app.clearSelectedReference();
                app.setStatusMessage('Selected Image Reference is invalid.');
                return
            end

            relPath = char(string(T.RelativePath(rowIdx)));
            absPath = char(string(T.AbsolutePath(rowIdx)));

            [ImageReference, resolvedAbsPath] = loadImageReference(absPath);

            app.SelectedRow = rowIdx;
            app.SelectedReferenceFileRel = relPath;
            app.SelectedReferenceFileAbs = resolvedAbsPath;
            app.SelectedImageReference = ImageReference;

            app.previewSelectedReference();
            app.updateMetadataTextArea();
            app.refreshButtonState();

            app.setStatusMessage(sprintf('Selected Image Reference: %s', ImageReference.fileName));
        end

        function selectReferenceByRelativePath(app, relativePath)
            %SELECTREFERENCEBYRELATIVEPATH Select a row by ImageReference relative path.

            if isempty(app.ReferenceTable) || height(app.ReferenceTable) == 0
                return
            end

            relativePath = string(relativePath);
            relPaths = string(app.ReferenceTable.RelativePath);

            idx = find(strcmp(relPaths, relativePath), 1, 'first');

            if isempty(idx)
                return
            end

            try
                app.UITable.Selection = idx;
            catch
            end

            app.selectReferenceRow(idx);
        end

        function clearSelectedReference(app)
            %CLEARSELECTEDREFERENCE Clear selected reference state and preview.

            if app.IsEditingReference
                app.cancelReferenceEdit();
            end

            app.SelectedRow = [];
            app.SelectedReferenceFileRel = '';
            app.SelectedReferenceFileAbs = '';
            app.SelectedImageReference = [];

            app.clearPreviewAxes('No Image Reference selected');

            app.MetaDataTextArea.Value = {'Select an Image Reference from the table.'};

            app.refreshButtonState();
        end

        function refreshButtonState(app)
            %REFRESHBUTTONSTATE Enable or disable buttons based on app state.

            hasCurrentFrame = ~isempty(app.CurrentFrameImage);
            hasSelection = ~isempty(app.SelectedReferenceFileRel) && ...
                ~isempty(app.SelectedImageReference);

            if app.IsEditingReference
                app.CreatefromcurrentframeButton.Enable = 'off';
                app.CreatefromfileButton.Enable = 'off';
                app.UseSelectedButton.Enable = 'off';
                app.ArchiveButton.Enable = 'off';
                app.CloseButton.Enable = 'off';

                app.EditButton.Enable = 'on';
                app.EditButton.Text = 'Apply';
                app.RestoreButton.Enable = 'on';
                return
            end

            if strcmpi(app.AppMode, 'manage')
                app.CreatefromcurrentframeButton.Enable = app.onOff(hasCurrentFrame);
                app.CreatefromfileButton.Enable = 'on';
                app.UseSelectedButton.Enable = 'off';
                app.ArchiveButton.Enable = app.onOff(hasSelection);
                app.EditButton.Enable = app.onOff(hasSelection);
                app.RestoreButton.Enable = 'off';
            else
                app.CreatefromcurrentframeButton.Enable = 'off';
                app.CreatefromfileButton.Enable = 'off';
                app.UseSelectedButton.Enable = app.onOff(hasSelection);
                app.ArchiveButton.Enable = 'off';
                app.EditButton.Enable = 'off';
                app.RestoreButton.Enable = 'off';
            end

            app.EditButton.Text = 'Edit';
            app.CloseButton.Enable = 'on';
        end

        function setStatusMessage(app, msg)
            %SETSTATUSMESSAGE Display status text.

            app.StatusLabel.Text = char(string(msg));
            drawnow limitrate;
        end

        function previewSelectedReference(app)
            %PREVIEWSELECTEDREFERENCE Show selected ImageReference image.

            if isempty(app.SelectedImageReference) || ...
                    ~isfield(app.SelectedImageReference, 'image')
                app.clearPreviewAxes('No Image Reference selected');
                return
            end

            img = single(app.SelectedImageReference.image);

            app.showReferencePreviewImage(img, app.SelectedImageReference.name);
        end

        function updateMetadataTextArea(app)
            %UPDATEMETADATATEXTAREA Show selected ImageReference metadata.

            if isempty(app.SelectedImageReference)
                app.MetaDataTextArea.Value = {'No Image Reference selected.'};
                return
            end

            R = app.SelectedImageReference;

            lines = strings(0, 1);

            lines(end+1) = "Name: " + string(R.name);
            lines(end+1) = "Project: " + string(R.projectName);
            lines(end+1) = "Group: " + string(R.groupName);
            lines(end+1) = "Mouse ID: " + string(R.mouseID);
            lines(end+1) = "Session ID: " + string(R.sessionID);
            lines(end+1) = "Created: " + string(R.createdOn);
            lines(end+1) = "Last modified: " + string(R.lastModified);
            lines(end+1) = "Image size: " + sprintf('%d x %d', R.imageSizeYX(1), R.imageSizeYX(2));
            lines(end+1) = "Axis convention: " + string(R.axisConvention);
            lines(end+1) = "";
            lines(end+1) = "Description:";
            lines(end+1) = string(R.description);
            lines(end+1) = "";
            lines(end+1) = "Source folder: " + string(R.sourceFolder);
            lines(end+1) = "Source file: " + string(R.sourceFile);
            lines(end+1) = "Source frame: " + app.valueToText(R.sourceFrame);
            lines(end+1) = "Source type: " + string(R.sourceType);
            lines(end+1) = "Created by: " + string(R.createdBy);
            lines(end+1) = "Manually transformed: " + string(R.wasManuallyTransformed);

            if isfield(R, 'manualTransformAppliedOn') && ~isempty(R.manualTransformAppliedOn)
                lines(end+1) = "Manual transform applied on: " + string(R.manualTransformAppliedOn);
            end

            if isfield(R, 'manualTransformNote') && ~isempty(R.manualTransformNote)
                lines(end+1) = "Manual transform note: " + string(R.manualTransformNote);
            end

            lines(end+1) = "";
            lines(end+1) = "Processing notes:";
            lines(end+1) = string(R.processingNotes);
            lines(end+1) = "";
            lines(end+1) = "Absolute path:";
            lines(end+1) = string(app.SelectedReferenceFileAbs);

            app.MetaDataTextArea.Value = cellstr(lines);
        end

        function metadata = promptCreateReferenceMetadata(app)
            %PROMPTCREATEREFERENCEMETADATA Modal metadata dialog for new reference.

            metadata = [];

            dlg = uifigure( ...
                'Name', 'Create Image Reference', ...
                'WindowStyle', 'modal', ...
                'Visible', 'off', ...
                'Position', [100 100 520 460]);

            grid = uigridlayout(dlg);
            grid.ColumnWidth = {130, '1x'};
            grid.RowHeight = {35, 35, 35, 35, 35, 80, 80, 40};
            grid.Padding = [12 12 12 12];

            projectField = app.addDialogEditField(grid, 1, 'Project name', 'DefaultProject');
            groupField = app.addDialogEditField(grid, 2, 'Group name', 'Control');
            mouseField = app.addDialogEditField(grid, 3, 'Mouse ID', 'Mouse01');
            sessionField = app.addDialogEditField(grid, 4, 'Session ID', 'Session01');
            nameField = app.addDialogEditField(grid, 5, 'Reference name', 'ImageReference');

            descriptionLabel = uilabel(grid);
            descriptionLabel.Text = 'Description';
            descriptionLabel.Layout.Row = 6;
            descriptionLabel.Layout.Column = 1;

            descriptionArea = uitextarea(grid);
            descriptionArea.Layout.Row = 6;
            descriptionArea.Layout.Column = 2;
            try
                descriptionArea.Placeholder = 'Optional notes';
            catch
                descriptionArea.Tooltip = 'Optional notes';
            end

            notesLabel = uilabel(grid);
            notesLabel.Text = 'Processing notes';
            notesLabel.Layout.Row = 7;
            notesLabel.Layout.Column = 1;

            notesArea = uitextarea(grid);
            notesArea.Layout.Row = 7;
            notesArea.Layout.Column = 2;
            try
                notesArea.Placeholder = 'Optional preprocessing/manual transform notes';
            catch
                notesArea.Tooltip = 'Optional preprocessing/manual transform notes';
            end

            buttonGrid = uigridlayout(grid);
            buttonGrid.ColumnWidth = {'1x', 90, 90};
            buttonGrid.RowHeight = {'1x'};
            buttonGrid.Padding = [0 0 0 0];
            buttonGrid.Layout.Row = 8;
            buttonGrid.Layout.Column = [1 2];

            createButton = uibutton(buttonGrid, 'push');
            createButton.Text = 'Create';
            createButton.Layout.Row = 1;
            createButton.Layout.Column = 2;

            cancelButton = uibutton(buttonGrid, 'push');
            cancelButton.Text = 'Cancel';
            cancelButton.Layout.Row = 1;
            cancelButton.Layout.Column = 3;

            dlg.UserData = [];

            createButton.ButtonPushedFcn = @(~, ~) onCreate();
            cancelButton.ButtonPushedFcn = @(~, ~) onCancel();
            dlg.CloseRequestFcn = @(~, ~) onCancel();

            movegui(dlg, 'center');
            dlg.Visible = 'on';

            uiwait(dlg);

            if isvalid(dlg)
                metadata = dlg.UserData;
                delete(dlg);
            end

            function onCreate()
                projectName = strtrim(string(projectField.Value));
                groupName = strtrim(string(groupField.Value));
                mouseID = strtrim(string(mouseField.Value));
                sessionID = strtrim(string(sessionField.Value));
                referenceName = strtrim(string(nameField.Value));

                if any([projectName, groupName, mouseID, sessionID] == "")
                    uialert(dlg, ...
                        'Project name, group name, mouse ID, and session ID are required.', ...
                        'Missing required fields', ...
                        'Icon', 'warning');
                    return
                end

                if referenceName == ""
                    referenceName = "ImageReference";
                end

                out = struct();
                out.projectName = projectName;
                out.groupName = groupName;
                out.mouseID = mouseID;
                out.sessionID = sessionID;
                out.referenceName = referenceName;
                out.description = strjoin(string(descriptionArea.Value), newline);
                out.processingNotes = strjoin(string(notesArea.Value), newline);

                dlg.UserData = out;
                uiresume(dlg);
            end

            function onCancel()
                dlg.UserData = [];
                uiresume(dlg);
            end
        end

        function editField = addDialogEditField(app, parentGrid, rowIdx, labelText, defaultText) %#ok<INUSL>
            %ADDDIALOGEDITFIELD Add labeled edit field to modal dialog.

            label = uilabel(parentGrid);
            label.Text = labelText;
            label.Layout.Row = rowIdx;
            label.Layout.Column = 1;

            editField = uieditfield(parentGrid, 'text');
            editField.Layout.Row = rowIdx;
            editField.Layout.Column = 2;
            editField.Value = char(string(defaultText));
        end

        function tryReadCurrentFrameContextFromParent(app)
            %TRYREADCURRENTFRAMECONTEXTFROMPARENT Best-effort DataViewer context read.
            %
            % This keeps the manager usable before the main DataViewer callback is
            % finalized. Explicit startup name-value inputs take precedence.

            if isempty(app.ParentApp)
                return
            end

            parentApp = app.ParentApp;

            if isempty(app.CurrentFrameImage)
                try
                    if ismethod(parentApp, 'getCurrentFrameImage')
                        app.CurrentFrameImage = parentApp.getCurrentFrameImage();
                    end
                catch
                end
            end

            if isempty(app.CurrentFrameSourceFile)
                try
                    if isprop(parentApp, 'CurrentFile')
                        app.CurrentFrameSourceFile = parentApp.CurrentFile;
                    end
                catch
                end
            end

            if isempty(app.CurrentFrameSourceFolder) && ~isempty(app.CurrentFrameSourceFile)
                try
                    app.CurrentFrameSourceFolder = fileparts(app.CurrentFrameSourceFile);
                catch
                end
            end

            if isnan(app.CurrentFrameIndex)
                try
                    if isprop(parentApp, 'CurrentFrame')
                        app.CurrentFrameIndex = double(parentApp.CurrentFrame);
                    elseif isprop(parentApp, 'FrameIndex')
                        app.CurrentFrameIndex = double(parentApp.FrameIndex);
                    end
                catch
                end
            end

            if isempty(app.CurrentSourceType)
                try
                    if ismethod(parentApp, 'getSourceType')
                        app.CurrentSourceType = parentApp.getSourceType();
                    end
                catch
                end
            end
        end

        function archiveRelPath = makeArchiveRelativePath(app, relativePath) %#ok<INUSL>
            %MAKEARCHIVERELATIVEPATH Build archive path preserving folder tree.

            relativePath = char(string(relativePath));
            relativePath = strrep(relativePath, '\', filesep);
            relativePath = strrep(relativePath, '/', filesep);

            parts = strsplit(relativePath, filesep);
            parts = parts(~cellfun(@isempty, parts));

            if isempty(parts)
                error('ImageReferenceManager:InvalidRelativePath', ...
                    'Selected reference has an invalid relative path.');
            end

            if strcmpi(parts{1}, 'referenceImages')
                tail = parts(2:end);
            else
                tail = parts;
            end

            archiveRelPath = fullfile('referenceImages', '_archive', tail{:});
        end

        function fileName = getFileNameFromPath(app, filePath) %#ok<INUSL>
            %GETFILENAMEFROMPATH Return file name plus extension.

            [~, name, ext] = fileparts(char(string(filePath)));
            fileName = [name ext];
        end

        function uniquePath = makeUniqueFilePath(app, filePath) %#ok<INUSL>
            %MAKEUNIQUEFILEPATH Append suffix if file already exists.

            uniquePath = filePath;

            if ~isfile(uniquePath)
                return
            end

            [folder, name, ext] = fileparts(filePath);

            for iFile = 1:1000
                candidate = fullfile(folder, sprintf('%s_%03d%s', name, iFile, ext));
                if ~isfile(candidate)
                    uniquePath = candidate;
                    return
                end
            end

            error('ImageReferenceManager:CouldNotCreateUniqueArchiveName', ...
                'Could not create a unique archive file name.');
        end

        function txt = valueToText(app, value) %#ok<INUSL>
            %VALUETOTEXT Convert arbitrary scalar/display value to text.

            if isempty(value)
                txt = "";
            elseif isnumeric(value) && isscalar(value)
                if isnan(value)
                    txt = "";
                else
                    txt = string(value);
                end
            elseif isstring(value) && isscalar(value)
                txt = value;
            elseif ischar(value)
                txt = string(value);
            else
                txt = string(evalc('disp(value)'));
                txt = strtrim(txt);
            end
        end

        function state = onOff(app, tf) %#ok<INUSL>
            %ONOFF Convert logical to App Designer Enable string.

            if tf
                state = 'on';
            else
                state = 'off';
            end
        end

        function startReferenceEdit(app)
            %STARTREFERENCEEDIT Start interactive reference-image curation.

            if isempty(app.SelectedImageReference) || ~isfield(app.SelectedImageReference, 'image')
                app.setStatusMessage('Select a valid Image Reference before editing.');
                return
            end

            app.ReferenceEditOriginalImage = single(app.SelectedImageReference.image);
            app.ReferenceEditCandidateImage = app.ReferenceEditOriginalImage;
            app.ReferenceEditTform = affine2d(eye(3));
            app.IsEditingReference = true;

            app.deleteReferenceEditRuntime();

            % Create the false-color preview once. During editing, only CData is
            % updated so user zoom/pan is not reset by repeated imshowpair calls.
            app.ReferenceEditPreviewHandle = imagesc(app.UIAxes, ...
                app.buildReferenceEditFalseColorFrame( ...
                app.ReferenceEditOriginalImage, ...
                app.ReferenceEditCandidateImage));

            axis(app.UIAxes, 'image');
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            title(app.UIAxes, 'Original vs edited reference', 'Interpreter', 'none');

            imgSize = size(app.ReferenceEditOriginalImage);
            rectPos = [1 1 imgSize(2) imgSize(1)];

            % Start slightly zoomed out so the rectangle edges/rotation handle are
            % easy to grab. The user can then zoom/pan normally.
            padFrac = 0.35;
            app.UIAxes.XLim = [0.5 - padFrac * imgSize(2), imgSize(2) + 0.5 + padFrac * imgSize(2)];
            app.UIAxes.YLim = [0.5 - padFrac * imgSize(1), imgSize(1) + 0.5 + padFrac * imgSize(1)];

            hold(app.UIAxes, 'on');

            app.ReferenceEditBoxHandle = drawrectangle(app.UIAxes, ...
                'Position', rectPos, ...
                'Rotatable', true, ...
                'DrawingArea', 'unlimited', ...
                'Color', [1 1 1], ...
                'FaceAlpha', 0, ...
                'LineWidth', 1.5, ...
                'InteractionsAllowed', 'all');

            app.ReferenceEditListeners = {};
            app.ReferenceEditListeners{end+1} = addlistener(app.ReferenceEditBoxHandle, ...
                'MovingROI', @(src, evt) app.onReferenceEditBoxMoving(src, evt));
            app.ReferenceEditListeners{end+1} = addlistener(app.ReferenceEditBoxHandle, ...
                'ROIMoved', @(src, evt) app.onReferenceEditBoxMoved(src, evt));

            app.updateReferenceEditCandidate();
            app.refreshButtonState();

            app.setStatusMessage('Editing Image Reference. Translate/rotate the box, then click Apply. Click Restore to cancel.');
        end

        function onReferenceEditBoxMoving(app, src, evt) %#ok<INUSD>
            %ONREFERENCEEDITBOXMOVING Update candidate while edit box moves.

            app.lockReferenceEditBoxSize();
            app.updateReferenceEditCandidate();
        end

        function onReferenceEditBoxMoved(app, src, evt) %#ok<INUSD>
            %ONREFERENCEEDITBOXMOVED Update candidate after edit box moves.

            app.lockReferenceEditBoxSize();
            app.updateReferenceEditCandidate();
        end

        function lockReferenceEditBoxSize(app)
            %LOCKREFERENCEEDITBOXSIZE Prevent scaling during reference editing.
            %
            %   The user may translate and rotate the reference image, but scaling is
            %   intentionally disabled. If the rectangle is resized interactively,
            %   this method restores its original image-sized extent around the
            %   current rectangle center.

            if ~app.isUsableReferenceEditBox() || isempty(app.ReferenceEditOriginalImage)
                return
            end

            imgSize = size(app.ReferenceEditOriginalImage);
            lockedSize = [imgSize(2), imgSize(1)]; % [width height]

            pos = double(app.ReferenceEditBoxHandle.Position(:).');

            if numel(pos) < 4 || any(~isfinite(pos))
                return
            end

            centerXY = [pos(1) + pos(3)/2, pos(2) + pos(4)/2];

            app.ReferenceEditBoxHandle.Position = [ ...
                centerXY(1) - lockedSize(1)/2, ...
                centerXY(2) - lockedSize(2)/2, ...
                lockedSize(1), ...
                lockedSize(2)];
        end

        function updateReferenceEditCandidate(app)
            %UPDATEREFERENCEEDITCANDIDATE Build candidate image from current edit box.

            if ~app.IsEditingReference || isempty(app.ReferenceEditOriginalImage) || ...
                    ~app.isUsableReferenceEditBox()
                return
            end

            img = app.ReferenceEditOriginalImage;
            imgSize = size(img);

            tform = app.getReferenceEditTform();
            Rout = imref2d(imgSize);

            candidate = imwarp(img, tform, ...
                'OutputView', Rout, ...
                'InterpolationMethod', 'linear', ...
                'FillValues', 0);

            candidate(~isfinite(candidate)) = 0;

            app.ReferenceEditCandidateImage = single(candidate);
            app.ReferenceEditTform = tform;

            rgbPreview = app.buildReferenceEditFalseColorFrame( ...
                app.ReferenceEditOriginalImage, ...
                app.ReferenceEditCandidateImage);

            if isempty(app.ReferenceEditPreviewHandle) || ~isvalid(app.ReferenceEditPreviewHandle)
                app.ReferenceEditPreviewHandle = imagesc(app.UIAxes, rgbPreview);
                axis(app.UIAxes, 'image');
                app.UIAxes.XTick = [];
                app.UIAxes.YTick = [];
                title(app.UIAxes, 'Original vs edited reference', 'Interpreter', 'none');
            else
                app.ReferenceEditPreviewHandle.CData = rgbPreview;
            end

            try
                uistack(app.ReferenceEditPreviewHandle, 'bottom');
                uistack(app.ReferenceEditBoxHandle, 'top');
            catch
            end
        end

        function rgb = buildReferenceEditFalseColorFrame(app, fixedImg, movingImg) %#ok<INUSL>
            %BUILDREFERENCEEDITFALSECOLORFRAME Create RGB false-color overlay.
            %
            %   Red   = original reference image.
            %   Green = edited candidate image.
            %   Blue  = average of both channels.

            fixedImg = single(fixedImg);
            movingImg = single(movingImg);

            fixedImg(~isfinite(fixedImg)) = 0;
            movingImg(~isfinite(movingImg)) = 0;

            fixedImg = iNormalize01(fixedImg);
            movingImg = iNormalize01(movingImg);

            rgb = zeros([size(fixedImg), 3], 'single');
            rgb(:, :, 1) = fixedImg;
            rgb(:, :, 2) = movingImg;
            rgb(:, :, 3) = 0.5 .* (fixedImg + movingImg);

            function imgOut = iNormalize01(imgIn)
                finiteVals = imgIn(isfinite(imgIn));

                if isempty(finiteVals)
                    imgOut = zeros(size(imgIn), 'single');
                    return
                end

                lo = min(finiteVals(:));
                hi = max(finiteVals(:));

                if hi <= lo
                    imgOut = zeros(size(imgIn), 'single');
                    return
                end

                imgOut = (imgIn - lo) ./ (hi - lo);
                imgOut = min(max(imgOut, 0), 1);
            end
        end

        function tform = getReferenceEditTform(app)
            %GETREFERENCEEDITTFORM Return affine2d from current edit-box state.
            %
            %   The transform is from original reference-image space to edited
            %   reference-image space. It is stored only as provenance.

            img = app.ReferenceEditOriginalImage;
            imgSize = size(img);

            pos = double(app.ReferenceEditBoxHandle.Position(:).');
            angleDeg = app.getReferenceEditBoxRotation();

            originalCenter = [(imgSize(2) + 1) / 2, (imgSize(1) + 1) / 2];
            newCenter = [pos(1) + pos(3) / 2, pos(2) + pos(4) / 2];

            dx = newCenter(1) - originalCenter(1);
            dy = newCenter(2) - originalCenter(2);

            angleRad = -deg2rad(angleDeg);

            Tcenter1 = [1 0 0; 0 1 0; -originalCenter(1) -originalCenter(2) 1];
            R = [cos(angleRad) sin(angleRad) 0; -sin(angleRad) cos(angleRad) 0; 0 0 1];
            Tcenter2 = [1 0 0; 0 1 0; originalCenter(1) originalCenter(2) 1];
            Ttrans = [1 0 0; 0 1 0; dx dy 1];

            tform = affine2d(Tcenter1 * R * Tcenter2 * Ttrans);
        end

        function angleDeg = getReferenceEditBoxRotation(app)
            %GETREFERENCEEDITBOXROTATION Return rectangle rotation angle.

            angleDeg = 0;

            if ~app.isUsableReferenceEditBox()
                return
            end

            try
                if isprop(app.ReferenceEditBoxHandle, 'RotationAngle')
                    angleDeg = double(app.ReferenceEditBoxHandle.RotationAngle);
                end
            catch
                angleDeg = 0;
            end

            if ~isscalar(angleDeg) || ~isfinite(angleDeg)
                angleDeg = 0;
            end
        end

        function applyReferenceEdit(app)
            %APPLYREFERENCEEDIT Save edited candidate image into ImageReference file.

            if ~app.IsEditingReference || isempty(app.ReferenceEditCandidateImage)
                app.setStatusMessage('No active Image Reference edit to apply.');
                return
            end

            answer = uiconfirm(app.UIFigure, ...
                ['Apply the edited image to the selected Image Reference file?' newline newline ...
                'The transform used here is saved only as provenance. It is not the ' ...
                'dataset-alignment transform used later in DataParams.registration.tform.'], ...
                'Apply Image Reference edit', ...
                'Options', {'Apply', 'Cancel'}, ...
                'DefaultOption', 'Apply', ...
                'CancelOption', 'Cancel', ...
                'Icon', 'warning');

            if ~strcmpi(answer, 'Apply')
                return
            end

            try
                notes = 'Reference image manually translated/rotated in ImageReferenceManager.';

                ImageReference = updateImageReferenceImage( ...
                    app.SelectedReferenceFileAbs, ...
                    app.ReferenceEditCandidateImage, ...
                    'manualTransformTform', app.ReferenceEditTform, ...
                    'manualTransformNote', notes, ...
                    'updatedBy', 'ImageReferenceManager');

                relPath = ImageReference.relativePath;

                app.deleteReferenceEditRuntime();
                app.IsEditingReference = false;
                app.ReferenceEditOriginalImage = [];
                app.ReferenceEditCandidateImage = [];
                app.ReferenceEditTform = [];

                app.refreshReferenceTable();
                app.selectReferenceByRelativePath(relPath);
                app.setStatusMessage('Image Reference edit applied.');

            catch ME
                app.setStatusMessage(sprintf('Failed to apply Image Reference edit: %s', ME.message));
                uialert(app.UIFigure, ...
                    getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Apply edit failed', ...
                    'Icon', 'error');
            end
        end

        function cancelReferenceEdit(app)
            %CANCELREFERENCEEDIT Cancel current edit and restore saved preview.

            app.deleteReferenceEditRuntime();

            app.IsEditingReference = false;
            app.ReferenceEditOriginalImage = [];
            app.ReferenceEditCandidateImage = [];
            app.ReferenceEditTform = [];

            app.previewSelectedReference();
            app.updateMetadataTextArea();
            app.refreshButtonState();

            app.setStatusMessage('Image Reference edit cancelled.');
        end

        function deleteReferenceEditRuntime(app)
            %DELETEREFERENCEEDITRUNTIME Delete temporary edit handles/listeners.

            if ~isempty(app.ReferenceEditListeners)
                for iListener = 1:numel(app.ReferenceEditListeners)
                    try
                        if ~isempty(app.ReferenceEditListeners{iListener}) && ...
                                isvalid(app.ReferenceEditListeners{iListener})
                            delete(app.ReferenceEditListeners{iListener});
                        end
                    catch
                    end
                end
            end

            app.ReferenceEditListeners = {};

            try
                if app.isUsableReferenceEditBox()
                    delete(app.ReferenceEditBoxHandle);
                end
            catch
            end

            app.ReferenceEditBoxHandle = gobjects(1);

            try
                if ~isempty(app.ReferenceEditPreviewHandle) && isvalid(app.ReferenceEditPreviewHandle)
                    delete(app.ReferenceEditPreviewHandle);
                end
            catch
            end

            app.ReferenceEditPreviewHandle = matlab.graphics.primitive.Image.empty;
            hold(app.UIAxes, 'off');
            app.UIAxes.NextPlot = 'replacechildren';
        end

        function tf = isUsableReferenceEditBox(app)
            %ISUSABLEREFERENCEEDITBOX True when edit box handle is valid.

            tf = ~isempty(app.ReferenceEditBoxHandle) && ...
                all(isgraphics(app.ReferenceEditBoxHandle)) && ...
                all(isvalid(app.ReferenceEditBoxHandle));
        end

        function previewCandidateReferenceImage(app, referenceImage, sourceInfo)
            %PREVIEWCANDIDATEREFERENCEIMAGE Preview external image before save.

            imagesc(app.UIAxes, referenceImage);
            axis(app.UIAxes, 'image');
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            colormap(app.UIAxes, gray);

            title(app.UIAxes, 'Candidate Image Reference', 'Interpreter', 'none');

            lines = strings(0, 1);

            lines(end+1) = "Candidate Image Reference";
            lines(end+1) = "";
            lines(end+1) = "Source type: " + string(sourceInfo.sourceType);
            lines(end+1) = "Source file:";
            lines(end+1) = string(sourceInfo.sourceFile);

            if isfield(sourceInfo, 'selectedEntry') && ...
                    strlength(string(sourceInfo.selectedEntry)) > 0
                lines(end+1) = "Selected UMT entry: " + string(sourceInfo.selectedEntry);
            end

            if isfield(sourceInfo, 'selectedVariable') && ...
                    strlength(string(sourceInfo.selectedVariable)) > 0
                lines(end+1) = "Selected MAT variable: " + string(sourceInfo.selectedVariable);
            end

            lines(end+1) = "";
            lines(end+1) = "Image size: " + sprintf('%d x %d', ...
                sourceInfo.imageSizeYX(1), sourceInfo.imageSizeYX(2));

            if isfield(sourceInfo, 'nSourceFrames') && ~isempty(sourceInfo.nSourceFrames)
                lines(end+1) = "Source frames/pages: " + string(sourceInfo.nSourceFrames);
            end

            if isfield(sourceInfo, 'usedFrameIndex') && ~isempty(sourceInfo.usedFrameIndex)
                lines(end+1) = "Frames/pages averaged: " + string(numel(sourceInfo.usedFrameIndex));
            end

            lines(end+1) = "";
            lines(end+1) = "Processing notes:";
            lines(end+1) = string(sourceInfo.processingNotes);

            app.MetaDataTextArea.Value = cellstr(lines);
        end

        function notes = combineProcessingNotes(app, sourceNotes, userNotes) %#ok<INUSL>
            %COMBINEPROCESSINGNOTES Combine source and user processing notes.

            sourceNotes = strtrim(string(sourceNotes));
            userNotes = strtrim(string(userNotes));

            if sourceNotes == "" && userNotes == ""
                notes = "";
            elseif sourceNotes == ""
                notes = userNotes;
            elseif userNotes == ""
                notes = sourceNotes;
            else
                notes = sourceNotes + newline + userNotes;
            end
        end

        function showReferencePreviewImage(app, img, titleText)
            %SHOWREFERENCEPREVIEWIMAGE Display selected reference image cleanly.
            %
            %   This resets edit-mode axes state before showing the selected
            %   grayscale reference image. It also updates image geometry so changing
            %   from one image size to another does not leave stale axes limits or
            %   old image overlays.

            if isempty(img)
                app.clearPreviewAxes('No Image Reference selected');
                return
            end

            img = single(img);
            img(~isfinite(img)) = 0;

            imgSize = size(img);
            nY = imgSize(1);
            nX = imgSize(2);

            % Remove edit-only graphics before normal preview.
            app.deleteReferenceEditRuntime();

            % Reset axes state that may have been changed during editing.
            hold(app.UIAxes, 'off');
            app.UIAxes.NextPlot = 'replacechildren';
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes.CLimMode = 'auto';

            % Reuse a normal preview image handle when possible. If another image
            % handle exists from a different preview mode, recreate cleanly.
            if isempty(app.ReferencePreviewHandle) || ...
                    ~isvalid(app.ReferencePreviewHandle) || ...
                    ~isequal(size(app.ReferencePreviewHandle.CData), size(img))

                cla(app.UIAxes);

                app.ReferencePreviewHandle = imagesc(app.UIAxes, ...
                    [1 nX], [1 nY], img);

            else
                app.ReferencePreviewHandle.CData = img;
                app.ReferencePreviewHandle.XData = [1 nX];
                app.ReferencePreviewHandle.YData = [1 nY];
            end

            axis(app.UIAxes, 'image');
            app.UIAxes.XLim = [0.5, nX + 0.5];
            app.UIAxes.YLim = [0.5, nY + 0.5];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            colormap(app.UIAxes, gray);

            title(app.UIAxes, titleText, 'Interpreter', 'none');
        end

        function clearPreviewAxes(app, titleText)
            %CLEARPREVIEWAXES Clear preview axes and reset image handles.

            app.deleteReferenceEditRuntime();

            if ~isempty(app.ReferencePreviewHandle)
                try
                    if isvalid(app.ReferencePreviewHandle)
                        delete(app.ReferencePreviewHandle);
                    end
                catch
                end
            end

            app.ReferencePreviewHandle = matlab.graphics.primitive.Image.empty;

            cla(app.UIAxes);
            hold(app.UIAxes, 'off');

            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            app.UIAxes.CLimMode = 'auto';
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];

            title(app.UIAxes, titleText, 'Interpreter', 'none');
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, appMode, varargin)
            %STARTUPFCN Initialize Image Reference Manager state.
            %
            %   startupFcn(app)
            %   startupFcn(app, parentApp)
            %   startupFcn(app, parentApp, appMode)
            %   startupFcn(..., 'currentFrameImage', img, ...)
            %
            %   appMode:
            %       'manage' - create/preview/archive references.
            %       'select' - select one reference and return it to caller.
            app.UIFigure.Visible = 'off';
            if nargin < 2
                parentApp = [];
            end

            if nargin < 3 || isempty(appMode)
                appMode = 'manage';
            end

            app.ParentApp = parentApp;

            appMode = lower(char(string(appMode)));
            if ~ismember(appMode, {'manage', 'select'})
                error('ImageReferenceManager:InvalidMode', ...
                    'appMode must be "manage" or "select".');
            end
            app.AppMode = appMode;

            p = inputParser;
            p.FunctionName = 'ImageReferenceManager.startupFcn';

            addParameter(p, 'currentFrameImage', [], @(x) isempty(x) || isnumeric(x) || islogical(x));
            addParameter(p, 'sourceFile', '', @(x) ischar(x) || (isstring(x) && isscalar(x)));
            addParameter(p, 'sourceFolder', '', @(x) ischar(x) || (isstring(x) && isscalar(x)));
            addParameter(p, 'sourceFrame', NaN, @(x) isempty(x) || (isnumeric(x) && isscalar(x)));
            addParameter(p, 'sourceType', '', @(x) ischar(x) || (isstring(x) && isscalar(x)));

            parse(p, varargin{:});
            R = p.Results;

            app.CurrentFrameImage = R.currentFrameImage;
            app.CurrentFrameSourceFile = char(string(R.sourceFile));
            app.CurrentFrameSourceFolder = char(string(R.sourceFolder));
            app.CurrentSourceType = char(string(R.sourceType));

            if isempty(R.sourceFrame)
                app.CurrentFrameIndex = NaN;
            else
                app.CurrentFrameIndex = double(R.sourceFrame);
            end

            % If explicit context was not provided, try to infer it from the parent.
            app.tryReadCurrentFrameContextFromParent();

            app.UIFigure.Name = 'Image Reference Manager';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowStyle = 'modal';

            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = false;

            app.configureInitialState();
            app.refreshReferenceTable();
            app.refreshButtonState();
            if ~isempty(app.ParentApp)
                placeAppInsideCaller(app.ParentApp, app.UIFigure, 'center');
            end
            app.UIFigure.Visible = 'on';

        end

        % Callback function: CloseButton, UIFigure
        function UIFigureCloseRequest(app, event)

            %UIFIGURECLOSEREQUEST Close the manager without confirming selection.

            app.WasSelectionConfirmed = false;

            if strcmpi(app.AppMode, 'select')
                app.UIFigure.Visible = 'off';
                uiresume(app.UIFigure);
            else
                delete(app);
            end

        end

        % Button pushed function: CreatefromcurrentframeButton
        function CreatefromcurrentframeButtonPushed(app, event)
            %CREATEFROMCURRENTFRAMEBUTTONPUSHED Create ImageReference from current frame.

            if isempty(app.CurrentFrameImage)
                uialert(app.UIFigure, ...
                    'No current frame image is available. Open this manager from DataViewer with loaded image data.', ...
                    'No current frame', ...
                    'Icon', 'warning');
                return
            end

            metadata = app.promptCreateReferenceMetadata();

            if isempty(metadata)
                app.setStatusMessage('Image Reference creation cancelled.');
                return
            end

            try
                sourceFrame = [];
                if isfinite(app.CurrentFrameIndex) && app.CurrentFrameIndex >= 1
                    sourceFrame = app.CurrentFrameIndex;
                end

                [ImageReference, referenceFileAbs, referenceFileRel] = createImageReference( ...
                    app.CurrentFrameImage, ...
                    'projectName', metadata.projectName, ...
                    'groupName', metadata.groupName, ...
                    'mouseID', metadata.mouseID, ...
                    'sessionID', metadata.sessionID, ...
                    'referenceName', metadata.referenceName, ...
                    'description', metadata.description, ...
                    'sourceFolder', app.CurrentFrameSourceFolder, ...
                    'sourceFile', app.CurrentFrameSourceFile, ...
                    'sourceFrame', sourceFrame, ...
                    'sourceType', app.CurrentSourceType, ...
                    'wasManuallyTransformed', false, ...
                    'processingNotes', metadata.processingNotes, ...
                    'createdBy', 'ImageReferenceManager');

                app.refreshReferenceTable();
                app.selectReferenceByRelativePath(referenceFileRel);

                app.setStatusMessage(sprintf('Image Reference created: %s', referenceFileRel));

                uialert(app.UIFigure, ...
                    sprintf('Image Reference created:\n%s', referenceFileAbs), ...
                    'Image Reference created', ...
                    'Icon', 'success');

            catch ME
                app.setStatusMessage(sprintf('Failed to create Image Reference: %s', ME.message));
                uialert(app.UIFigure, ...
                    getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Image Reference creation failed', ...
                    'Icon', 'error');
            end

        end

        % Button pushed function: UseSelectedButton
        function UseSelectedButtonPushed(app, event)

            %USESELECTEDBUTTONPUSHED Return selected ImageReference to caller.

            if ~strcmpi(app.AppMode, 'select')
                app.setStatusMessage('Use Selected is available only in selection mode.');
                return
            end

            if isempty(app.SelectedReferenceFileRel) || isempty(app.SelectedImageReference)
                app.setStatusMessage('No valid Image Reference selected.');
                return
            end

            app.OutputReferenceFileRel = app.SelectedReferenceFileRel;
            app.OutputReferenceFileAbs = app.SelectedReferenceFileAbs;
            app.OutputImageReference = app.SelectedImageReference;
            app.WasSelectionConfirmed = true;

            app.UIFigure.Visible = 'off';
            uiresume(app.UIFigure);

        end

        % Button pushed function: ArchiveButton
        function ArchiveButtonPushed(app, event)
            %ARCHIVESELECTEDBUTTONPUSHED Move selected ImageReference to archive.

            if ~strcmpi(app.AppMode, 'manage')
                app.setStatusMessage('Archive Selected is available only in manager mode.');
                return
            end

            if isempty(app.SelectedReferenceFileAbs) || ~isfile(app.SelectedReferenceFileAbs)
                app.setStatusMessage('No valid Image Reference selected.');
                return
            end

            answer = uiconfirm(app.UIFigure, ...
                ['Archive the selected Image Reference?' newline newline ...
                'This removes it from the active reference list but does not modify ' ...
                'datasets that already point to this reference file.'], ...
                'Archive Image Reference', ...
                'Options', {'Archive', 'Cancel'}, ...
                'DefaultOption', 'Cancel', ...
                'CancelOption', 'Cancel', ...
                'Icon', 'warning');

            if ~strcmpi(answer, 'Archive')
                return
            end

            try
                archiveRelPath = app.makeArchiveRelativePath(app.SelectedReferenceFileRel);
                archiveFolderRel = fileparts(archiveRelPath);
                archiveFolderAbs = getUmitFolder(archiveFolderRel);
                archiveAbsPath = fullfile(archiveFolderAbs, app.getFileNameFromPath(archiveRelPath));

                archiveAbsPath = app.makeUniqueFilePath(archiveAbsPath);

                movefile(app.SelectedReferenceFileAbs, archiveAbsPath);

                app.setStatusMessage(sprintf('Image Reference archived: %s', archiveAbsPath));

                app.refreshReferenceTable();
                app.clearSelectedReference();

            catch ME
                app.setStatusMessage(sprintf('Failed to archive Image Reference: %s', ME.message));
                uialert(app.UIFigure, ...
                    getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Archive failed', ...
                    'Icon', 'error');
            end

        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            %UITABLECELLSELECTION Preview selected ImageReference row.

            if isempty(event.Indices)
                app.clearSelectedReference();
                return
            end

            rowIdx = event.Indices(1);

            try
                app.selectReferenceRow(rowIdx);
            catch ME
                app.clearSelectedReference();
                app.setStatusMessage(sprintf('Failed to load selected reference: %s', ME.message));
                uialert(app.UIFigure, ...
                    getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Image Reference load failed', ...
                    'Icon', 'warning');
            end


        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            %EDITBUTTONPUSHED Start or apply ImageReference edit mode.

            if isempty(app.SelectedImageReference)
                app.setStatusMessage('Select an Image Reference before editing.');
                return
            end

            if app.IsEditingReference
                app.applyReferenceEdit();
            else
                app.startReferenceEdit();
            end

        end

        % Button pushed function: RestoreButton
        function RestoreButtonPushed(app, event)
            %RESTOREBUTTONPUSHED Cancel current ImageReference edit.

            if ~app.IsEditingReference
                app.setStatusMessage('No active Image Reference edit to restore.');
                return
            end

            app.cancelReferenceEdit();

        end

        % Button pushed function: CreatefromfileButton
        function CreatefromfileButtonPushed(app, event)
            %CREATEFROMFILEBUTTONPUSHED Create ImageReference from external file.

            if ~strcmpi(app.AppMode, 'manage')
                app.setStatusMessage('Create from file is available only in manager mode.');
                return
            end

            [fileName, folderName] = uigetfile( ...
                { ...
                '*.tif;*.tiff;*.dat;*.umt;*.mat', ...
                'Supported reference sources (*.tif, *.tiff, *.dat, *.umt, *.mat)'; ...
                '*.tif;*.tiff', ...
                'TIFF image or stack (*.tif, *.tiff)'; ...
                '*.dat', ...
                'DAT image time series (*.dat)'; ...
                '*.umt', ...
                'UMT image data (*.umt)'; ...
                '*.mat', ...
                'MAT-file 2D numeric image (*.mat)'}, ...
                'Select image reference source');

            if isequal(fileName, 0)
                app.setStatusMessage('Create from file cancelled.');
                return
            end

            sourceFile = fullfile(folderName, fileName);

            try
                app.setStatusMessage('Reading image reference source...');
                drawnow limitrate

                [referenceImage, sourceInfo] = readImageReferenceSource(sourceFile, ...
                    'maxFramesToAverage', 100);

                app.previewCandidateReferenceImage(referenceImage, sourceInfo);

                metadata = app.promptCreateReferenceMetadata();

                if isempty(metadata)
                    app.setStatusMessage('Image Reference creation cancelled.');
                    app.previewSelectedReference();
                    return
                end

                [~, referenceFileAbs, referenceFileRel] = createImageReference( ...
                    referenceImage, ...
                    'projectName', metadata.projectName, ...
                    'groupName', metadata.groupName, ...
                    'mouseID', metadata.mouseID, ...
                    'sessionID', metadata.sessionID, ...
                    'referenceName', metadata.referenceName, ...
                    'description', metadata.description, ...
                    'sourceFolder', sourceInfo.sourceFolder, ...
                    'sourceFile', sourceInfo.sourceFile, ...
                    'sourceFrame', sourceInfo.sourceFrame, ...
                    'sourceType', sourceInfo.sourceType, ...
                    'wasManuallyTransformed', false, ...
                    'processingNotes', app.combineProcessingNotes( ...
                    sourceInfo.processingNotes, metadata.processingNotes), ...
                    'createdBy', 'ImageReferenceManager');

                app.refreshReferenceTable();
                app.selectReferenceByRelativePath(referenceFileRel);

                app.setStatusMessage(sprintf( ...
                    'Image Reference created from file: %s', referenceFileRel));

                uialert(app.UIFigure, ...
                    sprintf('Image Reference created:\n%s', referenceFileAbs), ...
                    'Image Reference created', ...
                    'Icon', 'success');

            catch ME
                if strcmp(ME.identifier, 'readImageReferenceSource:SelectionCancelled')
                    app.setStatusMessage('Create from file cancelled.');
                    app.previewSelectedReference();
                    return
                end

                app.setStatusMessage(sprintf( ...
                    'Failed to create Image Reference from file: %s', ME.message));

                uialert(app.UIFigure, ...
                    getReport(ME, 'basic', 'hyperlinks', 'off'), ...
                    'Create from file failed', ...
                    'Icon', 'error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Reference Manager';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SelectedImageReferencePanel = uipanel(app.GridLayout);
            app.SelectedImageReferencePanel.Title = 'Selected Image Reference';
            app.GridSelectedImageReferencePanel = uigridlayout(app.SelectedImageReferencePanel);
            app.UIAxes = uiaxes(app.GridSelectedImageReferencePanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.MetaDataTextArea = uitextarea(app.GridSelectedImageReferencePanel);
            app.MetaDataTextArea.Editable = 'off';
            app.EditButton = uibutton(app.GridSelectedImageReferencePanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.RestoreButton = uibutton(app.GridSelectedImageReferencePanel, 'push');
            app.RestoreButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreButtonPushed, true);
            app.RestoreButton.Icon = 'icon_restore_state_Black.png';
            app.RestoreButton.Text = '';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.GridLayoutBottom = uigridlayout(app.GridLayout);
            app.UseSelectedButton = uibutton(app.GridLayoutBottom, 'push');
            app.UseSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @UseSelectedButtonPushed, true);
            app.UseSelectedButton.Text = 'Use Selected';
            app.CloseButton = uibutton(app.GridLayoutBottom, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CloseButton.Text = 'Close';
            app.ArchiveButton = uibutton(app.GridLayoutBottom, 'push');
            app.ArchiveButton.ButtonPushedFcn = createCallbackFcn(app, @ArchiveButtonPushed, true);
            app.ArchiveButton.Tooltip = {'Archive selected Image Reference File'};
            app.ArchiveButton.Text = 'Archive';
            app.CreatefromfileButton = uibutton(app.GridLayoutBottom, 'push');
            app.CreatefromfileButton.ButtonPushedFcn = createCallbackFcn(app, @CreatefromfileButtonPushed, true);
            app.CreatefromfileButton.Text = {'Create from'; 'file'};
            app.CreatefromcurrentframeButton = uibutton(app.GridLayoutBottom, 'push');
            app.CreatefromcurrentframeButton.ButtonPushedFcn = createCallbackFcn(app, @CreatefromcurrentframeButtonPushed, true);
            app.CreatefromcurrentframeButton.Text = {'Create from current '; 'frame'};
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageReferenceManager(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end