classdef DataViewer_Coreg2Cams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        UIAxes                         matlab.ui.control.UIAxes
        GridLayout2                    matlab.ui.container.GridLayout
        GeometrictransformationfilePanel  matlab.ui.container.Panel
        DeleteButton                   matlab.ui.control.Button
        RecoverfrombackupButton        matlab.ui.control.Button
        ShowinfolderButton             matlab.ui.control.Button
        RunCoregistrationButton        matlab.ui.control.Button
        CoregistrationparametersPanel  matlab.ui.container.Panel
        EnhancecontrastCheckBox        matlab.ui.control.CheckBox
        FliphorizontallyCheckBox       matlab.ui.control.CheckBox
        UIAxes2                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp % Handle to DataViewer        
        appMode % App mode: 'CoregistrationMode' or 'ResultsViewMode'.
    end
    
    properties (Access = public)
        filename = 'coreg2cam_tform' % Default name of the .mat file containing the tform and registered images.
        tformFile % Structure with info of all tform files from the Labeo folder:  organized in descending order of creation datetime.
        DataFolder % Folder containing the .dat files to be used to calculate the geometric transformation (Tform).
        LabeoFolder % " .../Documents/LabeoTech/Config/umIT/tformFiles"
    end
    
    methods (Access = {?DataViewer})
        
        function lookForTformFile(app)
            % This function looks for any tform files saved in
            % .../Documents/LabeoTech/Config/umIT/tformFiles/... folder.
            % If the folder doesn't exist, it creates one.
            % Output:
            %   tFormFile (struct): "dir" struct containing the info from all
            %   tform files found in the LabeoTech folder.
            
            % Find folder:
            if ispc
                % For windows:
                root = getenv('USERPROFILE');
            else
                % For unix:
                root = getenv('HOME');
            end
            % If the folder doesn't exist, create one:
            app.LabeoFolder = fullfile(root,'Documents', 'LabeoTech', 'Config','umIT','tformFiles');
            if ~isfolder(app.LabeoFolder)
                mkdir(app.LabeoFolder);
            end
            % Get the list of tform files:
            app.tformFile = dir(fullfile(app.LabeoFolder, [app.filename '.mat']));
        end


        function saveTform(app, tform, registeredImages)
            % saveTform - Save transformation data and registered images.
            %
            %   This function saves the provided transformation 'tform' and the
            %   'registeredImages' in the LabeoTech directory, creating a backup of
            %   the previous transformation file if it exists.
            %
            %   Inputs:
            %   - tform: The transformation data to be saved.
            %   - registeredImages: The registered images to be saved.
            %
            
            
            % Create a backup filename based on the current filename and timestamp.
            bkpName = [app.filename '_' datestr(now(),'yyyymmddHHMMSS') '_bkp.mat'];
            
            if ~isempty(app.tformFile)
                % If a previous transformation file exists, create a backup of it.
                copyfile(fullfile(app.tformFile.folder, app.tformFile.name), fullfile(app.tformFile.folder, bkpName));                
                % Overwrite the existing transformation file with new data.
                save(fullfile(app.tformFile.folder, app.tformFile.name), 'tform', 'registeredImages');
            else
                % If no previous transformation file exists, create a new one and
                % make a backup.
                save(fullfile(app.LabeoFolder, [app.filename '.mat']), 'tform', 'registeredImages');
                copyfile(fullfile(app.LabeoFolder, [app.filename '.mat']), fullfile(app.LabeoFolder, bkpName));
            end
        end
        
        function switchAppMode(app)
            % switchAppMode - Switches the application between Coregistration Mode and Results View Mode.
            %
            %   This function switches the application's display and functionality
            %   between Coregistration Mode and Results View Mode based on the provided 'AppMode'.
            %   In Coregistration Mode, it hides the Results View panel, adjusts the figure size,
            %   updates axes titles, and displays example images from each camera. In Results View Mode,
            %   it hides the Coregistration Mode panel, Execution button, and adjusts the figure size.            
            
            if strcmpi(app.appMode, 'CoregistrationMode')
                % In Coregistration Mode:
                
                % Hide ResultsViewMode panel:
                app.GridLayout2.RowHeight{3} = 0;
                
                % Set Figure Horizontal size:
                app.UIFigure.Position(3) = 960;
                
                % Set Axes titles:
                app.UIAxes.Title.String = 'Camera #1 Preview';
                app.UIAxes2.Title.String = 'Camera #2 Preview';
                
                % Show examples of images from each camera:
                load(fullfile(app.DataFolder, 'AcqInfos.mat')); %#ok
                NbIllum = sum(cellfun(@(X) contains(X, 'Illumination'), fieldnames(AcqInfoStream)));
                Cam1List = {};
                Cam2List = {};
                for ind = 1:NbIllum
                    idx = AcqInfoStream.("Illumination" + int2str(ind)).CamIdx;
                    chan = lower(AcqInfoStream.("Illumination" + int2str(ind)).Color);
                    if( contains(chan, 'fluo') )
                        chan = ['fluo_' chan(9:11)];
                    end
                    if( contains(chan, 'amber') )
                        chan = 'yellow';
                    end
                    if( idx == 1 )
                        Cam1List{end+1} = [chan '.dat'];%#ok
                    else
                        Cam2List{end+1} = [chan  '.dat'];%#ok
                    end
                end
                clear NbIllum ind idx chan
                
                % Get data size from the first channel.
                datMap = mapDatFile(fullfile(app.DataFolder, Cam1List{1}));
                cam1Img = zeros([length(Cam1List), size(datMap.Data.data, 1), size(datMap.Data.data, 2)], 'single');
                cam2Img = zeros([length(Cam2List), size(datMap.Data.data, 1), size(datMap.Data.data, 2)], 'single');
                clear datMap
                
               % Create a combined image from Camera 1:
                for ii = 1:length(Cam1List)
                    datMap = mapDatFile(fullfile(app.DataFolder, Cam1List{ii}));
                    dat = datMap.Data.data(:,:,1);
                    if mean(dat(:) > 1e3)
                        dat = dat ./ mean(dat(:));
                    else
                        dat = zeros(size(dat));
                    end
                    cam1Img(ii, :, :) = dat;
                end
                cam1Img = squeeze(sum(cam1Img, 1));
                clear datMap
                % Create a combined image from Camera 2:
                for ii = 1:length(Cam2List)
                    datMap = mapDatFile(fullfile(app.DataFolder, Cam2List{ii}));
                    dat = datMap.Data.data(:,:,1);
                    if mean(dat(:) > 1e3)
                        dat = dat ./ mean(dat(:));
                    else
                        dat = zeros(size(dat));
                    end
                    cam2Img(ii, :, :) = dat;
                end
                clear datMap
                cam2Img = squeeze(sum(cam2Img, 1));
                % Show combined images from each camera:
                imagesc(app.UIAxes, cam1Img); % Camera #1
                imagesc(app.UIAxes2, cam2Img); % Camera #2
                set(app.UIAxes, 'XTick',[],'YTick',[],'DataAspectRatio',[1 1 1], 'XLim',[1 size(cam1Img,1)], 'YLim',[1 size(cam1Img,2)], 'Colormap',gray)
                set(app.UIAxes2, 'XTick',[],'YTick',[],'DataAspectRatio',[1 1 1], 'XLim',[1 size(cam2Img,1)], 'YLim',[1 size(cam2Img,2)], 'Colormap',gray)
                return
            end
            
            % In Results View Mode:
            
            % Hide CoregistrationMode panel and Execution button:
            app.GridLayout2.RowHeight([1,2]) = {0,0};
            
            % Hide Axes2
            app.GridLayout.ColumnWidth{3} = 0;
            
            if isempty(app.tformFile)
                % If no transformation file is loaded, hide the plot and resize the
                % figure for better user experience.
                warning('off'); % Some rendering error is raising warnings. Disabled for now (BrunoO 11-09-2023)
                app.GridLayout.ColumnWidth{2} = 0; 
                warning('on');
                % Enable/Disable Buttons:
                app.UIFigure.Position(3) = 200;
                app.DeleteButton.Enable = 'off';
                bkpFiles = dir(fullfile(app.LabeoFolder, [app.filename '_*_bkp.mat']));
                if ~isempty(bkpFiles)
                    app.RecoverfrombackupButton.Enable = 'on';
                else
                    app.RecoverfrombackupButton.Enable = 'off';
                end
                app.ShowinfolderButton.Enable = 'off';
                return
            else
                app.RecoverfrombackupButton.Enable = 'on';
                app.DeleteButton.Enable = 'on';
                app.ShowinfolderButton.Enable = 'on';
            end
            
            % Set Figure Horizontal size:
            app.UIFigure.Position(3) = 660;
            
            % Set Axes titles:
            app.UIAxes.Title.String = 'Coregistration Result';
            
            % Make the plot visible by adjusting the UI layout.
            app.GridLayout.ColumnWidth{2} = '1x';
            
            % Resize the figure if its width is less than 300.
            if app.UIFigure.Position(3) < 300
                app.UIFigure.Position(3) = 680;
            end
            
            % Load the registered images from the transformation file.
            info = load(fullfile(app.tformFile.folder, app.tformFile.name));
            
            % Display the registered images in the UIAxes.
            imshowpair(info.registeredImages{2}, info.registeredImages{1}, 'falsecolor', 'Parent', app.UIAxes);
                    
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, AppMode)
            %
            app.UIFigure.Visible = 'off';
            app.DataFolder = MainApp.dummyProt.SaveDir;
            app.lookForTformFile;
            if strcmpi(AppMode, 'CoregistrationMode') && ~isempty(app.tformFile)
                MainApp.tformFile = fullfile(app.tformFile.folder,app.tformFile.name);
                app.appMode = ''; % To supress uiconfirm in CloseReq callback;
                close(app.UIFigure);
                return
            end
            app.appMode = AppMode;
            app.MainApp = MainApp;
            app.UIFigure.Visible = 'on';
            app.switchAppMode;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if strcmpi(app.appMode, 'CoregistrationMode')
                answer = uiconfirm(app.UIFigure, 'Abort creation of Geometric transformation file?','Abort process?',"Options",{'Yes','No'},'DefaultOption',2,'Icon',"question");
                if strcmpi(answer,'no')
                    return
                end
            end            
            delete(app)            
        end

        % Button pushed function: ShowinfolderButton
        function ShowinfolderButtonPushed(app, event)
            % Opens OS File managers in folder containing the tform files
            % used to coregister the data.
            
            if ispc
                winopen(app.tformFile.folder);
            elseif ismac
                system(['open "' app.tformFile.folder '"']);
            elseif isunix
                system(['xdg-open "' app.tformFile.folder '"']);
            end
            
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            % Creates a new tform file using the .dat files in the RawDir
            % from the currently opened file in DataViewer.
            % If there is already an existing file, the file will be moved
            % as backup.
            answer = 'Yes';
            
            if ~isempty(app.tformFile)
                answer = uiconfirm(app.UIFigure, 'Remove current Tform file? A copy of this file will be stored as backup.', 'Delete Tform file?',"Options",{'Yes','No'},'DefaultOption',2);
            end
            
            if ~strcmpi(answer,'yes')
                return
            end
            
            dat = load(fullfile(app.tformFile.folder, app.tformFile.name));
            % Save tform file:
            app.saveTform(dat.tform, dat.registeredImages);    
            delete(fullfile(app.tformFile.folder, app.tformFile.name))
            % Update tfom file info:
            app.lookForTformFile;
            % Update GUI and images:
            app.switchAppMode;         
        end

        % Button pushed function: RecoverfrombackupButton
        function RecoverfrombackupButtonPushed(app, event)
            % User picks one of the backup files to overwrite the current
            % tform file.
            
            % Confirm:
            if isempty(app.tformFile)
                msg = 'Create Tform file from a backup?';                
            else                
                msg = 'Replace the current Tform file with a backup?';
            end
            myPos = app.UIFigure.Position(3);
            if myPos < 300
                app.UIFigure.Position(3) = 400;
            end
            answer = uiconfirm(app.UIFigure, msg, 'Reset Tform file?', 'Options',{'Yes','No'}, 'DefaultOption',2);
            if ~strcmpi(answer,'yes')
                app.UIFigure.Position(3) = myPos;
                return
            end
            
            % Choose backup file:
            bkpFile = uigetfile([app.filename '_*_bkp.mat'],'Select backup tform file',fullfile(app.LabeoFolder,[app.filename '_bkp.mat']));
            if bkpFile == 0
                app.UIFigure.Position(3) = myPos;
                return
            end
            % Load backup data:
            try
                bkp = load(fullfile(app.LabeoFolder, bkpFile));
            catch
                uialert(app.UIFigure, ['Backup file "' bkpFile '" not found in folder "' app.LabeoFolder '"'],...
                    'Failed to load backup file','CloseFcn','uiresume(gcbf)','Icon',"error","Modal",true);
                uiwait(app.UIFigure);
                app.UIFigure.Position(3) = myPos;
                return
            end
            % Show a preview of the registration from the backup file:
            prevAns = false;
            f = uifigure('Name', 'Backup File registration preview (close fig. to continue)','CloseRequestFcn',@closeFig);
            ax =axes('Parent',f);
            imshowpair(bkp.registeredImages{2}, bkp.registeredImages{1},'falsecolor','Parent',ax);
            % Format backup file name to show backup date:
            str = regexpi(bkpFile,'coreg2cam_tform_(\d*)_bkp.mat','tokens');str = str{:}{:};
            str = ['Backup from ' datestr(datenum(str,'yyyymmddHHMMSS'), 'mmm dd yyyy (HH:MM:ss)')];
            title(ax,str);
            waitfor(f);
            if ~prevAns
                app.UIFigure.Position(3) = myPos;
                return
            end
            % Overwrite current tform file:
            save(fullfile(app.LabeoFolder,[app.filename '.mat']),'-struct','bkp');
            % Update tform lists and plot the results:
            app.lookForTformFile;
            % Update GUI and images:
            app.switchAppMode;
            figure(app.UIFigure);
            uialert(app.UIFigure, 'Reset successfull','Done', 'Icon','info', 'CloseFcn','uiresume(gcbf)');
            uiwait(app.UIFigure);
            figure(app.UIFigure)
            %% Local callbacks
            function closeFig(src,~)
                % Preview figure close request callback
                Ans = uiconfirm(src, 'Use this backup file?', 'Confirm file selection', 'Options',{'Yes','No'}, 'DefaultOption',2);
                if strcmpi(Ans,'Yes')
                    prevAns = true;
                end
                delete(src);
            end
            
        end

        % Button pushed function: RunCoregistrationButton
        function RunCoregistrationButtonPushed(app, event)
            % This callback function is triggered when the user clicks the "Run Coregistration" button
            % in the application. It performs the coregistration process between two images, calculates
            % the geometric transformation, and handles any warnings or errors.
            %            
            %
            % Description:
            %   1. Displays a progress dialog while calculating the geometric transformation.
            %   2. Calls the 'genTform2Cams' function to generate the transformation.
            %   3. Handles warnings and displays an alert if the transformation generation fails.
            %   4. Calls the 'saveTform' function to save the transformation and registered images.
            %
            % Example:
            %   RunCoregistrationButtonPushed(app, event);
            %
            % See also:
            %   genTform2Cams, saveTform
            
            % Display a progress dialog while calculating the transformation.
            w = uiprogressdlg(app.UIFigure, 'Message', 'Calculating geometric transformation...', 'Title', 'Please wait', 'Indeterminate', 'on');
            
            % Call the 'genTform2Cams' function to generate the transformation.
            [tform, registeredImages, warnmsg] = genTform2Cams(app.DataFolder, app.EnhancecontrastCheckBox.Value, app.FliphorizontallyCheckBox.Value);
            
            % Update the progress dialog and close it.
            w.Message = 'Finished with calculation';
            pause(0.8);
            delete(w);
            
            % Handle warnings and display an alert if the transformation generation fails.
            if ~isempty(warnmsg)
                uialert(app.UIFigure, warnmsg, 'Failed to generate geometric transformation file!', 'Icon', 'warning', 'CloseFcn', 'uiresume(gcbf)');
                uiwait(app.UIFigure);
                close(app.UIFigure);
                return;
            end
            
            % Save the geometric transformation.
            app.saveTform(tform, registeredImages);
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Dual Camera coregistration';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GeometrictransformationfilePanel = uipanel(app.GridLayout2);
            app.GeometrictransformationfilePanel.Title = 'Geometric transformation file';
            app.DeleteButton = uibutton(app.GeometrictransformationfilePanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Tooltip = {'Creates a backup and erases the current Geometric transformation file.'};
            app.DeleteButton.Text = 'Delete';
            app.RecoverfrombackupButton = uibutton(app.GeometrictransformationfilePanel, 'push');
            app.RecoverfrombackupButton.ButtonPushedFcn = createCallbackFcn(app, @RecoverfrombackupButtonPushed, true);
            app.RecoverfrombackupButton.Tooltip = {'Replace the curent Tform file with a backup Tform file.'};
            app.RecoverfrombackupButton.Text = {'Recover '; 'from backup'};
            app.ShowinfolderButton = uibutton(app.GeometrictransformationfilePanel, 'push');
            app.ShowinfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ShowinfolderButtonPushed, true);
            app.ShowinfolderButton.Tooltip = {'Show Tform files location.'};
            app.ShowinfolderButton.Text = 'Show in folder';
            app.RunCoregistrationButton = uibutton(app.GridLayout2, 'push');
            app.RunCoregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @RunCoregistrationButtonPushed, true);
            app.RunCoregistrationButton.Text = 'Run Coregistration';
            app.CoregistrationparametersPanel = uipanel(app.GridLayout2);
            app.CoregistrationparametersPanel.Title = 'Coregistration parameters';
            app.EnhancecontrastCheckBox = uicheckbox(app.CoregistrationparametersPanel);
            app.EnhancecontrastCheckBox.Tooltip = {'Check this box to enhance the image contrast to improve coregistration'};
            app.EnhancecontrastCheckBox.Text = 'Enhance contrast';
            app.EnhancecontrastCheckBox.Value = true;
            app.FliphorizontallyCheckBox = uicheckbox(app.CoregistrationparametersPanel);
            app.FliphorizontallyCheckBox.Tooltip = {'Check this box if the images from Camera #2 are mirrored'};
            app.FliphorizontallyCheckBox.Text = 'Flip horizontally';
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataViewer_Coreg2Cams(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end