classdef DataViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        OpenMenu                      matlab.ui.container.Menu
        ImportRawdataMenu             matlab.ui.container.Menu
        fromLabeoTechSystemMenu       matlab.ui.container.Menu
        fromTIFFMenu                  matlab.ui.container.Menu
        ExportMenu                    matlab.ui.container.Menu
        imagesMenu                    matlab.ui.container.Menu
        movieMenu                     matlab.ui.container.Menu
        datatoTIFFMenu                matlab.ui.container.Menu
        SaveasMenu                    matlab.ui.container.Menu
        BatchprocessingMenu           matlab.ui.container.Menu
        ConverttoTIFFMenu             matlab.ui.container.Menu
        RunscriptMenu                 matlab.ui.container.Menu
        menuSep2                      matlab.ui.container.Menu
        GenscriptMenu                 matlab.ui.container.Menu
        SettingsMenu                  matlab.ui.container.Menu
        ImageoptionsMenu              matlab.ui.container.Menu
        PlaybackspeedMenu             matlab.ui.container.Menu
        UtilitiesMenu                 matlab.ui.container.Menu
        DataProcessingMenu            matlab.ui.container.Menu
        ViewDataHistoryMenu           matlab.ui.container.Menu
        ShoweventtracesMenu           matlab.ui.container.Menu
        ManageeventsMenu              matlab.ui.container.Menu
        ROIoptionsMenu                matlab.ui.container.Menu
        ROItracesMenu                 matlab.ui.container.Menu
        CorrelationMatrixMenu         matlab.ui.container.Menu
        menuSep                       matlab.ui.container.Menu
        OiSDualCamCoregistrationMenu  matlab.ui.container.Menu
        ManualCoregistrationMenu      matlab.ui.container.Menu
        AddonsMenu                    matlab.ui.container.Menu
        ROImanagerMenu                matlab.ui.container.Menu
        MultiplotsMenu                matlab.ui.container.Menu
        Menu                          matlab.ui.container.Menu
        GotoWikiPageMenu              matlab.ui.container.Menu
        ReaddocumentationMenu         matlab.ui.container.Menu
        Slider                        matlab.ui.control.Slider
        TextArea                      matlab.ui.control.TextArea
        CoordinatesPanel              matlab.ui.container.Panel
        XEditFieldLabel               matlab.ui.control.Label
        XEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        YEditField                    matlab.ui.control.NumericEditField
        TEditFieldLabel               matlab.ui.control.Label
        TEditField                    matlab.ui.control.NumericEditField
        PlayButton                    matlab.ui.control.StateButton
        ViewmodeDropDownLabel         matlab.ui.control.Label
        ViewmodeDropDown              matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        
        start = false % boolean to start grabbing the mouse cursor position.
        T % timer object used by the ImageWindowButtonDown Fcn.
        T2 % timer object used to play movie.
        T3 % timer object used by WindowButtonDown Fcn of timeFig.
        text_obj % Text object used to create annotations over the image on app.spatialFig.Fig.
        mov_speed = 1 % Playback speed of movie in frames per second.
        trigFig % handle to figure with Triggers from "events.mat" file.
        tmpFile = '' % full path to a temporary file created by the StandAlone version of the app.
        %         oldpath = addpath('..\.'); % This is here so the createComponents method sees the icons in "..\". App designer calls createComponents before the startupFcn. This is a workaround of this issue.
        b_hasTrigPlot = false; % Boolean to indicate if the current data has Trigger plots.
        evntManager_App % Handle to DataViewer_evntManager.mlapp.
        freqVec % Vector of frequency values for timePlot with FFT data.
        GUIcurrState % Current state of "Enable" property of some GUI elements (USed by FFT view callback);
    end
    
    properties (Access = public, SetObservable)
        viewMode char {mustBeMember(viewMode,{'normal','image','event','fft','spcm'})} = 'normal' % Data Display mode. The viewMode value will affect the GUI elements' availability and their behaviours.
        inputFile % input File
        image_info = struct('cmap', [],'clims',[], 'clims_def',[], 'pxPermm',0, 'refPt', [0,0], 'logical_mask', []) % structure containing colormap data from image.
        timeFig = struct('Fig', [],'axT1',[], 'axT2',[]) % structure containing temporal figure parameters.
        spatialFig  = struct('Fig',[],'ax',[])% structure containing spatial figure parameters.
        phiFig = struct('Fig',[],'ax',[])% structure containing the Phase maps figure for FFT images (retinotopy for now).
        eventList % cell array with event ID (Case 2 only).
        dataInfo % structure containing input Data and metaData.
        currInfo = struct('pT', [1 1], 'sel_pT', [1 1], 'frame', 1, 'value',0, ...
            'rep', -1, 'cond', 1) % structure containing current spatiotemporal information of data
        roi_opts_params % structure containing the parameters to run "genCorrelationMatrix" function. This is only used inside the callback "GenerateCorrelationMatrixMenuSelected".
        %%%%%%%% APP HANDLES %%%%%%%%%%%
        multiPlot_App % Handle to "DataViewer_multiPlot" app.
        opts_App % Handle to imagApps_Opts.mlapp
        evnt_App % Handle to Events window app "funcImag_viz_evntWindow.mlapp
        ROImngr_App % Handle to ROImanager App.
        ROIopts_App % Handle to DataViewer_ROIopts App.
        Coreg2Cams_App % Hanfle to DataViewer_Coreg2Cams app.
        %%%%%%%%%% Some properties associated with the ROImanager App:
        %% Event listeners to catch changes in some ROImanager properties:
        el_ROImngr_ROIs % Tracks changes in the "ROI_list" property.
        el_ROImngr_imgParams % Tracks changes in the "image_info" property.
        el_ROImngr_msk % Tracks changes in the "mskObj" property.
        %
        ROI_data = struct('list', [],'spatialHandles', [],'plotHandles',[], 'mask', struct('Handle', [], 'Shape',[])) % list of ROIs created by ROImanager
        %%%%
        eventID_list % list of event IDs (used by evnt_App)
        eventName_list % list of event names (used by evnt_App)
        pipeObj % handle to PipelineManager instance.
        dummyProt % Protocol instance to use with PipelineManager
        tformFile = '' % Path to tform file (for DualCam Systems)
    end
    
    events
        updateMultiPlot
    end
    
    methods (Access = private)
        
        function b_isOK = loadData(app)
            % LOADDATA verifies if the inputFile contains a valid Imaging
            % dataset. If yes, it loads the data in the "dataInfo"
            % property.
            % Output:
            % b_isOK (bool): True if the data was loaded.
            
            b_isOK = false;
            % If no input is provided end function and let User select a file:
            
            [~,file,~] = fileparts(app.inputFile);
            if ~strcmpi(file, '_DATA_')
                app.inputFile = [file '.dat']; % Force input file to be a .DAT file.
            end
            
            % Access data from input file:
            if strcmpi(file,'_DATA_')
                % Output from PipelineManager This means that the data is already in RAM.
                mDat  = struct('Data', struct('data', app.pipeObj.current_data)); % Create "fake" map file to adapt to the rest of the code.
                metaDat = app.pipeObj.current_metaData; % Idem
            elseif ~isfile(fullfile(app.dummyProt.SaveDir,app.inputFile))
                app.UIFigure.Visible = 'off';
                err = errordlg({'Wrong data file! This app only reads valid .DAT file.', 'See documentation for details.'}, 'Invalid input file',...
                    'modal');
                waitfor(err)
                app.inputFile = ''; % Erase wrong inupt file.
                return
            else
                [mDat, metaDat] = mapDat(fullfile(app.dummyProt.SaveDir, app.inputFile));
            end
            % For retrocompatibility:
            if isfield(metaDat,'Freq') || isprop(metaDat, 'Freq')
                metaDat.FrameRateHz = metaDat.Freq;
            end
                                    
            % Populate dataInfo structure with Data and MetaData memory maps, dimension indexes and names:
            app.dataInfo.Map = mDat;
            app.dataInfo.MetaMap = metaDat;
            app.dataInfo.data = app.dataInfo.Map.Data.data;
            % Set viewMode to "normal" so the "setup" function will set the
            % GUI elements and behaviour accordingly:
            app.viewMode = 'normal';
            
            % In cases where dimensions are {'Y', 'X'}:
            if size(app.dataInfo.data,3) == 1
                % Set view mode to "image":
                app.viewMode = 'image';                
                % Create a dummy 3-D matrix, so "data" is always a 3-D
                % matrix:
                app.dataInfo.data = repmat(app.dataInfo.data,1,1,2);                              
            end
            % Fields used with "events":
            app.dataInfo.avg = [];
            app.dataInfo.SEM = [];
            
            % Add file path to prompt label:
            app.TextArea.Value = ['Opening File : ' app.inputFile];
            
            % Reset current info structure:
            app.currInfo = struct('pT', [1 1], 'sel_pT', [1 1], 'frame', 1, 'value',0, ...
                'rep', 1, 'cond', 1);
            
            % Check if the data comes from a Dual-Camera imaging system
            % (LabeoTech):
            app.OiSDualCamCoregistrationMenu.Enable = 'off';
            if app.dataInfo.MetaMap.MultiCam
                app.OiSDualCamCoregistrationMenu.Enable = 'on';
            end
            % Disable Manual Alignment tool if the data is not saved as a
            % .dat file:
            if strcmpi(app.inputFile,'_DATA_')
                app.ManualCoregistrationMenu.Enable = 'off';
            end
            
            % Set Output variable to TRUE:
            b_isOK = true;
            
        end
        
        function DoNotCloseFig(~,~,~)
            % EMPTY CALLBACK TO AVOID USER CLOSING SOME FIGURES %
        end
        
        %         function loadData(app)
        %             % LOADDATA loads data into app.dataInfo.data. Here, "data" will store only
        %             % 3D numerical matrices.
        % %             w = uiprogressdlg(app.UIFigure, 'Message','Loading data... Please wait.','Title','','Indeterminate',"on");
        %             % In cases where dimensions are {'Y', 'X', 'T'}:
        %             app.dataInfo.data = app.dataInfo.Map.Data.data;
        %             % In cases where dimensions are {'Y', 'X'}:
        %             if size(app.dataInfo.data,3) == 1
        %                 % Create a dummy 3-D matrix, so "data" is always a 3-D
        %                 % matrix:
        %                 app.dataInfo.data = repmat(app.dataInfo.data,1,1,2);
        %             end
        %                 case 2
        %                     % In case where dimensions are {'Y', 'X', 'T', 'E'}:
        %
        %                     app.loadEvents();
        %                     %
        %                     app.currInfo.cond = app.evnt_App.ConditionDropDown.Value;
        %                     app.currInfo.rep = app.evnt_App.RepetitionDropDown.Value;
        %                     % Find condition and repetition indices
        %                     all_reps_IDs = setdiff(app.evnt_App.RepetitionDropDown.ItemsData,[-1,0]);
        %                     % Get data snippet:
        %                     rep_ID = app.currInfo.rep;
        %                     if  rep_ID == -1  % Here, an index of -1 means the #Average.
        %
        %                         if isempty(app.dataInfo.avg)
        %
        %                             % If the average was not calculated yet, do it and save it to "avg":
        %                             if strcmpi(app.inputFile, '_DATA_')
        %                                 % For data available in RAM:
        %                                 app.dataInfo.data = app.dataInfo.Map.Data.data;
        %                             else
        %                                 % For data saved in Hard drive:
        %                                 % SPECIAL CASE: Here, we will load the whole data into RAM instead of
        %                                 % using memmapfile to be faster.
        %                                 app.dataInfo.data = [];
        %                                 app.dataInfo.Map = []; % Erase memmap pointer.
        %                                 % Load the whole data in RAM:
        %                                 app.dataInfo.data = loadDatFile(fullfile(app.dummyProt.SaveDir,app.inputFile));
        %                                 % Recreate Memmaps:
        %                                 app.dataInfo.Map = mapDatFile(fullfile(app.dummyProt.SaveDir,app.inputFile));
        %                             end
        %                             w.Message = 'Calculating average of events... Please wait.';
        %                             if ~isequaln(app.dataInfo.dim_idx, [1,2,3,4])
        %                                 app.dataInfo.data = permute(app.dataInfo.data, app.dataInfo.dim_idx);
        %                             end
        %                             nChunks = calculateMaxChunkSize(app.dataInfo.data,5);% Use 3 times the size of data in the available RAM.
        %                             idxChk = round(linspace(1,size(app.dataInfo.data,1), nChunks));
        %                             app.dataInfo.SEM = zeros([size(app.dataInfo.data,1), size(app.dataInfo.data,2), size(app.dataInfo.data,3)], app.dataInfo.MetaMap.Datatype);
        %                             app.dataInfo.avg= app.dataInfo.SEM;
        %                             if nChunks > 1
        %                                 for ii = 2:length(idxChk)
        %                                     app.dataInfo.avg(idxChk(ii-1):idxChk(ii),:,:) = squeeze(mean(app.dataInfo.data(idxChk(ii-1):idxChk(ii),:,:,all_reps_IDs), 4, 'omitnan'));
        %                                     % Calculate SEM data:
        %                                     app.dataInfo.SEM(idxChk(ii-1):idxChk(ii),:,:) = squeeze(std(app.dataInfo.data(idxChk(ii-1):idxChk(ii),:,:,all_reps_IDs),0,4,'omitnan')./sqrt(length(all_reps_IDs)));
        %                                 end
        %                             else
        %                                 app.dataInfo.avg = squeeze(mean(app.dataInfo.data(:,:,:,all_reps_IDs),4,'omitnan'));
        %                                 app.dataInfo.SEM = squeeze(std(app.dataInfo.data(:,:,:,all_reps_IDs),0,4,'omitnan')./sqrt(length(all_reps_IDs)));
        %                             end
        %                         end
        %                         % Copy average to data. This exists to make a faster transition between the visualization of single reps and the average without recalculating
        %                         % it.
        %                         app.dataInfo.data = app.dataInfo.avg;
        %                     else
        % %                         Store repetition data: Here, instead of permuting the whole data, we find where the "E"vent dimension is.
        %                         dimStr = strjoin(app.dataInfo.MetaMap.dim_names, ',');
        %                         dimStr = strrep(dimStr,'E', 'rep_ID'); dimStr(cellfun(@(a) strfind(dimStr,a), {'Y','X','T'})) = ':';
        %                         eval(['app.dataInfo.data = squeeze(app.dataInfo.Map.Data.data(' dimStr '));'])
        %             end
        %
        %             close(w);
        %         end
        
        function b_in_bounds = getBounds(app)
            % GETBOUNDS verifies if the current position of the mouse
            % cursor (stored in "app.currInfo.pT") is inside the boundaries
            % of the axis "UIAxes".
            % Output:
            % b_in_bounds (bool) : true, if cursor is inside the image,
            % false, if is not.
            
            % get axis limits:
            x_lims = get(app.spatialFig.ax, 'XLim');
            y_lims = get(app.spatialFig.ax, 'YLim');
            
            % Check if cursor is inside the axis limits:
            b_in_bounds = (app.currInfo.pT(1) >= x_lims(1)) && ...
                (app.currInfo.pT(1) <= x_lims(2)) && ...
                (app.currInfo.pT(2) >= y_lims(1)) && ...
                (app.currInfo.pT(2) <= y_lims(2));
        end
        
        function displayInfo(app)
            % DISPLAYINFO shows File info as well as the experiment type
            % on a text box in the main window. In addition, this function
            % updates the content of any datatip that is shown in the
            % "Image Window".
            
            txt = {};
            if startsWith(app.inputFile, '_DATA_')
                filePath = 'Data in RAM (not saved yet)';
            else
                filePath = fullfile(app.dummyProt.SaveDir,app.inputFile);
            end
            txt{1} = ['File: ' filePath];
            app.TextArea.Value = txt;
            % Update X,Y,T coodinate fields
            app.XEditField.Value = app.currInfo.pT(1);
            app.YEditField.Value = app.currInfo.pT(2);
            app.TEditField.Value = app.currInfo.frame;
        end
        
        function timeFigButtonDown_callback(app,src,~)
            % TIMEFIGBUTTONDOWN_CALLBACK is a callback of timeFig.axT1.
            % When User double-clicks on the plot, it picks
            %   the closest point to the cursor and shifts the frame in the main figure to the
            %   clicked point in time.
            
            
            % Get click type:
            click = get(src, 'SelectionType');
            coords = get(src.Children(1), 'CurrentPoint');
            
            % If is a double-click, update frame and plot:
            if strcmp(click, 'open')
                x_pos = round(coords(1,1));
                if x_pos < 1
                    x_pos = 1;
                elseif x_pos > size(app.dataInfo.data,3)
                    x_pos = size(app.dataInfo.data,3);
                end
                app.spatialFig.ax.Title.String = ['moving to Frame # ' num2str(x_pos)]; drawnow;
                % Store new current frame index:
                app.currInfo.frame = x_pos;
                % Update Image:
                app.updateImage;
                % Update slider position:
                app.Slider.Value = x_pos;
                % Update T(ime) value:
                app.TEditField.Value = x_pos;
                % Update timebar position:
                h = findobj(app.timeFig.Fig, 'Tag', 'TimeBar');
                h.XData = [x_pos x_pos];
                h.YData = app.timeFig.axT1.YLim;
                %             elseif strcmp(click, 'normal')
                %                 % Show DataTips if user holds button down for more than 300 ms:
                %                 app.T3 = timer('ExecutionMode', 'singleShot', 'StartDelay', .3);
                %                 app.T3.TimerFcn= @EnableDataTip;
                %                 app.T3.start;
            elseif strcmpi(click, 'alt')
                % Toggle Visibility of crossHair traces on time figure:
                boolMap = containers.Map({'on','off'},{'off','on'});
                chTrace = findall(app.timeFig.axT1, 'Tag','sigLine');
                chShade = findall(app.timeFig.axT1, 'Tag','sigShade');
                set(chTrace, 'Visible', boolMap(chTrace.Visible));
                set(chShade, 'Visible', boolMap(chShade.Visible));
                % Do the same in Image:
                chImg = findall(app.spatialFig.ax, 'Tag','selPixMarker');
                arrayfun(@(x) set(x, 'Visible',boolMap(x.Visible)), chImg);
            end
            %             function EnableDataTip(~,~)
            %                 app.timeFig.axT1.Title.String = 'Signal Amplitude (Data Cursor Mode ON!)';
            %                 app.timeFig.dcm_obj.Enable = 'on';
            %             end
        end
        
        function txt = showTimeDataTip(app,~,evnt)
            % UpdateFcn callback of datacursormode object stored in
            % app.timeFig.dcm_obj.
            
            % Get event bar:
            
            % Get Position
            pos = get(evnt,'Position');
            evntBar.XData = [0 0]; %%%% FOR TESTING
            tmVal = pos(1)/app.dataInfo.MetaMap.FrameRateHz;
            tmLab = 'Time (sec)';
            tm_evntVal = tmVal - evntBar.XData(1)/app.dataInfo.MetaMap.FrameRateHz;
           
            % Get Color of line
            if isa(evnt.Target, 'matlab.graphics.primitive.Patch')
                tag = ['SEM - ' evnt.Target.UserData];
                color = evnt.Target.FaceColor;
            else
                tag = evnt.Target.UserData;
                color = evnt.Target.Color;
            end
            
            
            % Get Name of line:
            
            txt = sprintf(' \\color[rgb]{%0.3f %0.3f %0.3f}\\bf', color(1), color(2), color(3));
%             if app.expType == 6
%                 % For FFT data
%                 txt = {[txt tag], ['Frequency: ' num2str(app.freqVec(pos(1))) ' Hz'], ['|Pow(f)|: ' num2str(pos(2))]};
%                 return
%             end
            switch evnt.Target.Tag
                case 'EvntBar'
                    tag = 'Event';
                    txt = {[txt tag], [tmLab ' : ' num2str(tmVal)]};
                case 'TimeBar'
                    tag = 'Current Frame';
                    txt = {[txt tag], [tmLab ' : ' num2str(tmVal)]};
                otherwise
                    txt = {[txt tag], [tmLab ' : ' num2str(tmVal)], ['Amplitude : ' num2str(pos(2))]};
            end
            %             txt = {[txt tag], [tmLab ' : ' num2str(tmVal)], ['Amplitude : ' num2str(pos(2))]};
            
            % Add event-related info, if applicable:
            if ~isempty(app.evnt_App)
                switch evnt.Target.Tag
                    case 'EvntBar'
                        % Do nothing
                    case 'TimeBar'
                        txt = [txt {[tmLab ' from event' ' : ' num2str(tm_evntVal)]}];
                    otherwise
                        preEvnt_median = median(evnt.Target.YData(1:evntBar.XData(1)), 'omitnan');
                        txt = [txt {[tmLab ' from event' ' : ' num2str(tm_evntVal)], ['Amplitude - median_{pre-event} : ' num2str(pos(2) - preEvnt_median)]}];
                end
            end
            % Avoid subscripting characters after "underscore":
            txt = strrep(txt, '_', '\_');
        end
        
        function showHideROIhandles(app, ~, evnt)
            % This callback enables/disables the interactions with the ROIs
            % displayed over the image so the user can create datatips
            % inside the polyshapes.
            
            if  isempty(app.ROI_data) || isempty(app.ROI_data.list) || isempty(app.ROI_data.list(1).Name)
                return
            end
            % Toggle handle visibility of ROIs:
            if any(arrayfun(@(x) ~isvalid(x), app.ROI_data.spatialHandles))
                return
            end
            if strcmp(evnt.AffectedObject.Enable, 'on')
                arrayfun(@(x) set(x, 'PickableParts', 'none'), app.ROI_data.spatialHandles)
            else
                arrayfun(@(x) set(x, 'PickableParts', 'Visible'), app.ROI_data.spatialHandles)
            end
        end
        
        function txt = showSpatialDataTip(app,~,evnt)
            % UpdateFcn callback of datacursormode object stored in
            % app.spatialFig.dcm_obj.
            
            % Get Position
            pos = round(get(evnt,'Position'));
            txt = {'Pixel:'};
            % Find out which axis was clicked:
            if isa(evnt, 'matlab.graphics.shape.internal.PointDataTip')
                axH = get(evnt, 'Parent');
            else
                axH = ancestor(evnt.Target,'Axes');    
            end
            
            if strcmpi(axH.Tag, 'MainAx')
                imgH = app.spatialFig.img;
            else
                imgH = app.spatialFig.img2;
            end
            
            if app.image_info.pxPermm > 0
                txt = [txt, {['X(mm) = ' num2str(pos(1)/app.image_info.pxPermm)], ['Y(mm) = ' num2str(pos(2)/app.image_info.pxPermm)], ...
                    ['Frame # ' num2str(app.currInfo.frame)]}];
            else
                txt = [txt, {['X(px) = ' num2str(pos(1))], ['Y(px) = ' num2str(pos(2))], ...
                    ['Frame # ' num2str(app.currInfo.frame)]}];
            end
            txt = [txt,{['Value = ' num2str(imgH.CData(pos(2), pos(1)))]}];
            if isempty(app.ROI_data) || isempty(app.ROI_data.list) || isempty(app.ROI_data.list(1).Name)
                return
            end
            
            % Find first ROI that contains the selected pixel:
            indx = find(arrayfun(@(x) x.Stats.ROI_binary_mask(pos(2), pos(1)), app.ROI_data.list),1,'first');
            if isempty(indx)
                return
            end
            % Calculate spatial average value of ROI:
            avgData = mean(imgH.CData(app.ROI_data.list(indx).Stats.ROI_binary_mask), 'all');
            % Add info to datatip:
            col_code = sprintf(' \\color[rgb]{%0.3f %0.3f %0.3f}\\bf', app.ROI_data.list(indx).Color(1), app.ROI_data.list(indx).Color(2),...
                app.ROI_data.list(indx).Color(3));
            txt = [txt,{col_code, ['ROI name = ' app.ROI_data.list(indx).Name], ['Spatial Avg = ' num2str(avgData)]}];
            % Avoid subscripting characters after "underscore":
            txt = strrep(txt, '_', '\_');
        end
        
        function loadEvents(app)
            % LOADEVENTS updates the GUI with the event information.
            % In addition, it saves the event list to property "eventList"
            % for later use.
            
            % Check if metaData has "eventID" field. If not, close the app:
            if isstruct(app.dataInfo.MetaMap)
                fn = fieldnames(app.dataInfo.MetaMap);
            else
                fn = properties(app.dataInfo.MetaMap);
            end
            if ~any(strcmpi('eventID',fn))
                uiwait(errordlg('Can''t find event list in Meta Data file!', 'Error!', 'modal'))
                app.CloseRequest_callback;
            end
            % Save "event list" to "eventList" property:
            app.eventID_list = app.dataInfo.MetaMap.eventID;
            app.eventName_list = app.dataInfo.MetaMap.eventNameList;
            
            % Open events window:
            if isempty(app.evnt_App) || ~isvalid(app.evnt_App)
                delete(app.evnt_App)
                app.evnt_App = DataViewer_evntWindow(app);
            else
                app.evnt_App.updateLists;
            end
            
        end
        
        function flag = isMultipleCall(~)
            % ISMULTIPLECALL detects multiple calls to a given function
            % (where it is called from) in order to avoid reentrancy
            % problems (e.g. when a callback is called multiple times
            % before finishing the execution of the first one, this
            % function detects it and the callback execution can be suspended until the first one is finished).
            
            flag = false;
            % Get the stack
            s = dbstack();
            if numel(s) <= 2
                % Stack too short for a multiple call
                return
            end
            % How many calls to the calling function are in the stack?
            names = {s(:).name};
            TF = strcmp(s(2).name,names);
            count = sum(TF);
            if count>1
                % More than 1 == multiple calls!
                flag = true;
            end
        end
        
        function setup(app)
            % This function initializes axes, GUI elements and callbacks depending on the input data features and the app's state.
            
            % Hide all figures:
            app.spatialFig.Fig.Visible = 'on'; %%%% FOR TESTING
            app.timeFig.Fig.Visible = 'on';
            % Initiate setup process
            w = uiprogressdlg(app.UIFigure, 'Value',0,'Message','Setting up plots...', 'Indeterminate',"on");
            % Set Basic Image window graphical components:
            imgSz = [size(app.dataInfo.data,2) size(app.dataInfo.data,1)];
            %
            set(app.spatialFig.ax,'XLim',[1 imgSz(1)],'YLim',[1 imgSz(2)]);
            set(app.spatialFig.ax2,'XLim',[1 imgSz(1)],'YLim',[1 imgSz(2)]);
            % Set image CLims:
            %   Sample up to 2500 points in middle half of the image across 100 frames to get an acceptable CLim without loading the whole array:
            midRangeY = unique(round(linspace(imgSz(1)/4,imgSz(1) - imgSz(1)/4,50)));
            midRangeX = unique(round(linspace(imgSz(2)/4,imgSz(2) - imgSz(2)/4,50)));
            rangeZ = unique(round(linspace(1,size(app.dataInfo.data,3),100)));
            app.image_info.clims_def = [min(app.dataInfo.data(midRangeX, midRangeY,rangeZ),[],'all','omitnan') max(app.dataInfo.data(midRangeX, midRangeY,rangeZ),[],'all','omitnan')];
            
            % Set default colormap:
            set(app.spatialFig.ax,'CLim',app.image_info.clims_def,'Colormap',parula);
           
            % Set crosshair position:
            
            % Axis 1:
            set(app.spatialFig.cross(1), 'XData',app.currInfo.pT,'YData',app.spatialFig.ax.YLim);
            set(app.spatialFig.cross(2), 'XData',app.spatialFig.ax.YLim,'YData',app.currInfo.pT);
            % Axis 2:
            set(app.spatialFig.cross2(1), 'XData',app.currInfo.pT,'YData',app.spatialFig.ax.YLim);
            set(app.spatialFig.cross2(2), 'XData',app.spatialFig.ax.YLim,'YData',app.currInfo.pT);
            % By default, hide Axis 2 and all of its contents:            
            app.spatialFig.ax2.Visible = 'off';
            arrayfun(@(x) set(x, 'Visible','off'), app.spatialFig.ax2.Children);
            %
            app.spatialFig.ax.XLabel.String = 'X (pixels)';
            app.spatialFig.ax.YLabel.String = 'Y (pixels)';
            % Reset Frame position:
            app.Slider.Value = 1;
            app.TEditField.Value = 1;
            % Set components' parameters and behaviours depending on the current view mode:
            switch app.viewMode
                case 'normal'
                    % Main GUI:
                    %   Set Slider:
                    set(app.Slider, ...                       
                        'Limits',[1 size(app.dataInfo.data,3)],...
                        'MajorTicks',unique(round(linspace(1,size(app.dataInfo.data,3),20))),...
                        'MajorTickLabels',{});                    
                    %   Set default movie playback speed:
                    app.mov_speed = app.dataInfo.MetaMap.FrameRateHz;
                    %   Change coordinate letter "T" to "F":
                    app.TEditFieldLabel.Text = 'T';
                    % Image window:
                    % Hide secondary axis and all of its contents:
                    app.spatialFig.ax2.Visible = 'off';
                    arrayfun(@(x) set(x, 'Visible','off'), app.spatialFig.ax2.Children)                    
                    % Reposition main axis:
                    set(app.spatialFig.ax, 'Position',[0.12  0.11 0.7157 0.815]);
                    % Time plot:
                    %    axT1:
                    set(app.timeFig.axT1,'XLim',[1 size(app.dataInfo.data,3)],'YLim',app.image_info.clims_def)
                    set(app.timeFig.axT1.XLabel, 'String', 'time (sec)');
                    
                    %   Show Time bar:
                    set(app.timeFig.timeBar,'XData',[app.currInfo.frame app.currInfo.frame],'YData',app.timeFig.axT1.YLim,'Visible','on');
                    %   Hide Event patch
                    set(app.timeFig.evShade,'Visible','off');
                    %   Hide Crosshair patch:
                    set(app.timeFig.sigShade,'Visible','off');
                    %   Pan behaviour:                    
                    set(pan(app.timeFig.Fig),'ActionPostCallback',@updateXaxis);
                    %   Zoom behaviour:
                    set(zoom(app.timeFig.Fig), 'ActionPostCallback',@updateXaxis);
                    %   Restore view  behaviour:
                    axToolstrip = axtoolbar(app.timeFig.axT1, 'default');
                    restoreButtonHandle = axToolstrip.Children(strcmpi({axToolstrip.Children.Icon}, 'restoreview'));
                    restoreButtonHandle.ButtonPushedFcn = @restoreAx;
                                                          
                case 'image'
                    %%% Main axis CLims already set.   
                    
                case 'event'
                    
                case 'fft'                    
                    % Calculate FFT 
                    w.Message = 'Calculating FFT...';                    
                    app.dataInfo.data = fft(app.dataInfo.data,[],3);
                    app.freqVec = (0:size(app.dataInfo.data,3)/2-1)*(app.dataInfo.MetaMap.FrameRateHz/size(app.dataInfo.data,3));
                    
                    % Main GUI:
                    %   Set Slider:
                    % Reset Slider position to 1, set up to 20 ticks
                    % without numbers:
                    set(app.Slider,...                        
                        'Limits',[1 size(app.dataInfo.data,3)/2],...
                        'MajorTicks',unique(round(linspace(1,size(app.dataInfo.data,3)/2,20))),...
                        'MajorTickLabels',{});
                    app.Slider.MajorTicks = unique(round(linspace(1,size(app.dataInfo.data,3)/2,20))); 
                    app.Slider.MajorTickLabels = {}; % Remove labels
                    
                    % Image window:
                    %   Show Main and secondary axes:
                    %       Set main Axis properties (Amplitude Map):                    
                    set(app.spatialFig.ax,...
                        'Position',[0.12 .11 0.3 0.815],...
                        'CLim',app.image_info.clims,...
                        'Colormap',app.image_info.cmap);
                    app.spatialFig.ax.Title.String = '|Pow|';                    
                    
                    %       Set secondary Axis properties (Phase Map):                    
                    app.spatialFig.ax2.Visible = 'on'; % Show axis 2
                    arrayfun(@(x) set(x, 'Visible','on'), app.spatialFig.ax2.Children); % Show all content from axis 2
                    set(app.spatialFig.ax2,...
                        'Position',[0.6 .11 0.3 0.815],...
                        'CLim',[0 2*pi],...
                        'Colormap',hsv);
                    app.spatialFig.ax2.Title.String = 'Phase';                    
                    
                    %   Change coordinate letter "T" to "F":
                    app.TEditFieldLabel.Text = 'F';
                    
                    % Frequency plot:
                    %    axT1:
                    set(app.timeFig.axT1.XLabel, 'String', 'Frequency (Hz)');                    
                    
                    %   Hide Event patch
                    set(app.timeFig.evShade,'Visible','off');
                    %   Hide Crosshair patch:
                    set(app.timeFig.sigShade,'Visible','off');
                    
                    
                case 'spcm'
                    
                otherwise
                    error('Unknown view mode!')
            end
            
            
            w.Message = 'Finishing up...';
            % Update Image Info structure:
            app.image_info.imageData = app.spatialFig.img.CData;
            app.image_info.clims = app.image_info.clims_def;
            app.image_info.cmap = app.spatialFig.ax.Colormap;
            % Show First Frame:
            app.updateImage;
            
            
            %             % If the data metaData file has a "Freq" variable,
            %             % transform the X axis units to "seconds":
            %             if (isprop(app.dataInfo.MetaMap, 'FrameRateHz') || isfield(app.dataInfo.MetaMap, 'FrameRateHz'))
            %                 if app.dataInfo.MetaMap.FrameRateHz > 0
            %                     updateXaxis;
            %                     % Update XTickLabels when zooming or panning:
            %                     set(pan(app.timeFig.Fig),'ActionPostCallback',@updateXaxis);
            %                     % Get the toolbar axes
            %                     axToolstrip = axtoolbar(app.timeFig.axT1, 'default');
            %                     % Get the restore view button and reset the callback function
            %                     isRestoreButton = strcmpi({axToolstrip.Children.Icon}, 'restoreview');
            %                     restoreButtonHandle = axToolstrip.Children(isRestoreButton);
            %                     restoreButtonHandle.ButtonPushedFcn = @restoreAx;
            %                     if app.expType == 6
            %                         set(zoom(app.timeFig.Fig),'ActionPostCallback',@updateXaxis);
            %                         set(app.timeFig.axT1.XLabel, 'String', 'Frequency (Hz)')
            %                     else
            %                         set(zoom(app.timeFig.Fig),'ActionPostCallback',@updateClims);
            %                         % Set Xlabel:
            %                         set(app.timeFig.axT1.XLabel, 'String', 'time (sec)');
            %                     end
            %                 end
            %             else
            %                 set(app.timeFig.axT1.XLabel, 'String', 'Frame');
            %             end
            
            % Set accessibility of GUI elements depending on the viewMode:
            boolMap = containers.Map([false,true],{'off','on'});
            
            UIelements = {...
                app.PlaybackspeedMenu, 'Enable';...
                app.PlayButton, 'Visible';...
                app.movieMenu, 'Enable';...
                app.Slider, 'Visible';...
                app.TEditField, 'Enable';...
                app.timeFig.Fig, 'Visible';...
                app.DataProcessingMenu, 'Enable';...
                app.MultiplotsMenu, 'Enable';...
%                 app.ShoweventtracesMenu, 'Enable';...
%                 app.ManageeventsMenu, 'Enable';...
                app.ROIoptionsMenu, 'Enable';...
                app.datatoTIFFMenu, 'Enable';...
                app.imagesMenu, 'Enable';...
                app.SaveasMenu 'Enable';...
                app.ViewmodeDropDown, 'Enable'...
                };
            switch app.viewMode
                case 'normal'
                    %   Hide secondary axis and colorbar
                    app.spatialFig.ax2.Visible = 'off';
                    app.spatialFig.cb2.Visible = 'off';
                    GUIsetMap = {...
                        true;...% PlaybackspeedMenu
                        true;...% PlayButton
                        true;...% movieMenu
                        true;...% Slider
                        true;...% TEditField
                        true;...% timeFig.Fig
                        true;...% DataProcessingMenu
                        true;...% MultiplotsMenu
%                         true;...% ShoweventtracesMenu
%                         true;...% ManageeventsMenu
                        true;...% ROIoptionsMenu
                        true;...% datatoTIFFMenu
                        true;...% imagesMenu
                        true;...% SaveasMenu
                        true;...% ViewModeDropDown
                        };
                case 'image'   
                    
                    GUIsetMap = {...
                        false;...% PlaybackspeedMenu
                        false;...% PlayButton
                        false;...% movieMenu
                        false;...% Slider
                        false;...% TEditField
                        false;...% timeFig.Fig
                        true;...% DataProcessingMenu
                        false;...% MultiplotsMenu
                        %                         true;...% ShoweventtracesMenu
                        %                         true;...% ManageeventsMenu
                        true;...% ROIoptionsMenu
                        true;...% datatoTIFFMenu
                        true;...% imagesMenu
                        true;...% SaveasMenu
                        false;...% ViewModeDropDown
                        };
                case 'event'
                    %                     set(evntLine, 'XData', repmat(round(app.dataInfo.MetaMap.FrameRateHz*app.dataInfo.MetaMap.preEventTime_sec),2,1), ...
                    %                         'YData', app.timeFig.axT1.YLim, 'Visible', 'on');
                    %                     set(app.timeFig.axT1, 'XTickLabel',...
%                         num2cell((app.timeFig.axT1.XTick./app.dataInfo.MetaMap.FrameRateHz) - app.dataInfo.MetaMap.preEventTime_sec));
%                     GUIsetMap([8,9,15]) = {false,false,false}; % Disable FFT viewer for data split by events.
                case 'fft'
                     %   Show secondary axis and colorbar
                    app.spatialFig.ax2.Visible = 'on';
                    app.spatialFig.cb2.Visible = 'on';
                    
                    GUIsetMap = {...
                        false;...% PlaybackspeedMenu
                        false;...% PlayButton
                        false;...% movieMenu
                        true;...% Slider
                        true;...% TEditField
                        true;...% timeFig.Fig
                        false;...% DataProcessingMenu
                        false;...% MultiplotsMenu
                        %                         true;...% ShoweventtracesMenu
                        %                         true;...% ManageeventsMenu
                        true;...% ROIoptionsMenu
                        false;...% datatoTIFFMenu
                        false;...% imagesMenu
                        false;...% SaveasMenu
                        true;...% ViewModeDropDown
                        };
                case 'spcm'
                    
                otherwise
                    error('Unknown view mode!')
            end
            
            % Toggle UI elements' accessibility:
            cellfun(@(x,y,z) set(x,y,boolMap(z)),UIelements(:,1),UIelements(:,2),GUIsetMap);
                                         
            % Update time plot:
            app.updateTimeFig;
            app.updateCrossHairPos;
            restoreAx;
            % Update info in main GUI:
            app.displayInfo;
            %             % Check if the input data contains "dataHistory":
            %             app.ViewDataHistoryMenu.Enable = boolMap(isprop(app.dataInfo.MetaMap, 'dataHistory') | isfield(app.dataInfo.MetaMap, 'dataHistory'));
            
            %             if isempty(app.ROI_data(1).list)
            %                 % Disable ROI options when no ROIs exist:
            %                 app.ROIoptionsMenu.Enable = 'off';
            %             end
            app.spatialFig.Fig.Visible = 'on';
            figure(app.UIFigure);
            close(w)
            
            %%%%%%%%%%%%%%%%%%%%-------------------------------------------------------
            % Custom callback for time figure:
            function restoreAx(~,~)
                % Custom callback for the "Restore View" button in timeFig.                
                if ~strcmpi(app.viewMode,'fft')
                    set(app.timeFig.axT1, 'XLim',[1 size(app.dataInfo.data,3)],'YLim',[app.image_info.clims]);
                    
                else
                    % Set FFT XLim to 1/2 of data length and YLim as 2 times the maximum frequency power exept the DC.
                    set(app.timeFig.axT1, 'YLim',[0, max(app.timeFig.sigLine.YData(2:end))],...
                        'XLim',[0 size(app.dataInfo.data,3)/2]);                  
                end
                                
                % Update X axis:
                updateXaxis
            end
           
            function updateXaxis(~,~)
                % Custom callback for the pan object to update the xtick
                % values. Additionally, it updates the YLim of some axis
                % elements.
                
                % Round xtick values:
                rVals = linspace(app.timeFig.axT1.XLim(1), app.timeFig.axT1.XLim(2),8);
                
                % Check if the rounding broke the xtick vector, if so, we
                % recalculate the xtick values without rounding.
                if any(diff(round(rVals/app.dataInfo.MetaMap.FrameRateHz,1)) <= 0) && ~any(diff(round(rVals/app.dataInfo.MetaMap.FrameRateHz,3)) <= 0)
                    rVals = round(rVals/app.dataInfo.MetaMap.FrameRateHz,3);
                elseif ~any(diff(round(rVals/app.dataInfo.MetaMap.FrameRateHz,1)) <= 0)
                    rVals = round(rVals/app.dataInfo.MetaMap.FrameRateHz,1);
                else
                    rVals = rVals/app.dataInfo.MetaMap.FrameRateHz;
                end
                rVals([1 end]) = [];
                
                % Calculate Xtick values and labels:
                newXtk = rVals*app.dataInfo.MetaMap.FrameRateHz; % Recreate xticks based on rounded xtick labels
                if strcmpi(app.viewMode, 'event')
                    rVals = rVals - double(app.dataInfo.MetaMap.preEventTime_sec);
                elseif strcmpi(app.viewMode,'fft')                    
                    newXtk= round(linspace(app.timeFig.axT1.XLim(1), app.timeFig.axT1.XLim(2),8))+1;
                    if any(diff(newXtk)<=0)
                        newXtk = [floor(app.timeFig.axT1.XLim(1)):round(app.timeFig.axT1.XLim(2))]+1;
                    end
                    newXtk(newXtk < 0 | newXtk > length(app.freqVec)) = [];
                    rVals = round(app.freqVec(newXtk),3);
                end
                % Set new XTicks:
                set(app.timeFig.axT1,'XTick',newXtk,'XTickLabel',num2cell(rVals));
                
                % Update YLim of Axis elements:
                %   TimeBar:
                set(app.timeFig.timeBar,'YData',app.timeFig.axT1.YLim);
                                               
            end
            
        end
        
        function updateCrossHairPos(app,varargin)
            % Changes the position of the crosshair and updates the plots
            % Optional input:
            % [X,Y] coordinates.
            
            if nargin == 2
                app.currInfo.pT = varargin{:};
            end
            % Find current point coordinates and shift crosshair (Main Axis):
            app.spatialFig.cross(1).XData = app.spatialFig.ax.XLim;
            app.spatialFig.cross(1).YData = [app.currInfo.pT(2) app.currInfo.pT(2)];
            app.spatialFig.cross(2).YData = app.spatialFig.ax.YLim;
            app.spatialFig.cross(2).XData =  [app.currInfo.pT(1) app.currInfo.pT(1)];
            % Do the same for the secondary axis:
            app.spatialFig.cross2(1).XData = app.spatialFig.ax.XLim;
            app.spatialFig.cross2(1).YData = [app.currInfo.pT(2) app.currInfo.pT(2)];
            app.spatialFig.cross2(2).YData = app.spatialFig.ax.YLim;
            app.spatialFig.cross2(2).XData =  [app.currInfo.pT(1) app.currInfo.pT(1)];
            % Update selected point value:
            app.currInfo.sel_pT = app.currInfo.pT;
            % Update Time figure:
            app.updateTimeFig;
            % Update X,Y coordinate fields:
            app.XEditField.Value = app.currInfo.sel_pT(1);
            app.YEditField.Value = app.currInfo.sel_pT(2);
            %             app.displayInfo;
        end
        
        %%%%%%%%%%%% CUSTOM CALLBACKS AND FUNCTIONS FOR ROImanager APP interaction %%%%%%%%%%%%%%%%%%
        function redrawROIs(app)
            % This function redraws existing ROIs and recreates the missing handles
            % when the current file was changed either as a result from a data processing
            % pipeline or from loading a different file.
            
            
            % If the ROImanager app was closed, redraw each ROI and plot
            % temporal profile (if applicable). If not, let ROImanager do
            % this.
            if isempty(app.ROImngr_App)
                return
            elseif ~isvalid(app.ROImngr_App)
                % Delete existing ROIs:
                arrayfun(@(x) delete(x), [app.ROI_data.spatialHandles(:)]);
                % Redraw ROIs:
                pShapes = [app.ROI_data.list.Shape];
                if ~isempty(pShapes)
                    hold(app.spatialFig.ax, 'on');
                    app.ROI_data.spatialHandles = plot(app.spatialFig.ax,pShapes);
                    arrayfun(@(x,y) set(x, 'FaceColor', y.Color, 'EdgeColor', [0 0 0]), app.ROI_data.spatialHandles, app.ROI_data.list);
                    uistack(app.ROI_data.spatialHandles, 'top');
                end
                % Redraw mask:
                if ~isempty(app.ROI_data.mask.Shape)
                    app.ROI_data.mask.Handle = plot(app.spatialFig.ax, app.ROI_data.mask.Handle.Shape);
                    app.ROI_data.mask.Handle.FaceColor = [1 1 1];
                    app.ROI_data.mask.Handle.FaceAlpha = .2;
                    app.ROI_data.mask.Handle.EdgeColor = 'none';
                    uistack(app.ROI_data.mask.Handle, 'top');
                end
                hold(app.spatialFig.ax, 'off')
                if strcmp(app.timeFig.Fig.Visible, 'on')
                    % Plot temporal profiles and rebuild handles:
                    app.drawROIProfile;
                end
            else
                app.ROImngr_App.rebuildROIinfo; % Let ROImanager recreate ROIs and their respective handles.
            end
        end
        
        function drawROIProfile(app,~,~)
            % This callback updates the ROI list and the time figure, if
            % applicable.
            
            % Show progress bar if it takes the rest of the code more than 500ms to conclude:
            w = [];
            T1 = timer('BusyMode', 'drop',"TimerFcn",{@showBar,app},'StopFcn',@delBar,'StartDelay',0.5,'ExecutionMode','fixedSpacing','Period',0.5);
            T1.start;
            
            % If ROImanager is open, update ROI list:
            
            if ~isempty(app.ROImngr_App)
                if isvalid(app.ROImngr_App)
                    % temporary(local) variable to check if there were changes in the ROI list from ROImngr.
                    
                    %                     if isequaln(app.ROI_data(1).list, app.ROImngr_App.ROI_list)
                    %                         showHideROIplots(app);
                    %                         copyROIsToPhiMap(app);
                    %                         T1.stop;
                    %                         warning("off")
                    %                         delete(T1); warning("on")
                    %                         return
                    %                     end
                    % Update ROI list from ROImanager:
                    app.ROI_data(1).list = app.ROImngr_App.ROI_list;
                    %
                    if isempty(app.ROI_data(1).list(1).Name)
                        % Case when there are no ROIs. When they were deleted.
                        % Delete ROIs and plot handles:
                        delete(app.ROI_data.spatialHandles);
                        if iscell(app.ROI_data.plotHandles)
                            cellfun(@(x) delete(x),app.ROI_data.plotHandles); % Delete ROI traces in time plot
                            h_del = findobj(app.timeFig.axT1, 'Tag', 'ROIshade');
                            delete(h_del); % delete ROI Shades in time plot
                        end
                        app.ROI_data.spatialHandles = [];
                        app.ROI_data.plotHandles = {};
                        pause(1); % Just be be sure that the progressbar appears, otherwise the "delete" won't work.
                        delete(w)
                        % Disable ROI options menu:
                        app.ROIoptionsMenu.Enable = 'off';
                        return
                    end
                    % Update Polyshape handles:
                    app.ROI_data.spatialHandles = app.ROImngr_App.overlayHandle{1};
                    % Enable ROI related buttons in GUI:
                    if strcmp(app.ROIoptionsMenu.Enable, 'off')
                        app.ROIoptionsMenu.Enable = 'on';
                    end
                end
            end
            % Plot average values of ROIs in timeFig:
            if strcmp(app.timeFig.Fig.Visible, 'on')
                % Delete existing ROI plot handles:
                if iscell(app.ROI_data(1).plotHandles)
                    h_del = findobj(app.timeFig.axT1, 'Tag', 'ROIshade');
                    delete(h_del); % delete ROI Shades in time plot
                    cellfun(@(x) delete(x), app.ROI_data.plotHandles);
                    app.ROI_data.plotHandles = {};
                end
                h = findall(app.timeFig.axT1, 'Tag', 'sigLine');
                hold(app.timeFig.axT1, 'on');
                b_hasItems = arrayfun(@(x) ~isempty(x.Name),app.ROI_data.list);
                for i = 1:sum(b_hasItems)
                    data = reshape(app.dataInfo.data, [],size(app.dataInfo.data,3));
                    if app.dataInfo.iscomplex
                        % For FFT data:
                        data = mean(abs(data(app.ROI_data.list(i).Stats.ROI_binary_mask(:),:)),1, 'omitnan');
                    else
                        data = mean(data(app.ROI_data.list(i).Stats.ROI_binary_mask(:),:),1, 'omitnan');
                    end
                    if app.currInfo.rep == -1
                        % Calculate and plot SEM for "#Average" condition only:
                        repIdx = app.eventID_list == app.currInfo.cond;
                        dimStr = strjoin(app.dataInfo.MetaMap.dim_names, ',');
                        dimStr = strrep(dimStr,'E', 'repIdx'); dimStr(cellfun(@(a) strfind(dimStr,a), {'Y','X','T'})) = ':';
                        eval(['dataSEM = squeeze(app.dataInfo.Map.Data.data(' dimStr '));'])
                        dataSEM = permute(dataSEM,app.dataInfo.dim_idx);
                        dataSEM = reshape(dataSEM,[prod([size(dataSEM,1) size(dataSEM,2)]), size(dataSEM,3), size(dataSEM,4)]);
                        dataSEM = squeeze(std(mean(dataSEM(app.ROI_data.list(i).Stats.ROI_binary_mask(:),:,:),1,'omitnan'),0,3,'omitnan')./sqrt(sum(repIdx)));
                        % get X values from average line:
                        x = h.XData;
                        % Calculate SEM:
                        y = [data - dataSEM; data + dataSEM];
                        % Plot shade:
                        p = patch(app.timeFig.axT1, [x, fliplr(x)], [y(1,:), fliplr(y(2,:))], app.ROI_data.list(i).Color, 'Tag', 'ROIshade');
                        set(p, 'FaceAlpha', .3, 'EdgeColor', p.FaceColor);
                        p.UserData = app.ROI_data.list(i).Name;
                    end
                    app.ROI_data.plotHandles{i} = plot(app.timeFig.axT1, h.XData, data(1:length(h.XData)), 'Color', app.ROI_data.list(i).Color, 'LineWidth',1.5, 'Tag','ROILine'); % Plot ROI average line.
                    app.ROI_data.plotHandles{i}.Color(4) = .75;
                    app.ROI_data.plotHandles{i}.UserData = app.ROI_data.list(i).Name;
                    uistack(app.ROI_data.plotHandles{i}, 'top');
                end
                app.displayInfo;
                hold(app.timeFig.axT1, 'off');
                % Toggle time plot visibility "on"/"off":
                showHideROIplots(app);
                % Copy ROIs to phase map:
                copyROIsToPhiMap(app);
            end
            T1.stop;
            warning("off")
            delete(T1); warning("on")
            
            % Local functions
            function showHideROIplots(app)
                % This function turns the visibility of ROI traces in the time plot on or off
                % depending on the visibility of the ROI shapes in the
                % image window.
                
                % Check if the ROImanager app is open:
                if isempty(app.ROImngr_App) || ~isvalid(app.ROImngr_App)
                    return
                end
                
                % Check if ROIs exist:
                if isempty(app.ROI_data) || isempty(app.ROI_data.plotHandles)
                    return
                end
                
                % Toggle visibility of ROI traces to match the visibility
                % of the ROIs in the image:
                viz_plt = cellfun(@(x) strcmp(x.Visible, 'on'), app.ROI_data.plotHandles);
                viz_spat = arrayfun(@(x) strcmp(x.Visible, 'on'), [app.ROImngr_App.overlayHandle{1}]);
                h_shades = findobj(app.timeFig.axT1, 'Tag', 'ROIshade');
                shade_names = arrayfun(@(x) x.UserData, h_shades, 'UniformOutput', false);
                [~, locB] = ismember(shade_names, {app.ROI_data.list.Name});
                h_shades = h_shades(locB);
                if ~all(viz_plt == viz_spat)
                    cellfun(@(x) set(x, 'Visible', 'off'), app.ROI_data.plotHandles(~viz_spat))
                    cellfun(@(x) set(x, 'Visible', 'on'), app.ROI_data.plotHandles(viz_spat))
                    if ~isempty(h_shades)
                        arrayfun(@(x) set(x, 'Visible', 'off'), h_shades(~viz_spat))
                    end
                end
            end
            
            function copyROIsToPhiMap(app)
                % This functions works with the FFT data. It copies the
                % ROIs from the power to the phase maps.
                
                if ~app.dataInfo.iscomplex
                    return
                end
                delete(findall(app.spatialFig.ax2, 'Type','Polygon'));% Clear existing ROIs from Phase map.
                delete(findobj(app.spatialFig.ax2, 'Type','image','-not','Tag','PhiImg')); % Clear Alpha Mask.
                % Copy ROIs to Phase map:
                roi_handles = findall(app.spatialFig.ax, 'Type','Polygon');
                for ii = 1:length(roi_handles)
                    copyobj(roi_handles(ii), app.spatialFig.ax2);
                end
                h_mask = findobj(app.spatialFig.ax, 'Type','image','-not','Tag','MyImg');
                copyobj(h_mask, app.spatialFig.ax2); % Copy AlphaMask
            end
            
            function showBar(~,~,app)
                if isempty(w) && strcmpi(app.UIFigure.Visible, 'on')
                    w = uiprogressdlg(app.UIFigure,'Message', 'Drawing ROIs. Please wait...', 'Indeterminate',"on");
                end
            end
            
            function delBar(~,~)
                delete(w);
            end
        end


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function roiFileName = createTempROI(app, savedir)
            % This helper function creates a temporary ROImasks file in the
            % current folder. This function is used in the callbacks
            % "ExtractdatafromROIMenuSelected" and
            % "GenerateCorrelationMatrixMenuSelected".
            
            % Create temporary ROI file with existing ROI information
            disp('Creating temporary ROI file in save folder...')
            ROI_info = app.ROI_data.list;
            img_info = app.image_info;
            img_info.data = img_info.imageData;
            roiFileName = fullfile(savedir,['tmpROI_' num2str(randi(9999),'0%i') '.mat']);
            save(roiFileName, 'ROI_info', 'img_info');
        end
        
        function updateImageInfo(app,~,~)
            % This custom callback updates the "image_info" property when
            % changes are performed inside ROImanager.
            
            fn = intersect(fieldnames(app.image_info), fieldnames(app.ROImngr_App.image_info));
            for i = 1:numel(fn)
                app.image_info.(fn{i}) = app.ROImngr_App.image_info.(fn{i});
            end
        end
        
        function updateMask(app,~,~)
            % This custom callback updates the "mask" field in "ROI_data" property when
            % changes are performed inside ROImanager.
            
            if isempty(app.ROI_data) || isempty(app.ROI_data(1).mask)
                % DoNothing
            elseif isequaln(app.ROI_data.mask.Handle, app.ROImngr_App.mskObj.Handle)
                return
            end
            % Set Mask:
            app.ROI_data(1).mask = app.ROImngr_App.mskObj;
        end
        
        function askUsertoSaveFile(app)
            % This function creates a question dialog asking the user to
            % save or discard the current data.
            % This function is used by the "OpenFile" and "Import Raw data"
            % callbacks.
            
            % Ask user to save current temporary file:
            if ~isempty(app.inputFile)
                [~,file,~] = fileparts(app.inputFile);
                if startsWith(file, 'tmpFile_') || strcmpi(file, '_DATA_')
                    answer = questdlg('Save current data?', 'Data not saved', 'Yes', 'No, discard Data', 'Yes');
                    if strcmp(answer, 'Yes')
                        app.SaveasMenuSelected;
                    else
                        % Erase input file name, so the function
                        % "reopenFile" prompts user to Open a new file.
                        app.inputFile = '';
                    end
                end
            end
            % clear memmapfile variables. This will allow the deletion of
            % the current temporary .dat file mapped to memory:
            app.dataInfo.data = []; app.dataInfo.Map = [];
            % Delete all temporary .dat files from folder:
            tmpFiles = dir(fullfile(fileparts(app.tmpFile), 'tmpFile_*.dat'));
            if ~isempty(tmpFiles)
                answer = questdlg('Delete temporary files in the save folder?', 'Temporary files found!', 'Yes', 'No', 'Yes');
                if strcmp(answer, 'Yes')
                    disp('Deleting temporary files from folder...')
                    for i = 1:length(tmpFiles)
                        delete(fullfile(tmpFiles(i).folder, tmpFiles(i).name))
                        delete(fullfile(tmpFiles(i).folder, strrep(tmpFiles(i).name, '.dat', '.mat')));
                    end
                    disp('Temporary files deleted!')
                end
            end
        end
        
        function plotTriggers(app)
            % This function creates trigger patches
            % from the "events.mat" file in an axis behind the temporal profile axis.
            
            if app.expType ~= 1 || ~exist(fullfile(app.dummyProt.SaveDir,'events.mat'),'file')
                return
            end
            % Clear current trigger patches:
            p = findall(app.timeFig.axT2, 'Tag', 'TrigPatch');
            leg = findall(app.timeFig.Fig, 'Tag','TrigPatchLeg');
            delete(p);delete(leg);
            % Load event data
            evnt = load(fullfile(app.dummyProt.SaveDir,'events.mat'));
            %             % Update axis:
            %             set(app.trigFig.ax, 'XLim', app.timeFig.axT1.XLim, 'XTick', app.timeFig.axT1.XTick,...
            %                 'XTickLabel', app.timeFig.axT1.XTickLabel, 'YLim', [0 1])
            idx = unique(evnt.eventID);
            % Create semi-transparent patches to represent HIGH state of triggers:
            xdata = evnt.timestamps.*app.dataInfo.MetaMap.FrameRateHz; % XData for trigger axis
            ydata = app.timeFig.axT1.YLim;% YData for trigger axis
            colorArr = [1 0 0; 0 0 1; 0 1 0; 0 1 1; 1 0 1; 1 1 0; 0 0 0];
            if numel(evnt.eventNameList) > size(colorArr,1)
                colorArr = jet(64);  % Trigger color code
                colorArr = colorArr(round(linspace(1,32,numel(evnt.eventNameList))),:);
            end
            hold(app.timeFig.axT2, 'on')
            for i = 1:length(idx)
                xOn = xdata(evnt.eventID == idx(i) & evnt.state == 1);
                xOff = xdata(evnt.eventID == idx(i) & evnt.state == 0);
                x = [xOn xOff xOff xOn];
                y = repmat([ydata(1) ydata(1) ydata(2) ydata(2)], size(xOn));
                p(i) = patch(app.timeFig.axT2, x',y',colorArr(i,:), 'FaceAlpha', .5, 'EdgeColor', 'none', 'Tag','TrigPatch');
            end
            hold(app.timeFig.axT2, 'off')
            legend(app.timeFig.axT2, p,evnt.eventNameList, 'Location',"northeast", 'Interpreter','none', 'Tag','TrigPatchLeg');
            % Force showing event patches in time plot:
            if contains(app.ShoweventtracesMenu.Text, 'show','IgnoreCase',true)
                event.Source.Text = 'Hide trigger traces';
                app.ShoweventtracesMenuSelected(event);
            end
            
        end
        
        function updateTrigPatches(app,~,~)
            % This method changes the size of Trigger patches to fit the
            % current axis YLim.
            %             disp(app.timeFig.axT1.YLim)
            myPatch = findall(app.timeFig.axT2, 'Tag', 'TrigPatch');
            arrayfun(@(x) set(x, 'YData', repelem(app.timeFig.axT2.YLim',2,size(x.YData,2))), myPatch);
        end
        
        function copyAxparams(~,sourceAx, targetAx)
            % This function makes a copy of all editable axis
            % parameters.
            uiAxParams = get(sourceAx);
            uiAxParamNames = fieldnames(sourceAx);
            % Get list of editable params in new axis
            editableParams = arrayfun(@(x) fieldnames(set(x)), targetAx, 'UniformOutput', false); editableParams = unique(vertcat(editableParams{:}));
            % Remove the UIAxes params that aren't editable in the new axes (add others you don't want)
            badFields = uiAxParamNames(~ismember(uiAxParamNames, editableParams));
            badFields = [badFields; 'Parent'; 'Children'; 'XAxis'; 'YAxis'; 'ZAxis';'Position';'OuterPosition'; 'XLabel'; 'YLabel';'ZLabel';'Title';'Toolbar'];
            uiAxGoodParams = rmfield(uiAxParams,badFields);
            % set editable params on new axes
            set(targetAx, 'Title', copy(sourceAx.Title), 'XLabel', copy(sourceAx.XLabel), 'YLabel', copy(sourceAx.YLabel), 'XLim', sourceAx.XLim, ...
                'YLim', sourceAx.YLim); drawnow
            fn = fieldnames(uiAxGoodParams);
            for ii = 1:length(fn)
                try
                    set(targetAx, fn{ii}, uiAxGoodParams.(fn{ii}))
                catch
                    disp(fn{ii})
                end
            end
            set(targetAx, uiAxGoodParams);
        end
        
        function runBatchScript(app, folderList, scriptName,fileExt)
            % Executes a batch script on a list of folders.
            %
            % Inputs:
            %   - app: The application object.
            %   - folderList: A cell array of folder paths.
            %   - scriptName: The name of the script to run.
            %   - maindir: The main directory.
            
            % Block main GUI:
            w = uiprogressdlg(app.UIFigure, 'Message', 'Running Batch Process', ...
                'Title', 'Please wait...', 'Indeterminate', 'on');
            
            % Create GUI to show progress to the user:
            f = uifigure('Name', ['Batch processing: ', scriptName], ...
                'Position', [0 0 400 450], 'CreateFcn', {@movegui, 'center'}, ...
                'Visible', 'off', 'CloseRequestFcn', @closeFig);
            f.Visible = 'on';
            
            % Confirm operation:
            answer = uiconfirm(f, 'Confirm batch processing? Data already processed in the selected folders may be overwritten.', ...
                'Confirm', 'Icon', 'warning');
            if ~strcmpi(answer, 'ok')
                disp('Operation Cancelled by User')
                delete(f);
                return
            end
            % Check for oversized log_batch_process.txt files and ask user
            % what to do with them:
            maxLogFileSizeBytes = 1e6; % Maximum of 1MB per file
            b_largeLogFile = false(size(folderList));
            for ii = 1:length(folderList)
                logFile = fullfile(folderList{ii},'log_batch_process.txt');
                if isfile(logFile)
                    info = dir(logFile);
                    b_largeLogFile(ii) = info.bytes > maxLogFileSizeBytes;
                end
            end
            answer = '';
            if any(b_largeLogFile)
                answer = uiconfirm(f, 'Some log files are larger than 1 MB. What do you want to do with them?', ...
                    'Large log files found', 'Icon', 'info','Options',{'Overwrite','Create backup and overwrite','Keep as is'},'DefaultOption',2,'CancelOption',3);
            end
            
            % Create List of folders:
            gd = uigridlayout('Parent', f, 'ColumnWidth', {'1x'}, 'RowHeight', {'1x', 50});
            pan = uipanel('Parent', gd, 'Scrollable', 'on');
            pan.Layout.Column = 1; pan.Layout.Row = 1;
            txa = uitextarea('Parent', pan, 'Position', [1 1 378 368]);
            
            % Create button
            abortBtn = uibutton(gd, 'Text', 'Abort', 'BackgroundColor', [.8 0 0], ...
                'ButtonPushedFcn', @abortBatch);
            
            % Populate Text area with folder list:
            strList = cellfun(@(x) ['[Waiting] ', x], folderList, 'UniformOutput', false);
            txa.Value = strList;
            
            % Execute script on each folder:
            b_abort = false;
            status = true(size(folderList));
            % Get script file extension:
            [~,~,scriptExt] = fileparts(scriptName);
            for ii = 1:length(folderList)
                % Update text area
                txa.Value{ii} = strrep(txa.Value{ii}, '[Waiting]', '[Processing...]');
                drawnow
                % Deal with large Log files:
                logFile = fullfile(folderList{ii}, 'log_batch_process.txt');
                if isfile(logFile) && b_largeLogFile(ii)
                    switch lower(answer)
                        case 'overwrite'
                            % Delete current log file and let
                            % "runScriptOnFolder" create a new one:
                            delete(logFile);
                        case 'create backup and overwrite'
                            % Copy log file and Delete:
                            copyfile(logFile, fullfile(folderList{ii}, ['bkp_' datestr(now(),'yyyymmdd_HH_MM_SS_') 'log_batch_process.txt']));
                            delete(logFile);
                    end
                end
                % Execute function:
                status(ii) = runScriptOnFolder(folderList{ii}, scriptName, fileExt,scriptExt);
                
                if status(ii)
                    txa.Value{ii} = strrep(txa.Value{ii}, '[Processing...]', '[COMPLETED]');
                else
                    txa.Value{ii} = strrep(txa.Value{ii}, '[Processing...]', '[FAILED!]');
                end
                
                if b_abort
                    txa.Value(ii:length(folderList)) = cellfun(@(x) strrep(x, '[Waiting]', '[CANCELLED]'), ...
                        txa.Value(ii:length(folderList)), 'UniformOutput', false);
                    drawnow
                    pause(1);
                    break
                end
            end
            
            if any(~status)
                % Tell the user that errors were found:
                uialert(f, ['A total of ', num2str(sum(~status)), ' process(es) failed! Check logs inside folders.'], ...
                    'Errors found!', 'Icon', 'error', 'CloseFcn', 'uiresume(gcbf)');
                uiwait(gcbf);
                set(abortBtn, 'Text','Close', 'ButtonPushedFcn',@closeFig);
            else
                uialert(f, 'Batch Processing completed!', 'Done!', 'Icon', 'info', 'Modal', true, 'CloseFcn', 'uiresume(gcbf)');
                uiwait(gcbf)
                % Change abort button behaviour to close:
                set(abortBtn, 'Text','Done!','BackgroundColor',[0 .8 0], 'ButtonPushedFcn',[], 'Enable','off');
            end
            b_abort = true;
            
            % Close progress bar:
            delete(w);
            
            %% Custom callbacks for Batch figure:
            function abortBatch(src, ~)
                % Cancels the execution of the batch processing:
                src.Text = 'Please wait';
                drawnow;
                src.Enable = 'off';
                fH = ancestor(src, 'Figure');
                answerBtn = uiconfirm(fH, 'Abort batch processing?', 'Confirm Abort', ...
                    'Options', {'Yes', 'No'});
                if strcmpi(answerBtn, 'no')
                    src.Text = 'Abort';
                    drawnow;
                    b_abort = false;
                    src.Enable = 'on';
                    return
                end
                
                b_abort = true;
            end
            
            function closeFig(~, ~)
                % Aborts the process and closes the figure:
                fH = ancestor(abortBtn,'Figure');
                if ~b_abort
                    abortBatch(abortBtn);
                else
                    delete(fH);
                end
            end
        end
        
        function reopenFile(app)
            % This function reopens the file pointed in "inputFile"
            % after the current data was cleared from memory. This function
            % will be called in cases such as:
            %  - The user cancels the data import process.
            %  - The user cancels the new data opening when there was
            %   already an open file.
            
            if isempty(app.image_info.cmap)
                % THis means that there were no previous data that was
                % opened.
                return
            end
            % Execute the OpenFile callback when no file exists:
            if isempty(app.inputFile)
                % execute the OpenFile callback:
                app.OpenFile_callback(app.dummyProt.SaveDir);
                return
            end
            % Reopen the current file:
            b_OK = app.checkInput;
            if b_OK
                app.loadData;
                app.setup;
                % Display Info:
                app.displayInfo;
                if isempty(app.ROI_data.list)
                    return
                end
                % Rebuild axis and objects' handles if ROIs are displayed
                app.redrawROIs; % Redraw ROIs and time profiles
                app.displayInfo;
            end
        end
    end
    
    methods (Access = public)
        
        %%%%%%%%%%%% CUSTOM CALLBACKS %%%%%%%%%%%%%%%%%%
        function condChanged(app,~,~)
            % Callback triggered by a change on the "condition" dropdown menu of
            % funcImag_viz_evntWindow.
            
            % Clear avg from dataInfo to allow "loadData" method to
            % calculate the average +-SEM for the newly selected condition.
            app.dataInfo.avg = [];
            % Reload data and update plots:
            app.loadData;
            app.updateImage;
            app.updateTimeFig;
            app.displayInfo;
            % Update ROI values, if applicable:
            if ~isempty(app.ROI_data)
                app.drawROIProfile;
            end
        end
        
        function repChanged(app,~,~)
            % Callback triggered by a change on the "repetition" dropdown menu of
            % funcImag_viz_evntWindow.
            
            % Reload data and update plots:
            app.loadData;
            app.updateImage;
            app.updateTimeFig;
            app.displayInfo;
            % Update ROI values, if applicable:
            if ~isempty(app.ROI_data)
                app.drawROIProfile;
            end
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        function updateImage(app, varargin)
            % UPDATEIMAGE changes the image displayed. In addition, this function
            % updates the content of any datatip that is shown in the
            % "Image Window".
            % The image is replaced without changing other axis elements.
            
            b_multiCall = app.isMultipleCall;
            if b_multiCall
                disp('Please wait!')
                return
            end
            % Update Axis colormap and CLim:
            
            if ~isequal(app.spatialFig.ax.CLim, app.image_info.clims) || ~isequal(app.spatialFig.ax.Colormap, app.image_info.cmap)
                set(app.spatialFig.ax, 'Colormap', app.image_info.cmap, 'CLim', app.image_info.clims);
            end
            % Skip frame updating for zoom object callback:
            if nargin > 1
                return
            end
            
            % Replace Image with new frame (Inefficient part of the code!):
            if strcmpi(app.viewMode,'fft')
                % Show Power map:
                app.spatialFig.img.CData = abs(app.dataInfo.data(:,:,round(app.currInfo.frame))).^2/size(app.dataInfo.data,3);
                % Override main axis CLims:
                set(app.spatialFig.ax, 'CLim', [min(app.spatialFig.img.CData,[],'all',"omitnan"), max(app.spatialFig.img.CData,[],'all',"omitnan")]); % Set CLims automatically for the amplitude frame
                % Update image CLims:
                app.image_info.clims = app.spatialFig.ax.CLim;
                % Show Unwrapped Phase map:
                app.spatialFig.img2.CData = mod(-1*angle(app.dataInfo.data(:,:,round(app.currInfo.frame))),2*pi); % Flip and wrap the phase betwee 0-2*pi
                
            else
                app.spatialFig.img.CData = app.dataInfo.data(:,:,round(app.currInfo.frame)); drawnow;
                
                % Update title:
                set(app.spatialFig.ax.Title, 'String',['Frame #' num2str(app.currInfo.frame)])
            end
            % Update "imageData" field in image_info structure.
            app.image_info.imageData = app.spatialFig.img.CData;
            
            
            % Update datatip content for "Image window"
            dt_h = findall(app.spatialFig.Fig,'Type','hggroup'); % Find datatip handles;
            for i = 1:length(dt_h)
                dt_h(i).String = app.showSpatialDataTip(dt_h(i),dt_h(i));
            end
        end
        
        function updateTimeFig(app)
            % UPDATETIMEFIG changes the temporal profile of a pixel in the axis "axT1" from the figure "timeFig".
            b_multiCall = app.isMultipleCall;
            if b_multiCall
                disp('Please wait!')
                return
            end
            if strcmp(app.timeFig.Fig.Visible, 'off')
                return
            end
            
            % If the new data is different from the current plot, update
            % plot:
            if ~isequaln(squeeze(app.dataInfo.data(app.currInfo.sel_pT(2), app.currInfo.sel_pT(1),:))',app.timeFig.sigLine.YData)
                % Redraw XData:
                app.timeFig.sigLine.XData = single(1:size(app.dataInfo.data,3));
                ydat = squeeze(app.dataInfo.data(app.currInfo.sel_pT(2), app.currInfo.sel_pT(1),:));
                
            else
                ydat = app.timeFig.sigLine.YData;
            end
            if strcmpi(app.viewMode, 'fft')
                % update XData:
                app.timeFig.sigLine.XData = single(1:round(size(app.dataInfo.data,3)/2));
                app.timeFig.sigLine.YData = abs(ydat(1:round(size(app.dataInfo.data,3)/2)));
            else
                app.timeFig.sigLine.YData = ydat;
            end
            % If the Ylim of the timeFig axis is different from the current
            %   CLim:
            if ~isequal(app.image_info.clims, app.timeFig.axT1.YLim) && ~strcmpi(app.viewMode,'fft')
                app.timeFig.axT1.YLim = app.image_info.clims;             
                app.timeFig.timeBar.YData = app.image_info.clims;
            end
            % If the experiment type is an "Image time series split by
            % Event", and the user selects to plot the "#Average", show
            % the Standard Error of the Mean (SEM) as a shaded area around
            % the mean plot:
            %             if app.expType == 2
            %                 % find shaded plot handle:
            %                 sh = findall(app.timeFig.axT1, 'Tag', 'sigShade');
            %                 % Update shaded area if "#Average" is selected:
            %                 if app.currInfo.rep == -1
            %
            %                     % get X values from average line:
            %                     x = p.XData;
            %                     % Calculate SEM:
            %                     y = [p.YData - squeeze(app.dataInfo.SEM(app.currInfo.sel_pT(2), app.currInfo.sel_pT(1),:))';...
            %                         p.YData + squeeze(app.dataInfo.SEM(app.currInfo.sel_pT(2), app.currInfo.sel_pT(1),:))'];
            %                     % Update shaded plot:
            %                     sh.XData = [x, fliplr(x)];
            %                     sh.YData = [y(1,:), fliplr(y(2,:))];
            %                     if strcmpi(p.Visible,'on')
            %                         sh.Visible = 'on';
            %                     end
            %                 else
            %                     sh.Visible = 'off';
            %                 end
            %             end
            %             Notify multi-plot app, if applicable:
            if ~isempty(app.multiPlot_App)
                notify(app, 'updateMultiPlot')
            end
            
        end
        
        function runPipeline(app, pipeObj, callerApp)
            %   This method is called by the "Run" button callback from the
            %   "PipelinConfig_dlgBox to execute the pipeline.
            
            % Close Pipeline Builder:
            close(callerApp.UIFigure);
            %
            app.pipeObj = pipeObj;
            w = uiprogressdlg(app.UIFigure, 'Value',0,'Message','', 'Indeterminate',"on");
            try
                metaData = load(app.dataInfo.MetaMap.Properties.Source);
                % Clear Map:
                app.dataInfo.data = []; app.dataInfo.Map = []; app.dataInfo.MetaMap = [];
                w.Message = 'Loading data for pipeline execution... Please wait.';
                data = loadDatFile(fullfile(app.dummyProt.SaveDir, app.inputFile));
                app.pipeObj.loadDataFromDummyProtocol(data,metaData);
            catch
                metaData = app.dataInfo.MetaMap; % For when the data is already in RAM
                app.pipeObj.loadDataFromDummyProtocol(app.dataInfo.Map.Data.data,metaData);
            end
            % Erase mean and SEM data.
            %             app.dataInfo.avg = [];
            %             app.dataInfo.SEM = [];
            % Hide figures:
            app.spatialFig.Fig.Visible = 'off';
            app.timeFig.Fig.Visible = 'off';
            app.tmpFile = '';
            %%%%% Further Validation of the pipeline:
            
            % Check if the last data created in the pipeline is a .DAT file:
            MatIndx = false(1,length(app.pipeObj.pipe));
            DatIndx = MatIndx;
            for ii = 1:length(app.pipeObj.pipe)
                if ischar(app.pipeObj.pipe(ii).outFileName)
                    MatIndx(ii) = all(app.pipeObj.pipe(ii).b_hasDataOut & endsWith(app.pipeObj.pipe(ii).outFileName, '.mat'));
                    DatIndx(ii) = all(app.pipeObj.pipe(ii).b_hasDataOut & endsWith(app.pipeObj.pipe(ii).outFileName, '.dat'));
                else
                    MatIndx(ii) = all(app.pipeObj.pipe(ii).b_hasDataOut & endsWith(app.pipeObj.pipe(ii).outFileName{end}, '.mat'));
                    DatIndx(ii) = all(app.pipeObj.pipe(ii).b_hasDataOut & endsWith(app.pipeObj.pipe(ii).outFileName{end}, '.dat'));
                end
            end
            
            lastMat = find(MatIndx,1,'last');
            lastDat = find(DatIndx,1,'last');
            % If the last data is a .MAT file, find the last .DAT file and
            % force save to disk (except the already opened one):
            b_loadFromTmpFile = false;
            if ~isempty(lastMat)
                
                % Force save the last .mat file:
                if ~app.pipeObj.pipe(lastMat).b_save2File
                    app.pipeObj.pipe(lastMat).b_save2File = true;
                    app.pipeObj.pipe(lastMat).saveFileName = app.pipeObj.pipe(lastMat).outFileName;
                end
                if ( lastDat > 0 & lastMat > lastDat & ~app.pipeObj.pipe(lastDat).b_save2File ) %#ok
                    % Save last .dat data to a temporary .dat file:
                    tmpFileName = ['tmpFile_' num2str(randi(9999),'0%i') '.dat'];
                    app.pipeObj.pipe(lastDat).b_save2File = true;
                    app.pipeObj.pipe(lastDat).saveFileName = tmpFileName;
                    app.tmpFile = fullfile(app.dummyProt.SaveDir, tmpFileName);
                    b_loadFromTmpFile = true;
                end
                
            end
            w.Message = 'Analysing data...';
            % Run pipeline
            app.pipeObj.run_pipeline;
            
            % If pipeline failed or it generated only .MAT file(s), reopen input file:
            if ~app.pipeObj.b_state || isempty(lastDat)
                app.reopenFile;
                % Delete data in pipeline object to save RAM:
                app.pipeObj.current_data = [];
                app.pipeObj.current_metaData = [];
                if ~isempty(lastMat)
                    % Open folder to show the .mat file:
                    openFolder(app.dummyProt.SaveDir);
                end
                return
            elseif ~isempty(lastMat) && b_loadFromTmpFile
                % If the pipeline generated a .mat file, open the last .dat
                % file:
                app.inputFile = app.tmpFile;
            end
            
            % If pipeline worked, load latest file created in the pipeline:
            if ~app.pipeObj.pipe(lastDat).b_save2File
                % Open the current data from PipelineManager
                app.inputFile = '_DATA_';
            else
                % Open the .Dat file created in the pipeline
                app.inputFile = fullfile(app.dummyProt.SaveDir, app.pipeObj.pipe(lastDat).saveFileName);
            end
            
            w.Message = 'Done.';
            % Load inputFile:
            app.checkInput;
            close(w);
            % Open folder if the pipeline generated a .mat file:
            if ~isempty(lastMat)
                % Open folder to show the .mat file:
                openFolder(app.dummyProt.SaveDir);
            end
            % Show figures:
            %             figure(app.UIFigure);
            %             app.spatialFig.Fig.Visible = 'on';
            %             app.timeFig.Fig.Visible = 'on';
            % Set axes parameters and load Data:
            app.loadData;
            app.setup;
            % Delete data in pipeline object to save RAM:
            app.pipeObj.current_data = [];
            app.pipeObj.current_metaData = [];
            %             app.pipeObj = [];
            % Display Info:
            app.displayInfo;
            if isempty(app.ROI_data.list)
                return
            end
            % Rebuild axis and objects' handles if ROIs are displayed
            app.redrawROIs; % Redraw ROIs and time profiles
            app.displayInfo;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            %%
            
            if ~isdeployed
                % Update umIT path:
                currPath = mfilename('fullpath');
                currPath = erase(currPath, ['GUI' filesep 'DataViz' filesep 'DataViewer']);
                addpath(currPath)
                umIT_path_cleanup(currPath)
            end
            updateVersionTag(app.UIFigure);
            app.UIFigure.Visible = 'off';
            % Update UIFigure position:
            movegui(app.UIFigure,'northwest');
            
            %%%%% TIME FIGURE CREATION %%%%%
            % Initialize Figure:
            app.timeFig.Fig = figure('Name', 'Temporal signal'...
                ,'NumberTitle','off'...
                ,'Color', 'w'...
                ,'MenuBar', 'none'...
                ,'Position', [1 1 600 250]...
                ,'ToolBar', 'figure'...
                ,'Resize', 'on'...
                ,'Visible','off'...
                ,'CreateFcn', {@movegui, 'northeast'}...
                ,'CloseRequestFcn', @app.DoNotCloseFig...
                ,'WindowButtonDownFcn', @app.timeFigButtonDown_callback);
            % Set Data cursor info and behaviour
            app.timeFig.dcm_obj = datacursormode(app.timeFig.Fig);
            set(app.timeFig.dcm_obj, 'DisplayStyle', 'datatip', 'Enable', 'off','UpdateFcn', @app.showTimeDataTip, 'Interpreter', 'tex');
            % Create axes:
            
            % Main Axis:
            app.timeFig.axT1 = axes(app.timeFig.Fig);
            set(app.timeFig.axT1, 'Position', [0.08 0.2 0.85 0.72],'NextPlot','replacechildren', 'Color', 'none');
            set(app.timeFig.axT1.Title, 'String', 'Signal Amplitude');
            % Create axis graphical components:
            hold(app.timeFig.axT1, 'on');
            % Crosshair temporal profile (sigLine):
            app.timeFig.sigLine = plot(app.timeFig.axT1, 0,0, 'Color', [.65 .65 .65], 'LineStyle', '-', 'HandleVisibility',"callback", 'LineWidth',1.5,'UserData', 'CrossHair');
            app.timeFig.sigLine.Color(4) = .5; % Add alpha channel to signal line;
            % Crosshair shaded area:
            app.timeFig.sigShade = patch(app.timeFig.axT1, [0 1 1 0],[0 1 1 0],1 ...
                ,'FaceColor',[.65 .65 .65]...
                ,'EdgeColor','k'...
                ,'FaceAlpha', .2...
                ,'EdgeAlpha', .5...
                ,'HandleVisibility', 'callback'...
                ,'UserData', 'CrossHair'...
                , 'Visible', 'off');
            % Time bar:
            app.timeFig.timeBar = line(app.timeFig.axT1,[0 0], [-10 10], 'Color', [0 0 0], 'HandleVisibility',"callback", 'Tag', 'TimeBar');
            % Event shaded area:
            app.timeFig.evShade = patch(app.timeFig.axT1, [0 1 1 0],[0 1 1 0],1 ...
                ,'FaceColor',[.85 0 0]...
                ,'EdgeColor','none'...
                ,'FaceAlpha', .2...
                ,'EdgeAlpha', .5...
                ,'HandleVisibility', 'callback'...
                ,'Visible', 'off');
            hold (app.timeFig.axT1, 'off')
            % Create event listener
            app.timeFig.el = addlistener(app.timeFig.axT1, 'YLim', 'PostSet',@app.updateTrigPatches);
            
            % Secondary axis for Trigger Patches overlay:
            app.timeFig.axT2 = axes(app.timeFig.Fig, 'Position', [0.08 0.2 0.85 0.72],'NextPlot','replacechildren', 'HandleVisibility','callback');
            set(app.timeFig.axT2, 'XTick', [], 'YTick', [], 'XTickLabel', [], 'YTickLabel',[]);
            % Create placeholders for trigger patches and legend in secondary axis. The real ones will be set in the "setup" function:
            app.timeFig.trigPatches = [];
            app.timeFig.legendT2 = [];
            
            % Put Secondary axis behind the primary axis.
            uistack(app.timeFig.axT2, 'bottom');
            
            % Link both axes:
            linkaxes([app.timeFig.axT1, app.timeFig.axT2], 'xy');
            
            %%%%% SPATIAL FIGURE CREATION %%%%%
            % Initialize Figure
            app.spatialFig.Fig = figure('Name', 'Image Window'...
                ,'NumberTitle',"off"...
                ,"MenuBar","none"...
                ,"ToolBar","figure"...
                ,'Position', [1 1 500 500]...
                ,'doublebuffer', 'off'...
                ,'Visible', 'off'...
                ,'CreateFcn', {@movegui, 'southwest'}...
                ,"WindowButtonDownFcn",@(a,b) app.ImageWindowButtonDown...
                ,'BusyAction', 'cancel'...
                ,'CloseRequestFcn',@app.DoNotCloseFig);
            % Set Data cursor info and behaviour
            app.spatialFig.dcm_obj = datacursormode(app.spatialFig.Fig);
            set(app.spatialFig.dcm_obj, 'DisplayStyle', 'datatip', 'Enable', 'off','UpdateFcn', @app.showSpatialDataTip, 'Interpreter', 'tex');
            app.spatialFig.dcm_el = addlistener(app.spatialFig.dcm_obj,'Enable', 'PostSet', @app.showHideROIhandles);
            % Set main axis properties:
            app.spatialFig.ax = axes(app.spatialFig.Fig...
                ,'YDir',"reverse"...
                ,'DataAspectRatio',[1 1 1]...
                ,'XLimMode', 'manual'...
                ,'YLimMode', 'manual'...
                ,'ZLimMode', 'manual'...
                ,'CLimMode', 'manual'...
                ,'ALimMode', 'manual'...
                ,'NextPlot',"replacechildren"...
                ,'Tag','MainAx');
            % Create a secondary axis (for Phase maps in FFT view):
            app.spatialFig.ax2 = copyobj(app.spatialFig.ax, app.spatialFig.Fig); uistack(app.spatialFig.ax2, 'bottom');
            set(app.spatialFig.ax2, 'Visible','off', 'Tag','SecAx');
            % Link axes:
            linkaxes([app.spatialFig.ax, app.spatialFig.ax2], 'xy');
            % Create graphical components for each axis:
            % Axis 1 (Main axis):
            % Image:
            app.spatialFig.img = image(app.spatialFig.ax,zeros(10,10)); app.spatialFig.img.CDataMapping = 'scaled';
            % Crosshair:
            app.spatialFig.cross = line(app.spatialFig.ax, [1 1; app.spatialFig.ax.XLim]', [app.spatialFig.ax.YLim ; 1 1]', 'Color', [1 1 1],'HandleVisibility', 'callback');
            % Colorbar:
            app.spatialFig.cb = colorbar(app.spatialFig.ax, 'eastoutside');
            
            % Axis 2 (secondary axis for FFT view mode):
            
            % Image:
            app.spatialFig.img2 = image(app.spatialFig.ax2,zeros(10,10)); app.spatialFig.img2.CDataMapping = 'scaled';
            % Crosshair :
            app.spatialFig.cross2 = line(app.spatialFig.ax2, [1 1; app.spatialFig.ax2.XLim]', [app.spatialFig.ax2.YLim ; 1 1]', 'Color', [1 1 1],'HandleVisibility', 'callback');
            % Colorbar:
            app.spatialFig.cb2 = colorbar(app.spatialFig.ax2, 'eastoutside');
            
            % Adjust figures positions in reference to the main figure:
            % Spatial
            app.spatialFig.Fig.OuterPosition(4) = app.UIFigure.Position(2)-5;
            app.spatialFig.Fig.Position(3) = app.UIFigure.Position(3);
            % Temporal
            app.timeFig.Fig.OuterPosition(1) = app.UIFigure.Position(1) + app.UIFigure.Position(3) + 5;
            
            %%%% INPUT VALIDATION %%%%%
            narginchk(1,3);
            if nargin == 1
                app.inputFile = '';
                RawFolder = '';
            elseif nargin == 2
                app.inputFile = convertStringsToChars(varargin{1});
                RawFolder = fileparts(app.inputFile);
            else
                app.inputFile = convertStringsToChars(varargin{1});
                RawFolder = varargin{2};
            end
            % Create dummy protocol to be able to use PipelineManager
            SaveFolder = fileparts(app.inputFile);
            if isempty(SaveFolder)
                SaveFolder = pwd;
            end
            if isempty(RawFolder)
                RawFolder = pwd;
            end
            mod = FluorescenceImaging('DataViewer_mod',{''},'Labeo',1);
            acq = Acquisition('DataViewer_acq',[]);acq.Array.addObj(mod);
            sbj = Subject('DataViewer_sbj','def',''); sbj.Array.addObj(acq);
            app.dummyProt = Protocol('dummyProt',RawFolder,SaveFolder,@(x) x, sbj, true);
            app.dummyProt.Idx_Filtered = [1 1 1];
            
            % Check if input is a valid Image dataset. If not, close the app:
            if isempty(app.inputFile)
                b_isOK = false;
            else
                b_isOK = app.loadData;
            end
            app.UIFigure.Visible = 'on';
            if ~b_isOK && isempty(app.inputFile)
                app.Slider.Enable = 'off';
                app.SettingsMenu.Enable = 'off';
                app.AddonsMenu.Enable = 'off';
                app.UtilitiesMenu.Enable = 'off';
                app.CoordinatesPanel.Visible = 'off';
                app.PlayButton.Enable = 'off';
                app.TextArea.Value = 'Please open a valid .DAT file or Import Raw Data.';
                return
            else
                app.UIFigure.Visible = 'on';
                % Set GUI parameters:
                app.setup;
                % Display Info:
                app.displayInfo;
                % Start tracking cursor position:
                app.start = true;
                % Show Image window:
                app.spatialFig.Fig.Visible = 'on';
            end
        end

        % Close request function: UIFigure
        function CloseRequest_callback(app, event)
            
            % Dealing with events:
            if ~isempty(app.evnt_App)
                if ~isequal(app.eventID_list, app.evnt_App.ID_list)
                    answer = questdlg('Repetitions and/or conditions were changed. What to do?', 'Changes in events detected!', 'Overwrite events', 'Close without saving', 'Cancel', 'Cancel');
                    switch answer
                        case 'Overwrite events'
                            % Reassign eventIDs to the new eventName_list:
                            origList = cellfun(@(x) num2str(x), app.evnt_App.condList, 'UniformOutput',false);
                            actualList = cellfun(@(x) num2str(x), app.eventName_list, 'UniformOutput',false);
                            [locA, locB] = ismember(actualList, origList);locA = find(locA);
                            new_eventID_list = app.eventID_list;
                            for i = 1:length(locB)
                                idx = app.eventID_list == locB(i);
                                new_eventID_list(idx) = locA(i);
                            end
                            % Save "event list" to "eventList" property:
                            app.dataInfo.MetaMap.Properties.Writable = true;
                            app.dataInfo.MetaMap.eventID = new_eventID_list;
                            app.dataInfo.MetaMap.eventNameList = app.eventName_list;
                            m = msgbox('Meta data successfully overwritten!', 'Data saved!');
                            waitfor(m);
                        case 'Cancel'
                            return
                    end
                end
            end
            
            % Ask user to save current temporary file:
            if ~isempty(app.inputFile)
                [~,file,~] = fileparts(app.inputFile);
                if startsWith(file, 'tmpFile_') || strcmpi(file, '_DATA_')
                    answer = questdlg('Save current data?', 'Data not saved', 'Yes', 'No, discard Data', 'Cancel', 'Yes');
                    if strcmpi(answer, 'Yes')
                        app.SaveasMenuSelected;
                    elseif strcmpi(answer,'Cancel') || isempty(answer)
                        return
                    end
                end
            end
            
            % Delete add-on apps:
            if ~isempty(app.ROImngr_App)
                if isvalid(app.ROImngr_App)
                    figure(app.ROImngr_App.UIFigure);
                    app.ROImngr_App.invokeCloseRequestFcn;
                end
                % If the user cancelled the operation inside ROImanager, return:
                if isvalid(app.ROImngr_App)
                    return
                end
            end
            if ~isempty(app.multiPlot_App)
                if isvalid(app.multiPlot_App)
                    delete(app.multiPlot_App.figH)
                    delete(app.multiPlot_App)
                end
            end
            % Clear memmapfile variables. Here, the variables "data" must be erased before "Map", otherwise the whole data will be loaded in RAM!
            app.dataInfo.data = 0;
            app.dataInfo = [];
            % Delete all temporary .dat files from folder:
            if ~isempty(app.tmpFile)
                disp('Deleting temporary files from folder...')
                folder = fileparts(app.tmpFile);
                tmpFiles = dir(fullfile(folder, 'tmpFile_*.dat'));
                for i = 1:length(tmpFiles)
                    delete(fullfile(tmpFiles(i).folder, tmpFiles(i).name))
                    delete(fullfile(tmpFiles(i).folder, strrep(tmpFiles(i).name, '.dat', '.mat')));
                end
                disp('Temporary files deleted!')
            end
            
            % Delete figures and other apps:
            delete(app.timeFig.Fig)
            delete(app.spatialFig.Fig)
            delete(app.phiFig.Fig)
            delete(app.evnt_App)
            delete(app.opts_App)
            delete(app.evntManager_App)
            delete(app.Coreg2Cams_App);
            % Delete main app
            delete(app)
        end

        % Callback function
        function ImageWindowButtonDown(app, event)
            % This callback updates the crosshair position when clicking
            % over the spatial figure axis.
            
            % Control for multiple calls:
            if app.isMultipleCall
                disp('Please Wait!')
                return
            end
            b_in_bounds  = [false, false];
            % Get cursor position:
            click = get(app.spatialFig.Fig, 'SelectionType');
            coords = get(app.spatialFig.ax, 'CurrentPoint');
            app.currInfo.pT = round(coords(1,1:2));
            b_in_bounds(1) = app.getBounds;
            
            if ~any(b_in_bounds) && strcmpi(app.spatialFig.ax2.Visible, 'on')
                % Also check in phase map figure;
                coords = get(app.spatialFig.ax2, 'CurrentPoint');
                app.currInfo.pT = round(coords(1,1:2));
                b_in_bounds(2) = app.getBounds;
            end
            % Check if the click was inside the axis:
            if ~any(b_in_bounds)
                return
            end
            
            % Update the position of the croisshair:
            if strcmp(click, 'normal')
                app.updateCrossHairPos;
            end
            % Get value of clicked pixel:
            app.currInfo.value = app.dataInfo.data(app.currInfo.pT(2), app.currInfo.pT(1), app.currInfo.frame);
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            % This callback updates the Time Bar position in the Time figure
            % when the slider position is being changed.
            
            % Control for multiple calls:
            if app.isMultipleCall
                disp('Multiple calls detected! Please wait!')
                return
            end
            % Change title to indicate target frame:
            app.spatialFig.ax.Title.String = ['moving to Frame # ' num2str(round(event.Value))]; drawnow;
            % Update time bar position:           
            app.timeFig.timeBar.XData = [round(event.Value) round(event.Value)];
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            % This callback updates the current frame  in the spatial figure
            % when a new slider position is set.
            
            % Control for multiple calls:
            if app.isMultipleCall
                disp('Multiple calls detected! Please wait!')
                return
            end
            % Get new frame index:
            app.currInfo.frame = round(event.Value);
            % Update frame and information:
            app.updateImage;
            app.displayInfo;
        end

        % Value changed function: XEditField, YEditField
        function XYEditFieldValueChanged(app, event)
            % Changes the crosshair position using the values from the X
            % and Y edit fields.
            X = round(app.XEditField.Value);
            Y = round(app.YEditField.Value);
            % Control for out of bounds values:
            if X < 1 || X > size(app.dataInfo.data,2)
                app.XEditField.Value = event.PreviousValue;
                return
            end
            if Y < 1 || Y > size(app.dataInfo.data,1)
                app.YEditField.Value = event.PreviousValue;
                return
            end
            % Update crossHair position:
            app.updateCrossHairPos([X,Y]);
        end

        % Value changed function: TEditField
        function TEditFieldValueChanged(app, event)
            % Callback that updates the data frame using the value from the
            % T edit field.
            
            % Get new frame index:
            fr = round(app.TEditField.Value);
            % Control for out of bounds values:
            if fr < 1 || fr > app.Slider.Limits(2)
                app.TEditField.Value = event.PreviousValue;
                return
            end
            % Update time bar:            
            app.timeFig.timeBar.XData = [round(event.Value) round(event.Value)];
            % Update frame
            app.currInfo.frame = fr;
            % Update slider:
            app.Slider.Value = fr;
            %
            app.updateImage;
            app.displayInfo;
        end

        % Value changed function: PlayButton
        function PlayButton_callback(app, event)
            % This callback plays a "movie" of image time series
            % experiments.
            % If the movie speed is not achieved, a warning is issued when
            % the button is disabled with the actual speed.
            fr0 = 1;
            t_len = size(app.dataInfo.data,3);
            % Here, we assume that th
            if app.PlayButton.Value
                app.PlayButton.Icon = 'icon_stopButton.png';
                app.Slider.Enable = 'off';
                app.T2 = timer("ExecutionMode", "fixedRate", "Period", 1/app.mov_speed, 'BusyMode', 'drop');
                app.T2.TimerFcn= @playMov;
                app.T2.StartFcn = @setTimer;
                app.T2.start;
            else
                if isvalid(app.T2)
                    app.PlayButton.Icon = 'icon_playButton.png';
                    app.Slider.Enable = 'on';
                    stop(app.T2);
                    delete(app.T2);
                end
            end
            
            %% Local callback %%
            function playMov(~,~)
                % This is the function that actually presents the frames in
                % an infinite loop.
                nextFrame = round(app.mov_speed*toc) + fr0;
                if nextFrame> t_len
                    % Reset timer
                    fr0 = 1;
                    tic;
                    nextFrame = round(app.mov_speed*toc) + fr0;
                end
                tmBar = findall(app.timeFig.axT1, 'Tag', 'TimeBar');
                tmBar.XData = double([nextFrame nextFrame]);
                app.Slider.Value = nextFrame;
                app.TEditField.Value = nextFrame;
                app.currInfo.frame = nextFrame;
                app.updateImage;
                app.updateTimeFig;drawnow
            end
            function setTimer(~,~)
                fr0 = app.currInfo.frame;
                tic;
            end
            
        end

        % Menu selected function: OpenMenu
        function OpenFile_callback(app, event)
            % Callback to open file through GUI.
            % This function opens a dialog box to open a .DAT file
            
            % Delete ROImanager app:
            if ( ~isempty(app.ROImngr_App) && isvalid(app.ROImngr_App) )
                app.ROImngr_App.invokeCloseRequestFcn;
                % If the user cancelled the operation inside ROImanager, return:
                if isvalid(app.ROImngr_App)
                    return
                end
                app.ROImanagerMenu.Enable = 'on';
            end
            
            % Delete existing event app:
            if ~isempty(app.evnt_App)
                delete(app.evnt_App)
                app.evnt_App = [];
            end
            
            if ischar(event)
                if isfolder(event)
                    [new_file, path] = uigetfile(fullfile(event, '*.dat'), 'Open a .DAT file');
                elseif isfile(event)
                    [path,file,ext] = fileparts(event);
                    new_file = [file ext];
                end
            else
                [new_file, path] = uigetfile(fullfile(app.dummyProt.SaveDir,'*.dat'), 'Open a .DAT file');
            end
            if new_file == 0
                disp('Operation cancelled by User')
                return
            end
            
            %             cd(path); % Change current directory to the folder containing the .dat file.
            % Reset image and current info structure:
            app.currInfo = struct('pT', [1 1], 'sel_pT', [1 1], 'frame', 1, 'value',0, ...
                'rep', -1, 'cond', 1);
            app.image_info = struct('imageData', [],'cmap', app.image_info.cmap,'clims',[], 'clims_def',[],...
                'pxPermm', 0, 'refPt', [0,0], 'logical_mask', []); % Keep current colormap.
            
            % Check if the current file will be saved or deleted:
            app.askUsertoSaveFile;
            %
            app.TextArea.Value = ['Please wait... Opening ' new_file ' from  ' path ]; drawnow;
            app.dummyProt.SaveDir = path; app.dummyProt.MainDir = path;
            app.inputFile = new_file;
            b_isOK = app.loadData;
            if ~b_isOK
                return
            end
            
            % Set axes parameters:
            app.setup;
            % Display Info:
            app.displayInfo;
            % Start tracking the cursor:
            app.start = true;
            % Show Image window:
            app.spatialFig.Fig.Visible = 'on';
            
            % Rebuild axis and objects' handles if ROIs are displayed
            if ~isempty(app.ROI_data.list)
                answer = questdlg('Use existing ROIs in the new data file?', 'Reuse ROI data?', 'Yes', 'No, erase ROI info', 'No, erase ROI info');
                b_del = false;
                % Check if Frame size is compatible with ROI info.
                if strcmp(answer, 'Yes')
                    % Clear all ROI handles
                    [~,yxDimIndx] = ismember(app.dataInfo.MetaMap.dim_names, {'Y', 'X'});
                    imgSz = [size(app.image_info.imageData,yxDimIndx(1)), size(app.image_info.imageData, yxDimIndx(2))];
                    if ~isempty(app.ROI_data.list(1).Name)
                        b_HasSameSize = isequaln(imgSz, size(app.ROI_data.list(1).Stats.ROI_binary_mask));
                    elseif ~isempty(app.ROI_data.mask.Handle)
                        [x,y] = app.ROI_data.mask.Handle.Shape.boundingbox;
                        mskSz = [y(2), x(2)] - .5;
                        b_HasSameSize = isequaln(imgSz, mskSz);
                    else
                        error('Cannot assess ROI size!')
                    end
                    if ~b_HasSameSize
                        w = warndlg('Can''t use existing ROI for this data because it has a different frame size. Existing ROI data will be cleared now.');
                        waitfor(w);
                        b_del = true;
                    end
                else
                    b_del = true;
                end
                
                if b_del
                    % Delete ROI handles:
                    arrayfun(@(x) delete(x), [app.ROI_data.plotHandles{:}]);
                    arrayfun(@(x) delete(x), [app.ROI_data.spatialHandles]);
                    % Clear ROI_data structure:
                    app.ROI_data = struct('list', [],'spatialHandles', [],'plotHandles',[], 'mask', struct('Handle', [], 'Shape',[]));
                    if ~isempty(app.ROImngr_App) && isvalid(app.ROImngr_App)
                        app.ROImngr_App.resetROItable;
                    end
                else
                    app.redrawROIs; % Redraw ROIs and time profiles
                end
            end
            % Show figures
            figure(app.UIFigure)
            figure(app.spatialFig.Fig)
            if strcmpi(app.timeFig.Fig.Visible, 'on')
                figure(app.timeFig.Fig)
            end
            
        end

        % Menu selected function: imagesMenu
        function Exportplots_callback(app, event)
            % This callback exports the current frame and time plots to two
            % separate image files.
            Path = app.dummyProt.SaveDir;
            if isempty(Path)
                Path = pwd;
            end
            % These are the options to save the plots:
            fig_filter = {'*.png', 'PNG 24-bit (*.png)'; '*.jpg', 'JPEG 24-bit  (*.jpg)';...
                '*.pdf', 'Portable Document Format  (*.pdf)';...
                '*.eps', 'Encapsulated PostScript  (*.eps)'};
            [filename, Path, indx] = uiputfile(fig_filter, 'Export Image(s)', fullfile(Path,'data.png'));
            print_dict = {'-dpng', '-djpeg', '-dpdf', '-depsc'};
            if filename == 0
                return
            end
            % Choose renderer depending on image format:
            rendererMap = containers.Map(print_dict, {'-opengl', '-opengl','-painters', '-painters'});
            
            % Get axes handles and create figure:
            fig1 = figure('Name', 'frame','Visible', 'off');
            copyobj(app.spatialFig.Fig.Children, fig1);
            print(fullfile(Path, filename), fig1, print_dict{indx}, rendererMap(print_dict{indx}), '-r300');
            close(fig1)
            if strcmp(get(app.timeFig.Fig, 'Visible'), 'on')
                fig2 = figure('Name', 'temporal_profile','Visible', 'off', 'Position', app.timeFig.Fig.Position);
                ax2 = axes(fig2);
                copyobj(app.timeFig.axT1.Children, ax2);
                lg = legend(ax2,findobj(app.timeFig.axT1,'Tag','sigLine','-or','Tag','ROILine'), 'Location', "northeastoutside", 'Interpreter','none');
                if app.b_hasTrigPlot && contains(app.ShoweventtracesMenu.Text,'hide', "IgnoreCase",true)
                    cp2 = copyobj(app.timeFig.axT2.Children, ax2);
                    uistack(cp2, 'bottom');
                end
                % Enlarge figure size to accomodate legend:
                fig2.Position(3) = fig2.Position(3) + 100;
                % Update ROI names in legend, if appliccable:
                roiLegendIndex = ~cellfun(@isempty,regexpi(lg.String,'data\d')) & strcmpi('on',{lg.PlotChildren.Visible});
                if any(roiLegendIndex)
                    lg.String(roiLegendIndex) = arrayfun(@(x) x.UserData, lg.PlotChildren(roiLegendIndex),'UniformOutput',false);
                end
                lg.String = lg.String(strcmpi('on',{lg.PlotChildren.Visible})); % Remove invisible objects from legend
                % Copy UIAxes parameters to new axis:
                app.copyAxparams(app.timeFig.axT1, ax2);
                [~,name,ext] = fileparts(filename);
                %
                print(fullfile(Path, [name '_tempProf' ext]), fig2, print_dict{indx}, rendererMap(print_dict{indx}), '-r300');
                close(fig2)
            end
            conf_msg = msgbox(['Plots saved in ' Path], 'Plots Saved!', 'help');
            waitfor(conf_msg);
            
            % Open folder with exported images:
            openFolder(Path)


        end

        % Menu selected function: fromTIFFMenu
        function ImportRawDataFromTIFF(app, event)
            % This callback reads LabeoTech binary data and saves the .DAT files to the current folder.
            % Then, the user can select a channel to display.
            
            % Ask user to save/discard current data, if applicable:
            app.askUsertoSaveFile;
            
            % Selection of LabeoTech ".bin" files:
            RawFolder = uigetdir(pwd,['Select the FOLDER containing the .TIFF file(s)']);
            if RawFolder == 0
                disp('Operation Cancelled by User')
                app.reopenFile;
                return
            end
            % Selection of LabeoTech ".bin" files:
            SaveFolder = uigetdir(RawFolder,'Select a folder to Save imaging data');
            if SaveFolder == 0
                disp('Operation Cancelled by User')
                app.reopenFile;
                return
            end
            % update dummy protocol
            app.dummyProt.MainDir = RawFolder;
            app.dummyProt.SaveDir = SaveFolder;
            % Set Pipeline manager and add functions:
            ppln = PipelineManager(app.dummyProt, 'FluorescenceImaging');
            % Disable automatic skipping and Enable overwriting:
            ppln.b_ignoreLoggedFiles = true;
            ppln.b_overwriteFiles = true;
            state = [true,true];
            % For LabeoTech's data only:
            state(1) = ppln.addTask('importFromTif');
            ppln.setOpts('importFromTif',1);
            
            if ~all(state)
                uialert(app.UIFigure, 'Import pipeline creation failed!', 'Operation aborted!');
                app.reopenFile;
                return
            end
            % Run Data Import:
            ppln.run_pipeline;
            app.TextArea.Value = 'Importing Raw Data... Please wait';drawnow
            b_Ok = all(ppln.PipelineSummary.Completed);
            delete(ppln)
            %             waitfor(ppln)
            app.TextArea.Value = '';drawnow
            
            % User selects a .DAT file from the "Path" folder:
            if b_Ok
                app.OpenFile_callback(SaveFolder);
            else
                app.reopenFile;
            end
        end

        % Menu selected function: fromLabeoTechSystemMenu
        function ImportRawDataFromLabeoSystem(app, event)
            % This callback reads LabeoTech binary data and saves the .DAT files to the current folder.
            % Then, the user can select a channel to display.
            
            % Ask user to save/discard current data, if applicable:
            app.askUsertoSaveFile;
            
            % Selection of LabeoTech ".bin" files:
            RawFolder = uigetdir(pwd,['Select the FOLDER containing the .bin file(s)']);
            if RawFolder == 0
                disp('Operation Cancelled by User')
                app.reopenFile;
                return
            end
            % Selection of LabeoTech ".bin" files:
            SaveFolder = uigetdir(RawFolder,'Select a folder to Save imaging data');
            if SaveFolder == 0
                disp('Operation Cancelled by User')
                app.reopenFile;
                return
            end
            % update dummy protocol
            app.dummyProt.MainDir = RawFolder;
            app.dummyProt.SaveDir = SaveFolder;
            % Set Pipeline manager and add functions:
            ppln = PipelineManager(app.dummyProt, 'FluorescenceImaging');
            % Disable automatic skipping and Enable overwriting:
            ppln.b_ignoreLoggedFiles = true;
            ppln.b_overwriteFiles = true;
            state = [true,true];
            idx = strcmpi('run_ImagesClassification',{ppln.funcList.name});
            ppln.funcList(idx).info.opts.DetectEvents = false;
            ppln.funcList(idx).info.opts_def.DetectEvents = false;
            ppln.funcList(idx).info.opts_vals.DetectEvents = [true,false];
            state(1) = ppln.addTask('run_ImagesClassification');
            ppln.setOpts('run_ImagesClassification',1);
            if ppln.pipe(1).opts.DetectEvents
                ev = EventsManager(app.dummyProt.MainDir{1});
                if isempty(ev.AnalogIN)
                    uialert(app.UIFigure, {lastwarn;'Only image classification will run!'},...
                        'Failed to Detect AnalogIN data','Icon',"warning",'Modal',true);
                else
                    if isempty(ev.timestamps)
                        idx = strcmpi('getEvents',{ppln.funcList.name});
                        stimChanList = setdiff(ev.AIChanList, {'CameraTrig', 'CameraTrig2'}, 'stable');
                        % Update stim channel names:
                        ppln.funcList(idx).info.opts.StimChannel = stimChanList{1};
                        ppln.funcList(idx).info.opts_def.StimChannel = stimChanList{1};
                        ppln.funcList(idx).info.opts_vals.StimChannel  = stimChanList;
                        % Add to pipeline and set opts:
                        state(2) = ppln.addTask('getEvents');
                        ppln.setOpts('getEvents',1);
                    else
                        ev.saveEvents(app.dummyProt.SaveDir);
                    end
                end
            end
            
            if ~all(state)
                uialert(app.UIFigure, 'Import pipeline creation failed!', 'Operation aborted!');
                app.reopenFile;
                return
            end
            % Run Data Import:
            ppln.run_pipeline;
            app.TextArea.Value = 'Importing Raw Data... Please wait';drawnow
            b_Ok = all(ppln.PipelineSummary.Completed);
            delete(ppln)
            %             waitfor(ppln)
            app.TextArea.Value = '';drawnow
            
            %%%% Special section %%%%%
            % For Dual-Camera imaging systems, perform coregistration of
            % data from camera #2, if the TFORM file doesn't exist:
            if isfile(fullfile(app.dummyProt.SaveDir,'AcqInfos.mat'))
                info = load(fullfile(app.dummyProt.SaveDir, 'AcqInfos.mat'));
                if info.AcqInfoStream.MultiCam
                    % If this is a dual-cam system, look for tform data:
                    figure(app.UIFigure);
                    w = uiprogressdlg(app.UIFigure,'Message','Running Dual Camera coregistration tool...','Title','Please wait','Indeterminate','on');
                    app.Coreg2Cams_App = DataViewer_Coreg2Cams(app,'CoregistrationMode');
                    waitfor(app.Coreg2Cams_App)
                    if isfile(app.tformFile)
                        % Load tform file and perform coregistration
                        tf = load(app.tformFile);
                        w.Message = 'Applying coregistration to data from camera #2...';
                        [status,warnmsg] = applyTform2Cams(app.dummyProt.SaveDir,tf.tform, tf.tformInfo);
                        if ~status
                            delete(w)
                            figure(app.UIFigure);
                            uialert(app.UIFigure, warnmsg, 'Failed to perform coregistration','Icon', "warning", 'CloseFcn','uiresume(gcbf)')
                            uiwait(app.UIFigure);
                        else
                            w.Message ='Done!';pause(.8);
                        end
                    end
                    delete(app.Coreg2Cams_App);
                    delete(w);
                end
            end
            
            % User selects a .DAT file from the "Path" folder:
            if b_Ok
                app.OpenFile_callback(SaveFolder);
            else
                app.reopenFile;
            end
        end

        % Menu selected function: movieMenu
        function Exportmovie_callback(app, event)
            % This callback exports the displayed data as a movie file.
            % The displayed data is the image time series {Y,X,T} that is
            % currently shown in the image and time windows.
            if ~isstruct(app.dataInfo.MetaMap)
                [~,movName,~] = fileparts(app.dataInfo.MetaMap.Properties.Source);
            else
                movName = 'data';
            end
            [file,path] = uiputfile({'*.avi', 'Uncompressed AVI (*.avi)'; '*.mp4','MPEG-4 (*.mp4)'}, 'Save movie as...', [movName, '_movie.avi']);
            if file == 0
                disp('Operation cancelled by User')
                return
            end
            % Confirm movie speed:
            answer = uiconfirm(app.UIFigure,['The current movie frame rate is : ' num2str(app.mov_speed) ' frames per second. Continue?'],'Export movie', 'Options',{'Yes','Cancel'}, 'CancelOption',2);
            if ~strcmpi(answer,'yes')
                return
            end
            disp('Exporting movie now...')
            % Hide Image and Temporal signal windows:
            app.spatialFig.Fig.Visible = 'off';
            app.timeFig.Fig.Visible = 'off';
            % Hide Event figure:
            if app.expType == 2
                app.evnt_App.UIFigure.Visible = 'off';
            end
            % Instantiate movie writer object:
            if endsWith(file, '.avi')
                profile = 'Uncompressed AVI';
            elseif endsWith(file, '.mp4')
                profile = 'MPEG-4';
            else
                errdgl = errordlg('Invalid Movie format!', 'Failed to create movie');
                waitfor(errdgl)
                return
            end
            writerObj = VideoWriter(fullfile(path,file), profile); % Name it.
            writerObj.FrameRate = app.mov_speed; % How many frames per second.
            if strcmp(profile, 'MPEG-4')
                writerObj.Quality = 100;
            end
            open(writerObj);
            w = waitbar(0,'Creating movie...');w.Name = 'Please wait...';
            pause(1);
            % Create figure with image and temporal signals:
            f = figure('Name', 'tempFig', 'Visible', 'on', 'CloseRequestFcn',@app.DoNotCloseFig);
            f.WindowState = 'maximized';
            % Create axes:
            s1 = subplot(3,2,[1:4]); % Image axis
            s2 = subplot(3,2,[5 6]); % Temporal profile axis
            % Set axes parameters:
            set(s1,'YDir', 'reverse', 'XLim', app.spatialFig.ax.XLim, 'YLim', app.spatialFig.ax.YLim,...
                'DataAspectRatio',app.spatialFig.ax.DataAspectRatio, 'Colormap', app.spatialFig.ax.Colormap, ...
                'CLim', app.spatialFig.ax.CLim);
            set(s2,'XLim', app.timeFig.axT1.XLim, 'YLim', app.timeFig.axT1.YLim, 'XTick', app.timeFig.axT1.XTick, ...
                'XTickLabel', app.timeFig.axT1.XTickLabel);
            axis(s1,'off')
            s2.XLabel.String = app.timeFig.axT1.XLabel.String;
            s2.YLabel.String = 'Signal Amplitude';
            title_prefix ='';
            if app.expType == 2
                % If the movie is from a data split by Events, put the
                % event info on the figure title.
                if app.evnt_App.RepetitionDropDown.Value == -1
                    title_prefix = strjoin([app.evnt_App.ConditionDropDown.Items(app.evnt_App.ConditionDropDown.Value), {'#Average'}], '--');
                else
                    title_prefix = strjoin([app.evnt_App.ConditionDropDown.Items(app.evnt_App.ConditionDropDown.Value),...
                        {['Rep#' app.evnt_App.RepetitionDropDown.Items{app.evnt_App.RepetitionDropDown.Value}]}], '--');
                end
            end
            % Add colorbar to image:
            colorbar(s1);
            copyobj(app.spatialFig.ax.Children, s1);
            copyobj(app.timeFig.axT1.Children,s2, 'legacy')
            lg = legend(s2,findobj(app.timeFig.axT1,'Tag','sigLine','-or','Tag','ROILine'), 'Location', "northeastoutside", 'Interpreter','none');
            if app.b_hasTrigPlot  && contains(app.ShoweventtracesMenu.Text,'hide', "IgnoreCase",true)
                cp2 = copyobj(app.timeFig.axT2.Children, s2, 'legacy');
                uistack(cp2, 'bottom');
            end
            % Update ROI names in legend, if appliccable:
            roiLegendIndex = ~cellfun(@isempty,regexpi(lg.String,'data\d'));
            if any(roiLegendIndex)
                lg.String(roiLegendIndex) = arrayfun(@(x) x.UserData, lg.PlotChildren(roiLegendIndex),'UniformOutput',false);
            end
            lg.String = lg.String(strcmpi('on',{lg.PlotChildren.Visible})); % Remove invisible objects from legend
            imgMov = findobj(s1,'Tag','MyImg');
            imgOrig = findobj(app.spatialFig.ax,'Tag','MyImg');
            tmLnMov = findobj(s2,'Tag','TimeBar');
            tmLnOrig = findobj(app.timeFig.axT1, 'Tag','TimeBar');
            for i = 1:size(app.dataInfo.data,3)
                % Update frame on DataViewer:
                app.SliderValueChanging(struct('Value', i))
                app.SliderValueChanged(struct('Value', i))
                % Update frame on Movie figure:
                imgMov.CData = imgOrig.CData;
                tmLnMov.XData = tmLnOrig.XData;
                sgtitle(f,[title_prefix ' Frame #' num2str(i)],'Interpreter', 'none');
                % Write frame to movie file:
                writeVideo(writerObj, getframe(f));
                waitbar(i/size(app.dataInfo.data,3),w, ['Exporting frame ' num2str(i) '/' num2str(size(app.dataInfo.data,3))]);
            end
            close(writerObj); % Saves the movie.
            waitbar(1,w,'Finished!');
            pause(1)
            delete(f)
            delete(w)
            % Make figures reappear:
            app.spatialFig.Fig.Visible = 'on';
            app.timeFig.Fig.Visible = 'on';
            % Show Event figure:
            if app.expType == 2
                app.evnt_App.UIFigure.Visible = 'on';
            end
            disp('Finished exporting movie!')
            disp(['Movie saved in folder: ' path ' as "' file '"']);
            openFolder(path); % Open folder outside Matlab
        end

        % Menu selected function: datatoTIFFMenu
        function ExportDataToTIFF_callback(app, event)
            % This callback saves the data as a .TIF file using the function "run_ConvertToTiff"
            
            % Instantiate PipelineManager Object:
            ppObj = PipelineManager(app.dummyProt, 'FluorescenceImaging');
            % Load data in manager:
            try
                metaData = load(app.dataInfo.MetaMap.Properties.Source);
            catch
                metaData = app.dataInfo.MetaMap;
            end
            ppObj.loadDataFromDummyProtocol(app.dataInfo.Map.Data.data,metaData);
            ppObj.addTask('run_ConvertToTiff');
            % Run pipeline
            ppObj.run_pipeline;
            if all(ppObj.PipelineSummary.Completed)
                openFolder(app.dummyProt.SaveDir)
            end
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            % This function allows the user to save the current .dat file
            % with a different name.
            
            folder = app.dummyProt.SaveDir;
            [fileOut, Path] = uiputfile(fullfile(folder,'*.dat'), 'Save .DAT file as...');
            if fileOut == 0
                return
            end
            app.TextArea.Value = 'Saving file... Please wait.'; drawnow;
            % For temporary files :
            if ~isempty(app.tmpFile) && ~isfile(fullfile(folder, app.inputFile))
                % Rename temporary file:
                movefile(fullfile(folder, app.tmpFile), fullfile(Path, fileOut));
                movefile(fullfile(folder, strrep(app.tmpFile,'.dat','.mat')), fullfile(Path, strrep(fileOut, '.dat','.mat')));
            elseif ~isstruct(app.dataInfo.Map) && isfile(fullfile(folder, app.inputFile))
                % Copy existing file:
                copyfile(fullfile(folder, app.inputFile), fullfile(Path, fileOut));
                copyfile(fullfile(folder, strrep(app.inputFile,'.dat','.mat')), fullfile(Path, strrep(fileOut, '.dat','.mat')));
            else
                % Create file from data stored in RAM:
                save2Dat(fullfile(Path,fileOut), app.dataInfo.Map.Data.data, app.dataInfo.MetaMap)
            end
            
            app.inputFile = fileOut;
            app.dummyProt.SaveDir = Path; app.dummyProt.MainDir = Path;
            % Load tmpFile
            app.checkInput;
            % Set axes parameters and load Data:
            app.loadData;
            app.setup;
            % Display Info:
            app.displayInfo;
            % Put figures in front:
            figure(app.UIFigure)
            figure(app.spatialFig.Fig)
            figure(app.timeFig.Fig)
            msgbox(['Data saved as "' fileOut '" in "' Path '"']);
            
        end

        % Menu selected function: DataProcessingMenu
        function DataProcessingMenuSelected(app, event)
            % This callback launches  a mini pipeline app, so the user can
            % pre-process the current data.
            % Instantiate PipelineManager Object:
            if isempty(app.pipeObj )|| ~isvalid(app.pipeObj)
                % Create new instance of PipelineManager
                app.pipeObj = PipelineManager(app.dummyProt,'FluorescenceImaging');
                %             else
                %                 % Only reset the pipeline of existing instance
                %                 app.pipeObj.reset_pipe;
            end
            % Configure pipeline:
            pipeBuilderObj = PipelineConfig_dlgBox(app,app.pipeObj,'DataViewer');
            uiwait(pipeBuilderObj.UIFigure); % The function "runPipeline" will be called from the "pipelineBuilderObj"!
        end

        % Menu selected function: ImageoptionsMenu
        function SetPlotOptions_callback(app, event)
            % Launches the imagApps_Opts app to set the current image's
            % colormap.
            delete(app.opts_App);
            app.opts_App = imagApps_Opts(app, 'cmap');
            app.ImageoptionsMenu.Enable = 'off';
            uiwait(app.opts_App.UIFigure);
            app.ImageoptionsMenu.Enable = 'on';
        end

        % Menu selected function: PlaybackspeedMenu
        function SetPlaybackspeed_callback(app, event)
            % This callback creates an uifigure with a spinner object so
            % the user can select the playback speed of the movie.
            uf = uifigure('Name', 'Set playback speed', 'NumberTitle', 'off','Position',[0 0 280,100], 'CreateFcn', {@movegui, 'center'}, 'Visible','off');
            uilabel('Text', 'movie speed:', 'Parent', uf, 'Position',[30 40 80 40]);
            spn = uispinner("Limits",[1 300], 'Position',[105 46 80 25], 'Parent', uf, 'RoundFractionalValues',"on",...
                'ValueDisplayFormat','%.0f fps');
            spn.Value = app.mov_speed;
            uibutton('Parent', uf,'BackgroundColor', [0 0.9 0],'Text', 'Ok', 'Position',[205 20 50 34],'ButtonPushedFcn', @saveMovSpeed);
            uf.Visible = 'on';
            %% Local function %%
            function saveMovSpeed(~,~)
                app.mov_speed = spn.Value;
                delete(uf);
            end
        end

        % Menu selected function: ViewDataHistoryMenu
        function ViewDataHistoryMenuSelected(app, event)
            % This callback creates a figure containng a table showing the
            % "dataHistory" variable in the data's meta data file.
            app.ViewDataHistoryMenu.Enable = 'off';
            fig = uifigure('Name', 'Data''s Analysis function History', 'Visible', 'off');movegui(fig, 'center');
            tab = uitable(fig);
            tab.OuterPosition = [10 10 (fig.InnerPosition(3)-10) (fig.InnerPosition(4)-10)];
            % Transform "dataHistory" structure as table:
            fn = intersect({'funcStr'}, fieldnames(app.dataInfo.MetaMap.dataHistory),'stable');
            dh = rmfield(app.dataInfo.MetaMap.dataHistory, fn);
            str_params = {};
            str_outFiles = {};
            for i = 1:length(dh)
                if  ~isfield(dh, 'inputFileName') || isempty(dh(i).inputFileName)
                    % Force from double to char.
                    dh(i).inputFileName = 'none';
                end
                % Organize function's params as string:
                params = dh(i).opts;
                if isempty(params)
                    C = [{'none'};{'none'}];
                else
                    fNames = fieldnames(params);
                    idxCell = cellfun(@(x) iscell(params.(x)), fNames);
                    % Concatenate non-cell values:
                    vals = cellfun(@(x) num2str(params.(x)), fNames(~idxCell), 'UniformOutput',false);
                    C = [fNames(~idxCell), vals];
                    % Unwrap cell content of parameters:
                    idxCell = find(idxCell);
                    for ii =1:length(idxCell)
                        str = strjoin(cellfun(@num2str, params.(fNames{idxCell(ii)}), 'UniformOutput',false), ';/n');
                        C = [C; [fNames{ii}, {str}]];
                    end
                end
                C = C';
                str_params{i} = sprintf('%s : %s\n', C{:});
                % Do the same to output files:
                if ~isfield(dh(i), 'outFileName') || isempty(dh(i).outFileName)
                    dh(i).outFileName = 'none';
                    str_outFiles{i} = 'none';
                elseif iscell(dh(i).outFileName)
                    [~,fList,ext] = cellfun(@fileparts, dh(i).outFileName,'UniformOutput',false);
                    str_outFiles{i} = strjoin(cellfun(@(x,y) [x,y], fList,ext, 'UniformOutput',false), '\n');
                else
                    str_outFiles{i} = dh(i).outFileName;
                end
            end
            info = struct2table(dh, 'AsArray',true);
            info.Parameters = str_params';
            info.OutputFiles = str_outFiles';
            info.opts = [];
            info.outFileName = [];
            tab.Data = info;
            tab.ColumnEditable = false;
            fig.Visible = 'on';
            fig.Position(3) = fig.Position(3) + 1;
            waitfor(fig)
            app.ViewDataHistoryMenu.Enable = 'on';
        end

        % Callback function
        function ExtractdatafromROIMenuSelected(app, event)
            % This callback calls the function "getDataFromROI.m" from
            % umIT. In order to work, a temporary ROI file will be created
            % in the save directory and deleted at the end of the execution
            % of this callback.
            if isempty(app.ROI_data)
                disp('No ROI info found!')
                app.app.ROIoptionsMenu.Enable = 'off';
                return
            end
            % Find Save directory:
            [savedir, ~, ~] = fileparts(app.inputFile);
            if isempty(savedir)
                savedir = pwd;
            end
            %
            [outFile, Path] = uiputfile(fullfile(savedir, '*.mat'), 'Save Data from ROI to...', fullfile(savedir, 'out.mat'));
            if outFile == 0
                disp('Operation Cancelled by User')
                return
            end
            % Open ROI options menu:
            app.ROIopts_App = DataViewer_ROIopts(app,'ExtractDataFromROI');
            waitfor(app.ROIopts_App);
            if isempty(app.roi_opts_params)
                return
            end
            % Create temporary ROI file with existing ROI information
            roiFileName = app.createTempROI(savedir);
            % Run getDataFromROI function
            opts = struct('ROImasks_filename', roiFileName, 'SpatialAggFcn', app.roi_opts_params.aggfcn);
            out = getDataFromROI(app.dataInfo.Map.Data.data, app.dataInfo.MetaMap, opts);
            % Save data to .mat file:
            disp('Saving data to .mat file...')
            save(fullfile(Path,outFile),'-struct', 'out', '-v7.3');
            % Remove temporary ROI file:
            delete(roiFileName);
            disp('Done!')
            msgbox(['ROI data saved as "' fullfile(Path,outFile) '"']);
        end

        % Callback function
        function GenerateCorrelationMatrixMenuSelected(app, event)
            % This callback calls the function "genCorrelationMatrix.m" from
            % umIT. In order to work, a temporary ROI file will be created
            % in the save directory and deleted at the end of the execution
            % of this callback.
            if isempty(app.ROI_data)
                disp('No ROI info found!')
                app.app.ROIoptionsMenu.Enable = 'off';
                return
            end
            % Find Save directory:
            [savedir, ~, ~] = fileparts(app.inputFile);
            if isempty(savedir)
                savedir = pwd;
            end
            %
            [outFile, Path] = uiputfile(fullfile(savedir, '*.mat'), 'Save Data from ROI to...', fullfile(savedir, 'out.mat'));
            if outFile == 0
                disp('Operation Cancelled by User')
                return
            end
            
            % Open ROI options menu:
            app.ROIopts_App = DataViewer_ROIopts(app,'GenCorrMat');
            waitfor(app.ROIopts_App);
            if isempty(app.roi_opts_params)
                return
            end
            % Create temporary ROI file with existing ROI information
            roiFileName = app.createTempROI(savedir);
            % Run "genCorrelationMatrix" function:
            opts = struct('ROImasks_filename', roiFileName, 'CorrAlgorithm', app.roi_opts_params.corrAlgo,...
                'SpatialAggFcn', app.roi_opts_params.aggfcn,'b_FisherZ_transform', app.roi_opts_params.b_applyZFisher, ...
                'b_genSPCMaps', app.roi_opts_params.b_genSPCMaps);
            out = genCorrelationMatrix(app.dataInfo.Map.Data.data, app.dataInfo.MetaMap, opts);
            % Save data to .mat file:
            save(fullfile(Path,outFile),'-struct', 'out', '-v7.3');
            % Remove temporary ROI file:
            delete(roiFileName);
            % Display message:
            disp('Done!')
            msgbox(['Data saved as ' fullfile(Path, outFile)]);
        end

        % Menu selected function: ShoweventtracesMenu
        function ShoweventtracesMenuSelected(app, event)
            % Show/Hides Trigger traces figure:
            h = findall(app.timeFig.Fig, 'Type', 'Legend');
            if contains(event.Source.Text, 'show', 'IgnoreCase',true)
                arrayfun(@(x) set(x, 'Visible', 'on'), app.timeFig.axT2.Children)
                set(h, 'Visible', 'on')
                app.ShoweventtracesMenu.Text = replace(event.Source.Text, 'Show', 'Hide');
            else
                arrayfun(@(x) set(x, 'Visible', 'off'), app.timeFig.axT2.Children)
                set(h, 'Visible', 'off')
                app.ShoweventtracesMenu.Text = replace(event.Source.Text, 'Hide', 'Show');
            end
        end

        % Menu selected function: MultiplotsMenu
        function MultiplotsMenuSelected(app, event)
            % Launches the "DataViewer_multiPlots" app
            app.multiPlot_App = DataViewer_multiPlots(app);
            % Disable menu to avoid opening the app more than one. The menu
            % will be re-enabled from the CloseFcn of DataViewer_multiPlots
            % app.
            app.MultiplotsMenu.Enable = 'off';
        end

        % Menu selected function: GotoWikiPageMenu
        function HelpMenuSelected(app, event)
            % This callback opens a web browser pointing to the
            % Documentation section of "DataViewer" in umIT's wiki page.
            url = 'https://labeotech.github.io/Umit/documentation/userDocs/apps/DataViewer.html';
            try
                webread(url); % Just to see if there is an internet connection.
                web(url, '-browser');
            catch
                errordlg({'Cannot access online documentation. Check internet connection and try again or go to:', url}, 'Connection failed')
                % Display same message in command window to facilitate copy/paste of url:
                error(['Cannot access online documentation. Check internet connection and try again or go to: ', url]);
            end
        end

        % Menu selected function: ROImanagerMenu
        function ROImanagerMenuSelected(app, event)
            % This callback launches the ROImamanger app with the image
            % axis as input.
            % Set logical mask before:
            if isempty(app.image_info.logical_mask)
                app.image_info.logical_mask = true([size(app.dataInfo.data,1), size(app.dataInfo.data,2)]);
            end
            % Launch ROImanager App:
            app.ROImngr_App = ROImanager(app, app.spatialFig.ax);
            % Create event listener to detect changes in ROI_list:
            app.el_ROImngr_ROIs = addlistener(app.ROImngr_App, 'updateROI', @app.drawROIProfile);
            app.el_ROImngr_imgParams = addlistener(app.ROImngr_App, 'updateImgParams', @app.updateImageInfo);
            app.el_ROImngr_msk = addlistener(app.ROImngr_App, 'updateMask', @app.updateMask);
            app.ROImanagerMenu.Enable = 'off';
        end

        % Menu selected function: ReaddocumentationMenu
        function ReaddocumentationMenuSelected(app, event)
            % Opens the local "DataViewer" documentation
            htmlFile = which('user_doc_index.html','-all');
            if ~isempty(htmlFile)
                htmlFile(contains(htmlFile,'_site')) = []; % Remove copy formatted for on-line documentation.
                if ~iscell(htmlFile)
                    htmlFile = {htmlFile};
                end
                web(htmlFile{1},'-browser'); % BrunoO (17-10-2023): Now using OS browser instead due to issues with .mp4 files.
            end
        end

        % Menu selected function: ROItracesMenu
        function ROItracesMenuSelected(app, event)
            % Shows executes the "extractDataFromROI" function on the
            % displayed data and launches the "PlotLongData" app for
            % visualization.
            
            % Check if the ROI exists:
            if isempty(app.ROI_data)
                disp('No ROI info found!')
                app.ROIoptionsMenu.Enable = 'off';
                return
            end
            % Open ROI options menu:
            app.ROIopts_App = DataViewer_ROIopts(app,'ExtractDataFromROI');
            waitfor(app.ROIopts_App);
            if isempty(app.roi_opts_params)
                return
            end
            % Create temporary ROI file with existing ROI information
            roiFileName = app.createTempROI(app.dummyProt.SaveDir);
            protObj = app.dummyProt.extractFilteredObjects(3);
            % Run getDataFromROI function
            opts = struct('ROImasks_filename', roiFileName, 'SpatialAggFcn', app.roi_opts_params.aggfcn);
            if ~isstruct(app.dataInfo.MetaMap)
                metaData = load(app.dataInfo.MetaMap.Properties.Source);
            else
                metaData = app.dataInfo.MetaMap;
            end
            out = getDataFromROI(app.dataInfo.Map.Data.data, metaData, opts, protObj{:});
            % Save data to temporary .mat file:
            tmpFileName = ['tmpFile_' num2str(randi(9999),'0%i') '.mat'];
            save(fullfile(app.dummyProt.SaveDir,tmpFileName),'-struct', 'out', '-v7.3');
            % Create StatsManager object:
            objList = app.dummyProt.extractFilteredObjects(3);
            obs_list = {app.ROI_data.list.Name};
            list_of_groups = repmat({'DataViewer'},size(objList));
            statsObj = StatsManager(objList, obs_list,list_of_groups,tmpFileName);
            % Launch Plotter
            if any(strcmpi('E',app.dataInfo.MetaMap.dim_names))
                XGPnames = {'Event','ROI','Group'};
            else
                XGPnames = {'Acquisition','ROI','Group'};
            end
            plotter = PlotLongData(statsObj, XGPnames,app.roi_opts_params.aggfcn);
            waitfor(plotter)
            % Remove temporary ROI file:
            delete(roiFileName);
            % Remove temporary .Mat file:
            delete(fullfile(app.dummyProt.SaveDir,tmpFileName));
        end

        % Menu selected function: CorrelationMatrixMenu
        function CorrelationMatrixMenuSelected(app, event)
            % Check if the ROI exists:
            if isempty(app.ROI_data)
                disp('No ROI info found!')
                app.ROIoptionsMenu.Enable = 'off';
                return
            end
            % Open ROI options menu:
            app.ROIopts_App = DataViewer_ROIopts(app,'GenCorrMat');
            waitfor(app.ROIopts_App);
            if isempty(app.roi_opts_params)
                return
            end
            % Create temporary ROI file with existing ROI information
            roiFileName = app.createTempROI(app.dummyProt.SaveDir);
            % Run getDataFromROI function
            opts = struct('ROImasks_filename', roiFileName, 'SpatialAggFcn', app.roi_opts_params.aggfcn);
            if ~isstruct(app.dataInfo.MetaMap)
                metaData = load(app.dataInfo.MetaMap.Properties.Source);
            else
                metaData = app.dataInfo.MetaMap;
            end
            tmpFileName = ['tmpFile_' num2str(randi(9999),'0%i') '.mat'];
            % Run "genCorrelationMatrix" function:
            opts = struct('ROImasks_filename', roiFileName, 'CorrAlgorithm', app.roi_opts_params.corrAlgo,...
                'SpatialAggFcn', app.roi_opts_params.aggfcn,'b_FisherZ_transform', app.roi_opts_params.b_applyZFisher, ...
                'SPCMapFileName', 'none');
            out = genCorrelationMatrix(app.dataInfo.Map.Data.data, metaData, app.dummyProt.SaveDir, opts);
            % Save data to temporary .mat file:
            
            save(fullfile(app.dummyProt.SaveDir,tmpFileName),'-struct', 'out', '-v7.3');
            % Create StatsManager object:
            objList = app.dummyProt.extractFilteredObjects(3);
            obs_list = {app.ROI_data.list.Name};
            list_of_groups = repmat({'DataViewer'},size(objList));
            statsObj = StatsManager(objList, obs_list,list_of_groups,tmpFileName);
            % Launch Plotter
            plotter = PlotCorrMatrix(statsObj);
            waitfor(plotter)
            % Remove temporary ROI file:
            delete(roiFileName);
            % Remove temporary .Mat file:
            delete(fullfile(app.dummyProt.SaveDir,tmpFileName));
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            % Focus associated windows when this one is focused.
            myFigs = [app.spatialFig.Fig, app.timeFig.Fig];
            for ii = 1:length(myFigs)
                if strcmpi(myFigs(ii).Visible, 'on')
                    figure(myFigs(ii))
                end
            end
            % For extra windows:
            if ~isempty(app.opts_App) && isvalid(app.opts_App)
                figure(app.opts_App.UIFigure)
            end
            if ~isempty(app.evnt_App)
                figure(app.evnt_App.UIFigure)
            end
            figure(app.UIFigure);
        end

        % Menu selected function: ManageeventsMenu
        function ManageeventsMenuSelected(app, event)
            % Launches the Event Manager app for visualization of AnalogIN
            % from recordings using the LabeoTech Imaging Systems.
            
            % Check for existence of analogIN data in raw directory:
            aiFiles = dir(fullfile(app.dummyProt.MainDir{1},'ai_*.bin'));
            if isempty(aiFiles)
                % Ask for User input:
                folder = uigetdir(app.dummyProt.MainDir{1}, 'Select folder with analog binary files (e.g., ai_0000x.bin)');
                if folder == 0
                    disp('Operation cancelled by User')
                    return
                end
                % Recheck:
                aiFiles = dir(fullfile(folder,'ai_*.bin'));
                if isempty(aiFiles)
                    uialert(app.UIFigure, ['Analog binary files not found in folder "' folder '"'],'Failed to find analog data', 'Modal',true, 'Icon',"error")
                    return
                end
                % Update main directory:
                app.dummyProt.MainDir = folder;
            end
            w = uiprogressdlg(app.UIFigure, 'Message','Running Events Manager...','Indeterminate',"on");
            app.evntManager_App = DataViewer_evntManager(app.dummyProt.MainDir{1}, app.dummyProt.SaveDir);
            waitfor(app.evntManager_App);
            if ~isempty(app.dataInfo)
                app.plotTriggers; % Update event patches in time plot.
            end
            close(w)
        end

        % Callback function
        function EnableFFTViewMenuSelected(app, event)
            % Calculates the FFT of an image time series and plots the
            % amplitude and phase maps as well as the frequency plot.
            %
            app.Slider.Value = 1; % Reset slider.
            if startsWith(app.EnableFFTViewMenu.Text, 'enable', 'IgnoreCase',true)
                w = uiprogressdlg(app.UIFigure, 'Message','Calculating FFT...','Indeterminate',"on");
                % Calculate fft over time and update data
                app.dataInfo.data = fft(app.dataInfo.data,[],3);
                app.dataInfo.dim_names{3} = 'F';
                app.expType = 6;
                app.dataInfo.iscomplex = true;
                app.EnableFFTViewMenu.Text = strrep(app.EnableFFTViewMenu.Text, 'Enable','Disable');
                
                close(w)
            else
                app.freqVec = [];
                app.EnableFFTViewMenu.Text = strrep(app.EnableFFTViewMenu.Text, 'Disable','Enable');
                if isa(app.dataInfo.Map, 'memmapfile')
                    % Reopen the file:
                    app.inputFile = app.dataInfo.Map.Filename;
                    app.checkInput;
                    % Set axes parameters:
                    app.loadData;
                    app.setup;
                    % Display Info:
                    app.displayInfo;
                else
                    w = uiprogressdlg(app.UIFigure, 'Message','Calculating inverse FFT...','Indeterminate',"on");
                    % Perform inverse FFT from data in RAM:
                    app.dataInfo.data = ifft(app.dataInfo.data,[],3);
                    app.dataInfo.dim_names{3} = 'T';
                    app.expType = 1;
                    app.dataInfo.iscomplex = false;
                    close(w)
                end
            end
            
            % Update axes properties;
            app.setup;
            app.redrawROIs;
        end

        % Menu selected function: OiSDualCamCoregistrationMenu
        function OiSDualCamCoregistrationMenuSelected(app, event)
            %   This callback launches a GUI to generate and manage coregistration TFORM between cameras of
            %   a Dual-camera imaging system from LabeoTech.
            w = uiprogressdlg(app.UIFigure, 'Message','Runnning Dual Camera coregistration utility...', 'Indeterminate','on');
            app.Coreg2Cams_App = DataViewer_Coreg2Cams(app,'ResultsViewMode');
            waitfor(app.Coreg2Cams_App);
            delete(w);
        end

        % Menu selected function: ManualCoregistrationMenu
        function ManualCoregistrationMenuSelected(app, event)
            % Launches the "manualAlignFrames" app with the current data as
            % the "moving" frame.
            
            % Delete memmapping of current file:
            app.dataInfo.data = []; app.dataInfo.Map = []; app.dataInfo.MetaMap = [];
            
            % Launch coregistration app:
            w = uiprogressdlg(app.UIFigure,'Message','Running Manual Coregistration tool...','Title','','Indeterminate','on');
            h = manualAlignFrames('',fullfile(app.dummyProt.SaveDir, app.inputFile));
            waitfor(h)
            % Re-open input file (we assume that the file was changed):
            app.reopenFile;
            % Close progress bar
            delete(w);
        end

        % Menu selected function: ConverttoTIFFMenu
        function ConverttoTIFFMenuSelected(app, event)
            % ConvertDATtoTIFFMenuSelected - Handles the user's selection to convert .DAT files to TIFF.
            %
            % This function allows the user to select a parent folder and a list of .DAT files
            % for conversion to TIFF format using the ConvertToTiff.m function.
            %
            % Steps:
            % 1. Select the parent folder for .DAT file search.
            % 2. List all subfolders containing .dat files.
            % 3. Display a list of folders for the user to choose from.
            % 4. Update the list of selected folders.
            % 5. Select .dat files from the chosen folders.
            % 6. Display a list of .dat files for the user to choose from.
            % 7. Update the list of selected .dat files.
            % 8. Generate a temporary script for batch processing.
            % 9. Execute the script on the selected .dat files.
            % 10. Delete the temporary script.
            %
            % Notes:
            % - It relies on external functions like getFileList and ConvertToTiff.
            % - A temporary script is generated for batch processing, executed, and then deleted.
            
            % Select Parent folder:
            path = uigetdir(app.dummyProt.MainDir{1},'Select Parent Folder for .DAT File Search');
            if path == 0
                disp('Operation cancelled by User')
                return
            end
            % Select Subfolders
            fListRaw = dir(fullfile(path, '**','img*_00000.bin'));
            fListDat = dir(fullfile(path,'**','*.dat'));
            fList = vertcat(fListRaw,fListDat);
            if isempty(fList)
                figure(app.UIFigure);
                uialert(app.UIFigure, 'No convertible files found in selected folder(s)!','File(s) not found!','Icon',"warning","CloseFcn",'uiresume(gcbf)')
                uiwait(gcbf)
                return
            end
            fList = unique({fList.folder}','stable');
            % Show list of folders to process:
            [indx,tf] = listdlg('ListString',fList,'PromptString','Folder List:','SelectionMode','multiple','ListSize',[300 400],'Name','Select Folders to process');
            if tf == 0
                disp('Operation Cancelled by User')
                return
            end
            % Update list
            fList = fList(indx);
            % Select file
            fileList = {};
            
            for ii = 1:length(fListDat)
                thisList = getFileList(fListDat(ii).folder,'.dat');
                % Limit the list to images and image time series (To be
                % removed in a later version):
                idx = false(size(thisList));
                for jj = 1:length(thisList)
                    md = matfile(fullfile(fListDat(ii).folder, strrep(thisList{jj},'.dat','.mat')));
                    if isprop(md,'dim_names')
                        idx(jj) = all(ismember(md.dim_names,{'X','Y','T'}));
                    end
                end
                fileList = [fileList;thisList(idx)];%#ok
            end
            fileList = [{'Raw Data Files (img_0000x.bin)'};unique(fileList,'stable')];
            
            % Show list of folders to process:
            [indx,tf] = listdlg('ListString',fileList,'PromptString','File List:','SelectionMode','multiple','ListSize',[300 400],'Name','Select Files to process');
            if tf == 0
                disp('Operation Cancelled by User')
                delete(w)
                return
            end
            datfileList = fileList(indx(indx>1));
            % Batch Process
            
            % Generate temporary script for batch processing:
            if isdeployed
                % For executable version of DataViewer, save script as
                % text:
                ext = '.txt';
            else
                ext = '.m';
            end
            tempScriptName = fullfile(path, ['ConversionFromDATtoTIFFformat' ext]);
            str = sprintf('%%%% Script to convert .DAT and/or Raw (binary) files to TIF format:\n%% Set list of files to convert:\n');
            extList = {'.tif'};
            if any(indx == 1)
                str = [str, sprintf('%% Execute conversion of raw binary files in current folder:\nConvertBinToTiff(pwd);\n')];
                extList = [{'.bin'} extList];
            end
            if any(indx > 1)
                datfileList = cellfun(@(x) ['''' x ''''],datfileList, 'uniformOutput',false);
                str = [str, sprintf('fileList = {%s};\n%% Execute conversion of .DaT files:\nConvertToTiff(pwd,fileList);\n',strjoin(datfileList,','))];
                extList = [{'.dat'} extList];
            end
            fid = fopen(tempScriptName,'w');
            fprintf(fid,'%s',str);fclose(fid);
            % Execute the script on folder list
            app.runBatchScript(fList,tempScriptName,extList)
            % Delete temporary script:
            delete(tempScriptName);
        end

        % Menu selected function: RunscriptMenu
        function RunscriptMenuSelected(app, event)
            % RunscriptMenuSelected - Execute a selected MATLAB script on multiple folders.
            %
            % This function allows the user to select a MATLAB script and a parent folder.
            % It then lists subfolders within the parent folder and executes the selected script
            % on each of them.
            %
            % Steps:
            % 1. Select a MATLAB script to execute.
            % 2. Select the parent folder where the script will be executed.
            % 3. List all subfolders within the parent folder.
            % 4. Display a list of subfolders for the user to choose from.
            % 5. Update the list of selected subfolders.
            % 6. Execute the selected script on each of the selected subfolders.
            %
            % Notes:
            % - It relies on an external function, runBatchScript, for executing scripts.
            
            % Select script to execute:
            if isdeployed
                [filename, scriptPath] = uigetfile('*.txt', 'Select Pipeline script to execute', 'myPipeScript.txt');
            else
                [filename, scriptPath] = uigetfile('*.m', 'Select MATLAB script to execute', 'myCustomScript.m');
            end
            if filename == 0
                disp('Operation canceled by User')
                return
            end
            scriptName = fullfile(scriptPath, filename);
            
            % Select Parent folder:
            path = uigetdir(app.dummyProt.MainDir{1}, 'Select Parent Folder');
            if path == 0
                disp('Operation canceled by User')
                return
            end
            
            % List subfolders within the parent folder:
            fList = dir(path);
            fList = fList([fList.isdir] == 1);
            fList(ismember({fList.name}, {'.', '..'})) = [];
            fList = fullfile({fList.folder}, {fList.name});
            if isempty(fList)
                uialert(app.UIFigure, 'Parent folder is empty!', 'Process aborted!','Modal',true,'CloseFcn','uiresume(gcbf)')
                uiwait(app.UIFigure);
                return
            end
            % Show list of folders to process:
            [indx, tf] = listdlg('ListString', fList, 'PromptString', 'Folder List:', 'SelectionMode', 'multiple', 'ListSize', [300 400], 'Name', 'Select Folders to process');
            if tf == 0
                disp('Operation Canceled by User')
                return
            end
            
            % Update the list of selected subfolders:
            fList = fList(indx);
            
            % Execute the selected script on each selected subfolder:
            app.runBatchScript(fList, scriptName, {'.dat', '.mat'});
            
        end

        % Menu selected function: GenscriptMenu
        function GenscriptMenuSelected(app, event)
            % Launches PipelineConfig_dlgBox GUI in genScript mode:
            
            if isempty(app.pipeObj )|| ~isvalid(app.pipeObj)
                % Create new instance of PipelineManager
                app.pipeObj = PipelineManager(app.dummyProt,'FluorescenceImaging');
                %             else
                %                 % Only reset the pipeline of existing instance
                %                 app.pipeObj.reset_pipe;
            else
                % Erase current pipeline. This step will ensure that the
                % user does not uses the pipeline already opened from the
                % "Data Processing" menu. The latter assumes that the input
                % data is already in RAM. By erasing the pipeline, we
                % ensure that the input data loading will be added to the
                % script.
                app.pipeObj.reset_pipe;
            end
            % Configure pipeline:
            pipeBuilderObj = PipelineConfig_dlgBox(app,app.pipeObj,'genScript');
            uiwait(pipeBuilderObj.UIFigure); % The function "runPipeline" will be called from the "pipelineBuilderObj"!
        end

        % Value changed function: ViewmodeDropDown
        function ViewmodeDropDownValueChanged(app, event)
            % Change data view mode.
            
                       
            % Call the setup function to prepare the data and GUI for the
            % selected view mode:
            if strcmpi(event.PreviousValue,'fft')
                % If the data comes from a FFT;  , before setup:
                if ~strcmpi(app.inputFile, '_DATA_')
                    % Reload the original data
                    app.loadData;                    
                else
                    % If the data was in RAM, perform the inverse FFT:
                    app.dataInfo.data = ifft(app.dataInfo.data,[],3);
                end                    
            end
            app.viewMode = lower(app.ViewmodeDropDown.Value); 
            app.setup;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'DataViewer';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequest_callback, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFile_callback, true);
            app.OpenMenu.Text = 'Open';
            app.ImportRawdataMenu = uimenu(app.FileMenu);
            app.ImportRawdataMenu.Tooltip = {'Import raw data from LabeoTech Systems'};
            app.ImportRawdataMenu.Text = 'Import Raw data';
            app.fromLabeoTechSystemMenu = uimenu(app.ImportRawdataMenu);
            app.fromLabeoTechSystemMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportRawDataFromLabeoSystem, true);
            app.fromLabeoTechSystemMenu.Text = 'from LabeoTech System';
            app.fromTIFFMenu = uimenu(app.ImportRawdataMenu);
            app.fromTIFFMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportRawDataFromTIFF, true);
            app.fromTIFFMenu.Text = 'from TIFF';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.Enable = 'off';
            app.ExportMenu.Text = 'Export ...';
            app.imagesMenu = uimenu(app.ExportMenu);
            app.imagesMenu.MenuSelectedFcn = createCallbackFcn(app, @Exportplots_callback, true);
            app.imagesMenu.Text = 'images';
            app.movieMenu = uimenu(app.ExportMenu);
            app.movieMenu.MenuSelectedFcn = createCallbackFcn(app, @Exportmovie_callback, true);
            app.movieMenu.Text = 'movie';
            app.datatoTIFFMenu = uimenu(app.ExportMenu);
            app.datatoTIFFMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportDataToTIFF_callback, true);
            app.datatoTIFFMenu.Text = 'data to .TIFF';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Tooltip = {'Opens dialog box to save current data to a .dat file.'};
            app.SaveasMenu.Enable = 'off';
            app.SaveasMenu.Text = 'Save as...';
            app.BatchprocessingMenu = uimenu(app.FileMenu);
            app.BatchprocessingMenu.Text = 'Batch processing';
            app.ConverttoTIFFMenu = uimenu(app.BatchprocessingMenu);
            app.ConverttoTIFFMenu.MenuSelectedFcn = createCallbackFcn(app, @ConverttoTIFFMenuSelected, true);
            app.ConverttoTIFFMenu.Tooltip = {'Converts LabeoTech`s binary (img_0000x.bin)  and/or .dat files to '; 'TIF format.'};
            app.ConverttoTIFFMenu.Text = 'Convert to TIFF';
            app.RunscriptMenu = uimenu(app.BatchprocessingMenu);
            app.RunscriptMenu.MenuSelectedFcn = createCallbackFcn(app, @RunscriptMenuSelected, true);
            app.RunscriptMenu.Tooltip = {'Executes a Matlab script across a list of folders.'};
            app.RunscriptMenu.Text = 'Run script';
            app.menuSep2 = uimenu(app.BatchprocessingMenu);
            app.menuSep2.Enable = 'off';
            app.menuSep2.Text = '___________________';
            app.GenscriptMenu = uimenu(app.BatchprocessingMenu);
            app.GenscriptMenu.MenuSelectedFcn = createCallbackFcn(app, @GenscriptMenuSelected, true);
            app.GenscriptMenu.Tooltip = {'Generate script from Pipeline for usage in "Run script".'};
            app.GenscriptMenu.Text = 'Gen. script';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.ImageoptionsMenu = uimenu(app.SettingsMenu);
            app.ImageoptionsMenu.MenuSelectedFcn = createCallbackFcn(app, @SetPlotOptions_callback, true);
            app.ImageoptionsMenu.Text = 'Image options';
            app.PlaybackspeedMenu = uimenu(app.SettingsMenu);
            app.PlaybackspeedMenu.MenuSelectedFcn = createCallbackFcn(app, @SetPlaybackspeed_callback, true);
            app.PlaybackspeedMenu.Text = 'Playback speed';
            app.UtilitiesMenu = uimenu(app.UIFigure);
            app.UtilitiesMenu.Tooltip = {'Coregister the current data to a .dat reference file.'};
            app.UtilitiesMenu.Text = 'Utilities';
            app.DataProcessingMenu = uimenu(app.UtilitiesMenu);
            app.DataProcessingMenu.MenuSelectedFcn = createCallbackFcn(app, @DataProcessingMenuSelected, true);
            app.DataProcessingMenu.Enable = 'off';
            app.DataProcessingMenu.Text = 'Data Processing';
            app.ViewDataHistoryMenu = uimenu(app.UtilitiesMenu);
            app.ViewDataHistoryMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewDataHistoryMenuSelected, true);
            app.ViewDataHistoryMenu.Tooltip = {'View list of analysis functions run in the data.'};
            app.ViewDataHistoryMenu.Enable = 'off';
            app.ViewDataHistoryMenu.Text = 'View Data History';
            app.ShoweventtracesMenu = uimenu(app.UtilitiesMenu);
            app.ShoweventtracesMenu.MenuSelectedFcn = createCallbackFcn(app, @ShoweventtracesMenuSelected, true);
            app.ShoweventtracesMenu.Enable = 'off';
            app.ShoweventtracesMenu.Text = 'Show event traces';
            app.ManageeventsMenu = uimenu(app.UtilitiesMenu);
            app.ManageeventsMenu.MenuSelectedFcn = createCallbackFcn(app, @ManageeventsMenuSelected, true);
            app.ManageeventsMenu.Tooltip = {'Manage events from Analog Inputs of LabeoTech Systems'};
            app.ManageeventsMenu.Text = 'Manage events';
            app.ROIoptionsMenu = uimenu(app.UtilitiesMenu);
            app.ROIoptionsMenu.Enable = 'off';
            app.ROIoptionsMenu.Text = 'View ROI data';
            app.ROItracesMenu = uimenu(app.ROIoptionsMenu);
            app.ROItracesMenu.MenuSelectedFcn = createCallbackFcn(app, @ROItracesMenuSelected, true);
            app.ROItracesMenu.Text = 'ROI traces';
            app.CorrelationMatrixMenu = uimenu(app.ROIoptionsMenu);
            app.CorrelationMatrixMenu.MenuSelectedFcn = createCallbackFcn(app, @CorrelationMatrixMenuSelected, true);
            app.CorrelationMatrixMenu.Text = 'Correlation Matrix';
            app.menuSep = uimenu(app.UtilitiesMenu);
            app.menuSep.Enable = 'off';
            app.menuSep.Text = '___________________';
            app.OiSDualCamCoregistrationMenu = uimenu(app.UtilitiesMenu);
            app.OiSDualCamCoregistrationMenu.MenuSelectedFcn = createCallbackFcn(app, @OiSDualCamCoregistrationMenuSelected, true);
            app.OiSDualCamCoregistrationMenu.Tooltip = {'Coregistration tool for LabeoTech''s imaging systems with two cameras.'};
            app.OiSDualCamCoregistrationMenu.Text = 'OiS Dual Cam Coregistration';
            app.ManualCoregistrationMenu = uimenu(app.UtilitiesMenu);
            app.ManualCoregistrationMenu.MenuSelectedFcn = createCallbackFcn(app, @ManualCoregistrationMenuSelected, true);
            app.ManualCoregistrationMenu.Text = 'Manual Coregistration';
            app.AddonsMenu = uimenu(app.UIFigure);
            app.AddonsMenu.Text = 'Add-ons';
            app.ROImanagerMenu = uimenu(app.AddonsMenu);
            app.ROImanagerMenu.MenuSelectedFcn = createCallbackFcn(app, @ROImanagerMenuSelected, true);
            app.ROImanagerMenu.Tooltip = {'App that creates/edits Regions of Interest.'};
            app.ROImanagerMenu.Text = 'ROImanager';
            app.MultiplotsMenu = uimenu(app.AddonsMenu);
            app.MultiplotsMenu.MenuSelectedFcn = createCallbackFcn(app, @MultiplotsMenuSelected, true);
            app.MultiplotsMenu.Tooltip = {'App that shows 1-3 extra data alongside the current file. Available only for 3D data.'};
            app.MultiplotsMenu.Text = 'Multi-plots';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '?';
            app.GotoWikiPageMenu = uimenu(app.Menu);
            app.GotoWikiPageMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.GotoWikiPageMenu.Text = 'Go to WikiPage';
            app.ReaddocumentationMenu = uimenu(app.Menu);
            app.ReaddocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @ReaddocumentationMenuSelected, true);
            app.ReaddocumentationMenu.Text = 'Read documentation';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.TextArea = uitextarea(app.UIFigure);
            app.CoordinatesPanel = uipanel(app.UIFigure);
            app.CoordinatesPanel.Title = 'Coordinates';
            app.XEditFieldLabel = uilabel(app.CoordinatesPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.XEditField.RoundFractionalValues = 'on';
            app.XEditField.ValueDisplayFormat = '%.0f';
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XYEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.CoordinatesPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.YEditField.RoundFractionalValues = 'on';
            app.YEditField.ValueDisplayFormat = '%.0f';
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @XYEditFieldValueChanged, true);
            app.TEditFieldLabel = uilabel(app.CoordinatesPanel);
            app.TEditFieldLabel.Text = 'T';
            app.TEditField = uieditfield(app.CoordinatesPanel, 'numeric');
            app.TEditField.Limits = [1 Inf];
            app.TEditField.RoundFractionalValues = 'on';
            app.TEditField.ValueDisplayFormat = '%.0f';
            app.TEditField.ValueChangedFcn = createCallbackFcn(app, @TEditFieldValueChanged, true);
            app.TEditField.Value = 1;
            app.PlayButton = uibutton(app.UIFigure, 'state');
            app.PlayButton.ValueChangedFcn = createCallbackFcn(app, @PlayButton_callback, true);
            app.PlayButton.Icon = 'icon_playButton.png';
            app.PlayButton.Text = '';
            app.ViewmodeDropDownLabel = uilabel(app.UIFigure);
            app.ViewmodeDropDownLabel.Text = 'View mode:';
            app.ViewmodeDropDown = uidropdown(app.UIFigure);
            app.ViewmodeDropDown.Items = {'Normal', 'Event', 'FFT', 'SPCM'};
            app.ViewmodeDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewmodeDropDownValueChanged, true);
            app.ViewmodeDropDown.Value = 'Normal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataViewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end