classdef DataViewer_evntWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        OptionsMenu              matlab.ui.container.Menu
        DeletecurrentMenu        matlab.ui.container.Menu
        ConditionMenu            matlab.ui.container.Menu
        RepetitionMenu           matlab.ui.container.Menu
        RestoreallMenu           matlab.ui.container.Menu
        EventsoptionsPanel       matlab.ui.container.Panel
        ConditionDropDownLabel   matlab.ui.control.Label
        ConditionDropDown        matlab.ui.control.DropDown
        RepetitionDropDownLabel  matlab.ui.control.Label
        RepetitionDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        evntObj EventsManager % Handle to EventsManager object
        repIndexList cell % List of repetition indices per condition
    end
    
    properties (Access = public)
        selectedCond % Index of the selected condition in the "eventNameList".
        selectedRep % Index of the selected repetition from the sel. condition.
    end
    
    methods (Access = private)
        
        function createLists(app)
            % Creates condition and repetition lists. These lists will be used
            % to populate dropdown menus in GUI.
            
            % Create condition list
            app.ConditionDropDown.Items = app.evntObj.eventNameList(~app.evntObj.ignoredConditions);
            app.selectedCond = app.ConditionDropDown.Value;
            app.repIndexList = cell(size(app.ConditionDropDown.Items));
            % Populate list of repetition indices per condition:
            for ii = 1:length(app.ConditionDropDown.Items)
                indxCond = find(strcmp(app.ConditionDropDown.Items{ii},app.evntObj.eventNameList));
                app.repIndexList{ii} = [setdiff(1:sum( [app.evntObj.eventID == indxCond & app.evntObj.state] ), app.evntObj.ignoredRepetitions{indxCond}), 0];
            end
            app.genRepList;
        end
        function genRepList(app)
            % Updates the Repetition dropdown menu
            
            previousRepetitionIndex = app.RepetitionDropDown.Value;
            % Populate menu:
            idxCond = strcmp(app.ConditionDropDown.Value,app.ConditionDropDown.Items);
            app.RepetitionDropDown.ItemsData = app.repIndexList{idxCond};
            app.RepetitionDropDown.Items = arrayfun(@(x) num2str(x),app.repIndexList{idxCond},'UniformOutput',false);
            app.RepetitionDropDown.Items{app.repIndexList{idxCond} == 0} = '# Average';
            % Keep the previously selected repetition, if it exists:            
            if ismember(previousRepetitionIndex, app.RepetitionDropDown.ItemsData)
                app.RepetitionDropDown.Value = previousRepetitionIndex;
            end            
        end
        
    end
    
    methods (Access = public)
        function updateLists(app)
            % Allows DataViewer to update the event lists in this GUI.
            app.createLists;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp)
            app.UIFigure.Visible = 'off';
            app.evntObj = MainApp.eventObj;
            % Update UIFigure position:
            app.UIFigure.Position([1 2]) = [MainApp.timeFig.Fig.Position(1) MainApp.timeFig.Fig.Position(2)-280];
            
            % Create repetition and condition lists:
            app.createLists;
            % Select #Average as default Item:
            app.RepetitionDropDown.Value = 0;
            % Note: The figure will be turned visible inside the "loadData"
            % method from DataViewer app. This is to avoid showing the
            % events gui before finishing calculating the data.
            app.UIFigure.Visible = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %%% Do Nothing!!!%%%
            
        end

        % Value changed function: ConditionDropDown
        function ConditionDropDownValueChanged(app, event)
            
            app.genRepList;    
            app.selectedCond = app.ConditionDropDown.Items(app.ConditionDropDown.Value);
            app.MainApp.eventChanged;
        end

        % Value changed function: RepetitionDropDown
        function RepetitionDropDownValueChanged(app, event)
            app.selectedRep = app.RepetitionDropDown.Value;
            app.MainApp.eventChanged;
        end

        % Menu selected function: RepetitionMenu
        function DeleteRepetitionMenuSelected(app, event)
            % Deletes the current repetition from the current Condition
            
            if app.RepetitionDropDown.Value == 0
                errordlg('Can''t delete "#Average"!', 'Failed to delete repetition')
                return
            end
            
            answer = questdlg(['Confirm deletion of repetition #' app.RepetitionDropDown.Items(app.RepetitionDropDown.Value) '?'], 'Delete Repetition?', 'Yes', 'No', 'No');
            if strcmp(answer, 'No') || isempty(answer)
                return
            end
            prevCondList = app.evntObj.ignoredConditions;
            % Delete repetition from repInfo structure:            
            app.evntObj.removeRepetition(app.ConditionDropDown.Items{app.ConditionDropDown.Value}, app.RepetitionDropDown.Value);            
            % Update Repetition DropDown menu:
            if ~isequaln(prevCondList, app.evntObj.ignoredConditions)
                app.createLists;               
            else
                app.genRepList;                
            end            
            app.MainApp.repChanged;
        end

        % Menu selected function: ConditionMenu
        function DeleteConditionMenuSelected(app, event)
            % Deletes the current condition in the Condition list
            
            % Retrieve current condition name:
            cond_name = app.ConditionDropDown.Items(app.ConditionDropDown.ItemsData == app.ConditionDropDown.Value);
            
            % Confirm deletion:
            answer = questdlg(['Confirm deletion of the condition below:', cond_name], 'Delete Condition?', 'Yes', 'No', 'No');
            if strcmp(answer, 'No') || isempty(answer)
                return
            end
            if numel(app.ConditionDropDown.Items) == 1
                errordlg('Can''t delete the last condition!', 'Failed to delete condition')
                return
            end
            % Move the condition to the list of ignored:
            app.evntObj.removeCondition(cond_name);            
            % Update Condition DropDown menu:
            app.createLists;
            % Update plots in Main App:
            app.MainApp.eventChanged;
        end

        % Menu selected function: RestoreallMenu
        function RestoreallMenuSelected(app, event)
            % Restores all conditions and repetitions to the original list
            % when the app was instantiated
            
            % User confirmation:
            answer = questdlg('Restore all conditions and repetitions?', 'Restore All?', 'Yes', 'No', 'No');
            if strcmp(answer, 'No') || isempty(answer)
                return
            end
            % Restore all
            app.evntObj.resetIgnoredLists;           
            app.createLists;
            % Update plots in Main App:
            app.MainApp.eventChanged;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Events';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.DeletecurrentMenu = uimenu(app.OptionsMenu);
            app.DeletecurrentMenu.Text = 'Delete current...';
            app.ConditionMenu = uimenu(app.DeletecurrentMenu);
            app.ConditionMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteConditionMenuSelected, true);
            app.ConditionMenu.Text = 'Condition';
            app.RepetitionMenu = uimenu(app.DeletecurrentMenu);
            app.RepetitionMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteRepetitionMenuSelected, true);
            app.RepetitionMenu.Text = 'Repetition';
            app.RestoreallMenu = uimenu(app.OptionsMenu);
            app.RestoreallMenu.MenuSelectedFcn = createCallbackFcn(app, @RestoreallMenuSelected, true);
            app.RestoreallMenu.Text = 'Restore all';
            app.EventsoptionsPanel = uipanel(app.UIFigure);
            app.EventsoptionsPanel.Title = 'Events options';
            app.ConditionDropDownLabel = uilabel(app.EventsoptionsPanel);
            app.ConditionDropDownLabel.Text = 'Condition';
            app.ConditionDropDown = uidropdown(app.EventsoptionsPanel);
            app.ConditionDropDown.ValueChangedFcn = createCallbackFcn(app, @ConditionDropDownValueChanged, true);
            app.RepetitionDropDownLabel = uilabel(app.EventsoptionsPanel);
            app.RepetitionDropDownLabel.Text = 'Repetition';
            app.RepetitionDropDown = uidropdown(app.EventsoptionsPanel);
            app.RepetitionDropDown.ValueChangedFcn = createCallbackFcn(app, @RepetitionDropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataViewer_evntWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end