classdef ROIMask_creator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ROIMask_creatorUIFigure  matlab.ui.Figure
        CreateROIsButton         matlab.ui.control.Button
        ControlsPanel            matlab.ui.container.Panel
        NormalizeCheckBox        matlab.ui.control.CheckBox
        EditField                matlab.ui.control.NumericEditField
        Slider                   matlab.ui.control.Slider
        ThresholdlevelLabel      matlab.ui.control.Label
        ExclROIsSpinnerLabel     matlab.ui.control.Label
        ExclROIsSpinner          matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        maskArr % Array of masks containing individual ROIs based on contiguous pixels.
        image % input image after replacemente of NaNs and Infs.
        orig_image % same as image in startup. Used to toggle betwee Normalized and not normalized.
        overlayHandle % handle for the mask overlay over the image axis.
        imgHandle % handle to image.
        maskHandle % handle to binary mask image.
        textHandle % handle of text annotation over image.
        MainApp % Handle to ROImanager app.
        lkPropsObj % Handle to linkprops object created in startupFcn
        myCmap % stores the cool colormap.
    end
    
    methods (Access = private)
        
        function DoNothing(~, ~, ~)
            % EMPTY CALLBACK %
            
        end
        
        function updateMask(app, thr)
            % This function updates the mask using the threshold in "thr".
            
            if ishandle(app.textHandle)
                delete(app.textHandle)
            end            
            app.maskArr = bwlabel(app.image > thr,8);
            set(app.overlayHandle, 'CData', app.maskArr, 'AlphaData', .5* single(app.maskArr~=0));          
            set(app.maskHandle, 'CData', app.maskArr);
            app.maskHandle.Parent.Title.String = 'Binary Mask';
        end
%         
%         function overlayLabels(app)
%             % This function annotates the number of the ROI over its
%             % centroid on the Binary image axis
%             
%             disp('Annotating ROI labels...')
%             msk_indx = unique(app.maskArr(:));
%             msk_indx(msk_indx == 0) =[];
%             hold(app.maskHandle.Parent, 'on');
%             centroids = zeros(length(msk_indx),2);
%             for i = 1:length(msk_indx)
%                 info = regionprops(app.maskArr == msk_indx(i), 'Centroid');
%                 centroids(i,:) = info.Centroid;
%             end
%             
%             app.textHandle = text(app.maskHandle.Parent, centroids(:,1), centroids(:,2),...
%                 arrayfun(@num2str,msk_indx, 'UniformOutput', false), 'Color', 'w', 'FontWeight', 'bold');
%             hold(app.maskHandle.Parent, 'off');
%             % Update mask image title:
%             app.maskHandle.Parent.Title.String = ['Binary Mask (Number of Regions = ' num2str(numel(msk_indx)) ')'];
%             %
%             disp('Done!');
%         end
        
%         function refineROIs(app)
%             % This function excludes the indices in app.maskArr using the
%             % threshold in ExclROIsSpinner.Value
%             
%             % Remove areas with number of pixels less than ExclROIsSpinner.Value:
%             msk_indx = unique(app.maskArr(:));
%             msk_indx(msk_indx == 0) = [];
%             
%             for i = 1:length(msk_indx)
%                 idx = app.maskArr == msk_indx(i);
%                 if sum(idx,'all') < app.ExclROIsSpinner.Value
%                     disp(msk_indx(i))
%                     app.maskArr(idx) = 0;
%                 end
%             end
%             % Update image:
%             set(app.overlayHandle, 'CData', app.maskArr, 'AlphaData', .5* single(app.maskArr~=0));
%             set(app.maskHandle, 'CData', app.maskArr);
%             % Erase ROI labels:
%             if ~isempty(app.textHandle)
%                 delete(app.textHandle)
%             end
%             
%             
%             
%             
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MainApp, imgData)
            % The input for the app must be a 2-D numerical matrix.
            app.ROIMask_creatorUIFigure.Visible = 'off';
            if ~ismatrix(imgData)
                uialert(app.ROIMask_creatorUIFigure, 'Wrong data type. This app accepts only 2D image data', 'Invalid Input', ...
                    "CloseFcn",'uiresume(gcbf)')
                uiwait(app.ROIMask_creatorUIFigure)
                app.FigureCloseRequest;
            end
            app.MainApp = MainApp;
            % Replace NaNs with zeros:
            imgData(isnan(imgData)) = 0;
            % Replace -Inf and Inf with min and max values of the image
            % data:
            idxInf = isinf(imgData);
            if any(idxInf)
                imgData(idxInf & imgData == min(imgData(:))) = min(imgData(~idxInf(:))); % Replace -Inf with min of imgData.
                imgData(idxInf & imgData == max(imgData(:))) = max(imgData(~idxInf(:))); % Replace -Inf with min of imgData.
            end
            app.image = imgData;
            app.orig_image = imgData;
            % Populate interface component values with image's data:
            app.Slider.Limits = double([min(app.image(:)) max(app.image(:))]);
            app.Slider.Value = app.Slider.Limits(1);
            app.EditField.Limits = app.Slider.Limits;
            app.EditField.Value = app.Slider.Value;
            app.ExclROIsSpinner.Limits = [0 numel(app.image)];
            % Create Figure and plots:
            f= figure('Name', 'Image Window', 'Position', [0 0 750 500],'Visible','on', "CloseRequestFcn",@app.DoNothing, 'Tag', 'imgFig');
            % Initialize mask
            app.maskArr = zeros(size(app.image),'single');
            
            % Create axes:
            imgAx = subplot(121);
            maskAx = subplot(122);
            overlayAx = axes();
            % Plot image and mask:
            app.imgHandle = imagesc(imgAx, app.image, 'Tag', 'imgData');
            % Plot overlay mask
            app.overlayHandle = imagesc(overlayAx, app.maskArr, 'AlphaData',app.maskArr);
            % Plot Mask
            app.maskHandle = imagesc(maskAx, app.maskArr);
            % set colormaps:            
            cmap = colormap('cool');
            cmap(1,:) = [0 0 0];
            app.myCmap = cmap;
            colormap(maskAx, cmap);
            colormap(overlayAx, cmap);
            colormap(imgAx, 'gray');
            % Add titles:
            imgAx.Title.String = 'Image';
            maskAx.Title.String = 'Binary mask';
            % Link properties:
            app.lkPropsObj = linkprop([imgAx, maskAx, overlayAx], {'XLim', 'YLim', 'YDir', 'DataAspectRatio'});
            %             linkprop([imgAx, overlayAx],{'Position'})
            set(overlayAx, 'Position', imgAx.Position, 'NextPlot', 'replaceChildren', 'DataAspectRatio', [1 1 1]);                      
            axis(imgAx, 'off')
            axis(maskAx, 'off')
            axis(overlayAx, 'off')
            movegui(f, 'center');
            movegui(app.ROIMask_creatorUIFigure, 'northwest');
            f.Visible = 'on';
            app.ROIMask_creatorUIFigure.Visible = 'on';
        end

        % Close request function: ROIMask_creatorUIFigure
        function FigureCloseRequest(app, event)
            disp('deleting app');
            delete(findall(0, 'Tag', 'imgFig'));
            delete(app);
        end

        % Value changed function: NormalizeCheckBox
        function NormalizeCheckBoxValueChanged(app, event)
            % This callback normalizes the image
            currVal = app.Slider.Value;
            if app.NormalizeCheckBox.Value
                app.image = (app.image - min(app.image(:)))./(max(app.image(:)) - min(app.image(:)));
                app.Slider.Limits = [0 1];
                app.Slider.Value = double((currVal - min(app.orig_image(:))) ./...
                    (max(app.orig_image(:)) - min(app.orig_image(:)))); % Normalize current Thr. val.
                
            else
                app.image = app.orig_image;
                app.Slider.Limits = double([min(app.image(:)) max(app.image(:))]);
                app.Slider.Value = double((currVal * (max(app.image(:)) - min(app.image(:)))) + min(app.image(:)));
            end
            % Update Limits of Edit field:
            app.EditField.Limits = app.Slider.Limits;
            app.EditField.Value = app.Slider.Value;
            % Update image:
            app.imgHandle.CData = app.image;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            % This callback updates the mask threshold level
            app.Slider.Value = app.EditField.Value;
            app.updateMask(app.EditField.Value);
            app.updateFinalMask;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            % This callback updates the mask threshold level
            app.EditField.Value = event.Value;
            app.updateMask(event.Value);            
        end

        % Button pushed function: CreateROIsButton
        function CreateROIsButtonPushed(app, event)
            % This callback creates the array of ROIs and saves to the
            % "newMasks" property of ROImanager
            app.MainApp.newMasks = app.maskArr;
            app.FigureCloseRequest;
        end

        % Value changed function: ExclROIsSpinner, Slider
        function updateFinalMask(app, event)
            
            % This callback excludes the indices in app.maskArr using the
            % threshold in ExclROIsSpinner.Value
            
            % Remove areas with number of pixels less than ExclROIsSpinner.Value:
            msk_indx = unique(app.maskArr(:));
            msk_indx(msk_indx == 0) = [];
            centroids = zeros(length(msk_indx),2);
            idx_rem = false(size(msk_indx));
            for i = 1:length(msk_indx)                
                idx = app.maskArr == msk_indx(i);
                info = regionprops(app.maskArr == msk_indx(i), 'Centroid');
                centroids(i,:) = info.Centroid;
                if sum(idx,'all') < app.ExclROIsSpinner.Value
                    app.maskArr(idx) = 0;
                    idx_rem(i) = true;                    
                end
            end
            % Remove small ROIs from mask:
            app.maskArr(idx_rem) = 0;
            
            % Update image:
            set(app.overlayHandle, 'CData', app.maskArr, 'AlphaData', .5* single(app.maskArr~=0));
            set(app.maskHandle, 'CData', app.maskArr);
            % Erase ROI labels:
            if ~isempty(app.textHandle)
                delete(app.textHandle)
            end 
            % Create new labels
            % Update indices and centroids:
            msk_indx(idx_rem) = [];
            centroids(idx_rem,:) = [];                                  
            app.textHandle = text(app.maskHandle.Parent, centroids(:,1), centroids(:,2),...
                arrayfun(@num2str,msk_indx, 'UniformOutput', false), 'Color', 'w', 'FontWeight', 'bold');
            hold(app.maskHandle.Parent, 'off');
            % Update mask image title:
            app.maskHandle.Parent.Title.String = ['Binary Mask (Number of Regions = ' num2str(numel(msk_indx)) ')'];
            %           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ROIMask_creatorUIFigure = uifigure('Visible', 'off');
            app.ROIMask_creatorUIFigure.Name = 'ROIMask_creator';
            app.ROIMask_creatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @FigureCloseRequest, true);
            app.CreateROIsButton = uibutton(app.ROIMask_creatorUIFigure, 'push');
            app.CreateROIsButton.ButtonPushedFcn = createCallbackFcn(app, @CreateROIsButtonPushed, true);
            app.CreateROIsButton.Text = 'Create ROIs';
            app.ControlsPanel = uipanel(app.ROIMask_creatorUIFigure);
            app.ControlsPanel.Title = 'Controls';
            app.NormalizeCheckBox = uicheckbox(app.ControlsPanel);
            app.NormalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeCheckBoxValueChanged, true);
            app.NormalizeCheckBox.Text = 'Normalize';
            app.ThresholdlevelLabel = uilabel(app.ControlsPanel);
            app.ThresholdlevelLabel.Text = 'Threshold level';
            app.Slider = uislider(app.ControlsPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @updateFinalMask, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.EditField = uieditfield(app.ControlsPanel, 'numeric');
            app.EditField.ValueDisplayFormat = '%0.3f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.ExclROIsSpinnerLabel = uilabel(app.ControlsPanel);
            app.ExclROIsSpinnerLabel.Text = 'Excl. ROIs';
            app.ExclROIsSpinner = uispinner(app.ControlsPanel);
            app.ExclROIsSpinner.Limits = [0 1000];
            app.ExclROIsSpinner.ValueChangedFcn = createCallbackFcn(app, @updateFinalMask, true);
            app.ExclROIsSpinner.Tooltip = {'Removes ROIs with a number of pixels less than N.'};
            % Show the figure after all components are created
            app.ROIMask_creatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROIMask_creator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ROIMask_creatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ROIMask_creatorUIFigure)
        end
    end
end