classdef SeedPixMap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        SeedPixelMapLabel      matlab.ui.control.Label
        OpenFileButton         matlab.ui.control.Button
        ToggleMapButtonGroup   matlab.ui.container.ButtonGroup
        CorrelationButton      matlab.ui.control.ToggleButton
        PValueButton           matlab.ui.control.ToggleButton
        colormapDropDownLabel  matlab.ui.control.Label
        colormapDropDown       matlab.ui.control.DropDown
        TextArea               matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        mDat % memmapfile of SeedPxCorr Data.
        btnDwnPos % Position of the clicked point in the plot.
        clickType % ID of left or right mouse click
        CurrentCoords = [1 1]; % Mouse Current Coordinates;
        SeedCoords = [1 1] % Seed Pixel Coordinates.
        CurrentVal  = -1% CurrentValue from Plot
        Data % Currently selected data
        CurrentFrame % Currently plotted Frame
        start = false % start getting Mouse position.
        my_cmap % selected colormap from DropDown menu
%         crossHairPtr % Description
        clims = [-1 1] % color limit for imagesc plots
    end
    
    methods (Access = private)
        
        function displayInfo(app)
            % Displays important information in APP.LABEL.
            txt = {};
            txt{1} = ['Current position (X,Y) : ' num2str(app.CurrentCoords(1)) ' , ' num2str(app.CurrentCoords(2))];
            txt{2} = ['Current Value : ' num2str(app.CurrentVal)];
            txt{3} = ['Seed Pixel Coordinate (X,Y) :' num2str(app.SeedCoords(1)) ' , ' num2str(app.SeedCoords(2))];
            app.TextArea.Value = txt;
        end
        
        
        function UpdateMap(app)
            ind = sub2ind(size(app.CurrentFrame), app.SeedCoords(2), app.SeedCoords(1));
            app.CurrentFrame = squeeze(app.Data(:,:,ind));
            imagesc(app.UIAxes,app.CurrentFrame, app.clims); hold(app.UIAxes, 'on')
%             app.crossHairPtr = images.roi.Crosshair(app.UIAxes, 'InteractionsAllowed', 'none', 'Position', app.CurrentCoords, 'Color', [1 1 1]);
            line(app.UIAxes, [app.UIAxes.XLim(1) app.UIAxes.XLim(2)], [app.CurrentCoords(2) app.CurrentCoords(2)], 'Color', 'white')
            line(app.UIAxes, [app.CurrentCoords(1) app.CurrentCoords(1)], [app.UIAxes.YLim(1) app.UIAxes.YLim(2)],'Color', 'white')
            hold(app.UIAxes, 'on')
        end
        
        %         function UpdateCurrentPt(app, hFig, eve)
        %             C = get(gca, 'CurrentPoint');
        %             app.CurrentCoords = round(C(1,1:2));
        %             in_bounds = getBounds(app.UIAxes, app.CurrentCoords);
        %             if ~in_bounds
        %                 return
        %             end
        %             hold on
        %             line(app.UIAxes, [app.CurrentCoords(1) app.CurrentCoords(1)], app.UIAxes.YLim, "Color", "white");
        %             line(app.UIAxes, app.UIAxes.XLim, [app.CurrentCoords(2) app.CurrentCoords(2)], app.UIAxes.YLim, "Color", "white");
        %             hold off
        %             app.displayInfo;
        %             function in_bounds = getBounds(axes_handle, position)
        %                 x_lims = get(axes_handle, 'XLim');
        %                 y_lims = get(axes_handle, 'YLim');
        %
        %                 in_bounds = (position(1) >= x_lims(1)) && ...
        %                     (position(1) <= x_lims(2)) && ...
        %                     (position(2) >= y_lims(1)) && ...
        %                     (position(2) <= y_lims(2));
        %             end
        %         end
        
        function updateCurrentCoords(app)
            coords = get(app.UIAxes, 'CurrentPoint');
            app.CurrentCoords = round(coords(1,1:2));
            
        end
        
        function in_bounds = getBounds(app)
            x_lims = get(app.UIAxes, 'XLim');
            y_lims = get(app.UIAxes, 'YLim');
            
            in_bounds = (app.CurrentCoords(1) >= x_lims(1)) && ...
                (app.CurrentCoords(1) <= x_lims(2)) && ...
                (app.CurrentCoords(2) >= y_lims(1)) && ...
                (app.CurrentCoords(2) <= y_lims(2));
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('D:\Academico\PostDoc_UdeM\LabeoTech\IsaToolbox'))
            app.my_cmap = app.colormapDropDown.Value;
            app.UIAxes.DataAspectRatio = [1 1 1];
            app.UIAxes.Title.String = app.ToggleMapButtonGroup.SelectedObject.Text;
        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            [file,path] = uigetfile('SeedPixCorr.dat', 'Select Seed Pixel Correlation File');
            if file == 0
                return
            end
            cd(path);
            app.mDat = mapDatFile(fullfile(path, file));
            app.Data = app.mDat.Data.CM;
            % Draw first Frame
            app.CurrentFrame = squeeze(app.Data(:,:,1));
            FrSz = size(app.CurrentFrame);
            app.UIAxes.XLim = [1 FrSz(1)]; app.UIAxes.YLim = [1 FrSz(2)];
            imagesc(app.UIAxes,app.CurrentFrame, app.clims); colormap(app.UIAxes, app.my_cmap); colorbar(app.UIAxes, 'westoutside')
%             app.crossHairPtr = images.roi.Crosshair(app.UIAxes, 'InteractionsAllowed', 'none', 'Position', [1,1], 'Color', [1 1 1]);
            app.start = true;
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            click = get(app.UIFigure, 'SelectionType');
            in_bounds = app.getBounds;
            if ~in_bounds
                return
            end
            if strcmp(click, 'normal')
                app.SeedCoords = app.CurrentCoords;
                app.UpdateMap;
            end
            app.CurrentVal = app.CurrentFrame(app.CurrentCoords(2), app.CurrentCoords(1));
            app.displayInfo;
        end

        % Selection changed function: ToggleMapButtonGroup
        function ToggleMapButtonGroupSelectionChanged(app, event)
            selectedButton = app.ToggleMapButtonGroup.SelectedObject;
            map = selectedButton.Text;
            switch map
                case 'Correlation'
                    app.Data = app.mDat.Data.CM;
                    app.UIAxes.Title.String = 'Correlation Map';
                    app.clims = [-1 1];
                otherwise
                    app.Data = app.mDat.Data.P;
                    app.UIAxes.Title.String = 'P-Value Map';
                    app.clims = [0 1];
            end
            
        end

        % Value changed function: colormapDropDown
        function colormapDropDownValueChanged(app, event)
            app.my_cmap = app.colormapDropDown.Value;
            colormap(app.UIAxes, app.my_cmap);
        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            if app.start
            app.updateCurrentCoords;
            in_bounds = app.getBounds;
            if ~in_bounds
                return
            else
%                 app.crossHairPtr.Position = app.CurrentCoords;
                app.CurrentVal = app.CurrentFrame(app.CurrentCoords(2), app.CurrentCoords(1));
                app.displayInfo;
            end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SeedPixelMapLabel = uilabel(app.UIFigure);
            app.SeedPixelMapLabel.Text = 'Seed Pixel Map';
            app.OpenFileButton = uibutton(app.UIFigure, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File';
            app.ToggleMapButtonGroup = uibuttongroup(app.UIFigure);
            app.ToggleMapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ToggleMapButtonGroupSelectionChanged, true);
            app.ToggleMapButtonGroup.Title = 'Toggle Map';
            app.CorrelationButton = uitogglebutton(app.ToggleMapButtonGroup);
            app.CorrelationButton.Text = 'Correlation';
            app.CorrelationButton.Value = true;
            app.PValueButton = uitogglebutton(app.ToggleMapButtonGroup);
            app.PValueButton.Text = 'P-Value';
            app.colormapDropDownLabel = uilabel(app.UIFigure);
            app.colormapDropDownLabel.Text = 'colormap';
            app.colormapDropDown = uidropdown(app.UIFigure);
            app.colormapDropDown.Items = {'jet', 'parula', 'hsv', 'spring', 'gray', 'copper'};
            app.colormapDropDown.ValueChangedFcn = createCallbackFcn(app, @colormapDropDownValueChanged, true);
            app.colormapDropDown.Value = 'jet';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SeedPixMap

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end