classdef imagApps_Opts < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ImageOptsUIFigure            matlab.ui.Figure
        SaveButton                   matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        SetcentroidROIradiusPanel    matlab.ui.container.Panel
        UnitButtonGroup              matlab.ui.container.ButtonGroup
        pixelsdefButton              matlab.ui.control.RadioButton
        mmButton                     matlab.ui.control.RadioButton
        RadiusEditFieldLabel         matlab.ui.control.Label
        RadiusEditField              matlab.ui.control.NumericEditField
        AreaoptionsPanel             matlab.ui.container.Panel
        ROIsurfaceButtonGroup        matlab.ui.container.ButtonGroup
        UsefullsurfaceButton         matlab.ui.control.RadioButton
        ShrinkROIButton              matlab.ui.control.RadioButton
        N_pixelsSpinnerLabel         matlab.ui.control.Label
        Area_shrink_spinner          matlab.ui.control.Spinner
        SetImageColormapPanel        matlab.ui.container.Panel
        LoadfromFileButton           matlab.ui.control.Button
        choosefromlistDropDownLabel  matlab.ui.control.Label
        choosefromlistDropDown       matlab.ui.control.DropDown
        orLabel                      matlab.ui.control.Label
        resetMaxButton               matlab.ui.control.Button
        minEditFieldLabel            matlab.ui.control.Label
        minEditField                 matlab.ui.control.NumericEditField
        maxLabel                     matlab.ui.control.Label
        maxEditField                 matlab.ui.control.NumericEditField
        SetMaskPanel                 matlab.ui.container.Panel
        numberorregionsLabel         matlab.ui.control.Label
        numberorregionsSpinner       matlab.ui.control.Spinner
    end

    
    properties (Access = private)
        MainApp % Handle to Imaging Main app.
        optsName % String indicating which options to show : {'area', 'centroid', 'cmap', 'msk'};
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, optsName)
            
            app.ImageOptsUIFigure.Visible = 'off';
            % Update UIFigure position:
            movegui(app.ImageOptsUIFigure, 'center');
            
            app.optsName = optsName;
            app.MainApp = callingApp;
            switch app.optsName
                case 'area'
                    app.AreaoptionsPanel.Visible = 'on';
                    app.Area_shrink_spinner.Value = app.MainApp.AllenROIopts.Area.n_shrinkPix;
                    % Set params:
                    if  app.MainApp.AllenROIopts.Area.n_shrinkPix == 0
                        app.ROIsurfaceButtonGroup.SelectedObject = app.UsefullsurfaceButton;
                        app.Area_shrink_spinner.Enable = 'off';
                    else
                        app.ROIsurfaceButtonGroup.SelectedObject = app.ShrinkROIButton;
                        app.Area_shrink_spinner.Enable = 'on';
                    end
                case 'centroid'
                    app.SetcentroidROIradiusPanel.Visible = 'on';
                    % Set params:
                    app.RadiusEditField.Value = app.MainApp.AllenROIopts.Centroid.Radius;
                    if strcmp('px', app.MainApp.AllenROIopts.Centroid.Unit)
                        app.UnitButtonGroup.SelectedObject = app.pixelsdefButton;
                    else
                        app.UnitButtonGroup.SelectedObject = app.mmButton;
                    end
                    
                    % If the pixel ratio is not set in the MainApp, Disable
                    % mmButton:
                    if isempty(app.MainApp.image_info.pxPermm)
                        app.mmButton.Enable = 'off';
                    end
                case 'cmap'
                    app.SetImageColormapPanel.Visible = 'on';
                    % Get (M x 3) values of colormaps and store in
                    % ItemsData:
                    f = figure('Visible', 'off'); ax = axes(f);
                    app.choosefromlistDropDown.ItemsData= cellfun(@(x) colormap(x), app.choosefromlistDropDown.Items, 'UniformOutput',false);
                    % Check for custom cmap:
                    idx = cellfun(@(x) isequal(x,app.MainApp.image_info.cmap), app.choosefromlistDropDown.ItemsData);
                    if ~(idx)
                        app.choosefromlistDropDown.Items = [app.choosefromlistDropDown.Items, {'custom'}];
                        app.choosefromlistDropDown.ItemsData = [app.choosefromlistDropDown.ItemsData, {app.MainApp.image_info.cmap}];
                        app.choosefromlistDropDown.Value = app.choosefromlistDropDown.ItemsData{end};
                    else
                        app.choosefromlistDropDown.Value = app.choosefromlistDropDown.ItemsData{idx};
                    end
                    close(f)
                    % Import CLim values:
                    app.minEditField.Value = double(app.MainApp.image_info.clims(1));
                    app.maxEditField.Value = double(app.MainApp.image_info.clims(2));
                case 'msk'
                    app.SetMaskPanel.Visible = 'on';
                    
            end
            
            app.ImageOptsUIFigure.Visible = 'off';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            switch app.optsName
                case 'area'
                    % Set Area params:
                    app.MainApp.AllenROIopts.Area.n_shrinkPix = app.Area_shrink_spinner.Value;
                case 'centroid'
                    % Set Centroid params:
                    app.MainApp.AllenROIopts.Centroid.Radius = app.RadiusEditField.Value;
                    % Set image unit:
                    unit = app.UnitButtonGroup.SelectedObject.Text;
                    if strcmp(unit, 'pixels (def)')
                        app.MainApp.AllenROIopts.Centroid.Unit = 'px';
                    else
                        app.MainApp.AllenROIopts.Centroid.Unit = 'mm';
                    end
                case 'cmap'
                    % Set image colormap data:
                    app.MainApp.image_info.cmap = app.choosefromlistDropDown.Value;
                    app.MainApp.image_info.clims(1) = app.minEditField.Value;
                    app.MainApp.image_info.clims(2) = app.maxEditField.Value;
                case 'msk'
                    % Set Mask region numbers:
                    app.MainApp.n_msk = app.numberorregionsSpinner.Value;
            end
            
            delete(app);
        end

        % Callback function: CancelButton, ImageOptsUIFigure
        function CancelButtonPushed(app, event)
            delete(app)
        end

        % Selection changed function: ROIsurfaceButtonGroup
        function ROIsurfaceButtonGroupSelectionChanged(app, event)
            selectedButton = app.ROIsurfaceButtonGroup.SelectedObject;
            if selectedButton == app.ShrinkROIButton
                app.Area_shrink_spinner.Enable = 'on';
            else
                app.Area_shrink_spinner.Value = 0;
                app.Area_shrink_spinner.Enable = 'off';
            end
        end

        % Button pushed function: LoadfromFileButton
        function LoadfromFileButtonPushed(app, event)
            [filename, path] = uigetfile('*.mat', 'Open .MAT file with colormap (M X 3 matrix)');
            if filename == 0
                disp('Operation Cancelled by User.')
                return
            end
            mD = load(fullfile(path, filename));
            varList = fieldnames(mD);
            [idx, tf] = listdlg("ListString", varList, "PromptString", {'Select colormap' ;'from the variables below:'}, ...
                "SelectionMode", 'single');
            if ~tf
                disp('Operation cancelled by User.')
                return
            end
            % Load mask:
            cmap = mD.(varList{idx});
            % Check if msk is a logical 2D matrix with size equal to the image:
            if size(cmap,2) ~=3 || ~ismatrix(cmap) || ~isnumeric(cmap)
                app.ImageOptsUIFigure.Visible = 'off';
                err = errordlg({'Invalid colormap.', 'It must be a M x 3 numerical matrix'}, 'Failed to import colormap', 'modal');
                waitfor(err);
                app.ImageOptsUIFigure.Visible = 'on';
                return
            end
            
            % Add custom map to list:
            idx = strcmp('custom', app.choosefromlistDropDown.Items);
            if any(idx)
                app.choosefromlistDropDown.ItemsData{idx} = cmap;
            else
                app.choosefromlistDropDown.Items = [app.choosefromlistDropDown.Items, {'custom'}];
                app.choosefromlistDropDown.ItemsData = [app.choosefromlistDropDown.ItemsData, {cmap}];
                app.choosefromlistDropDown.Value = app.choosefromlistDropDown.ItemsData{end};
            end
            
           
        end

        % Button pushed function: resetMaxButton
        function resetMaxButtonPushed(app, event)
            % Reset Button callback. Resets CLim values to default.
            app.minEditField.Value = double(min(app.MainApp.image_info.imageData(:)));
            app.maxEditField.Value = double(max(app.MainApp.image_info.imageData(:)));
            % Resets current value in image_info struct from MainApp
            app.MainApp.image_info.clims = [min(app.MainApp.image_info.imageData(:)) max(app.MainApp.image_info.imageData(:))];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ImageOptsUIFigure = uifigure('Visible', 'off');
            app.ImageOptsUIFigure.Name = 'Image--Opts';
            app.ImageOptsUIFigure.CloseRequestFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.SaveButton = uibutton(app.ImageOptsUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.ImageOptsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {''};
            app.CancelButton.Text = 'Cancel';
            app.SetcentroidROIradiusPanel = uipanel(app.ImageOptsUIFigure);
            app.SetcentroidROIradiusPanel.Title = 'Set centroid ROI radius';
            app.SetcentroidROIradiusPanel.Visible = 'off';
            app.UnitButtonGroup = uibuttongroup(app.SetcentroidROIradiusPanel);
            app.UnitButtonGroup.Title = 'Unit';
            app.pixelsdefButton = uiradiobutton(app.UnitButtonGroup);
            app.pixelsdefButton.Tooltip = {'Choose an unit'};
            app.pixelsdefButton.Text = 'pixels (def)';
            app.pixelsdefButton.Value = true;
            app.mmButton = uiradiobutton(app.UnitButtonGroup);
            app.mmButton.Tooltip = {'Disabled if Pixel size is not defined.'};
            app.mmButton.Text = 'mm';
            app.RadiusEditFieldLabel = uilabel(app.SetcentroidROIradiusPanel);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.SetcentroidROIradiusPanel, 'numeric');
            app.RadiusEditField.Limits = [0.01 Inf];
            app.RadiusEditField.Tooltip = {'Type positive radius value.'};
            app.RadiusEditField.Value = 1;
            app.AreaoptionsPanel = uipanel(app.ImageOptsUIFigure);
            app.AreaoptionsPanel.Title = 'Area options';
            app.AreaoptionsPanel.Visible = 'off';
            app.ROIsurfaceButtonGroup = uibuttongroup(app.AreaoptionsPanel);
            app.ROIsurfaceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ROIsurfaceButtonGroupSelectionChanged, true);
            app.ROIsurfaceButtonGroup.Title = 'ROI surface';
            app.UsefullsurfaceButton = uiradiobutton(app.ROIsurfaceButtonGroup);
            app.UsefullsurfaceButton.Tooltip = {'Use 100% of the ROI surface (default).'};
            app.UsefullsurfaceButton.Text = 'Use full surface';
            app.UsefullsurfaceButton.Value = true;
            app.ShrinkROIButton = uiradiobutton(app.ROIsurfaceButtonGroup);
            app.ShrinkROIButton.Tooltip = {'Removes pixels from ROI boundaries using the "shrink" algorithm of bwmorph MATLAB function.'};
            app.ShrinkROIButton.Text = 'Shrink ROI';
            app.N_pixelsSpinnerLabel = uilabel(app.AreaoptionsPanel);
            app.N_pixelsSpinnerLabel.Text = 'N_pixels';
            app.Area_shrink_spinner = uispinner(app.AreaoptionsPanel);
            app.Area_shrink_spinner.Limits = [0 Inf];
            app.Area_shrink_spinner.Tooltip = {'Number of pixels to remove from ROI boundaries.'};
            app.SetImageColormapPanel = uipanel(app.ImageOptsUIFigure);
            app.SetImageColormapPanel.Title = 'Set Image Colormap';
            app.SetImageColormapPanel.Visible = 'off';
            app.LoadfromFileButton = uibutton(app.SetImageColormapPanel, 'push');
            app.LoadfromFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromFileButtonPushed, true);
            app.LoadfromFileButton.Text = 'Load from File';
            app.choosefromlistDropDownLabel = uilabel(app.SetImageColormapPanel);
            app.choosefromlistDropDownLabel.Text = 'choose from list:';
            app.choosefromlistDropDown = uidropdown(app.SetImageColormapPanel);
            app.choosefromlistDropDown.Items = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink'};
            app.choosefromlistDropDown.Value = 'parula';
            app.orLabel = uilabel(app.SetImageColormapPanel);
            app.orLabel.Text = 'or';
            app.resetMaxButton = uibutton(app.SetImageColormapPanel, 'push');
            app.resetMaxButton.ButtonPushedFcn = createCallbackFcn(app, @resetMaxButtonPushed, true);
            app.resetMaxButton.Icon = 'icon_back_arrow.png';
            app.resetMaxButton.Text = '';
            app.minEditFieldLabel = uilabel(app.SetImageColormapPanel);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.SetImageColormapPanel, 'numeric');
            app.minEditField.ValueDisplayFormat = '%11.4f';
            app.maxLabel = uilabel(app.SetImageColormapPanel);
            app.maxLabel.Text = 'max';
            app.maxEditField = uieditfield(app.SetImageColormapPanel, 'numeric');
            app.maxEditField.ValueDisplayFormat = '%11.4f';
            app.SetMaskPanel = uipanel(app.ImageOptsUIFigure);
            app.SetMaskPanel.Title = 'Set Mask';
            app.SetMaskPanel.Visible = 'off';
            app.numberorregionsLabel = uilabel(app.SetMaskPanel);
            app.numberorregionsLabel.Text = 'number or regions:';
            app.numberorregionsSpinner = uispinner(app.SetMaskPanel);
            app.numberorregionsSpinner.Limits = [1 10];
            app.numberorregionsSpinner.Value = 1;
            % Show the figure after all components are created
            app.ImageOptsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = imagApps_Opts(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ImageOptsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ImageOptsUIFigure)
        end
    end
end