classdef manualAlignFrames < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        ReferencefileLabel        matlab.ui.control.Label
        LoadFixedButton           matlab.ui.control.Button
        MisalignedfileLabel       matlab.ui.control.Label
        LoadMovingButton          matlab.ui.control.Button
        OptionsPanel              matlab.ui.container.Panel
        DisplayresultasLabel      matlab.ui.control.Label
        DisplayresultasDropDown   matlab.ui.control.DropDown
        FliphorizontallyCheckBox  matlab.ui.control.CheckBox
        ImagesharpeningLabel      matlab.ui.control.Label
        ImagesharpeningDropDown   matlab.ui.control.DropDown
        InvertcolorsCheckBox      matlab.ui.control.CheckBox
        GridLayout2               matlab.ui.container.GridLayout
        FinetuningButton          matlab.ui.control.Button
        GridLayout3               matlab.ui.container.GridLayout
        AlignButton               matlab.ui.control.Button
        ExportButton              matlab.ui.control.Button
        FineTuningPanel           matlab.ui.container.Panel
        MovDropDown               matlab.ui.control.DropDown
        Spinner                   matlab.ui.control.Spinner
        MovementLabel             matlab.ui.control.Label
        StepLabel                 matlab.ui.control.Label
        StepEditField             matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        
        fixFileName % Name of .dat file to get the fixed frame
        movFileName % Name of .dat file to get the moving frame
        fr_fix = zeros(1024,1024,'single') % Frame of fixed image
        fr_mov = zeros(1024,1024,'single') % Frame of moving image
        orig_fr_fix % copy of fr_fix
        orig_fr_mov % copy of fr_mov
        tform = affine2d([1 0 0 ; 0 1 0 ; 0 0 1]) % Affine2D object to be used to coregister the data from the moving image
        rectH % Handle to rectangle used to interact with the axis.
        bcgImgH % Handle to image displayed in the axis
        ax % Handle to axis
        elMoving % event listener for MovingROI
        fH % figure handle
        initMat = [1 0 0 ; 0 1 0 ; 0 0 1]; % initial Tform. Used when moving image is flipped.
        T % timer object for Alternate option in drop down menu
        fineTuningSteps = struct('ID',{{'xTranslation','yTranslation','Rotation','Scaling'}},'values',[1,1,1,1], 'limits',[[1, Inf];[1, Inf];[0, 360];[0,100]],'b_round',[true true false false]);
        bOK = false % Boolean to set App's close request function
        figWidth = 185 % Default figure width
        figWidthLarge = 450 % Wider figure width for showing uialerts and other dialog boxes
    end
    
    methods (Access = private)
        
        function loadFrame(app, flag)
            % Loads a frame from a data file and updates the appropriate app properties.
            %
            % INPUTS:
            %   - filename: The full path of the .dat file to load.
            %   - b_fixed: A boolean flag indicating whether the frame is fixed.
            %
            if strcmpi(flag,'fixed')
                filename = app.fixFileName;
            else
                filename = app.movFileName;
            end
            if isempty(filename)
                return
            end
            % Load the data file using 'mapDatFile'.
            mp = mapDatFile(filename);
            
            % Extract file name and extension.
            [path, name, ext] = fileparts(filename);
            
            % Load the average of 100 frames from data.
            if size(mp.Data.data,3)> 100
                frame = mean(mp.Data.data(:,:,round(linspace(1,size(mp.Data.data,3),100))),3,'omitnan');
            else
                frame = mean(mp.Data.data,3,'omitnan');
            end
            % Normalize data from 0-1:
            frame = (frame - min(frame(:)))./(max(frame(:)) - min(frame(:)));
            % Update app properties based on the 'b_fixed' flag.
            if strcmpi(flag,'fixed')
                app.fr_fix = frame;
                app.orig_fr_fix = frame;
                btn = app.LoadFixedButton;
            else
                app.fr_mov = frame;
                app.orig_fr_mov = frame;
                btn = app.LoadMovingButton;
            end
            % update button
            set(btn,'Text',[name, ext],'ToolTip',path);
        end
        
        
        function showImages(app)
            
            % Create composite image:
            rgb_img = zeros([size(app.fr_fix),2],'single');
            % Put fixed frame as green:
            rgb_img(:,:,2) = app.fr_fix;
            % Put the moving frame as magenta:
            xMin = min([size(app.fr_fix,1) size(app.fr_mov,1)]);
            yMin = min([size(app.fr_fix,2) size(app.fr_mov,2)]);
            rgb_img(1:xMin,1:yMin,[1 3]) = cat(3,app.fr_mov(1:xMin,1:yMin),app.fr_mov(1:xMin,1:yMin));
            % Display images:
            app.bcgImgH = image(app.ax,'CData',rgb_img,'CDataMapping','scaled','Tag','bcgImage');
            % Draw rectangle:
            delete(app.rectH); % Remove existing rectangle
            app.rectH = drawrectangle(app.ax,'DrawingArea', 'unlimited','FixedAspectRatio',true,...
                'InteractionsAllowed','all','Rotatable',true,'Position',[0 0 fliplr(size(app.fr_mov))],...
                'Color',[1 0 1]','FaceAlpha',0);
            % Add event listeners to callback:
            app.elMoving = addlistener(app.rectH,'MovingROI',@(src,evnt) app.imageOverlayCallback);
        end
        
        function imageOverlayCallback(app,~,~)
            % imageOverlayCallback - Custom callback to resize and overlay a moving image using the rectangle handle.
            %
            % INPUTS:
            %   - app: An instance of the application (if used).
            %
            % OUTPUT: None (Updates app properties).
            %
            if ~isempty(app.T)
                if isvalid(app.T)
                    stop(app.T);
                end
                delete(app.T)
            end
            % Get the rectangle's position, scaling, and rotation
            Xoffset = app.rectH.Position(1);
            Yoffset = app.rectH.Position(2);
            offsetMat = [1 0 0; 0 1 0; Xoffset Yoffset 1];
            
            scaleFactor = app.rectH.Position(3) / size(app.fr_mov, 2);
            scalingMat = [scaleFactor 0 0; 0 scaleFactor 0; 0 0 1];
            
            centerImg = [1 0 0; 0 1 0; -size(app.fr_mov, 2) / 2 -size(app.fr_mov, 1) / 2 1];
            rot = [cosd(app.rectH.RotationAngle) -sind(app.rectH.RotationAngle) 0; sind(app.rectH.RotationAngle) cosd(app.rectH.RotationAngle) 0; 0 0 1];
            
            % Create the final transformation matrix
            newMat = centerImg *  app.initMat * rot * inv(centerImg) * scalingMat * offsetMat ;
            
            % Create a transformation object for the moving image
            newMat(:,3) = [0;0;1];
            newtform = affine2d(newMat);
            % Warp the moving image (app.fr_mov)
            % Note: szImgG is used here; make sure it's defined or provided
            imgM = imwarp(app.fr_mov, newtform, 'nearest', 'OutputView', imref2d(size(app.fr_fix)), 'FillValues', 0);
            
            % Update the image in the application (app.bcgImgH)
            app.bcgImgH.CData(:,:,[1 3]) = cat(3, imgM, imgM);
            app.bcgImgH.CData(:,:,2) = app.fr_fix;
            % Update the transformation object in the application
            app.tform = newtform;
        end
        
        function showResultCallback(app,~,~)
            % Displays the result as one of the drop down menu options
            %             pause(0.3);
            imgG = app.bcgImgH.CData(:,:,2);
            imgM = app.bcgImgH.CData(:,:,1);
            app.bcgImgH.CData = zeros(size(app.bcgImgH.CData),'single');
            if ~isempty(app.T)
                if isvalid(app.T)
                    stop(app.T);
                end
                delete(app.T)
            end
            switch lower(app.DisplayresultasDropDown.Value)
                case 'alternate view'
                    app.T = timer('Period', 0.75, 'ExecutionMode', 'FixedRate', 'TimerFcn', @(src,ev) TimerUpdate(app,src,ev));
                    bImShowed = false;
                    app.T.start;
                case 'reference'
                    app.bcgImgH.CData = repmat(imgG,1,1,3);
                case 'moving'
                    app.bcgImgH.CData = repmat(imgM,1,1,3);
                case 'difference'
                    app.bcgImgH.CData = repmat(imgM - imgG,1,1,3);
                otherwise
                    app.bcgImgH.CData(:,:,2) = imgG;
                    app.bcgImgH.CData(:,:,[1 3]) = cat(3,imgM, imgM);
            end
            
            % Local callbacks:
            function TimerUpdate(app,~,~)
                if( bImShowed )
                    app.bcgImgH.CData = repmat(imgG,1,1,3);
                    bImShowed = false;
                else
                    app.bcgImgH.CData = repmat(imgM,1,1,3);
                    bImShowed = true;
                end
                
            end
        end
        
        function DoNothing(~,~,~)
            %%EMPTY%% Used to avoid figure closing
            
        end
        
        function toggleGUIelements(app)
            % Hides/shows GUI elements that are dependent on the existence of
            % both the reference and the moving frames
            
            bHasfix = ~isempty(app.fixFileName);
            bHasMov = ~isempty(app.movFileName);
            
            app.rectH.Visible = 'on';
            
            if bHasfix && bHasMov
                app.AlignButton.Enable = 'on';
                app.ExportButton.Enable = 'on';
                app.OptionsPanel.Visible = 'on';
                app.FinetuningButton.Visible = 'on';
                app.FineTuningPanel.Visible = 'on';
                app.fH.Visible = 'on';
                app.DisplayresultasDropDown.Value = 'FalseColor';
            elseif bHasfix && ~bHasMov
                app.AlignButton.Enable = 'off';
                app.ExportButton.Enable = 'off';
                app.OptionsPanel.Visible = 'off';
                app.FinetuningButton.Visible = 'off';
                app.FineTuningPanel.Visible = 'off';
                app.fH.Visible = 'on';
                % Show only reference frame:
                app.DisplayresultasDropDown.Value = 'Reference';
                % Hide rectangle
                app.rectH.Visible = 'off';
                
            elseif ~bHasfix && bHasMov
                app.AlignButton.Enable = 'off';
                app.ExportButton.Enable = 'off';
                app.OptionsPanel.Visible = 'off';
                app.FinetuningButton.Visible = 'off';
                app.FineTuningPanel.Visible = 'off';
                app.fH.Visible = 'on';
                % Show only reference frame:
                app.DisplayresultasDropDown.Value = 'Moving';
                % Hide rectangle
                app.rectH.Visible = 'off';
            else
                app.AlignButton.Enable = 'off';
                app.ExportButton.Enable = 'off';
                app.OptionsPanel.Visible = 'off';
                app.FinetuningButton.Visible = 'off';
                app.FineTuningPanel.Visible = 'off';
                app.fH.Visible = 'off';
            end
            % Update image
            app.showResultCallback
        end
        
        function setupAxis(app)
        % Set figure axis properties:
        
            set(app.ax,'DataAspectRatio',[1 1 1]...
                ,'Xtick',[],'YTick',[]...
                ,'CLim',[0 1]...
                ,'XLimMode', 'auto'...
                ,'YLimMode', 'auto'...
                ,'ZLimMode', 'auto'...
                ,'CLimMode', 'manual'...
                ,'ALimMode', 'manual'...
                ,'Color','k'...
                ,'YDir','reverse');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, fixFile, movFile)
            app.UIFigure.Visible = 'off';
            
            app.LoadFixedButton.Tag = 'Fixed';
            app.LoadMovingButton.Tag = 'Moving';
            % Load fixed and moving files:
            if exist("fixFile","var")
                app.fixFileName = fixFile;
                % Load frames from both files:
                app.loadFrame('fixed');
            end
            if exist("movFile","var")
                
                app.movFileName = movFile;
                app.loadFrame('moving');
            end
            % Position app:
            movegui(app.UIFigure,'northwest');
            app.UIFigure.Position([1 2]) = app.UIFigure.Position([1 2]) - 5;
            app.UIFigure.Visible = 'on';
            %
            % Prepare axis:
            app.fH = figure("Name",'Manual Alignment','NumberTitle',"off",...
                'Position',[app.UIFigure.Position(1) + app.UIFigure.Position(3) + 5 app.UIFigure.Position(2) 750 app.UIFigure.OuterPosition(4) - 25],...
                'MenuBar',"none","ToolBar","figure",'doublebuffer','off','WindowButtonUpFcn',@app.showResultCallback,'CloseRequestFcn',@app.DoNothing);
            app.ax = axes(app.fH);
            app.setupAxis;
            % Plot
            app.showImages;
            % Toggle GUI elements:
            app.toggleGUIelements;
            % Update fine tuning options and hide section:            
            app.FinetuningButtonPushed(struct('Source',app.FinetuningButton));
            app.MovDropDownValueChanged;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.UIFigure.Position(3) = app.figWidthLarge;
            if app.bOK
                msg = 'Close app?';
                Title = 'Close';
            else
                msg = 'Cancel coregistration and close app?';
                Title = 'Cancel and Close';
            end
            answer = uiconfirm(app.UIFigure, msg,Title,'Options',{'Yes','No'},'DefaultOption',2);
            if strcmpi(answer,'no')
                app.UIFigure.Position(3) = app.figWidth;                
                return
            end
            % Delete timer
            if ~isempty(app.T)
                if isvalid(app.T)
                    stop(app.T)
                end
                delete(app.T)
            end
            % Delete figures
            delete(app.fH)
            delete(app)
        end

        % Value changed function: ImagesharpeningDropDown
        function ImagesharpeningDropDownValueChanged(app, event)
            % Sharpen the displayes images using either "adapthisteq" or an
            % equivalent of an unsharp masking where two spatially filtered version of the images are subtracted
            % The operation consists of a filtered image with a sigma of .5
            % minus a blurred version of the image. This operation mostly removes low frequency artifacts such as uneven illumination.
            
            app.fr_fix = app.orig_fr_fix;
            app.fr_mov = app.orig_fr_mov;
            
            switch lower(app.ImagesharpeningDropDown.Value)
                case 'enhance contrast'
                    app.fr_fix = adapthisteq(app.fr_fix);
                    app.fr_mov = adapthisteq(app.fr_mov);
                case 'gaussian filter'
                    radius = 0.05*max(size(app.fr_fix));
                    app.fr_fix = imgaussfilt(app.fr_fix,.5) - imgaussfilt(app.fr_fix, radius);
                    app.fr_fix = (app.fr_fix - min(app.fr_fix(:))) ./ (max(app.fr_fix(:)) - min(app.fr_fix(:)));
                    
                    app.fr_mov = imgaussfilt(app.fr_mov,.5) - imgaussfilt(app.fr_mov, radius);
                    app.fr_mov = (app.fr_mov- min(app.fr_mov(:))) ./ (max(app.fr_mov(:)) - min(app.fr_mov(:)));
                otherwise
                    % Apply unsharp mask
                    app.fr_fix = app.orig_fr_fix;
                    app.fr_mov = app.orig_fr_mov;
            end
            if app.InvertcolorsCheckBox.Value
                app.InvertcolorsCheckBoxValueChanged;
            end
            app.imageOverlayCallback();
            app.showResultCallback();
            
        end

        % Value changed function: FliphorizontallyCheckBox
        function FliphorizontallyCheckBoxValueChanged(app, event)
            % Flips moving image horizontally
            
            app.bcgImgH.CData(:,:,[1 3]) = cat(3,fliplr(app.bcgImgH.CData(:,:,1)),fliplr(app.bcgImgH.CData(:,:,1)));
            if app.FliphorizontallyCheckBox.Value
                app.initMat = [-1 0 0 ; 0 1 0 ; 0 0 1];
            else
                app.initMat = [1 0 0 ; 0 1 0 ; 0 0 1];
            end
            % Update images:
            app.imageOverlayCallback();
            app.showResultCallback();
        end

        % Value changed function: InvertcolorsCheckBox
        function InvertcolorsCheckBoxValueChanged(app, event)
            
            % Invert images color maps:
            app.fr_fix = 1 - app.fr_fix;
            app.fr_mov = 1- app.fr_mov;
            % Update images:
            app.imageOverlayCallback();
            app.showResultCallback();
        end

        % Value changed function: DisplayresultasDropDown
        function DisplayresultasDropDownValueChanged(app, event)
            app.imageOverlayCallback();
            app.showResultCallback(); %Update images
        end

        % Button pushed function: LoadFixedButton, LoadMovingButton
        function LoadDataButtonPushed(app, event)
            % Load .dat file:
            if strcmpi(event.Source.Tag,'fixed')
                name = 'Reference';
                flag = 'fixed';
                field = 'fixFileName';
            else
                name = 'Misaligned';
                flag = 'moving';
                field = 'movFileName';
            end
            w = uiprogressdlg(app.UIFigure,'Message','','Title','','Indeterminate',"on");
            [file,path] = uigetfile('*.dat',['Select ' name 'data'],'green.dat');
            if file == 0
                disp('Operation cancelled by User')
                return
            end
            app.(field) = fullfile(path,file);
            % Load frame
            app.loadFrame(flag);
            %
            app.showImages;
            % Hide/show figure and disable/enable controls depending on the existence of fixed and moving
            % frames:
            app.toggleGUIelements();
            delete(w)
        end

        % Button pushed function: AlignButton
        function AlignButtonPushed(app, event)
            % AlignButtonPushed - Apply geometric transformation to selected files
            %
            % This function is called when the "Align" button is pushed. It allows the user
            % to apply a geometric transformation to one or more selected files.
            %
            app.UIFigure.Position(3) = app.figWidthLarge;
            answer = uiconfirm(app.UIFigure,['Apply geometric transformation to file "' app.LoadMovingButton.Text '"?'],'Coregister images?','Options',{'Yes','Yes and apply to other files','Cancel'});
            if strcmpi(answer,'cancel')
                app.UIFigure.Position(3) = app.figWidth;
                return
            end
            w = uiprogressdlg(app.UIFigure,'Message','Please wait...','Title','Coregistering images','Indeterminate',"on");
            [folder,name,ext] = fileparts(app.movFileName);
            name = [name,ext];
            if ~strcmpi(answer,'Yes')
                % Get list of files in folder
                fileList = getFileList(fileparts(app.movFileName),'.dat');
                fileList = setdiff(fileList, {name},'stable');
                [indx, tf] = listdlg('ListString', fileList, 'PromptString', 'File List:', 'SelectionMode', 'multiple', 'ListSize', [300 400],...
                    'Name', 'Select extra Files to coregistrate');
                if tf == 0
                    close(w);
                    disp('Operation cancelled by User');
                    return
                end
                fileList = union({name},fileList(indx),'stable');
            else
                fileList = {name};
            end
            
            % Apply tform to data:
            
            RA = imref2d(size(app.fr_fix));
            tform = app.tform; %#ok
            for ii = 1:length(fileList)
                w.Title = ['Aligning "' fileList{ii} '"...'];
                w.Message = 'Loading data...';
                [dat,md] = loadDatFile(fullfile(folder, fileList{ii}));
                dat = imwarp(dat,imref2d(size(app.fr_mov)), tform, 'nearest', 'FillValues',0,'OutputView', RA);%#ok
                w.Message = 'Overwriting data...';
%                 fid = fopen(fullfile(folder, fileList{ii}), 'w');
%                 fwrite(fid, dat, 'single');
%                 fclose(fid);    
                % Update Meta data:
                md.datSize = [size(dat,1) size(dat,2)];
                %Save data:
                save2Dat(fullfile(folder,fileList{ii}),dat,md);
                w.Message = 'Done!';
            end
            close(w);
            % Copy the tform to the folder:
            save(fullfile(folder, 'tform.mat'),'tform');
            uialert(app.UIFigure, 'Finished!','',"CloseFcn",'uiresume(gcbf)', 'Icon','info')
            uiwait(app.UIFigure)
            app.UIFigure.Position(3) = app.figWidth;
            app.bOK = true;
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % ExportButtonPushed - Save the geometric transformation file
            %
            % This function allows the user to save the geometric transformation file
            % in the MAT format.
            %
            % Example:
            %   ExportButtonPushed(app, event)
            
            tform = app.tform; %#ok Get the transformation object
            
            [file, path] = uiputfile('*.mat', 'Save geometric transformation file', 'tform.mat');
            
            if file == 0
                disp('Operation cancelled by User');
                return;
            end
            
            try
                % Save the transformation object to the specified file
                save(fullfile(path, file), 'tform');
                
                % Adjust the UI figure position
                app.UIFigure.Position(3) = app.figWidthLarge;
                figure(app.UIFigure);
                
                % Display a confirmation message
                uialert(app.UIFigure, ['Geometric transformation file saved as: "' fullfile(path, file) '"'], 'Tform exported', "CloseFcn", 'uiresume(gcbf)','Icon','info');
                uiwait(gcbf);
                
                % Reset the UI figure position
                app.UIFigure.Position(3) = app.figWidth;
            catch exception
                % Handle any errors that may occur during file saving
                msg = ['Error while saving the file: ' exception.message];
                uialert(app.UIFigure, msg,'Error!','Icon','error');
            end
            app.bOK = true;
        end

        % Button pushed function: FinetuningButton
        function FinetuningButtonPushed(app, event)
            % Shows/Hides Fine tuning panel:
            
            if contains(event.Source.Icon, 'down',"IgnoreCase",true)
                % Hide Panel and change icon:
                event.Source.Icon = 'icon_rightTriangle_BW.png';
                app.GridLayout.RowHeight{7} = 0;                
                app.UIFigure.Position(4) = 535;
                app.UIFigure.Position(2) = app.UIFigure.Position(2) + 140;
            else
                % Show Panel and change icon:
                event.Source.Icon = 'icon_downTriangle_BW.png';
                app.GridLayout.RowHeight{7} = 130;
                app.UIFigure.Position(4) = 675;
                app.UIFigure.Position(2) = app.UIFigure.Position(2) - 140;
            end
        end

        % Value changed function: MovDropDown
        function MovDropDownValueChanged(app, event)

            % Update step parameters:                       
            idx = strcmpi(app.fineTuningSteps.ID,app.MovDropDown.Value);
            set(app.StepEditField, 'Value', app.fineTuningSteps.values(idx),'Limits',app.fineTuningSteps.limits(idx,:), 'RoundFractionalValues',app.fineTuningSteps.b_round(idx));
            % Update spinner parameters:
            set(app.Spinner,'Step',app.StepEditField.Value,'RoundFractionalValues',app.fineTuningSteps.b_round(idx)); 
            
        end

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            
            % Show results:
            app.showResultCallback;
            % Reset Value to zero:
            app.Spinner.Value = 0;
        end

        % Value changing function: Spinner
        function SpinnerValueChanging(app, event)
            % Move rectangle using the spinner arrows:
            
            % Set step:
            step = event.Value;
            % Move rectangle:
            switch app.MovDropDown.Value
                case 'xtranslation'
                    app.rectH.Position(1) = app.rectH.Position(1) + step;
                case 'ytranslation'    
                    app.rectH.Position(2) = app.rectH.Position(2) - step;
                case 'rotation'
                    app.rectH.RotationAngle = mod(app.rectH.RotationAngle - step,360);
                case 'scaling'
                    if step > 0 
                        app.rectH.Position([3 4]) = (1 + app.StepEditField.Value/100).*app.rectH.Position([3 4]);
                    else
                        app.rectH.Position([3 4]) = (1 - app.StepEditField.Value/100).*app.rectH.Position([3 4]);
                    end
            end
            app.imageOverlayCallback;
            % Reset Value to zero:
            app.Spinner.Value = 0;
        end

        % Value changed function: StepEditField
        function StepEditFieldValueChanged(app, event)
                        
            % Update step value in fineTuningSteps structure:
            idx = strcmpi(app.fineTuningSteps.ID, erase(app.MovDropDown.Value,' '));            
            app.fineTuningSteps.values(idx) = app.StepEditField.Value;
            % Update spinner step:
            if app.fineTuningSteps.b_round(idx)
                app.Spinner.Step = round(app.StepEditField.Value);
            else
                app.Spinner.Step = app.StepEditField.Value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Manual Alignment tool';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ReferencefileLabel = uilabel(app.GridLayout);
            app.ReferencefileLabel.Text = 'Reference file:';
            app.LoadFixedButton = uibutton(app.GridLayout, 'push');
            app.LoadFixedButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadFixedButton.Text = 'Load';
            app.MisalignedfileLabel = uilabel(app.GridLayout);
            app.MisalignedfileLabel.Text = 'Misaligned file:';
            app.LoadMovingButton = uibutton(app.GridLayout, 'push');
            app.LoadMovingButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadMovingButton.Text = 'Load';
            app.OptionsPanel = uipanel(app.GridLayout);
            app.OptionsPanel.Title = 'Options';
            app.DisplayresultasLabel = uilabel(app.OptionsPanel);
            app.DisplayresultasLabel.Text = 'Display result as:';
            app.DisplayresultasDropDown = uidropdown(app.OptionsPanel);
            app.DisplayresultasDropDown.Items = {'FalseColor', 'Reference', 'Moving', 'Difference', 'Alternate view', ''};
            app.DisplayresultasDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayresultasDropDownValueChanged, true);
            app.DisplayresultasDropDown.Value = 'FalseColor';
            app.FliphorizontallyCheckBox = uicheckbox(app.OptionsPanel);
            app.FliphorizontallyCheckBox.ValueChangedFcn = createCallbackFcn(app, @FliphorizontallyCheckBoxValueChanged, true);
            app.FliphorizontallyCheckBox.Tooltip = {'Flip moving frame horizontally'};
            app.FliphorizontallyCheckBox.Text = 'Flip horizontally';
            app.ImagesharpeningLabel = uilabel(app.OptionsPanel);
            app.ImagesharpeningLabel.Text = 'Image sharpening:';
            app.ImagesharpeningDropDown = uidropdown(app.OptionsPanel);
            app.ImagesharpeningDropDown.Items = {'None', 'Enhance Contrast', 'Gaussian Filter'};
            app.ImagesharpeningDropDown.ValueChangedFcn = createCallbackFcn(app, @ImagesharpeningDropDownValueChanged, true);
            app.ImagesharpeningDropDown.Tooltip = {'Select an option to filter the images'};
            app.ImagesharpeningDropDown.Value = 'None';
            app.InvertcolorsCheckBox = uicheckbox(app.OptionsPanel);
            app.InvertcolorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertcolorsCheckBoxValueChanged, true);
            app.InvertcolorsCheckBox.Tooltip = {'invert colors from both images'; ''};
            app.InvertcolorsCheckBox.Text = 'Invert colors';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.FinetuningButton = uibutton(app.GridLayout2, 'push');
            app.FinetuningButton.ButtonPushedFcn = createCallbackFcn(app, @FinetuningButtonPushed, true);
            app.FinetuningButton.Icon = 'icon_downTriangle_BW.png';
            app.FinetuningButton.Text = 'Fine tuning';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.AlignButton = uibutton(app.GridLayout3, 'push');
            app.AlignButton.ButtonPushedFcn = createCallbackFcn(app, @AlignButtonPushed, true);
            app.AlignButton.Tooltip = {'Align file'};
            app.AlignButton.Text = 'Align';
            app.ExportButton = uibutton(app.GridLayout3, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Tooltip = {'Export geometric transformation to a .mat file'};
            app.ExportButton.Text = 'Export';
            app.FineTuningPanel = uipanel(app.GridLayout);
            app.MovDropDown = uidropdown(app.FineTuningPanel);
            app.MovDropDown.Items = {'X translation', 'Y translation', 'Rotation (deg)', 'Scaling (%)'};
            app.MovDropDown.ItemsData = {'xtranslation', 'ytranslation', 'rotation', 'scaling'};
            app.MovDropDown.ValueChangedFcn = createCallbackFcn(app, @MovDropDownValueChanged, true);
            app.MovDropDown.Value = 'xtranslation';
            app.Spinner = uispinner(app.FineTuningPanel);
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Editable = 'off';
            app.MovementLabel = uilabel(app.FineTuningPanel);
            app.MovementLabel.Text = 'Movement:';
            app.StepLabel = uilabel(app.FineTuningPanel);
            app.StepLabel.Text = 'Step:';
            app.StepEditField = uieditfield(app.FineTuningPanel, 'numeric');
            app.StepEditField.Limits = [0 Inf];
            app.StepEditField.ValueChangedFcn = createCallbackFcn(app, @StepEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manualAlignFrames(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end