classdef retino_maps < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RetinotopySpectrumMapsLabel  matlab.ui.control.Label
        OpenButton                   matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes_2                     matlab.ui.control.UIAxes
        UIAxes_3                     matlab.ui.control.UIAxes
        Label                        matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        DirectionDropDownLabel       matlab.ui.control.Label
        DirectionDropDown            matlab.ui.control.DropDown
        plotButton                   matlab.ui.control.Button
        GenerateFigureButton         matlab.ui.control.Button
        ChannelfileDropDownLabel     matlab.ui.control.Label
        ChannelfileDropDown          matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        freq = 21 % frequency index chosen by user in fft plot
        start = false % boolean to start picking up the current cursor position.
        currentPt  = [1 1]% current point clicked with mouse.
        powData % power component of FFT data.
        phiData % angle component of FFT data.
        filesInfo % Description
        currentFile % Description
        folders % list of folders chosen by user
    end
    
    methods (Access = private)
        
        function updateFFTplot(app)
            pow = squeeze(app.powData(app.currentPt(1), app.currentPt(2), :));
            plot(app.UIAxes_3, pow, 'o-', 'Color', [.4 .4 .4], 'MarkerSize',3, 'MarkerFaceColor',[.4 .4 .4]);
            hold(app.UIAxes_3, 'on')
            plot(app.UIAxes_3, app.freq, pow(app.freq), 'ro', 'MarkerSize',10);
            hold(app.UIAxes_3, 'off');
            app.UIAxes_3.XLim = [2 2*app.freq];
        end
        
        function updateImages(app)
            powFr = abs(app.powData(:,:,app.freq));
            phiFr = app.phiData(:,:,app.freq);
            phiFr = mod(-1*phiFr,2*pi); % From Zhuang et al 2017
            imagesc(app.UIAxes, powFr); colormap(app.UIAxes, 'hot');
            app.UIAxes.XLim = [1 size(powFr,2)];
            app.UIAxes.YLim = [1 size(powFr,1)];
            app.UIAxes_2.XLim = app.UIAxes.XLim;
            app.UIAxes_2.YLim = app.UIAxes.YLim;
            hold(app.UIAxes, 'on');
            line(app.UIAxes, [app.UIAxes.XLim(1) app.UIAxes.XLim(2)], [app.currentPt(2) app.currentPt(2)], 'Color', 'white');
            line(app.UIAxes, [app.currentPt(1) app.currentPt(1)], [app.UIAxes.YLim(1) app.UIAxes.YLim(2)], 'Color', 'white');
            hold(app.UIAxes, 'off');
            imagesc(app.UIAxes_2, phiFr); colormap(app.UIAxes_2, 'hsv');
        end
        
        function in_bounds = getBounds(~, ax, currentPt)
            x_lims = get(ax, 'XLim');
            y_lims = get(ax, 'YLim');
            
            in_bounds = (currentPt(1) >= x_lims(1)) && ...
                (currentPt(1) <= x_lims(2)) && ...
                (currentPt(2) >= y_lims(1)) && ...
                (currentPt(2) <= y_lims(2));
        end
        
        function updateCurrentCoords(app)
            coords = get(app.UIFigure, 'CurrentPoint');
            app.CurrentCoords = round(coords(1,1:2));
            
        end
        
        function updateDirectionDropDownMenu(app)
            chan = app.ChannelfileDropDown.Value;
            idx = arrayfun(@(x) any(strcmp(chan, x.Channels)), app.filesInfo);
            if ~iscell([app.filesInfo(idx).direction])
                dirList = {app.filesInfo(idx).direction};
            else
                dirList = [app.filesInfo(idx).direction];
            end
            app.DirectionDropDown.Items = dirList;
        end
        
        function state = calculateFFT(app)
            % CALCULATEFFT calculates the Fast Fourier Transformation of a file
            % listed in APP.FILESINFO.
            % Output: 
            % state = boolean to indicate if calculation was successful.
            state = false;
            channel = app.ChannelfileDropDown.Value;
            direction = app.DirectionDropDown.Value;
            % Calculate FFT for different files
            if ~iscell(app.filesInfo(1).direction)
                idx = strcmp({app.filesInfo.direction}, direction);
                file = fullfile(app.filesInfo(idx).Folder, channel);
                mapDat = mapDatFile(file);
                data = flipud(rot90(mapDat.Data.data));
                % Calculate FFT for one file with all directions
            else
                file = fullfile(app.filesInfo.Folder, channel);
                events_file = fullfile(app.filesInfo.Folder, 'TTL_events.mat');
                if ~exist('events_file', 'file')
                    uialert(app.UIFigure, ['Cannot find TTL_events.mat file in ' app.filesInfo.Folder], 'Error!', 'Icon', 'error', 'modal', true);
                    return
                end
                a = load(events_file);
                mapDat = mapDatFile(file);
                metaData = matfile(strrep(file, '.dat', '_info.mat'));
                sr = metaData.Freq;
                stimVizInfo = metaData.stimVizInfo;
                interstimFr = stimVizInfo.interStimTime*sr; % transform to samples.
                timestamps = ceil(a.timestamps*sr); % transform to samples.
                
                start = timestamps(a.state == 1);
                stop = timestamps(a.state == 0);
                % control for missing timestamps
                if (length(start)~=length(stop)) || (lenght(start)~=app.filesInfo.nSweeps) || (lenght(start)~=app.filesInfo.nSweeps)
                    uialert(app.UIFigure, 'Error! Number of sweeps are different from number of TTL time stamps.', 'Error!', 'Icon', 'error', 'modal', true);
                    return
                end
               
                indx = find(strcmp(direction, app.filesInfo.direction));
                sweep_stop = indx*app.filesInfo.nSweeps;
                sweep_start = sweep_stop - app.filesInfo.nSweeps + 1;
                
                frStart = start(sweep_start) - round(interstimFr/2);
                frStop = stop(sweep_stop) + round(interstimFr/2);
                if frStop > size(mapDat.Data.data,3)
                    frStop = size(mapDat.Data.data,3);
                end
                data = mapDat.Data.data(:,:,frStart:frStop);
                data = flipud(rot90(data));
            end
            fftData = fft(data, [],3);
            app.powData = abs(fftData);
            app.phiData = angle(fftData);
            app.currentFile = file;
            state = true;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Label_2.Text = 'Open a file.';
            app.UIAxes.Title.String = 'power Map';
            app.UIAxes.DataAspectRatio = [1 1 1];
            
            app.UIAxes_2.Title.String = 'phase Map';
            app.UIAxes_2.DataAspectRatio = [1 1 1];
            
            app.UIAxes_3.Title.String = 'pixel FFT power plot';
            app.UIAxes_3.XLabel.String = 'Frequency (samples)';
            app.UIAxes_3.YLabel.String = 'power (A.U)';
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            app.filesInfo = [];
            folders = uigetfile_n_dir(pwd, 'Select Retino Acquisition Folder(s)');
            for i = 1:numel(folders)
                cd(folders{i})
                files = dir('**\*.dat');
                app.filesInfo(i).Channels =  {files.name};
                folder = unique({files.folder});
                app.filesInfo(i).Folder = folder{:};
                try
                    metaData = matfile(strrep(fullfile(files(1).folder, files(1).name), '.dat', '_info.mat'));
                    vizInfo = metaData.stimVizInfo;
                    app.filesInfo(i).direction = num2str(vizInfo.DriftDirection);
                    app.filesInfo(i).nSweeps = vizInfo.nTrials;
                catch
                    uialert(app.UIFigure, ['Cannot find direction info for experiment folder ' folders{i}], 'Wrong Folder?', "Icon", "error");
                end
            end
            
            % Check if all directions are different and control for
            % different experiment types (separate files with different
            % directions VS one file with all directions).
            directions = {app.filesInfo.direction};
            if numel(directions) ~= numel(unique(directions))
                uialert(app.UIFigure, 'Equal directions found. Please, select folders with different drift directions and try again.',...
                    'Equal directions found!', "Icon", "error");
                return
            elseif any(contains(directions, '-1')) && numel(directions) ~= 1
                uialert(app.UIFigure, 'Incompatible experiment types! Try again.',...
                    'Incompatible retinotopy experiments!', "Icon", "error");
                return
            elseif numel(app.filesInfo) == 1 && strcmp(directions, '-1')
                direction = 0:90:270;
                direction = arrayfun(@(x) num2str(x), direction, 'UniformOutput', false);
                app.filesInfo.direction = direction;
            else
                nums = cellfun(@(x) str2double(x), directions, 'UniformOutput',false);
                [~,idx] = sort([nums{:}]);
                app.filesInfo = app.filesInfo(idx);
            end
            app.Label_2.Text = 'Opening file...'; drawnow;
            chanList = unique([app.filesInfo.Channels]);
            app.ChannelfileDropDown.Items = chanList;
            app.ChannelfileDropDown.Value = chanList{1};
            app.updateDirectionDropDownMenu;
            state = app.calculateFFT;
            if ~state
                app.Label_2.Text = 'Open a file.';
                return
            end
            app.Label_2.Text  = ['File : ' app.currentFile];
            % update FFtplot:
            app.updateFFTplot;
            % update images:
            app.updateImages;
            app.start = true;
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            currentPt_img = get(app.UIAxes, 'CurrentPoint');
            currentPt_img = round(currentPt_img(1,[1 2]));
            currentPt_plot = get(app.UIAxes_3, 'CurrentPoint');
            currentPt_plot = round(currentPt_plot(1,[1 2]));
            in_bounds_img = app.getBounds(app.UIAxes, currentPt_img);
            in_bounds_plot = app.getBounds(app.UIAxes_3, currentPt_plot);
            if in_bounds_img
                app.currentPt = currentPt_img;
            elseif in_bounds_plot
                app.freq = currentPt_plot(1);
            end
            app.updateImages;
            app.updateFFTplot;
            
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            app.Label_2.Text = 'Updating plot and images...';drawnow
            app.calculateFFT;
            app.updateImages;
            app.updateFFTplot;
            app.Label_2.Text = app.currentFile;
        end

        % Value changed function: ChannelfileDropDown
        function ChannelfileDropDownValueChanged(app, event)
            app.updateDirectionDropDownMenu;
        end

        % Button pushed function: GenerateFigureButton
        function GenerateFigureButtonPushed(app, event)
            
            directions = app.DirectionDropDown.Items;
            directions = cellfun(@str2double, directions, 'UniformOutput',false);
            directions = [directions{:}];
            if ~ismember(90,diff(directions))
                uialert(app.UIFigure, 'Unable to create figure. You have to provide at least 2 perpendicular sweep directions.', 'Error!', 'Icon', 'error', 'Modal',true)
                return
            end
            app.Label_2.Text = 'Creating MV figure... Please wait...'; drawnow
            indx = find(diff(directions)== 90, 1, 'first');
            indx = 1+[indx-1 indx];
            for i = 1:numel(indx)
                app.DirectionDropDown.Value = app.DirectionDropDown.Items(indx(i));
                app.calculateFFT
                s.(['powF' num2str(i)]) = app.powData(:,:,app.freq);
                s.(['phiF' num2str(i)]) = app.phiData(:,:,app.freq);
                s.(['dir' num2str(i)]) = app.DirectionDropDown.Value;
            end
            fig = figure;
            fig.Visible = 'off';
            subplot(3,5,1); imagesc(s.phiF2,'AlphaData',s.powF2, "AlphaDataMapping","scaled"); ax=gca;ax.Color = [0 0 0]; colormap(ax,'hsv');title(['Elev (' s.dir2 '\circ)']); axis image;
            subplot(3,5,2); imagesc(s.phiF2);ax=gca; colormap(ax, 'hsv'); title('phase only'); axis image;
            subplot(3,5,3); imagesc(s.powF2);ax=gca;colormap(ax,'hot'); title('magnitude only'); axis image;
            
            subplot(3,5,6); imagesc(s.phiF1,'AlphaData',s.powF1, "AlphaDataMapping","scaled"); ax=gca;ax.Color = [0 0 0]; colormap(ax,'hsv');title(['Azim (' s.dir1 '\circ)']); axis image;
            subplot(3,5,7); imagesc(s.phiF1);ax=gca; colormap(ax, 'hsv'); title('phase only'); axis image;
            subplot(3,5,8); imagesc(s.powF1);ax=gca;colormap(ax,'hot'); title('magnitude only'); axis image;
            
            [Gx,Gy] = imgradientxy(s.phiF2);
            [Gmag1,G1] = imgradient(Gx,Gy);
            [Gx,Gy] = imgradientxy(s.phiF1);
            [Gmag2,G2] = imgradient(Gx,Gy);
            G1 = ((G1- min(G1(:)))/ max(G1 - min(G1(:)), [], 'all')) - .5;
            G2 = ((G2- min(G2(:)))/ max(G2 - min(G2(:)), [], 'all')) - .5;
            Gmag = sqrt(Gmag1.^2 + Gmag2.^2);
            dif = G2-G1;
            dif = dif + (dif<-.5)*1;
            dif = 2*dif;
            VSM = sin(dif*pi);
            M = s.powF2 + s.powF1;
            Me = sqrt(s.powF2.^2 + s.powF1.^2);
            VSM_Gmag = VSM.*Gmag;
            VSM_M = VSM.*M;
            VSM_Me = VSM.*Me;
            
            subplot(3,5,4); imagesc(G1);ax=gca; colormap(ax,'hsv'); title('dir grad Elev'); axis image;
            subplot(3,5,9); imagesc(G2); ax=gca; colormap(ax, 'hsv'); title('dir grad Azim'); axis image;
            subplot(3,5,5); imagesc(Gmag1),ax=gca; colormap(ax, 'gray'); title('grad mag Elev'); axis image;
            subplot(3,5,10); imagesc(Gmag2);ax=gca; colormap(ax, 'gray'); title('grad mag Azim'); axis image;
            subplot(3,5,11); imagesc(VSM);ax=gca; colormap(ax, 'jet'); title('sign map'); axis image;
            subplot(3,5,12); imagesc(Gmag);ax=gca; colormap(ax, 'jet'); title('grad mag Euclidian'); axis image;
            subplot(3,5,13); imagesc(VSM_Gmag,[-1 1]);ax=gca; colormap(ax, 'jet'); title('VSM/grad map'); axis image;
            subplot(3,5,14); imagesc(VSM_M);ax=gca; colormap(ax,'jet'); title('VSM/ampl Fourier'); axis image;
            subplot(3,5,15); imagesc(VSM_Me);ax=gca; colormap(ax,'jet'); title('VSM/ampl Fourier (eucl.)'); axis image;
            fig.Name = ['Retinotopy Plots of channel file ' app.currentFile];
            fig.Visible = 'on';
            app.Label_2.Text = 'MV figure created!';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.RetinotopySpectrumMapsLabel = uilabel(app.UIFigure);
            app.RetinotopySpectrumMapsLabel.Text = 'Retinotopy Spectrum Maps';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'* Click on a pixel from the upper left image to update fft plot.'; '** Click on a point in fft plot to update images.'};
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.DirectionDropDownLabel = uilabel(app.UIFigure);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.UIFigure);
            app.plotButton = uibutton(app.UIFigure, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Text = 'plot!';
            app.GenerateFigureButton = uibutton(app.UIFigure, 'push');
            app.GenerateFigureButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFigureButtonPushed, true);
            app.GenerateFigureButton.Text = {'Generate'; 'Figure'};
            app.ChannelfileDropDownLabel = uilabel(app.UIFigure);
            app.ChannelfileDropDownLabel.Text = 'Channel file';
            app.ChannelfileDropDown = uidropdown(app.UIFigure);
            app.ChannelfileDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelfileDropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = retino_maps

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end