classdef PipelineManagerTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        DiagnosticsPanel          matlab.ui.container.Panel
        GridDiagnostics           matlab.ui.container.GridLayout
        StatusLabel               matlab.ui.control.Label
        DiagnosticsTextArea       matlab.ui.control.TextArea
        ExecutionOrderPanel       matlab.ui.container.Panel
        GridExecutionOrder        matlab.ui.container.GridLayout
        ExecGraphHTML             matlab.ui.control.HTML
        SelectedstepdetailsPanel  matlab.ui.container.Panel
        GridSelectedStepDetails   matlab.ui.container.GridLayout
        FunctionNameField         matlab.ui.control.EditField
        ParametersTextArea        matlab.ui.control.TextArea
        ParametersLabel           matlab.ui.control.Label
        StepSummaryTextArea       matlab.ui.control.TextArea
        StepSummaryLabel          matlab.ui.control.Label
        StepTagField              matlab.ui.control.EditField
        StepTagLabel              matlab.ui.control.Label
        StepNameLabel             matlab.ui.control.Label
        PipelineGraphPanel        matlab.ui.container.Panel
        GridPipelineGraph         matlab.ui.container.GridLayout
        GraphHTML                 matlab.ui.control.HTML
        RefreshButton             matlab.ui.control.Button
        ResetViewButton           matlab.ui.control.Button
        AutoLayoutButton          matlab.ui.control.Button
        AvailableFunctionsPanel   matlab.ui.container.Panel
        GridAvailableFunctions    matlab.ui.container.GridLayout
        AddasNewBranchButton      matlab.ui.control.Button
        AddButton                 matlab.ui.control.Button
        FunctionTree              matlab.ui.container.Tree
        Node                      matlab.ui.container.TreeNode
        Node2                     matlab.ui.container.TreeNode
        Node3                     matlab.ui.container.TreeNode
        Node4                     matlab.ui.container.TreeNode
        SearchFunctionEditField   matlab.ui.control.EditField
        GridLayoutTop             matlab.ui.container.GridLayout
        ClearPipelineButton       matlab.ui.control.Button
        ValidateButton            matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        LoadButton                matlab.ui.control.Button
        NewButton                 matlab.ui.control.Button
    end


    properties (Access = private)
        pm = []
        graphHtmlFile char = ''
        execHtmlFile char = ''
        selectedNodeID double = NaN
        selectedStepTag char = ''
        selectedFunctionName char = ''
        bGraphHtmlReady logical = false
        bExecHtmlReady logical = false

        bReconnectMode logical = false
        reconnectTargetNodeID double = NaN
        reconnectTargetStepTag char = ''
        reconnectTargetInputName char = ''
        reconnectCandidateNodeIDs double = []
    end

    methods (Access = private)

        function appFolder = getAppFolder(~)
            appFile = mfilename('fullpath');
            appFolder = fileparts(appFile);
        end

        function initializeUiState(app)
            %INITIALIZEUISTATE Reset passive UI content and local state.

            app.UIFigure.Name = 'Pipeline Manager Tool';

            app.selectedNodeID = NaN;
            app.selectedStepTag = '';
            app.selectedFunctionName = '';
            app.bGraphHtmlReady = false;
            app.bExecHtmlReady = false;

            app.bReconnectMode = false;
            app.reconnectTargetNodeID = NaN;
            app.reconnectTargetStepTag = '';
            app.reconnectTargetInputName = '';
            app.reconnectCandidateNodeIDs = [];

            app.StepTagField.Value = '';
            app.FunctionNameField.Value = '';
            app.StepSummaryTextArea.Value = {'<no step selected>'};
            app.ParametersTextArea.Value = {'<no step selected>'};
            app.DiagnosticsTextArea.Value = {''};

            app.AddButton.Enable = 'off';
            app.AddasNewBranchButton.Enable = 'off';
            app.StepNameLabel.Text = 'Function';

            app.setStatus('Ready');

            try
                delete(app.FunctionTree.Children);
            catch
            end
        end

        function tf = promptCreatePipelineManager(app)
            %PROMPTCREATEPIPELINEMANAGER Create a new PipelineManager from a SaveFolder.

            tf = false;

            saveFolder = uigetdir(pwd, 'Select the SaveFolder for the new pipeline');
            if isequal(saveFolder, 0)
                app.appendDiagnostic('New pipeline creation cancelled by user.');
                app.setStatus('New pipeline cancelled.');
                return
            end

            try
                app.pm = PipelineManager(saveFolder);
                app.selectedNodeID = NaN;
                app.selectedStepTag = '';
                app.selectedFunctionName = '';

                app.populateFunctionTree();
                app.refreshAllViews();

                app.appendDiagnostic(sprintf('Created new PipelineManager using SaveFolder: %s', saveFolder));
                app.setStatus('New pipeline ready.');
                tf = true;

            catch ME
                app.appendDiagnostic(sprintf('Failed to create PipelineManager: %s', ME.message));
                app.setStatus('Failed to create PipelineManager.');
            end
        end

        function ensureHtmlFilesExist(app)
            %ENSUREHTMLFILESEXIST Resolve the HTML files used by the uihtml controls.

            appFolder = app.getAppFolder();

            graphTemplate = fullfile(appFolder, 'pipeline_manager_graph.html');
            execTemplate  = fullfile(appFolder, 'pipeline_manager_execution.html');

            if isfile(graphTemplate) && isfile(execTemplate)
                app.graphHtmlFile = graphTemplate;
                app.execHtmlFile = execTemplate;
            else
                htmlRoot = fullfile(tempdir, 'PipelineManagerTool_html');
                if ~isfolder(htmlRoot)
                    mkdir(htmlRoot);
                end

                app.graphHtmlFile = fullfile(htmlRoot, 'pipeline_manager_graph.html');
                app.execHtmlFile  = fullfile(htmlRoot, 'pipeline_manager_execution.html');

                app.writeTextFile(app.graphHtmlFile, app.getGraphHtmlLines());
                app.writeTextFile(app.execHtmlFile, app.getExecHtmlLines());
            end

            app.GraphHTML.HTMLSource = app.graphHtmlFile;
            app.ExecGraphHTML.HTMLSource = app.execHtmlFile;
        end

        function writeTextFile(~, filePath, lineList)
            %WRITETEXTFILE Write a cell array of lines to a text file.

            fid = fopen(filePath, 'w');
            if fid == -1
                error('PipelineManagerTool:writeTextFile:OpenFailed', ...
                    'Could not open "%s" for writing.', filePath);
            end

            cleaner = onCleanup(@() fclose(fid)); %#ok<NASGU>
            fprintf(fid, '%s\n', lineList{:});
        end

        function lineList = getGraphHtmlLines(~)
            %GETGRAPHHTMLLINES Fallback HTML source.

            lineList = { ...
                '<!DOCTYPE html>', ...
                '<html>', ...
                '<head>', ...
                '  <meta charset="utf-8">', ...
                '  <title>Pipeline Graph</title>', ...
                '  <style>', ...
                '    html, body { margin: 0; padding: 0; width: 100%; height: 100%; overflow: auto; background: #f4f5f7; font-family: Arial, Helvetica, sans-serif; }', ...
                '    #root { width: 100%; height: 100%; }', ...
                '    #canvas { position: relative; min-width: 1000px; min-height: 700px; }', ...
                '    #edges { position: absolute; left: 0; top: 0; pointer-events: none; overflow: visible; z-index: 0; }', ...
                '    #nodeLayer { position: absolute; inset: 0; z-index: 1; }', ...
                '    #edgeLabels { position: absolute; inset: 0; pointer-events: none; z-index: 2; }', ...
                '    #hint { position: absolute; top: 18px; left: 18px; color: #7a7a7a; font-size: 13px; z-index: 3; }', ...
                '    #hoverCard { position: fixed; z-index: 1200; max-width: 420px; background: #ffffff; border: 1px solid #cfd5dc; border-radius: 12px; box-shadow: 0 8px 24px rgba(0,0,0,0.18); padding: 10px 12px; display: none; pointer-events: none; white-space: pre-wrap; font-size: 12px; color: #2f3440; line-height: 1.35; }', ...
                '    #modeBanner { position: fixed; top: 12px; left: 50%; transform: translateX(-50%); z-index: 1250; display: none; align-items: center; gap: 12px; background: #ffffff; border: 1px solid #cfd5dc; border-radius: 999px; box-shadow: 0 6px 20px rgba(0,0,0,0.16); padding: 8px 12px; font-size: 12px; color: #2f3440; }', ...
                '    #modeBanner button { border: 1px solid #cfd5dc; background: #f8f9fb; border-radius: 999px; padding: 4px 10px; font-size: 12px; cursor: pointer; }', ...
                '    #modeBanner button:hover { background: #eef4ff; }', ...
                '    #choicePopup { position: fixed; z-index: 1300; min-width: 240px; max-width: 360px; background: #ffffff; border: 1px solid #cfd5dc; border-radius: 10px; box-shadow: 0 8px 24px rgba(0,0,0,0.18); display: none; overflow: hidden; }', ...
                '    .choiceHeader { padding: 8px 10px; font-size: 11px; font-weight: 700; color: #5f6368; border-bottom: 1px solid #eef1f4; text-transform: uppercase; letter-spacing: 0.04em; }', ...
                '    .choiceItem { padding: 10px 12px; font-size: 12px; cursor: pointer; }', ...
                '    .choiceItem:hover { background: #eef4ff; }', ...
                '    .node { position: absolute; width: 190px; height: 76px; box-sizing: border-box; border: 1px solid #c8ccd3; border-radius: 12px; background: #ffffff; box-shadow: 0 2px 8px rgba(0,0,0,0.08); padding: 10px 12px; cursor: pointer; display: flex; flex-direction: column; justify-content: center; transition: opacity 0.12s ease, box-shadow 0.12s ease, border-color 0.12s ease; }', ...
                '    .node.folder { background: #f7fbff; }', ...
                '    .node.selected { border: 2px solid #2f78ff; box-shadow: 0 0 0 2px rgba(47,120,255,0.15); }', ...
                '    .node.error { border-color: #d14343; box-shadow: 0 0 0 2px rgba(209,67,67,0.12); }', ...
                '    .node.warning { border-color: #d48a00; box-shadow: 0 0 0 2px rgba(212,138,0,0.12); }', ...
                '    .node.nonblocking { border-color: #c97300; border-style: dashed; box-shadow: 0 0 0 2px rgba(201,115,0,0.14); }', ...
                '    .node.reconnectTarget { border-color: #7c4dff; box-shadow: 0 0 0 2px rgba(124,77,255,0.16); }', ...
                '    .node.reconnectCandidate { border-color: #1f8f4e; box-shadow: 0 0 0 2px rgba(31,143,78,0.16); }', ...
                '    .node.reconnectMuted { opacity: 0.35; }', ...
                '    .node.ghostSource { border-style: dashed; border-color: #1f8f4e; background: #f5fbf7; }', ...
                '    .nodeHeader { display: flex; align-items: center; justify-content: space-between; gap: 8px; }', ...
                '    .title { font-size: 13px; font-weight: 700; white-space: nowrap; overflow: hidden; text-overflow: ellipsis; }', ...
                '    .subtitle { font-size: 12px; color: #5f6368; margin-top: 3px; white-space: nowrap; overflow: hidden; text-overflow: ellipsis; }', ...
                '    .iconRow { display: flex; align-items: center; gap: 6px; flex-shrink: 0; }', ...
                '    .icon { font-size: 13px; line-height: 1; }', ...
                '    .edgePath { stroke: #8c99a5; stroke-width: 2; fill: none; }', ...
                '    .edgeLabelPill { position: absolute; transform: translate(-50%, -50%); padding: 2px 6px; border-radius: 999px; background: rgba(255,255,255,0.97); border: 1px solid #d7dce3; box-shadow: 0 1px 4px rgba(0,0,0,0.10); font-size: 10px; color: #374151; white-space: nowrap; max-width: 180px; overflow: hidden; text-overflow: ellipsis; }', ...
                '    #menu { position: fixed; z-index: 1000; min-width: 190px; background: #ffffff; border: 1px solid #cfd5dc; border-radius: 10px; box-shadow: 0 6px 20px rgba(0,0,0,0.18); display: none; overflow: hidden; }', ...
                '    .menuItem { padding: 10px 12px; font-size: 12px; cursor: pointer; }', ...
                '    .menuItem:hover { background: #eef4ff; }', ...
                '  </style>', ...
                '</head>', ...
                '<body>', ...
                '  <div id="root">', ...
                '    <div id="canvas">', ...
                '      <svg id="edges"></svg>', ...
                '      <div id="nodeLayer"></div>', ...
                '      <div id="edgeLabels"></div>', ...
                '      <div id="hint">No pipeline loaded.</div>', ...
                '    </div>', ...
                '    <div id="menu">', ...
                '      <div class="menuItem" onclick="menuAction(''removeStep'')">Remove step</div>', ...
                '      <div class="menuItem" onclick="menuAction(''reconnectStep'')">Reconnect inputs</div>', ...
                '      <div class="menuItem" onclick="menuAction(''configureSaveOutputs'')">Save Outputs</div>', ...
                '      <div class="menuItem" onclick="menuAction(''editParameters'')">Edit parameters</div>', ...
                '    </div>', ...
                '    <div id="hoverCard"></div>', ...
                '    <div id="modeBanner">', ...
                '      <span id="modeBannerText"></span>', ...
                '      <button onclick="cancelReconnectMode()">Cancel</button>', ...
                '    </div>', ...
                '    <div id="choicePopup"></div>', ...
                '  </div>', ...
                '', ...
                '  <script>', ...
                '    let htmlComponent = null;', ...
                '    let model = { nodes: [], edges: [], selectedNodeID: null, reconnectMode: false, reconnectBanner: "", reconnectGhost: null };', ...
                '    let contextNode = null;', ...
                '    let hoverTimer = null;', ...
                '    const hoverDelayMs = 450;', ...
                '', ...
                '    function setup(component) {', ...
                '      htmlComponent = component;', ...
                '      htmlComponent.addEventListener("DataChanged", function() { applyData(); });', ...
                '      document.addEventListener("click", function() { hideMenu(); hideChoicePopup(); });', ...
                '      document.addEventListener("scroll", function() { hideHover(); hideChoicePopup(); }, true);', ...
                '      applyData();', ...
                '      htmlComponent.sendEventToMATLAB("htmlReady", { component: "graph" });', ...
                '    }', ...
                '', ...
                '    function normalizeToArray(value) {', ...
                '      if (Array.isArray(value)) {', ...
                '        return value;', ...
                '      }', ...
                '      if (value === undefined || value === null) {', ...
                '        return [];', ...
                '      }', ...
                '      if (typeof value === "object") {', ...
                '        return [value];', ...
                '      }', ...
                '      return [];', ...
                '    }', ...
                '', ...
                '    function applyData() {', ...
                '      const d = (htmlComponent && htmlComponent.Data) ? htmlComponent.Data : {};', ...
                '      model.nodes = normalizeToArray(d.nodes);', ...
                '      model.edges = normalizeToArray(d.edges);', ...
                '      if (Object.prototype.hasOwnProperty.call(d, "selectedNodeID")) {', ...
                '        model.selectedNodeID = d.selectedNodeID;', ...
                '      }', ...
                '      model.reconnectMode = !!d.reconnectMode;', ...
                '      model.reconnectBanner = d.reconnectBanner || "";', ...
                '      model.reconnectGhost = d.reconnectGhost || null;', ...
                '', ...
                '      if (d.command === "resetView") {', ...
                '        document.documentElement.scrollTop = 0;', ...
                '        document.documentElement.scrollLeft = 0;', ...
                '      }', ...
                '', ...
                '      render();', ...
                '', ...
                '      if (htmlComponent) {', ...
                '        htmlComponent.sendEventToMATLAB("graphRendered", { nodeCount: model.nodes.length, edgeCount: model.edges.length });', ...
                '      }', ...
                '    }', ...
                '', ...
                '    function render() {', ...
                '      const nodeLayer = document.getElementById("nodeLayer");', ...
                '      const edgeLabels = document.getElementById("edgeLabels");', ...
                '      const svg = document.getElementById("edges");', ...
                '      const hint = document.getElementById("hint");', ...
                '      const modeBanner = document.getElementById("modeBanner");', ...
                '      const modeBannerText = document.getElementById("modeBannerText");', ...
                '', ...
                '      nodeLayer.innerHTML = "";', ...
                '      edgeLabels.innerHTML = "";', ...
                '      svg.innerHTML = "";', ...
                '      hideHover();', ...
                '      hideChoicePopup();', ...
                '', ...
                '      if (model.reconnectMode && model.reconnectBanner) {', ...
                '        modeBanner.style.display = "flex";', ...
                '        modeBannerText.textContent = model.reconnectBanner;', ...
                '      } else {', ...
                '        modeBanner.style.display = "none";', ...
                '        modeBannerText.textContent = "";', ...
                '      }', ...
                '', ...
                '      if (!model.nodes.length) {', ...
                '        hint.style.display = "block";', ...
                '        return;', ...
                '      }', ...
                '', ...
                '      hint.style.display = "none";', ...
                '', ...
                '      const nodeMap = {};', ...
                '      let maxX = 1000;', ...
                '      let maxY = 700;', ...
                '', ...
                '      if (model.reconnectMode && model.reconnectGhost) {', ...
                '        maxX = Math.max(maxX, (model.reconnectGhost.x || 0) + (model.reconnectGhost.width || 170) + 80);', ...
                '        maxY = Math.max(maxY, (model.reconnectGhost.y || 0) + (model.reconnectGhost.height || 64) + 80);', ...
                '      }', ...
                '', ...
                '      model.nodes.forEach(function(n) {', ...
                '        nodeMap[n.id] = n;', ...
                '        maxX = Math.max(maxX, (n.x || 0) + (n.width || 190) + 80);', ...
                '        maxY = Math.max(maxY, (n.y || 0) + (n.height || 76) + 80);', ...
                '', ...
                '        const el = document.createElement("div");', ...
                '        let className = "node";', ...
                '        if (n.id === model.selectedNodeID) { className += " selected"; }', ...
                '        if (n.kind === "folder") { className += " folder"; }', ...
                '        if (n.hasError) { className += " error"; }', ...
                '        else if (n.hasInvalidNonblocking) { className += " nonblocking"; }', ...
                '        else if (n.hasWarning) { className += " warning"; }', ...
                '        if (n.isReconnectTarget) { className += " reconnectTarget"; }', ...
                '        if (n.isReconnectCandidate) { className += " reconnectCandidate"; }', ...
                '        if (model.reconnectMode && n.isReconnectMuted) { className += " reconnectMuted"; }', ...
                '        el.className = className;', ...
                '        el.style.left = (n.x || 0) + "px";', ...
                '        el.style.top = (n.y || 0) + "px";', ...
                '        el.style.width = (n.width || 190) + "px";', ...
                '        el.style.height = (n.height || 76) + "px";', ...
                '', ...
                '        let icons = "";', ...
                '        if (n.hasSavedOutputs) { icons += `<span class="icon" title="Saved outputs">💾</span>`; }', ...
                '        if (n.hasError) { icons += `<span class="icon" title="Blocking invalid node">❌</span>`; }', ...
                '        else if (n.hasInvalidNonblocking) { icons += `<span class="icon" title="Non-blocking invalid node">⊘</span>`; }', ...
                '        else if (n.hasWarning) { icons += `<span class="icon" title="Validation warning">⚠</span>`; }', ...
                '', ...
                '        el.innerHTML = `', ...
                '          <div class="nodeHeader">', ...
                '            <div class="title">${esc(n.stepTag || "")}</div>', ...
                '            <div class="iconRow">${icons}</div>', ...
                '          </div>', ...
                '          <div class="subtitle">${esc(n.funcName || "")}</div>`;', ...
                '', ...
                '        el.addEventListener("click", function(ev) {', ...
                '          ev.stopPropagation();', ...
                '          hideMenu();', ...
                '', ...
                '          if (model.reconnectMode) {', ...
                '            if (n.isReconnectCandidate) {', ...
                '              handleReconnectCandidateClick(n, ev.clientX, ev.clientY);', ...
                '            } else if (n.isReconnectTarget) {', ...
                '              sendSelected(n);', ...
                '            }', ...
                '            return;', ...
                '          }', ...
                '', ...
                '          sendSelected(n);', ...
                '        });', ...
                '', ...
                '        el.addEventListener("contextmenu", function(ev) {', ...
                '          ev.preventDefault();', ...
                '          ev.stopPropagation();', ...
                '          if (model.reconnectMode) {', ...
                '            return;', ...
                '          }', ...
                '          contextNode = n;', ...
                '          sendSelected(n);', ...
                '          showMenu(ev.clientX, ev.clientY);', ...
                '        });', ...
                '', ...
                '        el.addEventListener("mouseenter", function(ev) {', ...
                '          scheduleHover(n.hoverText || "", ev.clientX, ev.clientY);', ...
                '        });', ...
                '', ...
                '        el.addEventListener("mousemove", function(ev) {', ...
                '          moveHover(ev.clientX, ev.clientY);', ...
                '        });', ...
                '', ...
                '        el.addEventListener("mouseleave", function() {', ...
                '          cancelHover();', ...
                '          hideHover();', ...
                '        });', ...
                '', ...
                '        nodeLayer.appendChild(el);', ...
                '      });', ...
                '', ...
                '      if (model.reconnectMode && model.reconnectGhost) {', ...
                '        const g = model.reconnectGhost;', ...
                '        const ghost = document.createElement("div");', ...
                '        ghost.className = "node ghostSource reconnectCandidate";', ...
                '        ghost.style.left = (g.x || 0) + "px";', ...
                '        ghost.style.top = (g.y || 0) + "px";', ...
                '        ghost.style.width = (g.width || 170) + "px";', ...
                '        ghost.style.height = (g.height || 64) + "px";', ...
                '        ghost.innerHTML = `', ...
                '          <div class="nodeHeader">', ...
                '            <div class="title">${esc(g.stepTag || "Use File...")}</div>', ...
                '            <div class="iconRow"><span class="icon" title="Choose file">📁</span></div>', ...
                '          </div>', ...
                '          <div class="subtitle">${esc(g.funcName || "File Source")}</div>`;', ...
                '', ...
                '        ghost.addEventListener("click", function(ev) {', ...
                '          ev.stopPropagation();', ...
                '          hideMenu();', ...
                '          hideChoicePopup();', ...
                '          if (htmlComponent) {', ...
                '            htmlComponent.sendEventToMATLAB("reconnectUseFile", {});', ...
                '          }', ...
                '        });', ...
                '', ...
                '        ghost.addEventListener("mouseenter", function(ev) {', ...
                '          scheduleHover(g.hoverText || "", ev.clientX, ev.clientY);', ...
                '        });', ...
                '', ...
                '        ghost.addEventListener("mousemove", function(ev) {', ...
                '          moveHover(ev.clientX, ev.clientY);', ...
                '        });', ...
                '', ...
                '        ghost.addEventListener("mouseleave", function() {', ...
                '          cancelHover();', ...
                '          hideHover();', ...
                '        });', ...
                '', ...
                '        nodeLayer.appendChild(ghost);', ...
                '      }', ...
                '', ...
                '      const canvas = document.getElementById("canvas");', ...
                '      canvas.style.minWidth = maxX + "px";', ...
                '      canvas.style.minHeight = maxY + "px";', ...
                '      svg.setAttribute("width", maxX);', ...
                '      svg.setAttribute("height", maxY);', ...
                '      svg.style.width = maxX + "px";', ...
                '      svg.style.height = maxY + "px";', ...
                '', ...
                '      model.edges.forEach(function(e) {', ...
                '        const s = nodeMap[e.sourceNodeID];', ...
                '        const t = nodeMap[e.targetNodeID];', ...
                '        if (!s || !t) { return; }', ...
                '', ...
                '        const x1 = (s.x || 0) + (s.width || 190);', ...
                '        const y1 = (s.y || 0) + ((s.height || 76) / 2);', ...
                '        const x2 = (t.x || 0);', ...
                '        const y2 = (t.y || 0) + ((t.height || 76) / 2);', ...
                '        const midX = (x1 + x2) / 2;', ...
                '', ...
                '        const path = document.createElementNS("http://www.w3.org/2000/svg", "path");', ...
                '        path.setAttribute("class", "edgePath");', ...
                '        path.setAttribute("d", `M ${x1} ${y1} C ${midX} ${y1}, ${midX} ${y2}, ${x2} ${y2}`);', ...
                '        svg.appendChild(path);', ...
                '', ...
                '        const labelPoint = cubicPoint(', ...
                '          { x: x1, y: y1 },', ...
                '          { x: midX, y: y1 },', ...
                '          { x: midX, y: y2 },', ...
                '          { x: x2, y: y2 },', ...
                '          0.62', ...
                '        );', ...
                '', ...
                '        const dy = y2 - y1;', ...
                '        const offsetY = Math.abs(dy) < 18 ? -12 : 0;', ...
                '', ...
                '        const pill = document.createElement("div");', ...
                '        pill.className = "edgeLabelPill";', ...
                '        pill.style.left = labelPoint.x + "px";', ...
                '        pill.style.top = (labelPoint.y + offsetY) + "px";', ...
                '        pill.textContent = `${e.sourceLabel || ""}`;', ...
                '        pill.title = `${e.sourceLabel || ""} → ${e.targetLabel || ""}`;', ...
                '        edgeLabels.appendChild(pill);', ...
                '      });', ...
                '    }', ...
                '', ...
                '    function cubicPoint(p0, p1, p2, p3, t) {', ...
                '      const mt = 1 - t;', ...
                '      const x = (mt * mt * mt * p0.x) + (3 * mt * mt * t * p1.x) + (3 * mt * t * t * p2.x) + (t * t * t * p3.x);', ...
                '      const y = (mt * mt * mt * p0.y) + (3 * mt * mt * t * p1.y) + (3 * mt * t * t * p2.y) + (t * t * t * p3.y);', ...
                '      return { x, y };', ...
                '    }', ...
                '', ...
                '    function handleReconnectCandidateClick(node, x, y) {', ...
                '      const refs = normalizeToArray(node.reconnectChoiceRefs);', ...
                '      const labels = normalizeToArray(node.reconnectChoiceLabels);', ...
                '', ...
                '      if (!refs.length) {', ...
                '        return;', ...
                '      }', ...
                '', ...
                '      if (refs.length === 1) {', ...
                '        sendReconnectSourceChosen(refs[0]);', ...
                '        return;', ...
                '      }', ...
                '', ...
                '      showChoicePopup(labels, refs, x, y);', ...
                '    }', ...
                '', ...
                '    function showChoicePopup(labels, refs, x, y) {', ...
                '      const popup = document.getElementById("choicePopup");', ...
                '      popup.innerHTML = "";', ...
                '      popup.style.display = "block";', ...
                '', ...
                '      const header = document.createElement("div");', ...
                '      header.className = "choiceHeader";', ...
                '      header.textContent = "Select source";', ...
                '      popup.appendChild(header);', ...
                '', ...
                '      for (let i = 0; i < refs.length; i++) {', ...
                '        const item = document.createElement("div");', ...
                '        item.className = "choiceItem";', ...
                '        item.textContent = (labels[i] !== undefined && labels[i] !== null && String(labels[i]).length > 0) ? labels[i] : refs[i];', ...
                '        item.addEventListener("click", function(ev) {', ...
                '          ev.stopPropagation();', ...
                '          hideChoicePopup();', ...
                '          sendReconnectSourceChosen(refs[i]);', ...
                '        });', ...
                '        popup.appendChild(item);', ...
                '      }', ...
                '', ...
                '      const rect = popup.getBoundingClientRect();', ...
                '      const pad = 12;', ...
                '      let left = x + pad;', ...
                '      let top = y + pad;', ...
                '      if (left + rect.width > window.innerWidth - 8) {', ...
                '        left = Math.max(8, x - rect.width - pad);', ...
                '      }', ...
                '      if (top + rect.height > window.innerHeight - 8) {', ...
                '        top = Math.max(8, y - rect.height - pad);', ...
                '      }', ...
                '      popup.style.left = left + "px";', ...
                '      popup.style.top = top + "px";', ...
                '    }', ...
                '', ...
                '    function hideChoicePopup() {', ...
                '      const popup = document.getElementById("choicePopup");', ...
                '      popup.style.display = "none";', ...
                '      popup.innerHTML = "";', ...
                '    }', ...
                '', ...
                '    function sendSelected(node) {', ...
                '      model.selectedNodeID = node.id;', ...
                '      render();', ...
                '      if (htmlComponent) {', ...
                '        htmlComponent.sendEventToMATLAB("nodeSelected", { nodeID: node.id, stepTag: node.stepTag || "", funcName: node.funcName || "" });', ...
                '      }', ...
                '    }', ...
                '', ...
                '    function sendReconnectSourceChosen(ref) {', ...
                '      if (!htmlComponent) { return; }', ...
                '      htmlComponent.sendEventToMATLAB("reconnectSourceChosen", { ref: ref });', ...
                '    }', ...
                '', ...
                '    function cancelReconnectMode() {', ...
                '      if (!htmlComponent) { return; }', ...
                '      htmlComponent.sendEventToMATLAB("cancelReconnect", {});', ...
                '    }', ...
                '', ...
                '    function showMenu(x, y) {', ...
                '      const menu = document.getElementById("menu");', ...
                '      menu.style.left = x + "px";', ...
                '      menu.style.top = y + "px";', ...
                '      menu.style.display = "block";', ...
                '    }', ...
                '', ...
                '    function hideMenu() {', ...
                '      const menu = document.getElementById("menu");', ...
                '      menu.style.display = "none";', ...
                '    }', ...
                '', ...
                '    function scheduleHover(text, x, y) {', ...
                '      cancelHover();', ...
                '      if (!text) {', ...
                '        return;', ...
                '      }', ...
                '      hoverTimer = window.setTimeout(function() {', ...
                '        showHover(text, x, y);', ...
                '        hoverTimer = null;', ...
                '      }, hoverDelayMs);', ...
                '    }', ...
                '', ...
                '    function cancelHover() {', ...
                '      if (hoverTimer !== null) {', ...
                '        window.clearTimeout(hoverTimer);', ...
                '        hoverTimer = null;', ...
                '      }', ...
                '    }', ...
                '', ...
                '    function showHover(text, x, y) {', ...
                '      if (!text) {', ...
                '        hideHover();', ...
                '        return;', ...
                '      }', ...
                '      const card = document.getElementById("hoverCard");', ...
                '      card.textContent = text;', ...
                '      card.style.display = "block";', ...
                '      moveHover(x, y);', ...
                '    }', ...
                '', ...
                '    function moveHover(x, y) {', ...
                '      const card = document.getElementById("hoverCard");', ...
                '      if (card.style.display === "none") { return; }', ...
                '      const pad = 16;', ...
                '      const vw = window.innerWidth;', ...
                '      const vh = window.innerHeight;', ...
                '      const rect = card.getBoundingClientRect();', ...
                '      let left = x + pad;', ...
                '      let top = y + pad;', ...
                '      if (left + rect.width > vw - 8) {', ...
                '        left = Math.max(8, x - rect.width - pad);', ...
                '      }', ...
                '      if (top + rect.height > vh - 8) {', ...
                '        top = Math.max(8, y - rect.height - pad);', ...
                '      }', ...
                '      card.style.left = left + "px";', ...
                '      card.style.top = top + "px";', ...
                '    }', ...
                '', ...
                '    function hideHover() {', ...
                '      cancelHover();', ...
                '      const card = document.getElementById("hoverCard");', ...
                '      card.style.display = "none";', ...
                '      card.textContent = "";', ...
                '    }', ...
                '', ...
                '    function menuAction(action) {', ...
                '      hideMenu();', ...
                '      if (!contextNode || !htmlComponent) { return; }', ...
                '      htmlComponent.sendEventToMATLAB("contextAction", { action: action, nodeID: contextNode.id, stepTag: contextNode.stepTag || "" });', ...
                '    }', ...
                '', ...
                '    function esc(value) {', ...
                '      return String((value === undefined || value === null) ? "" : value)', ...
                '        .replace(/&/g, "&amp;")', ...
                '        .replace(/</g, "&lt;")', ...
                '        .replace(/>/g, "&gt;")', ...
                '        .replace(/"/g, "&quot;")', ...
                '        .replace(/''/g, "&#39;");', ...
                '    }', ...
                '  </script>', ...
                '</body>', ...
                '</html>' ...
                };
        end

        function lineList = getExecHtmlLines(~)
            %GETEXECHTMLLINES Fallback HTML source.

            lineList = { ...
                '<!DOCTYPE html>', ...
                '<html>', ...
                '<head>', ...
                '  <meta charset="utf-8">', ...
                '  <title>Execution Order</title>', ...
                '  <style>', ...
                '    html, body {', ...
                '      margin: 0;', ...
                '      padding: 0;', ...
                '      width: 100%;', ...
                '      height: 100%;', ...
                '      overflow: auto;', ...
                '      background: #f4f5f7;', ...
                '      font-family: Arial, Helvetica, sans-serif;', ...
                '    }', ...
                '', ...
                '    #root {', ...
                '      padding: 12px;', ...
                '      box-sizing: border-box;', ...
                '      min-width: 900px;', ...
                '      min-height: 220px;', ...
                '    }', ...
                '', ...
                '    #note {', ...
                '      color: #6b7280;', ...
                '      font-size: 12px;', ...
                '      margin-bottom: 10px;', ...
                '    }', ...
                '', ...
                '    #hint {', ...
                '      color: #7a7a7a;', ...
                '      font-size: 13px;', ...
                '    }', ...
                '', ...
                '    #canvasWrap {', ...
                '      position: relative;', ...
                '      min-height: 150px;', ...
                '    }', ...
                '', ...
                '    #edges {', ...
                '      position: absolute;', ...
                '      left: 0;', ...
                '      top: 0;', ...
                '      pointer-events: none;', ...
                '      overflow: visible;', ...
                '      z-index: 0;', ...
                '    }', ...
                '', ...
                '    #stepLayer {', ...
                '      position: absolute;', ...
                '      inset: 0;', ...
                '      z-index: 1;', ...
                '    }', ...
                '', ...
                '    .step {', ...
                '      position: absolute;', ...
                '      width: 170px;', ...
                '      height: 64px;', ...
                '      box-sizing: border-box;', ...
                '      border: 1px solid #d2d6dd;', ...
                '      border-radius: 12px;', ...
                '      padding: 8px 10px;', ...
                '      background: #ffffff;', ...
                '      box-shadow: 0 1px 4px rgba(0,0,0,0.06);', ...
                '      cursor: pointer;', ...
                '      display: flex;', ...
                '      flex-direction: column;', ...
                '      justify-content: center;', ...
                '    }', ...
                '', ...
                '    .step.selected {', ...
                '      border: 2px solid #2f78ff;', ...
                '      box-shadow: 0 0 0 2px rgba(47,120,255,0.12);', ...
                '    }', ...
                '', ...
                '    .stepHeader {', ...
                '      display: flex;', ...
                '      align-items: center;', ...
                '      justify-content: space-between;', ...
                '      gap: 8px;', ...
                '    }', ...
                '', ...
                '    .stepTitle {', ...
                '      font-size: 12px;', ...
                '      font-weight: 700;', ...
                '      white-space: nowrap;', ...
                '      overflow: hidden;', ...
                '      text-overflow: ellipsis;', ...
                '    }', ...
                '', ...
                '    .stepTag {', ...
                '      color: #5f6368;', ...
                '      font-size: 11px;', ...
                '      margin-top: 2px;', ...
                '      white-space: nowrap;', ...
                '      overflow: hidden;', ...
                '      text-overflow: ellipsis;', ...
                '    }', ...
                '', ...
                '    .orderBadge {', ...
                '      padding: 2px 6px;', ...
                '      border-radius: 999px;', ...
                '      background: #eef2f7;', ...
                '      font-size: 10px;', ...
                '      flex-shrink: 0;', ...
                '    }', ...
                '', ...
                '    .edgePath {', ...
                '      stroke: #8c99a5;', ...
                '      stroke-width: 2;', ...
                '      fill: none;', ...
                '    }', ...
                '', ...
                '    .edgeLabel {', ...
                '      position: absolute;', ...
                '      transform: translate(-50%, -50%);', ...
                '      padding: 2px 6px;', ...
                '      border-radius: 999px;', ...
                '      background: rgba(255,255,255,0.96);', ...
                '      border: 1px solid #d7dce3;', ...
                '      box-shadow: 0 1px 4px rgba(0,0,0,0.10);', ...
                '      font-size: 10px;', ...
                '      color: #374151;', ...
                '      white-space: nowrap;', ...
                '      pointer-events: none;', ...
                '    }', ...
                '  </style>', ...
                '</head>', ...
                '<body>', ...
                '  <div id="root">', ...
                '    <div id="note"></div>', ...
                '    <div id="canvasWrap">', ...
                '      <svg id="edges"></svg>', ...
                '      <div id="stepLayer"></div>', ...
                '    </div>', ...
                '    <div id="hint">No execution data available.</div>', ...
                '  </div>', ...
                '', ...
                '  <script>', ...
                '    let htmlComponent = null;', ...
                '    let model = { steps: [], selectedNodeID: null, note: "" };', ...
                '', ...
                '    function setup(component) {', ...
                '      htmlComponent = component;', ...
                '      htmlComponent.addEventListener("DataChanged", function() { applyData(); });', ...
                '      applyData();', ...
                '      htmlComponent.sendEventToMATLAB("htmlReady", { component: "execution" });', ...
                '    }', ...
                '', ...
                '    function normalizeToArray(value) {', ...
                '      if (Array.isArray(value)) {', ...
                '        return value;', ...
                '      }', ...
                '      if (value === undefined || value === null) {', ...
                '        return [];', ...
                '      }', ...
                '      if (typeof value === "object") {', ...
                '        return [value];', ...
                '      }', ...
                '      return [];', ...
                '    }', ...
                '', ...
                '    function applyData() {', ...
                '      const d = (htmlComponent && htmlComponent.Data) ? htmlComponent.Data : {};', ...
                '      model.steps = normalizeToArray(d.steps);', ...
                '      model.selectedNodeID = Object.prototype.hasOwnProperty.call(d, "selectedNodeID") ? d.selectedNodeID : null;', ...
                '      model.note = d.note || "";', ...
                '      render();', ...
                '    }', ...
                '', ...
                '    function render() {', ...
                '      const note = document.getElementById("note");', ...
                '      const layer = document.getElementById("stepLayer");', ...
                '      const svg = document.getElementById("edges");', ...
                '      const hint = document.getElementById("hint");', ...
                '      const wrap = document.getElementById("canvasWrap");', ...
                '', ...
                '      note.textContent = model.note || "";', ...
                '      layer.innerHTML = "";', ...
                '      svg.innerHTML = "";', ...
                '', ...
                '      if (!model.steps.length) {', ...
                '        hint.style.display = "block";', ...
                '        wrap.style.minHeight = "120px";', ...
                '        return;', ...
                '      }', ...
                '', ...
                '      hint.style.display = "none";', ...
                '', ...
                '      const stepWidth = 170;', ...
                '      const stepHeight = 64;', ...
                '      const gapX = 50;', ...
                '      const startX = 20;', ...
                '      const startY = 20;', ...
                '', ...
                '      const totalWidth = startX * 2 + (model.steps.length * stepWidth) + ((Math.max(model.steps.length - 1, 0)) * gapX);', ...
                '      const totalHeight = startY * 2 + stepHeight;', ...
                '', ...
                '      wrap.style.minWidth = totalWidth + "px";', ...
                '      wrap.style.minHeight = totalHeight + "px";', ...
                '      svg.setAttribute("width", totalWidth);', ...
                '      svg.setAttribute("height", totalHeight);', ...
                '      svg.style.width = totalWidth + "px";', ...
                '      svg.style.height = totalHeight + "px";', ...
                '', ...
                '      model.steps.forEach(function(step, idx) {', ...
                '        const x = startX + idx * (stepWidth + gapX);', ...
                '        const y = startY;', ...
                '', ...
                '        const card = document.createElement("div");', ...
                '        card.className = "step" + ((step.id === model.selectedNodeID) ? " selected" : "");', ...
                '        card.style.left = x + "px";', ...
                '        card.style.top = y + "px";', ...
                '', ...
                '        card.innerHTML = `', ...
                '          <div class="stepHeader">', ...
                '            <div class="stepTitle">${esc(step.funcName || "")}</div>', ...
                '            <div class="orderBadge">#${step.orderIndex || ""}</div>', ...
                '          </div>', ...
                '          <div class="stepTag">${esc(step.stepTag || "")}</div>`;', ...
                '', ...
                '        card.addEventListener("click", function() {', ...
                '          if (htmlComponent) {', ...
                '            htmlComponent.sendEventToMATLAB("stepSelected", {', ...
                '              nodeID: step.id,', ...
                '              stepTag: step.stepTag || "",', ...
                '              funcName: step.funcName || ""', ...
                '            });', ...
                '          }', ...
                '        });', ...
                '', ...
                '        layer.appendChild(card);', ...
                '', ...
                '        if (idx < model.steps.length - 1) {', ...
                '          const x1 = x + stepWidth;', ...
                '          const y1 = y + stepHeight / 2;', ...
                '          const x2 = x + stepWidth + gapX;', ...
                '          const y2 = y + stepHeight / 2;', ...
                '', ...
                '          const midX = (x1 + x2) / 2;', ...
                '', ...
                '          const path = document.createElementNS("http://www.w3.org/2000/svg", "path");', ...
                '          path.setAttribute("class", "edgePath");', ...
                '          path.setAttribute("d", `M ${x1} ${y1} C ${midX} ${y1}, ${midX} ${y2}, ${x2} ${y2}`);', ...
                '          svg.appendChild(path);', ...
                '', ...
                '          const label = document.createElement("div");', ...
                '          label.className = "edgeLabel";', ...
                '          label.style.left = midX + "px";', ...
                '          label.style.top = (y1 - 14) + "px";', ...
                '          label.textContent = String(idx + 1) + "→" + String(idx + 2);', ...
                '          layer.appendChild(label);', ...
                '        }', ...
                '      });', ...
                '    }', ...
                '', ...
                '    function esc(value) {', ...
                '      return String((value === undefined || value === null) ? "" : value)', ...
                '        .replace(/&/g, "&amp;")', ...
                '        .replace(/</g, "&lt;")', ...
                '        .replace(/>/g, "&gt;")', ...
                '        .replace(/"/g, "&quot;")', ...
                '        .replace(/''/g, "&#39;");', ...
                '    }', ...
                '  </script>', ...
                '</body>', ...
                '</html>' ...
                };
        end

        function populateFunctionTree(app)
            %POPULATEFUNCTIONTREE Populate the available-function tree.

            app.AddButton.Enable = 'off';
            app.selectedFunctionName = '';

            try
                delete(app.FunctionTree.Children);
            catch
            end

            if isempty(app.pm) || ~isprop(app.pm, 'funcList') || isempty(app.pm.funcList)
                placeholderNode = uitreenode(app.FunctionTree);
                placeholderNode.Text = 'Create or attach a PipelineManager first';
                placeholderNode.NodeData = struct('kind', 'placeholder');
                return
            end

            funcs = app.pm.funcList;

            filterText = strtrim(char(string(app.SearchFunctionEditField.Value)));
            if ~isempty(filterText)
                keepMask = false(numel(funcs), 1);
                filterLower = lower(filterText);

                for iFunc = 1:numel(funcs)
                    fName = lower(char(string(funcs(iFunc).name)));
                    fFolder = '';
                    if isfield(funcs(iFunc), 'folder') && ~isempty(funcs(iFunc).folder)
                        fFolder = lower(char(string(funcs(iFunc).folder)));
                    end
                    keepMask(iFunc) = contains(fName, filterLower) || contains(fFolder, filterLower);
                end

                funcs = funcs(keepMask);
            end

            if isempty(funcs)
                placeholderNode = uitreenode(app.FunctionTree);
                placeholderNode.Text = 'No matching functions';
                placeholderNode.NodeData = struct('kind', 'placeholder');
                return
            end

            folderList = strings(numel(funcs), 1);
            for iFunc = 1:numel(funcs)
                if isfield(funcs(iFunc), 'folder') && ~isempty(funcs(iFunc).folder)
                    folderList(iFunc) = string(funcs(iFunc).folder);
                else
                    folderList(iFunc) = "Uncategorized";
                end
            end

            commonRoot = app.findCommonFolderPrefix(folderList);
            nodeMap = containers.Map('KeyType', 'char', 'ValueType', 'any');

            for iFunc = 1:numel(funcs)
                folderStr = char(folderList(iFunc));
                relFolder = app.makeRelativeFolder(folderStr, commonRoot);
                folderParts = app.splitFolderParts(relFolder);

                if isempty(folderParts)
                    folderParts = {'Uncategorized'};
                end

                parentHandle = app.FunctionTree;
                currentKey = '';

                for iPart = 1:numel(folderParts)
                    thisPart = folderParts{iPart};

                    if isempty(currentKey)
                        currentKey = thisPart;
                    else
                        currentKey = [currentKey '|' thisPart];
                    end

                    if ~isKey(nodeMap, currentKey)
                        catNode = uitreenode(parentHandle);
                        catNode.Text = thisPart;
                        catNode.NodeData = struct('kind', 'category', 'pathKey', currentKey);
                        nodeMap(currentKey) = catNode;
                    end

                    parentHandle = nodeMap(currentKey);
                end

                funcNode = uitreenode(parentHandle);
                funcNode.Text = char(string(funcs(iFunc).name));
                funcNode.NodeData = struct( ...
                    'kind', 'function', ...
                    'name', char(string(funcs(iFunc).name)), ...
                    'folder', folderStr);
            end

            rootNodes = app.FunctionTree.Children;
            for iRoot = 1:numel(rootNodes)
                try
                    expand(rootNodes(iRoot));
                catch
                end
            end
        end

        function commonRoot = findCommonFolderPrefix(~, folderList)
            %FINDCOMMONFOLDERPREFIX Return the common folder prefix across paths.

            commonRoot = '';

            if isempty(folderList)
                return
            end

            partsCell = cell(numel(folderList), 1);
            minLen = inf;

            for i = 1:numel(folderList)
                parts = regexp(char(folderList(i)), ['[\\' filesep '/]+'], 'split');
                parts = parts(~cellfun(@isempty, parts));
                partsCell{i} = parts;
                minLen = min(minLen, numel(parts));
            end

            commonParts = {};

            for iPart = 1:minLen
                token = partsCell{1}{iPart};
                isShared = true;

                for iList = 2:numel(partsCell)
                    if ~strcmpi(partsCell{iList}{iPart}, token)
                        isShared = false;
                        break
                    end
                end

                if ~isShared
                    break
                end

                commonParts{end+1} = token; %#ok<AGROW>
            end

            if isempty(commonParts)
                commonRoot = '';
            else
                commonRoot = strjoin(commonParts, filesep);
            end
        end

        function relFolder = makeRelativeFolder(~, folderStr, commonRoot)
            %MAKERELATIVEFOLDER Strip the common root from one folder string.

            relFolder = folderStr;

            if isempty(commonRoot)
                return
            end

            prefixWithSep = [commonRoot filesep];

            if startsWith(folderStr, prefixWithSep)
                relFolder = extractAfter(string(folderStr), strlength(prefixWithSep));
                relFolder = char(relFolder);
            elseif strcmpi(folderStr, commonRoot)
                relFolder = '';
            end
        end

        function parts = splitFolderParts(~, folderStr)
            %SPLITFOLDERPARTS Split a folder string into non-empty path parts.

            if isempty(folderStr)
                parts = {};
                return
            end

            parts = regexp(folderStr, ['[\\' filesep '/]+'], 'split');
            parts = parts(~cellfun(@isempty, parts));
        end

        function refreshAllViews(app)
            %REFRESHALLVIEWS Refresh graph, execution view, and selected-step details.

            app.refreshGraphView();
            app.refreshExecutionView();
            app.refreshSelectedStepPanel();
        end

        function refreshGraphView(app)
            if ~app.bGraphHtmlReady
                return
            end
            payload = app.buildGraphPayload();
            payload.command = 'refresh';
            app.GraphHTML.Data = payload;
        end

        function refreshExecutionView(app)
            if ~app.bExecHtmlReady
                return
            end
            payload = app.buildExecutionPayload();
            app.ExecGraphHTML.Data = payload;
        end

        function payload = buildGraphPayload(app)
            %BUILDGRAPHPAYLOAD Build the payload rendered by the graph HTML view.

            payload = struct();
            payload.nodes = struct('id', {}, 'stepTag', {}, 'funcName', {}, 'kind', {}, ...
                'x', {}, 'y', {}, 'width', {}, 'height', {}, 'topoIndex', {}, ...
                'execIndex', {}, 'hasSavedOutputs', {}, 'hasWarning', {}, 'hasError', {}, ...
                'hasInvalidNonblocking', {}, 'hoverText', {}, 'isReconnectTarget', {}, ...
                'isReconnectCandidate', {}, 'isReconnectMuted', {}, ...
                'reconnectChoiceLabels', {}, 'reconnectChoiceRefs', {});
            payload.edges = struct('sourceNodeID', {}, 'targetNodeID', {}, ...
                'sourceLabel', {}, 'targetLabel', {});
            payload.selectedNodeID = app.selectedNodeID;
            payload.reconnectMode = app.bReconnectMode;
            payload.reconnectBanner = '';
            payload.reconnectGhost = struct([]);

            targetInputDef = [];
            targetNodeLocal = [];

            if app.bReconnectMode && ~isempty(app.reconnectTargetStepTag) && ~isempty(app.reconnectTargetInputName)
                payload.reconnectBanner = sprintf('Reconnect input "%s" of "%s": click a highlighted source or Use File....', ...
                    app.reconnectTargetInputName, app.reconnectTargetStepTag);
            end

            if isempty(app.pm) || isempty(app.pm.nodes)
                return
            end

            nodesLocal = app.pm.nodes;
            connectionsLocal = app.pm.connections;
            topoOrder = app.computeTopoOrderFromGraph(nodesLocal, connectionsLocal);
            levelMap = app.computeNodeLevels(nodesLocal, connectionsLocal, topoOrder);

            if app.bReconnectMode
                tgtIdx = find([nodesLocal.id] == app.reconnectTargetNodeID, 1, 'first');
                if ~isempty(tgtIdx)
                    targetNodeLocal = nodesLocal(tgtIdx);
                    if isfield(targetNodeLocal, 'info') && isfield(targetNodeLocal.info, 'inputs') && ~isempty(targetNodeLocal.info.inputs)
                        dataInputsLocal = targetNodeLocal.info.inputs(arrayfun(@(x) isfield(x, 'isData') && x.isData, targetNodeLocal.info.inputs));
                        for iIn = 1:numel(dataInputsLocal)
                            if strcmpi(char(string(dataInputsLocal(iIn).name)), app.reconnectTargetInputName)
                                targetInputDef = dataInputsLocal(iIn);
                                break
                            end
                        end
                    end
                end
            end

            % -------------------------------------------------------------
            % Pull structured node status once from diagnosePipeline
            % -------------------------------------------------------------
            nodeStatusTable = table();
            if ~isempty(app.pm)
                try
                    diagReport = app.pm.diagnosePipeline();
                    if isstruct(diagReport) && isfield(diagReport, 'nodeStatus') && istable(diagReport.nodeStatus)
                        nodeStatusTable = diagReport.nodeStatus;
                    end
                catch
                    nodeStatusTable = table();
                end
            end

            topoPosMap = containers.Map('KeyType', 'double', 'ValueType', 'double');
            execPosMap = containers.Map('KeyType', 'double', 'ValueType', 'double');
            rowCounterMap = containers.Map('KeyType', 'char', 'ValueType', 'double');

            for iTopo = 1:numel(topoOrder)
                topoPosMap(topoOrder(iTopo)) = iTopo;
                execPosMap(topoOrder(iTopo)) = iTopo;
            end

            for iTopo = 1:numel(topoOrder)
                nodeID = topoOrder(iTopo);
                nodeIdx = find([nodesLocal.id] == nodeID, 1, 'first');
                nodeLocal = nodesLocal(nodeIdx);

                levelVal = levelMap(nodeID);
                levelKey = sprintf('L%d', levelVal);

                if ~isKey(rowCounterMap, levelKey)
                    rowCounterMap(levelKey) = 0;
                end

                rowVal = rowCounterMap(levelKey) + 1;
                rowCounterMap(levelKey) = rowVal;

                x = 40 + (levelVal - 1) * 240;
                y = 40 + (rowVal - 1) * 120;
                widthVal = 190;
                heightVal = 76;

                funcName = app.getNodeFunctionName(nodeLocal);

                hasSavedOutputs = false;
                if isfield(nodeLocal, 'info') && isfield(nodeLocal.info, 'outputs') && ~isempty(nodeLocal.info.outputs)
                    for iOut = 1:numel(nodeLocal.info.outputs)
                        outDef = nodeLocal.info.outputs(iOut);

                        outMode = 'data';
                        if isfield(outDef, 'outputMode') && ~isempty(outDef.outputMode)
                            outMode = char(string(outDef.outputMode));
                        end

                        isSaveable = isfield(outDef, 'isData') && outDef.isData && ~strcmpi(outMode, 'file');
                        if isSaveable && isfield(outDef, 'saveFileName') && ~isempty(outDef.saveFileName)
                            hasSavedOutputs = true;
                            break
                        end
                    end
                end

                statusInfo = app.getNodeStatusInfo(nodeLocal, nodeStatusTable);
                hoverText = app.buildNodeHoverText(nodeLocal, topoPosMap(nodeLocal.id), execPosMap(nodeLocal.id), statusInfo);

                isReconnectTarget = app.bReconnectMode && nodeLocal.id == app.reconnectTargetNodeID;
                isReconnectCandidate = app.bReconnectMode && any(app.reconnectCandidateNodeIDs == nodeLocal.id);
                isReconnectMuted = app.bReconnectMode && ~(isReconnectTarget || isReconnectCandidate);

                reconnectChoiceLabels = {};
                reconnectChoiceRefs = {};

                if isReconnectCandidate && ~isempty(targetInputDef) && ~isempty(targetNodeLocal)
                    dstTypes = {};
                    if isfield(targetInputDef, 'type') && ~isempty(targetInputDef.type)
                        if ischar(targetInputDef.type) || (isstring(targetInputDef.type) && isscalar(targetInputDef.type))
                            dstTypes = {char(string(targetInputDef.type))};
                        else
                            dstTypes = cellstr(string(targetInputDef.type(:)));
                        end
                    end

                    [choiceLabels, choiceRefs] = app.buildExplicitSourceChoices(dstTypes, ...
                        'leafOnly', false, ...
                        'excludeNodeID', targetNodeLocal.id, ...
                        'candidateNodeIDs', nodeLocal.id);

                    reconnectChoiceLabels = choiceLabels;
                    reconnectChoiceRefs = choiceRefs;
                end

                payload.nodes(end+1) = struct( ...
                    'id', nodeLocal.id, ...
                    'stepTag', char(string(nodeLocal.name)), ...
                    'funcName', funcName, ...
                    'kind', char(string(nodeLocal.kind)), ...
                    'x', x, ...
                    'y', y, ...
                    'width', widthVal, ...
                    'height', heightVal, ...
                    'topoIndex', topoPosMap(nodeLocal.id), ...
                    'execIndex', execPosMap(nodeLocal.id), ...
                    'hasSavedOutputs', hasSavedOutputs, ...
                    'hasWarning', statusInfo.hasWarning, ...
                    'hasError', statusInfo.hasError, ...
                    'hasInvalidNonblocking', statusInfo.hasInvalidNonblocking, ...
                    'hoverText', hoverText, ...
                    'isReconnectTarget', isReconnectTarget, ...
                    'isReconnectCandidate', isReconnectCandidate, ...
                    'isReconnectMuted', isReconnectMuted, ...
                    'reconnectChoiceLabels', {reconnectChoiceLabels}, ...
                    'reconnectChoiceRefs', {reconnectChoiceRefs});
            end

            if app.bReconnectMode
                ghostY = 40;
                if ~isempty(targetNodeLocal)
                    ghostY = max(40, round(double(targetNodeLocal.id) * 0 + 40));
                    targetTopoIdx = find(topoOrder == targetNodeLocal.id, 1, 'first');
                    if ~isempty(targetTopoIdx)
                        targetLevelVal = levelMap(targetNodeLocal.id);
                        levelKey = sprintf('L%d', targetLevelVal);
                        if isKey(rowCounterMap, levelKey)
                            rowCounterTmp = 0;
                            for iTopo = 1:targetTopoIdx
                                curID = topoOrder(iTopo);
                                if levelMap(curID) == targetLevelVal
                                    rowCounterTmp = rowCounterTmp + 1;
                                end
                            end
                            ghostY = 40 + (rowCounterTmp - 1) * 120;
                        end
                    end
                end

                payload.reconnectGhost = struct( ...
                    'x', 20, ...
                    'y', ghostY, ...
                    'width', 170, ...
                    'height', 64, ...
                    'stepTag', 'Use File...', ...
                    'funcName', 'File Source', ...
                    'hoverText', sprintf('Select a file from SaveFolder for input "%s".', app.reconnectTargetInputName));
            end

            for iConn = 1:numel(connectionsLocal)
                connLocal = connectionsLocal(iConn);

                srcLabel = char(string(connLocal.sourceOutputName));
                if isfield(connLocal, 'selectedFile') && ~isempty(connLocal.selectedFile)
                    srcLabel = char(string(connLocal.selectedFile));
                end

                payload.edges(end+1) = struct( ...
                    'sourceNodeID', connLocal.sourceNodeID, ...
                    'targetNodeID', connLocal.targetNodeID, ...
                    'sourceLabel', srcLabel, ...
                    'targetLabel', char(string(connLocal.targetInputName)));
            end
        end

        function info = getNodeStatusInfo(~, nodeLocal, nodeStatusTable)
            %GETNODESTATUSINFO Return structured node status from diagnosePipeline.

            info = struct( ...
                'status', "valid", ...
                'severity', "none", ...
                'messages', strings(0,1), ...
                'hasWarning', false, ...
                'hasInvalidNonblocking', false, ...
                'hasError', false);

            if ~istable(nodeStatusTable) || isempty(nodeStatusTable)
                return
            end

            if ~ismember('nodeID', nodeStatusTable.Properties.VariableNames) || ...
                    ~ismember('status', nodeStatusTable.Properties.VariableNames)
                return
            end

            idx = find(nodeStatusTable.nodeID == nodeLocal.id, 1, 'first');
            if isempty(idx)
                return
            end

            info.status = string(nodeStatusTable.status(idx));

            if ismember('severity', nodeStatusTable.Properties.VariableNames)
                info.severity = string(nodeStatusTable.severity(idx));
            end

            if ismember('messages', nodeStatusTable.Properties.VariableNames) && iscell(nodeStatusTable.messages)
                msgLocal = string(nodeStatusTable.messages{idx});
                info.messages = msgLocal(:);
            end

            info.hasWarning = info.status == "warning";
            info.hasInvalidNonblocking = info.status == "invalid_nonblocking";
            info.hasError = info.status == "invalid_blocking";
        end

        function hoverText = buildNodeHoverText(app, nodeLocal, topoPos, execPos, statusInfo)
            %BUILDNODEHOVERTEXT Build one compact hover summary string for a node.

            lines = strings(0,1);

            stepTag = char(string(nodeLocal.name));
            funcName = app.getNodeFunctionName(nodeLocal);

            lines(end+1,1) = "Step: " + string(stepTag);
            lines(end+1,1) = "Function: " + string(funcName);
            lines(end+1,1) = "Topological order: " + string(app.valueOrPlaceholder(topoPos));
            lines(end+1,1) = "Execution order: " + string(app.valueOrPlaceholder(execPos));

            if isstruct(statusInfo)
                lines(end+1,1) = "Status: " + string(statusInfo.status);
                lines(end+1,1) = "Severity: " + string(statusInfo.severity);
            end

            inputLines = string(app.getDataInputLines(nodeLocal));
            outputLines = string(app.getOutputLines(nodeLocal));
            saveLines = string(app.getSavedOutputLines(nodeLocal));

            if ~isempty(inputLines)
                lines(end+1,1) = "";
                lines(end+1,1) = "Inputs:";
                lines = [lines; inputLines(:)];
            end

            if ~isempty(outputLines)
                lines(end+1,1) = "";
                lines(end+1,1) = "Outputs:";
                lines = [lines; outputLines(:)];
            end

            if ~isempty(saveLines)
                lines(end+1,1) = "";
                lines(end+1,1) = "Saved outputs:";
                lines = [lines; saveLines(:)];
            end

            if isstruct(statusInfo) && isfield(statusInfo, 'messages') && ~isempty(statusInfo.messages)
                lines(end+1,1) = "";
                lines(end+1,1) = "Issues:";
                lines = [lines; "- " + string(statusInfo.messages(:))];
            end

            hoverText = char(join(lines, newline));
        end

        function payload = buildExecutionPayload(app)
            %BUILDEXECUTIONPAYLOAD Build the payload rendered by the execution HTML view.

            payload = struct();
            payload.steps = struct('id', {}, 'stepTag', {}, 'funcName', {}, 'orderIndex', {});
            payload.selectedNodeID = app.selectedNodeID;
            payload.note = 'Execution preview currently follows topological order.';

            if isempty(app.pm) || isempty(app.pm.nodes)
                return
            end

            nodesLocal = app.pm.nodes;
            topoOrder = app.computeTopoOrderFromGraph(nodesLocal, app.pm.connections);

            for iStep = 1:numel(topoOrder)
                nodeIdx = find([nodesLocal.id] == topoOrder(iStep), 1, 'first');
                nodeLocal = nodesLocal(nodeIdx);

                payload.steps(end+1) = struct( ... %#ok<AGROW>
                    'id', nodeLocal.id, ...
                    'stepTag', char(string(nodeLocal.name)), ...
                    'funcName', app.getNodeFunctionName(nodeLocal), ...
                    'orderIndex', iStep);
            end
        end

        function topoOrder = computeTopoOrderFromGraph(~, nodesLocal, connectionsLocal)
            %COMPUTETOPOORDERFROMGRAPH Compute a topological order from nodes/connections.

            if isempty(nodesLocal)
                topoOrder = [];
                return
            end

            nodeIDs = [nodesLocal.id];

            if isempty(connectionsLocal)
                topoOrder = nodeIDs;
                return
            end

            inDegree = zeros(1, numel(nodeIDs));

            for iConn = 1:numel(connectionsLocal)
                tgtIdx = find(nodeIDs == connectionsLocal(iConn).targetNodeID, 1, 'first');
                if ~isempty(tgtIdx)
                    inDegree(tgtIdx) = inDegree(tgtIdx) + 1;
                end
            end

            queue = nodeIDs(inDegree == 0);
            topoOrder = [];

            while ~isempty(queue)
                currentID = queue(1);
                queue(1) = [];
                topoOrder(end+1) = currentID; %#ok<AGROW>

                outMask = [connectionsLocal.sourceNodeID] == currentID;
                outgoing = connectionsLocal(outMask);

                for iOut = 1:numel(outgoing)
                    tgtIdx = find(nodeIDs == outgoing(iOut).targetNodeID, 1, 'first');
                    if isempty(tgtIdx)
                        continue
                    end

                    inDegree(tgtIdx) = inDegree(tgtIdx) - 1;

                    if inDegree(tgtIdx) == 0
                        queue(end+1) = nodeIDs(tgtIdx); %#ok<AGROW>
                    end
                end
            end

            if numel(topoOrder) ~= numel(nodeIDs)
                topoOrder = nodeIDs;
            end
        end

        function levelMap = computeNodeLevels(~, nodesLocal, connectionsLocal, topoOrder)
            %COMPUTENODELEVELS Compute a simple layered layout index for each node.

            levelMap = containers.Map('KeyType', 'double', 'ValueType', 'double');

            if isempty(nodesLocal)
                return
            end

            % No connections -> every node is a root-level node
            if isempty(connectionsLocal)
                for iTopo = 1:numel(topoOrder)
                    levelMap(topoOrder(iTopo)) = 1;
                end
                return
            end

            for iTopo = 1:numel(topoOrder)
                nodeID = topoOrder(iTopo);

                inMask = [connectionsLocal.targetNodeID] == nodeID;
                incoming = connectionsLocal(inMask);

                if isempty(incoming)
                    levelMap(nodeID) = 1;
                    continue
                end

                parentLevels = zeros(1, numel(incoming));
                for iIn = 1:numel(incoming)
                    srcID = incoming(iIn).sourceNodeID;
                    if isKey(levelMap, srcID)
                        parentLevels(iIn) = levelMap(srcID);
                    else
                        parentLevels(iIn) = 1;
                    end
                end

                levelMap(nodeID) = max(parentLevels) + 1;
            end
        end

        function leafIDs = computeLeafNodeIDs(~, nodesLocal, connectionsLocal)
            %COMPUTELEAFNODEIDS Return node IDs with no outgoing edges.

            if isempty(nodesLocal)
                leafIDs = [];
                return
            end

            nodeIDs = [nodesLocal.id];
            if isempty(connectionsLocal)
                leafIDs = nodeIDs;
                return
            end

            srcIDs = [connectionsLocal.sourceNodeID];
            leafIDs = nodeIDs(~ismember(nodeIDs, srcIDs));
        end

        function refreshSelectedStepPanel(app)
            %REFRESHSELECTEDSTEPPANEL Refresh the selected-step details panel.

            if isempty(app.pm) || isempty(app.pm.nodes) || isnan(app.selectedNodeID)
                app.clearSelectedStepPanel();
                return
            end

            nodeIdx = find([app.pm.nodes.id] == app.selectedNodeID, 1, 'first');
            if isempty(nodeIdx)
                app.clearSelectedStepPanel();
                return
            end

            nodeLocal = app.pm.nodes(nodeIdx);

            app.StepTagField.Value = char(string(nodeLocal.name));
            app.FunctionNameField.Value = app.getNodeFunctionName(nodeLocal);
            app.StepSummaryTextArea.Value = app.buildStepSummaryText(nodeLocal);
            app.ParametersTextArea.Value = app.buildParameterText(nodeLocal);
        end

        function clearSelectedStepPanel(app)
            %CLEARSELECTEDSTEPPANEL Clear the right-hand details panel.

            app.StepTagField.Value = '';
            app.FunctionNameField.Value = '';
            app.StepSummaryTextArea.Value = {'<no step selected>'};
            app.ParametersTextArea.Value = {'<no step selected>'};
        end

        function outLines = buildStepSummaryText(app, nodeLocal)
            %BUILDSTEPSUMMARYTEXT Build the Step Summary text for one selected node.

            outLines = strings(0, 1);

            if isempty(app.pm)
                outLines = "<no step selected>";
                outLines = cellstr(outLines);
                return
            end

            topoOrder = app.computeTopoOrderFromGraph(app.pm.nodes, app.pm.connections);
            topoPos = find(topoOrder == nodeLocal.id, 1, 'first');
            execPos = topoPos; % Current GUI fallback

            outLines(end+1, 1) = "Topological order: " + string(app.valueOrPlaceholder(topoPos)); %#ok<AGROW>
            outLines(end+1, 1) = "Execution order: " + string(app.valueOrPlaceholder(execPos)) + " (GUI fallback)"; %#ok<AGROW>
            outLines(end+1, 1) = ""; %#ok<AGROW>

            outLines(end+1, 1) = "Inputs:"; %#ok<AGROW>
            dataInputLines = app.getDataInputLines(nodeLocal);
            outLines = [outLines; string(dataInputLines(:))]; %#ok<AGROW>
            outLines(end+1, 1) = ""; %#ok<AGROW>

            outLines(end+1, 1) = "Outputs:"; %#ok<AGROW>
            outputLines = app.getOutputLines(nodeLocal);
            outLines = [outLines; string(outputLines(:))]; %#ok<AGROW>

            saveLines = app.getSavedOutputLines(nodeLocal);
            if ~isempty(saveLines)
                outLines(end+1, 1) = ""; %#ok<AGROW>
                outLines(end+1, 1) = "Saved files:"; %#ok<AGROW>
                outLines = [outLines; string(saveLines(:))]; %#ok<AGROW>
            end

            outLines = cellstr(outLines);
        end

        function outLines = getDataInputLines(app, nodeLocal)
            %GETDATAINPUTLINES Build the input lines shown in the selected-step summary.

            outLines = strings(0, 1);

            if strcmpi(nodeLocal.kind, 'folder')
                outLines(end+1, 1) = "<file source node>"; %#ok<AGROW>
                outLines = cellstr(outLines);
                return
            end

            if ~isfield(nodeLocal, 'info') || ~isfield(nodeLocal.info, 'inputs') || isempty(nodeLocal.info.inputs)
                outLines(end+1, 1) = "<no inputs>"; %#ok<AGROW>
                outLines = cellstr(outLines);
                return
            end

            for iIn = 1:numel(nodeLocal.info.inputs)
                inDef = nodeLocal.info.inputs(iIn);

                if ~isfield(inDef, 'isData') || ~inDef.isData
                    continue
                end

                connIdx = find([app.pm.connections.targetNodeID] == nodeLocal.id & ...
                    strcmpi({app.pm.connections.targetInputName}, char(string(inDef.name))), 1, 'first');

                if isempty(connIdx)
                    outLines(end+1, 1) = "- " + string(inDef.name) + " <= <missing>"; %#ok<AGROW>
                    continue
                end

                connLocal = app.pm.connections(connIdx);
                srcIdx = find([app.pm.nodes.id] == connLocal.sourceNodeID, 1, 'first');
                srcNode = app.pm.nodes(srcIdx);

                srcRef = char(string(connLocal.sourceOutputName));
                if isfield(connLocal, 'selectedFile') && ~isempty(connLocal.selectedFile)
                    srcRef = char(string(connLocal.selectedFile));
                end

                outLines(end+1, 1) = "- " + string(inDef.name) + " <= " + string(srcNode.name) + ":" + string(srcRef); %#ok<AGROW>
            end

            if isempty(outLines)
                outLines(end+1, 1) = "<no data inputs>"; %#ok<AGROW>
            end

            outLines = cellstr(outLines);
        end

        function outLines = getOutputLines(app, nodeLocal)
            %GETOUTPUTLINES Build the output lines shown in the selected-step summary.

            outLines = strings(0, 1);

            if ~isfield(nodeLocal, 'info') || ~isfield(nodeLocal.info, 'outputs') || isempty(nodeLocal.info.outputs)
                outLines(end+1, 1) = "<no outputs>"; %#ok<AGROW>
                outLines = cellstr(outLines);
                return
            end

            for iOut = 1:numel(nodeLocal.info.outputs)
                outDef = nodeLocal.info.outputs(iOut);
                outName = char(string(outDef.name));
                outType = app.typeListToText(outDef.type);

                outMode = 'data';
                if isfield(outDef, 'outputMode') && ~isempty(outDef.outputMode)
                    outMode = char(string(outDef.outputMode));
                end

                outLines(end+1, 1) = "- " + string(outName) + " (" + string(outType) + ", " + string(outMode) + ")"; %#ok<AGROW>
            end

            outLines = cellstr(outLines);
        end

        function outLines = getSavedOutputLines(~, nodeLocal)
            %GETSAVEDOUTPUTLINES Return manager-save-target lines for one node.
            %
            % Only SAVEABLE outputs are listed:
            %   isData == true AND outputMode ~= 'file'

            outLines = strings(0, 1);

            if ~isfield(nodeLocal, 'info') || ~isfield(nodeLocal.info, 'outputs') || isempty(nodeLocal.info.outputs)
                outLines = cellstr(outLines);
                return
            end

            for iOut = 1:numel(nodeLocal.info.outputs)
                outDef = nodeLocal.info.outputs(iOut);

                outMode = 'data';
                if isfield(outDef, 'outputMode') && ~isempty(outDef.outputMode)
                    outMode = char(string(outDef.outputMode));
                end

                isSaveable = isfield(outDef, 'isData') && outDef.isData && ~strcmpi(outMode, 'file');

                if isSaveable && isfield(outDef, 'saveFileName') && ~isempty(outDef.saveFileName)
                    outLines(end+1, 1) = "- " + string(outDef.name) + " -> " + string(outDef.saveFileName); %#ok<AGROW>
                end
            end

            outLines = cellstr(outLines);
        end

        function outLines = buildParameterText(app, nodeLocal)
            %BUILDPARAMETERTEXT Build the Parameters text for one selected node.

            outLines = strings(0, 1);

            if ~isfield(nodeLocal, 'info') || ~isfield(nodeLocal.info, 'parameters') || isempty(nodeLocal.info.parameters)
                outLines(end+1, 1) = "<no parameters>"; %#ok<AGROW>
                outLines = cellstr(outLines);
                return
            end

            for iParam = 1:numel(nodeLocal.info.parameters)
                p = nodeLocal.info.parameters(iParam);

                if ~isfield(p, 'name') || isempty(p.name)
                    continue
                end

                pValue = [];
                if isfield(p, 'value')
                    pValue = p.value;
                elseif isfield(p, 'default')
                    pValue = p.default;
                end

                outLines(end+1, 1) = string(p.name) + ": " + string(app.formatValueForDisplay(pValue)); %#ok<AGROW>
            end

            if isempty(outLines)
                outLines(end+1, 1) = "<no parameters>"; %#ok<AGROW>
            end

            outLines = cellstr(outLines);
        end

        function txt = formatValueForDisplay(~, valueIn)
            %FORMATVALUEFORDISPLAY Convert a MATLAB value to short readable text.

            if isempty(valueIn)
                txt = '[]';
                return
            end

            if ischar(valueIn) || (isstring(valueIn) && isscalar(valueIn))
                txt = char(string(valueIn));
                return
            end

            if isnumeric(valueIn) || islogical(valueIn)
                if isscalar(valueIn) || (isvector(valueIn) && numel(valueIn) <= 6)
                    txt = mat2str(valueIn);
                else
                    txt = sprintf('[%s %s]', strjoin(string(size(valueIn)), 'x'), class(valueIn));
                end
                return
            end

            if iscell(valueIn)
                txt = sprintf('[%s cell]', strjoin(string(size(valueIn)), 'x'));
                return
            end

            if isstruct(valueIn)
                txt = sprintf('[%s struct]', strjoin(string(size(valueIn)), 'x'));
                return
            end

            if isa(valueIn, 'function_handle')
                txt = ['@' func2str(valueIn)];
                return
            end

            txt = class(valueIn);
        end

        function txt = getNodeFunctionName(~, nodeLocal)
            %GETNODEFUNCTIONNAME Return the callable function name for one node.

            if isfield(nodeLocal, 'kind') && strcmpi(nodeLocal.kind, 'folder')
                txt = 'File Source';
                return
            end

            txt = char(string(nodeLocal.name));

            if isfield(nodeLocal, 'info') && isfield(nodeLocal.info, 'name') && ~isempty(nodeLocal.info.name)
                txt = char(string(nodeLocal.info.name));
            end
        end

        function txt = typeListToText(~, typeIn)
            %TYPELISTTOTEXT Convert type metadata to one display string.

            if isempty(typeIn)
                txt = 'Unknown';
                return
            end

            if ischar(typeIn) || (isstring(typeIn) && isscalar(typeIn))
                txt = char(string(typeIn));
                return
            end

            txt = strjoin(string(typeIn), ' / ');
            txt = char(txt);
        end

        function outVal = valueOrPlaceholder(~, val)
            %VALUEORPLACEHOLDER Return a string-friendly value or placeholder.

            if isempty(val)
                outVal = '<n/a>';
            else
                outVal = val;
            end
        end

        function appendDiagnostic(app, msg)
            %APPENDDIAGNOSTIC Append one timestamped line to the diagnostics box.

            stamp = char(datetime('now', 'Format', 'HH:mm:ss'));
            newLine = sprintf('[%s] %s', stamp, msg);

            currentVal = string(app.DiagnosticsTextArea.Value);
            if isempty(currentVal) || (numel(currentVal) == 1 && strlength(currentVal(1)) == 0)
                app.DiagnosticsTextArea.Value = {newLine};
            else
                app.DiagnosticsTextArea.Value = cellstr([currentVal; string(newLine)]);
            end
        end

        function setStatus(app, msg)
            %SETSTATUS Update the bottom status label.

            app.StatusLabel.Text = ['Status: ' char(string(msg))];
        end

        function validateAndReportPipeline(app)
            %VALIDATEANDREPORTPIPELINE Run pipeline validation and log the result.

            if isempty(app.pm)
                app.appendDiagnostic('No PipelineManager is attached.');
                app.setStatus('No PipelineManager attached.');
                return
            end

            try
                report = app.pm.diagnosePipeline();

                if isempty(report.errors)
                    app.appendDiagnostic('Pipeline validation passed.');
                    app.setStatus('Pipeline is valid.');
                else
                    app.appendDiagnostic(sprintf('Pipeline validation failed with %d error(s).', numel(report.errors)));
                    for iErr = 1:numel(report.errors)
                        app.appendDiagnostic(report.errors{iErr});
                    end
                    for iWarn = 1:numel(report.warnings)
                        app.appendDiagnostic(['Warning: ' report.warnings{iWarn}]);
                    end
                    app.setStatus('Pipeline is invalid.');
                end

            catch ME
                app.appendDiagnostic(sprintf('Validation failed: %s', ME.message));
                app.setStatus('Validation failed.');
            end
        end

        function funcName = getSelectedFunctionName(app)
            %GETSELECTEDFUNCTIONNAME Return the currently selected function-tree leaf name.

            funcName = '';

            selectedNodes = app.FunctionTree.SelectedNodes;
            if isempty(selectedNodes)
                return
            end

            selectedNode = selectedNodes(1);
            nodeData = selectedNode.NodeData;

            if isempty(nodeData) || ~isstruct(nodeData) || ~isfield(nodeData, 'kind')
                return
            end

            if strcmpi(nodeData.kind, 'function') && isfield(nodeData, 'name')
                funcName = char(string(nodeData.name));
            end
        end

        function nodeLocal = getSelectedGraphNode(app)
            %GETSELECTEDGRAPHNODE Return the currently selected graph node struct or [].

            nodeLocal = [];

            if isempty(app.pm) || isempty(app.pm.nodes) || isnan(app.selectedNodeID)
                return
            end

            idx = find([app.pm.nodes.id] == app.selectedNodeID, 1, 'first');
            if isempty(idx)
                return
            end

            nodeLocal = app.pm.nodes(idx);
        end

        function tryAddSelectedFunction(app, isNewBranch)
            %TRYADDSELECTEDFUNCTION Add the selected function using GUI-resolved inputs.
            %
            %   tryAddSelectedFunction(app, false) -> standard add
            %   tryAddSelectedFunction(app, true)  -> add as new branch
            %
            % Standard add:
            %   - selected-node-first continuation
            %   - fallback to compatible leaf sources
            %
            % New-branch add:
            %   - always starts from a freshly selected file input
            %   - does not auto-use existing FileSource nodes
            %
            % In both modes, all DATA-input steps are added using explicit
            % 'input' references to avoid MATLAB CLI prompting.

            if nargin < 2 || isempty(isNewBranch)
                isNewBranch = false;
            end

            if isempty(app.pm)
                app.appendDiagnostic('No PipelineManager is attached.');
                app.setStatus('No PipelineManager attached.');
                return
            end

            funcName = app.getSelectedFunctionName();
            if isempty(funcName)
                app.appendDiagnostic('No function is selected.');
                app.setStatus('No function selected.');
                return
            end

            try
                info = app.pm.getPipelineInfo(funcName);

                dataInputs = struct([]);
                if isfield(info, 'inputs') && ~isempty(info.inputs)
                    dataMask = arrayfun(@(x) isfield(x, 'isData') && x.isData, info.inputs);
                    dataInputs = info.inputs(dataMask);
                end

                nDataInputs = numel(dataInputs);

                % -------------------------------------------------------------
                % No DATA inputs -> safe direct add
                % -------------------------------------------------------------
                if nDataInputs == 0
                    stepTag = app.pm.addStep(funcName);

                else
                    inputRefs = app.promptExplicitInputsForAdd(funcName, dataInputs, isNewBranch);

                    if isempty(inputRefs)
                        if isNewBranch
                            app.appendDiagnostic(sprintf('Add-as-new-branch operation for "%s" was cancelled.', funcName));
                            app.setStatus('Add-as-new-branch cancelled.');
                        else
                            app.appendDiagnostic(sprintf('Add-step operation for "%s" was cancelled.', funcName));
                            app.setStatus('Add-step cancelled.');
                        end
                        return
                    end

                    if nDataInputs == 1
                        stepTag = app.pm.addStep(funcName, 'input', inputRefs{1});
                    else
                        stepTag = app.pm.addStep(funcName, 'input', inputRefs);
                    end
                end

                if isempty(stepTag)
                    if isNewBranch
                        app.appendDiagnostic(sprintf('Add-as-new-branch operation for "%s" was cancelled.', funcName));
                        app.setStatus('Add-as-new-branch cancelled.');
                    else
                        app.appendDiagnostic(sprintf('Add-step operation for "%s" was cancelled.', funcName));
                        app.setStatus('Add-step cancelled.');
                    end
                    return
                end

                newIdx = find(strcmpi(stepTag, {app.pm.nodes.name}), 1, 'first');
                if ~isempty(newIdx)
                    app.selectedNodeID = app.pm.nodes(newIdx).id;
                    app.selectedStepTag = char(string(app.pm.nodes(newIdx).name));
                else
                    app.selectedNodeID = NaN;
                    app.selectedStepTag = '';
                end

                app.refreshAllViews();

                if isNewBranch
                    app.appendDiagnostic(sprintf('Added function "%s" as new-branch step "%s".', funcName, stepTag));
                    app.setStatus('New branch step added.');
                else
                    app.appendDiagnostic(sprintf('Added function "%s" as step "%s".', funcName, stepTag));
                    app.setStatus('Step added.');
                end

            catch ME
                if isNewBranch
                    app.appendDiagnostic(sprintf('Failed to add "%s" as new branch: %s', funcName, ME.message));
                    app.setStatus('Failed to add new branch step.');
                else
                    app.appendDiagnostic(sprintf('Failed to add function "%s": %s', funcName, ME.message));
                    app.setStatus('Failed to add step.');
                end
            end
        end

        function inputRefs = promptExplicitInputsForAdd(app, funcName, dataInputs, isNewBranch)
            %PROMPTEXPLICITINPUTSFORADD Resolve explicit input references for addStep.
            %
            %   Standard mode (isNewBranch = false):
            %       1) selected node only
            %       2) compatible leaf nodes
            %       3) browse file
            %
            %   New-branch mode (isNewBranch = true):
            %       - always prompt for a new file selection for each DATA input
            %       - do not auto-use existing FileSource nodes
            %
            % Output:
            %   inputRefs - 1xN cell array of explicit input references suitable for
            %               PipelineManager.addStep(...,'input',inputRefs)
            %
            % Notes:
            %   - Each returned item is one of:
            %         'StepTag:OutputName'
            %         'StepTag:filename.ext'
            %         'filename.ext'
            %   - Returning [] means user cancelled.

            if nargin < 4 || isempty(isNewBranch)
                isNewBranch = false;
            end

            inputRefs = [];

            if nargin < 3 || isempty(dataInputs)
                inputRefs = {};
                return
            end

            inputRefs = cell(1, numel(dataInputs));

            for iIn = 1:numel(dataInputs)

                inputDef = dataInputs(iIn);
                inputName = char(string(inputDef.name));
                chosenRef = '';

                % -------------------------------------------------------------
                % MODE A: Add as new branch
                % -------------------------------------------------------------
                if isNewBranch
                    chosenRef = app.promptFileInputReference(inputName);

                    if isempty(chosenRef)
                        inputRefs = [];
                        return
                    end

                    app.appendDiagnostic(sprintf( ...
                        'Selected new file source "%s" for input "%s" of "%s".', ...
                        chosenRef, inputName, funcName));

                    inputRefs{iIn} = chosenRef;
                    continue
                end

                % -------------------------------------------------------------
                % MODE B: Standard add (selected-node-first)
                % -------------------------------------------------------------

                % First pass: selected node only
                [selectedDisplay, selectedRefs, selectedDefaultIdx] = app.getAddChoicesForInput( ...
                    inputDef, 'selectedNodeOnly', true);

                if ~isempty(selectedRefs)

                    if numel(selectedRefs) == 1
                        chosenRef = selectedRefs{1};

                        app.appendDiagnostic(sprintf( ...
                            ['Auto-selected source "%s" for input "%s" of "%s" ' ...
                            'from the currently selected step.'], ...
                            chosenRef, inputName, funcName));

                        inputRefs{iIn} = chosenRef;
                        continue
                    end

                    chosenRef = app.promptSourceChoiceDialog( ...
                        inputName, funcName, selectedDisplay, selectedRefs, selectedDefaultIdx);

                    if isempty(chosenRef)
                        inputRefs = [];
                        return
                    end

                    app.appendDiagnostic(sprintf( ...
                        ['Selected source "%s" for input "%s" of "%s" ' ...
                        'from the currently selected step.'], ...
                        chosenRef, inputName, funcName));

                    inputRefs{iIn} = chosenRef;
                    continue
                end

                % Second pass: global leaf search
                [displayList, refList, defaultIdx] = app.getAddChoicesForInput( ...
                    inputDef, 'selectedNodeOnly', false);

                if isempty(refList)
                    chosenRef = app.promptFileInputReference(inputName);

                    if isempty(chosenRef)
                        inputRefs = [];
                        return
                    end

                    app.appendDiagnostic(sprintf( ...
                        'Selected file "%s" for input "%s" of "%s".', ...
                        chosenRef, inputName, funcName));

                    inputRefs{iIn} = chosenRef;
                    continue
                end

                if numel(refList) == 1
                    chosenRef = refList{1};

                    app.appendDiagnostic(sprintf( ...
                        'Auto-selected source "%s" for input "%s" of "%s".', ...
                        chosenRef, inputName, funcName));

                    inputRefs{iIn} = chosenRef;
                    continue
                end

                chosenRef = app.promptSourceChoiceDialog( ...
                    inputName, funcName, displayList, refList, defaultIdx);

                if isempty(chosenRef)
                    inputRefs = [];
                    return
                end

                app.appendDiagnostic(sprintf( ...
                    'Selected source "%s" for input "%s" of "%s".', ...
                    chosenRef, inputName, funcName));

                inputRefs{iIn} = chosenRef;
            end
        end

        function [displayList, refList, defaultIdx] = getAddChoicesForInput(app, inputDef, varargin)
            %GETADDCHOICESFORINPUT Build explicit source choices for one standard add-step input.
            %
            %   [displayList, refList, defaultIdx] = getAddChoicesForInput(app, inputDef)
            %   [displayList, refList, defaultIdx] = getAddChoicesForInput(..., ...
            %       'selectedNodeOnly', true)
            %
            % Standard add-step policy:
            %   - Default search uses current leaf nodes only
            %   - If selectedNodeOnly=true, only the currently selected graph node is used
            %   - File outputs are expanded into explicit file selections:
            %         StepTag:filename.ext
            %   - Data outputs use:
            %         StepTag:OutputPortName

            p = inputParser;
            addParameter(p, 'selectedNodeOnly', false, @(x) islogical(x) && isscalar(x));
            parse(p, varargin{:});

            selectedNodeOnly = p.Results.selectedNodeOnly;

            displayList = {};
            refList = {};
            defaultIdx = [];

            dstTypes = {};
            if isfield(inputDef, 'type') && ~isempty(inputDef.type)
                if ischar(inputDef.type) || (isstring(inputDef.type) && isscalar(inputDef.type))
                    dstTypes = {char(string(inputDef.type))};
                else
                    dstTypes = cellstr(string(inputDef.type(:)));
                end
            end

            if selectedNodeOnly
                if isnan(app.selectedNodeID)
                    return
                end

                [displayList, refList, defaultIdx] = app.buildExplicitSourceChoices(dstTypes, ...
                    'leafOnly', false, ...
                    'candidateNodeIDs', app.selectedNodeID);
            else
                [displayList, refList, defaultIdx] = app.buildExplicitSourceChoices(dstTypes, ...
                    'leafOnly', true, ...
                    'preferredNodeID', app.selectedNodeID);
            end
        end

        function [displayList, refList, defaultIdx] = buildFileSourceChoices(app, dstTypes, varargin)
            %BUILDFILESOURCECHOICES Build selectable choices from existing FileSource nodes.
            %
            % Output:
            %   displayList - Cellstr for listdlg display
            %   refList     - Cellstr of plain filenames suitable for addStep(...,'input',...)
            %   defaultIdx  - Suggested default index

            p = inputParser;
            addParameter(p, 'candidateNodeIDs', [], @(x) isnumeric(x) || isempty(x));
            parse(p, varargin{:});

            candidateNodeIDs = p.Results.candidateNodeIDs;

            displayList = {};
            refList = {};
            defaultIdx = [];

            if isempty(app.pm) || isempty(app.pm.nodes)
                return
            end

            candidateNodes = app.pm.nodes;

            % Keep only folder/FileSource nodes
            kindMask = false(1, numel(candidateNodes));
            for iNode = 1:numel(candidateNodes)
                kindMask(iNode) = isfield(candidateNodes(iNode), 'kind') && strcmpi(candidateNodes(iNode).kind, 'folder');
            end
            candidateNodes = candidateNodes(kindMask);

            if isempty(candidateNodes)
                return
            end

            if ~isempty(candidateNodeIDs)
                candidateNodeIDs = unique(candidateNodeIDs(:).', 'stable');
                candidateNodes = candidateNodes(ismember([candidateNodes.id], candidateNodeIDs));
            end

            if isempty(candidateNodes)
                return
            end

            for iNode = 1:numel(candidateNodes)

                srcNode = candidateNodes(iNode);

                if ~isfield(srcNode, 'info') || ~isfield(srcNode.info, 'outputs') || isempty(srcNode.info.outputs)
                    continue
                end

                compatibleFound = false;
                typeText = 'Unknown';

                for iOut = 1:numel(srcNode.info.outputs)
                    outDef = srcNode.info.outputs(iOut);

                    if ~isfield(outDef, 'isData') || ~outDef.isData
                        continue
                    end

                    srcTypes = {};
                    if isfield(outDef, 'type') && ~isempty(outDef.type)
                        if ischar(outDef.type) || (isstring(outDef.type) && isscalar(outDef.type))
                            srcTypes = {char(string(outDef.type))};
                        else
                            srcTypes = cellstr(string(outDef.type(:)));
                        end
                    end

                    if app.areTypesCompatible(srcTypes, dstTypes)
                        compatibleFound = true;
                        typeText = app.typeListToText(srcTypes);
                        break
                    end
                end

                if ~compatibleFound
                    continue
                end

                % Use the filename itself as the explicit input ref
                fileNameRef = char(string(srcNode.name));

                displayList{end+1,1} = sprintf('%s [%s, existing file source]', ...
                    fileNameRef, typeText); %#ok<AGROW>
                refList{end+1,1} = fileNameRef; %#ok<AGROW>
            end

            if isempty(displayList)
                return
            end

            defaultIdx = 1;
        end

        function [displayList, refList, defaultIdx] = buildExplicitSourceChoices(app, dstTypes, varargin)
            %BUILDEXPLICITSOURCECHOICES Build explicit selectable source references.
            %
            %   [displayList, refList, defaultIdx] = buildExplicitSourceChoices(app, dstTypes)
            %   [displayList, refList, defaultIdx] = buildExplicitSourceChoices(..., ...
            %       'leafOnly', true, ...
            %       'excludeNodeID', NaN, ...
            %       'preferredNodeID', NaN, ...
            %       'candidateNodeIDs', [])
            %
            % Output:
            %   displayList - Cellstr for listdlg display.
            %   refList     - Cellstr explicit references suitable for addStep/reconnectStep:
            %                   StepTag:OutputPortName
            %                   StepTag:filename.ext
            %   defaultIdx  - Suggested default selection index.
            %
            % Notes:
            %   - candidateNodeIDs, when provided, restricts the search to exactly
            %     those node IDs.
            %   - preferredNodeID only affects ordering when candidateNodeIDs is not used.

            p = inputParser;
            addParameter(p, 'leafOnly', false, @(x) islogical(x) && isscalar(x));
            addParameter(p, 'excludeNodeID', NaN, @(x) isnumeric(x) && isscalar(x));
            addParameter(p, 'preferredNodeID', NaN, @(x) isnumeric(x) && isscalar(x));
            addParameter(p, 'candidateNodeIDs', [], @(x) isnumeric(x) || isempty(x));
            parse(p, varargin{:});

            leafOnly = p.Results.leafOnly;
            excludeNodeID = p.Results.excludeNodeID;
            preferredNodeID = p.Results.preferredNodeID;
            candidateNodeIDs = p.Results.candidateNodeIDs;

            displayList = {};
            refList = {};
            defaultIdx = [];

            if isempty(app.pm) || isempty(app.pm.nodes)
                return
            end

            candidateNodes = app.pm.nodes;

            % -------------------------------------------------------------
            % Restrict to explicit node IDs when requested
            % -------------------------------------------------------------
            if ~isempty(candidateNodeIDs)
                candidateNodeIDs = unique(candidateNodeIDs(:).', 'stable');
                candidateNodes = candidateNodes(ismember([candidateNodes.id], candidateNodeIDs));
            elseif leafOnly
                leafIDs = app.computeLeafNodeIDs(app.pm.nodes, app.pm.connections);
                candidateNodes = candidateNodes(ismember([candidateNodes.id], leafIDs));
            end

            if ~isnan(excludeNodeID)
                candidateNodes = candidateNodes([candidateNodes.id] ~= excludeNodeID);
            end

            if isempty(candidateNodes)
                return
            end

            % -------------------------------------------------------------
            % Prefer the currently selected node when applicable
            % -------------------------------------------------------------
            if isempty(candidateNodeIDs) && ~isnan(preferredNodeID) && any([candidateNodes.id] == preferredNodeID)
                selMask = [candidateNodes.id] == preferredNodeID;
                candidateNodes = [candidateNodes(selMask), candidateNodes(~selMask)];
            end

            for iNode = 1:numel(candidateNodes)

                srcNode = candidateNodes(iNode);

                if ~isfield(srcNode, 'info') || ~isfield(srcNode.info, 'outputs') || isempty(srcNode.info.outputs)
                    continue
                end

                for iOut = 1:numel(srcNode.info.outputs)

                    outDef = srcNode.info.outputs(iOut);

                    if ~isfield(outDef, 'isData') || ~outDef.isData
                        continue
                    end

                    srcTypes = {};
                    if isfield(outDef, 'type') && ~isempty(outDef.type)
                        if ischar(outDef.type) || (isstring(outDef.type) && isscalar(outDef.type))
                            srcTypes = {char(string(outDef.type))};
                        else
                            srcTypes = cellstr(string(outDef.type(:)));
                        end
                    end

                    if ~app.areTypesCompatible(srcTypes, dstTypes)
                        continue
                    end

                    outName = char(string(outDef.name));
                    outMode = 'data';
                    if isfield(outDef, 'outputMode') && ~isempty(outDef.outputMode)
                        outMode = char(string(outDef.outputMode));
                    end

                    typeText = app.typeListToText(srcTypes);

                    if strcmpi(outMode, 'file')

                        declaredFiles = {};
                        if isfield(outDef, 'defOutfilename') && ~isempty(outDef.defOutfilename)
                            if ischar(outDef.defOutfilename) || (isstring(outDef.defOutfilename) && isscalar(outDef.defOutfilename))
                                declaredFiles = {char(string(outDef.defOutfilename))};
                            elseif iscell(outDef.defOutfilename)
                                declaredFiles = cellfun(@(x) char(string(x)), outDef.defOutfilename, 'UniformOutput', false);
                            end
                        end

                        declaredFiles = declaredFiles(~cellfun(@isempty, declaredFiles));
                        declaredFiles = unique(declaredFiles, 'stable');

                        if isempty(declaredFiles)
                            displayList{end+1,1} = sprintf('%s:%s [%s, file]', srcNode.name, outName, typeText); %#ok<AGROW>
                            refList{end+1,1} = sprintf('%s:%s', srcNode.name, outName); %#ok<AGROW>
                        else
                            for iFile = 1:numel(declaredFiles)
                                displayList{end+1,1} = sprintf('%s:%s [%s, file]', ...
                                    srcNode.name, declaredFiles{iFile}, typeText); %#ok<AGROW>
                                refList{end+1,1} = sprintf('%s:%s', ...
                                    srcNode.name, declaredFiles{iFile}); %#ok<AGROW>
                            end
                        end

                    else
                        displayList{end+1,1} = sprintf('%s:%s [%s]', srcNode.name, outName, typeText); %#ok<AGROW>
                        refList{end+1,1} = sprintf('%s:%s', srcNode.name, outName); %#ok<AGROW>
                    end
                end
            end

            if isempty(displayList)
                return
            end

            defaultIdx = 1;
        end

        function chosenRef = promptSourceChoiceDialog(app, inputName, funcName, displayList, refList, defaultIdx)
            %PROMPTSOURCECHOICEDIALOG Show the GUI dialog for ambiguous source selection.
            %
            % Output:
            %   chosenRef - Explicit source reference string, or '' if cancelled.

            chosenRef = '';

            if isempty(displayList) || isempty(refList)
                return
            end

            browseToken = '__BROWSE_FILE__';
            promptDisplay = [displayList(:); {sprintf('[Browse SaveFolder file...]  -> %s', inputName)}];
            promptRefs    = [refList(:); {browseToken}];

            if isempty(defaultIdx) || defaultIdx < 1 || defaultIdx > numel(displayList)
                defaultIdx = 1;
            end

            [selIdx, tf] = listdlg( ...
                'PromptString', sprintf('Select source for input "%s" of "%s":', inputName, funcName), ...
                'SelectionMode', 'single', ...
                'ListString', promptDisplay, ...
                'InitialValue', defaultIdx, ...
                'ListSize', [700 320]);

            if ~tf || isempty(selIdx)
                return
            end

            chosenRef = promptRefs{selIdx};

            if strcmp(chosenRef, browseToken)
                chosenRef = app.promptFileInputReference(inputName);
            end
        end

        function [displayList, refList] = getReconnectChoices(app, targetNode, inputDef)
            %GETRECONNECTCHOICES Build explicit reconnect choices for one target input.
            %
            % Reconnect policy:
            %   - Show all compatible source nodes except the target node itself
            %   - Do not restrict to leaf nodes

            displayList = {};
            refList = {};

            dstTypes = {};
            if isfield(inputDef, 'type') && ~isempty(inputDef.type)
                if ischar(inputDef.type) || (isstring(inputDef.type) && isscalar(inputDef.type))
                    dstTypes = {char(string(inputDef.type))};
                else
                    dstTypes = cellstr(string(inputDef.type(:)));
                end
            end

            [displayList, refList] = app.buildExplicitSourceChoices(dstTypes, ...
                'leafOnly', false, ...
                'excludeNodeID', targetNode.id);
        end

        function inputRef = promptFileInputReference(app, inputName)
            %PROMPTFILEINPUTREFERENCE Select an existing file from SaveFolder for one input.
            %
            % Output:
            %   inputRef - filename.ext suitable for PipelineManager.resolveInputReference
            %              or '' if cancelled.

            inputRef = '';

            startFolder = pwd;
            if ~isempty(app.pm) && isprop(app.pm, 'SaveFolderList') && ~isempty(app.pm.SaveFolderList)
                startFolder = app.pm.SaveFolderList{1};
            end

            [fileName, folderName] = uigetfile('*.*', ...
                sprintf('Select file for input "%s"', inputName), ...
                startFolder);

            if isequal(fileName, 0)
                return
            end

            % PipelineManager.resolveInputReference expects a file name relative to SaveFolder
            % for the file-in-folder path.
            inputRef = char(string(fileName));

            app.appendDiagnostic(sprintf('Selected file "%s" for input "%s".', inputRef, inputName));
        end

        function reconnectStepInteractive(app, stepTag)
            %RECONNECTSTEPINTERACTIVE Start reconnect mode for one selected step.
            %
            % Stage 1 behavior:
            %   - If the step has multiple data inputs, ask which input to reconnect
            %   - Highlight compatible source nodes in the graph
            %   - User clicks a highlighted source node to complete reconnect

            if isempty(app.pm)
                app.appendDiagnostic('Reconnect requested without an attached PipelineManager.');
                app.setStatus('No PipelineManager attached.');
                return
            end

            nodeIdx = find(strcmpi(stepTag, {app.pm.nodes.name}), 1, 'first');
            if isempty(nodeIdx)
                app.appendDiagnostic(sprintf('Reconnect failed. Step "%s" was not found.', stepTag));
                app.setStatus('Reconnect failed.');
                return
            end

            nodeLocal = app.pm.nodes(nodeIdx);

            if strcmpi(nodeLocal.kind, 'folder')
                app.appendDiagnostic('Folder nodes cannot be reconnected.');
                app.setStatus('Reconnect not allowed.');
                return
            end

            if ~isfield(nodeLocal, 'info') || ~isfield(nodeLocal.info, 'inputs') || isempty(nodeLocal.info.inputs)
                app.appendDiagnostic(sprintf('Step "%s" has no inputs to reconnect.', stepTag));
                app.setStatus('No inputs to reconnect.');
                return
            end

            dataInputs = nodeLocal.info.inputs(arrayfun(@(x) isfield(x, 'isData') && x.isData, nodeLocal.info.inputs));

            if isempty(dataInputs)
                app.appendDiagnostic(sprintf('Step "%s" has no data inputs to reconnect.', stepTag));
                app.setStatus('No data inputs to reconnect.');
                return
            end

            targetInputName = '';

            if numel(dataInputs) == 1
                targetInputName = char(string(dataInputs(1).name));
            else
                inputLabels = cell(numel(dataInputs), 1);
                for iIn = 1:numel(dataInputs)
                    inputLabels{iIn} = sprintf('%s [%s]', ...
                        char(string(dataInputs(iIn).name)), ...
                        app.typeListToText(dataInputs(iIn).type));
                end

                [selIdx, tf] = listdlg( ...
                    'PromptString', sprintf('Select input to reconnect for "%s":', stepTag), ...
                    'SelectionMode', 'single', ...
                    'ListString', inputLabels, ...
                    'ListSize', [450 260]);

                if ~tf || isempty(selIdx)
                    app.appendDiagnostic('Reconnect cancelled by user.');
                    app.setStatus('Reconnect cancelled.');
                    return
                end

                targetInputName = char(string(dataInputs(selIdx).name));
            end

            targetInputDef = [];
            for iIn = 1:numel(dataInputs)
                if strcmpi(char(string(dataInputs(iIn).name)), targetInputName)
                    targetInputDef = dataInputs(iIn);
                    break
                end
            end

            if isempty(targetInputDef)
                app.appendDiagnostic('Reconnect failed. Target input definition was not found.');
                app.setStatus('Reconnect failed.');
                return
            end

            [~, refList] = app.getReconnectChoices(nodeLocal, targetInputDef);
            candidateNodeIDs = app.refListToNodeIDs(refList);

            if isempty(candidateNodeIDs)
                app.appendDiagnostic(sprintf('No compatible source nodes were found for input "%s".', targetInputName));
                app.setStatus('Reconnect aborted.');
                return
            end

            app.bReconnectMode = true;
            app.reconnectTargetNodeID = nodeLocal.id;
            app.reconnectTargetStepTag = stepTag;
            app.reconnectTargetInputName = targetInputName;
            app.reconnectCandidateNodeIDs = candidateNodeIDs;

            app.selectedNodeID = nodeLocal.id;
            app.selectedStepTag = stepTag;

            app.refreshGraphView();
            app.appendDiagnostic(sprintf('Reconnect mode started for input "%s" of "%s".', targetInputName, stepTag));
            app.setStatus(sprintf('Reconnect mode: click a new source for %s', targetInputName));
        end

        function reconnectSourceNodeSelected(app, sourceNodeID)
            %RECONNECTSOURCENODESELECTED Complete reconnect after a graph source click.

            if ~app.bReconnectMode || isempty(app.pm)
                return
            end

            if ~any(app.reconnectCandidateNodeIDs == sourceNodeID)
                app.appendDiagnostic('Selected source is not a valid reconnect candidate.');
                app.setStatus('Invalid reconnect source.');
                return
            end

            targetIdx = find([app.pm.nodes.id] == app.reconnectTargetNodeID, 1, 'first');
            if isempty(targetIdx)
                app.appendDiagnostic('Reconnect failed. Target step no longer exists.');
                app.exitReconnectMode();
                app.setStatus('Reconnect failed.');
                return
            end

            targetNode = app.pm.nodes(targetIdx);

            if ~isfield(targetNode, 'info') || ~isfield(targetNode.info, 'inputs') || isempty(targetNode.info.inputs)
                app.appendDiagnostic('Reconnect failed. Target step has no inputs.');
                app.exitReconnectMode();
                app.setStatus('Reconnect failed.');
                return
            end

            dataInputs = targetNode.info.inputs(arrayfun(@(x) isfield(x, 'isData') && x.isData, targetNode.info.inputs));

            inputDef = [];
            for iIn = 1:numel(dataInputs)
                if strcmpi(char(string(dataInputs(iIn).name)), app.reconnectTargetInputName)
                    inputDef = dataInputs(iIn);
                    break
                end
            end

            if isempty(inputDef)
                app.appendDiagnostic('Reconnect failed. Target input definition was not found.');
                app.exitReconnectMode();
                app.setStatus('Reconnect failed.');
                return
            end

            dstTypes = {};
            if isfield(inputDef, 'type') && ~isempty(inputDef.type)
                if ischar(inputDef.type) || (isstring(inputDef.type) && isscalar(inputDef.type))
                    dstTypes = {char(string(inputDef.type))};
                else
                    dstTypes = cellstr(string(inputDef.type(:)));
                end
            end

            [displayList, refList, defaultIdx] = app.buildExplicitSourceChoices(dstTypes, ...
                'leafOnly', false, ...
                'excludeNodeID', targetNode.id, ...
                'candidateNodeIDs', sourceNodeID);

            if isempty(refList)
                app.appendDiagnostic('Reconnect failed. No explicit source reference was found for the selected node.');
                app.setStatus('Reconnect failed.');
                return
            end

            if numel(refList) == 1
                chosenRef = refList{1};
            else
                chosenRef = app.promptSourceChoiceDialog(app.reconnectTargetInputName, app.reconnectTargetStepTag, ...
                    displayList, refList, defaultIdx);

                if isempty(chosenRef)
                    app.appendDiagnostic('Reconnect source disambiguation cancelled.');
                    app.setStatus('Reconnect still active.');
                    return
                end
            end

            try
                inputRefs = cell(1, numel(dataInputs));

                for iIn = 1:numel(dataInputs)
                    thisInputName = char(string(dataInputs(iIn).name));

                    if strcmpi(thisInputName, app.reconnectTargetInputName)
                        inputRefs{iIn} = chosenRef;
                    else
                        inputRefs{iIn} = app.getCurrentExplicitInputReference(targetNode, dataInputs(iIn));
                        if isempty(inputRefs{iIn})
                            error('Current input "%s" is not connected, so partial reconnect cannot proceed.', thisInputName);
                        end
                    end
                end

                app.pm.reconnectStep(app.reconnectTargetStepTag, 'input', inputRefs);

                targetStepTag = app.reconnectTargetStepTag;
                targetInputName = app.reconnectTargetInputName;

                app.exitReconnectMode();
                app.refreshAllViews();
                app.appendDiagnostic(sprintf('Reconnected input "%s" of "%s".', targetInputName, targetStepTag));
                app.setStatus('Step reconnected.');

            catch ME
                app.appendDiagnostic(sprintf('Reconnect failed for "%s": %s', app.reconnectTargetStepTag, ME.message));
                app.setStatus('Reconnect failed.');
            end
        end

        function inputRef = getCurrentExplicitInputReference(app, targetNode, inputDef)
            %GETCURRENTEXPLICITINPUTREFERENCE Return the current explicit input reference string.

            inputRef = '';

            if isempty(app.pm) || isempty(app.pm.connections)
                return
            end

            inputName = char(string(inputDef.name));

            connIdx = find([app.pm.connections.targetNodeID] == targetNode.id & ...
                strcmpi({app.pm.connections.targetInputName}, inputName), 1, 'first');

            if isempty(connIdx)
                return
            end

            connLocal = app.pm.connections(connIdx);
            srcIdx = find([app.pm.nodes.id] == connLocal.sourceNodeID, 1, 'first');
            if isempty(srcIdx)
                return
            end

            srcNode = app.pm.nodes(srcIdx);

            srcRef = char(string(connLocal.sourceOutputName));
            if isfield(connLocal, 'selectedFile') && ~isempty(connLocal.selectedFile)
                srcRef = char(string(connLocal.selectedFile));
            end

            inputRef = sprintf('%s:%s', char(string(srcNode.name)), srcRef);
        end

        function nodeIDs = refListToNodeIDs(app, refList)
            %REFLISTTONODEIDS Convert explicit source refs to unique node IDs.

            nodeIDs = [];

            if isempty(refList) || isempty(app.pm) || isempty(app.pm.nodes)
                return
            end

            nodeIDs = zeros(0,1);

            for iRef = 1:numel(refList)
                refStr = char(string(refList{iRef}));
                tok = regexp(refStr, '^([^:]+):', 'tokens', 'once');
                if isempty(tok)
                    continue
                end

                stepTag = tok{1};
                idx = find(strcmpi(stepTag, {app.pm.nodes.name}), 1, 'first');
                if ~isempty(idx)
                    nodeIDs(end+1,1) = app.pm.nodes(idx).id; %#ok<AGROW>
                end
            end

            nodeIDs = unique(nodeIDs(:).', 'stable');
        end

        function exitReconnectMode(app)
            %EXITRECONNECTMODE Leave reconnect mode and clear transient state.

            app.bReconnectMode = false;
            app.reconnectTargetNodeID = NaN;
            app.reconnectTargetStepTag = '';
            app.reconnectTargetInputName = '';
            app.reconnectCandidateNodeIDs = [];

            app.refreshGraphView();
        end

        function tf = areTypesCompatible(~, srcTypes, dstTypes)
            %ARETYPESCOMPATIBLE Return true when two type lists are compatible.

            if isempty(srcTypes) || isempty(dstTypes)
                tf = true;
                return
            end

            srcTypes = cellstr(string(srcTypes(:)));
            dstTypes = cellstr(string(dstTypes(:)));

            srcHasWildcard = any(strcmp(srcTypes, 'UnknownDataType'));
            dstHasWildcard = any(strcmp(dstTypes, 'UnknownDataType'));

            tf = srcHasWildcard || dstHasWildcard || ~isempty(intersect(srcTypes, dstTypes));
        end

        function configureSaveTargetsInteractive(app, stepTag)
            %CONFIGURESAVETARGETSINTERACTIVE Configure PipelineManager-managed save targets.
            %
            % v1 behavior:
            %   - Single SAVEABLE output:
            %       blank -> clear save
            %       text  -> save to that filename
            %
            %   - Multiple SAVEABLE outputs:
            %       all blank -> clear all saves
            %       all filled -> set save targets for all SAVEABLE outputs
            %       partial fill -> rejected locally
            %
            % Saveable outputs are:
            %   isData == true AND outputMode ~= 'file'

            if isempty(app.pm)
                app.appendDiagnostic('Configure-save requested without an attached PipelineManager.');
                app.setStatus('No PipelineManager attached.');
                return
            end

            nodeIdx = find(strcmpi(stepTag, {app.pm.nodes.name}), 1, 'first');
            if isempty(nodeIdx)
                app.appendDiagnostic(sprintf('Configure-save failed. Step "%s" was not found.', stepTag));
                app.setStatus('Configure-save failed.');
                return
            end

            nodeLocal = app.pm.nodes(nodeIdx);

            if strcmpi(nodeLocal.kind, 'folder')
                app.appendDiagnostic('Folder nodes cannot have PipelineManager-managed save targets.');
                app.setStatus('Configure-save not allowed.');
                return
            end

            saveableOutputs = app.getSaveableOutputDefsForNode(nodeLocal);

            if isempty(saveableOutputs)
                app.appendDiagnostic(sprintf('Step "%s" has no manager-saveable outputs.', stepTag));
                app.setStatus('No saveable outputs.');
                return
            end

            promptList = cell(numel(saveableOutputs), 1);
            defaultVals = cell(numel(saveableOutputs), 1);

            for iOut = 1:numel(saveableOutputs)
                outDef = saveableOutputs(iOut);

                outName = char(string(outDef.name));
                outType = app.typeListToText(outDef.type);

                defaultTarget = '';
                if isfield(outDef, 'saveFileName') && ~isempty(outDef.saveFileName)
                    defaultTarget = char(string(outDef.saveFileName));
                elseif isfield(outDef, 'defOutfilename') && ~isempty(outDef.defOutfilename)
                    if iscell(outDef.defOutfilename)
                        defaultTarget = char(string(outDef.defOutfilename{1}));
                    else
                        defaultTarget = char(string(outDef.defOutfilename));
                    end
                end

                promptList{iOut} = sprintf('Save filename for output "%s" [%s]:', outName, outType);
                defaultVals{iOut} = defaultTarget;
            end

            answer = inputdlg( ...
                promptList, ...
                sprintf('Configure Save Outputs - %s', stepTag), ...
                repmat([1 70], numel(promptList), 1), ...
                defaultVals);

            if isempty(answer)
                app.appendDiagnostic('Configure-save action cancelled by user.');
                app.setStatus('Configure-save cancelled.');
                return
            end

            answer = cellfun(@(x) strtrim(char(string(x))), answer, 'UniformOutput', false);
            isBlank = cellfun(@isempty, answer);

            try
                if numel(saveableOutputs) == 1

                    if isBlank(1)
                        app.pm.setSaveTargets(stepTag, '');
                        app.appendDiagnostic(sprintf('Cleared save target for "%s".', stepTag));
                        app.setStatus('Save target cleared.');
                    else
                        app.pm.setSaveTargets(stepTag, answer{1});
                        app.appendDiagnostic(sprintf('Updated save target for "%s" -> %s', stepTag, answer{1}));
                        app.setStatus('Save target updated.');
                    end

                else
                    if all(isBlank)
                        app.pm.setSaveTargets(stepTag, '');
                        app.appendDiagnostic(sprintf('Cleared all save targets for "%s".', stepTag));
                        app.setStatus('Save targets cleared.');

                    elseif any(isBlank)
                        app.appendDiagnostic(['Partial save configuration is not supported yet for multi-output steps. ' ...
                            'Fill every saveable output filename or leave all blank to clear all.']);
                        app.setStatus('Invalid save-target selection.');
                        return

                    else
                        app.pm.setSaveTargets(stepTag, answer);
                        app.appendDiagnostic(sprintf('Updated %d save targets for "%s".', numel(answer), stepTag));
                        app.setStatus('Save targets updated.');
                    end
                end

                app.refreshAllViews();

            catch ME
                app.appendDiagnostic(sprintf('Failed to configure save outputs for "%s": %s', stepTag, ME.message));
                app.setStatus('Failed to configure save outputs.');
            end
        end

        function saveableOutputs = getSaveableOutputDefsForNode(~, nodeLocal)
            %GETSAVEABLEOUTPUTDEFSFORNODE Return saveable outputs for one node.
            %
            % Saveable outputs are:
            %   isData == true AND outputMode ~= 'file'

            saveableOutputs = struct([]);

            if ~isfield(nodeLocal, 'info') || ~isfield(nodeLocal.info, 'outputs') || isempty(nodeLocal.info.outputs)
                return
            end

            mask = false(1, numel(nodeLocal.info.outputs));
            for iOut = 1:numel(nodeLocal.info.outputs)

                outDef = nodeLocal.info.outputs(iOut);

                outMode = 'data';
                if isfield(outDef, 'outputMode') && ~isempty(outDef.outputMode)
                    outMode = char(string(outDef.outputMode));
                end

                mask(iOut) = isfield(outDef, 'isData') && outDef.isData && ~strcmpi(outMode, 'file');
            end

            saveableOutputs = nodeLocal.info.outputs(mask);
        end

        function dataOutputs = getDataOutputDefsForNode(~, nodeLocal)
            %GETDATAOUTPUTDEFSFORNODE Return DATA outputs for one node.

            dataOutputs = struct([]);

            if ~isfield(nodeLocal, 'info') || ~isfield(nodeLocal.info, 'outputs') || isempty(nodeLocal.info.outputs)
                return
            end

            mask = false(1, numel(nodeLocal.info.outputs));
            for iOut = 1:numel(nodeLocal.info.outputs)
                mask(iOut) = isfield(nodeLocal.info.outputs(iOut), 'isData') && nodeLocal.info.outputs(iOut).isData;
            end

            dataOutputs = nodeLocal.info.outputs(mask);
        end

        function completeReconnectWithRef(app, chosenRef)
            %COMPLETERECONNECTWITHREF Complete reconnect using one explicit reference.

            if ~app.bReconnectMode || isempty(app.pm)
                return
            end

            targetIdx = find([app.pm.nodes.id] == app.reconnectTargetNodeID, 1, 'first');
            if isempty(targetIdx)
                app.appendDiagnostic('Reconnect failed. Target step no longer exists.');
                app.exitReconnectMode();
                app.setStatus('Reconnect failed.');
                return
            end

            targetNode = app.pm.nodes(targetIdx);

            if ~isfield(targetNode, 'info') || ~isfield(targetNode.info, 'inputs') || isempty(targetNode.info.inputs)
                app.appendDiagnostic('Reconnect failed. Target step has no inputs.');
                app.exitReconnectMode();
                app.setStatus('Reconnect failed.');
                return
            end

            dataInputs = targetNode.info.inputs(arrayfun(@(x) isfield(x, 'isData') && x.isData, targetNode.info.inputs));

            inputRefs = cell(1, numel(dataInputs));

            for iIn = 1:numel(dataInputs)
                thisInputName = char(string(dataInputs(iIn).name));

                if strcmpi(thisInputName, app.reconnectTargetInputName)
                    inputRefs{iIn} = chosenRef;
                else
                    inputRefs{iIn} = app.getCurrentExplicitInputReference(targetNode, dataInputs(iIn));
                    if isempty(inputRefs{iIn})
                        error('Current input "%s" is not connected, so partial reconnect cannot proceed.', thisInputName);
                    end
                end
            end

            try
                targetStepTag = app.reconnectTargetStepTag;
                targetInputName = app.reconnectTargetInputName;

                app.pm.reconnectStep(targetStepTag, 'input', inputRefs);

                app.exitReconnectMode();
                app.refreshAllViews();
                app.appendDiagnostic(sprintf('Reconnected input "%s" of "%s".', targetInputName, targetStepTag));
                app.setStatus('Step reconnected.');

            catch ME
                app.appendDiagnostic(sprintf('Reconnect failed for "%s": %s', app.reconnectTargetStepTag, ME.message));
                app.setStatus('Reconnect failed.');
            end
        end

    end

    methods (Access = public)

        function attachPipelineManager(app, pm)
            %ATTACHPIPELINEMANAGER Attach an existing PipelineManager instance.

            if nargin < 2 || isempty(pm) || ~isa(pm, 'PipelineManager')
                error('PipelineManagerTool:attachPipelineManager:InvalidInput', ...
                    'attachPipelineManager expects a valid PipelineManager instance.');
            end

            app.pm = pm;
            app.selectedNodeID = NaN;
            app.selectedStepTag = '';
            app.selectedFunctionName = '';

            app.populateFunctionTree();
            app.refreshAllViews();

            app.appendDiagnostic(sprintf('PipelineManager attached. Current step count: %d.', numel(app.pm.nodes)));
            app.setStatus('PipelineManager attached.');
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.initializeUiState();

            try
                app.ensureHtmlFilesExist();
                app.appendDiagnostic(sprintf('Loaded graph HTML: %s', app.graphHtmlFile));
                app.appendDiagnostic(sprintf('Loaded execution HTML: %s', app.execHtmlFile));
            catch ME
                app.appendDiagnostic(sprintf('Failed to initialize HTML components: %s', ME.message));
                app.setStatus('Failed to initialize HTML components.');
            end

            app.populateFunctionTree();
            app.refreshAllViews();

            app.appendDiagnostic('App initialized.');
            app.setStatus('App initialized.');

        end

        % Button pushed function: NewButton
        function NewButtonPushed(app, event)
            if isempty(app.pm)
                app.promptCreatePipelineManager();
                return
            end

            choice = uiconfirm(app.UIFigure, ...
                'Clear the current pipeline and start a new one?', ...
                'New Pipeline', ...
                'Options', {'Clear Pipeline', 'Cancel'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);

            if ~strcmp(choice, 'Clear Pipeline')
                app.appendDiagnostic('New pipeline action cancelled.');
                app.setStatus('New pipeline cancelled.');
                return
            end

            try
                app.pm.clearPipeline();
                app.selectedNodeID = NaN;
                app.selectedStepTag = '';
                app.refreshAllViews();

                app.appendDiagnostic('Current pipeline cleared.');
                app.setStatus('New empty pipeline ready.');

            catch ME
                app.appendDiagnostic(sprintf('Failed to clear pipeline: %s', ME.message));
                app.setStatus('Failed to clear pipeline.');
            end

        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if isempty(app.pm)
                tf = app.promptCreatePipelineManager();
                if ~tf
                    return
                end
            end

            [fileName, folderName] = uigetfile({'*.pipe', 'Pipeline files (*.pipe)'}, 'Load pipeline');
            if isequal(fileName, 0)
                app.appendDiagnostic('Load pipeline cancelled by user.');
                app.setStatus('Load cancelled.');
                return
            end

            pipeFile = fullfile(folderName, fileName);

            try
                app.pm.loadPipe(pipeFile);

                app.selectedNodeID = NaN;
                app.selectedStepTag = '';

                app.populateFunctionTree();
                app.refreshAllViews();

                app.appendDiagnostic(sprintf('Loaded pipeline: %s', pipeFile));
                app.setStatus('Pipeline loaded.');

            catch ME
                app.appendDiagnostic(sprintf('Failed to load pipeline: %s', ME.message));
                app.setStatus('Failed to load pipeline.');
            end

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            if isempty(app.pm)
                app.appendDiagnostic('Save requested without an attached PipelineManager.');
                app.setStatus('No PipelineManager attached.');
                return
            end

            [fileName, folderName] = uiputfile({'*.pipe', 'Pipeline files (*.pipe)'}, ...
                'Save pipeline as', fullfile(pwd, 'MyPipeline.pipe'));

            if isequal(fileName, 0)
                app.appendDiagnostic('Save pipeline cancelled by user.');
                app.setStatus('Save cancelled.');
                return
            end

            try
                app.pm.savePipe(fullfile(folderName, fileName));
                app.appendDiagnostic(sprintf('Saved pipeline to %s', fullfile(folderName, fileName)));
                app.setStatus('Pipeline saved.');

            catch ME
                app.appendDiagnostic(sprintf('Failed to save pipeline: %s', ME.message));
                app.setStatus('Failed to save pipeline.');
            end

        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)

            app.validateAndReportPipeline();
            app.refreshGraphView();

        end

        % Value changed function: SearchFunctionEditField
        function SearchFunctionEditFieldValueChanged(app, event)
            app.populateFunctionTree();

            if isempty(strtrim(char(string(app.SearchFunctionEditField.Value))))
                app.appendDiagnostic('Function search cleared.');
            else
                app.appendDiagnostic(sprintf('Function search filter updated: %s', char(string(app.SearchFunctionEditField.Value))));
            end

            app.setStatus('Function tree updated.');


        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            app.tryAddSelectedFunction(false);
        end

        % Button pushed function: AddasNewBranchButton
        function AddasNewBranchButtonPushed(app, event)
            app.tryAddSelectedFunction(true);
        end

        % Button pushed function: AutoLayoutButton
        function AutoLayoutButtonPushed(app, event)
            app.refreshGraphView();
            app.appendDiagnostic('Auto-layout applied.');
            app.setStatus('Graph auto-layout applied.');

        end

        % Button pushed function: ResetViewButton
        function ResetViewButtonPushed(app, event)
            payload = app.buildGraphPayload();
            payload.command = 'resetView';
            app.GraphHTML.Data = payload;

            app.appendDiagnostic('Graph view reset.');
            app.setStatus('Graph view reset.');

        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.refreshAllViews();
            app.appendDiagnostic('Views refreshed.');
            app.setStatus('Views refreshed.');

        end

        % Data changed function: GraphHTML
        function GraphHTMLDataChanged(app, event)
            % This callback is currently not used.
            % Graph interactions are handled through HTMLEventReceived.


        end

        % Event received function: GraphHTML
        function GraphHTMLEventReceived(app, event)
            eventName = lower(char(string(event.HTMLEventName)));
            eventData = event.HTMLEventData;

            switch eventName

                case 'htmlready'
                    if isstruct(eventData) && isfield(eventData, 'component') && strcmpi(char(string(eventData.component)), 'graph')
                        app.bGraphHtmlReady = true;
                        app.refreshGraphView();
                        app.appendDiagnostic('HTML graph is ready.');
                        app.setStatus('Graph view ready.');
                    end

                case 'graphrendered'
                    % No action needed.

                case 'nodeselected'
                    if isstruct(eventData) && isfield(eventData, 'nodeID')
                        app.selectedNodeID = double(eventData.nodeID);

                        if isfield(eventData, 'stepTag')
                            app.selectedStepTag = char(string(eventData.stepTag));
                        else
                            app.selectedStepTag = '';
                        end

                        app.refreshSelectedStepPanel();
                        app.refreshGraphView();
                        app.refreshExecutionView();

                        if ~isempty(app.selectedStepTag)
                            app.setStatus(['Selected step: ' app.selectedStepTag]);
                        else
                            app.setStatus('Step selected.');
                        end
                    end

                case 'reconnectsourcechosen'
                    if isstruct(eventData) && isfield(eventData, 'ref')
                        chosenRef = char(string(eventData.ref));
                        if ~isempty(chosenRef)
                            app.completeReconnectWithRef(chosenRef);
                        end
                    end

                case 'reconnectusefile'
                    if app.bReconnectMode
                        chosenRef = app.promptFileInputReference(app.reconnectTargetInputName);
                        if isempty(chosenRef)
                            app.appendDiagnostic('Reconnect file selection cancelled.');
                            app.setStatus('Reconnect still active.');
                        else
                            app.completeReconnectWithRef(chosenRef);
                        end
                    end

                case 'cancelreconnect'
                    if app.bReconnectMode
                        app.exitReconnectMode();
                        app.appendDiagnostic('Reconnect mode cancelled.');
                        app.setStatus('Reconnect cancelled.');
                    end

                case 'contextaction'
                    if ~isstruct(eventData) || ~isfield(eventData, 'action')
                        return
                    end

                    action = lower(char(string(eventData.action)));
                    stepTag = '';

                    if isfield(eventData, 'stepTag')
                        stepTag = char(string(eventData.stepTag));
                    end

                    switch action

                        case 'removestep'
                            if isempty(stepTag) || isempty(app.pm)
                                return
                            end

                            choice = uiconfirm(app.UIFigure, ...
                                sprintf('Remove step "%s"?', stepTag), ...
                                'Remove Step', ...
                                'Options', {'Remove', 'Cancel'}, ...
                                'DefaultOption', 2, ...
                                'CancelOption', 2);

                            if ~strcmp(choice, 'Remove')
                                app.appendDiagnostic('Remove-step action cancelled.');
                                app.setStatus('Remove-step cancelled.');
                                return
                            end

                            try
                                app.pm.rmStep(stepTag);

                                if strcmpi(app.selectedStepTag, stepTag)
                                    app.selectedNodeID = NaN;
                                    app.selectedStepTag = '';
                                end

                                app.exitReconnectMode();
                                app.refreshAllViews();
                                app.appendDiagnostic(sprintf('Removed step "%s".', stepTag));
                                app.setStatus('Step removed.');

                            catch ME
                                app.appendDiagnostic(sprintf('Failed to remove step "%s": %s', stepTag, ME.message));
                                app.setStatus('Failed to remove step.');
                            end

                        case 'editparameters'
                            if isempty(stepTag) || isempty(app.pm)
                                return
                            end

                            try
                                app.pm.setParameters(stepTag);
                                app.refreshAllViews();
                                app.appendDiagnostic(sprintf('Parameter dialog completed for "%s".', stepTag));
                                app.setStatus('Parameters updated.');

                            catch ME
                                app.appendDiagnostic(sprintf('Failed to edit parameters for "%s": %s', stepTag, ME.message));
                                app.setStatus('Failed to edit parameters.');
                            end

                        case 'reconnectstep'
                            if isempty(stepTag)
                                return
                            end
                            app.reconnectStepInteractive(stepTag);

                        case 'configuresaveoutputs'
                            if isempty(stepTag)
                                return
                            end
                            app.configureSaveTargetsInteractive(stepTag);

                        otherwise
                            app.appendDiagnostic(sprintf('Unknown graph context action: %s', action));
                            app.setStatus('Unknown graph action.');
                    end
            end

        end

        % Data changed function: ExecGraphHTML
        function ExecGraphHTMLDataChanged(app, event)
            % This callback is currently not used.
            % Execution-view interactions are handled through HTMLEventReceived.


        end

        % Event received function: ExecGraphHTML
        function ExecGraphHTMLEventReceived(app, event)

            eventName = lower(char(string(event.HTMLEventName)));
            eventData = event.HTMLEventData;

            switch eventName

                case 'htmlready'
                    if isstruct(eventData) && isfield(eventData, 'component') && strcmpi(char(string(eventData.component)), 'execution')
                        app.bExecHtmlReady = true;
                        app.refreshExecutionView();
                        app.appendDiagnostic('Execution-order HTML view is ready.');
                        app.setStatus('Execution view ready.');
                    end

                case 'stepselected'
                    if isstruct(eventData) && isfield(eventData, 'nodeID')
                        app.selectedNodeID = double(eventData.nodeID);

                        if isfield(eventData, 'stepTag')
                            app.selectedStepTag = char(string(eventData.stepTag));
                        else
                            app.selectedStepTag = '';
                        end

                        app.refreshSelectedStepPanel();
                        app.refreshGraphView();
                        app.refreshExecutionView();

                        if ~isempty(app.selectedStepTag)
                            app.setStatus(['Selected step: ' app.selectedStepTag]);
                        else
                            app.setStatus('Execution step selected.');
                        end
                    end
            end


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)

        end

        % Selection changed function: FunctionTree
        function FunctionTreeSelectionChanged(app, event)

            selectedNodes = app.FunctionTree.SelectedNodes;

            if isempty(selectedNodes)
                app.selectedFunctionName = '';
                app.AddButton.Enable = 'off';
                app.AddasNewBranchButton.Enable = 'off';
                return
            end

            selectedNode = selectedNodes(1);
            nodeData = selectedNode.NodeData;

            if isempty(nodeData) || ~isstruct(nodeData) || ~isfield(nodeData, 'kind')
                app.selectedFunctionName = '';
                app.AddButton.Enable = 'off';
                app.AddasNewBranchButton.Enable = 'off';
                return
            end

            if strcmpi(nodeData.kind, 'function') && isfield(nodeData, 'name')
                app.selectedFunctionName = char(string(nodeData.name));
                app.AddButton.Enable = 'on';
                app.AddasNewBranchButton.Enable = 'on';
                app.appendDiagnostic(sprintf('Selected function: %s', app.selectedFunctionName));
                app.setStatus('Function selected.');
            else
                app.selectedFunctionName = '';
                app.AddButton.Enable = 'off';
                app.AddasNewBranchButton.Enable = 'off';
            end

        end

        % Button pushed function: ClearPipelineButton
        function ClearPipelineButtonPushed(app, event)
            if isempty(app.pm)
                app.appendDiagnostic('Clear pipeline requested without an attached PipelineManager.');
                app.setStatus('No PipelineManager attached.');
                return
            end

            choice = uiconfirm(app.UIFigure, ...
                'Clear the current pipeline?', ...
                'Clear Pipeline', ...
                'Options', {'Clear Pipeline', 'Cancel'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);

            if ~strcmp(choice, 'Clear Pipeline')
                app.appendDiagnostic('Clear pipeline action cancelled.');
                app.setStatus('Clear pipeline cancelled.');
                return
            end

            try
                app.pm.clearPipeline();

                app.selectedNodeID = NaN;
                app.selectedStepTag = '';

                if app.bReconnectMode
                    app.bReconnectMode = false;
                    app.reconnectTargetNodeID = NaN;
                    app.reconnectTargetStepTag = '';
                    app.reconnectTargetInputName = '';
                    app.reconnectCandidateNodeIDs = [];
                end

                app.refreshAllViews();

                app.appendDiagnostic('Current pipeline cleared.');
                app.setStatus('Pipeline cleared.');

            catch ME
                app.appendDiagnostic(sprintf('Failed to clear pipeline: %s', ME.message));
                app.setStatus('Failed to clear pipeline.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayoutTop = uigridlayout(app.GridLayout);
            app.NewButton = uibutton(app.GridLayoutTop, 'push');
            app.NewButton.ButtonPushedFcn = createCallbackFcn(app, @NewButtonPushed, true);
            app.NewButton.Text = 'New';
            app.LoadButton = uibutton(app.GridLayoutTop, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.GridLayoutTop, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ValidateButton = uibutton(app.GridLayoutTop, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate';
            app.ClearPipelineButton = uibutton(app.GridLayoutTop, 'push');
            app.ClearPipelineButton.ButtonPushedFcn = createCallbackFcn(app, @ClearPipelineButtonPushed, true);
            app.ClearPipelineButton.Text = 'Clear Pipeline';
            app.AvailableFunctionsPanel = uipanel(app.GridLayout);
            app.AvailableFunctionsPanel.Title = 'Available Functions';
            app.GridAvailableFunctions = uigridlayout(app.AvailableFunctionsPanel);
            app.SearchFunctionEditField = uieditfield(app.GridAvailableFunctions, 'text');
            app.SearchFunctionEditField.ValueChangedFcn = createCallbackFcn(app, @SearchFunctionEditFieldValueChanged, true);
            app.SearchFunctionEditField.Placeholder = 'Search function ...';
            app.FunctionTree = uitree(app.GridAvailableFunctions);
            app.FunctionTree.SelectionChangedFcn = createCallbackFcn(app, @FunctionTreeSelectionChanged, true);
            app.Node = uitreenode(app.FunctionTree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.AddButton = uibutton(app.GridAvailableFunctions, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Enable = 'off';
            app.AddButton.Text = 'Add ';
            app.AddasNewBranchButton = uibutton(app.GridAvailableFunctions, 'push');
            app.AddasNewBranchButton.ButtonPushedFcn = createCallbackFcn(app, @AddasNewBranchButtonPushed, true);
            app.AddasNewBranchButton.Enable = 'off';
            app.AddasNewBranchButton.Text = 'Add as New Branch';
            app.PipelineGraphPanel = uipanel(app.GridLayout);
            app.PipelineGraphPanel.Title = 'Pipeline Graph ';
            app.GridPipelineGraph = uigridlayout(app.PipelineGraphPanel);
            app.AutoLayoutButton = uibutton(app.GridPipelineGraph, 'push');
            app.AutoLayoutButton.ButtonPushedFcn = createCallbackFcn(app, @AutoLayoutButtonPushed, true);
            app.AutoLayoutButton.Text = 'Auto Layout';
            app.ResetViewButton = uibutton(app.GridPipelineGraph, 'push');
            app.ResetViewButton.ButtonPushedFcn = createCallbackFcn(app, @ResetViewButtonPushed, true);
            app.ResetViewButton.Text = 'Reset View';
            app.RefreshButton = uibutton(app.GridPipelineGraph, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.GraphHTML = uihtml(app.GridPipelineGraph);
            app.GraphHTML.DataChangedFcn = createCallbackFcn(app, @GraphHTMLDataChanged, true);
            app.GraphHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @GraphHTMLEventReceived, true);
            app.SelectedstepdetailsPanel = uipanel(app.GridLayout);
            app.SelectedstepdetailsPanel.Title = 'Selected step details';
            app.GridSelectedStepDetails = uigridlayout(app.SelectedstepdetailsPanel);
            app.StepNameLabel = uilabel(app.GridSelectedStepDetails);
            app.StepNameLabel.Text = 'Step';
            app.StepTagLabel = uilabel(app.GridSelectedStepDetails);
            app.StepTagLabel.Text = 'Step Tag';
            app.StepTagField = uieditfield(app.GridSelectedStepDetails, 'text');
            app.StepTagField.Editable = 'off';
            app.StepSummaryLabel = uilabel(app.GridSelectedStepDetails);
            app.StepSummaryLabel.Text = 'Step Summary';
            app.StepSummaryTextArea = uitextarea(app.GridSelectedStepDetails);
            app.StepSummaryTextArea.Editable = 'off';
            app.ParametersLabel = uilabel(app.GridSelectedStepDetails);
            app.ParametersLabel.Text = 'Parameters';
            app.ParametersTextArea = uitextarea(app.GridSelectedStepDetails);
            app.ParametersTextArea.Editable = 'off';
            app.FunctionNameField = uieditfield(app.GridSelectedStepDetails, 'text');
            app.FunctionNameField.Editable = 'off';
            app.ExecutionOrderPanel = uipanel(app.GridLayout);
            app.ExecutionOrderPanel.Title = 'Execution Order';
            app.GridExecutionOrder = uigridlayout(app.ExecutionOrderPanel);
            app.ExecGraphHTML = uihtml(app.GridExecutionOrder);
            app.ExecGraphHTML.DataChangedFcn = createCallbackFcn(app, @ExecGraphHTMLDataChanged, true);
            app.ExecGraphHTML.HTMLEventReceivedFcn = createCallbackFcn(app, @ExecGraphHTMLEventReceived, true);
            app.DiagnosticsPanel = uipanel(app.GridLayout);
            app.DiagnosticsPanel.Title = 'Diagnostics';
            app.GridDiagnostics = uigridlayout(app.DiagnosticsPanel);
            app.DiagnosticsTextArea = uitextarea(app.GridDiagnostics);
            app.DiagnosticsTextArea.Editable = 'off';
            app.StatusLabel = uilabel(app.GridDiagnostics);
            app.StatusLabel.Text = 'Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PipelineManagerTool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end