classdef ProjectBindingManager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        StatusLabel                 matlab.ui.control.Label
        BottomGrid                  matlab.ui.container.GridLayout
        BindFolderButton            matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
        ViewprojectButton           matlab.ui.control.Button
        ProjectContextPanel         matlab.ui.container.Panel
        ProjectContextGridLayout    matlab.ui.container.GridLayout
        NewProjectEditField         matlab.ui.control.EditField
        NewsessionButton            matlab.ui.control.Button
        SessionDropDown             matlab.ui.control.DropDown
        SessionLabel                matlab.ui.control.Label
        NewsubjectButton            matlab.ui.control.Button
        SubjectDropDown             matlab.ui.control.DropDown
        SubjectLabel                matlab.ui.control.Label
        ProjectDropDown             matlab.ui.control.DropDown
        ProjectLabel                matlab.ui.control.Label
        RefreshprojectsButton       matlab.ui.control.Button
        ProjectModeDropDown         matlab.ui.control.DropDown
        ProjectmodeLabel            matlab.ui.control.Label
        CurrentsavefolderLabel      matlab.ui.control.Label
        AssociatethecurrentfoldertoaprojectcontexLabel  matlab.ui.control.Label
        AssignFoldertoProjectLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        ProjectsTable = table()
        ProjectStore = []
        ProjectInfo = struct()

        PendingSubjectInfo = struct()
        PendingSessionInfo = struct()
        PendingSessionOwner = ''

        BindingState = 'unbound'
        ExistingBindingContext = struct()
        ExistingBindingError = ''

        IsRefreshingUI = false

        PendingSubjectToken = '__PENDING_SUBJECT__'
        PendingSessionToken = '__PENDING_SESSION__'
        Mode char = 'assign'
    end


    properties (SetAccess = private)
        CallerApp = []
        SaveFolder = ''

        OutputProjectStore = []
        OutputContext = struct()
        OutputProjectBinding = struct()
        WasBindingConfirmed = false

    end

    methods (Access = private)

        function inspectCurrentBinding(app)
            if strcmp(app.currentBindingState(), 'unbound')
                app.BindingState = 'unbound';
                app.ExistingBindingContext = struct();
                app.ExistingBindingError = '';
                app.refreshProjects('', '', '');
                return
            end

            if strcmp(app.Mode, 'rebind')
                try
                    [isOrphan, ProjectBinding] = ...
                        UMITProjectStore.isOrphanProjectBinding( ...
                        app.SaveFolder);

                    if ~isOrphan
                        % The project became available again between DataViewer's check
                        % and opening this dialog. Treat it as a valid existing binding.
                        [context, store] = ...
                            UMITProjectStore.resolveProjectBinding( ...
                            app.SaveFolder);

                        app.BindingState = 'valid';
                        app.ExistingBindingContext = context;
                        app.ExistingBindingError = '';
                        app.ProjectStore = store;
                        app.ProjectInfo = store.getProjectInfo();

                        app.OutputProjectStore = store;
                        app.OutputContext = context;
                        app.OutputProjectBinding = ProjectBinding;

                        app.refreshProjects( ...
                            context.projectUUID, ...
                            context.subjectUUID, ...
                            context.sessionUUID);
                        app.updateUIState();
                        app.setStatus(sprintf( ...
                            'Project is available again: %s / %s / %s', ...
                            context.projectName, ...
                            context.subjectID, ...
                            context.sessionID), 'success');
                        return
                    end

                    app.BindingState = 'rebind';
                    app.ExistingBindingContext = struct();
                    app.ExistingBindingError = '';
                    app.OutputProjectBinding = ProjectBinding;
                    app.ProjectStore = [];
                    app.ProjectInfo = struct();

                    app.refreshProjects('', '', '');
                    app.updateUIState();
                    app.setStatus( ...
                        ['The original project is unavailable. Select a replacement ' ...
                        'project context.'], ...
                        'warning');
                    return

                catch ME
                    app.BindingState = 'invalid';
                    app.ExistingBindingContext = struct();
                    app.ExistingBindingError = ME.message;
                    app.ProjectStore = [];
                    app.ProjectInfo = struct();

                    app.clearDropdown(app.ProjectDropDown, ...
                        '<Binding cannot be reassigned>');
                    app.clearDropdown(app.SubjectDropDown, '<Unavailable>');
                    app.clearDropdown(app.SessionDropDown, '<Unavailable>');

                    app.updateUIState();
                    app.setStatus( ...
                        'The existing binding is not a removable orphan link.', ...
                        'error');

                    uialert(app.UIFigure, sprintf([ ...
                        'The existing binding cannot be reassigned.\n\n%s'], ...
                        ME.message), ...
                        'Binding Reassignment Unavailable', ...
                        'Icon', 'error');
                    return
                end
            end

            % Normal assignment mode: preserve the existing implementation.
            try
                [context, store] = ...
                    UMITProjectStore.resolveProjectBinding(app.SaveFolder);

                app.BindingState = 'valid';
                app.ExistingBindingContext = context;
                app.ExistingBindingError = '';
                app.ProjectStore = store;
                app.ProjectInfo = store.getProjectInfo();

                app.OutputProjectStore = store;
                app.OutputContext = context;
                app.OutputProjectBinding = ...
                    UMITProjectStore.readProjectBinding(app.SaveFolder);

                app.refreshProjects( ...
                    context.projectUUID, ...
                    context.subjectUUID, ...
                    context.sessionUUID);

                app.updateUIState();
                app.setStatus(sprintf( ...
                    'Already bound: %s / %s / %s', ...
                    context.projectName, ...
                    context.subjectID, ...
                    context.sessionID), 'success');

            catch ME
                app.BindingState = 'invalid';
                app.ExistingBindingContext = struct();
                app.ExistingBindingError = ME.message;
                app.ProjectStore = [];
                app.ProjectInfo = struct();

                app.clearDropdown(app.ProjectDropDown, ...
                    '<Binding must be repaired>');
                app.clearDropdown(app.SubjectDropDown, '<Unavailable>');
                app.clearDropdown(app.SessionDropDown, '<Unavailable>');

                app.updateUIState();
                app.setStatus('The existing project binding is invalid.', ...
                    'error');

                uialert(app.UIFigure, sprintf([ ...
                    'The SaveFolder already contains a project binding, ' ...
                    'but it could not be resolved.\n\n%s\n\n' ...
                    'The binding will not be overwritten.'], ME.message), ...
                    'Invalid Project Binding', 'Icon', 'error');
            end
        end

        function state = currentBindingState(app)
            %CURRENTBINDINGSTATE 'unbound', 'bound' or 'invalid', from the project ledger.
            %
            %   The ledger file stays after an unbind (its history is kept), so
            %   whether the file exists says nothing. An unreadable ledger is
            %   'invalid': resolving it then reports the actual error.

            try
                state = UMITProjectStore.getProjectBindingState(app.SaveFolder);
            catch
                state = 'invalid';
            end
        end
        function refreshProjects(app, preferredProjectUUID, ...
                preferredSubjectUUID, preferredSessionUUID)

            if nargin < 2
                preferredProjectUUID = '';
            end
            if nargin < 3
                preferredSubjectUUID = '';
            end
            if nargin < 4
                preferredSessionUUID = '';
            end

            if strcmp(app.BindingState, 'invalid')
                return
            end

            app.IsRefreshingUI = true;
            cleanupRefresh = onCleanup(@() app.finishUIRefresh());

            try
                projects = UMITProjectStore.listProjects();
            catch ME
                app.ProjectsTable = table();
                app.ProjectStore = [];
                app.ProjectInfo = struct();
                app.clearDropdown(app.ProjectDropDown, ...
                    '<Projects unavailable>');
                app.clearDropdown(app.SubjectDropDown, '<No subject>');
                app.clearDropdown(app.SessionDropDown, '<No session>');
                clear cleanupRefresh
                app.updateUIState();
                app.setStatus(ME.message, 'error');
                return
            end

            if isempty(projects)
                readableProjects = projects;
            else
                readableProjects = projects(projects.IsReadable, :);
            end
            app.ProjectsTable = readableProjects;

            if isempty(readableProjects)
                app.ProjectStore = [];
                app.ProjectInfo = struct();
                app.clearDropdown(app.ProjectDropDown, ...
                    '<No readable projects>');
                app.clearDropdown(app.SubjectDropDown, '<No subject>');
                app.clearDropdown(app.SessionDropDown, '<No session>');
                clear cleanupRefresh
                app.updateUIState();
                return
            end

            labels = strings(height(readableProjects), 1);
            values = strings(height(readableProjects), 1);

            for iProject = 1:height(readableProjects)
                uuid = readableProjects.ProjectUUID(iProject);
                labels(iProject) = sprintf('%s  [%s]', ...
                    readableProjects.ProjectName(iProject), ...
                    app.shortUUID(uuid));
                values(iProject) = uuid;
            end

            app.ProjectDropDown.Items = cellstr(labels);
            app.ProjectDropDown.ItemsData = cellstr(values);

            preferredProjectUUID = char(string(preferredProjectUUID));
            if ~isempty(preferredProjectUUID) && ...
                    any(strcmpi(values, preferredProjectUUID))
                app.ProjectDropDown.Value = preferredProjectUUID;
            else
                app.ProjectDropDown.Value = char(values(1));
            end

            clear cleanupRefresh
            app.loadSelectedProject( ...
                preferredSubjectUUID, preferredSessionUUID);
        end

        function loadSelectedProject(app, preferredSubjectUUID, ...
                preferredSessionUUID)

            if nargin < 2
                preferredSubjectUUID = '';
            end
            if nargin < 3
                preferredSessionUUID = '';
            end

            if strcmp(app.ProjectModeDropDown.Value, 'Create new project')
                app.ProjectStore = [];
                app.ProjectInfo = struct();
                app.refreshSubjects('', '');
                return
            end

            projectUUID = char(string(app.ProjectDropDown.Value));
            if isempty(projectUUID)
                app.ProjectStore = [];
                app.ProjectInfo = struct();
                app.refreshSubjects('', '');
                return
            end

            try
                app.ProjectStore = UMITProjectStore.open(projectUUID);
                app.ProjectInfo = app.ProjectStore.getProjectInfo();
                app.refreshSubjects( ...
                    preferredSubjectUUID, preferredSessionUUID);

                if app.ProjectStore.IsReadOnly
                    app.setStatus( ...
                        'The selected project is invalid and opened read-only.', ...
                        'warning');
                end

            catch ME
                app.ProjectStore = [];
                app.ProjectInfo = struct();
                app.clearDropdown(app.SubjectDropDown, '<No subject>');
                app.clearDropdown(app.SessionDropDown, '<No session>');
                app.updateUIState();
                app.setStatus(ME.message, 'error');
            end
        end

        function refreshSubjects(app, preferredSubjectUUID, ...
                preferredSessionUUID)

            if nargin < 2
                preferredSubjectUUID = '';
            end
            if nargin < 3
                preferredSessionUUID = '';
            end

            app.IsRefreshingUI = true;
            cleanupRefresh = onCleanup(@() app.finishUIRefresh());

            labels = strings(0, 1);
            values = strings(0, 1);

            if strcmp(app.ProjectModeDropDown.Value, 'Use existing project') && ...
                    ~isempty(app.ProjectStore) && ...
                    ~isempty(fieldnames(app.ProjectInfo))

                registry = app.ProjectInfo.subjectRegistry;
                for iSubject = 1:numel(registry)
                    labels(end+1, 1) = app.formatRegistryLabel( ...
                        registry(iSubject)); %#ok<AGROW>
                    values(end+1, 1) = string( ...
                        registry(iSubject).uuid); %#ok<AGROW>
                end
            end

            if ~isempty(fieldnames(app.PendingSubjectInfo))
                labels(end+1, 1) = sprintf('New: %s', ...
                    app.PendingSubjectInfo.subjectID); %#ok<AGROW>
                values(end+1, 1) = string( ...
                    app.PendingSubjectToken); %#ok<AGROW>
            end

            if isempty(values)
                app.clearDropdown(app.SubjectDropDown, '<Create a subject>');
                app.clearDropdown(app.SessionDropDown, '<No session>');
                clear cleanupRefresh
                app.updateUIState();
                return
            end

            app.SubjectDropDown.Items = cellstr(labels);
            app.SubjectDropDown.ItemsData = cellstr(values);

            preferredSubjectUUID = char(string(preferredSubjectUUID));
            if ~isempty(preferredSubjectUUID) && ...
                    any(strcmpi(values, preferredSubjectUUID))
                app.SubjectDropDown.Value = preferredSubjectUUID;
            elseif ~isempty(fieldnames(app.PendingSubjectInfo))
                app.SubjectDropDown.Value = app.PendingSubjectToken;
            else
                app.SubjectDropDown.Value = char(values(1));
            end

            clear cleanupRefresh
            app.refreshSessions(preferredSessionUUID);
        end

        function refreshSessions(app, preferredSessionUUID)
            if nargin < 2
                preferredSessionUUID = '';
            end

            app.IsRefreshingUI = true;
            cleanupRefresh = onCleanup(@() app.finishUIRefresh());

            labels = strings(0, 1);
            values = strings(0, 1);
            selectedSubject = char(string(app.SubjectDropDown.Value));

            % Only the new session staged with "New session" is offered, in
            % Assign and Rebind mode: binding the folder to an existing
            % session would move that session off its own SaveFolder. Moving
            % a session to another folder is done in the Project Manager.

            if ~isempty(fieldnames(app.PendingSessionInfo)) && ...
                    strcmp(app.PendingSessionOwner, selectedSubject)
                labels(end+1, 1) = sprintf('New: %s', ...
                    app.PendingSessionInfo.sessionID); %#ok<AGROW>
                values(end+1, 1) = string( ...
                    app.PendingSessionToken); %#ok<AGROW>
            end

            if isempty(values)
                app.clearDropdown(app.SessionDropDown, '<Create a session>');
                clear cleanupRefresh
                app.updateUIState();
                return
            end

            app.SessionDropDown.Items = cellstr(labels);
            app.SessionDropDown.ItemsData = cellstr(values);

            preferredSessionUUID = char(string(preferredSessionUUID));
            if ~isempty(preferredSessionUUID) && ...
                    any(strcmpi(values, preferredSessionUUID))
                app.SessionDropDown.Value = preferredSessionUUID;
            elseif ~isempty(fieldnames(app.PendingSessionInfo)) && ...
                    strcmp(app.PendingSessionOwner, selectedSubject)
                app.SessionDropDown.Value = app.PendingSessionToken;
            else
                app.SessionDropDown.Value = char(values(1));
            end

            clear cleanupRefresh
            app.updateUIState();
        end

        function updateUIState(app)
            if strcmp(app.BindingState, 'valid')
                app.setSelectionControlsEnabled(false);
                app.BindFolderButton.Text = 'Use Binding';
                app.BindFolderButton.Enable = 'on';
                app.ViewprojectButton.Enable = 'on';
                return
            end

            if strcmp(app.BindingState, 'invalid')
                app.setSelectionControlsEnabled(false);
                app.BindFolderButton.Text = 'Bind Folder';
                app.BindFolderButton.Enable = 'off';
                app.ViewprojectButton.Enable = 'off';
                return
            end

            isExistingMode = strcmp( ...
                app.ProjectModeDropDown.Value, 'Use existing project');
            hasProjectStore = ~isempty(app.ProjectStore);
            isWritableProject = hasProjectStore && ...
                ~app.ProjectStore.IsReadOnly;

            app.ProjectModeDropDown.Enable = 'on';
            app.ProjectDropDown.Enable = app.onOff(isExistingMode && ...
                ~isempty(app.ProjectsTable));
            app.RefreshprojectsButton.Enable = app.onOff(isExistingMode);
            app.NewProjectEditField.Enable = app.onOff(~isExistingMode);

            hasSubject = app.hasDropdownSelection(app.SubjectDropDown);
            hasSession = app.hasDropdownSelection(app.SessionDropDown);

            if isExistingMode
                canModifyProject = isWritableProject;
            else
                canModifyProject = true;
            end

            app.SubjectDropDown.Enable = app.onOff(hasSubject);
            app.NewsubjectButton.Enable = app.onOff(canModifyProject);
            app.SessionDropDown.Enable = app.onOff(hasSession);
            app.NewsessionButton.Enable = app.onOff( ...
                canModifyProject && hasSubject);
            app.ViewprojectButton.Enable = app.onOff(hasProjectStore);

            if isExistingMode
                isReady = isWritableProject && hasSubject && hasSession;
            else
                hasProjectName = ~isempty(strtrim(app.NewProjectEditField.Value));
                hasPendingSubject = strcmp( ...
                    char(string(app.SubjectDropDown.Value)), ...
                    app.PendingSubjectToken);
                hasPendingSession = strcmp( ...
                    char(string(app.SessionDropDown.Value)), ...
                    app.PendingSessionToken);
                isReady = hasProjectName && ...
                    hasPendingSubject && hasPendingSession;
            end

            if strcmp(app.BindingState, 'rebind')
                app.BindFolderButton.Text = 'Rebind Folder';
            else
                app.BindFolderButton.Text = 'Bind Folder';
            end
            app.BindFolderButton.Enable = app.onOff(isReady);

            if isExistingMode && isempty(app.ProjectsTable)
                app.setStatus( ...
                    'No readable projects were found. Create a new project.', ...
                    'warning');
            elseif isExistingMode && hasProjectStore && ...
                    app.ProjectStore.IsReadOnly
                app.setStatus( ...
                    'The selected project is read-only and cannot be modified.', ...
                    'warning');
            elseif isReady && strcmp(app.BindingState, 'rebind')
                app.setStatus( ...
                    'Ready to replace the orphan binding.', 'success');
            elseif isReady
                app.setStatus( ...
                    'Ready to bind the current SaveFolder.', 'success');
            elseif isExistingMode
                app.setStatus( ...
                    'Select or create a subject and session.', 'info');
            else
                app.setStatus( ...
                    ['Enter a project name, then create a subject ' ...
                    'and session.'], 'info');
            end
        end


        function setSelectionControlsEnabled(app, tf)
            state = app.onOff(tf);
            app.ProjectModeDropDown.Enable = state;
            app.ProjectDropDown.Enable = state;
            app.RefreshprojectsButton.Enable = state;
            app.NewProjectEditField.Enable = state;
            app.SubjectDropDown.Enable = state;
            app.NewsubjectButton.Enable = state;
            app.SessionDropDown.Enable = state;
            app.NewsessionButton.Enable = state;
        end

        function clearPendingEntities(app)
            app.PendingSubjectInfo = struct();
            app.PendingSessionInfo = struct();
            app.PendingSessionOwner = '';
        end

        function clearPendingSession(app)
            app.PendingSessionInfo = struct();
            app.PendingSessionOwner = '';
        end

        function selection = resolveBindingSelection(app, isRebind)
            %RESOLVEBINDINGSELECTION Describe the selected binding target. Never writes.
            %
            %   selection = resolveBindingSelection(app, isRebind)
            %
            %   Returns the SELECTION struct of bindFolderToProjectSession:
            %   project (projectUUID, or a struct for a new project), subject
            %   (subjectID, or the staged subject struct), session (sessionID,
            %   or the staged session struct) and replaceOrphanBinding. All
            %   writes happen in bindFolderToProjectSession, all or nothing.

            isNewProject = strcmp(app.ProjectModeDropDown.Value, ...
                'Create new project');
            selectedSubject = char(string(app.SubjectDropDown.Value));
            selectedSession = char(string(app.SessionDropDown.Value));
            isNewSubject = strcmp(selectedSubject, app.PendingSubjectToken);
            isNewSession = strcmp(selectedSession, app.PendingSessionToken);

            selection = struct('project', '', 'subject', '', 'session', '', ...
                'replaceOrphanBinding', logical(isRebind));

            if isNewProject
                projectName = strtrim(app.NewProjectEditField.Value);
                if isempty(projectName)
                    error('Umitoolbox:ProjectBindingManager:missingProjectName', ...
                        'Enter a name for the new project.');
                end
                if ~isNewSubject || ~isNewSession || ...
                        isempty(fieldnames(app.PendingSubjectInfo)) || ...
                        isempty(fieldnames(app.PendingSessionInfo))
                    error('Umitoolbox:ProjectBindingManager:missingContext', ...
                        ['A new project requires a staged subject and ' ...
                        'session before binding.']);
                end
                selection.project = struct('projectName', projectName);
            else
                if isempty(app.ProjectStore)
                    error('Umitoolbox:ProjectBindingManager:noProject', ...
                        'Select a readable project.');
                end
                if app.ProjectStore.IsReadOnly
                    error('Umitoolbox:ProjectBindingManager:readOnlyProject', ...
                        'The selected project is read-only.');
                end
                selection.project = app.ProjectStore.getProjectInfo().projectUUID;
            end

            if isNewSubject
                selection.subject = app.PendingSubjectInfo;
            else
                SubjectInfo = app.ProjectStore.getSubjectInfoByUUID(selectedSubject);
                selection.subject = SubjectInfo.subjectID;
            end

            if ~isNewSession || isempty(fieldnames(app.PendingSessionInfo))
                error('Umitoolbox:ProjectBindingManager:newSessionRequired', ...
                    ['Create a new session for this folder ("New session"). To ' ...
                    'move an existing session to this folder, use the Project ' ...
                    'Manager.']);
            end
            if ~strcmp(app.PendingSessionOwner, selectedSubject)
                error('Umitoolbox:ProjectBindingManager:sessionOwnerMismatch', ...
                    'The new session was created for another subject.');
            end
            selection.session = app.PendingSessionInfo;
        end


        function [tf, message] = validateManagedID(app, idValue, idLabel)
            %#ok<INUSD>
            idValue = char(strtrim(string(idValue)));
            schema = getUMITProjectSchema();
            rules = schema.namingRules;

            tf = false;
            message = '';

            if isempty(idValue) || ...
                    strlength(string(idValue)) > rules.maxIDLength
                message = sprintf('%s must contain 1 to %d characters.', ...
                    idLabel, rules.maxIDLength);
                return
            end

            if isempty(regexp(idValue, rules.idPattern, 'once'))
                message = sprintf([ ...
                    '%s must start with a letter or digit and contain only ' ...
                    'letters, digits, underscores, and hyphens.'], idLabel);
                return
            end

            if any(strcmpi(idValue, rules.reservedNames))
                message = sprintf('%s uses a reserved filesystem name.', ...
                    idLabel);
                return
            end

            for iPrefix = 1:numel(rules.reservedPrefixes)
                if startsWith(idValue, rules.reservedPrefixes{iPrefix}, ...
                        'IgnoreCase', true)
                    message = sprintf('%s uses reserved prefix "%s".', ...
                        idLabel, rules.reservedPrefixes{iPrefix});
                    return
                end
            end

            tf = true;
        end

        function tf = subjectIDExists(app, subjectID)
            tf = false;
            if isempty(fieldnames(app.ProjectInfo)) || ...
                    ~isfield(app.ProjectInfo, 'subjectRegistry')
                return
            end

            registry = app.ProjectInfo.subjectRegistry;
            if ~isempty(registry)
                tf = any(strcmpi({registry.id}, subjectID));
            end
        end

        function tf = sessionIDExists(app, subjectUUID, sessionID)
            tf = false;
            if isempty(app.ProjectStore) || isempty(subjectUUID) || ...
                    strcmp(subjectUUID, app.PendingSubjectToken)
                return
            end

            SubjectInfo = app.ProjectStore.getSubjectInfoByUUID(subjectUUID);
            registry = SubjectInfo.sessionRegistry;
            if ~isempty(registry)
                tf = any(strcmpi({registry.id}, sessionID));
            end
        end

        function label = formatRegistryLabel(app, record)
            %#ok<INUSD>
            recordID = char(string(record.id));
            displayName = char(string(record.displayName));

            if isempty(displayName) || strcmp(displayName, recordID)
                label = string(recordID);
            else
                label = string(sprintf('%s  [%s]', displayName, recordID));
            end
        end

        function output = shortUUID(app, uuid)
            %#ok<INUSD>
            uuid = char(string(uuid));
            output = uuid(1:min(8, numel(uuid)));
        end

        function tf = hasDropdownSelection(app, dropdown)
            %#ok<INUSD>
            value = char(string(dropdown.Value));
            tf = ~isempty(value);
        end

        function clearDropdown(app, dropdown, placeholder)
            %#ok<INUSD>
            dropdown.Items = {placeholder};
            dropdown.ItemsData = {''};
            dropdown.Value = '';
            dropdown.Enable = 'off';
        end

        function setStatus(app, message, severity)
            app.StatusLabel.Text = char(string(message));
            app.StatusLabel.Tooltip = char(string(message));

            switch lower(char(string(severity)))
                case 'success'
                    app.StatusLabel.FontColor = [0.10 0.50 0.20];
                case 'warning'
                    app.StatusLabel.FontColor = [0.85 0.45 0.00];
                case 'error'
                    app.StatusLabel.FontColor = [0.75 0.10 0.10];
                otherwise
                    app.StatusLabel.FontColor = [0.25 0.25 0.25];
            end
        end

        function state = onOff(app, tf)
            %#ok<INUSD>
            if tf
                state = 'on';
            else
                state = 'off';
            end
        end

        function folderPath = normalizeFolderPath(app, folderPath)
            %#ok<INUSD>
            if ~(ischar(folderPath) || ...
                    (isstring(folderPath) && isscalar(folderPath)))
                error('Umitoolbox:ProjectBindingManager:invalidSaveFolder', ...
                    'SaveFolder must be a character vector or string scalar.');
            end

            folderPath = char(string(folderPath));
            if ~isfolder(folderPath)
                error('Umitoolbox:ProjectBindingManager:invalidSaveFolder', ...
                    'SaveFolder does not exist: %s', folderPath);
            end

            [ok, attributes] = fileattrib(folderPath);
            if ok
                folderPath = attributes.Name;
            end
        end

        function centerOnCaller(app)
            if isempty(app.CallerApp) || ...
                    ~isprop(app.CallerApp, 'UIFigure') || ...
                    isempty(app.CallerApp.UIFigure) || ...
                    ~isvalid(app.CallerApp.UIFigure)
                movegui(app.UIFigure, 'center');
                return
            end

            callerPosition = app.CallerApp.UIFigure.Position;
            figurePosition = app.UIFigure.Position;

            figurePosition(1) = callerPosition(1) + ...
                (callerPosition(3) - figurePosition(3)) / 2;
            figurePosition(2) = callerPosition(2) + ...
                (callerPosition(4) - figurePosition(4)) / 2;

            app.UIFigure.Position = figurePosition;
        end

        function finishUIRefresh(app)
            app.IsRefreshingUI = false;
        end

        function finishDialog(app)
            if isempty(app.UIFigure) || ~isvalid(app.UIFigure)
                return
            end

            app.UIFigure.Visible = 'off';
            try
                uiresume(app.UIFigure);
            catch
                % The caller may not have entered uiwait.
            end
        end

        function tf = isSelectionEditable(app)
            %ISSELECTIONEDITABLE True in normal assignment and orphan-rebind modes.

            tf = any(strcmp(app.BindingState, {'unbound', 'rebind'}));
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callerApp, saveFolder, varargin)
            if nargin < 2
                callerApp = [];
            end
            if nargin < 3
                error('Umitoolbox:ProjectBindingManager:missingSaveFolder', ...
                    'A SaveFolder must be supplied by the caller.');
            end
            placeAppInsideCaller(callerApp,app,'center');
            p = inputParser;
            p.FunctionName = 'ProjectBindingManager.startupFcn';
            addParameter(p, 'Mode', 'assign', ...
                @(x) ischar(x) || (isstring(x) && isscalar(x)));
            parse(p, varargin{:});

            mode = lower(strtrim(char(string(p.Results.Mode))));
            if ~ismember(mode, {'assign', 'rebind'})
                error('Umitoolbox:ProjectBindingManager:invalidMode', ...
                    'Mode must be "assign" or "rebind".');
            end

            app.Mode = mode;
            app.CallerApp = callerApp;
            app.SaveFolder = app.normalizeFolderPath(saveFolder);

            app.UIFigure.Name = 'Project Binding Manager';
            app.UIFigure.WindowStyle = 'modal';

            if strcmp(app.Mode, 'rebind')
                app.AssignFoldertoProjectLabel.Text = ...
                    'Reassign Folder to Project';
                app.AssociatethecurrentfoldertoaprojectcontexLabel.Text = ...
                    ['Select a replacement project and subject for the current ' ...
                    'orphan binding, then create a new session.'];
            else
                app.AssignFoldertoProjectLabel.Text = ...
                    'Assign Folder to Project';
                app.AssociatethecurrentfoldertoaprojectcontexLabel.Text = ...
                    'Associate the current SaveFolder with a project context.';
            end

            app.CurrentsavefolderLabel.Text = sprintf( ...
                'SaveFolder: %s', app.SaveFolder);
            app.CurrentsavefolderLabel.Tooltip = app.SaveFolder;

            app.ProjectModeDropDown.Items = { ...
                'Use existing project', ...
                'Create new project'};
            app.ProjectModeDropDown.Value = 'Use existing project';

            app.NewProjectEditField.Value = '';
            app.NewProjectEditField.Placeholder = 'New project name';

            app.OutputProjectStore = [];
            app.OutputContext = struct();
            app.OutputProjectBinding = struct();
            app.WasBindingConfirmed = false;

            app.centerOnCaller();
            app.inspectCurrentBinding();
            initializeUmitTheme(app.UIFigure);
        end

        % Value changed function: ProjectModeDropDown
        function ProjectModeDropDownValueChanged(app, event)

            if app.IsRefreshingUI || ...
                    ~app.isSelectionEditable()
                return
            end

            app.clearPendingEntities();

            if strcmp(app.ProjectModeDropDown.Value, 'Create new project')
                app.ProjectStore = [];
                app.ProjectInfo = struct();
                app.NewProjectEditField.Value = '';
                app.clearDropdown(app.SubjectDropDown, '<Create a subject>');
                app.clearDropdown(app.SessionDropDown, '<Create a session>');
                app.updateUIState();
            else
                app.refreshProjects('', '', '');
            end

        end

        % Button pushed function: RefreshprojectsButton
        function RefreshprojectsButtonPushed(app, event)
            %#ok<INUSD>
            if ~app.isSelectionEditable()
                return
            end

            currentProjectUUID = char(string(app.ProjectDropDown.Value));
            app.clearPendingEntities();
            app.refreshProjects(currentProjectUUID, '', '');

        end

        % Value changed function: ProjectDropDown
        function ProjectDropDownValueChanged(app, event)
            %#ok<INUSD>
            if app.IsRefreshingUI || ...
                    ~app.isSelectionEditable()
                return
            end

            app.clearPendingEntities();
            app.loadSelectedProject('', '');

        end

        % Value changed function: NewProjectEditField
        function NewProjectEditFieldValueChanged(app, event)
            %#ok<INUSD>
            if app.IsRefreshingUI || ...
                    ~app.isSelectionEditable()
                return
            end

            app.NewProjectEditField.Value = strtrim(app.NewProjectEditField.Value);
            app.updateUIState();

        end

        % Value changed function: SubjectDropDown
        function SubjectDropDownValueChanged(app, event)
            %#ok<INUSD>
            if app.IsRefreshingUI || ...
                    ~app.isSelectionEditable()
                return
            end

            selectedSubject = char(string(app.SubjectDropDown.Value));
            if ~isempty(app.PendingSessionOwner) && ...
                    ~strcmp(app.PendingSessionOwner, selectedSubject)
                app.clearPendingSession();
            end

            app.refreshSessions('');

        end

        % Button pushed function: NewsubjectButton
        function NewsubjectButtonPushed(app, event)
            %#ok<INUSD>
            if ~app.isSelectionEditable()
                return
            end

            answer = inputdlg( ...
                {'Subject ID:', 'Display name (optional):', ...
                'Description (optional):'}, ...
                'New Subject', ...
                [1 55; 1 55; 3 55], ...
                {'', '', ''});

            if isempty(answer)
                return
            end

            subjectID = strtrim(answer{1});
            displayName = strtrim(answer{2});
            description = strtrim(answer{3});

            [isValid, message] = app.validateManagedID( ...
                subjectID, 'Subject ID');
            if ~isValid
                uialert(app.UIFigure, message, ...
                    'Invalid Subject ID', 'Icon', 'error');
                return
            end

            if strcmp(app.ProjectModeDropDown.Value, 'Use existing project') && ...
                    app.subjectIDExists(subjectID)
                uialert(app.UIFigure, sprintf( ...
                    'Subject ID "%s" already exists in this project.', ...
                    subjectID), ...
                    'Duplicate Subject ID', 'Icon', 'error');
                return
            end

            if isempty(displayName)
                displayName = subjectID;
            end

            app.PendingSubjectInfo = struct( ...
                'subjectID', subjectID, ...
                'displayName', displayName, ...
                'description', description);

            app.clearPendingSession();
            app.refreshSubjects(app.PendingSubjectToken, '');

        end

        % Value changed function: SessionDropDown
        function SessionDropDownValueChanged(app, event)
            %#ok<INUSD>
            if app.IsRefreshingUI || ...
                    ~app.isSelectionEditable()
                return
            end

            app.updateUIState();

        end

        % Button pushed function: NewsessionButton
        function NewsessionButtonPushed(app, event)
            %#ok<INUSD>
            if ~app.isSelectionEditable()
                return
            end

            selectedSubject = char(string(app.SubjectDropDown.Value));
            if isempty(selectedSubject)
                uialert(app.UIFigure, ...
                    'Select or create a subject first.', ...
                    'Subject Required', 'Icon', 'warning');
                return
            end

            if strcmp(selectedSubject, app.PendingSubjectToken)
                subjectIDForDialog = app.PendingSubjectInfo.subjectID;
            else
                try
                    subjectIDForDialog = app.ProjectStore.getSubjectInfoByUUID( ...
                        selectedSubject).subjectID;
                catch
                    subjectIDForDialog = selectedSubject;
                end
            end

            sessionInfo = promptAddSessionDialog(app.UIFigure, ...
                struct('subjectID', subjectIDForDialog), app.SaveFolder);
            if isempty(sessionInfo)
                return
            end

            sessionInfo.processedDataFolder = app.SaveFolder;
            app.PendingSessionInfo = sessionInfo;
            app.PendingSessionOwner = selectedSubject;

            app.refreshSessions(app.PendingSessionToken);


        end

        % Button pushed function: ViewprojectButton
        function ViewprojectButtonPushed(app, event)
            %#ok<INUSD>
            if isempty(app.ProjectStore)
                return
            end

            try
                ProjectInfo = app.ProjectStore.getProjectInfo();
                subjectCount = numel(ProjectInfo.subjectRegistry);

                message = sprintf([ ...
                    'Project: %s\n' ...
                    'UUID: %s\n' ...
                    'Subjects: %d\n' ...
                    'Location: %s'], ...
                    ProjectInfo.projectName, ...
                    ProjectInfo.projectUUID, ...
                    subjectCount, ...
                    app.ProjectStore.ProjectRoot);

                uialert(app.UIFigure, message, ...
                    'Project Information', 'Icon', 'info');
            catch ME
                uialert(app.UIFigure, ME.message, ...
                    'Could Not Read Project', 'Icon', 'error');
            end

        end

        % Button pushed function: BindFolderButton
        function BindFolderButtonPushed(app, event)
            %#ok<INUSD>
            if strcmp(app.BindingState, 'valid')
                app.WasBindingConfirmed = true;
                app.finishDialog();
                return
            end

            if ~app.isSelectionEditable()
                return
            end

            isRebind = strcmp(app.BindingState, 'rebind');
            ledgerState = app.currentBindingState();

            if ~isRebind && ~strcmp(ledgerState, 'unbound')
                % Bound from elsewhere since the dialog opened.
                app.inspectCurrentBinding();
                return
            end

            if isRebind
                if strcmp(ledgerState, 'unbound')
                    app.BindingState = 'unbound';
                    app.updateUIState();
                    app.setStatus( ...
                        ['The orphan binding was removed externally. The folder can ' ...
                        'now be assigned normally.'], ...
                        'warning');
                    return
                end
                try
                    isStillOrphan = UMITProjectStore.isOrphanProjectBinding( ...
                        app.SaveFolder);
                catch
                    isStillOrphan = false;
                end
                if ~isStillOrphan
                    % The project came back, or the link changed: inspect again.
                    app.inspectCurrentBinding();
                    return
                end
            end

            app.BindFolderButton.Enable = 'off';
            app.CancelButton.Enable = 'off';

            if isRebind
                app.setStatus('Replacing orphan project binding...', 'info');
            else
                app.setStatus('Creating project binding...', 'info');
            end
            drawnow

            try
                selection = app.resolveBindingSelection(isRebind);
                [context, store] = bindFolderToProjectSession( ...
                    app.SaveFolder, selection);

                app.OutputProjectStore = store;
                app.OutputContext = context;
                app.OutputProjectBinding = ...
                    UMITProjectStore.readProjectBinding(app.SaveFolder);
                app.WasBindingConfirmed = true;
                app.BindingState = 'valid';

                app.setStatus(sprintf( ...
                    'Bound to %s / %s / %s', ...
                    context.projectName, ...
                    context.subjectID, ...
                    context.sessionID), 'success');
                drawnow

                app.finishDialog();

            catch ME
                app.WasBindingConfirmed = false;
                app.CancelButton.Enable = 'on';
                app.updateUIState();

                lockStore = [];
                if strcmp(app.ProjectModeDropDown.Value, 'Use existing project')
                    lockStore = app.ProjectStore;
                end
                [~, lockMessage, isLockError] = offerStaleLockClearing( ...
                    app.UIFigure, ME, 'Folder', app.SaveFolder, 'Store', lockStore);
                if isLockError
                    app.setStatus(lockMessage, 'warning');
                else
                    app.setStatus('Project binding failed.', 'error');
                    uialert(app.UIFigure, ME.message, ...
                        'Could Not Bind Folder', 'Icon', 'error');
                end
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            %#ok<INUSD>
            app.WasBindingConfirmed = false;
            app.OutputProjectStore = [];
            app.OutputContext = struct();
            app.OutputProjectBinding = struct();
            app.finishDialog();

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %#ok<INUSD>
            app.WasBindingConfirmed = false;
            app.OutputProjectStore = [];
            app.OutputContext = struct();
            app.OutputProjectBinding = struct();
            app.finishDialog();

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Project Binding Manager';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AssignFoldertoProjectLabel = uilabel(app.GridLayout);
            app.AssignFoldertoProjectLabel.Text = 'Assign Folder to Project';
            app.AssociatethecurrentfoldertoaprojectcontexLabel = uilabel(app.GridLayout);
            app.AssociatethecurrentfoldertoaprojectcontexLabel.Text = 'Associate the current folder to a project contex. ';
            app.CurrentsavefolderLabel = uilabel(app.GridLayout);
            app.CurrentsavefolderLabel.Text = 'Current save folder';
            app.ProjectContextPanel = uipanel(app.GridLayout);
            app.ProjectContextPanel.Title = 'Project Context';
            app.ProjectContextGridLayout = uigridlayout(app.ProjectContextPanel);
            app.ProjectmodeLabel = uilabel(app.ProjectContextGridLayout);
            app.ProjectmodeLabel.Text = 'Project mode:';
            app.ProjectModeDropDown = uidropdown(app.ProjectContextGridLayout);
            app.ProjectModeDropDown.Items = {'Use existing project', 'Create new project'};
            app.ProjectModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectModeDropDownValueChanged, true);
            app.ProjectModeDropDown.Value = 'Use existing project';
            app.RefreshprojectsButton = uibutton(app.ProjectContextGridLayout, 'push');
            app.RefreshprojectsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshprojectsButtonPushed, true);
            app.RefreshprojectsButton.Text = 'Refresh projects';
            app.ProjectLabel = uilabel(app.ProjectContextGridLayout);
            app.ProjectLabel.Text = 'Project:';
            app.ProjectDropDown = uidropdown(app.ProjectContextGridLayout);
            app.ProjectDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectDropDownValueChanged, true);
            app.SubjectLabel = uilabel(app.ProjectContextGridLayout);
            app.SubjectLabel.Text = 'Subject:';
            app.SubjectDropDown = uidropdown(app.ProjectContextGridLayout);
            app.SubjectDropDown.ValueChangedFcn = createCallbackFcn(app, @SubjectDropDownValueChanged, true);
            app.NewsubjectButton = uibutton(app.ProjectContextGridLayout, 'push');
            app.NewsubjectButton.ButtonPushedFcn = createCallbackFcn(app, @NewsubjectButtonPushed, true);
            app.NewsubjectButton.Text = 'New subject';
            app.SessionLabel = uilabel(app.ProjectContextGridLayout);
            app.SessionLabel.Text = 'Session';
            app.SessionDropDown = uidropdown(app.ProjectContextGridLayout);
            app.SessionDropDown.ValueChangedFcn = createCallbackFcn(app, @SessionDropDownValueChanged, true);
            app.NewsessionButton = uibutton(app.ProjectContextGridLayout, 'push');
            app.NewsessionButton.ButtonPushedFcn = createCallbackFcn(app, @NewsessionButtonPushed, true);
            app.NewsessionButton.Text = 'New session';
            app.NewProjectEditField = uieditfield(app.ProjectContextGridLayout, 'text');
            app.NewProjectEditField.ValueChangedFcn = createCallbackFcn(app, @NewProjectEditFieldValueChanged, true);
            app.NewProjectEditField.Placeholder = 'New project name';
            app.BottomGrid = uigridlayout(app.GridLayout);
            app.ViewprojectButton = uibutton(app.BottomGrid, 'push');
            app.ViewprojectButton.ButtonPushedFcn = createCallbackFcn(app, @ViewprojectButtonPushed, true);
            app.ViewprojectButton.Enable = 'off';
            app.ViewprojectButton.Text = 'View project';
            app.CancelButton = uibutton(app.BottomGrid, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.BindFolderButton = uibutton(app.BottomGrid, 'push');
            app.BindFolderButton.ButtonPushedFcn = createCallbackFcn(app, @BindFolderButtonPushed, true);
            app.BindFolderButton.Text = 'Bind Folder';
            app.StatusLabel = uilabel(app.GridLayout);
            app.StatusLabel.Text = 'Status';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectBindingManager(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end