classdef ProjectManagerTool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayoutBottom                matlab.ui.container.GridLayout
        SelectedItemPanel               matlab.ui.container.Panel
        SelectedItemGrid                matlab.ui.container.GridLayout
        MetadataTabGroup                matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        SelectedItemBottomGrid          matlab.ui.container.GridLayout
        OpenResourceLocationButton      matlab.ui.control.Button
        RestoreResourceButton           matlab.ui.control.Button
        ArchiveResourceButton           matlab.ui.control.Button
        SetActiveResourceButton         matlab.ui.control.Button
        MoveSessionButton               matlab.ui.control.Button
        BindProcessedFolderButton       matlab.ui.control.Button
        BindRawFolderButton             matlab.ui.control.Button
        AddRegistrationTransformButton  matlab.ui.control.Button
        AddSubjectButton                matlab.ui.control.Button
        AddImageReferenceButton         matlab.ui.control.Button
        AddSessionButton                matlab.ui.control.Button
        RevertButton                    matlab.ui.control.Button
        SaveChangesButton               matlab.ui.control.Button
        ItemHeaderLabel                 matlab.ui.control.Label
        ProjectStructurePanel           matlab.ui.container.Panel
        ProjectStructureGrid            matlab.ui.container.GridLayout
        ProjectTree                     matlab.ui.container.Tree
        GridLayoutTop                   matlab.ui.container.GridLayout
        StatusLabel                     matlab.ui.control.Label
        ValidationModeDropDown          matlab.ui.control.DropDown
        ValidateButton                  matlab.ui.control.Button
        RefreshButton                   matlab.ui.control.Button
        NewProjectButton                matlab.ui.control.Button
        OpenProjectButton               matlab.ui.control.Button
    end

    properties (Access = private)
        Store = []
        NodeMap
        CurrentNodeKey = ''
        Editor = struct()
        IsDirty = false
        IsSelectionGuard = false
        IsClosing = false
    end

    methods (Access = private)
        function initializeUI(app)
            app.UIFigure.Name = 'UMIT Project Manager';
            app.ValidationModeDropDown.Items = {'Quick', 'Full'};
            app.ValidationModeDropDown.Value = 'Quick';
            app.SelectedItemBottomGrid.ColumnWidth = ...
                {'1x', '1x', '1x'};
            app.SelectedItemBottomGrid.RowHeight = {28, 28};
            app.hideAllActionButtons();
            app.clearMetadataTabs();
            app.ItemHeaderLabel.Text = 'No item selected';
            app.setToolbarEnabled(false);
            app.setStatus('Ready.', 'neutral');
        end

        function store = resolveStore(app, storeOrProjectUUID)
            if isempty(storeOrProjectUUID)
                store = [];
            elseif isa(storeOrProjectUUID, 'UMITProjectStore')
                store = storeOrProjectUUID;
            elseif ischar(storeOrProjectUUID) || ...
                    (isstring(storeOrProjectUUID) && ...
                    isscalar(storeOrProjectUUID))
                store = UMITProjectStore.open( ...
                    char(string(storeOrProjectUUID)));
            else
                error( ...
                    'Umitoolbox:ProjectManagerTool:invalidStartupInput', ...
                    ['Input must be a UMITProjectStore object, a project ' ...
                    'UUID text scalar, or empty.']);
            end
        end

        function store = chooseProjectStore(app)
            store = [];
            projects = UMITProjectStore.listProjects();
            readableProjects = projects(projects.IsReadable, :);

            if isempty(readableProjects)
                uialert(app.UIFigure, ...
                    'No readable UMIT projects were found.', ...
                    'Open Project');
                return
            end

            labels = strings(height(readableProjects), 1);
            for iProject = 1:height(readableProjects)
                labels(iProject) = sprintf('%s  |  %s', ...
                    char(readableProjects.ProjectName(iProject)), ...
                    char(readableProjects.ProjectUUID(iProject)));
            end

            [selection, ok] = listdlg( ...
                'PromptString', 'Select a UMIT project:', ...
                'SelectionMode', 'single', ...
                'ListString', cellstr(labels), ...
                'ListSize', [620, 300]);
            if ok
                store = UMITProjectStore.open( ...
                    char(readableProjects.ProjectUUID(selection)));
            end
        end

        function setStore(app, store)
            app.Store = store;
            app.Editor = struct();
            app.CurrentNodeKey = '';
            app.IsDirty = false;
            app.setToolbarEnabled(true);
            app.refreshTree('');
        end

        function showEmptyState(app, message)
            app.Store = [];
            app.NodeMap = containers.Map( ...
                'KeyType', 'char', 'ValueType', 'any');
            app.CurrentNodeKey = '';
            app.Editor = struct();
            app.IsDirty = false;
            delete(app.ProjectTree.Children);
            app.clearMetadataTabs();
            app.hideAllActionButtons();
            app.ItemHeaderLabel.Text = 'No item selected';
            app.setToolbarEnabled(false);
            app.setStatus(message, 'info');
        end

        function setToolbarEnabled(app, hasStore)
            app.RefreshButton.Enable = app.onOff(hasStore);
            app.ValidateButton.Enable = app.onOff(hasStore);
            app.ValidationModeDropDown.Enable = app.onOff(hasStore);
        end

        function onOpenProject(app)
            if ~app.guardPendingEdits('opening another project')
                return
            end

            try
                store = app.chooseProjectStore();
                if ~isempty(store)
                    app.setStore(store);
                end
            catch ME
                app.showException(ME, 'Open Project Failed');
            end
        end

        function onNewProject(app)
            if ~app.guardPendingEdits('creating another project')
                return
            end

            answer = inputdlg( ...
                {'Project name:', 'Description:'}, ...
                'Create UMIT Project', ...
                [1, 70; 4, 70], ...
                {'', ''});
            if isempty(answer)
                return
            end

            projectName = strtrim(answer{1});
            if isempty(projectName)
                uialert(app.UIFigure, ...
                    'Project name cannot be empty.', ...
                    'Invalid Project Name');
                return
            end

            try
                app.setStatus('Creating project...', 'busy');
                store = UMITProjectStore.create(struct( ...
                    'projectName', projectName, ...
                    'description', answer{2}));
                app.setStore(store);
                app.setStatus('Project created successfully.', 'success');
            catch ME
                app.showException(ME, 'Project Creation Failed');
            end
        end

        function onRefresh(app)
            if isempty(app.Store) || ...
                    ~app.guardPendingEdits('refreshing the project')
                return
            end
            app.refreshTree(app.CurrentNodeKey);
        end

        function refreshTree(app, requestedKey)
            if isempty(app.Store)
                app.showEmptyState('No project is open.');
                return
            end

            app.setStatus('Refreshing project...', 'busy');
            delete(app.ProjectTree.Children);
            nodeMap = containers.Map( ...
                'KeyType', 'char', 'ValueType', 'any');

            try
                projectInfo = app.Store.getProjectInfo();
                projectData = app.nodeData( ...
                    'project', projectInfo.projectUUID, '', '', '');
                projectNode = uitreenode(app.ProjectTree, ...
                    'Text', sprintf('Project: %s', ...
                    projectInfo.projectName), ...
                    'NodeData', projectData);
                nodeMap(projectData.key) = projectNode;

                for iSubject = 1:numel(projectInfo.subjectRegistry)
                    subjectRecord = ...
                        projectInfo.subjectRegistry(iSubject);
                    try
                        subjectInfo = app.Store.getSubjectInfoByUUID( ...
                            subjectRecord.uuid);
                        subjectData = app.nodeData( ...
                            'subject', subjectInfo.uuid, ...
                            subjectInfo.uuid, '', '');
                        subjectNode = uitreenode(projectNode, ...
                            'Text', app.displayNodeText( ...
                            'Subject', subjectInfo.displayName, ...
                            subjectInfo.subjectID), ...
                            'NodeData', subjectData);
                        nodeMap(subjectData.key) = subjectNode;

                        subjectResources = ...
                            app.Store.listSubjectResources( ...
                            subjectInfo.subjectID, ...
                            'Status', ...
                            {'active', 'available', 'archived'});
                        app.addResourceNodes( ...
                            subjectNode, nodeMap, subjectResources, ...
                            subjectInfo.uuid, '');

                        for iSession = ...
                                1:numel(subjectInfo.sessionRegistry)
                            sessionRecord = ...
                                subjectInfo.sessionRegistry(iSession);
                            try
                                sessionInfo = ...
                                    app.Store.getSessionInfoByUUID( ...
                                    sessionRecord.uuid);
                                sessionData = app.nodeData( ...
                                    'session', sessionInfo.uuid, ...
                                    subjectInfo.uuid, ...
                                    sessionInfo.uuid, '');
                                sessionNode = uitreenode(subjectNode, ...
                                    'Text', app.displayNodeText( ...
                                    'Session', ...
                                    sessionInfo.displayName, ...
                                    sessionInfo.sessionID), ...
                                    'NodeData', sessionData);
                                nodeMap(sessionData.key) = sessionNode;

                                sessionResources = ...
                                    app.Store.listSessionResources( ...
                                    subjectInfo.subjectID, ...
                                    sessionInfo.sessionID, ...
                                    'Status', ...
                                    {'active', 'available', ...
                                    'archived'});
                                app.addResourceNodes( ...
                                    sessionNode, nodeMap, ...
                                    sessionResources, ...
                                    subjectInfo.uuid, ...
                                    sessionInfo.uuid);
                            catch ME
                                app.addErrorNode( ...
                                    subjectNode, nodeMap, ...
                                    sessionRecord.uuid, ...
                                    subjectInfo.uuid, ...
                                    sessionRecord.uuid, ...
                                    sprintf('Session error: %s', ...
                                    sessionRecord.id), ME.message);
                            end
                        end
                        expand(subjectNode);
                    catch ME
                        app.addErrorNode( ...
                            projectNode, nodeMap, ...
                            subjectRecord.uuid, ...
                            subjectRecord.uuid, '', ...
                            sprintf('Subject error: %s', ...
                            subjectRecord.id), ME.message);
                    end
                end

                expand(projectNode);
                app.NodeMap = nodeMap;

                if nargin < 2 || isempty(requestedKey) || ...
                        ~isKey(nodeMap, requestedKey)
                    selectedNode = projectNode;
                else
                    selectedNode = nodeMap(requestedKey);
                end

                app.selectNodeWithoutCallback(selectedNode);
                app.renderSelectedNode(selectedNode);
                app.updateToolbarStatus();
            catch ME
                app.NodeMap = nodeMap;
                app.showException(ME, 'Project Refresh Failed');
            end
        end

        function addResourceNodes(app, parentNode, nodeMap, resources, ...
                subjectUUID, sessionUUID)
            for iResource = 1:numel(resources)
                resource = resources(iResource);
                resourceData = app.nodeData( ...
                    'resource', resource.uuid, subjectUUID, ...
                    sessionUUID, resource.uuid);
                resourceNode = uitreenode(parentNode, ...
                    'Text', app.resourceNodeText(resource), ...
                    'NodeData', resourceData);
                nodeMap(resourceData.key) = resourceNode;
            end
        end

        function addErrorNode(app, parentNode, nodeMap, uuid, ...
                subjectUUID, sessionUUID, label, message)
            errorData = app.nodeData( ...
                'error', uuid, subjectUUID, sessionUUID, '');
            errorData.message = message;
            errorNode = uitreenode(parentNode, ...
                'Text', label, 'NodeData', errorData);
            nodeMap(errorData.key) = errorNode; %#ok<NASGU>
        end

        function onTreeSelectionChanged(app, event)
            if app.IsSelectionGuard || isempty(event.SelectedNodes)
                return
            end

            selectedNode = event.SelectedNodes(1);
            selectedKey = selectedNode.NodeData.key;
            if strcmp(selectedKey, app.CurrentNodeKey)
                return
            end

            previousKey = app.CurrentNodeKey;
            [proceed, didSave] = app.resolvePendingEdits( ...
                'changing the selection');
            if ~proceed
                if ~isempty(previousKey) && ...
                        isKey(app.NodeMap, previousKey)
                    app.selectNodeWithoutCallback( ...
                        app.NodeMap(previousKey));
                end
                return
            end

            if didSave
                app.refreshTree(selectedKey);
            else
                app.renderSelectedNode(selectedNode);
            end
        end

        function selectNodeWithoutCallback(app, node)
            app.IsSelectionGuard = true;
            cleanup = onCleanup(@() app.clearSelectionGuard());
            app.ProjectTree.SelectedNodes = node;
            clear cleanup
        end

        function clearSelectionGuard(app)
            app.IsSelectionGuard = false;
        end

        function renderSelectedNode(app, node)
            nodeData = node.NodeData;

            try
                switch nodeData.kind
                    case 'project'
                        metadata = app.Store.getProjectInfo();
                        metadata.projectRoot = app.Store.ProjectRoot;
                        metadata.isReadOnly = app.Store.IsReadOnly;
                        report = app.Store.LastValidationReport;
                        if isstruct(report) && ...
                                isfield(report, 'isValid')
                            metadata.validationIsValid = report.isValid;
                        end
                        titleText = sprintf('Project: %s', ...
                            metadata.projectName);

                    case 'subject'
                        [metadata, record] = ...
                            app.Store.getSubjectInfoByUUID( ...
                            nodeData.subjectUUID);
                        metadata.relativePath = record.relativePath;
                        titleText = app.displayNodeText( ...
                            'Subject', metadata.displayName, ...
                            metadata.subjectID);

                    case 'session'
                        [metadata, subjectInfo, record] = ...
                            app.Store.getSessionInfoByUUID( ...
                            nodeData.sessionUUID);
                        metadata.parentSubjectDisplayName = ...
                            subjectInfo.displayName;
                        metadata.relativePath = record.relativePath;
                        titleText = app.displayNodeText( ...
                            'Session', metadata.displayName, ...
                            metadata.sessionID);

                    case 'resource'
                        metadata = app.Store.getResource( ...
                            nodeData.resourceUUID);
                        titleText = sprintf('Resource: %s', ...
                            metadata.displayName);

                    case 'error'
                        metadata = struct('message', nodeData.message);
                        titleText = 'Unavailable Item';

                    otherwise
                        error( ...
                            'Umitoolbox:ProjectManagerTool:invalidNode', ...
                            'Unsupported tree node kind: %s', ...
                            nodeData.kind);
                end

                editor = app.renderMetadataEditor( ...
                    nodeData.kind, metadata);
                editor.NodeData = nodeData;
                editor.Metadata = metadata;
                app.Editor = editor;
                app.CurrentNodeKey = nodeData.key;
                app.IsDirty = false;

                app.ItemHeaderLabel.Text = titleText;
                app.configureActionButtons(nodeData.kind, metadata);
            catch ME
                app.showException(ME, 'Metadata Display Failed');
            end
        end

        function editor = renderMetadataEditor(app, kind, metadata)
            app.clearMetadataTabs();

            detailTab = uitab( ...
                app.MetadataTabGroup, 'Title', 'Details');
            fields = fieldnames(metadata);
            structured = false(size(fields));
            for iField = 1:numel(fields)
                structured(iField) = app.requiresStructuredView( ...
                    metadata.(fields{iField}));
            end

            scalarFields = app.orderMetadataFields( ...
                kind, fields(~structured));
            nRows = max(numel(scalarFields), 1);
            rowHeights = repmat({28}, 1, nRows);
            descriptionIndex = find( ...
                strcmp(scalarFields, 'description'), 1);
            if ~isempty(descriptionIndex)
                rowHeights{descriptionIndex} = 90;
            end

            grid = uigridlayout(detailTab, [nRows, 2]);
            grid.ColumnWidth = {210, '1x'};
            grid.RowHeight = rowHeights;
            grid.Padding = [10, 10, 10, 10];
            grid.RowSpacing = 6;
            grid.ColumnSpacing = 8;

            controls = struct();
            if isempty(scalarFields)
                label = uilabel(grid, ...
                    'Text', 'No scalar metadata fields are available.');
                label.Layout.Row = 1;
                label.Layout.Column = [1, 2];
            else
                for iField = 1:numel(scalarFields)
                    fieldName = scalarFields{iField};
                    editable = ~app.Store.IsReadOnly && ...
                        app.isEditableField(kind, fieldName);

                    label = uilabel(grid, ...
                        'Text', app.humanizeField(fieldName), ...
                        'HorizontalAlignment', 'right');
                    label.Layout.Row = iField;
                    label.Layout.Column = 1;

                    control = app.createMetadataControl( ...
                        grid, fieldName, ...
                        metadata.(fieldName), editable);
                    control.Layout.Row = iField;
                    control.Layout.Column = 2;
                    controls.(fieldName) = control;
                end
            end

            structuredFields = fields(structured);
            for iField = 1:numel(structuredFields)
                fieldName = structuredFields{iField};
                tab = uitab(app.MetadataTabGroup, ...
                    'Title', app.humanizeField(fieldName));
                tableGrid = uigridlayout(tab, [1, 1]);
                tableGrid.Padding = [8, 8, 8, 8];
                uitable(tableGrid, ...
                    'Data', app.tabularizeMetadata( ...
                    metadata.(fieldName)), ...
                    'ColumnSortable', true);
            end

            editor = struct( ...
                'Kind', kind, ...
                'Controls', controls);
        end

        function control = createMetadataControl(app, parent, ...
                fieldName, value, editable)
            if strcmp(fieldName, 'description')
                control = uitextarea(parent, ...
                    'Value', app.textAreaValue(value), ...
                    'Editable', app.onOff(editable));
                app.assignDirtyCallback(control, editable);
                return
            end

            if strcmp(fieldName, 'rigID') && editable
                control = app.createRigDropDown(parent, value);
                app.assignDirtyCallback(control, true);
                return
            end

            if isdatetime(value) && isscalar(value)
                if editable && strcmp(fieldName, 'acquisitionDate')
                    control = uidatepicker(parent, ...
                        'Value', value, ...
                        'DisplayFormat', 'yyyy-MM-dd');
                else
                    control = uieditfield(parent, 'text', ...
                        'Value', app.formatDatetime(value), ...
                        'Editable', 'off');
                end
                app.assignDirtyCallback(control, editable);
                return
            end

            if islogical(value) && isscalar(value)
                control = uicheckbox(parent, ...
                    'Text', '', ...
                    'Value', value, ...
                    'Enable', app.onOff(editable));
                app.assignDirtyCallback(control, editable);
                return
            end

            if isnumeric(value) && isscalar(value)
                control = uieditfield(parent, 'numeric', ...
                    'Value', double(value), ...
                    'Editable', app.onOff(editable));
                app.assignDirtyCallback(control, editable);
                return
            end

            if app.isCategoricalField(fieldName)
                currentValue = char(string(value));
                items = {currentValue};
                if strcmp(fieldName, 'status') && ...
                        isfield(app.Store.Schema, 'resourceStates')
                    items = unique([{currentValue}, ...
                        app.Store.Schema.resourceStates(:).'], ...
                        'stable');
                end
                control = uidropdown(parent, ...
                    'Items', items, ...
                    'Value', currentValue, ...
                    'Enable', app.onOff(editable));
                app.assignDirtyCallback(control, editable);
                return
            end

            textValue = app.scalarText(value);
            control = uieditfield(parent, 'text', ...
                'Value', textValue, ...
                'Editable', app.onOff(editable), ...
                'Tooltip', textValue);
            app.assignDirtyCallback(control, editable);
        end

        function control = createRigDropDown(app, parent, currentRigID)
            currentRigID = char(string(currentRigID));
            ids = {''};
            labels = {'(No rig)'};

            try
                rigs = UMITRigStore.listRigs();
                rigs = rigs(rigs.IsReadable, :);
                for iRig = 1:height(rigs)
                    rigID = char(rigs.RigID(iRig));
                    displayName = char(rigs.DisplayName(iRig));
                    status = char(rigs.Status(iRig));
                    if isempty(displayName) || strcmp(displayName, rigID)
                        label = rigID;
                    else
                        label = sprintf('%s (%s)', displayName, rigID);
                    end
                    if strcmp(status, 'archived')
                        label = sprintf('%s [archived]', label);
                    end
                    ids{end+1} = rigID; %#ok<AGROW>
                    labels{end+1} = label; %#ok<AGROW>
                end
            catch
                % Preserve the current value if rig discovery is unavailable.
            end

            if ~isempty(currentRigID) && ...
                    ~any(strcmp(ids, currentRigID))
                ids{end+1} = currentRigID;
                labels{end+1} = sprintf('%s [unavailable]', currentRigID);
            end

            control = uidropdown(parent, ...
                'Items', labels, ...
                'ItemsData', ids, ...
                'Value', currentRigID);
        end

        function assignDirtyCallback(app, control, editable)
            if editable && isprop(control, 'ValueChangedFcn')
                control.ValueChangedFcn = ...
                    @(~, ~) app.markDirty();
            end
        end

        function markDirty(app)
            app.IsDirty = true;
            if ~isempty(app.Store) && ~app.Store.IsReadOnly
                app.SaveChangesButton.Enable = 'on';
            end
            app.setStatus('Unsaved changes.', 'warning');
        end

        function configureActionButtons(app, kind, metadata)
            app.hideAllActionButtons();
            if isempty(app.Store)
                return
            end

            canWrite = ~app.Store.IsReadOnly;
            buttons = {};

            if any(strcmp(kind, {'subject', 'session', 'resource'}))
                app.SaveChangesButton.Enable = 'off';
                app.RevertButton.Enable = 'on';
                buttons = { ...
                    app.SaveChangesButton, ...
                    app.RevertButton};
            end

            switch kind
                case 'project'
                    buttons{end+1} = app.AddSubjectButton;

                case 'subject'
                    buttons{end+1} = app.AddSessionButton;
                    buttons{end+1} = ...
                        app.AddImageReferenceButton;

                case 'session'
                    buttons{end+1} = ...
                        app.AddRegistrationTransformButton;
                    buttons{end+1} = app.BindRawFolderButton;
                    buttons{end+1} = ...
                        app.BindProcessedFolderButton;
                    buttons{end+1} = app.MoveSessionButton;

                    if isempty(metadata.rawDataFolder)
                        app.BindRawFolderButton.Text = ...
                            'Bind Raw Folder';
                    else
                        app.BindRawFolderButton.Text = ...
                            'Unbind Raw Folder';
                    end
                    if isempty(metadata.processedDataFolder)
                        app.BindProcessedFolderButton.Text = ...
                            'Bind Processed Folder';
                    else
                        app.BindProcessedFolderButton.Text = ...
                            'Unbind Processed Folder';
                    end

                case 'resource'
                    if strcmp(metadata.status, 'archived')
                        buttons{end+1} = ...
                            app.RestoreResourceButton;
                    else
                        buttons{end+1} = ...
                            app.SetActiveResourceButton;
                        buttons{end+1} = ...
                            app.ArchiveResourceButton;
                        app.SetActiveResourceButton.Enable = ...
                            app.onOff(canWrite && ...
                            ~strcmp(metadata.status, 'active'));
                        app.ArchiveResourceButton.Enable = ...
                            app.onOff(canWrite && ...
                            ~strcmp(metadata.status, 'active'));
                    end
                    buttons{end+1} = ...
                        app.OpenResourceLocationButton;
                    app.OpenResourceLocationButton.Enable = ...
                        app.onOff(metadata.fileExists);
            end

            for iButton = 1:numel(buttons)
                button = buttons{iButton};
                button.Visible = 'on';
                button.Layout.Row = ceil(iButton / 3);
                button.Layout.Column = mod(iButton - 1, 3) + 1;
                if isequal(button, app.SaveChangesButton)
                    button.Enable = app.onOff( ...
                        canWrite && app.IsDirty);
                elseif ~isequal(button, app.RevertButton) && ...
                        ~isequal(button, ...
                        app.OpenResourceLocationButton) && ...
                        ~isequal(button, ...
                        app.SetActiveResourceButton) && ...
                        ~isequal(button, ...
                        app.ArchiveResourceButton)
                    button.Enable = app.onOff(canWrite);
                end
            end
        end

        function hideAllActionButtons(app)
            buttons = app.actionButtons();
            for iButton = 1:numel(buttons)
                buttons{iButton}.Visible = 'off';
                buttons{iButton}.Enable = 'off';
            end
        end

        function buttons = actionButtons(app)
            buttons = { ...
                app.SaveChangesButton, ...
                app.RevertButton, ...
                app.AddSubjectButton, ...
                app.AddSessionButton, ...
                app.AddImageReferenceButton, ...
                app.AddRegistrationTransformButton, ...
                app.BindRawFolderButton, ...
                app.BindProcessedFolderButton, ...
                app.MoveSessionButton, ...
                app.SetActiveResourceButton, ...
                app.ArchiveResourceButton, ...
                app.RestoreResourceButton, ...
                app.OpenResourceLocationButton};
        end

        function onSaveChanges(app)
            if app.applyEditorChanges()
                app.refreshTree(app.CurrentNodeKey);
                app.setStatus('Changes saved successfully.', 'success');
            end
        end

        function success = applyEditorChanges(app)
            success = false;
            if isempty(app.Store) || isempty(fieldnames(app.Editor))
                return
            end
            if app.Store.IsReadOnly
                uialert(app.UIFigure, ...
                    'This project is open in read-only mode.', ...
                    'Read-only Project');
                return
            end

            try
                switch app.Editor.Kind
                    case 'subject'
                        app.saveSubjectChanges();
                    case 'session'
                        app.saveSessionChanges();
                    case 'resource'
                        app.saveResourceChanges();
                    otherwise
                        success = true;
                        return
                end
                app.IsDirty = false;
                success = true;
            catch ME
                app.showException(ME, 'Save Failed');
            end
        end

        function saveSubjectChanges(app)
            original = app.Editor.Metadata;
            controls = app.Editor.Controls;
            newSubjectID = app.readTextControl(controls.subjectID);
            currentSubjectID = original.subjectID;

            if ~strcmp(newSubjectID, original.subjectID)
                app.Store.renameEntity( ...
                    'subject', original.uuid, newSubjectID);
                currentSubjectID = newSubjectID;
            end

            updates = struct();
            newDisplayName = ...
                app.readTextControl(controls.displayName);
            newDescription = ...
                app.readTextControl(controls.description);
            if ~strcmp(newDisplayName, original.displayName)
                updates.displayName = newDisplayName;
            end
            if ~strcmp(newDescription, original.description)
                updates.description = newDescription;
            end
            if ~isempty(fieldnames(updates))
                app.Store.updateSubjectMetadata( ...
                    currentSubjectID, updates);
            end
        end

        function saveSessionChanges(app)
            original = app.Editor.Metadata;
            controls = app.Editor.Controls;
            newSessionID = app.readTextControl(controls.sessionID);

            if ~strcmp(newSessionID, original.sessionID)
                app.Store.renameEntity( ...
                    'session', original.uuid, newSessionID);
            end

            [currentSession, currentSubject] = ...
                app.Store.getSessionInfoByUUID(original.uuid);
            updates = struct();

            newDisplayName = ...
                app.readTextControl(controls.displayName);
            newDescription = ...
                app.readTextControl(controls.description);
            newAcquisitionDate = controls.acquisitionDate.Value;
            newRigID = char(string(controls.rigID.Value));

            if ~strcmp(newDisplayName, original.displayName)
                updates.displayName = newDisplayName;
            end
            if ~strcmp(newDescription, original.description)
                updates.description = newDescription;
            end
            if ~isequaln(newAcquisitionDate, ...
                    original.acquisitionDate)
                updates.acquisitionDate = newAcquisitionDate;
            end
            if ~strcmp(newRigID, original.rigID)
                updates.rigID = newRigID;
            end

            if ~isempty(fieldnames(updates))
                app.Store.updateSessionMetadata( ...
                    currentSubject.subjectID, ...
                    currentSession.sessionID, updates);
            end
        end

        function saveResourceChanges(app)
            original = app.Editor.Metadata;
            controls = app.Editor.Controls;
            updates = struct();
            newDisplayName = ...
                app.readTextControl(controls.displayName);
            newDescription = ...
                app.readTextControl(controls.description);

            if ~strcmp(newDisplayName, original.displayName)
                updates.displayName = newDisplayName;
            end
            if ~strcmp(newDescription, original.description)
                updates.description = newDescription;
            end
            if ~isempty(fieldnames(updates))
                app.Store.updateResourceMetadata( ...
                    original.uuid, updates);
            end
        end

        function onRevertSelection(app)
            if isempty(app.CurrentNodeKey) || ...
                    ~isKey(app.NodeMap, app.CurrentNodeKey)
                return
            end
            app.renderSelectedNode( ...
                app.NodeMap(app.CurrentNodeKey));
            app.setStatus('Changes reverted.', 'info');
        end

        function [proceed, didSave] = resolvePendingEdits(app, actionText)
            proceed = true;
            didSave = false;
            if ~app.IsDirty
                return
            end

            choice = uiconfirm(app.UIFigure, ...
                sprintf(['There are unsaved changes. Save them before ' ...
                '%s?'], actionText), ...
                'Unsaved Changes', ...
                'Options', {'Save', 'Discard', 'Cancel'}, ...
                'DefaultOption', 1, ...
                'CancelOption', 3);
            switch choice
                case 'Save'
                    proceed = app.applyEditorChanges();
                    didSave = proceed;
                case 'Discard'
                    app.IsDirty = false;
                otherwise
                    proceed = false;
            end
        end

        function proceed = guardPendingEdits(app, actionText)
            [proceed, ~] = app.resolvePendingEdits(actionText);
        end

        function onAddSubject(app)
            answer = inputdlg( ...
                {'Subject ID:', 'Display name:', 'Description:'}, ...
                'Add Subject', ...
                [1, 60; 1, 60; 4, 60], ...
                {'', '', ''});
            if isempty(answer)
                return
            end

            try
                uuid = app.Store.addSubject(struct( ...
                    'subjectID', strtrim(answer{1}), ...
                    'displayName', answer{2}, ...
                    'description', answer{3}));
                app.refreshTree(app.makeNodeKey('subject', uuid));
                app.setStatus('Subject added successfully.', 'success');
            catch ME
                app.showException(ME, 'Add Subject Failed');
            end
        end

        function onAddSession(app)
            subjectInfo = app.Store.getSubjectInfoByUUID( ...
                app.Editor.NodeData.subjectUUID);
            answer = inputdlg( ...
                {'Session ID:', 'Display name:', 'Description:', ...
                'Acquisition date (yyyy-MM-dd, optional):', ...
                'Rig ID (optional):'}, ...
                sprintf('Add Session to %s', subjectInfo.subjectID), ...
                [1, 60; 1, 60; 4, 60; 1, 60; 1, 60], ...
                {'', '', '', '', ''});
            if isempty(answer)
                return
            end

            try
                acquisitionDate = NaT;
                if ~isempty(strtrim(answer{4}))
                    acquisitionDate = datetime( ...
                        strtrim(answer{4}), ...
                        'InputFormat', 'yyyy-MM-dd');
                end
                uuid = app.Store.addSession( ...
                    subjectInfo.subjectID, struct( ...
                    'sessionID', strtrim(answer{1}), ...
                    'displayName', answer{2}, ...
                    'description', answer{3}, ...
                    'acquisitionDate', acquisitionDate, ...
                    'rigID', strtrim(answer{5})));
                app.refreshTree(app.makeNodeKey('session', uuid));
                app.setStatus('Session added successfully.', 'success');
            catch ME
                app.showException(ME, 'Add Session Failed');
            end
        end

        function onAddImageReference(app)
            subjectInfo = app.Store.getSubjectInfoByUUID( ...
                app.Editor.NodeData.subjectUUID);
            filterSpec = app.buildResourceFileFilter( ...
                'imageReference', 'Image reference files');
            [fileName, folder] = uigetfile( ...
                filterSpec, 'Select Image Reference');
            if isequal(fileName, 0)
                return
            end

            resourceInfo = app.promptResourceInfo( ...
                'Image Reference', fileName);
            if isempty(resourceInfo)
                return
            end

            try
                uuid = app.Store.addImageReference( ...
                    subjectInfo.subjectID, ...
                    fullfile(folder, fileName), resourceInfo);
                app.refreshTree(app.makeNodeKey('resource', uuid));
                app.setStatus( ...
                    'Image reference added successfully.', 'success');
            catch ME
                app.showException(ME, ...
                    'Add Image Reference Failed');
            end
        end

        function onAddRegistrationTransform(app)
            [sessionInfo, subjectInfo] = ...
                app.Store.getSessionInfoByUUID( ...
                app.Editor.NodeData.sessionUUID);
            filterSpec = app.buildResourceFileFilter( ...
                'registrationTransform', ...
                'Registration transform files');
            [fileName, folder] = uigetfile( ...
                filterSpec, 'Select Registration Transform');
            if isequal(fileName, 0)
                return
            end

            resourceInfo = app.promptResourceInfo( ...
                'Registration Transform', fileName);
            if isempty(resourceInfo)
                return
            end

            try
                uuid = app.Store.addRegistrationTransform( ...
                    subjectInfo.subjectID, ...
                    sessionInfo.sessionID, ...
                    fullfile(folder, fileName), resourceInfo);
                app.refreshTree(app.makeNodeKey('resource', uuid));
                app.setStatus( ...
                    'Registration transform added successfully.', ...
                    'success');
            catch ME
                app.showException(ME, ...
                    'Add Registration Transform Failed');
            end
        end
        function onToggleDataBinding(app, folderRole)
            [sessionInfo, subjectInfo] = ...
                app.Store.getSessionInfoByUUID( ...
                app.Editor.NodeData.sessionUUID);
            currentFolder = sessionInfo.(folderRole);

            try
                if isempty(currentFolder)
                    selectedFolder = uigetdir(pwd, ...
                        sprintf('Select %s', ...
                        app.humanizeField(folderRole)));
                    if isequal(selectedFolder, 0)
                        return
                    end

                    try
                        app.bindFolder( ...
                            subjectInfo.subjectID, ...
                            sessionInfo.sessionID, folderRole, ...
                            selectedFolder, false);
                    catch ME
                        if strcmp(ME.identifier, ...
                                ['Umitoolbox:UMITProjectStore:' ...
                                'bindingFileExists'])
                            choice = uiconfirm(app.UIFigure, ...
                                ['The selected folder already contains a ' ...
                                'project binding. Replace it only if it ' ...
                                'is an orphan?'], ...
                                'Existing Project Binding', ...
                                'Options', ...
                                {'Check and Replace Orphan', 'Cancel'}, ...
                                'DefaultOption', 2, ...
                                'CancelOption', 2);
                            if strcmp(choice, ...
                                    'Check and Replace Orphan')
                                app.bindFolder( ...
                                    subjectInfo.subjectID, ...
                                    sessionInfo.sessionID, ...
                                    folderRole, selectedFolder, true);
                            end
                        else
                            rethrow(ME)
                        end
                    end
                else
                    choice = uiconfirm(app.UIFigure, ...
                        sprintf('Unbind this folder?\n\n%s', ...
                        currentFolder), ...
                        'Confirm Unbind', ...
                        'Options', {'Unbind', 'Cancel'}, ...
                        'DefaultOption', 2, ...
                        'CancelOption', 2);
                    if ~strcmp(choice, 'Unbind')
                        return
                    end

                    if strcmp(folderRole, 'rawDataFolder')
                        app.Store.unbindRawDataFolder( ...
                            subjectInfo.subjectID, ...
                            sessionInfo.sessionID);
                    else
                        app.Store.unbindProcessedDataFolder( ...
                            subjectInfo.subjectID, ...
                            sessionInfo.sessionID);
                    end
                end

                app.refreshTree(app.Editor.NodeData.key);
                app.setStatus( ...
                    'Folder binding updated successfully.', 'success');
            catch ME
                app.showException(ME, 'Folder Binding Failed');
            end
        end

        function bindFolder(app, subjectID, sessionID, ...
                folderRole, folder, replaceOrphan)
            if strcmp(folderRole, 'rawDataFolder')
                app.Store.bindRawDataFolder( ...
                    subjectID, sessionID, folder, ...
                    'ReplaceOrphanBinding', replaceOrphan);
            else
                app.Store.bindProcessedDataFolder( ...
                    subjectID, sessionID, folder, ...
                    'ReplaceOrphanBinding', replaceOrphan);
            end
        end

        function onMoveSession(app)
            [sessionInfo, currentSubject] = ...
                app.Store.getSessionInfoByUUID( ...
                app.Editor.NodeData.sessionUUID);
            projectInfo = app.Store.getProjectInfo();
            targets = projectInfo.subjectRegistry( ...
                ~strcmpi({projectInfo.subjectRegistry.uuid}, ...
                currentSubject.uuid));

            if isempty(targets)
                uialert(app.UIFigure, ...
                    'There is no other subject in this project.', ...
                    'Move Session');
                return
            end

            labels = strings(numel(targets), 1);
            for iTarget = 1:numel(targets)
                labels(iTarget) = sprintf('%s (%s)', ...
                    targets(iTarget).displayName, ...
                    targets(iTarget).id);
            end
            [selection, ok] = listdlg( ...
                'PromptString', sprintf( ...
                'Move session "%s" to:', sessionInfo.sessionID), ...
                'SelectionMode', 'single', ...
                'ListString', cellstr(labels), ...
                'ListSize', [420, 260]);
            if ~ok
                return
            end

            try
                app.Store.moveSessionToSubject( ...
                    sessionInfo.uuid, targets(selection).uuid);
                app.refreshTree(app.Editor.NodeData.key);
                app.setStatus('Session moved successfully.', 'success');
            catch ME
                app.showException(ME, 'Move Session Failed');
            end
        end

        function onSetActiveResource(app)
            resource = app.Store.getResource( ...
                app.Editor.NodeData.resourceUUID);
            try
                switch resource.ownerType
                    case 'subject'
                        subjectInfo = ...
                            app.Store.getSubjectInfoByUUID( ...
                            resource.ownerUUID);
                        app.Store.setActiveImageReference( ...
                            subjectInfo.subjectID, resource.uuid);
                    case 'session'
                        [sessionInfo, subjectInfo] = ...
                            app.Store.getSessionInfoByUUID( ...
                            resource.ownerUUID);
                        app.Store.setActiveRegistrationTransform( ...
                            subjectInfo.subjectID, ...
                            sessionInfo.sessionID, resource.uuid);
                    otherwise
                        error( ...
                            ['Umitoolbox:ProjectManagerTool:' ...
                            'invalidResourceOwner'], ...
                            'Unsupported resource owner type: %s', ...
                            resource.ownerType);
                end
                app.refreshTree(app.Editor.NodeData.key);
                app.setStatus('Active resource updated.', 'success');
            catch ME
                app.showException(ME, ...
                    'Set Active Resource Failed');
            end
        end

        function onArchiveResource(app)
            resource = app.Store.getResource( ...
                app.Editor.NodeData.resourceUUID);
            if strcmp(resource.status, 'active')
                uialert(app.UIFigure, ...
                    ['Select another active resource before archiving ' ...
                    'this one.'], ...
                    'Active Resource');
                return
            end

            choice = uiconfirm(app.UIFigure, ...
                sprintf('Archive resource "%s"?', ...
                resource.displayName), ...
                'Confirm Archive', ...
                'Options', {'Archive', 'Cancel'}, ...
                'DefaultOption', 2, ...
                'CancelOption', 2);
            if ~strcmp(choice, 'Archive')
                return
            end

            try
                app.Store.archiveResource(resource.uuid);
                app.refreshTree(app.Editor.NodeData.key);
                app.setStatus('Resource archived.', 'success');
            catch ME
                app.showException(ME, 'Archive Resource Failed');
            end
        end

        function onRestoreResource(app)
            try
                app.Store.restoreResource( ...
                    app.Editor.NodeData.resourceUUID);
                app.refreshTree(app.Editor.NodeData.key);
                app.setStatus('Resource restored.', 'success');
            catch ME
                app.showException(ME, 'Restore Resource Failed');
            end
        end

        function onOpenResourceLocation(app)
            resource = app.Store.getResource( ...
                app.Editor.NodeData.resourceUUID);
            if ~resource.fileExists
                uialert(app.UIFigure, ...
                    'The managed resource file is unavailable.', ...
                    'Missing Resource File');
                return
            end

            try
                app.openFolder(fileparts(resource.absolutePath));
            catch ME
                app.showException(ME, 'Open Folder Failed');
            end
        end

        function onValidateProject(app)
            if isempty(app.Store)
                return
            end
            mode = lower(app.ValidationModeDropDown.Value);
            try
                app.setStatus('Validating project...', 'busy');
                report = app.Store.validate('Mode', mode);
                app.updateToolbarStatus();
                nErrors = app.structCount(report, 'errors');
                nWarnings = app.structCount(report, 'warnings');

                if report.isValid
                    message = sprintf( ...
                        'Project is valid.\nWarnings: %d\nMode: %s', ...
                        nWarnings, mode);
                    titleText = 'Validation Passed';
                else
                    message = sprintf( ...
                        ['Validation failed.\nErrors: %d\nWarnings: %d' ...
                        '\n\n%s'], ...
                        nErrors, nWarnings, ...
                        app.issueSummary(report.errors, 8));
                    titleText = 'Validation Failed';
                end
                uialert(app.UIFigure, message, titleText);

                if isfield(app.Editor, 'Kind') && ...
                        strcmp(app.Editor.Kind, 'project') && ...
                        isKey(app.NodeMap, app.CurrentNodeKey)
                    app.renderSelectedNode( ...
                        app.NodeMap(app.CurrentNodeKey));
                end
            catch ME
                app.showException(ME, 'Validation Failed');
            end
        end

        function updateToolbarStatus(app)
            projectInfo = app.Store.getProjectInfo();
            if app.Store.IsReadOnly
                accessText = 'Read-only';
            else
                accessText = 'Writable';
            end

            validationText = 'Not validated';
            report = app.Store.LastValidationReport;
            if isstruct(report) && isfield(report, 'isValid')
                if report.isValid
                    validationText = 'Valid';
                else
                    validationText = 'Invalid';
                end
            end
            app.setStatus(sprintf('%s | %s | %s', ...
                projectInfo.projectName, accessText, ...
                validationText), 'neutral');
        end

        function onCloseRequest(app)
            if app.IsClosing
                return
            end
            if ~app.guardPendingEdits('closing the app')
                return
            end

            app.IsClosing = true;
            app.UIFigure.CloseRequestFcn = [];
            delete(app);
        end

        function clearMetadataTabs(app)
            tabs = app.MetadataTabGroup.Children;
            if ~isempty(tabs)
                delete(tabs);
            end
        end

        function setStatus(app, message, severity)
            setStatusMessage(app.StatusLabel, message, ...
                'Severity', severity, ...
                'Background', 'light', ...
                'MaxLength', 180);
        end

        function showException(app, exception, titleText)
            app.setStatus(exception, 'error');
            message = exception.message;
            if ~isempty(exception.identifier)
                message = sprintf('%s\n\n%s', ...
                    message, exception.identifier);
            end
            uialert(app.UIFigure, message, titleText);
        end
        function filterSpec = buildResourceFileFilter(app,  ...
                resourceType, label)
            filterSpec = {'*.*', 'All files'};
            if ~isfield(app.Store.Schema, 'resourceTypes') || ...
                    ~isfield(app.Store.Schema.resourceTypes, resourceType)
                return
            end
            definition = ...
                app.Store.Schema.resourceTypes.(resourceType);
            if ~isfield(definition, 'allowedExtensions') || ...
                    isempty(definition.allowedExtensions)
                return
            end
            extensions = cellstr( ...
                string(definition.allowedExtensions(:)));
            patterns = cellfun( ...
                @(extension) ['*', extension], extensions, ...
                'UniformOutput', false);
            filterSpec = { ...
                strjoin(patterns, ';'), label; ...
                '*.*', 'All files'};
        end

        function resourceInfo = promptResourceInfo(~, resourceLabel, defaultName)
            [~, baseName, ~] = fileparts(defaultName);
            answer = inputdlg( ...
                {'Display name:', 'Description:'}, ...
                sprintf('Add %s', resourceLabel), ...
                [1, 60; 4, 60], ...
                {baseName, ''});
            if isempty(answer)
                resourceInfo = [];
            else
                resourceInfo = struct( ...
                    'displayName', answer{1}, ...
                    'description', answer{2});
            end
        end

        function data = nodeData(app,  ...
                kind, uuid, subjectUUID, sessionUUID, resourceUUID)
            data = struct( ...
                'kind', kind, ...
                'uuid', uuid, ...
                'subjectUUID', subjectUUID, ...
                'sessionUUID', sessionUUID, ...
                'resourceUUID', resourceUUID, ...
                'key', ...
                app.makeNodeKey(kind, uuid));
        end

        function key = makeNodeKey(app, kind, uuid)
            key = sprintf('%s:%s', ...
                char(string(kind)), char(string(uuid)));
        end

        function text = displayNodeText(app, prefix, displayName, id)
            displayName = char(string(displayName));
            id = char(string(id));
            if isempty(displayName) || strcmp(displayName, id)
                text = sprintf('%s: %s', prefix, id);
            else
                text = sprintf('%s: %s (%s)', ...
                    prefix, displayName, id);
            end
        end

        function text = resourceNodeText(app, resource)
            text = sprintf('%s: %s [%s]', ...
                app.humanizeField( ...
                resource.type), ...
                resource.displayName, resource.status);
            if ~resource.fileExists
                text = sprintf('%s [missing]', text);
            end
        end

        function tf = requiresStructuredView(app, value)
            tf = isstruct(value) || istable(value) || ...
                (iscell(value) && ~isscalar(value)) || ...
                (isstring(value) && ~isscalar(value)) || ...
                (isnumeric(value) && ~isscalar(value)) || ...
                (islogical(value) && ~isscalar(value)) || ...
                iscategorical(value);
        end

        function fields = orderMetadataFields(app, kind, fields)
            switch kind
                case 'project'
                    preferred = { ...
                        'projectName', 'projectUUID', 'description', ...
                        'schemaVersion', 'createdOn', 'modifiedOn', ...
                        'projectRoot', 'isReadOnly', ...
                        'validationIsValid'};
                case 'subject'
                    preferred = { ...
                        'subjectID', 'displayName', 'description', ...
                        'uuid', 'schemaVersion', ...
                        'activeImageReferenceUUID', ...
                        'createdOn', 'modifiedOn', 'relativePath'};
                case 'session'
                    preferred = { ...
                        'sessionID', 'displayName', 'description', ...
                        'acquisitionDate', 'uuid', ...
                        'subjectID', 'subjectUUID', ...
                        'parentSubjectDisplayName', ...
                        'rigID', 'rigUUID', ...
                        'rawDataFolder', 'rawDataBindingUUID', ...
                        'processedDataFolder', ...
                        'processedDataBindingUUID', ...
                        'activeRegistrationTransformUUID', ...
                        'createdOn', 'modifiedOn', ...
                        'relativePath', 'schemaVersion'};
                case 'resource'
                    preferred = { ...
                        'displayName', 'description', 'uuid', ...
                        'type', 'status', 'ownerType', 'ownerUUID', ...
                        'fileName', 'fileExists', 'absolutePath', ...
                        'relativePath', 'sourceFile', 'checksum', ...
                        'createdOn', 'modifiedOn', 'archivedOn'};
                otherwise
                    preferred = {'message'};
            end

            ordered = preferred(ismember(preferred, fields));
            remaining = fields(~ismember(fields, ordered));
            fields = [ordered(:); remaining(:)];
        end

        function tf = isCategoricalField(app, fieldName)
            tf = any(strcmp(fieldName, ...
                {'status', 'type', 'ownerType', 'folderRole'}));
        end

        function tf = isEditableField(app, kind, fieldName)
            switch kind
                case 'subject'
                    tf = any(strcmp(fieldName, ...
                        {'subjectID', 'displayName', 'description'}));
                case 'session'
                    tf = any(strcmp(fieldName, ...
                        {'sessionID', 'displayName', 'description', ...
                        'acquisitionDate', 'rigID'}));
                case 'resource'
                    tf = any(strcmp(fieldName, ...
                        {'displayName', 'description'}));
                otherwise
                    tf = false;
            end
        end

        function data = tabularizeMetadata(app, value)
            try
                if istable(value)
                    data = value;
                elseif isstruct(value)
                    data = struct2table(value(:));
                elseif iscell(value)
                    data = table((1:numel(value)).', string(value(:)), ...
                        'VariableNames', {'Index', 'Value'});
                elseif isstring(value) || iscategorical(value)
                    data = table((1:numel(value)).', string(value(:)), ...
                        'VariableNames', {'Index', 'Value'});
                elseif isnumeric(value) || islogical(value)
                    data = table((1:numel(value)).', value(:), ...
                        'VariableNames', {'Index', 'Value'});
                else
                    data = table(string(value), ...
                        'VariableNames', {'Value'});
                end
            catch
                data = table(string(evalc('disp(value)')), ...
                    'VariableNames', {'Value'});
            end
        end

        function value = readTextControl(app, control)
            if isa(control, 'matlab.ui.control.TextArea')
                value = strjoin(string(control.Value(:)), newline);
                value = char(value);
            else
                value = char(string(control.Value));
            end
        end

        function value = textAreaValue(app, inputValue)
            if isempty(inputValue)
                value = {''};
            else
                value = cellstr(splitlines(string(inputValue)));
            end
        end

        function text = scalarText(app, value)
            if isempty(value)
                text = '';
            elseif ischar(value)
                text = value;
            elseif isstring(value) && isscalar(value)
                text = char(value);
            else
                text = char(string(value));
            end
        end

        function text = formatDatetime(app, value)
            if isempty(value) || isnat(value)
                text = '';
            else
                formatted = value;
                formatted.Format = 'yyyy-MM-dd HH:mm:ss';
                text = char(string(formatted));
            end
        end

        function text = humanizeField(app, fieldName)
            text = regexprep(char(string(fieldName)), ...
                '([a-z0-9])([A-Z])', '$1 $2');
            text = strrep(text, '_', ' ');
            if ~isempty(text)
                text(1) = upper(text(1));
            end
        end

        function value = onOff(app, tf)
            if tf
                value = 'on';
            else
                value = 'off';
            end
        end

        function count = structCount(app, container, fieldName)
            if isfield(container, fieldName)
                count = numel(container.(fieldName));
            else
                count = 0;
            end
        end

        function text = issueSummary(app, issues, maxIssues)
            if isempty(issues)
                text = '';
                return
            end
            nShow = min(numel(issues), maxIssues);
            lines = strings(nShow, 1);
            for iIssue = 1:nShow
                if isfield(issues(iIssue), 'message')
                    lines(iIssue) = sprintf('%d. %s', ...
                        iIssue, issues(iIssue).message);
                else
                    lines(iIssue) = sprintf( ...
                        '%d. Validation issue', iIssue);
                end
            end
            if numel(issues) > nShow
                lines(end+1) = sprintf('...and %d more.', ...
                    numel(issues) - nShow);
            end
            text = char(strjoin(lines, newline));
        end

        function openFolder(app, folderPath)
            if ispc
                winopen(folderPath);
            elseif ismac
                system(sprintf('open "%s"', folderPath));
            else
                system(sprintf( ...
                    'xdg-open "%s" >/dev/null 2>&1 &', folderPath));
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.NodeMap = containers.Map( ...
                'KeyType', 'char', 'ValueType', 'any');
            app.initializeUI();

            try
                if isempty(varargin)
                    store = app.chooseProjectStore();
                elseif isscalar(varargin)
                    store = app.resolveStore(varargin{1});
                else
                    error( ...
                        'Umitoolbox:ProjectManagerTool:invalidStartupInput', ...
                        'Expected at most one store object or project UUID.');
                end

                if ~isempty(store)
                    app.setStore(store);
                else
                    app.showEmptyState( ...
                        'Open or create a project to begin.');
                end
            catch ME
                app.showException(ME, 'Project Manager Startup Failed');
                app.showEmptyState('No project is open.');
            end
        end

        % Button pushed function: OpenProjectButton
        function OpenProjectButtonPushed(app, event)
            app.onOpenProject();
        end

        % Button pushed function: NewProjectButton
        function NewProjectButtonPushed(app, event)
app.onNewProject();
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.onRefresh();
        end

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            app.onValidateProject();
        end

        % Selection changed function: ProjectTree
        function ProjectTreeSelectionChanged(app, event)
            app.onTreeSelectionChanged(event);

        end

        % Button pushed function: SaveChangesButton
        function SaveChangesButtonPushed(app, event)
            app.onSaveChanges();
        end

        % Button pushed function: RevertButton
        function RevertButtonPushed(app, event)
            app.onRevertSelection();
        end

        % Button pushed function: AddSubjectButton
        function AddSubjectButtonPushed(app, event)
            app.onAddSubject();
        end

        % Button pushed function: AddSessionButton
        function AddSessionButtonPushed(app, event)
            app.onAddSession();
        end

        % Button pushed function: AddImageReferenceButton
        function AddImageReferenceButtonPushed(app, event)
            app.onAddImageReference();
        end

        % Button pushed function: AddRegistrationTransformButton
        function AddRegistrationTransformButtonPushed(app, event)
            app.onAddRegistrationTransform();
        end

        % Button pushed function: BindRawFolderButton
        function BindRawFolderButtonPushed(app, event)
            app.onToggleDataBinding('rawDataFolder');
        end

        % Button pushed function: BindProcessedFolderButton
        function BindProcessedFolderButtonPushed(app, event)
            app.onToggleDataBinding('processedDataFolder');
        end

        % Button pushed function: MoveSessionButton
        function MoveSessionButtonPushed(app, event)
            app.onMoveSession();
        end

        % Button pushed function: SetActiveResourceButton
        function SetActiveResourceButtonPushed(app, event)
            app.onSetActiveResource();
        end

        % Button pushed function: ArchiveResourceButton
        function ArchiveResourceButtonPushed(app, event)
            app.onArchiveResource();
        end

        % Button pushed function: RestoreResourceButton
        function RestoreResourceButtonPushed(app, event)
            app.onRestoreResource();
        end

        % Button pushed function: OpenResourceLocationButton
        function OpenResourceLocationButtonPushed(app, event)
            app.onOpenResourceLocation();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.onCloseRequest();

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Project Manager';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayoutTop = uigridlayout(app.GridLayout);
            app.OpenProjectButton = uibutton(app.GridLayoutTop, 'push');
            app.OpenProjectButton.ButtonPushedFcn = createCallbackFcn(app, @OpenProjectButtonPushed, true);
            app.OpenProjectButton.Text = 'Open Project';
            app.NewProjectButton = uibutton(app.GridLayoutTop, 'push');
            app.NewProjectButton.ButtonPushedFcn = createCallbackFcn(app, @NewProjectButtonPushed, true);
            app.NewProjectButton.Text = 'New Project';
            app.RefreshButton = uibutton(app.GridLayoutTop, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ValidateButton = uibutton(app.GridLayoutTop, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate';
            app.ValidationModeDropDown = uidropdown(app.GridLayoutTop);
            app.ValidationModeDropDown.Items = {'Quick', 'Full'};
            app.ValidationModeDropDown.Value = 'Quick';
            app.StatusLabel = uilabel(app.GridLayoutTop);
            app.StatusLabel.Text = 'Status';
            app.GridLayoutBottom = uigridlayout(app.GridLayout);
            app.ProjectStructurePanel = uipanel(app.GridLayoutBottom);
            app.ProjectStructurePanel.Title = 'Project Structure';
            app.ProjectStructureGrid = uigridlayout(app.ProjectStructurePanel);
            app.ProjectTree = uitree(app.ProjectStructureGrid);
            app.ProjectTree.SelectionChangedFcn = createCallbackFcn(app, @ProjectTreeSelectionChanged, true);
            app.SelectedItemPanel = uipanel(app.GridLayoutBottom);
            app.SelectedItemPanel.Title = 'Selected Item';
            app.SelectedItemGrid = uigridlayout(app.SelectedItemPanel);
            app.ItemHeaderLabel = uilabel(app.SelectedItemGrid);
            app.ItemHeaderLabel.Text = 'ItemHeader';
            app.SelectedItemBottomGrid = uigridlayout(app.SelectedItemGrid);
            app.SaveChangesButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.SaveChangesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChangesButtonPushed, true);
            app.SaveChangesButton.Text = 'Save Changes';
            app.RevertButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.RevertButton.ButtonPushedFcn = createCallbackFcn(app, @RevertButtonPushed, true);
            app.RevertButton.Text = 'Revert';
            app.AddSessionButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.AddSessionButton.ButtonPushedFcn = createCallbackFcn(app, @AddSessionButtonPushed, true);
            app.AddSessionButton.Text = 'Add Session';
            app.AddImageReferenceButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.AddImageReferenceButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageReferenceButtonPushed, true);
            app.AddImageReferenceButton.Text = 'Add Image Reference';
            app.AddSubjectButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.AddSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddSubjectButtonPushed, true);
            app.AddSubjectButton.Text = 'Add Subject';
            app.AddRegistrationTransformButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.AddRegistrationTransformButton.ButtonPushedFcn = createCallbackFcn(app, @AddRegistrationTransformButtonPushed, true);
            app.AddRegistrationTransformButton.Visible = 'off';
            app.AddRegistrationTransformButton.Text = 'Add Registration Transform';
            app.BindRawFolderButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.BindRawFolderButton.ButtonPushedFcn = createCallbackFcn(app, @BindRawFolderButtonPushed, true);
            app.BindRawFolderButton.Visible = 'off';
            app.BindRawFolderButton.Text = 'Bind Raw Folder';
            app.BindProcessedFolderButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.BindProcessedFolderButton.ButtonPushedFcn = createCallbackFcn(app, @BindProcessedFolderButtonPushed, true);
            app.BindProcessedFolderButton.Visible = 'off';
            app.BindProcessedFolderButton.Text = 'Bind Processed Folder';
            app.MoveSessionButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.MoveSessionButton.ButtonPushedFcn = createCallbackFcn(app, @MoveSessionButtonPushed, true);
            app.MoveSessionButton.Visible = 'off';
            app.MoveSessionButton.Text = 'Move Session';
            app.SetActiveResourceButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.SetActiveResourceButton.ButtonPushedFcn = createCallbackFcn(app, @SetActiveResourceButtonPushed, true);
            app.SetActiveResourceButton.Visible = 'off';
            app.SetActiveResourceButton.Text = 'Set Active Resource';
            app.ArchiveResourceButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.ArchiveResourceButton.ButtonPushedFcn = createCallbackFcn(app, @ArchiveResourceButtonPushed, true);
            app.ArchiveResourceButton.Visible = 'off';
            app.ArchiveResourceButton.Text = 'Archive Resource';
            app.RestoreResourceButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.RestoreResourceButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreResourceButtonPushed, true);
            app.RestoreResourceButton.Visible = 'off';
            app.RestoreResourceButton.Text = 'Restore Resource';
            app.OpenResourceLocationButton = uibutton(app.SelectedItemBottomGrid, 'push');
            app.OpenResourceLocationButton.ButtonPushedFcn = createCallbackFcn(app, @OpenResourceLocationButtonPushed, true);
            app.OpenResourceLocationButton.Visible = 'off';
            app.OpenResourceLocationButton.Text = 'Open Resource Location';
            app.MetadataTabGroup = uitabgroup(app.SelectedItemGrid);
            app.Tab = uitab(app.MetadataTabGroup);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProjectManagerTool(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end