classdef PlotAvgMaps < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlotAvgMapsUIFigure          matlab.ui.Figure
        OptionsPanel                 matlab.ui.container.Panel
        UselogicalmasksCheckBox      matlab.ui.control.CheckBox
        SetcolormapButton            matlab.ui.control.Button
        colorscaleButtonGroup        matlab.ui.container.ButtonGroup
        AutoButton_2                 matlab.ui.control.RadioButton
        UniformButton                matlab.ui.control.RadioButton
        SpatFiltsigmaEditFieldLabel  matlab.ui.control.Label
        SpatFiltsigmaEditField       matlab.ui.control.NumericEditField
        CreatemapsButton             matlab.ui.control.Button
        ExportButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        StatsObj % Handle to StatisticManager object
        mskArr = {}; % Cell array containing the logical masks for each subject
        avgMapArr = {}; % Cell array containing the average maps with dimensions (group x acquisition x observation)
        gNames % List of group names        
        hLink % linkprop handle for axes.
        sumMsk % sum of logical masks per acquisition.
        hiddenFig % Figure containing dummy axis for interaction with Image options app.
        currFigs = findall(0, 'Type', 'Figure');
    end
    
    properties (Access = public)
        image_info = struct('cmap', colormap('jet'),'clims',[0 1], 'clims_def',[0 1], 'pxPermm',0, 'refPt', [0,0], 'logical_mask', [])
        hiddenAx % handle to dummy axis.
    end
    
    methods (Access = private)
        
        function calcAvgMaps(app)
            % AVGMAPS creates an average map (i.e. image with dimensions Y,X) per group
            % per acquisition.
            disp('Averaging data...')
            
            % Get names of groups and segregate data per aquisition index:
            app.gNames = unique(app.StatsObj.list_of_groups);
            indxAcq = app.StatsObj.stats_data(:, strcmp(app.StatsObj.stats_data_headers,'AcquisitionIndx'));
            indxAcq = [indxAcq{:}]';
            nAcq = unique(indxAcq);
            subjNames = unique({app.StatsObj.stats_data{:,strcmp(app.StatsObj.stats_data_headers, 'SubjectID')}});
            % Instantiate output variabe:
            app.avgMapArr = cell(length(app.gNames), length(nAcq), length(app.StatsObj.obs_list));                        
            
            for i = 1:numel(app.gNames)
                idxG = strcmp(app.gNames{i}, app.StatsObj.stats_data(:,strcmp(app.StatsObj.stats_data_headers,'groupID')));
                for j = 1:length(nAcq)
                    % Get all acquisitions:
                    indxA = find(idxG & indxAcq == nAcq(j));
                    dataIn = cell(length(indxA),length(app.StatsObj.obs_list));                    
                    refPt_list = zeros(length(indxA),2);
                    pxPermm_list = zeros(length(indxA),1);                    
                    for k = 1:length(indxA)
                        % get data from each acquisition:
                        [idxObs, locB] = ismember(app.StatsObj.obs_list, ...
                            app.StatsObj.stats_data{indxA(k),strcmp(app.StatsObj.stats_data_headers,'observationID')});
                        locB(locB == 0) = [];
                        dataIn(k,idxObs) = app.StatsObj.stats_data{indxA(k),strcmp(app.StatsObj.stats_data_headers,'data')}(locB);                        
                        refPt_list(k,:) = app.StatsObj.stats_data{indxA(k),strcmp(app.StatsObj.stats_data_headers,'MatFile')}.refPt;
                        pxPermm_list(k) = app.StatsObj.stats_data{indxA(k),strcmp(app.StatsObj.stats_data_headers,'MatFile')}.pxPermm;                                                               
                    end
                    % Sort data and metadata lists by subject ID:
                    [~,locS] = ismember(subjNames,...
                        {app.StatsObj.stats_data{indxA,strcmp(app.StatsObj.stats_data_headers,'SubjectID')}});
                    dataIn = dataIn(locS,:);
                    refPt_list = refPt_list(locS',:);
                    pxPermm_list = pxPermm_list(locS);
                    
                    % Perform averaging for each acquisition and group:
                    if ~isempty(app.mskArr) 
                        % Get masks after imwarp(ing):                        
                        [app.avgMapArr(i,j,:), app.sumMsk{i}] = calcAvg(dataIn, refPt_list, pxPermm_list, app.mskArr(i,indxA),...
                            app.SpatFiltsigmaEditField.Value);                                              
                    else
                        % get only data:
                         app.avgMapArr(i,j,:) = calcAvg(dataIn, refPt_list, pxPermm_list, [],...
                            app.SpatFiltsigmaEditField.Value);
                    end                                                                            
                end
            end
            % Local function:
            function [out, msk_out] = calcAvg(data, ref, px_size, logical_mask, sigma)
                % This function aligns the reference points and scales the
                % frames in data, then calculates the average maps.
                
                msk_out = cell(size(logical_mask));
                % Translate and scale the data to match position of first item of
                % "data":
                ref = ref(1,:) - ref;
                px_size = px_size(1)./px_size;
                RA = imref2d(size(data{1,1})); % Use size of first data as reference.
                for ii = 1:size(data,1)
                    tform = affine2d([px_size(ii) 0 0; 0 px_size(ii) 0; ref(ii,1) ref(ii,2) 1]); 
                    if ~isempty(logical_mask)
                        myMsk = logical_mask{ii};
                        % Warp mask:
                        msk_out{ii} = imwarp(logical_mask{ii}, tform, 'nearest','OutputView', RA, 'FillValues', 0);
                    end
                    
                    for jj = 1:size(data,2)
                        if isempty(data{ii,jj})
                            continue
                        end                        
                        % Remove NaNs and Infs from data                        
                        idxNaN = isnan(data{ii,jj});
                        idxInfPos = isinf(data{ii,jj}) & data{ii,jj} > 0;
                        idxInfNeg = isinf(data{ii,jj}) & data{ii,jj} < 0;
                        data{ii,jj}(idxNaN | idxInfPos | idxInfNeg) = 0;
                        
                        data{ii,jj} = imwarp(data{ii,jj}, tform, 'nearest','OutputView', RA, 'FillValues', nan);
                        % Put NaNs and Infs back to the translated data:
                        if any(idxNaN, 'all')
                            idxNaN = imwarp(idxNaN, tform, 'nearest', 'FillValues', 0);
                            data{ii,jj}(idxNaN) = nan;
                        end
                        if any(idxInfPos, 'all')
                            idxInfPos = imwarp(idxInfPos, tform, 'nearest', 'FillValues', 0);
                            data{ii,jj}(idxInfPos) = Inf;
                        end
                        if any(idxInfNeg, 'all')
                            idxInfNeg = imwarp(idxInfNeg, tform, 'nearest', 'FillValues', 0);
                            data{ii,jj}(idxInfNeg) = -Inf;
                        end
                        % Perform geometric transformation on image:
                        if exist('myMsk', 'var')
                            % Erase pixel values outside the logical mask:
                            data{ii,jj}(~msk_out{ii}) = nan;
                        end                        
                    end                    
                end
                % Average acquisitions:
                out = cell(1,size(data,2));
                msk_out = sum(cat(3,msk_out{:}),3);
                for ii = 1:size(data,2)                    
                    out{ii} = mean(cat(3,data{:,ii}),3,'omitnan');
                    % Perform spatial filter in average image:
                    if sigma ~= 0 
                        % Populate NaN areas with interpolated data:
                        nanMask = isnan(out{ii});
                        [r, c] = find(~nanMask);
                        [rNan, cNan] = find(nanMask);
                        F = scatteredInterpolant(c, r, double(out{ii}(~nanMask)), 'nearest');
                        interpVals = F(cNan, rNan);                        
                        out{ii}(nanMask) = single(interpVals);
                        % Perform spatial filter:
                        out{ii} = imgaussfilt(out{ii},sigma);
                        % Put NaNs back:
                        out{ii}(nanMask) = nan;                        
                    end
                end
            end
        end        
        
        function plotMaps(app)
            % This function creates the figures containing the average
            % maps.
            
             % Plot maps:
            disp('plotting maps...')
            % Plot All Maps for each observation: 
            for i = 1:size(app.avgMapArr,3)
                f = figure('Name', app.StatsObj.obs_list{i}, 'Visible', 'off','NumberTitle', 'off',...
                    'Tag', 'avgMapFig', 'CloseRequestFcn',@app.DoNothing);                
                for j = 1:size(app.avgMapArr,1)
                    ax = subplot(size(app.avgMapArr,1),1,j, 'Parent', f);                    
                    frames = cat(3, app.avgMapArr{j,:,i});
                    mnt = montage(frames, 'Size',[1 size(app.avgMapArr,2)], 'Parent', ax);
                    mnt.Tag = 'myImg';
                    mnt.AlphaData = ~isnan(mnt.CData); % Make NaNs transparent.
                    
                    set(ax, 'CLim', [min(frames(:)), max(frames(:))], 'ColorMap', colormap('jet'), 'Color', 'k', ...
                        'Visible', 'on', 'XTick',[], 'YTick',[], 'PlotBoxAspectRatio',[1 1 1]);
                    set(ax.YLabel, 'String', app.gNames{j},'Interpreter', 'none');
                    if j == size(app.avgMapArr,1)                       
                        set(ax.XLabel, 'String', 'Acquisition','Interpreter', 'none');
                    end
                    ax.Tag = 'myAx';
                    colorbar(ax, 'Location', 'eastoutside');
                end                
                sgtitle(f,app.StatsObj.obs_list{i}, 'Interpreter','none');                                                              
                f.Visible = 'on';
                linkaxes(findobj(f, 'Tag', 'myAx'),'xy');
                set(f, 'InvertHardCopy', 'off');
            end
            % Link properties of axis
            h = findall(0, 'Tag', 'myAx');
            app.hLink = linkprop([app.hiddenAx; h], 'Colormap');
            disp('Done')
            % Plot sum of logical masks:
            if app.UselogicalmasksCheckBox.Value
                f2 = figure('Name', 'Logical masks (sum)','NumberTitle', 'off',...
                    'Tag', 'MskFig');
                 for i = 1:size(app.sumMsk,1)
                     ax = subplot(size(app.sumMsk,1),1,i, 'Parent', f2);
                     imagesc(ax,app.sumMsk{i}, 'AlphaData',app.sumMsk{i} ~= 0)
                     set(ax, 'ColorMap', colormap('jet'), 'Color', 'k', ...
                        'Visible', 'on', 'XTick',[], 'YTick',[], 'DataAspectRatio',[1 1 1]);
                     set(ax.YLabel, 'String', app.gNames{j},'Interpreter', 'none');                     
                     sgtitle(f2,'Sum of logical Masks')
                 end
            end
        end
        function DoNothing(~,~)
            % EMPTY
        end              
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, StatsObj)
            % Create a hidden figure. This will enable the communication
            % with the "imagApps_Opts.mlapp": 
            app.hiddenFig = figure('Visible', 'off','Tag', 'hiddenFig');
            app.PlotAvgMapsUIFigure.Visible = 'off';movegui(app.PlotAvgMapsUIFigure, 'northwest');app.PlotAvgMapsUIFigure.Visible = 'on';                       
            % Instantiate variable:
            app.StatsObj = StatsObj;
            % Validate if the data has Y,X dimensions:
            disp('Checking data requirements...');
            idx = false(length(app.StatsObj.list_of_objs),4);
            for i = 1:length(app.StatsObj.list_of_objs)
                % Check if data has "dim_names" variable
                idx(i,1) = isprop(app.StatsObj.MfileArr{i}, 'dim_names');
                % Check if the data is a 2D matrix (i.e. an image):
                if idx(i,1)
                    idx(i,2) = all(ismember(upper(app.StatsObj.MfileArr{i}.dim_names), upper({'Y', 'X'})));
                end
                % Check if "refPt" coordinates exist:
                idx(i,3) = isprop(app.StatsObj.MfileArr{i}, 'refPt');
                % Check if "pxPermm" exists:
                idx(i,4) = isprop(app.StatsObj.MfileArr{i}, 'pxPermm') || ( app.StatsObj.MfileArr{i}.pxPermm == 0 );
            end
            % Show alert if any of the above checks failed:
            if ~any(idx,'all')
                % disp uialert:
                msg = {'The following recordings are invalid (Name : reason):'};
                for i = size(idx,1)
                    indx = find(~idx(i,:), 1, 'first');
                    switch indx
                        case 1
                            reason = 'dim_names is missing';
                        case 2
                            reason = 'data has is not an image with dimensions Y,X';
                        case 3
                            reason = 'Reference point is missing';
                        case 4
                            reason = 'Pixel ratio is missing';
                        otherwise
                            reason = 'unknown reason';
                    end
                    if indx
                        msg = vertcat(msg, {[strjoin(app.StatsObj.stats_data(i,[1:3]), '--') ': ' reason]});
                    end
                end
                
                app.PlotAvgMapsUIFigure.Visible = 'on';
                uialert(app.PlotAvgMapsUIFigure, msg, 'Data validation failed!', 'CloseFcn',@app.CloseRequestFcn)
                disp('Done');
            end
            
            % Create axis on hidden figure:           
            app.hiddenAx = axes(app.hiddenFig);
            app.hiddenAx.Colormap = app.image_info.cmap;
            app.hiddenAx.CLim = app.image_info.clims; 
            % Remove extra-figure that is created for some reason when the
            % app is opened.
%             h = setdiff(findall(0,'Type', 'Figure'), [app.currFigs; app.hiddenFig;app.PlotAvgMapsUIFigure]);
%             if ~isempty(h)
%                 delete(h);
%             end
        end

        % Close request function: PlotAvgMapsUIFigure
        function CloseRequestFcn(app, event)
            delete(findall(0, 'Tag', 'hiddenFig'));
            h = findall(0,'Tag', 'avgMapFig');
            if ~isempty(h)
                answer = questdlg('Keep figures open?', 'Close app', 'Yes', 'Close all', 'Cancel', 'Close all');                
                if strcmpi(answer, 'yes')
                    arrayfun(@(x) set(x, 'Tag', '','CloseRequestFcn', 'closereq'), h);
                elseif strcmpi(answer, 'close all')
                    delete(h);                                       
                    % Delete logical mask figure:
                    h2 = findall(0,'Tag', 'MskFig');
                    delete(h2)
                else
                    return
                end
            end
            
            
            delete(app)
        end

        % Button pushed function: CreatemapsButton
        function CreatemapsButtonPushed(app, event)
            % This callback performs the averaging of the data stored in
            % the "StatsObj" object.
            h = findall(0, 'Tag', 'avgMapFig');
            % Close currently opened figures:
            if ~isempty(h)
                delete(h);
            end
            % Validate for logical masks:                                    
            if app.UselogicalmasksCheckBox.Value
                roiFile = uigetfile(fullfile(app.StatsObj.list_of_objs{1}.SaveFolder, 'ImagingReferenceFrame.mat'),...
                    'Select the Imaging Reference file');                
                app.mskArr = cell(size(app.StatsObj.list_of_objs));
                for i = 1:length(app.mskArr)                    
                    try
                        mskFileName = findMyROIfile(roiFile, app.StatsObj.list_of_objs{i});    
                        mtf = matfile(mskFileName);
                        app.mskArr{i} = mtf.logical_mask;
                    catch ME
                        ME = addCause(ME, MException('umIToolbox:umIToolbox:missingInput', ['Logical mask not found in  file: ' roiFile]));
                        throw(ME)                                                               
                    end                                       
                end                
            else
                app.mskArr = {};
            end
            % Create the average maps:
            app.calcAvgMaps;
            % Plot maps:
            app.plotMaps;
            % Update CLims:
            app.colorscaleButtonGroupSelectionChanged;
            % Enable buttons:
            app.ExportButton.Enable = 'on';
            app.SetcolormapButton.Enable = 'on';
        end

        % Button pushed function: SetcolormapButton
        function SetcolormapButtonPushed(app, event)
            % Changes the colormap and it's scale of  the currently opened
            % images.
            disp('changing colormap...')
            app.SetcolormapButton.Enable = 'off';
            if strcmpi(app.colorscaleButtonGroup.SelectedObject.Text, 'uniform')                
                h = imagApps_Opts(app,'cmap', app.hiddenAx, false);
            else
                h = imagApps_Opts(app,'cmap', app.hiddenAx, true);
            end
            waitfor(h)
            app.SetcolormapButton.Enable = 'on';                   
        end

        % Selection changed function: colorscaleButtonGroup
        function colorscaleButtonGroupSelectionChanged(app, event)
            h = findall(0,'Tag', 'avgMapFig');
            if isempty(h)
                return
            end
            b_hasCLim = any(strcmpi('CLim', app.hLink.PropertyNames));
            if strcmpi(app.colorscaleButtonGroup.SelectedObject.Text, 'uniform')
                app.image_info.clims = [min([app.avgMapArr{:}],[],'all') max([app.avgMapArr{:}],[],'all')];
                app.image_info.clims_def = app.image_info.clims;
                if ~b_hasCLim
                    addprop(app.hLink, 'CLim')
                end
            else
                if b_hasCLim
                    removeprop(app.hLink, 'CLim');                    
                end
                if ~isempty(h)
                    for i = 1:length(h)
                        imgH = findobj(h(i), 'Tag', 'myImg');                        
                        lims = [];
                        for j = 1:length(imgH)
                            lims = [lims; min(imgH(j).CData, [],'all') max(imgH(j).CData, [],'all')];                            
                        end
                        arrayfun(@(x) set(x.Parent, 'CLim', [min(lims,[],'all') max(lims, [],'all')]), imgH);
                    end
                end                
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            % Exports currenlty opened figures to an image file.
            
            % CD to a path inside the project's savefolder:
            cd(app.StatsObj.list_of_objs{1}.SaveFolder);
            % These are the options to save the plots:
            fig_filter = {'*.png', 'PNG 24-bit (*.png)'; '*.jpg', 'JPEG 24-bit  (*.jpg)'; '*.tiff', 'Uncompressed TIF (*.tiff)'};            
            print_dict = {'-dpng', '-djpeg', '-dtiffn'};
            [filename, Path, indx] = uiputfile(fig_filter, 'Export Image(s)', 'data.png');
            if filename == 0
                return
            end
            [~,name,ext] = fileparts(filename);
            % Save figures:
            h = findall(0,'Tag', 'avgMapFig');
            w = waitbar(0,'Exporting figures...');
            for i = 1:length(h)
                prevState = h(i).WindowState;
                set(h(i), 'Visible', 'off', 'WindowState', 'maximized');
                print(fullfile(Path, [name '_' h(i).Name ext]), h(i), print_dict{indx},'-opengl');
                set(h(i),'Visible', 'on', 'WindowState', prevState);
                waitbar(i/length(h), w);
            end
            close(w)
            % Open folder with exported images:
            if (ispc) % For windows
                winopen(Path)
            elseif (ismac) % Macs
                system(['open ' Path]);
            else % Linux
                system(['xdg-open ' Path]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlotAvgMapsUIFigure = uifigure('Visible', 'off');
            app.PlotAvgMapsUIFigure.Name = 'PlotAvgMaps';
            app.PlotAvgMapsUIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseRequestFcn, true);
            app.OptionsPanel = uipanel(app.PlotAvgMapsUIFigure);
            app.OptionsPanel.Title = 'Options';
            app.UselogicalmasksCheckBox = uicheckbox(app.OptionsPanel);
            app.UselogicalmasksCheckBox.Tooltip = {''};
            app.UselogicalmasksCheckBox.Text = ' Use logical masks';
            app.SetcolormapButton = uibutton(app.OptionsPanel, 'push');
            app.SetcolormapButton.ButtonPushedFcn = createCallbackFcn(app, @SetcolormapButtonPushed, true);
            app.SetcolormapButton.Enable = 'off';
            app.SetcolormapButton.Tooltip = {''};
            app.SetcolormapButton.Text = {'Set'; 'colormap'};
            app.colorscaleButtonGroup = uibuttongroup(app.OptionsPanel);
            app.colorscaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @colorscaleButtonGroupSelectionChanged, true);
            app.colorscaleButtonGroup.Title = 'color scale';
            app.AutoButton_2 = uiradiobutton(app.colorscaleButtonGroup);
            app.AutoButton_2.Text = 'Auto';
            app.AutoButton_2.Value = true;
            app.UniformButton = uiradiobutton(app.colorscaleButtonGroup);
            app.UniformButton.Text = 'Uniform';
            app.SpatFiltsigmaEditFieldLabel = uilabel(app.OptionsPanel);
            app.SpatFiltsigmaEditFieldLabel.Text = 'Spat. Filt. sigma';
            app.SpatFiltsigmaEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.SpatFiltsigmaEditField.Limits = [0 Inf];
            app.CreatemapsButton = uibutton(app.PlotAvgMapsUIFigure, 'push');
            app.CreatemapsButton.ButtonPushedFcn = createCallbackFcn(app, @CreatemapsButtonPushed, true);
            app.CreatemapsButton.Tooltip = {''};
            app.CreatemapsButton.Text = {'Create'; 'maps'};
            app.ExportButton = uibutton(app.PlotAvgMapsUIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Tooltip = {''};
            app.ExportButton.Text = 'Export ';
            % Show the figure after all components are created
            app.PlotAvgMapsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotAvgMaps(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlotAvgMapsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlotAvgMapsUIFigure)
        end
    end
end