classdef PlotCorrMatrix < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        OptionsPanel               matlab.ui.container.Panel
        reorderMatrixButton        matlab.ui.control.Button
        ShowMatrixasDropDownLabel  matlab.ui.control.Label
        ShowMatrixasDropDown       matlab.ui.control.DropDown
        HidediagonalCheckBox       matlab.ui.control.CheckBox
        SetcolormapButton          matlab.ui.control.Button
        LockplotYaxisCheckBox      matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        statsObj % Handle to StatsManager object
        dataArr  % Data array from "dataArr" property of statsObj.
        imgOptsApp % Handle to ImagApps_Opts app.
        hiddenAx % dummy axis to interact with imgOptsApp.
        matrix_struct = struct() % Structure containing the Merged Matrix from the observations listed in statsObj.
        figs = struct() % structure containing handles of matrix and plot figures.
        data = struct() % structure containing the data to be shown.
        baselineIdx = 1 % Index(es) of the data that is considered as baseline.
        labels % X/YTickLabels of matrices.
        AlphaMatrix % Logical mask to show/hide diagonal;
        color_list = jet(64); % Colormap used to label groups.
        
        dcm_obj  % DataCursorMode object handle for custom datatips.
        currFigs = findall(0, 'Type', 'Figure'); % Currently opened figures
    end
    
    properties (Access = public)
        image_info = struct('clims', [-1 1],'clims_def',[-1 1], 'cmap', colormap('parula'), 'imageData',[]) % Matrix CLims and cmap.
        obsList % List of observations. Used by PlotMatrix_reorderObs app to change the order of observations in the correlation matrix.
    end
    
    methods (Access = private)
        
        
        function genMatrixArray(app)
            % This function creates an array of Matrices per acquisition,
            % per group and stores it in a structure (matrix_struct).
            
            target_obs = app.statsObj.obs_list;
            groupList = unique(app.statsObj.list_of_groups);
            a = 1;
            for i = 1:numel(groupList)
                for j = 1:max([app.dataArr.AcquisitionIndx])
                    idx = [app.dataArr.AcquisitionIndx]' == j & strcmp(groupList{i}, {app.dataArr.groupID}');
                    app.matrix_struct(a).groupName = groupList{i};
                    app.matrix_struct(a).IndxList = find(idx); % Index of element in app.dataArr structure!
                    app.matrix_struct(a).acqIdx = j; % Acquisition index.
                    app.matrix_struct(a).data = createMatrix(target_obs, app.dataArr(idx));
                    app.matrix_struct(a).avg = mean(app.matrix_struct(a).data,3,'omitnan');
                    app.matrix_struct(a).sem = std(app.matrix_struct(a).data,0,3,'omitnan')./sqrt(size(app.matrix_struct(a).data,3));
                    app.matrix_struct(a).dataArrIndx = idx;
                    a = a+1;
                end
            end
            
            % Local function
            function out = createMatrix(obsList, stats_struct)
                % This function merges all data from obsStruct into a 3-D
                % matrix with sizes n_obsList x n_obsList x n_obsStruct.
                % Inputs
                % obsList (cell array of char) = list of observation IDs
                % stats_struct (struct array) = structure subset from  app.dataArr containing observation IDs
                % and their respective data for a given acquisition per group.
                % Ouput
                % out (3D array) = numerical array containing data merged
                % from obsStruct.
                
                out = nan(numel(obsList),numel(obsList), length(stats_struct));
                for ii = 1:length(stats_struct)
                    for jj = 1:numel(obsList)
                        % Retrieve original observation list:
                        [idx_obs,lb] = ismember(obsList, stats_struct(ii).observationID);lb(lb==0) = [];
                        idx_curr_obs = strcmp(obsList{jj}, stats_struct(ii).observationID);
                        if ~any(idx_curr_obs)
                            continue
                        end
                        % Rearrange observation data
                        obsData = stats_struct(ii).data(lb);
                        % Rearrange data inside each observation.
                        [~, lb2] = ismember(stats_struct(ii).observationID(lb), stats_struct(ii).labels);
                        out(jj,idx_obs,ii) = obsData{idx_curr_obs(lb)}(1,lb2);
                    end
                end
            end
        end
        
        function createMatrixFig(app, flag)
            % This function creates a matrix figure and its callbacks
            % Input
            % flag (str) = 'create' OR 'update'
            if strcmp(flag, 'create')
                fH = figure('Name', 'Matrices', 'NumberTitle',"off", 'MenuBar',"none", 'ToolBar',"figure",  'Units', 'normalized', ...
                    'Position',[0.1385 0.5444 0.6661 0.3889], "CloseRequestFcn",@app.DoNothing);
                app.AlphaMatrix = true(size(app.data(1).MatData(1).avg));
                % Create a merged matrix so the ImagApps_Opts app can retrieve min and max vals of avg matrices:
                MatData = [app.data.MatData];
                app.image_info.imageData = [MatData.avg];
            else
                fH = app.figs.matFig.fH;
                clf(fH)
            end
            
            groups = unique(app.statsObj.list_of_groups);
            AcqIdx_list = unique(arrayfun(@(x) x.MatData.Idx, app.data));
            a = 1;
            MatData = [app.data.MatData];
            for i = 1:numel(groups)
                for j = 1:numel(AcqIdx_list)
                    s(a) = subplot(numel(groups), numel(AcqIdx_list),a, 'Parent', fH);
                    idx = strcmp(groups{i},{app.data.groupName}) & [MatData.Idx] == AcqIdx_list(j);
                    img(a) = imagesc(s(a), MatData(idx).avg, 'AlphaData',app.AlphaMatrix);
                    img(a).Tag = 'my_matrix';
                    img(a).ButtonDownFcn = @app.pixSelect;
                    img(a).HandleVisibility = 'callback';
                    set(s(a), 'CLim', app.image_info.clims);
                    colormap(s(a), app.image_info.cmap);
                    set(s(a), 'YTick', 1:numel(app.labels), 'XTick',1:numel(app.labels),'YTickLabel', app.labels, 'XTickLabel', app.labels,...
                        'XTickLabelRotation', 90,'FontSize',8, 'TitleFontSizeMultiplier', 1.25, 'NextPlot', 'replacechildren', 'DataAspectRatio', [1 1 1]);
                    if MatData(idx).b_isBsln
                        s(j).Title.String = 'Baseline';
                    else
                        s(j).Title.String = ['Acq# ' num2str(AcqIdx_list(j))];
                    end
                    red_dot(a) = plot(s(a),1,2,'ro', 'MarkerSize',6);
                    if j == 1
                        s(a).YLabel.String = groups{i};
                        s(a).YLabel.FontSize = 10;
                        s(a).YLabel.FontWeight = 'bold';
                        s(a).YLabel.Interpreter = 'none';
                    end
                    a=a+1;
                end
            end
            cb = colorbar(s(1), 'westoutside'); cb.Position = [0.04 .6 .02 .36];
            % Link Color and axis scaling properties:
            
            hlink = linkprop([s, app.hiddenAx], {'CLim', 'XLim', 'YLim', 'Colormap'});
            % Save handles to fig struct:
            app.figs.matFig.fH = fH;
            app.figs.matFig.imgH = img;
            app.figs.matFig.pltH = red_dot;
            app.figs.matFig.axH = s;
            app.figs.matFig.hlink = hlink;
        end
        
        function createPlotFig(app)
            % This function creates the plot figure and sets its callbacks
            
            % The data will be plotted as requested.
            
            % Create Figure
            app.figs.tmFig.fH = figure('Name', 'Timeline', 'NumberTitle',"off", 'MenuBar',"none", 'ToolBar',"figure", 'Units', 'normalized', ...
                'Position',[0.1375 0.0861 0.6672 0.3889],"CloseRequestFcn",@app.DoNothing);
            % Create axis
            app.figs.tmFig.axH = axes('Parent', app.figs.tmFig.fH, 'Units', 'normalized', 'Position',[.05 .13 .9 .75], 'NextPlot','replacechildren', ...
                'Color',[.9 .9 .9]);  
            % Add Data tips to plots:
            app.dcm_obj = datacursormode(app.figs.tmFig.fH);
            set(app.dcm_obj, 'DisplayStyle', 'datatip', 'Enable', 'off','UpdateFcn', @app.showDataTip, 'Interpreter', 'tex');
            % Create plots
            gNames = unique({app.data.groupName});
            % Create color range:
            colors = app.color_list(linspace(1,64,numel(gNames)),:);
            hold(app.figs.tmFig.axH, 'on')
            for i = 1:numel(gNames)
                idx_g = strcmp(gNames{i}, {app.data.groupName});
                tm_data = [app.data(idx_g).TmData];
                tm_UserData = arrayfun(@(x) x.TmData.dataArrIndx, app.data(idx_g), 'UniformOutput',false, 'ErrorHandler',@nan);
                tm_UserData = vertcat(tm_UserData{:})';
                x = 1:numel(unique([tm_data.Idx]));
                y = cellfun(@(x) x(1,2), {tm_data.avg});
                ysem = cellfun(@(x) x(1,2), {tm_data.SEM});
                % Create errorbar plot
                errPlt(i) = errorbar(app.figs.tmFig.axH, x,y,ysem, 'Tag', ['eb_' gNames{i}]);
                set(errPlt(i), 'Color', colors(i,:), 'LineWidth', 1.5, 'CapSize', 20);                
                % Create Scatterplot
                acqLen = arrayfun(@(x) size(x.data,3), tm_data);
                x_scat = arrayfun(@(a,b) repmat(a,1,b), x, acqLen, 'UniformOutput',0); x_scat = [x_scat{:}];
                y_scat = arrayfun(@(x) squeeze(x.data(1,2,:)), tm_data, 'UniformOutput',0); y_scat = vertcat(y_scat{:})';
                scatPlt(i) = scatter(x_scat, y_scat, 'Tag', ['scat_' gNames{i}], 'Parent', app.figs.tmFig.axH);
                % Update UserData for datatips:
                s.XPos = x_scat;
                s.YPos = y_scat;
                s.Indx = tm_UserData;
                % Apply params to scatter plot:
                set(scatPlt(i), 'MarkerFaceColor',colors(i,:), 'MarkerEdgeColor', 'k', 'MarkerFaceAlpha',.4, 'UserData', s);
            end
            
            hold(app.figs.tmFig.axH, 'off')            
            % Set axes labels
            x_tickLabels = unique(arrayfun(@(a) num2str(a.TmData.Idx),app.data, 'UniformOutput',0));            
            bsln_idx = arrayfun(@(x) x.TmData.b_isBsln, app.data);
            bsln_idx = unique(arrayfun(@(x) x.TmData.Idx, app.data(bsln_idx)));
            x_tickLabels(bsln_idx) = {'baseline'};
            
            set(app.figs.tmFig.axH, 'XTick',...
                1:numel(x_tickLabels),...
                'XTickLabel', x_tickLabels,...
                'XLim', [0 app.figs.tmFig.axH.XLim(2) + 1]);
            xlabel(app.figs.tmFig.axH, 'Acquisition index');
            ylabel(app.figs.tmFig.axH, 'Values');
            % Set legend
            legend(app.figs.tmFig.axH, scatPlt, gNames, 'Location',"northeastoutside", 'Interpreter',"none");
            % Set title
            app.figs.tmFig.axH.Title.String = [app.figs.matFig.axH(1).YTickLabel{2} ' vs '  app.figs.matFig.axH(1).XTickLabel{1}];
            app.figs.tmFig.axH.Title.Interpreter = 'none';
            % Hide plot handles:
            arrayfun(@(x) set(x, 'HandleVisibility', 'callback'), errPlt)
            arrayfun(@(x) set(x, 'HandleVisibility', 'callback'), scatPlt)
            
        end
        
        %%%%%%%%%%%%%%%%%%%%%%% CUSTOM CALLBACKS%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function pixSelect(app,src,evnt)
            % This callback shows a cursor and text over the clicked
            % pixel in a matrix.
            selPt = round(evnt.IntersectionPoint([1 2]));
            if ~src.AlphaData(selPt(2),selPt(1))
                return
            end
            % clear previous text:
            axH = src.Parent.Parent.Children;
            ht = arrayfun(@(x) findall(x.Children, 'type', 'Text'), axH, 'UniformOutput',0);
            cellfun(@(x) delete(x), ht);
            % Write x-y pair name to plot;
            text(src.Parent, selPt(1), selPt(2), [src.Parent.YTickLabel{selPt(2)} ' vs ' src.Parent.XTickLabel{selPt(1)}],...
                'HorizontalAlignment', 'left', 'VerticalAlignment','bottom', 'FontSize',7,...
                'Interpreter','none');
            % Update position of red dot
            arrayfun(@(x) set(x, 'XData', selPt(1), 'YData', selPt(2)), app.figs.matFig.pltH);
            % Update Timeline plot
            app.updatePlot;
        end
        
        function DoNothing(~,~,~)
            %%% NOTHING %%%
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function updatePlot(app)
            % This function plots the data on the TimeLine figure once the
            % user clicks on a region of the matrix.
            
            % Find X,Y location of a red dot
            x = app.figs.matFig.pltH(1).XData;
            y = app.figs.matFig.pltH(1).YData;
            % Change figure title:
            app.figs.tmFig.axH.Title.String = [app.figs.matFig.axH(1).YTickLabel{y} ' vs ' app.figs.matFig.axH(1).YTickLabel{x}];
            gNames = unique({app.data.groupName});
            
            % Update Data points in axis
            for i = 1:numel(gNames)
                idx_g = strcmp(gNames{i}, {app.data.groupName});
                tm_data = [app.data(idx_g).TmData];
                tm_UserData = arrayfun(@(x) x.TmData.dataArrIndx, app.data(idx_g), 'UniformOutput',false, 'ErrorHandler',@nan);
                tm_UserData = vertcat(tm_UserData{:})';
                % Extract average and SEM data:
                eb_x = 1:numel(unique([tm_data.Idx]));
                eB_y = cellfun(@(a) a(y,x), {tm_data.avg});
                eB_ysem = cellfun(@(a) a(y,x), {tm_data.SEM});
                % Update values in errbar objects
                ebH = findobj(app.figs.tmFig.axH, 'Tag', ['eb_' gNames{i}]);
                set(ebH, 'XData', eb_x, 'YData', eB_y, 'YNegativeDelta', eB_ysem, 'YPositiveDelta', eB_ysem)
                % Extract data for scatter plots:
                acqLen = arrayfun(@(a) size(a.data,3), tm_data);
                x_scat = arrayfun(@(a,b) repmat(a,1,b), eb_x, acqLen, 'UniformOutput',0); x_scat = [x_scat{:}];
                y_scat = arrayfun(@(a) squeeze(a.data(y,x,:)), tm_data, 'UniformOutput',0); y_scat = vertcat(y_scat{:})';
                % Update UserData for datatips:
                s.XPos = x_scat;
                s.YPos = y_scat;
                s.Indx = tm_UserData;
                
                % Update scatter plot:
                scatH = findobj(app.figs.tmFig.axH, 'Tag', ['scat_' gNames{i}]);                
                set(scatH, 'XData', x_scat, 'YData', y_scat, 'UserData', s);
            end
            % Update Y axis limits:
            if ~app.LockplotYaxisCheckBox.Value
                ylim(app.figs.tmFig.axH,'auto')
            end
        end
        
        function updateData(app)
            % This function changes the values of data to be displayed in the matrix
            % and TimeLine figures.
            % Inputs:
            % Mat_dispType (str) = value from ShowMatrixAsDropDown menu
            
            Mat_dispType = app.ShowMatrixasDropDown.Value;
            % Clear data structure:
            app.data = struct();            
            for ind = 1:length(app.matrix_struct)
                app.data(ind).groupName = app.matrix_struct(ind).groupName;
                app.data(ind).MatData.data = app.matrix_struct(ind).data;
                app.data(ind).MatData.avg = app.matrix_struct(ind).avg;
                app.data(ind).MatData.SEM  = app.matrix_struct(ind).sem;
                app.data(ind).MatData.Idx = app.matrix_struct(ind).acqIdx;                
                if app.data(ind).MatData.Idx == app.baselineIdx
                    app.data(ind).MatData.b_isBsln = true;
                else
                    app.data(ind).MatData.b_isBsln = false;
                end                
                app.data(ind).TmData = app.data(ind).MatData; % Copy Data to TimeLine
                app.data(ind).TmData.dataArrIndx = find(app.matrix_struct(ind).dataArrIndx);
            end
            
            % Perform arithmetic operation on data:
            if strcmp(Mat_dispType, 'Data')
                return
            else
                app.data = changeDat(app.data, Mat_dispType);
            end
            
            % Local function
            
            function out = changeDat(data,dispType)
                % Performs arithmetic operations on data
                in = [data.MatData];
                idx_bsln = [in.b_isBsln];
                gNames = unique({data.groupName});
                for ii = 1:numel(gNames)
                    idx_g = strcmp(gNames{ii}, {data.groupName});
                    if numel(idx_g)<2 % Only perform operation in data with 2 or more acquisitions per group.
                        continue
                    end
                    bsln = in(idx_bsln & idx_g);
                    if strcmp(dispType, 'Diff. from baseline')
                        in(idx_g) = arrayfun(@(x) setfield(x, 'avg', (x.avg - bsln.avg)), in(idx_g));
                    else
                        [la,lb] = sort([in(idx_g).Idx]);
                        tmp = in(idx_g); tmp = tmp(lb);
                        for jj = length(tmp):-1:2
                            tmp(jj).avg = tmp(jj).avg - tmp(jj-1).avg;
                        end
                        [~,ib] = ismember([in(idx_g).Idx],la);
                        in(idx_g) = tmp(ib);
                    end                    
                    out = arrayfun(@(x,y) setfield(x,'MatData',y), data,in);
                end
            end
            
        end


        function txt = showDataTip(app,~,evnt)
            % Callback to show object's information in datatip.
          
            switch class(evnt.Target)
                
                case 'matlab.graphics.chart.primitive.Scatter'
                    % Get data point information:
                    indx = evnt.Target.UserData.Indx(evnt.Target.UserData.XPos == evnt.Position(1) & evnt.Target.UserData.YPos == evnt.Position(2));
                    txt = sprintf('X: %d\nValue: %0.3f\nGroupID: %s\nSubjectID: %s\nAcquisitionID: %s\nRecStartDateTime: %s\nAcqIndx: %d', ...
                        evnt.Position(1),evnt.Position(2), app.dataArr(indx).groupID, app.dataArr(indx).SubjectID, ...
                        app.dataArr(indx).AcquisitionID, app.dataArr(indx).RecStartDateTime, app.dataArr(indx).AcquisitionIndx);
                otherwise
                    % Get info from errorbar handle:
                    txt = '';    
                    fNames = {'X: %d\n', 'Mean: %0.3f\n', 'SEM: \\pm%0.4f'};
                    fMap = containers.Map(fNames, {'XData', 'YData', 'YPositiveDelta'});                    
                    for ii = 1:fMap.Count
                        txt = [txt, sprintf(fNames{ii}, evnt.Target.(fMap(fNames{ii}))(evnt.Position(1)))];
                    end
            end
            
            txt = strrep(txt, '_','\_'); % To keep Tex interpreter and avoid subscripting text.
        end
end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, statsObj)
            % This startup function creates the Matrix and the Plot figures
            % with default parameters.
            
            app.UIFigure.Visible = 'off';
            app.statsObj = statsObj;
            app.dataArr = app.statsObj.dataArr;
            app.labels = app.statsObj.obs_list;
            app.obsList = app.labels; % Used to re-order observations in matrix.

            % Update UIFigure position:
            movegui(app.UIFigure, 'northwest'); app.UIFigure.Visible = 'on';
            % Data validation            
            if ~strcmpi(app.statsObj.inputFeatures.dataType, 'matrix') && ~app.statsObj.inputFeatures.b_hasSameDimSize
                app.CloseApp;
                return
            end
            % Build Matrix arrays
            app.genMatrixArray;
            % Prepare data to be displayed:
            app.updateData
            
            % Create Matrix figure
            % Create dummy axis to interact with ImagOpts app:
            f = figure('Visible', 'off');
            app.hiddenAx = axes(f);
            % Remove extra-figure that is created for some reason when the
            % app is opened.
            h = setdiff(findall(0,'Type', 'Figure'), [app.currFigs; f; app.UIFigure]);
            if ~isempty(h)
                delete(h);
            end
            % Create Matrix figure:
            app.createMatrixFig('create'); 
            
            % Disable Baseline-related interface elements if there is only
            % one acquisition:
            if length(app.matrix_struct) == 1                
                app.ShowMatrixasDropDown.Enable = 'off';
            end
            % Create Plot figure
            app.createPlotFig
            % Show Matrices and plots
            app.updatePlot;
            % Reorder matrix based on Mouse Allen Brain Atlas:
            app.reorderMatrixButtonPushed('startup');
            
            
        end

        % Close request function: UIFigure
        function CloseApp(app, event)
            delete(app.hiddenAx.Parent)
            try
                delete(app.figs.matFig.fH); % delete matrix figure
                delete(app.figs.tmFig.fH); % delete time figure               
                delete(app) % delete main figure               
            catch                
                delete(app)                
            end
        end

        % Value changed function: ShowMatrixasDropDown
        function ShowMatrixasDropDownValueChanged(app, event)
            
            app.updateData;
            app.createMatrixFig('update');
            app.updatePlot;
            % Update imageData in image_info:
            imgdt = arrayfun(@(x) x.CData, app.figs.matFig.imgH, 'UniformOutput',0);
            app.image_info.imageData = [imgdt{:}];
        end

        % Value changed function: HidediagonalCheckBox
        function HidediagonalCheckBoxValueChanged(app, event)
            % This callback hides/shows the upper diagonal of the
            % correlation matrices.
            if app.HidediagonalCheckBox.Value
                for i = 1:size(app.AlphaMatrix,2)
                    app.AlphaMatrix(i,i:end) = false;
                end
            else
                app.AlphaMatrix = true(size(app.AlphaMatrix));
            end
            app.createMatrixFig('update');
        end

        % Button pushed function: SetcolormapButton
        function SetcolormapButtonPushed(app, event)
            % Launches the imagApps_Opts app to set the current image's
            % colormap.
            
            app.imgOptsApp = imagApps_Opts(app, 'cmap',app.hiddenAx, false);
            uiwait(app.imgOptsApp.ImageOptsUIFigure);
%             disp('Setting Matrix opts...');
%             app.createMatrixFig('update');
            % Update YLim in time figure:
            if app.LockplotYaxisCheckBox.Value
                app.LockplotYaxisCheckBoxValueChanged
            end
        end

        % Button pushed function: reorderMatrixButton
        function reorderMatrixButtonPushed(app, event)
            % This function allows the user to reorder the observation list
            if ischar(event)
                % This condition is a workaround to reorder the observation
                % list from startup!
                
                % If the observations were created from the Mouse Allen Brain
                % Atlas, reorder them to segregate into Left and right
                % hemispheres and group by modality:
                a = load(fullfile(erase(mfilename('fullpath'),['GUI' filesep 'StatsModule' filesep 'PlotCorrMatrix']), 'GUI', 'DataViz', 'mouse_ctx_borders'), 'atlas');
                areatag_sorted = [];
                idxL = endsWith(a.atlas.areatag, '_L');
                [~,indx_mod] = sort(a.atlas.area_ClusterNames(idxL)); % Sort left areas by modality;
                areatagL = a.atlas.areatag(idxL);
                areatag_sorted =[areatag_sorted; areatagL(indx_mod)];
                [~,indx_mod] = sort(a.atlas.area_ClusterNames(~idxL)); % Sort right areas by modality;
                areatagR = a.atlas.areatag(~idxL);
                areatag_sorted =[areatag_sorted; areatagR(indx_mod)];
                % Abort reordering if there are observations that are not
                % from the Atlas:
                if ~all(ismember(app.obsList, areatag_sorted))
                    return
                end
                app.obsList = areatag_sorted(ismember(areatag_sorted, app.obsList));
            else
                reOrderObs_dlg = PlotMatrix_reorderObs(app);
                uiwait(reOrderObs_dlg.UIFigure)
            end
            disp('Re-ordering Matrices...')
            % Find re-ordered indices
            [~, lb] = ismember(app.obsList, app.labels);
            disp('Updating matrices...')
            % Change X,Y order of matrices in app.data
            for i = 1:length(app.matrix_struct)
                app.matrix_struct(i).data = app.matrix_struct(i).data(lb,lb,:);
                app.matrix_struct(i).avg = app.matrix_struct(i).avg(lb,lb);
                app.matrix_struct(i).sem = app.matrix_struct(i).sem(lb,lb);
            end
            % Update labels
            app.labels = app.obsList;
            disp('Updating figures...')
            % Update Data and figures
            app.updateData;
            app.createMatrixFig('update')
            app.updatePlot;
            % Update show/hide diagonal:
            app.HidediagonalCheckBoxValueChanged;
        end

        % Value changed function: LockplotYaxisCheckBox
        function LockplotYaxisCheckBoxValueChanged(app, event)
                % This callback toggles the Y axis limits in the plot on the PlotFig.
                if app.LockplotYaxisCheckBox.Value
                    % Lock the plot`s Y axis to the same values as the CLims in
                    % the matrix plots:
                    set(app.figs.tmFig.axH, 'YLim', app.figs.matFig.axH(1).CLim,'YLimMode', 'manual')
                else
                    set(app.figs.tmFig.axH, 'YLimMode', 'auto');
                end
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'PlotCorrMatrix';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CloseApp, true);
            app.OptionsPanel = uipanel(app.UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.reorderMatrixButton = uibutton(app.OptionsPanel, 'push');
            app.reorderMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @reorderMatrixButtonPushed, true);
            app.reorderMatrixButton.Text = {'re-order'; 'Matrix'};
            app.ShowMatrixasDropDownLabel = uilabel(app.OptionsPanel);
            app.ShowMatrixasDropDownLabel.Text = 'Show Matrix as:';
            app.ShowMatrixasDropDown = uidropdown(app.OptionsPanel);
            app.ShowMatrixasDropDown.Items = {'Data', 'Diff. from baseline', 'Diff. from previous'};
            app.ShowMatrixasDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowMatrixasDropDownValueChanged, true);
            app.ShowMatrixasDropDown.Value = 'Data';
            app.HidediagonalCheckBox = uicheckbox(app.OptionsPanel);
            app.HidediagonalCheckBox.ValueChangedFcn = createCallbackFcn(app, @HidediagonalCheckBoxValueChanged, true);
            app.HidediagonalCheckBox.Text = 'Hide diagonal';
            app.SetcolormapButton = uibutton(app.OptionsPanel, 'push');
            app.SetcolormapButton.ButtonPushedFcn = createCallbackFcn(app, @SetcolormapButtonPushed, true);
            app.SetcolormapButton.Text = {'Set'; 'colormap'};
            app.LockplotYaxisCheckBox = uicheckbox(app.OptionsPanel);
            app.LockplotYaxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockplotYaxisCheckBoxValueChanged, true);
            app.LockplotYaxisCheckBox.Text = 'Lock plot Y axis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotCorrMatrix(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end