classdef PlotLongData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FiguresMenu                 matlab.ui.container.Menu
        SavetoFIGMenu               matlab.ui.container.Menu
        ExportMenu                  matlab.ui.container.Menu
        DataMenu                    matlab.ui.container.Menu
        SelectMenu                  matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        DatainmainfigurePanel       matlab.ui.container.Panel
        DatainmainfigureDropDown    matlab.ui.control.DropDown
        AxissettingsPanel           matlab.ui.container.Panel
        YlabelEditFieldLabel        matlab.ui.control.Label
        YlabelEditField             matlab.ui.control.EditField
        YscaleButtonGroup           matlab.ui.container.ButtonGroup
        AutoButton                  matlab.ui.control.RadioButton
        UniformButton               matlab.ui.control.RadioButton
        LinearCheckBox              matlab.ui.control.CheckBox
        LogCheckBox                 matlab.ui.control.CheckBox
        FixedButton                 matlab.ui.control.RadioButton
        minEditFieldLabel           matlab.ui.control.Label
        minEditField                matlab.ui.control.NumericEditField
        maxEditFieldLabel           matlab.ui.control.Label
        maxEditField                matlab.ui.control.NumericEditField
        GroupplottypeDropDownLabel  matlab.ui.control.Label
        GroupplottypeDropDown       matlab.ui.control.DropDown
        XjitterCheckBox             matlab.ui.control.CheckBox
        XlabelEditFieldLabel        matlab.ui.control.Label
        XlabelEditField             matlab.ui.control.EditField
        VariationtypeButtonGroup    matlab.ui.container.ButtonGroup
        StandarddeviationSDButton   matlab.ui.control.RadioButton
        StandardErrorofthemeanSEMButton  matlab.ui.control.RadioButton
        ConfidenceintervalCIButton  matlab.ui.control.RadioButton
        TimevectorstyleButtonGroup  matlab.ui.container.ButtonGroup
        ShadedButton                matlab.ui.control.RadioButton
        LinesButton                 matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        statsObj % StatManager object containing ROI data.
        mainFig % Handle to the main figure.
        summaryFigs % Handle to Summary figures.
        mainAx % Handle to axis in main figure;
        scatData % Structure containing the metaData of each item from the scatter plots in the summary figures.
        mainAxMetaData % MetaData for each scatter point in the main figure;
        dcm_obj % DataCursor mode object for datatips in Main Figure.
        cmp % current mouse position. This property is updated by the WindowButtonMotionFcn of the main fig.
        errMeasure % Measurement of error to be represented in the error bars (i.e. 'SD', 'SEM', '95%CI').
        hlink  % linkprop object handle. 
        varType = 'std' % name of variation measure to be plotted using error bars.
        summaryAxH % List of axis handles from the summary figures.
        b_isTimeVector % Boolean to indicate if the input data is a time vector.
        Freq % Sampling frequency. This property is used by time vector plots ONLY.
        b_hasEvents  = false% boolean to indicate if the input data has "E"vent dimension.
        elYlim % event Listener for event bar resizing after changes in YLim.
    end
    
    properties (Access = public)
        data % This is the data that from StatsObj that was repackaged for plotting by the "Data_selector" app.
    end
    
    methods (Access = private)
        
        function createSummaryFigs(app)
            % This method creates figures with line + scatter plots. A maximum of 8 plots
            % is allowed per figure.
            disp('Creating figures...')
            if strcmp(app.UIFigure.Visible, 'on')
                w = uiprogressdlg(app.UIFigure, 'Title', 'Please wait', 'Message', 'Creating Figures', 'Indeterminate','on');
            else
                w = [];
            end
            
            % Get Timing of event trigger for plotting, if applicable:
            if ( app.b_hasEvents )
                preEvTm = mean(cellfun(@(x) x.preEventTime_sec, app.statsObj.MfileArr)); 
            end
            
            % Delete existing figures:
            delete(app.summaryFigs) 
            % Create first figure:
            app.summaryFigs = figure('Name', ['Summary figure #' num2str(1)], 'NumberTitle', 'off',...
                'Position',[0 0 600 700],'Visible','off', 'CloseRequestFcn',@app.DoNothing);
            movegui(app.summaryFigs(1), 'southeast');
            cnt = 1; % Axes counter.
            % Create a plot for each observation:              
            plotTitleList = {app.data.plotTitle};
            b_isBoxPlot = contains(app.GroupplottypeDropDown.Value, 'box','IgnoreCase', true);
            for ii = 1:numel(plotTitleList)
                % Create axis:
                s(ii) = subplot(4,2,cnt,"replace",'Parent',app.summaryFigs(end), 'Tag', plotTitleList{ii});
                if app.b_isTimeVector                    
                    %Plot time vector:
                    switch app.TimevectorstyleButtonGroup.SelectedObject.Text
                        case 'Shaded'
                            s(ii) = plotTimeVector(app.data(ii).data,app.varType,'plotType','shaded','group',app.data(ii).groupIndx,'color',app.data(ii).gColor,...
                                'axHandle',s(ii),'dataTip',app.data(ii).dataTip, 'Freq', app.Freq);
                        case 'Lines'
                            s(ii) = plotTimeVector(app.data(ii).data,app.varType,'plotType','line','group',app.data(ii).groupIndx,'color',app.data(ii).gColor,...
                                'axHandle',s(ii),'dataTip',app.data(ii).dataTip, 'Freq', app.Freq);
                    end
                    % Plot Event bar:
                    if ( app.b_hasEvents  ) && ~isempty(app.Freq)
                                                                                                                   
                        l = line(s(ii), [preEvTm preEvTm], [s(ii).YLim(1), s(ii).YLim(2)], 'Color', 'k', 'Tag', 'evBar');                                               
                        uistack(l,'bottom');
                    end
                else                                                          
                    % Reshape data for plotting:
                    xIn = repmat([1:numel(app.data(1).xLabel)],size(app.data(ii).data,1),1);xIn = xIn(:);
                    gIn = repmat(app.data(ii).groupIndx,1,numel(app.data(1).xLabel)); gIn = gIn(:);
                    yIn = app.data(ii).data(:);
                    dataTip = reshape(app.data(ii).dataTip,[],1);
                    % Plot the data in the current axis:
                    s(ii) = stripchart(xIn,yIn,app.varType,'group',gIn, 'axHandle',s(ii),'color',app.data(ii).gColor,...
                        'Xjitter', app.XjitterCheckBox.Value, 'Boxplot', b_isBoxPlot,'dataTip', dataTip);
                    set(s(ii),'XLim', [0 size(app.data(1).data,2)+1], 'XTick',1:size(app.data(1).data,2), 'XTickLabel', app.data(1).xLabel, ...
                    'TickLabelInterpreter', 'none', 'Color',[.95 .95 .95], 'XTickLabelRotation', -23);
                end
                    
                % Add Title:
                title(s(ii),plotTitleList{ii}, 'Interpreter',"none");                
                % Create new figure if the number of axis reaches "8":
                cnt = cnt+1;
                if ( cnt > 8 )
                    cnt = 1;
                    if ( ii < numel(plotTitleList) )
                        app.summaryFigs = vertcat(app.summaryFigs,...
                            figure('Name', ['Summary figure #' num2str(length(app.summaryFigs)+1)], 'NumberTitle', 'off',...
                            'Position',[0 0 600 700],'Visible','off', 'CloseRequestFcn',@app.DoNothing));
                        movegui(app.summaryFigs(end), 'southeast');
                    end
                end
            end
            app.summaryAxH = s;
            % Link axes properties:
            app.hlink = linkprop(app.summaryAxH,{'XLim','YLimMode', 'YScale'}); 
            % Add event listener to resize event bar, if applicable:
            if ( app.b_hasEvents )
                app.elYlim = addlistener([app.summaryAxH, app.mainAx], 'YLim', 'PostSet',@app.updateEvBars);
            end
            % Show figures:
            arrayfun(@(x) set(x, 'Visible', 'on'), app.summaryFigs);
            disp('Done.')
            delete(w);
            
        end
                
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% CUSTOM CALLBACKS %%%%%%%%%%%%%%%%%%%%%%
        function DoNothing(~,~,~)
            % EMPTY!%
        end
        
        function txt = showDataTip(app,~,evnt)
            % Callback to show object's information in datatip.
            txt = '';
            % Identify the type of the selected graphical object:            
            switch class(evnt.Target)
                case 'matlab.graphics.chart.primitive.Scatter'
                    objType = 'scatter';
                case {'matlab.graphics.primitive.Line','matlab.graphics.chart.primitive.Line' ,'matlab.graphics.primitive.Patch'}
                    childNames = arrayfun(@(x) x.Tag, evnt.Target.Parent.Children, 'UniformOutput',false);
                    if any(contains(childNames,'Box', 'IgnoreCase',true))
                        objType = 'boxplot';
                    elseif any(contains(childNames,'CapLine', 'IgnoreCase',true))
                        objType = 'errorbar';
                    else
                        objType = 'timeplot';                   
                    end
                otherwise 
                    objType = 'unknown';
            end
            
            
            switch objType
                case 'scatter'
                % Data tip for scatter plot
                indx = find(evnt.Target.YData == evnt.Position(2));
                txt = [sprintf('X: %s\nY: %0.3f\n',evnt.Target.Parent.XTickLabel{evnt.Target.UserData.Xpos(indx)},...
                    evnt.Position(2)), evnt.Target.UserData.DataTip{indx}];  
                case 'errorbar'
                    % Data tip for error bar. 
                    % Here, since this app changes  the values of the error bars, we will grab the values
                    % from the graphical objects instead from the UserData when the objects were created.
                    fn = {'Xpos','avgLine','errorLine'};                    
                    gIndx = cellfun(@str2double, regexp(evnt.Target.Parent.Tag,'(?<=errG)\d+(?=X\d+)', 'match'));
                    gName = app.data(1).gName{gIndx};
                    txt = sprintf('Group ID: %s\n',gName);                    
                    for ii = 1:length(fn)
                        if strcmp(fn{ii},'Xpos')
                            val = evnt.Target.Parent.Parent.XTickLabel{evnt.Target.Parent.UserData.Xpos};
                            txt = [txt,sprintf('X: %s\n',val)];
                        elseif strcmpi(fn{ii}, 'avgLine')
                            h = findobj(evnt.Target.Parent, 'Tag', 'avgLine');
                            val = h.YData(1);                            
                            txt = [txt, sprintf('Mean: %0.3f\n',val)];
                        else
                            h = findobj(evnt.Target.Parent, 'Tag', 'errorLine');
                            val = diff(h.YData)/2; % Half of the variation extent (length of the error bar)                            
                            txt = [txt, sprintf('%s: \\pm%f\n', upper(app.varType),val)];
                        end
                    end
                case 'boxplot'
                    % Data tip for Boxplot:
                    fn = flipud(fieldnames(evnt.Target.Parent.UserData));
                    gIndx = cellfun(@str2double, regexp(evnt.Target.Parent.Tag,'(?<=errG)\d+(?=X\d+)', 'match'));
                    gName = app.data(1).gName{gIndx};
                    txt = sprintf('Group ID: %s\n',gName);
                    for ii = 1:length(fn)
                        if strcmp(fn{ii},'Xpos')
                            val = evnt.Target.Parent.Parent.XTickLabel{evnt.Target.Parent.UserData.Xpos};
                            txt = [txt,sprintf('X: %s\n',val)];
                        else
                            val = evnt.Target.Parent.UserData.(fn{ii});
                            txt = [txt, sprintf('%s: %0.3f\n', fn{ii},val)];
                        end
                        
                    end
                case 'timeplot'
                    evTime = 0;
                    if app.Freq
                        xPos = round(evnt.Position(1)*app.Freq);
                        timeTag = 'second(s)'; 
                        timePrecision = '%0.3f';
                        evH = findobj(app.mainAx, 'Tag', 'evBar');
                        if ~isempty(evH)
                            evTime = evH.XData(1);
                        end                        
                    else
                        xPos = evnt.Position(1);
                        timeTag = '(frame)';
                        timePrecision = '%i';                        
                    end
                    %
                    if strcmp(evnt.Target.Tag, 'evBar')
                        txt = sprintf(['Event Trigger at ' timePrecision ' ' timeTag], evnt.Position(1));
                        return
                    end                    
                    siblings = evnt.Target.Parent.Children;
                    childNames = arrayfun(@(x) x.Tag, siblings, 'UniformOutput',false);                                       
                    % Data tip for time plot:                    
                    myGroup = regexp(evnt.Target.Tag,'(?<=\w*)G\d+', 'match'); myGroup = myGroup{:};
                    gIndx = str2double(erase(myGroup,'G'));
                    GroupID = app.data(1).gName{gIndx};
                    txt = sprintf('GroupID: %s\n', GroupID);
                    if startsWith(evnt.Target.Tag,'avgLn', 'IgnoreCase',true)
                        avg = evnt.Target.UserData.Mean;
                        sH = siblings(strcmp(childNames,['Shade' myGroup]));
                        if ~isempty(sH)
                            txt = [txt, sprintf(['X: ' timePrecision ' ' timeTag '\nTime from Event: ' timePrecision ' ' timeTag...
                                '\nMean: %0.3f\n' upper(app.varType) ': \\pm%0.4f'],...
                                evnt.Position(1), evnt.Position(1) - evTime, avg(xPos), abs(abs(sH.YData(xPos)) - avg(xPos)))];
                        else
                            % Calculate data variation:
                            dataH = siblings(startsWith(childNames, 'dataLn') & endsWith(childNames,myGroup));
                            Ydat = arrayfun(@(x) x.YData(xPos), dataH);
                            stDev = std(Ydat,0,'all','omitnan');
                            switch app.varType
                                case 'std'
                                    varDat = stDev;
                                case 'sem'
                                    varDat = stDev./sqrt(length(Ydat));
                                case 'ci'
                                   varDat = (tinv(0.975,length(Ydat)/2-1)).*(varDat./sqrt(length(Ydat)/2));                                     
                            end
                            txt = [txt, sprintf(['X: ' timePrecision ' ' timeTag '\nTime from Event: ' timePrecision ' ' timeTag...
                                '\nMean: %0.3f\n' upper(app.varType) ': \\pm%0.4f'],...
                                evnt.Position(1),evnt.Position(1) - evTime, evnt.Position(2), varDat)];
                        end
                    elseif startsWith(evnt.Target.Tag,'Shade', 'IgnoreCase',true)
                        avgH = siblings(strcmp(childNames,['avgLn' myGroup]));
                        avg = avgH.UserData.Mean;                                                                   
                        txt = [txt, sprintf(['X: ' timePrecision ' ' timeTag '\nTime from Event: ' timePrecision ' ' timeTag...
                            '\nMean: %0.3f\n' upper(app.varType) ': \\pm%0.4f'],...
                            evnt.Position(1), evnt.Position(1) - evTime, avg(xPos), abs(evnt.Position(2)) - avg(xPos))];
                    elseif startsWith(evnt.Target.Tag,'dataLn', 'IgnoreCase',true)                                                                                                                     
                        % Print X,Y values from the selected point:
                        txt = sprintf(['X: ' timePrecision ' ' timeTag '\nTime from Event: ' timePrecision ' ' timeTag...
                            '\nY: %0.3f'],evnt.Position(1), evnt.Position(1) - evTime, evnt.Position(2));
                        % Add metaData:
                        txt = [txt, sprintf('\n%s',evnt.Target.UserData.DataTip{:})];
                    end                                       
                otherwise
                    % Generic data tip. This should never be reached.
                txt = sprintf('X: %d\nY: %0.3f', evnt.Position(1), evnt.Position(2));            
                
            end                       
            txt = strrep(txt, '_','\_'); % To keep Tex interpreter and avoid subscripting text.
            txt = sprintf(' \\color[rgb]{.4 .4 .4}\\bf%s',txt);               
        end

        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% %%%%%%
       
        
        function addLegend(app, ax, location)
            % ADDLEGEND adds a legend to the main axis or to a figure to be
            % saved. The function will change the string inside the legend
            % elements depending on the variation measure ('std','sem' or
            % 'ci') chosen by the User. If a boxplot is selected, just the
            % group name is displayed.
            % Inputs:
            %   ax: axis to plot the legend
            %   location: 'Location' property of the legend object. See
            %   "legend" documentation for options.
            
            if ( strcmpi(app.GroupplottypeDropDown.Enable,'on') &&  contains(app.GroupplottypeDropDown.Value, 'error', "IgnoreCase",true) ) || ...
                    ( app.b_isTimeVector && strcmpi(app.TimevectorstyleButtonGroup.SelectedObject.Text, 'Shaded') ) 
                errBarName = regexp(app.VariationtypeButtonGroup.SelectedObject.Text, '(?<=\()\w*\)', 'match', 'once');
                gNames = cellfun(@(x) [x ' (\pm' errBarName], app.data(1).gName, 'UniformOutput', false);
            else
                gNames = app.data(1).gName;
            end
            childNames = arrayfun(@(x) x.Tag, ax.Children, 'UniformOutput',false);
                        
            hSample = cell(size(gNames));
            for ii = 1:numel(gNames)
                if ( app.b_isTimeVector )
                    hSample{ii} = ax.Children(strcmp(childNames, ['avgLnG' num2str(ii)]));
                else
                    hSample{ii} = ax.Children(strcmp(childNames, ['scatG' num2str(ii) 'X1']));    
                end                
            end            
            legend(ax, vertcat(hSample{:}), gNames(~cellfun(@isempty, hSample)),'AutoUpdate',"off", 'Location',location);               
        end
        
        
        function updateEvBars(app,~,~)
            % This method changes the size of Event Bar to fit the
            % current axis YLim.            
            myAxes = [app.summaryAxH, app.mainAx];
            myPatch = arrayfun(@(x) findobj(x, 'Tag', 'evBar'), myAxes, 'UniformOutput', false);            
            idxEmpty = cellfun(@isempty, myPatch);
            if all(idxEmpty)
                return
            end
            flip = false;
            if strcmp(app.mainAx.YLimMode, 'auto')
                flip = true;
                arrayfun(@(x) set(x, 'YLimMode', 'manual'), myAxes(~idxEmpty))
            end
            arrayfun(@(x,y) set(x, 'YData', y.YLim), [myPatch{~idxEmpty}],myAxes(~idxEmpty));
            if flip
                arrayfun(@(x) set(x, 'YLimMode', 'auto'), myAxes(~idxEmpty))
            end
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, statsObj)
            
            % Check if input data from statsObj are either scalar or time-vector (i.e. one data point per acquisition per subject):  
            app.UIFigure.Visible = 'off';
            if ~ismember(statsObj.inputFeatures.dataType, {'scalar', 'time-vector'})
                w = errordlg('This app does not support this type of data.', 'Invalid input', "modal");
                waitfor(w);
                app.UIFigure.Visible = 'on';
                app.UIFigureCloseRequest;
                return
            end                       
            app.statsObj = statsObj;
            % Check for Statistics toolbox and unlock extra-options:
            a = ver;
            if any(strcmp('Statistics and Machine Learning Toolbox', {a.Name})) 
                app.ConfidenceintervalCIButton.Enable = 'on';
                app.GroupplottypeDropDown.Enable = 'on';
            end           
            % Prepare app for time-vector data:
            if strcmpi(app.statsObj.inputFeatures.dataType, 'time-vector')
                % Check if all sampling frequencies are the
                % same:            
                app.b_isTimeVector = true;                               
                app.Freq = unique(cellfun(@(x) x.Freq, app.statsObj.MfileArr));
                if numel(app.Freq)>1
                    w = errordlg('The time-series data must have the same sampling frequencies.', 'Invalid input', "modal");
                    waitfor(w);
                    app.UIFigure.Visible = 'on';
                    app.UIFigureCloseRequest;
                    return
                end
                % Change GUI options:
                if ~isempty(app.Freq)
                    app.XlabelEditField.Value = 'Time (seconds)';
                else
                    app.XlabelEditField.Value = 'Time (frames)';
                end
                app.XlabelEditField.Enable = 'off';
                app.XjitterCheckBox.Enable = 'off';
                app.GroupplottypeDropDown.Enable = 'off';                               
            else
               % Hide Time-vector options:
               app.GridLayout.RowHeight(3) = 0;
            end
            % Check if data has events:
            if ismember('E', upper(app.statsObj.inputFeatures.dim_names))
                app.b_hasEvents = true;
            end
            movegui(app.UIFigure, 'northwest');                        
            % Get data from "Data_selector" app with default dimension order (acquisition on the x axis, groups on the y and ROIs per plot):
            ds = Data_selector(statsObj, app,false);
            waitfor(ds);                        
            % Populate dropdown list:
            app.DatainmainfigureDropDown.Items = {app.data.plotTitle};
            % Create main figure:
            app.mainFig = figure('Name', ['Main - ' app.DatainmainfigureDropDown.Value], 'NumberTitle','off',...
                'Position', [0 0 880 420],'Visible', 'off', 'CloseRequestFcn', @app.DoNothing);
            app.mainAx = axes('Parent', app.mainFig, 'Position',[.1 .22 .82 .7], 'Units',"normalized");
            % Create summary figures:
            app.createSummaryFigs;
            % Show Figures and app:
            app.ShowPlotInMainFig;
            movegui(app.mainFig, 'center');
            % Set some axis properties of mainAx:
            app.mainAx.Title.Interpreter = 'none';            
            app.mainAx.YLabel.String = app.YlabelEditField.Value;
            app.mainAx.XLabel.String = app.XlabelEditField.Value;
            %
            app.minEditField.Value = double(app.mainAx.YLim(1));
            app.maxEditField.Value = double(app.mainAx.YLim(2));
            % Add mainAx to hlink object:
            app.hlink.addtarget(app.mainAx)
            % Set Data cursor info:
            app.dcm_obj = datacursormode(app.mainFig);
            set(app.dcm_obj, 'DisplayStyle', 'datatip', 'Enable', 'off','UpdateFcn', @app.showDataTip, 'Interpreter', 'tex');
            
            % Make it visible:
            app.mainFig.Visible = 'on';
            app.UIFigure.Visible = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            w = uiprogressdlg(app.UIFigure, 'Title', 'Please wait', 'Message', 'Closing app', 'Indeterminate',"on");
            delete(app.mainFig)
            delete(app.summaryFigs)
            delete(w)
            delete(app)
        end

        % Value changed function: maxEditField, minEditField
        function min_max_EditFieldValueChanged(app, event)
            app.minEditField.Value;
            app.hlink.addprop('YLim');
            set(app.mainAx, 'YLim', [app.minEditField.Value, app.maxEditField.Value], 'YLimMode','manual');
        end

        % Value changed function: GroupplottypeDropDown
        function GroupplottypeDropDownValueChanged(app, event)
            szDiff = 100;            
            if contains(app.GroupplottypeDropDown.Value, 'box', 'IgnoreCase', true)                
                app.GridLayout.RowHeight{2} = 0;                
                app.UIFigure.Position(4) = app.UIFigure.Position(4) - szDiff;
            else
                app.GridLayout.RowHeight{2} = 100;
                app.UIFigure.Position(2) = app.UIFigure.Position(2) - szDiff;
                app.UIFigure.Position(4) = app.UIFigure.Position(4) + szDiff;
            end
            
            % Update plots:
            app.updateAllFigs;
        end

        % Value changed function: DatainmainfigureDropDown
        function ShowPlotInMainFig(app, event)
            % This callback makes a copy of selected ROI plot from the
            % summaryFigs into the main fig axis
            
            idx_plot = strcmp(app.DatainmainfigureDropDown.Value, app.DatainmainfigureDropDown.Items);
            cla(app.mainAx);
            % Copy source axis content to main plot:
            copyobj(app.summaryAxH(idx_plot).Children,app.mainAx);
            % Copy title and labels:    
            app.mainAx.Title.String = app.summaryAxH(idx_plot).Title.String;
            app.mainAx.YLim = app.summaryAxH(idx_plot).YLim;
            app.mainAx.XTick  = app.summaryAxH(idx_plot).XTick;
            app.mainAx.XTickLabel = app.summaryAxH(idx_plot).XTickLabel;
            app.mainAx.XTickLabelRotation = app.summaryAxH(idx_plot).XTickLabelRotation;
            % Create legend:
            app.addLegend(app.mainAx, 'bestoutside');
            % Relink main ax with summary figures:
            app.hlink.addtarget(app.mainAx);
            % Update evbars:
            app.updateEvBars;
        end

        % Selection changed function: VariationtypeButtonGroup
        function VariationtypeButtonGroupSelectionChanged(app, event)
            % This callback updates the error bars of all plots as well as
            % the legend in the main figure for the type of error measure
            % selected in the GUI.
            
            indx = find(arrayfun(@(x) isequaln(app.VariationtypeButtonGroup.SelectedObject, x), app.VariationtypeButtonGroup.Buttons));
            
            for ii = 1:length(app.summaryAxH)
                hList = app.summaryAxH(ii).Children;
                childNames = arrayfun(@(x) x.Tag, hList, 'UniformOutput',false);
                
                if ( app.b_isTimeVector )
                    avgH = hList(startsWith(childNames, 'avgLn'));
                    shadeH = hList(startsWith(childNames, 'Shade'));
                    for jj = 1:numel(avgH)
                        avg = avgH(jj).UserData.Mean;
                        % Get shadeHandle associated with the current handle:
                        sh = shadeH(strcmpi(strrep(avgH(jj).Tag,'avgLn','Shade'),{shadeH.Tag}));
                        varDat = sh.UserData.std;
                        switch indx
                            case 1
                                % SD
                                app.varType = 'std';
                            case 2
                                %SEM
                                varDat = varDat./sqrt(length(sh.XData)/2);                                
                                app.varType = 'sem';
                            case 3
                                % 95%CI
                                varDat = (tinv(0.975,length(sh.XData)/2-1)).*(varDat./sqrt(length(sh.XData)/2));                                
                                app.varType = 'ci';                                
                        end
                        set(sh, 'YData', [avg - varDat, fliplr(varDat + avg)]); 
                    end
                                        
                else
                    
                    scatH = hList(startsWith(childNames, 'scat'));
                    for jj = 1:length(scatH)
                        % Start by calculating the data's standard deviation:
                        stDev = std(scatH(jj).YData, 0,'all', "omitnan");
                        % Find the corresponding error bar:
                        errH = hList(strcmp(childNames, strrep(scatH(jj).Tag, 'scat', 'err')));
                        % Manually replace and resize the elements from the
                        % error bar:
                        PosCap = errH.Children(strcmp({errH.Children.Tag}, 'PositiveCapLine'));
                        NegCap = errH.Children(strcmp({errH.Children.Tag}, 'NegativeCapLine'));
                        ErrLn = errH.Children(strcmp({errH.Children.Tag}, 'errorLine'));
                        avg = errH.UserData.Mean;
                        switch indx
                            case 1
                                % SD
                                set(PosCap, 'YData', [stDev + avg, stDev + avg]);
                                set(NegCap, 'YData', [avg - stDev, avg - stDev]);
                                set(ErrLn, 'YData', [avg - stDev, stDev + avg]);
                                app.varType = 'std';
                            case 2
                                %SEM
                                sem = stDev./sqrt(length(scatH(jj).XData));
                                set(PosCap, 'YData', [sem + avg, sem + avg]);
                                set(NegCap, 'YData', [avg - sem, avg - sem]);
                                set(ErrLn, 'YData', [avg - sem, sem + avg]);
                                app.varType = 'sem';
                            case 3
                                % 95%CI
                                ci = (tinv([0.975],length(scatH(jj).XData)-1)).*(stDev./sqrt(length(scatH(jj).XData)));
                                set(PosCap, 'YData', [ci + avg, ci + avg]);
                                set(NegCap, 'YData', [avg - ci, avg - ci]);
                                set(ErrLn, 'YData', [avg - ci, ci + avg]);
                                app.varType = 'ci';
                        end
                    end
                end
            end
            
            % Update Main figure as well:
            app.ShowPlotInMainFig;
        end

        % Value changed function: YlabelEditField
        function YlabelEditFieldValueChanged(app, event)
         
            app.mainAx.YLabel.String = app.YlabelEditField.Value;           
        end

        % Selection changed function: YscaleButtonGroup
        function YscaleButtonGroupSelectionChanged(app, event)
            selectedButton = app.YscaleButtonGroup.SelectedObject;
            indx = find(arrayfun(@(x) isequaln(x,selectedButton), app.YscaleButtonGroup.Buttons));
            app.minEditField.Enable = 'off';
            app.maxEditField.Enable = 'off';
            switch indx
                case 1
                    % Auto
                    app.hlink.removeprop('YLim');
                    set(app.mainAx, 'YLimMode','auto')
                case 2
                    % Uniform
                    set(app.mainAx, 'YLimMode','auto')
                    y_lims = vertcat(app.summaryAxH.YLim);                    
                    y_lims = [min(y_lims(:,1)), max(y_lims(:,2))];
                    app.hlink.addprop('YLim');
                    set(app.mainAx, 'YLim',y_lims,'YLimMode','manual');
                case 3
                    % Fixed
                    app.minEditField.Enable = 'on';
                    app.maxEditField.Enable = 'on';
            end
            % Set min and max YLims values from main ax:
            app.minEditField.Value = double(app.mainAx.YLim(1));
            app.maxEditField.Value = double(app.mainAx.YLim(2));
        end

        % Value changed function: LinearCheckBox
        function LinearCheckBoxValueChanged(app, event)
            if app.LinearCheckBox.Value
                app.LogCheckBox.Value = false;
                set(app.mainAx, 'YScale', 'linear')
            else 
                app.LinearCheckBox.Value = true;
            end
        end

        % Value changed function: LogCheckBox
        function LogCheckBoxValueChanged(app, event)
            if app.LogCheckBox.Value
                app.LinearCheckBox.Value = false;
                set(app.mainAx, 'YScale', 'log')
            else 
                app.LogCheckBox.Value = true;
            end
        end

        % Menu selected function: ExportMenu, SavetoFIGMenu
        function Save_Export_Figs(app, event)
            % This callback either saves as .fig or exports individual plots as
            % bitmap/vector images.
            
            % Create format if the User chose to save as .fig:
            if startsWith(event.Source.Text, 'save', "IgnoreCase",true)               
                [file,path] = uiputfile('*.fig', 'Save plots as...', 'LongDatPlots');
            else
                fig_filter = {'*.png', 'PNG 24-bit (*.png)'; '*.jpg', 'JPEG 24-bit  (*.jpg)';...
                '*.pdf', 'Portable Document Format  (*.pdf)';...
                '*.eps', 'Encapsulated PostScript  (*.eps)'};
                [file, path, indx] = uiputfile(fig_filter, 'Export Image(s)', 'LongDatPlots.png');
                print_dict = {'-dpng', '-djpeg', '-dpdf', '-depsc'};
            end
            if file == 0
              disp('Operation cancelled by User')
              return
            end
                        
            [~,prefix,fmt] = fileparts(file);            
            f = figure('Position',[0 0 880 420], 'Visible', 'off');
            movegui(f, 'center');f.Visible = 'on';
            h = waitbar(0,'Saving figures...'); movegui(h,'center');                                    
            for ii = 1:length(app.summaryAxH)                
                ax = axes(f, 'Units',"normalized" , 'Position',[.1 .15 .8 .7]);
                copyobj(app.summaryAxH(ii).Children, ax, 'legacy')
                set(ax, 'Color', app.summaryAxH(ii).Parent.Color, 'XLim', app.summaryAxH(ii).XLim, ...
                    'YLim', app.summaryAxH(ii).YLim, 'YScale', app.summaryAxH(ii).YScale, ...
                    'XTick', app.mainAx.XTick, 'XTickLabel', app.mainAx.XTickLabel, 'XTickLabelRotation', app.mainAx.XTickLabelRotation);
                ax.XLabel.String = app.mainAx.XLabel.String;
                ax.YLabel.String = app.mainAx.YLabel.String;                                
                title(ax, app.summaryAxH(ii).Title.String, 'Interpreter', 'none');
                app.addLegend(ax,'best');
                if startsWith(event.Source.Text, 'save', "IgnoreCase",true)                    
                    savefig(f, fullfile(path,[prefix '_' ax.Title.String fmt]))
                else
                    if strcmp(fmt, '.pdf')
                        print(fullfile(path,[prefix '_' ax.Title.String fmt]) , f, print_dict{indx}, '-painters','-r300', '-bestfit');
                    else
                        print(fullfile(path,[prefix '_' ax.Title.String fmt]) , f, print_dict{indx}, '-painters','-r300');
                    end
                end
                clf(f);                 
                waitbar(ii/length(app.summaryAxH), h);
            end
            close(h)
            close(f)                       
        end

        % Menu selected function: SelectMenu
        function DataMenuSelected(app, event)
            % This callback erases the current summary figures and launches
            % the "Data_selector" to rebuild all figures. 
             
            % Clear summary figures and main axis:
            arrayfun(@delete, app.summaryFigs);app.summaryFigs = [];
            cla(app.mainAx);
            % Hide main figure and GUI:
            app.UIFigure.Visible = 'off';
            app.mainFig.Visible = 'off';
            % Launch Data_selector;
            ds = Data_selector(app.statsObj, app, true);
            waitfor(ds);            
            % Rebuild summary figures and update GUI:            
            app.DatainmainfigureDropDown.Items = {app.data.plotTitle};                                   
            app.createSummaryFigs;            
            app.ShowPlotInMainFig;
            movegui(app.mainFig, 'center');
            
            app.mainFig.Visible = 'on';
            app.UIFigure.Visible = 'on';                       
            % Relink main axis:
            app.hlink.addtarget(app.mainAx)
        end

        % Value changed function: XjitterCheckBox
        function updateAllFigs(app, event)
            cla(app.mainAx);
            app.createSummaryFigs;
            app.ShowPlotInMainFig;
            app.YscaleButtonGroupSelectionChanged;
        end

        % Value changed function: XlabelEditField
        function XlabelEditFieldValueChanged(app, event)
            
            app.mainAx.YLabel.String = app.YlabelEditField.Value;
        end

        % Selection changed function: TimevectorstyleButtonGroup
        function TimevectorstyleButtonGroupSelectionChanged(app, event)
            selectedButton = app.TimevectorstyleButtonGroup.SelectedObject;
            w = uiprogressdlg(app.UIFigure, 'Title', '', 'Message', 'Please wait...', 'Indeterminate',"on");            
            if strcmpi(selectedButton.Text, 'shaded')
                app.GridLayout.RowHeight{2} = 100;
            else
                app.GridLayout.RowHeight{2} = 0;
            end                        
            % Update main axis:
            app.updateAllFigs;
            delete(w)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Longitudinal data Plotter';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FiguresMenu = uimenu(app.UIFigure);
            app.FiguresMenu.Text = 'Figures';
            app.SavetoFIGMenu = uimenu(app.FiguresMenu);
            app.SavetoFIGMenu.MenuSelectedFcn = createCallbackFcn(app, @Save_Export_Figs, true);
            app.SavetoFIGMenu.Text = 'Save to .FIG';
            app.ExportMenu = uimenu(app.FiguresMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @Save_Export_Figs, true);
            app.ExportMenu.Text = 'Export';
            app.DataMenu = uimenu(app.UIFigure);
            app.DataMenu.Text = 'Data';
            app.SelectMenu = uimenu(app.DataMenu);
            app.SelectMenu.MenuSelectedFcn = createCallbackFcn(app, @DataMenuSelected, true);
            app.SelectMenu.Text = 'Select';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.DatainmainfigurePanel = uipanel(app.GridLayout2);
            app.DatainmainfigurePanel.Title = 'Data in main figure';
            app.DatainmainfigureDropDown = uidropdown(app.DatainmainfigurePanel);
            app.DatainmainfigureDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowPlotInMainFig, true);
            app.AxissettingsPanel = uipanel(app.GridLayout2);
            app.AxissettingsPanel.Title = 'Axis settings';
            app.YlabelEditFieldLabel = uilabel(app.AxissettingsPanel);
            app.YlabelEditFieldLabel.Text = 'Y label';
            app.YlabelEditField = uieditfield(app.AxissettingsPanel, 'text');
            app.YlabelEditField.ValueChangedFcn = createCallbackFcn(app, @YlabelEditFieldValueChanged, true);
            app.YlabelEditField.Value = 'Amplitude';
            app.YscaleButtonGroup = uibuttongroup(app.AxissettingsPanel);
            app.YscaleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YscaleButtonGroupSelectionChanged, true);
            app.YscaleButtonGroup.Title = 'Y scale';
            app.AutoButton = uiradiobutton(app.YscaleButtonGroup);
            app.AutoButton.Tooltip = {'Sets the best Y limits for each plot independently.'};
            app.AutoButton.Text = 'Auto';
            app.AutoButton.Value = true;
            app.UniformButton = uiradiobutton(app.YscaleButtonGroup);
            app.UniformButton.Tooltip = {'Sets all Y limits for all plots using the largest range found across plots.'};
            app.UniformButton.Text = 'Uniform';
            app.LinearCheckBox = uicheckbox(app.YscaleButtonGroup);
            app.LinearCheckBox.ValueChangedFcn = createCallbackFcn(app, @LinearCheckBoxValueChanged, true);
            app.LinearCheckBox.Text = 'Linear';
            app.LinearCheckBox.Value = true;
            app.LogCheckBox = uicheckbox(app.YscaleButtonGroup);
            app.LogCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogCheckBoxValueChanged, true);
            app.LogCheckBox.Text = 'Log';
            app.FixedButton = uiradiobutton(app.YscaleButtonGroup);
            app.FixedButton.Tooltip = {'Set Y limits manually.'};
            app.FixedButton.Text = 'Fixed';
            app.minEditFieldLabel = uilabel(app.YscaleButtonGroup);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.YscaleButtonGroup, 'numeric');
            app.minEditField.ValueDisplayFormat = '%11.3f';
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @min_max_EditFieldValueChanged, true);
            app.minEditField.Enable = 'off';
            app.maxEditFieldLabel = uilabel(app.YscaleButtonGroup);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.YscaleButtonGroup, 'numeric');
            app.maxEditField.ValueDisplayFormat = '%11.3f';
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @min_max_EditFieldValueChanged, true);
            app.maxEditField.Enable = 'off';
            app.GroupplottypeDropDownLabel = uilabel(app.AxissettingsPanel);
            app.GroupplottypeDropDownLabel.Text = 'Group plot type';
            app.GroupplottypeDropDown = uidropdown(app.AxissettingsPanel);
            app.GroupplottypeDropDown.Items = {'Error bar', 'Box plot'};
            app.GroupplottypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GroupplottypeDropDownValueChanged, true);
            app.GroupplottypeDropDown.Enable = 'off';
            app.GroupplottypeDropDown.Value = 'Error bar';
            app.XjitterCheckBox = uicheckbox(app.AxissettingsPanel);
            app.XjitterCheckBox.ValueChangedFcn = createCallbackFcn(app, @updateAllFigs, true);
            app.XjitterCheckBox.Tooltip = {'Spreads the data across the X axis to reduce excess overlap.'};
            app.XjitterCheckBox.Text = 'X jitter';
            app.XlabelEditFieldLabel = uilabel(app.AxissettingsPanel);
            app.XlabelEditFieldLabel.Text = 'X label';
            app.XlabelEditField = uieditfield(app.AxissettingsPanel, 'text');
            app.XlabelEditField.ValueChangedFcn = createCallbackFcn(app, @XlabelEditFieldValueChanged, true);
            app.XlabelEditField.Value = 'Acquisition';
            app.VariationtypeButtonGroup = uibuttongroup(app.GridLayout);
            app.VariationtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VariationtypeButtonGroupSelectionChanged, true);
            app.VariationtypeButtonGroup.Title = 'Variation type';
            app.StandarddeviationSDButton = uiradiobutton(app.VariationtypeButtonGroup);
            app.StandarddeviationSDButton.Text = 'Standard deviation (SD)';
            app.StandarddeviationSDButton.Value = true;
            app.StandardErrorofthemeanSEMButton = uiradiobutton(app.VariationtypeButtonGroup);
            app.StandardErrorofthemeanSEMButton.Text = 'Standard Error of the mean (SEM)';
            app.ConfidenceintervalCIButton = uiradiobutton(app.VariationtypeButtonGroup);
            app.ConfidenceintervalCIButton.Enable = 'off';
            app.ConfidenceintervalCIButton.Tooltip = {'95% CI of the mean. Available with Statistics and Machine Learning Toolbox.'};
            app.ConfidenceintervalCIButton.Text = '95% Confidence interval (CI)';
            app.TimevectorstyleButtonGroup = uibuttongroup(app.GridLayout);
            app.TimevectorstyleButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TimevectorstyleButtonGroupSelectionChanged, true);
            app.TimevectorstyleButtonGroup.Title = 'Time vector style';
            app.ShadedButton = uiradiobutton(app.TimevectorstyleButtonGroup);
            app.ShadedButton.Tooltip = {'Shows variation as shaded areas'};
            app.ShadedButton.Text = 'Shaded';
            app.ShadedButton.Value = true;
            app.LinesButton = uiradiobutton(app.TimevectorstyleButtonGroup);
            app.LinesButton.Tooltip = {'Show individual traces'};
            app.LinesButton.Text = 'Lines';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotLongData(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end