classdef data_grouping_tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DataGroupingtoolUIFigure       matlab.ui.Figure
        DataFilterMenu                 matlab.ui.container.Menu
        filterbasedatabyMenu           matlab.ui.container.Menu
        QueryMenu                      matlab.ui.container.Menu
        DateMenu                       matlab.ui.container.Menu
        EventMenu                      matlab.ui.container.Menu
        resetbaseMenu                  matlab.ui.container.Menu
        restorebaseanderasegroupsMenu  matlab.ui.container.Menu
        GroupoptionsMenu               matlab.ui.container.Menu
        changegrouptypeMenu            matlab.ui.container.Menu
        addnewgroupMenu                matlab.ui.container.Menu
        removegroupMenu                matlab.ui.container.Menu
        Tree                           matlab.ui.container.Tree
        SavetofileButton               matlab.ui.control.Button
        BasedataLabel                  matlab.ui.control.Label
        movetoDropDownLabel            matlab.ui.control.Label
        movetoDropDown                 matlab.ui.control.DropDown
        GoButton                       matlab.ui.control.Button
    end

    
    properties (Access = private)
        group_settingsApp % handle to group_settings App.
        MainApp % handle to stats_module_main App.
        treeList = [] % array of UITree objects.
        treeNames % Names of each tree.
        SearchBuilderApp % handle to SeachBuilder_dlgBox App.
        FilterStructFromSearchBuilder % Description
        baseIdxList % current index list of objects from Base UITree.
        currentDate = datetime('now') % Date chosen by date picker
        dateSearchDir = 'before' % Direction of date search.
    end
    
    properties (Access = public)
        groupType = 'inter-subject' % Type of grouping ("intra-subject" OR "inter-subject)
        Ngroups = 2 % Number of groups;
    end
    
    methods (Access = private)
        
        function fillUITree(app, Tree, tree_name, idxList)
            Tree.Enable = 'off';
            delete(Tree.Children)
            root = uitreenode(Tree, 'Text', tree_name,'Icon', 'icon_WinFolder.png');
            subjIdx = unique(idxList(:,1));
            for i = 1:numel(subjIdx)
                ID = app.MainApp.ProtocolObj.Array.ObjList(subjIdx(i)).ID;
                subjNode = uitreenode(root, 'Text', ID, 'Icon', 'icon_WinFolder.png');
                idx = idxList(:,1) == subjIdx(i);
                acqIdx = unique(idxList(idx,2), 'rows');
                for j = 1:size(acqIdx,1)
                    acqID = app.MainApp.ProtocolObj.Array.ObjList(subjIdx(i)).Array.ObjList(acqIdx(j)).ID;
                    uitreenode(subjNode, 'Text', acqID, 'Icon', 'icon_WinFolder.png');
                end
            end
            expand(Tree.Children);
            Tree.Enable = 'on';
            Tree.Multiselect = 'on';
        end
        
        function createUITree(app, tree_name, tree_position)
            % This function creates a uitree at coordinates
            % TREE_POSITION with label TREE_NAME and the associated buttons.
            
            % Create uitree:
            tree = uitree("Parent", app.DataGroupingtoolUIFigure, "Position", tree_position);
            tree.Enable = 'off';
            tree.Multiselect = 'on';
            % Create uitree label:
            tree_label_pos = tree_position;
            tree_label_pos(2) = tree_position(2) + tree_position(4) + 5;
            tree_label_pos(4) = 18;
            tree_label = uieditfield(app.DataGroupingtoolUIFigure, "FontWeight", "bold","BackgroundColor", [.9 .9 .9], "Position", tree_label_pos);
            tree_label.Value = tree_name;
            tree_label.Tooltip = 'click to edit';
            tree_label.ValueChangedFcn = @app.updateTreeNames;
            % Create label, drop-down menu and GO button:
            % label
            label_pos(1) = tree_position(1);
            label_pos(2) = tree_position(2) - 26;
            label_pos(3) = 50;
            label_pos(4) = 20;
            label = uilabel("Parent",app.DataGroupingtoolUIFigure, 'Text', 'move to', "Position", label_pos);
            % drop-down menu
            drop_pos(1) = label_pos(1) + label_pos(3) + 10;
            drop_pos(2) = label_pos(2);
            drop_pos(3) = 77;
            drop_pos(4) = label_pos(4);
            drop = uidropdown("Parent",app.DataGroupingtoolUIFigure, "Position", drop_pos);
            % button:
            but_pos(1) = tree_position(1) + tree_position(3) - 42;
            but_pos(2) = drop_pos(2);
            but_pos(3) = 42;
            but_pos(4) = drop_pos(4);
            but = uibutton(app.DataGroupingtoolUIFigure,"push","Text", 'Go!', "BackgroundColor", [.9 .9 .9],'Position',but_pos);
            but.ButtonPushedFcn = @app.GoButtonPushed;
            %             but.UserData = tree_label;
            % Use USERDATA property of UITree to store label and drop-down
            % objects:
            tree.UserData = {tree_label, drop, but, label};
            app.treeList = [app.treeList, tree];
            tree.Enable = 'on';
        end
        
        function showUITrees(app)
            % SHOWUITREES displays the UITrees on the UIFigure.
            nTrees = app.Ngroups;
            if strcmp(app.groupType, 'intra-subject')
                tree_names = {'group 1a', 'group 1b'};
            else
                for i = 1:nTrees
                    tree_names{i} = ['group ' num2str(i)];
                end
            end
            pos = [10 86.3198  210 500.6802];
            % Create Trees
            for i = 1:nTrees
                pos(1) = pos(1) + pos(3) + 10;
                app.createUITree(tree_names{i}, pos);
            end
            % Populate drop-down menus
            Items = arrayfun(@(x) x.UserData{1}.Value, app.treeList, 'UniformOutput', false);
            Items = ['Base' Items];
            app.treeNames = Items;
            app.updateDropDownItems;
            app.updateRemoveGroupMenuItems;
        end
        
        function updateDropDownItems(app)
            % UPDATEDROPDOWNITEMS uses the property treeNames to populate the
            % drop-down menus of each UITree displayed in the figure;
            app.movetoDropDown.Items = setdiff(app.treeNames, 'Base');
            for i = 1:numel(app.treeList)
                treeName = app.treeList(i).UserData{1}.Value;
                items = setdiff(app.treeNames, treeName);
                app.treeList(i).UserData{2}.Items = items;
            end
        end
        
        function updateRemoveGroupMenuItems(app)
            % UPDATEREMOVEGROUPMENUITEMS updates the list of groups inside
            % "Group Options" >> "Remove group" menu.
            gNames = setdiff(app.treeNames, {'Base'});
            delete(app.removegroupMenu.Children);
            for i = 1:numel(gNames)
                uimenu("Parent",app.removegroupMenu, "Text", gNames{i},'MenuSelectedFcn', @app.delGroup)
            end
        end
        
        % Helper functions (callbacks for programmatically created
        % components
        function updateTreeNames(app, src, event)
            % UPDATETREENAMES updates the names of displayed trees. It is
            % the callback of the editable fields of the programmatically
            % created trees.
            
            % Check if new name is valid (not empty and different from
            % other names):
            idx = strcmp(app.treeNames, event.PreviousValue);
            otherNames = app.treeNames(~idx);
            if isempty(event.Value) || any(strcmp(otherNames, event.Value))
                errordlg('Invalid group name. Must be non-empty and different from other names.')
                src.Value = event.PreviousValue;
                return
            end
            app.treeNames{idx} = event.Value;
            app.updateDropDownItems;
            app.updateRemoveGroupMenuItems;
            % Change Tree root name:
            idx_names = arrayfun(@(x) strcmp(x.UserData{1}.Value, event.Value), app.treeList);
            if any(idx_names) && ~isempty(app.treeList(idx_names).Children)
                app.treeList(idx_names).Children.Text = event.Value;
            end
        end
        
        function delGroup(app, src, ~)
            % DELGROUP deletes an UITREE and the associated components.
            gNameList = app.treeNames(2:end);
            gName = src.Text;
            % Check if there will be at least 2 groups left after deletion:
            if (numel(gNameList) - 1) < 2
                uialert(app.DataGroupingtoolUIFigure,'A minimum of 2 groups is allowed.', 'Group deletion failed!','Icon','warning');
                return
            end
            
            % Confirm deletion:
            answer = uiconfirm(app.DataGroupingtoolUIFigure,['Delete ' gName ' and all its contents?'],'Confirm deletion','Icon','warning');
            if strcmp(answer, 'Cancel')
                return
            end
            
            idx = strcmp(gNameList, gName);
            % delete tree and associated components:
            delete(app.treeList(idx).UserData{4})
            delete(app.treeList(idx).UserData{3})
            delete(app.treeList(idx).UserData{2})
            delete(app.treeList(idx).UserData{1})
            delete(app.treeList(idx));
            app.treeList(idx) = [];
            idx = strcmp(app.treeNames, gName);
            app.treeNames(idx) = [];
            tree_position = app.Tree.Position;
            % Reposition remaining trees:
            for i = 1:length(app.treeList)
                tree_position(1) = tree_position(1) + tree_position(3) + 10;
                % Tree Label Position
                tree_label_pos = tree_position;
                tree_label_pos(2) = tree_position(2) + tree_position(4) + 5;
                tree_label_pos(4) = 18;
                % label position
                label_pos(1) = tree_position(1);
                label_pos(2) = tree_position(2) - 26;
                label_pos(3) = 50;
                label_pos(4) = 20;
                % drop-down menu position
                drop_pos(1) = label_pos(1) + label_pos(3) + 10;
                drop_pos(2) = label_pos(2);
                drop_pos(3) = 77;
                drop_pos(4) = label_pos(4);
                % button position
                but_pos(1) = tree_position(1) + tree_position(3) - 42;
                but_pos(2) = drop_pos(2);
                but_pos(3) = 42;
                but_pos(4) = drop_pos(4);
                app.treeList(i).Position = tree_position;
                app.treeList(i).UserData{1}.Position = tree_label_pos;
                app.treeList(i).UserData{2}.Position = drop_pos;
                app.treeList(i).UserData{3}.Position = but_pos;
                app.treeList(i).UserData{4}.Position = label_pos;
            end
            app.updateDropDownItems;
            app.updateRemoveGroupMenuItems;
            app.Ngroups = app.Ngroups - 1;
        end
        
        function GoButtonPushed(app, src, ~)
            % GOBUTTTONPUSHED is executed when the "Go!" button from programmatically generated trees is pushed.
            idx = arrayfun(@(x) isequal(x.UserData{3}, src), app.treeList);
            sourceTree= app.treeList(idx);
            selectedNodes = sourceTree.SelectedNodes;
            targetTreeName = app.treeList(idx).UserData{2}.Value;
            if strcmp(targetTreeName, 'Base')
                targetTree = app.Tree;
            else
                treeNameList = arrayfun(@(x) x.UserData{1}.Value, app.treeList, 'UniformOutput', false);
                idx_target = strcmp(treeNameList, targetTreeName);
                targetTree = app.treeList(idx_target);
            end
            app.moveNodes(targetTree, targetTreeName, selectedNodes);
            expand(targetTree.Children);
        end
        
        function moveNodes(~, targetTree, targetTreeName, selectedNodes)
            % Helper function for "Go!" buttons callbacks
            if isempty(targetTree.Children)
                root = uitreenode(targetTree, 'Text', targetTreeName,'Icon', 'icon_WinFolder.png');
            else
                root = targetTree.Children;
            end
            for i = 1:numel(selectedNodes)
                node = selectedNodes(i);
                if isa(node.Parent, 'matlab.ui.container.Tree')
                    continue
                end
                myParentName = node.Parent.Text;
                
                if isa(node.Parent.Parent, 'matlab.ui.container.Tree')
                    myNewParent = root;
                else
                    if ~isempty(root.Children)
                        idx = strcmp(myParentName, {root.Children.Text});
                        if sum(idx) == 1
                            myNewParent = root.Children(idx);
                        elseif sum(idx) > 1
                            errordlg('Error! Found more than one Parent. Debug this!')
                            return
                        else
                            newNode = uitreenode(root, 'Text', myParentName,'Icon', 'icon_WinFolder.png');
                            myNewParent = newNode;
                        end
                    else
                        newNode = uitreenode(root, 'Text', myParentName,'Icon', 'icon_WinFolder.png');
                        myNewParent = newNode;
                    end
                end
                
                % if a subject node already exists in target Tree, merge
                % them:
                if ~isempty(root.Children)
                    idx = strcmp(node.Text, {root.Children.Text});
                    if isequal(myNewParent, root) && any(idx)
                        while ~isempty(node.Children)
                            node.Children(end).Parent = root.Children(idx);
                        end
                        delete(node)
                    elseif isequal(node.Parent.Children, node)
                        oldParent = node.Parent;
                        if isa(oldParent.Parent, 'matlab.ui.container.Tree')
                            node.Parent = root;
                        else
                            node.Parent = myNewParent;
                            delete(oldParent)
                        end
                    elseif ~isempty(myNewParent.Children)
                        myNewBrother_names = {myNewParent.Children.Text};
                        idx = ~ismember(node.Text, myNewBrother_names);
                        if idx
                            node.Parent = myNewParent;
                        end
                    else
                        node.Parent = myNewParent;
                    end
                else
                    node.Parent = myNewParent;
                end
            end
        end
        
        function filterDataByDate(app)
            idxList = app.MainApp.ProtocolObj.Idx_Filtered;
            elemArr = app.MainApp.ProtocolObj.extractFilteredObjects(2);
            dateArr = cellfun(@(x) datetime(x.Start_datetime), elemArr, 'UniformOutput',true);
            switch app.dateSearchDir
                case 'before'
                    idx = dateArr < app.currentDate;
                otherwise
                    idx = dateArr >= app.currentDate;
            end
            app.MainApp.ProtocolObj.Idx_Filtered = idxList(idx,:);
            app.fillUITree(app.Tree, 'Base', app.MainApp.ProtocolObj.Idx_Filtered);
        end
        
        function out = genIdxList(app)
            % This function generates a list of indices in "protocol" object corresponding to the selected
            % elements in the UITREE.
            
            % Check if at least one group has child nodes:
            idx = true(1,length(app.treeList));
            for i = 1:numel(app.treeList)
                tree = app.treeList(i);
                if isempty(tree.Children) || isempty(tree.Children.Children)
                    idx(i) = false;
                end
            end
            
            % Warn user if less than 2 groups has children:
            if ~strcmp(app.groupType, 'descriptive stats') && sum(idx) < 2
                uialert(app.DataGroupingtoolUIFigure, 'Minimum of 2 groups required! Transfer elements from Base to at least 2 groups.',...
                    'Failed to save groups', 'Modal',true, "Icon","error");
                return
            elseif strcmp(app.groupType, 'descriptive stats') && sum(idx) == 0
                uialert(app.DataGroupingtoolUIFigure, ['The group is empty. Transfer elements from Base to  "' app.treeNames{2} '" and try again.'],...
                    'Failed to save groups', 'Modal',true, "Icon","error");
                return
            end
            % create structure containing idxList of each group and group
            % names:
            treeArr = app.treeList(idx);
            out = struct();
            for i = 1:length(treeArr)
                tree = treeArr(i);
                rootName = tree.Children.Text;
                prot_idx_list = [];
                subjList = {tree.Children.Children.Text};
                idx_subj = cellfun(@(x) app.MainApp.ProtocolObj.Array.findElement('ID', x, 'strcmp'), subjList, 'UniformOutput', false);
                for j = 1:numel(subjList)
                    acqList = {tree.Children.Children(j).Children.Text};
                    idx_acq = cellfun(@(x) app.MainApp.ProtocolObj.Array.ObjList(idx_subj{j}).Array.findElement('ID', x, 'strcmp'), acqList, 'UniformOutput', false);
                    idx_mod = cellfun(@(x) 1:numel(app.MainApp.ProtocolObj.Array.ObjList(idx_subj{j}).Array.ObjList(x)), idx_acq, 'UniformOutput', false);
                    [a,b,c] = cellfun(@(x,y,z)  ndgrid(x,y,z), repmat(idx_subj(j), 1, length(idx_acq)), idx_acq, idx_mod, 'UniformOutput',false);
                    tmp = [a{:}; b{:}; c{:}];
                    tmp = reshape(tmp,3,[]);
                    prot_idx_list = [prot_idx_list; tmp'];
                end
                out(i).Name = rootName;
                out(i).idxList = sortrows(prot_idx_list,[1 2]);
            end
            
            % save idxList to MainApp:
            app.MainApp.groupInfo.gIdxList = out;
            
        end
        
        function sendNodesToGroups(app)
            % This function was created just to run at Startup in order to
            % pre-load existing groups from MainApp.
            
            for i = 1:app.Ngroups
                gTree = app.treeList(i);
                gTree_name = app.MainApp.groupInfo.gIdxList(i).Name;
                idxList = app.MainApp.groupInfo.gIdxList(i).idxList;
                subjList = arrayfun(@(x) app.MainApp.ProtocolObj.Array.ObjList(x).ID, idxList(:,1), 'UniformOutput', 0);
                acqList = arrayfun(@(x,y) app.MainApp.ProtocolObj.Array.ObjList(x).Array.ObjList(y).ID, idxList(:,1), idxList(:,2), 'UniformOutput', 0);
                nodeList = getNodeFromName(app.Tree, subjList, acqList);
                app.moveNodes(gTree,gTree_name,nodeList);
            end
            
            function nodes = getNodeFromName(tree,sList,aList)
                nodes = [];
                subjs = arrayfun(@(x) x.Text, tree.Children.Children, 'UniformOutput',0);
                for ii = 1:numel(sList) 
                    idx_s = strcmp(sList{ii},subjs);
                    acqs = arrayfun(@(x) x.Text, tree.Children.Children(idx_s).Children, 'UniformOutput',0);
                    for jj = 1:numel(aList)
                       idx_a = strcmp(aList{jj}, acqs);
                       nodes = [nodes; tree.Children.Children(idx_s).Children(idx_a)]; %#ok
                    end
                end
            end
            
        end
    end
    
    methods (Access = public)
        
        function filterSearch(app, FilterStruct)
            app.MainApp.ProtocolObj.FilterStruct = FilterStruct;
            app.FilterStructFromSearchBuilder = FilterStruct;
            app.MainApp.ProtocolObj.queryFilter;
            app.Tree.Enable = "off"; drawnow
            idxList = app.MainApp.ProtocolObj.Idx_Filtered;
            if isempty(idxList)
                msgbox('Your query returned zero results. Change query terms and try again!', ...
                    'Advanced Search','warn', "modal");
                app.Tree.Enable ="on";
                return
            end
            app.baseIdxList = idxList;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.MainApp = mainApp;
            % Turn Main App invisible:
            app.MainApp.UIFigure.Visible = 'off';
            app.DataGroupingtoolUIFigure.Visible = 'off';
            app.Tree.Multiselect = 'on';
            app.MainApp.ProtocolObj.clearFilterStruct;
            app.MainApp.ProtocolObj.queryFilter;
            app.fillUITree(app.Tree, 'Base', app.MainApp.ProtocolObj.Idx_Filtered);
            if app.MainApp.groupInfo.nGroups == 1 && strcmp(app.MainApp.groupInfo.gIdxList(1).Name, 'Base')
                app.group_settingsApp = group_settings(app);
                waitfor(app.group_settingsApp);
                app.showUITrees;
            else
                app.groupType = app.MainApp.groupInfo.gType;
                app.Ngroups = app.MainApp.groupInfo.nGroups;
                app.showUITrees;
                % Automate "move to group" button... % 
                app.sendNodesToGroups;
            end
            
            app.DataGroupingtoolUIFigure.Visible = 'on';
            
        end

        % Menu selected function: changegrouptypeMenu
        function changegrouptypeMenuSelected(app, event)
            if app.Ngroups > 2
                uialert(app.DataGroupingtoolUIFigure, 'Cannot change group type with more than 2 groups.', 'Failed to change group type')
                return
            end
            app.group_settingsApp = group_settings(app, true);
            waitfor(app.group_settingsApp);
        end

        % Close request function: DataGroupingtoolUIFigure
        function DataGroupingtoolUIFigureCloseRequest(app, event)
            app.genIdxList;
            app.MainApp.groupInfo.gType = app.groupType;
            app.MainApp.groupInfo.nGroups = app.Ngroups;
            app.MainApp.UIFigure.Visible = 'on';
            delete(app.group_settingsApp)
            delete(app)
        end

        % Button pushed function: GoButton
        function BaseGoButtonPushed(app, event)
            selectedNodes = app.Tree.SelectedNodes;
            targetTreeName = app.movetoDropDown.Value;
            treeNameList = arrayfun(@(x) x.UserData{1}.Value, app.treeList, 'UniformOutput', false);
            idx = strcmp(targetTreeName, treeNameList);
            targetTree = app.treeList(idx);
            app.moveNodes(targetTree, targetTreeName, selectedNodes);
            expand(targetTree.Children);
        end

        % Menu selected function: QueryMenu
        function QueryMenuSelected(app, event)
            app.MainApp.ProtocolObj.clearFilterStruct;
            app.MainApp.ProtocolObj.queryFilter;
            app.SearchBuilderApp = SearchBuilder_dlgBox(app, app.MainApp.ProtocolObj.FilterStruct);
            waitfor(app.SearchBuilderApp)
            app.fillUITree(app.Tree, 'Base', app.baseIdxList);
        end

        % Menu selected function: restorebaseanderasegroupsMenu
        function restorebaseanderasegroupsMenuSelected(app, event)
            % clear Protocol query Filter:
            app.MainApp.ProtocolObj.clearFilterStruct;
            app.MainApp.ProtocolObj.queryFilter;
            app.baseIdxList = app.MainApp.ProtocolObj.Idx_Filtered;
            % Reset Base tree:
            app.fillUITree(app.Tree, 'Base', app.baseIdxList);
            % Delete existing trees:
            for i = 1:numel(app.treeList)
                delete(app.treeList(i).Children);
            end
        end

        % Menu selected function: addnewgroupMenu
        function addnewgroupMenuSelected(app, event)
            if strcmp(app.groupType, 'intra-subject')
                warndlg('Cannot add groups for intra-subject comparisons. Maximum of 2 groups allowed.', 'Already reached maximum number of groups')
                return
            end
            groupName = ['group ' num2str(app.Ngroups + 1)];
            pos = app.treeList(end).Position;
            pos(1) = pos(1) + pos(3) + 10;
            app.createUITree(groupName, pos);
            app.treeNames = [app.treeNames groupName];
            app.updateDropDownItems;
            app.updateRemoveGroupMenuItems;
            app.Ngroups = app.Ngroups + 1;
        end

        % Menu selected function: DateMenu
        function DateMenuSelected(app, event)
            dt_app = uifigure('Name', 'Pick a Date', ...
                'Position',[500 500 375 280], 'CloseRequestFcn', {@getMyDate, app});
            d = uidatepicker(dt_app,'Position',[18 225 150 22], 'ValueChangedFcn', @dispMsg);
            d.DisplayFormat = 'dd-MM-yyyy';
            d.Value = datetime('today');
            d.Tooltip = 'Dates are inclusive and time is set to midnight!';
            bg = uibuttongroup("Parent", dt_app, "Position",[18 100 100 60]);
            uiradiobutton(bg, "Text", 'before', 'Position',[10 35 90 15]);
            uiradiobutton(bg, "Text", 'after', 'Position',[10 10 90 15]);
            function getMyDate(src, ~,app)
                dt_comp = src.Children;
                app.currentDate = dt_comp(2).Value;
                app.dateSearchDir = dt_comp(1).SelectedObject.Text;
                delete(src);
            end
            function dispMsg(src,~)
                src.Parent.Name = 'Close Figure to apply changes!';
            end
            waitfor(dt_app)
            app.filterDataByDate;
        end

        % Menu selected function: EventMenu
        function EventMenuSelected(app, event)
            event_file = fullfile(app.MainApp.ProtocolObj.SaveDir, 'Text_events.mat');
            if ~isfile(event_file)
                uialert(app.DataGroupingtoolUIFigure,['Text_events.mat file not found in ' app.MainApp.ProtocolObj.SaveDir])
                return
            end
            a = load(event_file);
            a.timestamps.Format = 'dd-MMM-yyyy HH:mm:ss';
            t_data = table(a.eventID, a.timestamps, false(length(a.timestamps),1), 'VariableNames', {'Event', 'Date', 'Choice'});
            
            ev_app = uifigure('Name', 'Choose an event from list', ...
                'Position',[300 300 550 500], 'CloseRequestFcn', {@getMyEvent, app});
            
            tab = uitable("Parent", ev_app, 'Data', t_data,'Position', [20 20 360 460]);
            tab.ColumnSortable = [false true true];
            tab.ColumnEditable = [false false true];
            tab.Tooltip = 'Check box to select row.';
            tab.CellEditCallback = @checkBox;
            bg = uibuttongroup("Parent", ev_app, "Position",[440 400 100 60]);
            uiradiobutton(bg, "Text", 'before', 'Position',[10 35 90 15]);
            uiradiobutton(bg, "Text", 'after', 'Position',[10 10 90 15]);
            function checkBox(src, event)
                indx = event.Indices(1);
                src.Data.Choice = false(length(src.Data.Choice),1);
                src.Data.Choice(indx) = true;
            end
            
            function getMyEvent(src, ~,app)
                idx = src.Children(2).Data.Choice;
                if sum(idx) == 0
                    uialert(app.DataGroupingtoolUIFigure, 'No event was selected. Operation cancelled!','Operation Cancelled', 'Icon',"error");
                    app.currentDate = datetime('now');
                    app.dateSearchDir = 'before';
                else
                    app.currentDate = src.Children(2).Data.Date(idx);
                    app.dateSearchDir = src.Children(1).SelectedObject.Text;
                end
                delete(src);
            end
            waitfor(ev_app)
            app.filterDataByDate;
        end

        % Menu selected function: resetbaseMenu
        function resetbaseMenuSelected(app, event)
            app.MainApp.ProtocolObj.clearFilterStruct;
            app.MainApp.ProtocolObj.queryFilter;
            app.fillUITree(app.Tree, 'Base', app.MainApp.ProtocolObj.Idx_Filtered);
        end

        % Button pushed function: SavetofileButton
        function SavetofileButtonPushed(app, event)
            % This callback creates a UISAVE interface for the user to write the filename of the StatsGroup .mat file and 
            % saves it to the protocol's SaveDir.
                        
            % Genereate list of indices from "protocol" object:
            groupStruct.gIdxList = app.genIdxList;
            groupStruct.gType = app.groupType;
            groupStruct.nGroups = app.Ngroups;
            % Create dialog box for input of new file:
            dlg_box = uifigure('Name', 'Type file name', 'Position', [300 700 300 100], 'CloseRequestFcn', @Close_dlg_box);
            lab1 = uilabel('Parent',dlg_box, 'Text', 'StatsGroup_','Position', [40 55 70 20], 'FontColor', [.5 .5 .5]);
            txt_box = uieditfield('Parent', dlg_box, "BackgroundColor", [1 1 1], "Position", [112 55 118 20]);
            lab2 = uilabel('Parent',dlg_box, 'Text', '.mat','Position', [232 55 50 20], 'FontColor', [.5 .5 .5]);
            ok_but = uibutton('Parent', dlg_box, "BackgroundColor",[0 .8 0], "Text", 'Save',...
                'Position', [40 10 60 25], 'ButtonPushedFcn',@(src,evnt) SaveROIas(src,evnt,app,groupStruct));
            
            % Local callbacks
            function SaveROIas(src,~,app,groupInfo)
                src.Enable = 'off';
                % Save StatsGroup structure to file:
                filename = fullfile(app.MainApp.ProtocolObj.SaveDir, ['StatsGroup_' src.Parent.Children(3).Value '.mat']);
                save(filename, 'groupInfo');
                msg = msgbox(['Stats Group info saved as ' filename],"modal");
                waitfor(msg)
                delete(src.Parent)
            end
            
            function Close_dlg_box(src,~)
                disp('Operation cancelled by User');
                delete(src)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DataGroupingtoolUIFigure = uifigure('Visible', 'off');
            app.DataGroupingtoolUIFigure.Name = 'Data Grouping tool';
            app.DataGroupingtoolUIFigure.CloseRequestFcn = createCallbackFcn(app, @DataGroupingtoolUIFigureCloseRequest, true);
            app.DataFilterMenu = uimenu(app.DataGroupingtoolUIFigure);
            app.DataFilterMenu.Text = 'Data Filter';
            app.filterbasedatabyMenu = uimenu(app.DataFilterMenu);
            app.filterbasedatabyMenu.Text = 'filter base data by';
            app.QueryMenu = uimenu(app.filterbasedatabyMenu);
            app.QueryMenu.MenuSelectedFcn = createCallbackFcn(app, @QueryMenuSelected, true);
            app.QueryMenu.Tooltip = {'Modality filter is not taken into account'};
            app.QueryMenu.Text = 'Query';
            app.DateMenu = uimenu(app.filterbasedatabyMenu);
            app.DateMenu.MenuSelectedFcn = createCallbackFcn(app, @DateMenuSelected, true);
            app.DateMenu.Text = 'Date';
            app.EventMenu = uimenu(app.filterbasedatabyMenu);
            app.EventMenu.MenuSelectedFcn = createCallbackFcn(app, @EventMenuSelected, true);
            app.EventMenu.Text = 'Event';
            app.resetbaseMenu = uimenu(app.DataFilterMenu);
            app.resetbaseMenu.MenuSelectedFcn = createCallbackFcn(app, @resetbaseMenuSelected, true);
            app.resetbaseMenu.Text = 'reset base';
            app.restorebaseanderasegroupsMenu = uimenu(app.DataFilterMenu);
            app.restorebaseanderasegroupsMenu.MenuSelectedFcn = createCallbackFcn(app, @restorebaseanderasegroupsMenuSelected, true);
            app.restorebaseanderasegroupsMenu.Text = 'restore base and erase groups';
            app.GroupoptionsMenu = uimenu(app.DataGroupingtoolUIFigure);
            app.GroupoptionsMenu.Text = 'Group options';
            app.changegrouptypeMenu = uimenu(app.GroupoptionsMenu);
            app.changegrouptypeMenu.MenuSelectedFcn = createCallbackFcn(app, @changegrouptypeMenuSelected, true);
            app.changegrouptypeMenu.Text = 'change group type';
            app.addnewgroupMenu = uimenu(app.GroupoptionsMenu);
            app.addnewgroupMenu.MenuSelectedFcn = createCallbackFcn(app, @addnewgroupMenuSelected, true);
            app.addnewgroupMenu.Text = 'add new group';
            app.removegroupMenu = uimenu(app.GroupoptionsMenu);
            app.removegroupMenu.Text = 'remove group';
            app.Tree = uitree(app.DataGroupingtoolUIFigure);
            app.SavetofileButton = uibutton(app.DataGroupingtoolUIFigure, 'push');
            app.SavetofileButton.ButtonPushedFcn = createCallbackFcn(app, @SavetofileButtonPushed, true);
            app.SavetofileButton.Text = 'Save to file';
            app.BasedataLabel = uilabel(app.DataGroupingtoolUIFigure);
            app.BasedataLabel.Text = 'Base data';
            app.movetoDropDownLabel = uilabel(app.DataGroupingtoolUIFigure);
            app.movetoDropDownLabel.Text = 'move to';
            app.movetoDropDown = uidropdown(app.DataGroupingtoolUIFigure);
            app.GoButton = uibutton(app.DataGroupingtoolUIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @BaseGoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            % Show the figure after all components are created
            app.DataGroupingtoolUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_grouping_tool(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DataGroupingtoolUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DataGroupingtoolUIFigure)
        end
    end
end