classdef umIToolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ProjectMenu                    matlab.ui.container.Menu
        NewMenu                        matlab.ui.container.Menu
        OpenMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        SaveasMenu                     matlab.ui.container.Menu
        UtilitiesMenu                  matlab.ui.container.Menu
        ProjectMenu_2                  matlab.ui.container.Menu
        LookfornewdataMenu             matlab.ui.container.Menu
        UpdateprojectMenu              matlab.ui.container.Menu
        FullupdateMenu                 matlab.ui.container.Menu
        AddnewdataonlyMenu             matlab.ui.container.Menu
        ViewLogBookMenu                matlab.ui.container.Menu
        ResetLogBookMenu_2             matlab.ui.container.Menu
        RestoregarbageListMenu         matlab.ui.container.Menu
        MergerecordingsMenu            matlab.ui.container.Menu
        GridLayoutMain                 matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        DashboardTab                   matlab.ui.container.Tab
        GridLayoutDashTab1             matlab.ui.container.GridLayout
        UITable                        matlab.ui.control.Table
        TableTitle                     matlab.ui.control.Label
        GridLayoutDashTab2             matlab.ui.container.GridLayout
        ListoffilesLabel               matlab.ui.control.Label
        PropertiesofselecteditemLabel  matlab.ui.control.Label
        TextAreaDashTab                matlab.ui.control.TextArea
        ListoffilesListBox             matlab.ui.control.ListBox
        PipelinecontrolpanelTab        matlab.ui.container.Tab
        GridPipeline                   matlab.ui.container.GridLayout
        GridPipeline2                  matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        LaunchPipelineConfigButton     matlab.ui.control.Button
        SkipstepsCheckBox              matlab.ui.control.CheckBox
        SavedatabeforeerrorCheckBox    matlab.ui.control.CheckBox
        TextAreaPipe                   matlab.ui.control.TextArea
        PipelineresultsummaryLabel     matlab.ui.control.Label
        UITablePipe                    matlab.ui.control.Table
        VisualizationTab               matlab.ui.container.Tab
        GridLayoutVizTab               matlab.ui.container.GridLayout
        SelectafileListBox             matlab.ui.control.ListBox
        SelectafileLabel               matlab.ui.control.Label
        AppstoolsLabel                 matlab.ui.control.Label
        GridLayoutVizTab2              matlab.ui.container.GridLayout
        FunctionalmapsvisualizationLabel  matlab.ui.control.Label
        DataViewerButton               matlab.ui.control.Button
        ImageRegistrationLabel         matlab.ui.control.Label
        ManualImageregistrationButton  matlab.ui.control.Button
        ROIcreationandmanagementLabel  matlab.ui.control.Label
        ROImanagerButton               matlab.ui.control.Button
        AnalysisTab                    matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        SelectafilePanel               matlab.ui.container.Panel
        SelectFileListBox_analysis     matlab.ui.control.ListBox
        UITable2                       matlab.ui.control.Table
        SelectButton_analysis          matlab.ui.control.StateButton
        GridLayout3                    matlab.ui.container.GridLayout
        AppstoolsPanel                 matlab.ui.container.Panel
        UtilitiesPanel                 matlab.ui.container.Panel
        ExporttoCSVButton              matlab.ui.control.Button
        TableViewButton                matlab.ui.control.Button
        SavetoMatButton                matlab.ui.control.Button
        AvgAcquisitionsButton          matlab.ui.control.Button
        ChangeListsButton              matlab.ui.control.Button
        ObservationlistListBoxLabel    matlab.ui.control.Label
        ObservationlistListBox         matlab.ui.control.ListBox
        EventlistListBoxLabel          matlab.ui.control.Label
        EventlistListBox               matlab.ui.control.ListBox
        PlottingtoolsPanel             matlab.ui.container.Panel
        PlotCorrelationMatrixButton    matlab.ui.control.Button
        LineBoxplotsButton             matlab.ui.control.Button
        PlotAverageMapsButton          matlab.ui.control.Button
        GridLayoutLeftCol              matlab.ui.container.GridLayout
        SearchtoolsPanel               matlab.ui.container.Panel
        AdvancedSearchButton           matlab.ui.control.Button
        ResetSearchButton_3            matlab.ui.control.Button
        OptionsPanel                   matlab.ui.container.Panel
        SelectionLabel                 matlab.ui.control.Label
        EditButton                     matlab.ui.control.Button
        DeleteButton                   matlab.ui.control.Button
        UndoSelectionButton            matlab.ui.control.Button
        SelectButton                   matlab.ui.control.Button
        GroupingToolBtn                matlab.ui.control.Button
        ErasegroupsBtn                 matlab.ui.control.Button
        Tree                           matlab.ui.container.Tree
    end

    
    properties (Access = private)
        ProtocolFile % Protocol File Path
        SearchBuilderApp; % Property containing SearchBuilder app.
        PipelineConfigApp % Property containing PipelineConfig app.
        FilterStructFromSearchBuilder % Saves the filtered structure from SearchBuilder app.
        matFileList % Used in Analysis tab. List of .MAT files from selected nodes and children.
        nodeList % Used in Analysis tab. List of selected Nodes (+ children)
        nodeObjList % Used in Analysis tab. List of objects associated with nodeList.
        obsList % Used in Analysis tab. List of observations from selected .mat files.
        rootDir % umIT's directory.
    end
    
    properties (Access = public)
        selectedObjs % selected object in Tree3 (see function DISPLAYNODEFILES).
        ProtocolObj % PROTOCOL Object
        PipelineObj % PIPELINEMANAGER Object
        StatsObj % StatsManager Object
        groupInfo = struct('nGroups', 1, 'gIdxList',...
            struct('Name', {'Base'}, 'idxList',[])) % Structure containing group info created by "data_grouping_tool.mlapp".
        b_HasNewAcq % (bool) Used by the app MergeRecs to indicate if a new acquisition was created or not
    end
    
    methods (Access = private)
        
        function check4newData(app)
            % Checks the Raw folder of PROTOCOL for new/suppressed Subjects
            % and Acquisitions.
            app.ListoffilesLabel.Text = 'Checking for new Data in Raw Directory...'; drawnow
            pause(.1);
            % Look for protocol function in data's raw and save directory:
            [~, idxInfo, msg] = app.ProtocolObj.lookForNewData(true);
            iNewAcq = cellfun(@sum, idxInfo.iNewAcq);
            iMissAcq = cellfun(@sum, idxInfo.iMissAcq);
            if any([idxInfo.iNewSubj idxInfo.iMissSubj iNewAcq iMissAcq])                
                app.ListoffilesLabel.Text = 'Changes detected in raw folder:';
                app.Label_2.Text = msg; drawnow
            else
                app.ListoffilesLabel.Text = 'All Raw Files read.';
                app.Label_2.Text = ''; drawnow
            end            
            
        end
        
        function makeUItreeNodes(app)
            % MAKEUITREENODES creates nodes of Protocol hierarchy in the UITREE "app.Tree" object.
            
            app.Tree.Enable = 'off';
            % Reset protocol's Idx_Filtered list:
            app.ProtocolObj.clearFilterStruct;
            app.ProtocolObj.queryFilter;
            % Reset groupInfo data:
            app.groupInfo = struct('nGroups', 1, 'gIdxList',...
                struct('Name', {repmat({app.ProtocolObj.Name},1,length(app.ProtocolObj.Idx_Filtered))}, 'idxList',app.ProtocolObj.Idx_Filtered));
            % Build Tree nodes:
            delete(app.Tree.Children)
            if isempty(app.ProtocolObj.Array.ObjList)
                uialert(app.UIFigure, 'Your project file seems to have Zero elements.', 'Query Filter Result', "Icon","warning", 'Modal',true)
                return
            end
            %             root = uitreenode(app.Tree, 'Text', app.ProtocolObj.Name, 'NodeData','Base');
            root = uitreenode(app.Tree, 'Text', app.ProtocolObj.Name);
            subjList =app.ProtocolObj.Array.listProp('ID');
            for i = 1:numel(subjList)
                uitreenode(root, 'Text', subjList{i});
            end
            %             expand(app.Tree.Children.Children);
            app.Tree.Enable = 'on';
            %
        end
        
        function displayNodeInfo(app, NodeObj)
            % DISPLAYNODEINFO displays the NODEOBJ's properties in
            % APP.TEXTAREA.
            % Input:
            % NodeObj = object handle associated with a selected node from
            % an UITREE.
            
            eval(['mc = ?' class(NodeObj) ';'])
            b_isPublic = cellfun(@(x) any(strcmp(x, 'public')), {mc.PropertyList.GetAccess});
            propList = {mc.PropertyList(b_isPublic).Name};
            b_showable= cellfun(@(x) (isnumeric(NodeObj.(x)) || ischar(NodeObj.(x))), propList);
            propList = propList(b_showable);
            propVals  = cellfun(@(x) num2str(NodeObj.(x)), propList, 'UniformOutput',false);
            classInfo = {'Type', class(NodeObj)};
            NodeObjProps = horzcat(propList', propVals');
            NodeObjProps = vertcat(classInfo, NodeObjProps);
            % Temporary solution for displaying Function Handle...
            if isa(NodeObj,'Protocol')
                NodeObjProps{4,2} = char(NodeObjProps{4,2});
            end
            %%%
            Data = [];
            % The if statement here controls for known property data types.
            % Has to be improved to be more flexible.
            for i = 1:size(NodeObjProps,1)
                tag = string([NodeObjProps{i,1} ':']);
                value = string(['    ' NodeObjProps{i,2}]);
                Data = vertcat(Data, tag, value);
            end
            
            app.TextAreaDashTab.Value = Data;
            app.TextAreaDashTab.Editable = "off";
            
            if isempty(NodeObj.LastLog)
                tableData = table(0,0,0,0,0);
            else
                tableData = NodeObj.LastLog;
            end
            app.UITable.Data = tableData;
            app.UITable.ColumnName = tableData.Properties.VariableNames;
            app.UITable.ColumnEditable = false;
            app.TableTitle.Text = 'Last operations in selected Item:';
        end
        
        function checkTree(app)
            % CHECKTREE verifies if the hierarchical tree has all
            % necessary elements for analysis. This means that at least one
            % Subject has one Acquisition that itself has one modality.
            
            % Change icons of subjects with empty Acquisitions to ERROR:
            idxS = arrayfun(@(x) isempty(x.Array.ObjList), app.ProtocolObj.Array.ObjList); % Empty Subject
            indxS = find(idxS);
            % Look into the other Subjects for acquisitions with empty
            % modalities and change Icons to ERROR:
            otherS = find(~idxS);
            AcqIndxList = [];
            a = 1;
            for i = 1:length(otherS)
                indxA = find(arrayfun(@(x) isempty(x.Array.ObjList), app.ProtocolObj.Array.ObjList(otherS(i)).Array.ObjList));
                for j = 1:length(indxA)
                    AcqIndxList(a,:) = [otherS(i) indxA(j)];
                    a = a+1;
                end
            end
            strS = []; strA = [];
            faultyS_ID = '';
            faultyA_ID = '';
            if ~isempty(indxS)
                strS = 'Subject(s): ';
                faultyS_ID = arrayfun(@(x) app.ProtocolObj.Array.ObjList(x).ID, indxS, 'UniformOutput', false);
                for i = 1:length(faultyS_ID)
                    strS = {strS;faultyS_ID{i}};
                end
            elseif~isempty(AcqIndxList)
                strA = 'Acquisition(s):';
                for i = 1:size(AcqIndxList,1)
                    faultyA_ID {i,1} = app.ProtocolObj.Array.ObjList(AcqIndxList(i,1)).ID;
                    faultyA_ID {i,2} = app.ProtocolObj.Array.ObjList(AcqIndxList(i,1)).Array.ObjList(AcqIndxList(i,2)).ID;
                    strA = {strA; strjoin(faultyA_ID(i,:), '_')};
                end
            else
                return
            end
            str = vertcat(strS,strA);
            msg = [{'The following items are empty: '}, str(:)',{' Remove faulty items?'}];
            answer = uiconfirm(app.UIFigure, msg, 'Warning!', 'Options', {'Remove Items', 'Continue anyway'});
            switch answer
                case 'Remove Items'
                    for i = 1:numel(faultyS_ID)
                        idxS = app.ProtocolObj.Array.findElement('ID', faultyS_ID{i}, 'strcmp');
                        app.ProtocolObj.manualRemoveObj(idxS);
                    end
                    for i = 1:size(faultyA_ID,1)
                        idxS = app.ProtocolObj.Array.findElement('ID', faultyA_ID{i,1}, 'strcmp');
                        idxA = app.ProtocolObj.Array.ObjList(idxS).Array.findElement('ID', faultyA_ID{i,2}, 'strcmp');
                        app.ProtocolObj.manualRemoveObj(idxS, idxA);
                    end
                    app.ListoffilesLabel.Text = 'Updating list...';
                    app.ProtocolObj.updateList;
                    app.ListoffilesLabel.Text = 'Recreating tree...';
                    app.makeUItreeNodes;
                case 'Continue anyway'
                    uialert(app.UIFigure, 'Attention! This may cause problems later during analysis!', 'Warning!', "Modal",true, "Icon","warning");
            end
            
        end
        
        function expandNode(app, node, b_recursive)
            % EXPANDNODE creates Children nodes of "node" as Parent.
            % This function allows a lazy-load of node Children to improve
            % the speed of the GUI.
            % Input:
            % node = handle to the selected UITREENODE.
            % b_recursive(bool): If TRUE, this function will be called
            % recursively until all elements inside "node" (i.e. children
            % and grandchildren, and so on) are created.
            
            nodeObj = app.getNodeObj(node);
            if isa(nodeObj, 'Protocol') || isa(nodeObj, 'Modality')
                return
            end
            
            if isempty(app.ProtocolObj.Idx_Filtered)
                app.ProtocolObj.clearFilterStruct;
                app.ProtocolObj.queryFilter;
            end
            root = node;
            while 1
                root = root.Parent;
                if isa(root.Parent, 'matlab.ui.container.Tree')
                    break
                end
            end
            gNames = {app.groupInfo.gIdxList.Name};
            gNames = cellfun(@(x) x(1), gNames);
            % Use index list of the group found above to extract the
            % objects. Here, we assume that there will be only one group
            % with the same name:
            app.ProtocolObj.Idx_Filtered = app.groupInfo.gIdxList(strcmp(root.Text, gNames)).idxList;
            % Find objects and their children:
            switch class(nodeObj)
                case 'Subject'
                    indxS = nodeObj.MyParent.Array.findElement('ID', nodeObj.ID, 'strcmp');
                    idx = app.ProtocolObj.Idx_Filtered(:,1) == indxS;
                    child_list = app.ProtocolObj.extractFilteredObjects(2);
                case 'Acquisition'
                    indxS = nodeObj.MyParent.MyParent.Array.findElement('ID', nodeObj.MyParent.ID, 'strcmp');
                    indxA = nodeObj.MyParent.Array.findElement('ID', nodeObj.ID, 'strcmp');
                    idx = all(app.ProtocolObj.Idx_Filtered(:,[1 2]) == [indxS indxA],2);
                    child_list = app.ProtocolObj.extractFilteredObjects(3);
            end
            child_list = child_list(idx);
            obj_chilNames = cellfun(@(x) x.ID, child_list, 'UniformOutput',false);
            
            if isempty(node.Children)
                childNames = obj_chilNames;
                cellfun(@(x) uitreenode(node, 'Text', x), childNames);
            end
            
            if b_recursive
                b_noChild = arrayfun(@(x) isempty(x.Children), node.Children);
                arrayfun(@(x) app.expandNode(x, true), node.Children(b_noChild));
            end
        end
        
        
        function [NodeObj, lvl] = getNodeObj(app,Node)
            % GETNODEOBJ retrieves the object associated with the selected node
            % in a UITree.
            % Input:
            % node = handle to the selected UITREENODE.
            % Outputs:
            % NodeObj = handle of the Protocol object associated with the
            % UITREE node.
            % lvl = level of the node (0 = protocol; 1 = subject; 2 =
            % acquisition; 3 = modality).
            
            ID = Node.Text;
            b_top = false; a = 0;
            str = 'typeClass = class(Node.';
            str2 = 'Parent';
            while ~b_top
                eval([str str2 ');']);
                lvl = a;
                if strcmp(typeClass,'matlab.ui.container.Tree')
                    b_top= true;
                end
                str = [str str2 '.'];
                a = a+1;
            end
            switch lvl
                case 0
                    NodeObj = app.ProtocolObj;
                case 1
                    idx = app.ProtocolObj.Array.findElement('ID', ID, 'strcmp');
                    NodeObj = app.ProtocolObj.Array.ObjList(idx);
                case 2
                    idxS = app.ProtocolObj.Array.findElement('ID', Node.Parent.Text, 'strcmp');
                    idx = app.ProtocolObj.Array.ObjList(idxS).Array.findElement('ID', ID, 'strcmp');
                    NodeObj = app.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idx);
                case 3
                    idxS = app.ProtocolObj.Array.findElement('ID', Node.Parent.Parent.Text, 'strcmp');
                    idxA = app.ProtocolObj.Array.ObjList(idxS).Array.findElement('ID', Node.Parent.Text, 'strcmp');
                    idx = app.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idxA).Array.findElement('ID', ID, 'strcmp');
                    NodeObj = app.ProtocolObj.Array.ObjList(idxS).Array.ObjList(idxA).Array.ObjList(idx);
            end
        end
        
        function displayNodeFiles(app, myList, fileTag)
            % DISPLAYNODEFILES shows the files listed in the Save Folder of
            % selected nodes.
            % Inputs:
            %   myList (cell) : content of a LISTBOX object.
            %   fileTag (char) : string to identify the extension of the
            %   file : ".dat", ".mat", or "all";
            
            myList.Items = {'Loading...'}; drawnow
            selectedNodes = app.Tree.SelectedNodes;
            
            app.selectedObjs = arrayfun(@(x) app.getNodeObj(x), selectedNodes, 'UniformOutput', false);
            filelist = {};
            
            for i = 1:numel(app.selectedObjs)
                filelist = [filelist ; app.getFileList(app.selectedObjs{i}, fileTag)];
            end
            
            if isempty([filelist{:}])
                myList.Items = {'No files found!'};
                return
            end
            filelist = unique(filelist);
            idx_empty = cellfun(@isempty, filelist);
            myList.Items = filelist(~idx_empty);
            myList.Value = {};
        end
        
        function out = getFileList(~,obj, tag, varargin)
            % This function reads the files contained in the SaveFolder of the selected object.
            % and retrieves a cell array with the names of all valid files listed.
            % Input:
            %   obj : object from Protocol (Subject/Acquisition/Modality)
            %   tag (char): string to indicate which extension {'.dat',
            %   '.mat', 'all'};
            % Output:
            %   out (cell): cell array containing the names of valid files
            %   inside object's SaveFolder.
            p = inputParser;
            addRequired(p,'obj')
            addRequired(p,'tag', @(x) ismember(x,{'.dat', '.mat', 'all'}))
            addParameter(p,'fullPath', false, @islogical)
            parse(p, obj,tag,varargin{:})
            obj = p.Results.obj;
            tag = p.Results.tag;
            b_fullPath = p.Results.fullPath;
            clear p
            %%
            
            % List all .DAT and .MAT files in the object's save folder:
            datList = dir(fullfile(obj.SaveFolder, '*.dat'));
            matList = dir(fullfile(obj.SaveFolder, '*.mat'));
            % Here, we consider that all .DAT files are valid, however we
            % can find .mat files that were not created in the pipeline. So
            % we will filter them based on the existence of the variable
            % "dataHistory" inside .MAT files:
            
            % Exclude .MAT files containing metaData of .DAT files and files that do NOT contain "dataHistory" variable:
            [~,datNames,~] = cellfun(@(x) fileparts(x), {datList.name}, 'UniformOutput',false);
            [~,matNames,~] = cellfun(@(x) fileparts(x), {matList.name}, 'UniformOutput',false);
            idx_datInMat = ismember(datNames, matNames); % Get all dat files that have .mat files with the same name.
            idx_matInDat = ismember(matNames, datNames); % Get all mat files that have .dat files with the same name.
            idx_valid = true(size(matList));
            idx_RefFrame = idx_valid;
            warning('off')
            
            for i = 1:length(matList)
                % Trying to load the "dataHistory" variable from a .mat file is way faster than querying the .mat file
                % using "matfile" or "whos":
                a = load(fullfile(matList(i).folder, matList(i).name), 'dataHistory', 'reference_frame');
                %                 load(fullfile(matList(i).folder, matList(i).name), 'reference_frame'); % To detect ImagingReferenceFrame files with different names.
                if ~isfield(a, 'dataHistory')
                    idx_valid(i) = false;
                elseif ~isfield(a, 'reference_frame')
                    idx_RefFrame(i) = false;
                end
                
            end
            warning('on')
            
            % Remove .dat files that do not have an associated .mat file.
            datList(~idx_datInMat) = [];
            switch tag
                case '.dat'
                    FilesList = datList;
                case '.mat'
                    matList = matList(~idx_matInDat & idx_valid');
                    FilesList = matList;
                case 'all'
                    % Include "auxiliary" .mat files such as ROImasks_data.mat and
                    % ImagingReferenceFrame.mat
                    idx_valid = idx_valid | idx_RefFrame;
                    idx_auxFiles = strcmp(matNames, 'events.mat') | startsWith(matNames, 'ROImasks_');
                    matList = matList(~idx_matInDat & idx_valid' | idx_auxFiles);
                    FilesList = vertcat(datList, matList);
                otherwise
                    error('Wrong flag name!')
            end
            
            if isempty(FilesList)
                out = {''};
            else
                if b_fullPath
                    out = arrayfun(@(x) fullfile(obj.SaveFolder,x.name), FilesList, 'UniformOutput',false);
                else
                    out = {FilesList.name}';
                end
            end
        end
        function checkAndLoad_protocol(app)
            % CHECKANDLOAD_PROTOCOL loads the "protocol" object and verifies if it is
            % valid.
            % Disable warning while loading Protocol object. Sometimes the
            % loadobj method issues warnings about empty properties.
            % However, this does not affect the loading of the protocol
            % object to the workspace.
            warning('off','all')
            a = load(app.ProtocolFile);
            warning('on','all')
            if ~isfield(a, 'protocol')
                uialert(app.UIFigure, 'Wrong File!', 'Error!', "Icon","error", "Modal",true);
                app.ProtocolFile = [];
                app.ListoffilesLabel.Text = '';
                app.Label_2.Text = '';
                return
            end
            protocol= a.protocol;
            % addpath(protocol.MainDir); % Add Main Dir to path to be sure that the protocol function is in Matlab's path.
            msg = '';
            if isa(protocol, 'Protocol') && ~isempty(protocol.Name)
                app.ProtocolObj = protocol;
                % Make Tree:
                app.makeUItreeNodes;
                % Check data integrity:
                app.checkTree;
                % Raise warning if the raw directory doesn't exist:
                if ~exist(protocol.MainDir, 'dir')
                    uialert(app.UIFigure, ['The raw data "' protocol.MainDir '" doesn''t exist! Data import functions will not work!'], 'Raw folder not found','Icon','warning')
                    return
                else
                    uialert(app.UIFigure, msg, 'All set!','Icon','info');
                    return
                end
            elseif ~isa(protocol, 'Protocol')
                msg = sprintf('%s\n%s', ['The file ' filename ' does not contain a valid Project.'], 'Try again!');
            else
                msg = sprintf('%s\n%s', 'Failed to load project File!', 'Check Matlab''s command Window!');
            end
            % Raise warning:
            uialert(app.UIFigure, msg, 'Warning','Icon','warning');
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%% STATS FUNCTIONS    %%%%%%%%%%%%%%%%%%%%%%%%%%%
        function fillTree4(app)
            % Function to Create nodes in Analysis Tab.
            % In contrast with the other trees, this function draws all
            % nodes. This may be time consuming for large amounts of data.
            % This function creates Subject and Acquisition nodes. Modality
            % nodes are not drawn.
            app.Tree4.Enable = 'off';
            delete(app.Tree4.Children)
            %%% Allow the creation of several roots!
            s = app.groupInfo.gIdxList;
            nRoots = length(s);
            for ii = 1:nRoots
                idxList = s(ii).idxList;
                root = uitreenode(app.Tree4, 'Text', s(ii).Name,'Icon', 'icon_WinFolder.png');
                subjIdx = unique(idxList(:,1));
                for i = 1:numel(subjIdx)
                    ID = app.ProtocolObj.Array.ObjList(subjIdx(i)).ID;
                    subjNode = uitreenode(root, 'Text', ID, 'Icon', 'icon_WinFolder.png');
                    idx = idxList(:,1) == subjIdx(i);
                    acqIdx = unique(idxList(idx,2), 'rows');
                    for j = 1:size(acqIdx,1)
                        acqID = app.ProtocolObj.Array.ObjList(subjIdx(i)).Array.ObjList(acqIdx(j)).ID;
                        uitreenode(subjNode, 'Text', acqID, 'Icon', 'icon_WinFolder.png');
                    end
                end
            end
            %             expand(app.Tree, 'all');
            app.Tree4.Enable = 'on';
            app.Tree4.Multiselect = 'on';
        end
        
        function [matList, nodeList, nodeObjList] = findStatsFiles(app)
            % FINDSTATSFILES looks for stats-ready .MAT files listed in
            % the objects' folders selected in app.Tree.
            %
            % Output:
            % matList: A list of full paths of .MAT files that are common to
            % selected objects (grouped by hierarchy).
            % nodeList : Cell array of UITree node handles associated with each
            % .Mat file listed in MATLIST.
            % nodeObjList: Cell array of objects associated with nodeList
            % elements.
            
            nodeList = {};
            matList = {};
            nodeObjList = {};
            nodes = app.Tree.SelectedNodes;
            % First, get a list of all File Pointers from Selected Nodes:
            for i = 1:numel(nodes)
                node = nodes(i);
                nodeObj = app.getNodeObj(node);
                list = app.getFileList(nodeObj, '.mat', 'fullPath', true);
                if ~isempty(list)
                    nodeObjList = [nodeObjList, repmat({nodeObj}, 1,length(list))];
                    nodeList = [nodeList, repmat({node}, 1,length(list))];
                    matList = [matList; list];
                end
                %                 if isa(nodeObj, 'Acquisition')
                %                     for j = 1:numel(nodeObj.Array.ObjList)
                %                         mod_list = app.getFileList(nodeObj.Array.ObjList(j), '.mat', 'fullPath', true);
                %                         nodeList = [nodeList, repmat({node}, 1, length(mod_list))];
                %                         matList = [matList; mod_list];
                %                         if ~isempty(mod_list)
                %                             nodeObjList = [nodeObjList, repmat({nodeObj.Array.ObjList(j)}, 1,length(mod_list))];
                %                         end
                %                     end
                %                 end
            end
            
            % Remove empty items from lists:
            idxEmpty = cellfun(@isempty, matList);
            matList(idxEmpty) = [];
            nodeList(idxEmpty) = [];
            nodeObjList(idxEmpty) = [];
            
        end
        
        function lvl = findNodeLevel(~,Node)
            a = false;
            lvl = 0;
            while ~a
                myParent = Node.Parent;
                if isa(myParent, 'matlab.ui.container.Tree')
                    a = true;
                else
                    Node = Node.Parent;
                    lvl = lvl + 1;
                end
            end
        end
        
        function groupList = getMyGroup(~,nodeList)
            % Retrieves the name of the group from each element
            % contained in nodeList.
            groupList = cell(1,length(nodeList));
            for i = 1:numel(nodeList)
                node = nodeList{i};
                b_isNode = true;
                while b_isNode
                    myParent = node.Parent;
                    if isa(myParent, 'matlab.ui.container.Tree')
                        b_isNode = false;
                        groupName = node.Text;
                    else
                        node = node.Parent;
                    end
                end
                groupList{i} = groupName;
            end
        end
        
        function updateProtocolObjFromNodeList(app, nodeList)
            % This function recreates the index array of selected objects
            % in the Protocol object (i.e. Idx_Filtered) based on the node
            % list ("nodeList").
            % Input:
            % nodeList(array): array of TreeNodes.
            
            % Get all objects from the list of tree nodes:
            NodeObj_list = arrayfun(@(x) app.getNodeObj(x), nodeList, 'UniformOutput', false);
            % Identify the ones that are "Modality":
            idx_minLvl = cellfun(@(x) isa(x, 'Modality'), NodeObj_list);
            NodeObj_list = NodeObj_list(idx_minLvl);
            indx_List = zeros(length(NodeObj_list),3,'single');
            % Look on Protocol's object hierarchy from the ground up to
            % find all "complete" branches from nodeList:
            for i = 1:length(NodeObj_list)
                j=3;
                tmpObj = NodeObj_list{i};
                my_parent = tmpObj.MyParent;
                indx_List(i,j) = my_parent.Array.findElement('ID', tmpObj.ID, 'strcmp');
                while ~isa(my_parent, 'Protocol')
                    j = j -1;
                    tmpObj = tmpObj.MyParent;
                    my_parent = tmpObj.MyParent;
                    indx_List(i,j) = my_parent.Array.findElement('ID', tmpObj.ID, 'strcmp');
                end
            end
            % update IdxFiltered property of Protocol object:
            app.ProtocolObj.Idx_Filtered = sortrows(unique(indx_List, 'rows'));
        end
        
        function groupNodes(app)
            % GROUPNODES rebuilds the UITREE using the information in groupInfo.
            
            app.Tree.Enable = 'off';
            
            gNames = {app.groupInfo.gIdxList.Name};
            gNames = cellfun(@(x) x(1), gNames);
            gIdxList = {app.groupInfo.gIdxList.idxList};
            % Build Tree nodes:
            delete(app.Tree.Children)
            for ii = 1:numel(gNames)
                root = uitreenode(app.Tree, 'Text', gNames{ii});
                app.ProtocolObj.Idx_Filtered = gIdxList{ii};
                subjs = app.ProtocolObj.extractFilteredObjects(1);
                subjList= cellfun(@(x) x.ID, subjs,'UniformOutput', false);
                for i = 1:numel(subjList)
                    uitreenode(root, 'Text', subjList{i});
                end
            end
            app.Tree.Enable = 'on';
        end
        
        function [mapArr,gNames] = avgMaps(app, mskArr, b_isPolar)
            % AVGMAPS creates an average map (i.e. image with dimensions Y,X) per group
            % per acquisition.
            disp('creating average plots...')
            msk = [];
            % Get names of groups and segregate data per aquisition index:
            gNames = unique(app.StatsObj.list_of_groups);
            indxAcq = app.StatsObj.stats_data(:, strcmp(app.StatsObj.stats_data_headers,'AcquisitionIndx'));
            indxAcq = [indxAcq{:}]';
            nAcq = unique(indxAcq);
            % Instantiate output variabe:
            mapArr = cell(length(gNames), length(nAcq), length(app.StatsObj.obs_list));
            for i = 1:numel(gNames)
                idxG = strcmp(gNames{i}, app.StatsObj.stats_data(:,strcmp(app.StatsObj.stats_data_headers,'groupID')));
                for j = 1:length(nAcq)
                    % Get all acquisitions:
                    indxA = find(idxG & indxAcq == nAcq(j));
                    dataIn = cell(length(indxA),length(app.StatsObj.obs_list));
                    refPt_list = zeros(length(indxA),2);
                    pxPermm_list = zeros(length(indxA),1);
                    for k = 1:length(indxA)
                        [idxObs, locB] = ismember(app.StatsObj.obs_list, ...
                            app.StatsObj.stats_data{indxA(k),strcmp(app.StatsObj.stats_data_headers,'observationID')});
                        locB(locB == 0) = [];
                        dataIn(k,idxObs) = app.StatsObj.stats_data{indxA(k),strcmp(app.StatsObj.stats_data_headers,'data')}(locB);
                        refPt_list(k,:) = app.StatsObj.stats_data{indxA(k),strcmp(app.StatsObj.stats_data_headers,'MatFile')}.refPt;
                        pxPermm_list(k) = app.StatsObj.stats_data{indxA(k),strcmp(app.StatsObj.stats_data_headers,'MatFile')}.pxPermm;
                        if ~isempty(mskArr)
                            msk = mskArr{indxA(k)};
                        end
                    end
                    % Perform averaging for each acquisition and group:
                    mapArr(i,j,:) = calcAvg(dataIn, refPt_list, pxPermm_list, msk);
                end
            end
            % Local function:
            function out = calcAvg(data, ref, px_size, logical_mask)
                % This function aligns the reference points and scales the
                % frames in data, then calculates the average maps.
                disp('calculating average maps...')
                
                % Translate and scale the data to match position of first item of
                % "data":
                ref = ref(1,:) - ref;
                px_size = px_size(1)./px_size;
                RA = imref2d(size(data{1,1})); % Use size of first data as reference.
                for ii = 1:size(data,1)
                    tform = affine2d([px_size(ii) 0 0; 0 px_size(ii) 0; ref(ii,1) ref(ii,2) 1]);
                    for jj = 1:size(data,2)
                        if isempty(data{ii,jj})
                            continue
                        end
                        % Perform geometric transformation on image:
                        if ~isempty(logical_mask)
                            % Erase pixel values outside the logical mask:
                            data{ii,jj}(~logical_mask) = nan;
                        end
                        % Remove NaNs and Infs from data
                        idxNaN = isnan(data{ii,jj});
                        idxInfPos = isinf(data{ii,jj}) & data{ii,jj} > 0;
                        idxInfNeg = isinf(data{ii,jj}) & data{ii,jj} < 0;
                        data{ii,jj}(idxNaN | idxInfPos | idxInfNeg) = 0;
                        
                        data{ii,jj} = imwarp(data{ii,jj}, tform, 'nearest','OutputView', RA, 'FillValues', nan);
                        % Put NaNs and Infs back to the translated data:
                        if any(idxNaN, 'all')
                            idxNaN = imwarp(idxNaN, tform, 'nearest', 'FillValues', 0);
                            data{ii,jj}(idxNaN) = nan;
                        end
                        if any(idxInfPos, 'all')
                            idxInfPos = imwarp(idxInfPos, tform, 'nearest', 'FillValues', 0);
                            data{ii,jj}(idxInfPos) = Inf;
                        end
                        if any(idxInfNeg, 'all')
                            idxInfNeg = imwarp(idxInfNeg, tform, 'nearest', 'FillValues', 0);
                            data{ii,jj}(idxInfNeg) = -Inf;
                        end
                    end
                end
                % Average acquisitions:
                out = cell(1,size(data,2));
                for ii = 1:size(data,2)
                    out{ii} = mean(cat(3,data{:,ii}),3,'omitnan');
                end
            end
        end
        
        function controlAnalysisButtons(app, varargin)
            % CONTROLANALYSISBUTTONS Enables/Disables the buttons in the
            % "Analysis" tab depending on the input data types.
            %   Inputs (optional):
            %   EnableAll(char): Enables all buttons.
            
            % Get indices of all buttons from PLot panel:
            idxPlot = arrayfun(@(x) isa(x, 'matlab.ui.control.Button'), app.PlottingtoolsPanel.Children);
            idxUtil = arrayfun(@(x) isa(x, 'matlab.ui.control.Button'), app.UtilitiesPanel.Children);
            bMap = containers.Map([false,true],{'off','on'});
            % Disable all buttons
            arrayfun(@(x) set(x, 'Enable','off'), app.PlottingtoolsPanel.Children(idxPlot));
            arrayfun(@(x) set(x, 'Enable','off'), app.UtilitiesPanel.Children(idxUtil));
            %
            if nargin > 1
                % Enable all buttons in the Analysis Tab:
                arrayfun(@(x) set(x, 'Enable',bMap(varargin{:})), app.PlottingtoolsPanel.Children(idxPlot))
                arrayfun(@(x) set(x, 'Enable',bMap(varargin{:})), app.UtilitiesPanel.Children.Children(idxUtil))
                return
            end
            % Enable some buttons:
            set(app.SavetoMatButton, 'Enable', 'on'); % Enable Save2Mat button
            set(app.ChangeListsButton, 'Enable', 'on'); % Enable Change Lists button
            set(app.AvgAcquisitionsButton, 'Enable', 'on'); % Enable Acquisition Averaging button
            % Toggle export buttons if the data is 1-2D:
            set(app.TableViewButton, 'Enable', bMap(app.StatsObj.inputFeatures.b_isExportable));
            set(app.ExporttoCSVButton, 'Enable', bMap(app.StatsObj.inputFeatures.b_isExportable));
            % Toggle Average Acquisisions Button:
            set(app.AvgAcquisitionsButton, 'Enable', bMap(app.StatsObj.inputFeatures.b_hasSameAcqN & app.StatsObj.inputFeatures.b_SubjHasSameDimSize));
            % Toggle plots based on data type
            switch app.StatsObj.inputFeatures.dataType
                case {'scalar', 'time-vector'}
                    set(app.LineBoxplotsButton, 'Enable',bMap(app.StatsObj.inputFeatures.b_hasSameAcqN));
                case 'map'
                    set(app.PlotAverageMapsButton, 'Enable',...
                        bMap(app.StatsObj.inputFeatures.b_hasSameAcqN & app.StatsObj.inputFeatures.b_SubjHasSameDimSize & app.StatsObj.inputFeatures.b_AcqHasSameDimSize));
                case 'matrix'
                    % Allow for correlation matrices WITHOUT events!!
                    set(app.PlotCorrelationMatrixButton, 'Enable',bMap(~app.StatsObj.inputFeatures.b_hasValidEvent));
                otherwise
                    uialert(app.UIFigure, ['No plotting options for data type: ' app.StatsObj.inputFeatures.dataType ' !'],...
                        'Plot option unavailable', 'Icon', 'warning', "CloseFcn",'uiresume(gcbf)');
                    waitfor(app.UIFigure);
                    return
            end
        end
        
        
    end
    methods (Access = public)
        function filterSearch(app, FilterData)
            % FILTERSEARCH applies a query filter on the protocol object.
            % This public function is used by the SearchBuilderApp.
            
            if isstruct(FilterData)
                app.ProtocolObj.FilterStruct = FilterData;
                app.FilterStructFromSearchBuilder = FilterData;
                app.ProtocolObj.queryFilter;
            else
                app.ProtocolObj.Idx_Filtered = FilterData;
            end
            
            % Get group names and indices:
            gNames = {app.groupInfo.gIdxList.Name};
            gNames = cellfun(@(x) x(1), gNames);
            gIdxList = {app.groupInfo.gIdxList.idxList};
            %
            app.Tree.Enable = "off"; drawnow
            % Check for empty queries:
            if isempty(setdiff(vertcat(gIdxList{:}), app.ProtocolObj.Idx_Filtered,'rows')) || isempty(app.ProtocolObj.Idx_Filtered)
                msgbox('Your query returned zero results. Change query terms and try again OR reset search!', ...
                    'Advanced Search','warn', "modal");
                app.ProtocolObj.Idx_Filtered = vertcat(gIdxList{:});
                app.Tree.Enable ="on";
                return
            end
            % Update GroupInfo with new filtered objects:
            for i = 1:length(app.groupInfo.gIdxList)
                app.groupInfo.gIdxList(i).idxList = intersect(app.groupInfo.gIdxList(i).idxList, app.ProtocolObj.Idx_Filtered,'rows');
                app.groupInfo.gIdxList(i).Name = repmat(app.groupInfo.gIdxList(i).Name(1), 1, length(app.groupInfo.gIdxList(i).idxList));
            end
            %
            app.TextAreaPipe.Value = 'Filtering object Tree...'; drawnow
            app.TextAreaDashTab.Value = 'Filtering object Tree...'; drawnow
            % Filter each group node:
            for ii = 1:numel(app.Tree.Children)
                root = app.Tree.Children(ii);
                gIdx = strcmp(root.Text,gNames);
                % Delete subject nodes
                subjNodeIDs = {root.Children.Text};
                subjSelecIDs = {app.ProtocolObj.Array.ObjList(unique(app.groupInfo.gIdxList(gIdx).idxList(:,1))).ID};
                idx_del = ~ismember(subjNodeIDs, subjSelecIDs);
                delete(root.Children(idx_del));
                % Delete acquisition nodes
                for i = 1:numel(root.Children)
                    Node = root.Children(i);
                    app.expandNode(Node, false);
                    indxS = app.ProtocolObj.Array.findElement('ID', Node.Text, 'strcmp');
                    AcqList = app.groupInfo.gIdxList(gIdx).idxList(app.groupInfo.gIdxList(gIdx).idxList(:,1) == indxS,:);
                    acqNodeIDs ={Node.Children.Text};
                    acqSelecIDs = {app.ProtocolObj.Array.ObjList(indxS).Array.ObjList(AcqList(:,2)).ID};
                    idx_del = ~ismember(acqNodeIDs, acqSelecIDs);
                    delete(Node.Children(idx_del))
                end
                % Delete Modality nodes
                for i = 1:numel(root.Children)
                    for j = 1:numel(root.Children(i).Children)
                        Node = root.Children(i).Children(j);
                        indxS = app.ProtocolObj.Array.findElement('ID', Node.Parent.Text, 'strcmp');
                        indxA = app.ProtocolObj.Array.ObjList(indxS).Array.findElement('ID', Node.Text, 'strcmp');
                        Modlist = app.groupInfo.gIdxList(gIdx).idxList(app.groupInfo.gIdxList(gIdx).idxList(:,1) == indxS & ...
                            app.groupInfo.gIdxList(gIdx).idxList(:,2) == indxA,:);
                        try
                            modNodeIDs = {Node.Children.Text};
                            modSelecIDs  = {app.ProtocolObj.Array.ObjList(indxS).Array.ObjList(indxA).Array.ObjList(Modlist(:,3)).ID};
                            idx_del = ~ismember(modNodeIDs, modSelecIDs);
                            delete(Node.Children(idx_del))
                        catch
                            %empty%
                        end
                        
                    end
                end
            end
            
            
            app.Tree.Enable ="on";
            %             expand(app.Tree.Children, 'all');
            app.TextAreaPipe.Value = 'Done!'; drawnow
            app.TextAreaDashTab.Value = 'Done!'; drawnow
        end
        
        function displayPipeInfo(app, b_resetArea)
            % DISPLAYPIPEINFO displays information about the pipeline.
            % Input:
            % b_resetArea (bool) : If TRUE, erases the text area
            % TextArea_2 before displaying the information. If FALSE,
            % appends the new text to the current text.
            
            if b_resetArea
                app.TextAreaPipe.Value = '';
            end
            Pipe = app.PipelineObj.pipe;
            Text = app.TextAreaPipe.Value;
            txt = {['Number of steps: ' num2str(length(Pipe))]};
            txt = [txt {'------------------------------------------------------'}];
            for i = 1:length(Pipe)
                txt = [txt {['Step #' num2str(i) ': ']}];
                txt = [txt {['Class: ' Pipe(i).className]}];
                txt = [txt {['Function: ' Pipe(i).name]}];
                if ~isempty(Pipe(i).opts)
                    fields = fieldnames(Pipe(i).opts);
                    txt = [txt {'Parameters: '}];
                    for j = 1:length(fields)
                        txt = [txt {[fields{j} ': ' num2str(Pipe(i).opts.(fields{j}))]}];
                    end
                end
                txt = [txt {'------------------------------------------------------'}];
            end
            Text = [Text txt];
            app.TextAreaPipe.Value = Text;
            app.TextAreaPipe.Editable = 'off';
        end
        
        function runPipeline(app, ~, callerApp)
            %   This method is called by the "Run" button callback from the
            %   "   PipelinConfig_dlgBox to execute the pipeline.
            
            % Close Pipeline Builder:
            close(callerApp.UIFigure);
            % Bring app to front:
            figure(app.UIFigure);
            
            % Disable LaunchPipeline button:
            app.LaunchPipelineConfigButton.Enable = 'off';            
            % Add info to text area:
            app.displayPipeInfo(true); % Show pipeline summary
            % Show timestamps of start-stop of current pipeline:
            txt = app.TextAreaPipe.Value;
            txt = [txt; {['(' char(datetime('now')) ') Running pipeline...']}];
            app.TextAreaPipe.Value = txt;
            % Delete current pipeline summary table:
            app.UITablePipe.Data = table();
            app.PipelineObj.b_ignoreLoggedFiles = ~app.SkipstepsCheckBox.Value;
            app.PipelineObj.b_saveDataBeforeFail = app.SavedatabeforeerrorCheckBox.Value;
            app.PipelineObj.run_pipeline;
            % Show Pipeline Summary in UITable:
            tab = app.PipelineObj.PipelineSummary;
            tab.Messages = [];
            % Rearrange columns so "Error Message column" closer to the left
            tab = tab(:,[1:5 8:10]);
            % Rename Error Message column;
            tab.Properties.VariableNames{8} = 'Error_log';
            app.UITablePipe.Data = tab;
            app.UITablePipe.ColumnName = tab.Properties.VariableNames;
            app.UITablePipe.ColumnEditable = false;
            app.UITablePipe.ColumnSortable = false; 
            % Update Text area:
            txt = [txt; {['(' char(datetime('now')) ') Finished! Check the table below!']}];
            if any(~app.PipelineObj.PipelineSummary.Completed)
                txt = [txt; {'WARNING! Errors were found during the execution of this pipeline! Check table below!'}];    
                % Highlight rows with error messages:                                          
                app.UITablePipe.BackgroundColor(~app.UITablePipe.Data.Completed,:) = [.85 0 0];
            end            
            app.TextAreaPipe.Value = txt;                                            
            % Enable button
            app.LaunchPipelineConfigButton.Enable = 'on';
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            warning('off')
            % Update umIT path:
            if ~isdeployed
                app.rootDir = mfilename('fullpath');
                app.rootDir = erase(app.rootDir, ['GUI' filesep 'umIToolbox']);
                addpath(app.rootDir)
                umIT_path_cleanup(app.rootDir)
            end
            updateVersionTag(app.UIFigure); 
            % Update UIFigure position:
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure, 'center');
            app.UIFigure.Visible = 'on';
            if numel(varargin) == 1
                disp('Loading Data. Please wait...')
                app.ProtocolFile = varargin{1};
                app.checkAndLoad_protocol;
                disp('Done.')
            else
                app.TabGroup.Visible = 'off';
                app.SearchtoolsPanel.Visible = 'off';
                app.OptionsPanel.Visible = 'off';
                uialert(app.UIFigure, 'Open or create a new Project file!', 'Project file', 'Icon', "info");
            end
            warning('on')
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [filename, path] = uigetfile('*mat', 'Open a Project File');
            if filename == 0
                return
            end
            app.ProtocolFile = fullfile(path, filename);
            % Lock figure:
            app.TabGroup.Visible = 'off';
            app.checkAndLoad_protocol;
            app.TabGroup.Visible = 'on';
            app.SearchtoolsPanel.Visible = 'on';
            app.OptionsPanel.Visible = 'on';
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            protocol = app.ProtocolObj;
            if isempty(protocol)
                return
            end
            if isempty(app.ProtocolFile)
                app.ProtocolFile= uigetdir(app.ProtocolObj.SaveDir, 'Select Folder to Save Project File');
                app.ProtocolFile = fullfile(app.ProtocolFile, [app.ProtocolObj.Name '.mat']);
                save(app.ProtocolFile, "protocol");
                msgbox('Project Saved!');
            else
                save(app.ProtocolFile, 'protocol');
                uiwait(msgbox(['Project File Saved as ' app.ProtocolFile]));
            end
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            % Reset Protocol Object and FileName
            app.ProtocolObj = [];
            app.ProtocolFile = [];
            %%
            
            Name = inputdlg('Type the Name of your project: ', 'Project Name', [1 60], {''}, 'on');
            if isempty(Name)
                disp('Operation Cancelled by User')
                return
            end
            % Show GUI components:
            app.TabGroup.Visible = 'on';
            app.SearchtoolsPanel.Visible = 'on';
            app.OptionsPanel.Visible = 'on';
            %
            app.ListoffilesLabel.Text = 'Creating New Project File...';
            app.Label_2.Text = '...';drawnow;
            MainDir = uigetdir(pwd, 'Select Directory with Raw Data.');
            if MainDir == 0
                app.ListoffilesLabel.Text = 'Create a New Project File!';
                app.Label_2.Text = '';drawnow;
                disp('Operation cancelled by User')
                return
            end
            SaveDir = uigetdir(pwd, 'Select Save Directory.');
            if SaveDir == 0
                app.ListoffilesLabel.Text = 'Create a New Project File!';
                app.Label_2.Text = '';drawnow;
                disp('Operation cancelled by User')
                return
            end
            [ProtoFunc, FuncPath] = uigetfile('*.m', 'Select Protocol Function.');
            if ProtoFunc == 0
                app.ListoffilesLabel.Text = 'Create a New Project File!';
                app.Label_2.Text = '';drawnow;
                disp('Operation cancelled by User')
                return
            end
            ProtoFunc = erase(ProtoFunc, '.m');
            %             addpath(genpath(MainDir));
            %             addpath(genpath(SaveDir));
            addpath(FuncPath);
            eval(['FuncHandle = @' ProtoFunc ';']);
            app.ProtocolObj = Protocol(Name{:},MainDir, SaveDir,FuncHandle, []);
            app.ListoffilesLabel.Text = 'New Project Created!'; drawnow
            app.Label_2.Text = 'Creating Object Arrays...'; drawnow
            app.ProtocolObj.generateList;
            app.Label_2.Text = 'Creating Save Directories...'; drawnow
            app.ProtocolObj.generateSaveFolders;
            % Make Trees:
            app.makeUItreeNodes;
            app.Label_2.Text = 'All set!';drawnow
            % Save protocol file:
            protocol = app.ProtocolObj;
            save(fullfile(protocol.SaveDir, [protocol.Name '.mat']), 'protocol');
            msgbox('Project Saved!');
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)
            % TREESELECTIONCHANGED is the callback function for Tree, Tree2 and Tree3.
            % It runs the code below every time the user clicks on a node.
            % Lock tree:
            
            app.Tree.Enable = 'off';drawnow
            w = uiprogressdlg(app.UIFigure, 'Title', '','Message', 'Please wait...', 'Indeterminate',"on");
            % Un-select rows in List Of Files TextArea:
            app.ListoffilesListBox.Value = {};
            % This section is used by both Tree and Tree2
            Nodes = app.Tree.SelectedNodes;
            % This for loop exists just to find a "Protocol" object
            idx = false(size(Nodes));
            for i = 1:numel(Nodes)
                if isa(Nodes(i).Parent, 'matlab.ui.container.Tree')
                    idx(i) = true;
                end
            end
            % Remove Group nodes from list:
            Nodes(idx) = [];
            if isempty(Nodes)
                app.Tree.Enable = 'on';
                return
            end
            % Automatically select all childrens from the selected node(s) (Not Dashboard tab):
            
            if ~strcmp(app.TabGroup.SelectedTab.Title, 'Dashboard')
                node_list = [];
                for i = 1:numel(Nodes)
                    node = Nodes(i);
                    app.expandNode(node, true);
                    try
                        node_list = [node_list;node;node.Children;[node.Children.Children]'];
                    catch
                        try
                            node_list = [node_list;node;node.Children];
                        catch
                            node_list = [node_list;node];
                        end
                    end
                    
                end
                app.Tree.SelectedNodes = unique(node_list);
                
            else
                app.expandNode(Nodes, false);
            end
            if strcmp(app.TabGroup.SelectedTab.Title, 'Dashboard')
                NodeObj = app.getNodeObj(Nodes);
                app.displayNodeInfo(NodeObj);
                app.displayNodeFiles(app.ListoffilesListBox, 'all');
                
            elseif strcmp(app.TabGroup.SelectedTab.Title, 'Visualization')
                app.displayNodeFiles(app.SelectafileListBox, '.dat');
            elseif strcmp(app.TabGroup.SelectedTab.Title, 'Analysis')
                % app.displayNodeFiles(app.SelectFileListBox_analysis, '.mat');
                [app.matFileList, app.nodeList, app.nodeObjList] = app.findStatsFiles;
                % Display Node Files in Selection ListBox:
                [~, files, ~] = cellfun(@(x) fileparts(x), app.matFileList, 'UniformOutput', false);
                app.SelectFileListBox_analysis.Value ={};
                app.SelectFileListBox_analysis.Items = unique(files);
                %                 if isempty(files)
                %                     app.SelectButton_analysis.Enable = 'off';
                %                 else
                %                     app.SelectButton_analysis.Enable = 'on';
                %                 end
            else
                app.SelectafileListBox.Items = {''};
            end
            % Unlock tree:
            app.Tree.Enable = 'on';
            %             disp('Node Selected!')
            delete(w)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isempty(app.ProtocolObj)
                delete(app)
            elseif isempty(app.ProtocolFile)
                msg = 'You Created a new Project. Do you want to save it?';
                title = 'Save new Project';
                selection = uiconfirm(app.UIFigure, msg, title, 'Options',{'Save', 'No', 'Cancel'}, ...
                    "DefaultOption",1, "CancelOption",3);
                switch selection
                    case 'Save'
                        protocol = app.ProtocolObj;
                        app.ProtocolFile= uigetdir(protocol.SaveDir, 'Select Folder to Save Project File');
                        app.ProtocolFile = fullfile(app.ProtocolFile, [protocol.Name '.mat']);
                        save(app.ProtocolFile, "protocol");
                        msgbox('Project Saved!');
                        delete(app);
                    case 'No'
                        delete(app)
                    case 'Cancel'
                        return
                end
            else
                a = load(app.ProtocolFile);
                if ~isequal(a.protocol, app.ProtocolObj)
                    msg = 'It seems that you made changes to your Project. Do you want to save them?';
                    title = 'Confirm Save';
                    selection = uiconfirm(app.UIFigure,msg,title,...
                        'Options',{'Save','No', 'Cancel'},...
                        'DefaultOption',1,'CancelOption',3);
                    switch selection
                        case 'Save'
                            protocol = app.ProtocolObj;
                            save(app.ProtocolFile, "protocol");
                            uiwait(msgbox(['Project File Saved as ' app.ProtocolFile]));
                            delete(app)
                        case 'No'
                            delete(app)
                        case 'Cancel'
                            return
                    end
                else
                    delete(app)
                end
            end
        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            protocol = app.ProtocolObj;
            if isempty(protocol)
                return
            end
            [file, Path] = uiputfile('*.mat','Save Protocol as...', fullfile(protocol.SaveDir,[protocol.Name '.mat']));
            if file == 0
                disp('Operation Cancelled by User')
                return
            end
            [~,protocol.Name,~] = fileparts(file);
            save(fullfile(Path,file), 'protocol');
            uiwait(msgbox('Project file saved!'));
            clear protocol
        end

        % Button pushed function: LaunchPipelineConfigButton
        function LaunchPipelineConfigButtonPushed(app, event)
            
            app.TextAreaPipe.Value  = '';
            app.LaunchPipelineConfigButton.Enable = 'off';
            % Control for existing PipelineManager:
            if ~isempty(app.PipelineObj)
                answer = uiconfirm(app.UIFigure, ['The current Pipeline is configured for object "' app.PipelineObj.ClassName '". Continue?'],...
                    'Warning', "Options", {'Yes','Start over', 'Cancel'}, 'DefaultOption',1, 'CancelOption',3);
                if strcmp(answer, 'Cancel')
                    app.LaunchPipelineConfigButton.Enable = 'on';
                    return
                elseif strcmp(answer, 'Start over')
                    app.PipelineObj = [];
                end
            end
            
            % Create PipelineManager object:
            if isempty(app.PipelineObj)
                % Ask User which object the Pipeline will run on:
                classNames = {'Subject', 'Acquisition'};
                selObjs = app.ProtocolObj.extractFilteredObjects(3);
                modNames = unique(cellfun(@(x) class(x), selObjs, 'UniformOutput', false));
                classNames = [classNames modNames];
                [indx,tf] = listdlg('ListString',classNames, 'PromptString', {'Select an object type to',....
                    'run the analysis'}, "ListSize",[160 200],'SelectionMode', 'single');
                if ~tf
                    disp('Operation Cancelled by User')
                    app.LaunchPipelineConfigButton.Enable = 'on';
                    return
                end
                app.PipelineObj = PipelineManager(app.ProtocolObj, classNames{indx});
            end
            % Launch Pipeline Config GUI:
            app.PipelineConfigApp = PipelineConfig_dlgBox(app,app.PipelineObj, false);
            uiwait(app.PipelineConfigApp.UIFigure)
%             % If the user cancelled from the list boxes generated by
%             % PipelineManager class, erase the object and return:
%             if isequal(app.PipelineObj,  PipelineManager(app.ProtocolObj, app.PipelineObj.ClassName))
%                 app.PipelineObj = [];
%                 return
%             end
            % Ensure that the last data will be saved:
            app.PipelineObj.saveLastData;
%             
%             % Generate summary
%             pipeSummary = app.PipelineObj.showPipeSummary;
%             
%             if isempty(pipeSummary)
%                 app.TextAreaPipe.Value  = ''
%             else
%                 app.TextAreaPipe.Value = {app.PipelineObj.showPipeSummary, 'Pipeline is ready to run!'};
%             end
%             app.RunPipelineButton.Enable = 'on';
%             app.LaunchPipelineConfigButton.Enable = 'on';
        end

        % Callback function
        function SaveButtonPushed(app, event)
            Pipeline = app.PipelineObj;
            if isempty(Pipeline)
                uialert(app.UIFigure, 'Pipeline doesn''t exist!', 'Warning!', 'Modal',true);
                return
            end
            answer = inputdlg('Name of Pipeline:', 'Save Pipeline Config File', [1 40]); answer = answer{:};
            app.PipelineObj.savePipe(answer)
            msgbox(['Pipeline saved as ' answer ' in ' fullfile(app.ProtocolObj.SaveDir, 'PipelineConfigFiles')]);
        end

        % Callback function
        function OpenButtonPushed(app, event)
            if ~exist([app.ProtocolObj.SaveDir 'PipelineConfigFiles'], 'dir')
                uialert(app.UIFigure, 'There are no Pipelines saved yet. Create one!', 'Warning!', "Modal",true);
                return
            end
            cd([app.ProtocolObj.SaveDir 'PipelineConfigFiles']);
            [filename, path] = uigetfile('*.json','Open a Pipeline Configuration File');
            if filename == 0
                return
            end
            app.PipelineObj.loadPipe(fullfile(path,filename));
            msgbox(['Pipeline ' filename ' loaded!']);
            app.displayPipeInfo(true);
            app.SaveButton.Enable = 'on';
            app.RunPipelineButton.Enable = 'on';
        end

        % Callback function
        function RunPipelineButtonPushed(app, event)
            % Disable LaunchPipeline button:
            app.LaunchPipelineConfigButton.Enable = 'off';
            % Disable this button:
            app.RunPipelineButton.Enable = 'off';
            txt = app.TextAreaPipe.Value;
            txt = [txt; {['(' char(datetime('now')) ') Running pipeline...']}];
            app.TextAreaPipe.Value = txt;
            app.PipelineObj.b_ignoreLoggedFiles = app.SkipstepsCheckBox.Value;
            app.PipelineObj.b_saveDataBeforeFail = app.SavedatabeforeerrorCheckBox.Value;
            app.PipelineObj.run_pipeline;
            txt = [txt; {['(' char(datetime('now')) ') Finished! Check the table below!']}];
            app.TextAreaPipe.Value = txt;
            % Show Pipeline Summary in UITable_2:
            tab = app.PipelineObj.PipelineSummary;
            tab.Messages = [];
            % Rearrange columns so "Error Message column" closer to the left
            tab = tab(:,[1:5 8:10]);
            % Rename Error Message column;
            tab.Properties.VariableNames{8} = 'Error_log';
            app.UITablePipe.Data = tab;
            app.UITablePipe.ColumnName = tab.Properties.VariableNames;
            app.UITablePipe.ColumnEditable = false;
            app.UITablePipe.ColumnSortable = true;
            % Enable button
            app.RunPipelineButton.Enable = 'on';
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            
            app.TextAreaPipe.Value = 'Selecting items... Please wait.'; drawnow;
            app.Tree.Enable = "off"; drawnow
            
            if isempty(app.Tree.SelectedNodes)
                app.Tree.SelectedNodes = app.Tree.Children.Children;
            end
            % Update Protocol's Idx_Filtered property from the selected
            % nodes:
            app.updateProtocolObjFromNodeList(app.Tree.SelectedNodes);
            
            if strcmp(app.TabGroup.SelectedTab.Title, 'Pipeline control panel')
                app.LaunchPipelineConfigButton.Enable = 'on';
                app.SelectButton.Enable = 'off';
                app.AdvancedSearchButton.Enable = 'off';
                app.TextAreaPipe.Value = {'Selection is done!', 'Now you are good to go with the pipeline!'}; drawnow
                %             elseif strcmp(app.TabGroup.SelectedTab.Title, 'Visualization')
                %                 app.displayNodeFiles(app.SelectafileListBox);
            end
        end

        % Button pushed function: ResetSearchButton_3
        function ResetSearchButtonPushed(app, event)
            app.Tree.Visible = 'off';
            app.TextAreaDashTab.Value = 'Resetting Search... please wait.'; drawnow
            app.TextAreaPipe.Value = 'Resetting Search... please wait.'; drawnow
            
            app.makeUItreeNodes;
            app.ProtocolObj.Idx_Filtered = [];
            app.FilterStructFromSearchBuilder = [];
            app.ProtocolObj.clearFilterStruct;
            app.ProtocolObj.queryFilter;
            app.Tree.Visible = 'on';
            app.TextAreaDashTab.Value = 'Done.'; drawnow
            app.TextAreaPipe.Value = 'Done.'; drawnow
            app.AdvancedSearchButton.Enable = 'on';
            % Some things to do in the Analysis tab:
            app.matFileList = [];
            app.nodeList = [];
            app.nodeObjList = [];
            app.SelectFileListBox_analysis.Items = {''};
        end

        % Value changed function: SavedatabeforeerrorCheckBox
        function SavedatabeforeerrorCheckBoxValueChanged(app, event)
            
            if app.SavedatabeforeerrorCheckBox.Value
                app.PipelineObj.b_saveDataBeforeFail = true;
            end
            
        end

        % Button pushed function: EditButton
        function EditButtonPushed(app, event)
            NodeObj = app.getNodeObj(app.Tree.SelectedNodes);
            eval(['mc = ?' class(NodeObj) ';'])
            b_isPublic = cellfun(@(x,y) any(strcmp(x, 'public')), {mc.PropertyList.SetAccess});
            b_isHidden = [mc.PropertyList.Hidden];
            propList = setdiff({mc.PropertyList(b_isPublic & ~b_isHidden).Name}, {'ID', 'RawFiles'});
            b_isSettable = cellfun(@(x) (isnumeric(NodeObj.(x)) || ischar(NodeObj.(x)) || isdatetime(NodeObj.(x))), propList);
            if ~any(b_isSettable)
                msgbox('There are no editable properties for this object.', 'modal')
                return
            end
            propList = propList(b_isSettable);
            val = cellfun(@(x) num2str(NodeObj.(x)), propList, 'UniformOutput',false);
            prompt = propList;
            dlgTitle = ['Editor : ' app.Tree.SelectedNodes.Text];
            answer = inputdlg(prompt, dlgTitle, 1, val, "on");
            if ~isempty(answer)
                for i = 1:length(prompt)
                    try
                        if ~isnan(str2double(answer{i}))
                            disp(answer{i})
                            eval(['NodeObj.' prompt{i} ' = str2double(answer{' num2str(i) ...
                                '});'])
                        else
                            eval(['NodeObj.' prompt{i} ' = answer{' num2str(i) ...
                                '};'])
                        end
                    catch ME
                        errordlg(getReport(ME, 'basic'), 'Error!')
                    end
                end
                app.displayNodeInfo(NodeObj);
            else
                return
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            Node = app.Tree.SelectedNodes;
            NodeObj = app.getNodeObj(Node);
            if isa(NodeObj, 'Protocol') || isa(NodeObj, 'Modality')
                uialert(app.UIFigure, ['Cannot delete "' Node.Text '". One can only delete Subjects or Acquisitions.'], 'Delete failed.',...
                    'Icon', 'error', 'Modal', true);
                return
            end
            onlyChild = false;
            if isempty(setdiff({Node.Parent.Children.Text}, Node.Text))
                onlyChild = true;
            end
            if onlyChild
                selection = uiconfirm(app.UIFigure,['You are about to delete ' Node.Text '. This is the only element inside ' ...
                    Node.Parent.Text '. This will also delete ' Node.Parent.Text '. Proceed?'], 'Delete Element?',...
                    'Options', {'Delete Item only', 'Delete Item and Files', 'Cancel'}, 'DefaultOption',1,...
                    'CancelOption',3,'Icon','warning');
            else
                selection = uiconfirm(app.UIFigure,['You are about to delete ' Node.Text '. Proceed?'], 'Delete Element?',...
                    'Options', {'Delete Item only', 'Delete Item and Files', 'Cancel'}, 'DefaultOption',1,...
                    'CancelOption',3,'Icon','warning');
            end
            if strcmp(selection, 'Cancel')
                disp('Operation Cancelled by User')
                return
            end
            
            % Proceed with item's deletion:
            
            if strcmp(selection, 'Delete Item only')
                b_delFolder = false;
            else
                b_delFolder = true;
            end
            
            if onlyChild
                % Delete the parent:
                obj_to_del = app.getNodeObj(Node.Parent);
                idx_obj = obj_to_del.MyParent.Array.findElement('ID', obj_to_del.ID, 'strcmp');
                switch class(obj_to_del)
                    case 'Subject'
                        app.ProtocolObj.manualRemoveObj(idx_obj,0, b_delFolder)
                    case 'Acquisition'
                        idx_subj = obj_to_del.MyParent.MyParent.Array.findElement('ID', obj_to_del.MyParent.ID, 'strcmp');
                        app.ProtocolObj.manualRemoveObj(idx_subj,idx_obj, b_delFolder)
                    otherwise
                        uialert(app.UIFigure, 'Cannot delete Protocol!', 'Delete failed!', 'Icon', 'error', 'Modal',true);
                        return
                end
                % Delete Parent Node:
                %                 idx = arrayfun(@(x) isequal(x,app.Tree.SelectedNodes), app.Tree.SelectedNodes.Parent.Children);
                %                 delete(app.Tree.SelectedNodes.Parent.Children(idx))
                
            else
                % Delete the selected object:
                idx_obj = NodeObj.MyParent.Array.findElement('ID', NodeObj.ID, 'strcmp');
                switch class(NodeObj)
                    case 'Subject'
                        app.ProtocolObj.manualRemoveObj(idx_obj,0,b_delFolder);
                    case 'Acquisition'
                        idx_subj = NodeObj.MyParent.MyParent.Array.findElement('ID', NodeObj.MyParent.ID, 'strcmp');
                        app.ProtocolObj.manualRemoveObj(idx_subj,idx_obj,b_delFolder);
                end
                %                 % Delete Node:
                %                 delete(app.Tree.SelectedNodes)
            end
            % rebuild Tree:
            app.ListoffilesLabel.Text = 'Updating list...';
            app.ProtocolObj.updateList;
            app.ListoffilesLabel.Text = 'Recreating tree...';
            app.makeUItreeNodes;
            app.ListoffilesLabel.Text = 'Done';
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            if isempty(app.ProtocolObj)
                uiwait(warndlg('You have to load/create a Project File first!', 'Warning!', 'modal'));
                return
            end
            if strcmp(app.SelectButton.Enable, 'off')
                app.UndoSelectionButtonPushed;
            end
            selectedTab = app.TabGroup.SelectedTab;
            % Show SearchTool Panel (for 3 out of 4 tabs);
            if strcmp(app.SearchtoolsPanel.Visible, 'off')
                app.SearchtoolsPanel.Visible = 'on';
            end
            % Hide Options Panel's content:
            arrayfun(@(x) set(x, 'Visible', 'off'), app.OptionsPanel.Children); app.OptionsPanel.Visible = 'on';
            % Remove any pre-selected items in UITREE:
            app.Tree.SelectedNodes = [];
            % Show/Hide items for each tab:
            switch selectedTab.Title
                case 'Dashboard'
                    
                    app.Tree.Multiselect = 'off';
                    app.SelectionLabel.Visible = 'on';
                    app.EditButton.Visible = 'on';
                    app.DeleteButton.Visible = 'on';
                    app.Tree.Enable = 'on';
                case 'Pipeline control panel'
                    
                    app.Tree.Multiselect = 'on';
                    app.SelectButton.Visible = 'on';
                    if strcmp(app.SelectButton.Enable, 'on')
                        app.Tree.Enable = 'on';
                    else
                        app.Tree.Enable = 'off';
                    end
                    app.UndoSelectionButton.Visible = 'on';
                case 'Visualization'
                    
                    app.Tree.Multiselect = 'on';
                    app.Tree.Enable = 'on';
                    app.SelectafileListBox.Items = {''};
                case 'Analysis'
                    app.Tree.Multiselect = 'on';
                    app.GroupingToolBtn.Visible = 'on';
                    app.ErasegroupsBtn.Visible = 'on';
                    app.SelectFileListBox_analysis.Items = {''};
            end
            %             disp(['Multiselect is ' upper(app.Tree.Multiselect)])
        end

        % Menu selected function: ResetLogBookMenu_2
        function ResetLogBookMenu_2Selected(app, event)
            app.ProtocolObj.clearLogBook;
            msgbox('Log Book reset!',"modal")
        end

        % Menu selected function: RestoregarbageListMenu
        function RestoregarbageListMenuSelected(app, event)
            app.ProtocolObj.purgeGarbageList;
            uialert(app.UIFigure, 'Garbage List was restored. You should see the deleted items back into the List if the raw data were not changed.',...
                'Garbage List restored!', "Icon","info", 'Modal',true, 'CloseFcn','uiresume(gcbf)'); uiwait(gcbf);
            app.ProtocolObj.updateList;
            app.makeUItreeNodes;
        end

        % Menu selected function: AddnewdataonlyMenu, FullupdateMenu
        function UpdateprojectMenuSelected(app, event)
            % This callback updates the project file
            
            % Choose to keep(false) or discard(true) objects from
            % "protocol" with missing recordings.
            if startsWith(event.Source.Text, 'full', "IgnoreCase",true)
                b_discardData = true;
            else
                b_discardData = false;
            end
            % Update "protocol" object:
            if ~isempty(app.ProtocolObj)
                app.ListoffilesLabel.Text = 'Updating Project Files...'; drawnow
                app.ProtocolObj.updateList(b_discardData);
                app.check4newData;
                % Make Tree:
                app.makeUItreeNodes;
                % Save updated version of the protocol file:
                app.SaveMenuSelected;
            else
                uialert(app.UIFigure, 'Open a Project file first!', 'Warning!', "Icon","warning", "Modal",true);
            end
        end

        % Menu selected function: LookfornewdataMenu
        function LookfornewdataMenuSelected(app, event)
            if isempty(app.ProtocolObj)
                uialert(app.UIFigure, 'Open a Project file first!', 'Warning!', "Icon","warning", "Modal",true);
                return
            end
            app.check4newData
        end

        % Button pushed function: UndoSelectionButton
        function UndoSelectionButtonPushed(app, event)
            % Re-enables the UITREE and erases PROTOCOLOBJ.IDX_FILTERED.
            app.SelectButton.Enable = 'on';
            app.AdvancedSearchButton.Enable = 'on';            
            app.LaunchPipelineConfigButton.Enable = 'off';
            % Erase text area:
            app.TextAreaPipe.Value = 'Select the objects and click on "Select" to start.';
            % Reset Idx_Filtered prop. of ProtocolObj
            app.ProtocolObj.clearFilterStruct; app.ProtocolObj.queryFilter;
            app.Tree.Enable = "on"; drawnow
        end

        % Menu selected function: ViewLogBookMenu
        function ViewLogBookMenuSelected(app, event)
            if isempty(app.ProtocolObj)
                return
            end
            fig = uifigure('Name', 'Project''s Log Book');
            tab = uitable(fig);
            tab.OuterPosition = [10 10 (fig.InnerPosition(3)-10) (fig.InnerPosition(4)-10)];
            a = load(app.ProtocolObj.LogBookFile);
            tab.Data = a.LogBook;
            tab.ColumnName = a.LogBook.Properties.VariableNames;
            tab.ColumnEditable = false;
            tab.ColumnSortable = true;
        end

        % Value changed function: SkipstepsCheckBox
        function SkipstepsCheckBoxValueChanged(app, event)
            value = app.SkipstepsCheckBox.Value;
            app.PipelineObj.b_ignoreLoggedFiles = value;
        end

        % Callback function
        function RetinotopyButtonPushed(app, event)
            retino_maps(app.selectedObjs, app.SelectafileListBox.Value);
        end

        % Callback function
        function SeedPixelCorrelationButtonPushed(app, event)
            if numel(app.selectedObjs) > 1
                uialert(app.UIFigure, 'This app accepts only one Object!','Warning', 'Icon',"warning", "Modal",true);
                return
            end
            SeedPixMap(app.selectedObjs, app.SelectafileListBox.Value);
        end

        % Button pushed function: ROImanagerButton
        function ROImanagerButtonPushed(app, event)
            if numel(app.selectedObjs) > 1
                uialert(app.UIFigure, 'This app accepts only one Object!','Warning', 'Icon',"warning", "Modal",true);
                return
            end
            obj = app.selectedObjs{:};
            app.ROImanagerButton.Enable = 'off';
            r = ROImanager(app,fullfile(obj.SaveFolder, app.SelectafileListBox.Value));
            waitfor(r)
            app.ROImanagerButton.Enable = 'on';
        end

        % Button pushed function: ManualImageregistrationButton
        function ManualImageregistrationButtonPushed(app, event)
            if numel(app.selectedObjs) > 1
                uialert(app.UIFigure, 'This app accepts only one Object!','Warning', 'Icon',"warning", "Modal",true);
                return
            end
            if isempty(app.SelectafileListBox.Value)
                uialert(app.UIFigure, 'Select one file from the list to be aligned!','Warning', 'Icon',"warning", "Modal",true);
                return
            end
            myObj = app.selectedObjs{:};
            app.ManualImageregistrationButton.Enable = 'off';
            b_redo = true;
            while b_redo
                b_redo = manual_alignFrames(myObj, app.SelectafileListBox.Value);
            end
            app.ManualImageregistrationButton.Enable = 'on';
        end

        % Button pushed function: DataViewerButton
        function DataViewerButtonPushed(app, event)
            if numel(app.selectedObjs) ~=1
                uialert(app.UIFigure, 'This app only accepts one file.', 'Too many inputs',"Icon","warning")
                return
            end
            app.DataViewerButton.Enable = 'off';
            filename = fullfile(app.selectedObjs{1}.SaveFolder, app.SelectafileListBox.Value);
            d = DataViewer(filename);
            waitfor(d)
            app.DataViewerButton.Enable = 'on';
        end

        % Value changed function: ListoffilesListBox
        function ListoffilesListBoxValueChanged(app, event)
            % Callback to show the data (or metaData) of selected file.
            % Single-selection only!
            % Here, the dataHistory will be displayed as a table while the
            % dimension of other properties will be displayed in the text
            % area.
            value = app.ListoffilesListBox.Value;
            
            if isempty(value)
                return
            elseif numel(value)>1
                app.TextAreaDashTab.Value = 'Select ONE file to show info.';
                app.UITable.Data = table(0,0,0,0,0);
                return
            else
                value = value{:};
            end
            
            NodeObj = app.getNodeObj(app.Tree.SelectedNodes);
            % Map data/metaData File
            if endsWith(value,'.dat')
                mapData = matfile(fullfile(NodeObj.SaveFolder, strrep(value, '.dat', '.mat')));
            else
                mapData = matfile(fullfile(NodeObj.SaveFolder, value));
            end
            
            % Extract variable dimensions and string values as text
            propList = setdiff(properties(mapData),{'Properties', 'dataHistory'});
            disp_txt = cell(1,length(propList));
            app.TextAreaDashTab.Value = '';
            for i = 1:length(propList)
                data = mapData.(propList{i});
                if ischar(data(:)) || isstring(data(:))
                    disp_txt{i} = sprintf('%s:\n\tValue:"%s"', propList{i}, data);
                elseif isnumeric(data) && numel(data)<5
                    vals = arrayfun(@(x) num2str(x), data, 'UniformOutput', false);
                    vals = strjoin(vals, ', ');
                    disp_txt{i} = sprintf('%s:\n\tValue:"%s"', propList{i}, vals);
                elseif iscell(data) && ischar([data{:}])
                    data = mapData.(propList{i});
                    data = cellfun(@(x) num2str(x), data, 'UniformOutput',false);
                    data = strjoin(data,', ');
                    disp_txt{i} = sprintf('%s:\n\tValue:{%s}', propList{i}, data);
                else
                    dims = size(data);
                    dims = arrayfun(@(x) num2str(x), dims, 'UniformOutput', false);
                    dims = strjoin(dims, ' x ');
                    disp_txt{i} = sprintf('%s:\n\tDimension:%s, Type: %s"', propList{i}, dims, class(data));
                end
                
            end
            app.TextAreaDashTab.Value = disp_txt;
            % Create Data History table:
            
            % Transform "dataHistory" structure as table:
            if isprop(mapData, 'dataHistory')
                info = struct2table(mapData.dataHistory, 'AsArray',true);
                str_params = {};
                str_outFiles = {};
                for i = 1:height(info)
                    % Organize function's params as string:
                    params = table2array(info(i,5));
                    if iscell(params)
                        params = params{:};
                    end
                    if isempty(params)
                        C = {'None', 'None'};
                    else
                        fNames = fieldnames(params);
                        idxNum = cellfun(@(x) isnumeric(params.(x)) | islogical(params.(x)), fNames);
                        idxCell = cellfun(@(x) iscell(params.(x)), fNames);
                        valsGeneric = cellfun(@(x) params.(x),fNames(~idxNum & ~idxCell), 'UniformOutput',false);
                        valsNum = cellfun(@(x) num2str(params.(x)), fNames(idxNum), 'UniformOutput',false);
                        valsCell = cellfun(@(x) strjoin(params.(x), ';\n'), fNames(idxCell), 'UniformOutput',false); % Here, we assume that the cell array contains chars.
                        C = [fNames(~idxNum & ~idxCell)', fNames(idxNum)', fNames(idxCell)'; valsGeneric', valsNum', valsCell'];
                    end
                    str_params{i} = sprintf('%s : %s\n', C{:});
                    % Do the same to output files:
                    fList = table2array(info(i,7));
                    if iscell([fList{:}])
                        fList = fList{:};
                    end
                    str_outFiles{i} = strjoin(fList, '\n');
                end
                info.Parameters = str_params';
                info.OutputFiles = str_outFiles';
                info.opts = [];
                info.outputFile_list = [];
                % Update table:
                app.UITable.Data = info;
                app.UITable.ColumnName = info.Properties.VariableNames;
                app.UITable.ColumnEditable = false;
                app.UITable.ColumnSortable = true;
                app.TableTitle.Text = ['History of File: ' value];
            end
        end

        % Button pushed function: AdvancedSearchButton
        function AdvancedSearchButtonPushed(app, event)
            app.AdvancedSearchButton.Enable = 'off';
            % Some things to do in the Analysis tab:
            app.SelectFileListBox_analysis.Items = {''};
            
            %
            app.SearchBuilderApp = SearchBuilder_dlgBox(app, app.ProtocolObj.FilterStruct);
            waitfor(app.SearchBuilderApp)
            app.AdvancedSearchButton.Enable = 'on';
        end

        % Value changed function: SelectafileListBox
        function SelectafileListBoxValueChanged(app, event)
            
            % Lock Tree3:
            app.Tree.Enable = 'off';
            
            value = app.SelectafileListBox.Value;
            if isempty(value)
                return
            end
            idxList = false(numel(app.Tree.SelectedNodes),1);
            for i = 1:numel(app.Tree.SelectedNodes)
                idxList(i) = ismember(value, app.getFileList(app.selectedObjs{i},'all'));
            end
            
            % Change Icons in tree nodes to show selection:
            app.Tree.SelectedNodes(~idxList) = [];
            app.selectedObjs = arrayfun(@(x) app.getNodeObj(x), app.Tree.SelectedNodes, 'UniformOutput', false);
            arrayfun(@(x) expand(x.Parent, 'all'), app.Tree.SelectedNodes)
            % Unlock Tree:
            app.Tree.Enable = 'on';
            % Pre-select first Ige
        end

        % Callback function
        function Tree4SelectionChanged(app, event)
            % Callback made for controlling behaviour of Analysis tab
            % UITree.
            app.SelectButton_analysis.Enable = 'off';
            selectedNodes = app.Tree4.SelectedNodes;
            % erase List boxes:
            app.SelectFileListBox_analysis.Items = {''};
            %
            
            if isempty(selectedNodes)
                return
            end
            disp('Building Node Selection...')
            lvl = arrayfun(@(x) app.findNodeLevel(x), selectedNodes);
            top_rank = min(lvl);
            nodelist = [];
            switch top_rank
                case 0
                    top_nodes = [app.Tree4.SelectedNodes(lvl == 0)];
                    for i = 1:numel(top_nodes)
                        top_node = top_nodes(i);
                        childs = [top_node.Children];
                        g_childs = vertcat(childs.Children);
                        nodelist = [nodelist; childs; g_childs];
                    end
                    app.Tree4.SelectedNodes = union(app.Tree4.SelectedNodes, nodelist);
                    tmp_lvl = arrayfun(@(x) app.findNodeLevel(x), app.Tree4.SelectedNodes);
                    tmp_idx = (tmp_lvl == 0);
                    app.Tree4.SelectedNodes = app.Tree4.SelectedNodes(~tmp_idx);
                case 1
                    idx = (lvl == 1);
                    nodes = selectedNodes(idx);
                    nodelist = nodes;
                    for i = 1:numel(nodes)
                        nodelist = [nodelist; nodes(i).Children; [nodes(i).Children.Children]'];
                        expand(nodes(i), 'all');
                    end
                    app.Tree4.SelectedNodes = union(app.Tree4.SelectedNodes, nodelist);
                case 2
                    idx = (lvl == 2);
                    nodes = selectedNodes(idx);
                    nodelist = nodes;
                    for i = 1:numel(nodes)
                        nodelist = [nodelist; nodes(i).Children];
                        expand(nodes(i), 'all');
                    end
                    app.Tree4.SelectedNodes = union(app.Tree4.SelectedNodes, nodelist);
            end
            
            [app.matFileList, app.nodeList, app.nodeObjList] = app.findStatsFiles;
            disp('Done Selecting!')
            % Display Node Files in Selection ListBox:
            [~, files, ~] = cellfun(@(x) fileparts(x), app.matFileList, 'UniformOutput', false);
            if isempty(files)
                app.SelectFileListBox_analysis.Items = {'No .MAT files found!'};
            else
                app.SelectFileListBox_analysis.Items = unique(files);
                app.SelectFileListBox_analysis.Value = {};
                app.SelectFileListBox_analysisValueChanged;
            end
        end

        % Value changed function: SelectFileListBox_analysis
        function SelectFileListBox_analysisValueChanged(app, event)
            value = app.SelectFileListBox_analysis.Value;
            if isempty(value)
                cellfun(@(x) set(x, 'Icon', ''), app.nodeList);
                set(app.SelectButton_analysis, 'Enable','off');
                return
            end
            w = uiprogressdlg(app.UIFigure, 'Title', 'Please wait...','Message', 'Gathering list of observation from selected file...', 'Indeterminate',"on");
            % Erase all check icons from the UITree:
            h = findobj(app.Tree, 'Icon', 'icon_check.png');
            arrayfun(@(x) set(x, 'Icon', ''), h);
            % Redraw icons for selected items:
            [~,all_files,~] = cellfun(@(x) fileparts(x), app.matFileList, 'UniformOutput',false);
            idx = strcmp(all_files, value);
            cellfun(@(x) set(x, 'Icon', ''), app.nodeList(~idx));
            cellfun(@(x) set(x, 'Icon', 'icon_check.png'), app.nodeList(idx));
            cellfun(@(x) expand(x.Parent, 'all'), app.nodeList(idx));
            
            % Open .MAT files and show variable names:
            matFiles = app.matFileList(idx);
            obsNames = cell(size(matFiles));
            for i = 1:numel(matFiles)
                mDt = matfile(matFiles{i});
                obsNames{i} = mDt.obsID;
            end
            % Update obsList:
            app.obsList = obsNames;
            
            obsNames = cellfun(@num2str, unique(vertcat(obsNames{:}),'stable'), 'UniformOutput', false);
            app.ObservationlistListBox.Items = obsNames;
            if ~isempty(obsNames)
                app.SelectButton_analysis.Enable = 'on';
            else
                app.SelectButton.Enable = 'off';
            end
            delete(w)
        end

        % Value changed function: SelectButton_analysis
        function SelectButton_analysisValueChanged(app, event)
            value = app.SelectButton_analysis.Value;
            % Disable other GUI elements:
            app.GroupingToolBtn.Enable ='off';
            app.ErasegroupsBtn.Enable = 'off';
            
            if value
                % Create waitbar:
                w = uiprogressdlg(app.UIFigure, 'Title','Please wait...', 'Message', 'Opening data...', 'Indeterminate',"on");
                app.SavetoMatButton.Enable = 'on';
                app.SelectButton_analysis.Text = 'Undo';drawnow
                app.Tree.Enable = 'off';
                app.SelectFileListBox_analysis.Enable = 'off';
                % Get selected Nodes (those with the green check box Icon):
                [~,all_files,~] = cellfun(@(x) fileparts(x), app.matFileList, 'UniformOutput',false);
                idx = strcmp(all_files, app.SelectFileListBox_analysis.Value) & cellfun(@(x) strcmp(x.Icon, 'icon_check.png'), app.nodeList');
                gList = app.getMyGroup(app.nodeList(idx));
                statsFile = [app.SelectFileListBox_analysis.Value '.mat'];
                w.Message = 'Formatting data for plotting...';
                % Instantiate StastManager object:
                app.StatsObj = StatsManager(app.nodeObjList(idx), app.ObservationlistListBox.Items, gList, statsFile);
                app.EventlistListBox.Items = app.StatsObj.list_of_events;
                w.Message = 'Finishing...';
                % Add inputFeatures info to UITable:
                boolMap = containers.Map([true, false], {'Yes', 'No'});
                fn = fieldnames(app.StatsObj.inputFeatures);
                values = cell(size(fn));
                bcgCol = repmat([.98, .98, .98],numel(fn),1);
                for ind = 1:numel(fn)
                    if islogical(app.StatsObj.inputFeatures.(fn{ind}))
                        values{ind} = boolMap(app.StatsObj.inputFeatures.(fn{ind}));
                        if ~app.StatsObj.inputFeatures.(fn{ind})
                            bcgCol(ind,:) = [.7,0,0];
                        end
                    elseif iscell(app.StatsObj.inputFeatures.(fn{ind}))
                        values{ind} = strjoin(app.StatsObj.inputFeatures.(fn{ind}), ',');
                    else
                        values{ind} = app.StatsObj.inputFeatures.(fn{ind});
                    end
                end
                app.UITable2.Data = cell2table(values);
                app.UITable2.RowName = {'Equal number of Acq.', 'Equal dimensions',...
                    'Equal sizes per Subj.', 'Equal sizes overall', 'Exportable to .CSV', 'Has all obs.', 'Has Events','Data type', 'Dimension Name'};
                %                 app.UITable2.ColumnName = '';
                %                 app.UITable2.Position(4) = 134;
                app.UITable2.BackgroundColor = bcgCol;
                % Turn Visible the Apps&Tool panel and sub-panels:
                app.AppstoolsPanel.Visible = 'on';
                app.PlottingtoolsPanel.Visible = 'on';
                app.UtilitiesPanel.Visible = 'on';
                % Enable pertinent buttons:
                app.controlAnalysisButtons;
                
                close(w)
            else
                app.SelectButton_analysis.Text = 'Select';
                app.Tree.Enable = 'on';
                % Hide panels:
                app.AppstoolsPanel.Visible = 'off';
                app.PlottingtoolsPanel.Visible = 'off';
                app.StatsObj = [];
                app.GroupingToolBtn.Enable ='on';
                app.ErasegroupsBtn.Enable = 'on';
                % Erase table content:
                app.UITable2.Data = [];
                % Erase green check icons in UITree:
                nodes = app.Tree.Children;
                % Subject Level
                for i = 1:numel(nodes)
                    sbj_nodes = nodes(i).Children;
                    set(sbj_nodes, 'Icon','')
                    % Acq Level
                    for j = 1:numel(sbj_nodes)
                        acq_nodes = sbj_nodes(j).Children;
                        set(acq_nodes, 'Icon','')
                        for k = 1:numel(acq_nodes)
                            mod_nodes = acq_nodes(k).Children;
                            set(mod_nodes, 'Icon','')
                        end
                    end
                end
                % Erase content of "Select file" listbox:
                app.SelectFileListBox_analysis.Items = {};
                app.SelectFileListBox_analysis.Enable = 'on';
                % Disable button
                app.SelectButton_analysis.Enable = 'off';
            end
            
        end

        % Button pushed function: ExporttoCSVButton
        function ExporttoCSVButtonPushed(app, event)
            % This callback calls StatsManager methods to create and save
            % all observation data into a .CSV file.
            [file, path] = uiputfile('*.csv', 'Export Data to .CSV file', 'obsData.csv');
            app.StatsObj.exportToCSV(fullfile(path, file));
            answer = uiconfirm(app.UIFigure, ['Data saved as: ' fullfile(path, file)], 'Data saved as .CSV', "Icon","info", 'Options',{'OK', 'Show in folder'});
            if strcmpi(answer, 'ok')
                return
            end
            if (ispc)
                winopen(path);
            elseif ismac
                [status, pathOut] = system(['open ' path]); %#ok
            else
                % using xdg-open to open a file in Linux. Some Linux systems might not have
                % xdg-open. In that case display an error with the file path:
                [status, pathOut] = system(['xdg-open ' path]); %#ok
            end
        end

        % Button pushed function: TableViewButton
        function TableViewButtonPushed(app, event)
            % Callback to create a figure with a table per observation
            
            % Disable button:
            set(app.TableViewButton, 'Enable','off');drawnow
            % Delete currently opened figure before creating a new one:
            h = findall(0, 'Tag', 'TableFig');
            delete(h)
            format long G; % Change number format to facilitate viz in UITable.
            % Get Array of tables:
            [obsTab, labels] = app.StatsObj.createTable;
            if size(labels,2)> size(labels,1)
                labels = labels';
            end
            % Transform the numeric data into strings to control the display format:
            cn = obsTab.Properties.VariableNames;
            for ii = 7:size(obsTab,2)
                obsTab.(cn{ii}) = arrayfun(@(x) sprintf('%0.3f',x),table2array(obsTab(:,ii)), 'UniformOutput',false);
            end
            % Create figure and display tables:
            uf = uifigure('Name', 'Observation Table', 'NumberTitle', 'off', 'Position', [1 1 800 500], 'ToolBar',...
                "none", "MenuBar","none", 'Tag', 'TableFig', 'Visible', 'off', 'CreateFcn',{@movegui, 'center'});                       
            g = uigridlayout(uf, [1 2]); g.ColumnWidth = {'3x','1x'};g.RowHeight = {'1x'};
            pan = uipanel(g,'Title', 'Observations'); pan.Layout.Column = 1;
            g1 = uigridlayout(pan,[1 1]);
            table = uitable('Parent', g1, "Data",obsTab, 'BackgroundColor',repmat([.95 .95 .95],height(obsTab),1), 'DisplayDataChangedFcn',@resetBcgColor);
            % Sort table only by meta data, given that the numerical data were transformed into strings.
            toSort = false(1,size(obsTab,2));
            toSort(1:6) = true;
            table.ColumnSortable = toSort;
            % Apply callback
            cnd_tab = uitable(g, 'Data',labels, 'CellSelectionCallback',@(src,evnt) condSelect(src,evnt,table));
            cnd_tab.ColumnName = 'Val ID'; cnd_tab.Layout.Column = 2;
            % Make it visible
            uf.Visible = 'on';
            uiwait(uf);
            set(app.TableViewButton, 'Enable','on');
            % Local callbacks
            function condSelect(src,evnt, tabH)
                % This callback highlights the cells in the main table when
                % the user clicks on a value ID if the observation ID is
                % the same as the value ID (This occurs mainly in
                % correlation matrices).
                selVal = src.Data(evnt.Indices(:,1));
                indxVal = find(ismember(table2cell(tabH.DisplayData(:,6)), selVal));
                newCol = repmat([.95 .95 .95],height(tabH.DisplayData),1);
                for i = 1:length(indxVal)
                    newCol(indxVal(i),:) = [0 .8 0];
                end
                set(tabH, 'BackgroundColor', newCol);
            end
            
            function resetBcgColor(src,~)
                % This callback resets the Background color of cells when
                % the User sorts the columns.
                set(src, 'BackgroundColor', repmat([.95 .95 .95], height(src.Data),1));
            end
        end

        % Button pushed function: GroupingToolBtn
        function GroupingToolBtnPushed(app, event)
            % This callback opens the data_grouping_tool app and updates
            % the UITree.
            app.GroupingToolBtn.Enable = 'off';
            a = data_grouping_tool(app);
            waitfor(a);
            app.groupNodes;
            % Disable Advanced Search Panel when grouping data:
            app.AdvancedSearchButton.Enable = 'off';
            app.ResetSearchButton_3.Enable = 'off';
            app.GroupingToolBtn.Enable = 'on';
            figure(app.UIFigure); % Show main app.
        end

        % Button pushed function: SavetoMatButton
        function SavetoMatButtonPushed(app, event)
            % Callback to save current selected data to a table in a .MAT
            % file.
            disp('Saving data to .MAT file...')
            % Select filename and path to save file:
            [file, path] = uiputfile('*.mat', 'Save data to MAT file', fullfile(app.ProtocolObj.SaveDir,'out.mat'));
            if file == 0
                disp('Operation cancelled by User')
                return
            end
            stats_data = app.StatsObj.dataArr;
            list_of_groups = unique(app.StatsObj.list_of_groups);
            list_of_observations = app.StatsObj.obs_list;
            list_of_events = app.StatsObj.list_of_events;
            % remove the MatFile field from stats_data:
            stats_data = arrayfun(@(x) rmfield(x, 'MatFile'), stats_data);
            save(fullfile(path,file), 'stats_data','list_of_groups', 'list_of_events', 'list_of_observations');
            answer = uiconfirm(app.UIFigure, ['Data saved as: ' fullfile(path, file)], 'Data saved as .MAT', "Icon","info", 'Options',{'OK', 'Show in folder'});
            if strcmpi(answer, 'ok')
                return
            end
            
            if (ispc)
                winopen(path);
            elseif ismac
                [status, pathOut] = system(['open ' path]); %#ok
            else
                % using xdg-open to open a file in Linux. Some Linux systems might not have
                % xdg-open. In that case display an error with the file path:
                [status, pathOut] = system(['xdg-open ' path]); %#ok
            end
        end

        % Button pushed function: PlotCorrelationMatrixButton
        function PlotCorrelationMatrixButtonPushed(app, event)
            % Launches the PlotCorrMatrix app
            app.PlotCorrelationMatrixButton.Enable = 'off'; drawnow
            h = PlotCorrMatrix(app.StatsObj);
            waitfor(h)
            app.PlotCorrelationMatrixButton.Enable = 'on';
            
        end

        % Node expanded function: Tree
        function TreeNodeExpanded(app, event)
            %             disp('Node expanded!')
            
        end

        % Button pushed function: ErasegroupsBtn
        function ErasegroupsBtnButtonPushed(app, event)
            % This callback erases the Group Nodes in the UITREE and
            % regroups the data into one tree organized as
            % Subject>>Acquisition>>Modality.
            
            if app.groupInfo.nGroups<2 & strcmp(app.groupInfo.gIdxList.Name(1), app.ProtocolObj.Name)
                disp('No groups found! Operation cancelled.')
                return
            end
            answer = uiconfirm(app.UIFigure, 'All groups will be erased. Proceed?', 'Erase groups', 'Options',{'Yes', 'Cancel'}, 'DefaultOption',2);
            if strcmp(answer, 'Cancel')
                disp('Operation cancelled by User');
                return
            end
            %
            disp('Erasing groups...')
            % Merge indices into one array:
            idxList = vertcat(app.groupInfo.gIdxList.idxList);
            % Rebuild UITree:
            app.makeUItreeNodes;
            app.filterSearch(idxList);
            % Re-enable Advanced Search buttons:
            app.AdvancedSearchButton.Enable = 'on';
            app.ResetSearchButton_3.Enable = 'on';
            disp('Done!')
        end

        % Button pushed function: LineBoxplotsButton
        function LineBoxplotsButtonPushed(app, event)
            app.LineBoxplotsButton.Enable = 'off'; drawnow
            h = PlotLongData(app.StatsObj);
            waitfor(h);
            app.LineBoxplotsButton.Enable = 'on'; drawnow
        end

        % Button pushed function: PlotAverageMapsButton
        function PlotAverageMapsButtonPushed(app, event)
            app.PlotAverageMapsButton.Enable = 'off';
            h = PlotAvgMaps(app.StatsObj);
            waitfor(h)
            app.PlotAverageMapsButton.Enable = 'on';
            %             waitfor(h)
            %             app.UIFigure.Visible = 'on';
            
            %             % Validate if the data has Y,X dimensions:
            %             disp('Checking data requirements...');
            %
            %             idx = false(length(app.StatsObj.list_of_objs),4);
            %             for i = 1:length(app.StatsObj.list_of_objs)
            %                 % Check if data has "dim_names" variable
            %                 idx(i,1) = isprop(app.StatsObj.MfileArr{i}, 'dim_names');
            %                 % Check if "dim_names" has "Y" and "X" dimensions:
            %                 if idx(i,1)
            %                     idx(i,2) = all(ismember(upper(app.StatsObj.MfileArr{i}.dim_names), upper({'Y', 'X'})));
            %                 end
            %                 % Check if "refPt" coordinates exist:
            %                 idx(i,3) = isprop(app.StatsObj.MfileArr{i}, 'refPt');
            %                 % Check if "pxPermm" exists:
            %                 idx(i,4) = isprop(app.StatsObj.MfileArr{i}, 'pxPermm');
            %             end
            %             % Show alert if any of the above checks failed:
            %             if ~any(idx,'all')
            %                 % disp uialert:
            %                 msg = {'The following recordings are invalid (Name : reason):'};
            %                 for i = size(idx,1)
            %                     indx = find(~idx(i,:), 1, 'first');
            %                     switch indx
            %                         case 1
            %                             reason = 'dim_names is missing';
            %                         case 2
            %                             reason = 'data has no Y,X dimensions';
            %                         case 3
            %                             reason = 'Reference point is missing';
            %                         case 4
            %                             reason = 'Pixel ratio is missing';
            %                         otherwise
            %                             reason = 'unknown reason';
            %                     end
            %                     if indx
            %                         msg = vertcat(msg, {[strjoin(app.StatsObj.stats_data(i,[1:3]), '--') ': ' reason]});
            %                     end
            %                 end
            %                 uialert(app.UIFigure, msg, 'Data validation failed!')
            %                 return
            %             end
            
            %             % Get user input for function's options:
            %             mskArr = {};
            %             b_isPolar = false;
            %             answer = uiconfirm(app.UIFigure, 'Use logical mask?','Set options', 'Options',{'Yes', 'No'}, 'DefaultOption',2);
            %             if strcmpi(answer, 'yes')
            %                 disp('Loading masks...');
            %                 roiFile = uigetfile(fullfile(app.StatsObj.list_of_objs{1}.SaveFolder, 'ImagingReferenceFrame.mat'),...
            %                     'Select the Imaging Reference file');
            %                 mskArr = cell(size(app.StatsObj.list_of_objs));
            %                 for i = 1:length(mskArr)
            %                     try
            %                         mskFileName = findMyROIfile(roiFile, app.StatsObj.list_of_objs{i});
            %                         mtf = matfile(mskFileName);
            %                         mskArr{i} = mtf.logical_mask;
            %                     catch ME
            %                         ME = addCause(ME, MException('umIToolbox:umIToolbox:missingInput', ['Logical mask not found in  file: ' roiFile]));
            %                         throw(ME)
            %                     end
            %                 end
            %             end
            % SECTION BELOW DISABLED FOR NOW
            %             answer = uiconfirm(app.UIFigure, 'Does your data have polar coordinates (e.g. retinotopy phase maps)?',...
            %             'Set options', 'Options',{'Yes', 'No'}, 'DefaultOption',2);
            %             if strcmpi(answer, 'yes')
            %                 b_isPolar = true;
            %             end
            %             % Create average maps:
            %             [avgMapArr, gNames] = app.avgMaps(mskArr, b_isPolar);
            %             % Plot maps:
            %             disp('plotting maps')
            %             % Plot All Maps for each observation:
            %             for i = 1:size(avgMapArr,3)
            %                 f=figure('Name', app.StatsObj.obs_list{i}, 'Visible', 'off');
            %                 for j = 1:size(avgMapArr,1)
            %                     s(j) = subplot(size(avgMapArr,1),1,j, 'Parent', f);
            %                     frames = cat(3, avgMapArr{j,:,i});
            %                     mnt = montage(frames, 'Size',[1 size(avgMapArr,2)], 'Parent', s(j));
            %                     set(s(j), 'CLim', [min(frames(:)), max(frames(:))], 'ColorMap', colormap('jet'));
            %                     s(j).YLabel.String = gNames{j};
            %                     if j == size(avgMapArr,1)
            %                         s(j).XLabel.String = 'time';
            %                     end
            %                 end
            %                 sgtitle(f,app.StatsObj.obs_list{i}, 'Interpreter','none')
            %                 f.Visible = 'on';
            %             end
        end

        % Menu selected function: MergerecordingsMenu
        function MergerecordingsMenuSelected(app, event)
            % Launches MergeRecordings tool.
            
            app.UIFigure.Visible = 'off';
            % Expand all nodes before launching the app:
            for i = 1:numel(app.Tree.Children)
                for k = 1:numel(app.Tree.Children(i).Children)
                    app.expandNode(app.Tree.Children(i).Children(k), true)
                end
            end
            w = MergeRecs(app);
            waitfor(w)
            app.UIFigure.Visible = 'on';
            if app.b_HasNewAcq
                % Save protocol file:
                app.SaveMenuSelected;
                % Reload UITREE with new acquisition:
                app.ResetSearchButtonPushed;
            end
        end

        % Button pushed function: AvgAcquisitionsButton
        function AvgAcquisitionsButtonPushed(app, event)
            acqMerger = acqMerging_tool(app.StatsObj);
            waitfor(acqMerger);
            out = app.StatsObj.getAcqIndexList('current');
%             if isempty(out)
%                 return
%             end
            % Update table with acquisition average info:
            tab = app.UITable2.Data;
            rowNames = app.UITable2.RowName;
            rowNames = rowNames(1:9);
            tab = tab(1:9,:);
            
            for ii = 1:length(out)
                rowNames = [rowNames; {['Avg acq. Group #' num2str(ii)]}];
                tab = vertcat(tab, table({sprintf('(%d to %d)', out{ii}(1), out{ii}(end))},'VariableNames', {'values'}));
            end
            set(app.UITable2, 'Data', tab, 'RowName',rowNames);
            
        end

        % Button pushed function: ChangeListsButton
        function ChangeListsButtonPushed(app, event)
            % Launches the "List_selector" app and updates list of
            % observations OR events:
            
            answer = uiconfirm(app.UIFigure,'Select the list to be edited','Select list','Options',{'observation','event','Cancel'},'DefaultOption',1);
            if strcmpi(answer,'cancel')
                disp('Operation Cancelled by User');
                return
            end
            % Launch app:
            ls = List_selector(app.StatsObj,answer);
            waitfor(ls);
            % Update lists in GUI:
            switch answer
                case 'observation'
                    app.ObservationlistListBox.Items = app.StatsObj.obs_list;
                case 'event'
                    app.EventlistListBox.Items = app.StatsObj.list_of_events;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'umIToolbox - Main ';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ProjectMenu = uimenu(app.UIFigure);
            app.ProjectMenu.Text = 'Project';
            app.NewMenu = uimenu(app.ProjectMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.ProjectMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.ProjectMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveasMenu = uimenu(app.ProjectMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as ...';
            app.UtilitiesMenu = uimenu(app.UIFigure);
            app.UtilitiesMenu.Text = 'Utilities';
            app.ProjectMenu_2 = uimenu(app.UtilitiesMenu);
            app.ProjectMenu_2.Text = 'Project';
            app.LookfornewdataMenu = uimenu(app.ProjectMenu_2);
            app.LookfornewdataMenu.MenuSelectedFcn = createCallbackFcn(app, @LookfornewdataMenuSelected, true);
            app.LookfornewdataMenu.Text = 'Look for new data';
            app.UpdateprojectMenu = uimenu(app.ProjectMenu_2);
            app.UpdateprojectMenu.Text = 'Update project';
            app.FullupdateMenu = uimenu(app.UpdateprojectMenu);
            app.FullupdateMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateprojectMenuSelected, true);
            app.FullupdateMenu.Tooltip = {'Adds new recordings and ERASES missing ones.'};
            app.FullupdateMenu.Text = 'Full update';
            app.AddnewdataonlyMenu = uimenu(app.UpdateprojectMenu);
            app.AddnewdataonlyMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdateprojectMenuSelected, true);
            app.AddnewdataonlyMenu.Tooltip = {'Adds new recordings and KEEP missing ones.'};
            app.AddnewdataonlyMenu.Text = 'Add new data only';
            app.ViewLogBookMenu = uimenu(app.ProjectMenu_2);
            app.ViewLogBookMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewLogBookMenuSelected, true);
            app.ViewLogBookMenu.Text = 'View LogBook';
            app.ResetLogBookMenu_2 = uimenu(app.ProjectMenu_2);
            app.ResetLogBookMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ResetLogBookMenu_2Selected, true);
            app.ResetLogBookMenu_2.Text = 'Reset LogBook';
            app.RestoregarbageListMenu = uimenu(app.ProjectMenu_2);
            app.RestoregarbageListMenu.MenuSelectedFcn = createCallbackFcn(app, @RestoregarbageListMenuSelected, true);
            app.RestoregarbageListMenu.Text = 'Restore garbage List';
            app.MergerecordingsMenu = uimenu(app.UtilitiesMenu);
            app.MergerecordingsMenu.MenuSelectedFcn = createCallbackFcn(app, @MergerecordingsMenuSelected, true);
            app.MergerecordingsMenu.Tooltip = {'Creates an acquisition with the merged data of two or more acquisitions.'};
            app.MergerecordingsMenu.Text = 'Merge recordings';
            app.GridLayoutMain = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayoutMain);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.DashboardTab = uitab(app.TabGroup);
            app.DashboardTab.Title = 'Dashboard';
            app.GridLayoutDashTab1 = uigridlayout(app.DashboardTab);
            app.UITable = uitable(app.GridLayoutDashTab1);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.TableTitle = uilabel(app.GridLayoutDashTab1);
            app.TableTitle.Text = 'Last operations run in selected item:';
            app.GridLayoutDashTab2 = uigridlayout(app.GridLayoutDashTab1);
            app.ListoffilesLabel = uilabel(app.GridLayoutDashTab2);
            app.ListoffilesLabel.Text = 'List of files:';
            app.PropertiesofselecteditemLabel = uilabel(app.GridLayoutDashTab2);
            app.PropertiesofselecteditemLabel.Text = 'Properties of selected item:';
            app.TextAreaDashTab = uitextarea(app.GridLayoutDashTab2);
            app.ListoffilesListBox = uilistbox(app.GridLayoutDashTab2);
            app.ListoffilesListBox.Items = {};
            app.ListoffilesListBox.Multiselect = 'on';
            app.ListoffilesListBox.ValueChangedFcn = createCallbackFcn(app, @ListoffilesListBoxValueChanged, true);
            app.ListoffilesListBox.Value = {};
            app.PipelinecontrolpanelTab = uitab(app.TabGroup);
            app.PipelinecontrolpanelTab.Title = 'Pipeline control panel';
            app.GridPipeline = uigridlayout(app.PipelinecontrolpanelTab);
            app.GridPipeline2 = uigridlayout(app.GridPipeline);
            app.Panel = uipanel(app.GridPipeline2);
            app.LaunchPipelineConfigButton = uibutton(app.Panel, 'push');
            app.LaunchPipelineConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LaunchPipelineConfigButtonPushed, true);
            app.LaunchPipelineConfigButton.Enable = 'off';
            app.LaunchPipelineConfigButton.Tooltip = {'Launch pipeline configuration interface.'};
            app.LaunchPipelineConfigButton.Text = 'Launch Pipeline Config.';
            app.SkipstepsCheckBox = uicheckbox(app.Panel);
            app.SkipstepsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SkipstepsCheckBoxValueChanged, true);
            app.SkipstepsCheckBox.Tooltip = {'Uncheck this box if you want to rerun tasks already performed in the data.'};
            app.SkipstepsCheckBox.Text = 'Skip steps';
            app.SkipstepsCheckBox.Value = true;
            app.SavedatabeforeerrorCheckBox = uicheckbox(app.Panel);
            app.SavedatabeforeerrorCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavedatabeforeerrorCheckBoxValueChanged, true);
            app.SavedatabeforeerrorCheckBox.Tooltip = {'Saves the last data created before the pipeline stops due to an error.'};
            app.SavedatabeforeerrorCheckBox.Text = 'Save data before error';
            app.SavedatabeforeerrorCheckBox.Value = true;
            app.TextAreaPipe = uitextarea(app.GridPipeline2);
            app.PipelineresultsummaryLabel = uilabel(app.GridPipeline);
            app.PipelineresultsummaryLabel.Text = 'Pipeline result summary:';
            app.UITablePipe = uitable(app.GridPipeline);
            app.UITablePipe.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITablePipe.RowName = {};
            app.VisualizationTab = uitab(app.TabGroup);
            app.VisualizationTab.Title = 'Visualization';
            app.GridLayoutVizTab = uigridlayout(app.VisualizationTab);
            app.SelectafileListBox = uilistbox(app.GridLayoutVizTab);
            app.SelectafileListBox.Items = {};
            app.SelectafileListBox.ValueChangedFcn = createCallbackFcn(app, @SelectafileListBoxValueChanged, true);
            app.SelectafileListBox.Value = {};
            app.SelectafileLabel = uilabel(app.GridLayoutVizTab);
            app.SelectafileLabel.Text = 'Select a file:';
            app.AppstoolsLabel = uilabel(app.GridLayoutVizTab);
            app.AppstoolsLabel.Text = 'Apps & tools';
            app.GridLayoutVizTab2 = uigridlayout(app.GridLayoutVizTab);
            app.FunctionalmapsvisualizationLabel = uilabel(app.GridLayoutVizTab2);
            app.FunctionalmapsvisualizationLabel.Text = 'Functional maps visualization';
            app.DataViewerButton = uibutton(app.GridLayoutVizTab2, 'push');
            app.DataViewerButton.ButtonPushedFcn = createCallbackFcn(app, @DataViewerButtonPushed, true);
            app.DataViewerButton.Icon = 'myIcon_DataViewer.png';
            app.DataViewerButton.Tooltip = {'App dedicated to the exploration of imaging datasets'};
            app.DataViewerButton.Text = 'DataViewer';
            app.ImageRegistrationLabel = uilabel(app.GridLayoutVizTab2);
            app.ImageRegistrationLabel.Text = 'Image Registration';
            app.ManualImageregistrationButton = uibutton(app.GridLayoutVizTab2, 'push');
            app.ManualImageregistrationButton.ButtonPushedFcn = createCallbackFcn(app, @ManualImageregistrationButtonPushed, true);
            app.ManualImageregistrationButton.Icon = 'myIcon_cpselect.png';
            app.ManualImageregistrationButton.Text = {'Manual Image '; 'registration'};
            app.ROIcreationandmanagementLabel = uilabel(app.GridLayoutVizTab2);
            app.ROIcreationandmanagementLabel.Text = 'ROI creation and management';
            app.ROImanagerButton = uibutton(app.GridLayoutVizTab2, 'push');
            app.ROImanagerButton.ButtonPushedFcn = createCallbackFcn(app, @ROImanagerButtonPushed, true);
            app.ROImanagerButton.Icon = 'myIcon_ROImanager.png';
            app.ROImanagerButton.Tooltip = {'App dedicated to the creation and management of Regions of Interest.'};
            app.ROImanagerButton.Text = 'ROI manager';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.GridLayout = uigridlayout(app.AnalysisTab);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SelectafilePanel = uipanel(app.GridLayout2);
            app.SelectafilePanel.Title = 'Select a file';
            app.SelectFileListBox_analysis = uilistbox(app.SelectafilePanel);
            app.SelectFileListBox_analysis.Items = {};
            app.SelectFileListBox_analysis.ValueChangedFcn = createCallbackFcn(app, @SelectFileListBox_analysisValueChanged, true);
            app.SelectFileListBox_analysis.Value = {};
            app.UITable2 = uitable(app.GridLayout2);
            app.UITable2.ColumnName = {'Data info'};
            app.UITable2.RowName = {''};
            app.SelectButton_analysis = uibutton(app.GridLayout2, 'state');
            app.SelectButton_analysis.ValueChangedFcn = createCallbackFcn(app, @SelectButton_analysisValueChanged, true);
            app.SelectButton_analysis.Enable = 'off';
            app.SelectButton_analysis.Text = 'Select';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.AppstoolsPanel = uipanel(app.GridLayout3);
            app.AppstoolsPanel.Title = 'Apps & tools';
            app.AppstoolsPanel.Visible = 'off';
            app.UtilitiesPanel = uipanel(app.AppstoolsPanel);
            app.UtilitiesPanel.Title = 'Utilities';
            app.ExporttoCSVButton = uibutton(app.UtilitiesPanel, 'push');
            app.ExporttoCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoCSVButtonPushed, true);
            app.ExporttoCSVButton.Enable = 'off';
            app.ExporttoCSVButton.Tooltip = {'Exports scalar or vector data to a .CSV file'};
            app.ExporttoCSVButton.Text = 'Export to .CSV';
            app.TableViewButton = uibutton(app.UtilitiesPanel, 'push');
            app.TableViewButton.ButtonPushedFcn = createCallbackFcn(app, @TableViewButtonPushed, true);
            app.TableViewButton.Enable = 'off';
            app.TableViewButton.Tooltip = {'Visualizes scalar or vector data in a table'};
            app.TableViewButton.Text = 'Table View';
            app.SavetoMatButton = uibutton(app.UtilitiesPanel, 'push');
            app.SavetoMatButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoMatButtonPushed, true);
            app.SavetoMatButton.Tooltip = {'Saves data to a structure in a .MAT file'};
            app.SavetoMatButton.Text = 'Save to .Mat';
            app.AvgAcquisitionsButton = uibutton(app.UtilitiesPanel, 'push');
            app.AvgAcquisitionsButton.ButtonPushedFcn = createCallbackFcn(app, @AvgAcquisitionsButtonPushed, true);
            app.AvgAcquisitionsButton.Tooltip = {'Average consecutive acquisitions.'};
            app.AvgAcquisitionsButton.Text = 'Avg. Acquisitions';
            app.ChangeListsButton = uibutton(app.UtilitiesPanel, 'push');
            app.ChangeListsButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeListsButtonPushed, true);
            app.ChangeListsButton.Tooltip = {'Rearranges observation OR event lists'};
            app.ChangeListsButton.Text = 'Change Lists';
            app.ObservationlistListBoxLabel = uilabel(app.AppstoolsPanel);
            app.ObservationlistListBoxLabel.Text = 'Observation list';
            app.ObservationlistListBox = uilistbox(app.AppstoolsPanel);
            app.ObservationlistListBox.Items = {};
            app.ObservationlistListBox.Value = {};
            app.EventlistListBoxLabel = uilabel(app.AppstoolsPanel);
            app.EventlistListBoxLabel.Text = 'Event list';
            app.EventlistListBox = uilistbox(app.AppstoolsPanel);
            app.EventlistListBox.Items = {};
            app.EventlistListBox.Value = {};
            app.PlottingtoolsPanel = uipanel(app.GridLayout3);
            app.PlottingtoolsPanel.Title = 'Plotting tools';
            app.PlottingtoolsPanel.Visible = 'off';
            app.PlotCorrelationMatrixButton = uibutton(app.PlottingtoolsPanel, 'push');
            app.PlotCorrelationMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @PlotCorrelationMatrixButtonPushed, true);
            app.PlotCorrelationMatrixButton.Enable = 'off';
            app.PlotCorrelationMatrixButton.Text = {'Plot Correlation'; 'Matrix'};
            app.LineBoxplotsButton = uibutton(app.PlottingtoolsPanel, 'push');
            app.LineBoxplotsButton.ButtonPushedFcn = createCallbackFcn(app, @LineBoxplotsButtonPushed, true);
            app.LineBoxplotsButton.Enable = 'off';
            app.LineBoxplotsButton.Text = 'Line/Box plots';
            app.PlotAverageMapsButton = uibutton(app.PlottingtoolsPanel, 'push');
            app.PlotAverageMapsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAverageMapsButtonPushed, true);
            app.PlotAverageMapsButton.Enable = 'off';
            app.PlotAverageMapsButton.Text = 'Plot Average Maps';
            app.GridLayoutLeftCol = uigridlayout(app.GridLayoutMain);
            app.SearchtoolsPanel = uipanel(app.GridLayoutLeftCol);
            app.SearchtoolsPanel.Title = 'Search tools';
            app.AdvancedSearchButton = uibutton(app.SearchtoolsPanel, 'push');
            app.AdvancedSearchButton.ButtonPushedFcn = createCallbackFcn(app, @AdvancedSearchButtonPushed, true);
            app.AdvancedSearchButton.Text = 'Advanced Search';
            app.ResetSearchButton_3 = uibutton(app.SearchtoolsPanel, 'push');
            app.ResetSearchButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetSearchButtonPushed, true);
            app.ResetSearchButton_3.Text = 'Reset Search';
            app.OptionsPanel = uipanel(app.GridLayoutLeftCol);
            app.OptionsPanel.Title = 'Options';
            app.SelectionLabel = uilabel(app.OptionsPanel);
            app.SelectionLabel.Text = 'Selection:';
            app.EditButton = uibutton(app.OptionsPanel, 'push');
            app.EditButton.ButtonPushedFcn = createCallbackFcn(app, @EditButtonPushed, true);
            app.EditButton.Text = 'Edit';
            app.DeleteButton = uibutton(app.OptionsPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.UndoSelectionButton = uibutton(app.OptionsPanel, 'push');
            app.UndoSelectionButton.ButtonPushedFcn = createCallbackFcn(app, @UndoSelectionButtonPushed, true);
            app.UndoSelectionButton.Visible = 'off';
            app.UndoSelectionButton.Text = 'Undo Selection';
            app.SelectButton = uibutton(app.OptionsPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Visible = 'off';
            app.SelectButton.Text = 'Select';
            app.GroupingToolBtn = uibutton(app.OptionsPanel, 'push');
            app.GroupingToolBtn.ButtonPushedFcn = createCallbackFcn(app, @GroupingToolBtnPushed, true);
            app.GroupingToolBtn.Visible = 'off';
            app.GroupingToolBtn.Text = {'Grouping '; 'tool'};
            app.ErasegroupsBtn = uibutton(app.OptionsPanel, 'push');
            app.ErasegroupsBtn.ButtonPushedFcn = createCallbackFcn(app, @ErasegroupsBtnButtonPushed, true);
            app.ErasegroupsBtn.Visible = 'off';
            app.ErasegroupsBtn.Text = {'Erase'; 'groups'};
            app.Tree = uitree(app.GridLayoutLeftCol);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.Tree.NodeExpandedFcn = createCallbackFcn(app, @TreeNodeExpanded, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = umIToolbox(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end