classdef umIToolbox_rawDir_selector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        GridFolder    matlab.ui.container.GridLayout
        AddButton     matlab.ui.control.Button
        FolderButton  matlab.ui.control.StateButton
        GridLayout2   matlab.ui.container.GridLayout
        UpdateButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        protocolObj % Handle to protocol object
    end
    
    methods (Access = private)
        
        function buildFolderList(app)
            % Populates the GridFolder with the current list of Raw folders
            % from protocol object.            
            event.Source = app.AddButton;
            set(app.FolderButton, 'Text',app.protocolObj.MainDir{1}, 'HorizontalAlignment','left','Value',true);            
            for ii = 2:length(app.protocolObj.MainDir)
                app.AddFolderButtonPushed(event);                
                set(app.GridFolder.Children(find(arrayfun(@(x) strcmp(x.Text,'(Select)'),app.GridFolder.Children),1,'first')),...
                    'Text',app.protocolObj.MainDir{ii}, 'HorizontalAlignment','left', 'Value',true);                               
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, protocolObj)
            movegui(app.UIFigure, 'center');
            app.protocolObj = protocolObj;
            app.buildFolderList;
        end

        % Value changed function: FolderButton
        function SelectFolderButtonPushed(app, event)
            % Opens dialog for folder selection
            src = event.Source;
            if src.Value
                % Open folder selection
                if strcmpi(src.Text, '(Select)')
                    path = app.protocolObj.MainDir{end};
                else
                    path = src.Text;
                end
                newFolder = uigetdir(path, 'Select the folder containing the raw data');
                if newFolder == 0
                    disp('Operation cancelled by User')
                    src.Value = false;
                    return
                end
                % Update the button Text
                src.Text = newFolder;
                set(src, 'Text',newFolder,'HorizontalAlignment', 'left');
            else
                % Check if this is the last one:
                if all(arrayfun(@(x) isa(x,'StateButton') & strcmp(x.Text, '(Select)'), app.GridFolder.Children));
                    return
                end
                set(src, 'Text','(Select)','HorizontalAlignment', 'center');
            end
        end

        % Button pushed function: AddButton
        function AddFolderButtonPushed(app, event)
            % Creates new button for Folder selection.                      
            src = event.Source;
            gridH = src.Parent;
            % Add new Row:
            gridH.RowHeight = [gridH.RowHeight , {40}];
            % Shift Add button:
            src.Layout.Row = length(gridH.RowHeight);
            % Populate new row with button            
            newBtn = copyobj(app.FolderButton, gridH, 'legacy');
            newBtn.Layout.Row = length(gridH.RowHeight)-1;
            set(newBtn, 'Text','(Select)', 'HorizontalAlignment','center', 'Value',false);     
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % Updates the MainDir property of the protocol object.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Raw Folder selection';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridFolder = uigridlayout(app.GridLayout);
            app.AddButton = uibutton(app.GridFolder, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddFolderButtonPushed, true);
            app.AddButton.Icon = 'icon_plusBW.png';
            app.AddButton.Tooltip = {'Add new raw folder to  project'};
            app.AddButton.Text = '';
            app.FolderButton = uibutton(app.GridFolder, 'state');
            app.FolderButton.ValueChangedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.FolderButton.Text = '(Select)';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.UpdateButton = uibutton(app.GridLayout2, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = umIToolbox_rawDir_selector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end